classdef EEGpalCS_main_Data < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_Main                  matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        TOOLSPanel                     matlab.ui.container.Panel
        FileCutLabel                   matlab.ui.control.Label
        FileCutButton                  matlab.ui.control.Button
        ConvertLabel                   matlab.ui.control.Label
        ExportButton                   matlab.ui.control.Button
        HelpButton                     matlab.ui.control.Button
        QUITButton                     matlab.ui.control.Button
        SaveFolder                     matlab.ui.control.TextArea
        SavingfolderLabel              matlab.ui.control.Label
        DataSavingButton               matlab.ui.control.Button
        SavingUpperFolderLabel         matlab.ui.control.Label
        OutputExtension                matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel    matlab.ui.control.Label
        SavingOptionsLabel             matlab.ui.control.Label
        RootFolder                     matlab.ui.control.TextArea
        RootfolderLabel                matlab.ui.control.Label
        InputFolderButton              matlab.ui.control.Button
        InputUpperFolderLabel          matlab.ui.control.Label
        ExclusionString                matlab.ui.control.EditField
        ExclusionstringforexcludingsubsetoffilesoptionalLabel  matlab.ui.control.Label
        FilterString                   matlab.ui.control.EditField
        InclusionstringforselectingsubsetoffilesoptionalLabel  matlab.ui.control.Label
        FilesExtension                 matlab.ui.control.ListBox
        ExtensionofinputfilesCtrlclicktoselectmultipleextensionsLabel  matlab.ui.control.Label
        FilesSelection                 matlab.ui.control.Label
        ParameterFilePath              matlab.ui.control.EditField
        SaveAsButton                   matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        InputParametersFile            matlab.ui.control.Label
        EEGpalLabel                    matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        CenterPanel                    matlab.ui.container.Panel
        InBatchModeCheckBox            matlab.ui.control.CheckBox
        RunBatchButton                 matlab.ui.control.Button
        ResetButton                    matlab.ui.control.Button
        BatchButton                    matlab.ui.control.Button
        SaveAsButtonSessionParameters  matlab.ui.control.Button
        OpenButtonSessionParameters    matlab.ui.control.Button
        SessionParametersFilePath      matlab.ui.control.EditField
        ProcessingAnalysisparametersLabel  matlab.ui.control.Label
        versionText                    matlab.ui.control.Label
        Warning                        matlab.ui.control.TextArea
        WarningLabel                   matlab.ui.control.Label
        SaveTableButton                matlab.ui.control.Button
        DataFilesTable                 matlab.ui.control.Table
        DATALabel                      matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        PresetparametersLabel          matlab.ui.control.Label
        PresetParametersSwitch         matlab.ui.control.Switch
        ANALYSISMODULESPanel           matlab.ui.container.Panel
        PeaksorTraceexportButton       matlab.ui.control.Button
        InversesolutionsButton         matlab.ui.control.Button
        StatisticsButton               matlab.ui.control.Button
        FrequencyingButton             matlab.ui.control.Button
        PROCESSINGMODULESPanel         matlab.ui.container.Panel
        GEDAIButton                    matlab.ui.control.Button
        BridgeDetectionButton          matlab.ui.control.Button
        EpochingButton                 matlab.ui.control.Button
        RereferenceButton              matlab.ui.control.Button
        InterpolationButton            matlab.ui.control.Button
        ICAButton                      matlab.ui.control.Button
        FilteringButton                matlab.ui.control.Button
        DesignButton                   matlab.ui.control.Button
        ElectrodeSettingButton         matlab.ui.control.Button
        FirstSubjCode                  matlab.ui.control.EditField
        StudyName                      matlab.ui.control.EditField
        StudyNameEditFieldLabel        matlab.ui.control.Label
        CodeofFIRSTparticipantLabel    matlab.ui.control.Label
        Design                         matlab.ui.control.Label
        DeselectallButton              matlab.ui.control.Button
        SelectallButton                matlab.ui.control.Button
        NumFilesFirstSubj              matlab.ui.control.NumericEditField
        NumberoffilesidentifiedforthefirstsubjectLabel  matlab.ui.control.Label
        NumFiles                       matlab.ui.control.NumericEditField
        NumberoffilesidentifiedLabel   matlab.ui.control.Label
        NumSubj                        matlab.ui.control.NumericEditField
        NumberofsubjectsidentifiedLabel  matlab.ui.control.Label
        SubjectSelectionTable          matlab.ui.control.Table
        FolderSelectionTable           matlab.ui.control.Table
        FOLDERANDSUBJECTSSELECTIONLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        SessionParameters
        InputFilesParameters
        FilesTable
        AppPath % save the toolboxpath
        Versions % save the versions of toolbox elements
    end
    
    properties (Access = private)
        ElectrodesApp % Dialog box app
        DesignSettingApp % Dialog box app
        FilteringApp % Dialog box app
        ICA_App % Dialog box app
        GEDAI_App % Dialog box app
        InterpolationApp % Dialog box app
        RereferenceApp % Dialog box app
        EpochingApp
        FrequencyingApp
        
        EPheader
        
        % WERE PUBLIC...
        
        ParameterFile % Description
        ParameterPath
        SUBJ
        
        
    end
    
    methods (Access = public)
        
        
        function updateSelection(app)
            
            % Folder selection
            FOLDERSselection = app.FolderSelectionTable.DisplayData;
            TMPtable = app.FilesTable(~contains(app.FilesTable.Path,...
                FOLDERSselection(cell2mat(FOLDERSselection(:,2))==0,1)),:); % Keep ticked folders
            app.DataFilesTable.Data = app.FilesTable(~contains(app.FilesTable.Path,...
                FOLDERSselection(cell2mat(FOLDERSselection(:,2))==0,1)),:); % Keep ticked folders
            
            % update Subject information
            if ~isempty(app.FirstSubjCode.Value)
                SelectedSubj = app.SubjectSelectionTable.Data(cell2mat(app.SubjectSelectionTable.Data(:,2)),1);
                app.SUBJ = unique(TMPtable.Subject);
                SelectedSubj = num2cell(ismember(app.SUBJ,SelectedSubj));
                app.SubjectSelectionTable.Data = [cellstr(app.SUBJ), SelectedSubj];
%                 toDelete = ~ismember(app.SubjectSelectionTable.Data(:,1),app.SUBJ);
%                 app.SubjectSelectionTable.Data(toDelete,:) = [];
                
                %app.SubjectSelectionTable.Data = [cellstr(app.SUBJ), repmat({true},[size(app.SUBJ,1) 1])];
                
                % Subject selection
                SUBJselection = app.SubjectSelectionTable.DisplayData;
                app.DataFilesTable.Data = TMPtable(~contains(TMPtable.Subject,...
                    SUBJselection(cell2mat(SUBJselection(:,2))==0,1)),:); % Keep ticked subjects
            else
                app.SUBJ = '';
            end
            
            % Update information
            app.SUBJ = unique(app.DataFilesTable.Data.Subject); % update SUBJ
            app.NumFilesFirstSubj.Value = sum(app.DataFilesTable.Data.Subject==app.FirstSubjCode.Value); % Number of files for the first subject
            app.NumSubj.Value = length(app.SUBJ); % number of subjects for analysis
            app.NumFiles.Value = height(app.DataFilesTable.Data);
            
            % Check consistency between files
            consistencyWarning(app)
        end
        
        function updateCondColumn(app,CondFactTable)
            for cond = 1:size(CondFactTable,1)
                app.DataFilesTable.Data.Condition(contains(app.DataFilesTable.Data.File,CondFactTable(cond,1))) = CondFactTable(cond,1);
            end
        end
        
        
        function  RunBatchProcessing(app)
            %need to write the code to execute the batch
        end
    end
    
    methods (Access = private)
        
        function applyInputFilesPara(app)
            % File
            app.FilesExtension.Value        = app.InputFilesParameters.Files.FilesExtension;
            app.FilterString.Value          = app.InputFilesParameters.Files.FilterString;
            app.ExclusionString.Value       = app.InputFilesParameters.Files.ExclusionString;
            app.RootFolder.Value            = app.InputFilesParameters.Files.RootFolder;
            app.FilesTable                  = app.InputFilesParameters.Files.FilesTable;
            app.DataFilesTable.Data         = app.InputFilesParameters.Files.DataFilesTable;
            app.FolderSelectionTable.Data   = app.InputFilesParameters.Files.FolderSelectionTable;
            app.SubjectSelectionTable.Data  = app.InputFilesParameters.Files.SubjectSelectionTable;
            app.NumSubj.Value               = app.InputFilesParameters.Files.NumSubj;
            app.NumFiles.Value              = app.InputFilesParameters.Files.NumFiles;
            app.NumFilesFirstSubj.Value     = app.InputFilesParameters.Files.NumFilesFirstSubj;
            
            % Saving
            app.OutputExtension.Value       = app.InputFilesParameters.Files.OutputExtension;
            app.SaveFolder.Value            = app.InputFilesParameters.Files.SaveFolder;
            
            % Design
            app.StudyName.Value             = app.InputFilesParameters.Design.StudyName;
            app.FirstSubjCode.Value         = app.InputFilesParameters.Design.FirstSubjCode;
        end
        
        function InputFilesParameters = updateInputFilesParameters(app,InputFilesParameters)
            % File
            InputFilesParameters.Files.FilesExtension           = app.FilesExtension.Value;
            InputFilesParameters.Files.FilterString             = app.FilterString.Value;
            InputFilesParameters.Files.ExclusionString          = app.ExclusionString.Value;
            InputFilesParameters.Files.RootFolder               = app.RootFolder.Value;
            if isprop(app,'FilesTable') && ~isempty(app.FilesTable)
                InputFilesParameters.Files.FilesTable           = app.FilesTable;
            else
                InputFilesParameters.Files.FilesTable           = [];
            end
            InputFilesParameters.Files.DataFilesTable           = app.DataFilesTable.Data;
            InputFilesParameters.Files.FolderSelectionTable     = app.FolderSelectionTable.Data;
            InputFilesParameters.Files.SubjectSelectionTable    = app.SubjectSelectionTable.Data;
            InputFilesParameters.Files.NumSubj                  = app.NumSubj.Value;
            InputFilesParameters.Files.NumFiles                 = app.NumFiles.Value;
            InputFilesParameters.Files.NumFilesFirstSubj        = app.NumFilesFirstSubj.Value;
            
            % Saving
            InputFilesParameters.Files.OutputExtension          = app.OutputExtension.Value;
            InputFilesParameters.Files.SaveFolder               = app.SaveFolder.Value;
            
            % Design
            InputFilesParameters.Design.StudyName               = app.StudyName.Value;
            InputFilesParameters.Design.FirstSubjCode           = app.FirstSubjCode.Value;
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            % File
            SessionParameters.Files.FilesExtension          = app.FilesExtension.Value;
            SessionParameters.Files.FilterString            = app.FilterString.Value;
            SessionParameters.Files.ExclusionString         = app.ExclusionString.Value;
            SessionParameters.Files.RootFolder              = app.RootFolder.Value;
            if isprop(app,'FilesTable') && ~isempty(app.FilesTable)
                SessionParameters.Files.FilesTable          = app.FilesTable;
            else
                SessionParameters.Files.FilesTable          = [];
            end
            SessionParameters.Files.DataFilesTable          = app.DataFilesTable.Data;
            SessionParameters.Files.FolderSelectionTable    = app.FolderSelectionTable.Data;
            SessionParameters.Files.SubjectSelectionTable   = app.SubjectSelectionTable.Data;
            SessionParameters.Files.NumSubj                 = app.NumSubj.Value;
            SessionParameters.Files.NumFiles                = app.NumFiles.Value;
            SessionParameters.Files.NumFilesFirstSubj       = app.NumFilesFirstSubj.Value;
            
            % Saving
            SessionParameters.Files.OutputExtension         = app.OutputExtension.Value;
            SessionParameters.Files.SaveFolder              = app.SaveFolder.Value;
            
            % Design
            SessionParameters.Design.StudyName              = app.StudyName.Value;
            SessionParameters.Design.FirstSubjCode          = app.FirstSubjCode.Value;
        end
        
        function consistencyWarning(app)
            WarnSR = '';
            WarnChan = '';
            WarnTF = '';
            Format = '';
            if length(unique(app.DataFilesTable.Data.SamplingRate)) > 1
                WarnSR = '/!\ The sampling rate is not consistent between files. Resample data in the Filtering+ module.';
                Format = [Format, '%s\n'];
            end
            if length(unique(app.DataFilesTable.Data.nChan)) > 1
                WarnChan = '/!\ The number of channels is not consistent between files. Not sure I can handle that...';
                Format = [Format, '%s\n'];
            end
            if length(unique(app.DataFilesTable.Data.nTF)) > 1
                WarnTF = 'The number of time-frames is not consistent between files, but that may be normal. You know what you''re doing!';
                Format = [Format, '%s'];
            end
            app.Warning.Value = sprintf(Format,WarnSR,WarnChan,WarnTF);
        end
        
        function updateDesign(app)
            Condition = strings([height(app.FilesTable),1]); % Create array for condition
            Factor_level = strings([height(app.FilesTable),1]); % Create array for factor_level

            %generate levels
            levels="";
            for i=1:app.SessionParameters.Design.NumCond
                s="";
                for j=2:app.SessionParameters.Design.NumFact+1
                    s=strcat(s,app.SessionParameters.Design.CondFactTable(i,j));
                end
                levels(i)=s;
            end

            %% update the main Table 
            nbFile= height(app.FilesTable);
            for filesNum=1:nbFile %look each file
                for k=1:app.SessionParameters.Design.NumCond %loop through all possible conditions
                    Cond=app.SessionParameters.Design.CondFactTable(k,1); %condition string pattern
                    CondIndice=strfind(app.FilesTable.File{filesNum},Cond);
                    
                    if ~isempty(CondIndice) %there is a correspondance
                        Condition(filesNum)=Cond;
                        Factor_level(filesNum)=levels(k);
                    end
                end
            end

            % Updated Columns (Files Table)
            app.FilesTable.Condition = Condition;
            app.FilesTable.FactorLevel = Factor_level;

            % Updated Columns (DataFiles Table)
            updateSelection(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add functions folders to path
            FileName = mfilename('fullpath');
            if ispc
                iBS = strfind(FileName,'\'); % index of the \ characters in the path and name of the script
            else
                iBS = strfind(FileName,'/');
            end
            
            AppPath = FileName(1:iBS(end)-1); % path of the script
            FuncPath = genpath(AppPath);
            addpath(FuncPath)
            
            % Creat necessary empty variables
            app.InputFilesParameters = [];
            app.SessionParameters.Electrodes.SettingTable = [];
            app.SessionParameters.BatchProcessing.BatchExecution = 0;

            % Save the current location of the toolbox
            app.AppPath.value=AppPath;
            
            % Save the versions number of the elements of the toolbox
            app.Versions.EEGpal=2.16;
            app.Versions.eeglab=2025.0;
            app.Versions.clean_rawdata=2.10;
            app.Versions.cleanline=2.1;
            app.Versions.ICLabel=1.6;
            app.Versions.GEDAI=1.7;
     
            
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [File,Path] = uigetfile('*.mat','Select input parameter file','InputFilesParameters_EEGpal.mat');
            drawnow; figure(app.EEGpalCS_Main); % Restaure the app window
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            Parameters = load(app.ParameterFilePath.Value);
            app.InputFilesParameters  = Parameters;
            try
                applyInputFilesPara(app);
            catch
                msgbox(sprintf('%s','No input file parameters saved in this file.'),...
                    'No parameters','warn');
            end
            cd (app.RootFolder.Value{1});
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            % Save parameter file
            [File,Path] = uiputfile('*.mat','Save input parameter file','InputFilesParameters_EEGpal.mat');
            drawnow; figure(app.EEGpalCS_Main); % Restaure the app window
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);

            app.InputFilesParameters = updateInputFilesParameters(app,app.InputFilesParameters);
            Parameters = app.InputFilesParameters;

            save(app.ParameterFilePath.Value,'-struct','Parameters');
        end

        % Button pushed function: InputFolderButton
        function InputFolderButtonPushed(app, event)
            rootfolder = uigetdir('title',...
                'Choose the path of your most upper folder containing the files');
            drawnow; figure(app.EEGpalCS_Main); % Restaure the app window

            app.ParameterFilePath.Value='';
            
            
            if isequal(rootfolder,0)
               return; % User pressed cancel
            end
            cd(rootfolder)
            
            % If filter string entered, add '*' for file selection
            filter_string = app.FilterString.Value;
            if ~isempty(app.FilterString.Value)
                filter_string = strcat(filter_string,'*');
            end

            if strcmp(app.FilesExtension.Value{1}, '.eeg/.vhdr')
                FilesList = dir(['**/*' filter_string '.vhdr']); % First extension selected
            else
                FilesList = dir(['**/*' filter_string app.FilesExtension.Value{1}]); % First extension selected
            end           
            
            if length(app.FilesExtension.Value) > 1 % If more than one extension selected
                for ext = 2:length(app.FilesExtension.Value)
                    FilesList = [FilesList;dir(['**/*' filter_string app.FilesExtension.Value{ext}])];
                end
            end

            if ~isempty(app.ExclusionString.Value)
                FilesList = FilesList(~contains({FilesList.name},app.ExclusionString.Value)); % Remove files containing exclusion string
            end
            
            
            % If no files found, re-ask for files parameters
            if isempty(FilesList)
                msgbox(sprintf('%s\n%s\n\n%s%s\n%s%s\n%s%s\n%s%s\n\n%s\n\n%s','No files found.', ...
                    'Please make sure to select the correct files or enter the correct parameters.',...
                    'Files extension entered: ',app.FilesExtension.Value{:},...
                    'Filtering string entered: ',app.FilterString.Value,...
                    'Exclusion string entered: ',app.ExclusionString.Value,...
                    'Path of your most upper folder containing the files: ',rootfolder,...
                    'Please try again!'),'No files found','warn');
            else
                app.RootFolder.Value = rootfolder;
                
                
                % For some reason, it is much faster to use an intermediate
                % FilesTable table than work directly on app.DataFilesTable.Data
                app.FilesTable = struct2table(FilesList);
                app.FilesTable = app.FilesTable(:,1:2);
                app.FilesTable.Properties.VariableNames = {'File','Path'};
                app.FilesTable.File = cellstr(app.FilesTable.File); % convert to cell if only one file selected (in that case, Matalb creates a string)
                app.FilesTable.Path = cellstr(app.FilesTable.Path); % convert to cell if only one file selected (in that case, Matalb creates a string)
                app.FilesTable.Path = strcat('..',erase(app.FilesTable.Path,app.RootFolder.Value));
                app.FilesTable.nChan = nan(height(app.FilesTable),1);
                app.FilesTable.SamplingRate = nan(height(app.FilesTable),1);
                app.FilesTable.nTF = nan(height(app.FilesTable),1);
                app.FilesTable.Duration = duration(nan(height(app.FilesTable),3),'Format','mm:ss.SSS');
                app.FilesTable.Subject = strings(height(app.FilesTable),1);
                app.FilesTable.Condition = strings(height(app.FilesTable),1);
                app.FilesTable.FactorLevel = strings(height(app.FilesTable),1);
                
                h = waitbar(0,'Scan file.....');
                nbFile=height(app.FilesTable);
                for file = 1:nbFile
                    disp(['scanning file ' num2str(file) ' out of ' num2str(height(app.FilesTable))])
                    waitbar(file/nbFile,h, strcat('Scan file ',num2str(file),'/',num2str(nbFile)));
                    openfilename = cell2mat(fullfile(app.RootFolder.Value,erase(app.FilesTable.Path(file),'..'),app.FilesTable.File(file)));
                    if strcmpi(app.FilesExtension.Value,'.bdf')
                        header = open_bdf(openfilename);
                    elseif strcmpi(app.FilesExtension.Value,'.set')
                        header = open_eeglab(openfilename);
                    elseif strcmpi(app.FilesExtension.Value,'.ep') || strcmpi(app.FilesExtension.Value,'.eph')
                        header = open_eph(openfilename);
                        if strcmpi(app.FilesExtension.Value,'.ep')
%                             clear header
%                             header.NumTF = nan;
%                              header.NumChan = nan;
                             header.SamplingRate = nan;
                        end
                    elseif strcmpi(app.FilesExtension.Value,'.sef')
                        header = open_sef(openfilename);
                    elseif strcmpi(app.FilesExtension.Value,'.ris')
                        header = open_ris(openfilename);
                        header.NumChan = header.NumSP;
                    elseif strcmpi(app.FilesExtension.Value,'.freq')
                        header = open_freq(openfilename);
                        header.NumTF = header.NumBlocks; % Number of frequencies might be more interesting 
                        % Resolution could be interesting too (instead of the sampling rate)
                    elseif strcmpi(app.FilesExtension.Value,'.eeg/.vhdr')
                        [tempPath,tempName,tempExt] = fileparts(openfilename);
                        tempEEG =pop_loadbv(tempPath,strcat(tempName,tempExt)); %use of the bvy-io toolbox to import BrainVision file into eeglab
                        
                        tempEEG=convertEeglabEventStructure(tempEEG,openfilename); %modification of event name string to num

                        pop_saveset( tempEEG, 'filename',strcat(tempName,'.set'),'filepath',tempPath,'savemode','twofiles'); %save it in .set
                        header = open_eeglab(fullfile(tempPath,strcat(tempName,'.set')));
                        app.FilesTable.File(file) = {strcat(tempName,'.set')}; %correct the file table
                    end
                    app.FilesTable.nChan(file) = header.NumChan;
                    app.FilesTable.SamplingRate(file) = header.SamplingRate;
                    app.FilesTable.nTF(file) = header.NumTF;
                    DurationS = header.NumTF / header.SamplingRate;
                    app.FilesTable.Duration(file) = duration(seconds(DurationS),'Format','mm:ss.SSS');
                end
                delete(h);

                %transfert of FilesTable to the main windows Table
                app.DataFilesTable.Data = app.FilesTable;

                
                % If .ep files, insert sampling rate and number of channels
                if contains('.ep',app.FilesExtension.Value)
                    app.EPheader = EEGpalCS_dlg_Header(app);
                    waitfor(app.EPheader)
                    for file = 1:nbFile
                        openfilename = cell2mat(fullfile(app.RootFolder.Value,erase(app.FilesTable.Path(file),'..'),app.FilesTable.File(file)));
                        header = open_eph(openfilename);
                        DurationS = header.NumTF / app.FilesTable.SamplingRate(file);
                        app.FilesTable.Duration(file) = duration(seconds(DurationS),'Format','mm:ss.SSS');
                    end
                    app.DataFilesTable.Data = app.FilesTable; %transfert it again for update duration
                end


                % Check consistency between files
                consistencyWarning(app)
                
                % Prepare folders names
                Folders = unique({FilesList.folder}); % Retrieve names from the FileList structure
                SubFolders = cellfun(@(x) x(length(app.RootFolder.Value{:})+2:end),Folders,'UniformOutput',false); % Removing the consistant part of the path name
                app.FolderSelectionTable.Data = [cellstr(SubFolders'), repmat({true},[size(SubFolders,2) 1])];
                
                app.NumFiles.Value = length(FilesList);

                app.FirstSubjCode.Value = '';
                app.SubjectSelectionTable.Data  = [];
                app.SelectallButton.Enable      = 0;
                app.DeselectallButton.Enable    = 0;
                app.NumSubj.Value               = 0;
                app.NumFilesFirstSubj.Value     = 0;
                app.StudyName.Value             = "";

                
                clear FilesList % In case of second try
                drawnow; figure(app.EEGpalCS_Main); % Restaure the app window
            end
        end

        % Button pushed function: DataSavingButton
        function DataSavingButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_Main); % Restaure the app window
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            app.SessionParameters.Files.SaveFolder={savefolder};
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            %% CHECK
            
            if isempty(app.DataFilesTable.Data)
                msgbox(sprintf('%s\n\n%s','No data to analyse!', ...
                    'Please make sure to select the files to analyse in the main window.'),...
                    'No data selected','warn');
                return
            end
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            if strcmp(app.OutputExtension.Value,'.freq')
                msgbox(sprintf('%s', ...
                    'Files cannot be converted into the .freq format (I''m really sorry about that).'),...
                    'No conversion to .freq','warn');
                return
            end
            
            Export = questdlg(sprintf('%s\n\n%s%d\n%s%s\n\n%s',...
                'Conversion of files about to start!', ...
                'Number of files: ',height(app.DataFilesTable.Data),...
                'New extension: ',app.OutputExtension.Value,...
                'Do you wish to proceed?'),'Export','OK','Cancel','Cancel');
            
            if strcmpi(Export,'Cancel')
                return
            end
            
            
            %% PROCESSING INFORMATION SHEET

            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
%             % Last official update
%             fileID = fopen(strcat(currentfile.folder,'\',currentfile.name)); % open this file
%             update = char(string(textscan(fileID,'%s',1,'HeaderLines',1,'delimiter','\n'))); % Extract 'Update' line
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_FileConversion_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Files Conversion Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.Versions.EEGpal);
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Input files extension:',app.FilesExtension.Value{:});
            try
                fprintf(PifFileID,'%s\t\t%s\r\n','Input inclusion string:',app.FilterString.Value{:});
            catch
            end
            try
                fprintf(PifFileID,'%s\t\t%s\r\n','Input exclusion string:',app.ExclusionString.Value{:});
            catch
            end
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            try
                fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            catch
            end
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.OutputExtension.Value);
            try
                fprintf(PifFileID,'%s\t\t%s\r\n','Study parameters file:',app.ParameterFilePath.Value);
            catch
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
                Included = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                fprintf(PifFileID,'%s\r\n','Channels left in file:');
                fprintf(PifFileID,'\t\t%s\r\n',Included{:});
            catch
                fprintf(PifFileID,'%s\r\n','No electrodes setting file.');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataFilesTable.Data.File';num2cell(app.DataFilesTable.Data.(3))';num2cell(app.DataFilesTable.Data.(4))';app.DataFilesTable.Data.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN EXPORT
            
            ProcessedDataTable = app.DataFilesTable.Data; % For updating the Data Table
            
            disp('Run Export...')
            StartTime = datetime('now');
            tStart = tic;
            
            h = waitbar(0,'Exporting.....');
            nbFile= height(app.DataFilesTable.Data);
             
            for file = 1:nbFile
                events = ''; % In case no events in input file
                nOutFiles = 1; % FREQ files will yield 1 file per frequence, other formats: just 1
                
                waitbar(file/nbFile,h, strcat('Exporting File ',num2str(file),'/',num2str(nbFile)));
                
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataFilesTable.Data.Path(file),app.DataFilesTable.Data.File(file)))])
                disp(['Root folder: ', app.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.RootFolder.Value,erase(app.DataFilesTable.Data.Path(file),'..'),app.DataFilesTable.Data.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
%                     if ~isempty(events)
%                         events = [events{:,2} events{:,1}]; % convert to same format as for the other file types
%                     end
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                    if strcmpi(FileExtension,'.ep')
                        header.SamplingRate = app.DataFilesTable.Data.SamplingRate(file);
                    end
                elseif strcmpi(FileExtension,'.ris')
                    [header,data] = open_ris(openfilename);
                elseif strcmpi(FileExtension,'.freq')
                    [header,data] = open_freq(openfilename);
                    header.firstindex = 0; % In Cartool, 1st time-frame is 0
                    data = permute(data,[3 2 1]); %  rearranges dimensions for conversion in another format
                    nOutFiles = header.NumFreq;
                else
                    disp('File extension not recognized.')
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    continue
                end
                disp('File loaded!')
                
                
                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                catch
                end
                
                
                % Save processed file
%                 if ~isempty(app.SuffixString.Value)
%                     suffix = ['_', app.SuffixString.Value];
%                 end
                if strcmpi(FileExtension,'.freq')
                    FreqName = header.frequencies;
                else
                    FreqName = "";
                    nOutFiles = 1;
                end
                suffix = '_exported';
                
                SubPath = app.DataFilesTable.Data.Path{file}(3:end);
                NewPath = [app.SaveFolder.Value{:} SubPath];
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end
                
                for out = 1:nOutFiles
                    savefilename = fullfile(NewPath,char(strcat(FileName, FreqName(out,:), suffix, app.OutputExtension.Value)));
                    disp(['Saving ',savefilename])
                    if strcmp(app.OutputExtension.Value,'.ep') || strcmp(app.OutputExtension.Value,'.eph')
                        save_eph(savefilename,data(:,:,out),header.SamplingRate,events,header.firstindex)
                    elseif strcmp(app.OutputExtension.Value,'.sef')
                        try
                             Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                        catch
                            Channels = '';
                        end
                        save_sef(savefilename,data(:,:,out),header.SamplingRate,Channels,events,header.firstindex)
                    elseif strcmp(app.OutputExtension.Value,'.set')
                        try
                            ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                            Channels            = ElectrodeSetting(:,2);
                            %Channels.labels     = char(ElectrodeSetting.labels);

                            % Recompute Theta and Radius to match EEGlab orientation
                            [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                            Channels.theta      = -rad2deg(az);
                            Channels.radius     = 0.5 - rad2deg(elev)/180;
                            Channels.sph_theta  = -Channels.theta;
                            Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                            Channels.X          = ElectrodeSetting.y;
                            Channels.Y          = -ElectrodeSetting.x;
                            Channels.Z          = ElectrodeSetting.z;
    %                         Channels.ref        = char(repmat({''},height(Channels),1));
    %                         Channels.sph_radius = repmat({[]},height(Channels),1);
    %                         Channels.types      = char(repmat({''},height(Channels),1));
    %                         Channels.urchan     = repmat({[]},height(Channels),1);
                            Channels            = table2struct(Channels);
                            save_eeglab(savefilename,data(:,:,out),header.SamplingRate,events,header.firstindex,Channels)
                        catch
                            save_eeglab(savefilename,data(:,:,out),header.SamplingRate,events,header.firstindex)
                        end
                        
                    elseif strcmp(app.OutputExtension.Value,'.ris')
                        save_ris(savefilename,data(:,:,out),header.SamplingRate)
    %                 elseif strcmp(app.OutputExtension.Value,'.freq')
    %                     save_freq(savefilename,data,header.SamplingRate,Resolution,FrequencyName,Channels)
                    end
                %end
                
                    % Update Table
                    %ProcessedDataTable.(1)((file-1) * nOutFiles + out)      = {[FileName, FreqName(out,:), suffix, app.OutputExtension.Value]};
                    %ProcessedDataTable.(2:7)((file-1) * nOutFiles + out)  = app.DataFilesTable.Data(file);
                    %ProcessedDataTable.(3)(file)        = size(data,2);
                end
                
                % File completed
                tMid = toc(tStart);
                disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataFilesTable.Data)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                disp('...............................................................')
            end
            delete(h); %close progression bar
            
            % UPDATE INFORMATION BASED ON THE PROCESSED DATA
            
            % Data table in the Main Window
            app.DataFilesTable.Data   = ProcessedDataTable;
            %app.RootFolder.Value      = app.SaveFolder.Value;
            disp('Data table updated with the exported files')
            cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(data,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataFilesTable.Data.File';num2cell(app.DataFilesTable.Data.(4))';num2cell(app.DataFilesTable.Data.(3))';app.DataFilesTable.Data.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Filtering+ processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            rz = msgbox('Export done !');
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            [FileData,PathData] = uiputfile({'*.csv';'*.xlsx'},'Save data table',[app.StudyName.Value '_DataTable']);
            drawnow; figure(app.EEGpalCS_Main); % Restaure the app window
            if isequal(FileData,0) || isequal(PathData,0)
               return; % User pressed cancel
            end
            writetable(app.DataFilesTable.Data,fullfile(PathData,FileData))
        end

        % Cell edit callback: FolderSelectionTable
        function FolderSelectionTableCellEdit(app, event)
            updateSelection(app)

%             FOLDERSselection = app.FolderSelectionTable.DisplayData;
%             app.DataFilesTable.Data = app.FilesTable(~contains(app.FilesTable.Path,...
%                 FOLDERSselection(cell2mat(FOLDERSselection(:,2))==0,1)),:); % Keep ticked folders
%             app.NumFiles.Value = height(app.DataFilesTable.Data);
%             
%             % update Subject information
%             app.SUBJ = unique(app.DataFilesTable.Data.Subject);
%             app.SubjectSelectionTable.Data = [cellstr(app.SUBJ), repmat({true},[size(app.SUBJ,1) 1])];
%             app.NumFilesFirstSubj.Value = sum(app.DataFilesTable.Data.Subject==app.FirstSubjCode.Value); % Number of files for the first subject
%             app.NumSubj.Value = length(app.SUBJ); % number of subjects for analysis
%             
%             % Check consistency between files
%             consistencyWarning(app)
        end

        % Cell edit callback: SubjectSelectionTable
        function SubjectSelectionTableCellEdit(app, event)
            updateSelection(app)
            
%             SUBJselection = app.SubjectSelectionTable.DisplayData;
%             app.DataFilesTable.Data = app.FilesTable(~contains(app.FilesTable.Subject,...
%                 SUBJselection(cell2mat(SUBJselection(:,2))==0,1)),:); % Keep ticked subjects
%             
%             % Update information
%             app.SUBJ = unique(app.DataFilesTable.Data.Subject); % update SUBJ
%             app.NumFilesFirstSubj.Value = sum(app.DataFilesTable.Data.Subject==app.FirstSubjCode.Value); % Number of files for the first subject
%             app.NumSubj.Value = length(app.SUBJ); % number of subjects for analysis
%                         
%             app.NumFiles.Value = height(app.DataFilesTable.Data);
%             
%             % Check consistency between files
%             consistencyWarning(app)
        end

        % Button pushed function: FilteringButton
        function FilteringButtonPushed(app, event)
            % Check if data files selected
            if isempty(app.DataFilesTable.Data)
                if app.SessionParameters.BatchProcessing.BatchExecution == 0 %case when you the module individually         
                    Filtering = questdlg(sprintf('%s\n\n%s',...
                        'No data files selected.',...
                        'Otherwise, you can configure the processing parameters for your next batch.'),...
                        'Question','PROCEED ANYWAY','Cancel','Cancel');                
                    if strcmpi(Filtering,'Cancel')
                        return
                    end
                else %case when you run a batch
                    msgbox(sprintf('%s\n\n%s\n%s','No data files selected.', ...
                    'The batch process has been interrupted because there is no input data specified in the main EEGpal window',...
                    'Please select input files!'),...
                    'No data specified','warn');
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                    return
                end
            end
            
            % Check if electrode setting exists
            if isempty(app.SessionParameters.Electrodes.SettingTable)
                Filtering = questdlg(sprintf('%s\n%s\n\n%s',...
                    'No electrode setting uploaded.',...
                    'This might reduce the quality of CleanRawData bad channels detection, if you want to use it.',...
                    'Maybe you want to go to the electrode setting module before going to the Filtering module.'),...
                    'Question','PROCEED ANYWAY','Cancel','Cancel');
                
                if strcmpi(Filtering,'Cancel')
                    return
                end
            end
            
            
            % Disable Filtering+ button while dialog is open
            app.FilteringButton.Enable = 'off';
            
            % Open Filtering+ module
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            app.FilteringApp = EEGpalCS_mod_Filtering(app);

        end

        % Button pushed function: InterpolationButton
        function InterpolationButtonPushed(app, event)
            % Check if data files selected
            if isempty(app.DataFilesTable.Data)
                if app.SessionParameters.BatchProcessing.BatchExecution == 0 %case when you the module individually
                    Interpolation = questdlg(sprintf('%s\n\n%s',...
                        'No data files selected.',...
                        'Otherwise, you can configure the processing parameters for your next batch.'),...
                        'Question','PROCEED ANYWAY','Cancel','Cancel');
                    
                    if strcmpi(Interpolation,'Cancel')
                        return
                    end
                else %case when you run a batch
                    msgbox(sprintf('%s\n\n%s\n%s','No data files selected.', ...
                    'The batch process has been interrupted because there is no input data specified in the main EEGpal window',...
                    'Please select input files!'),...
                    'No data specified','warn');
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                    return
                end
            end
            
            % Check if electrode setting exists
            if isempty(app.SessionParameters.Electrodes.SettingTable)
                msgbox(sprintf('%s\n\n%s\n%s','No electrode setting.', ...
                    'Please go to the ''Electrode setting'' module and upload an electrode setting file.',...
                    'No interpolation without it!'),...
                    'No electrodes setting','warn');
                return
            end
            
            
            % Disable Interpolation button while dialog is open
            app.InterpolationButton.Enable = 'off';
            
            % Open Interpolation module
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            app.InterpolationApp = EEGpalCS_mod_Interpolation(app);
        end

        % Button pushed function: RereferenceButton
        function RereferenceButtonPushed(app, event)
            % Check if data files selected
             if isempty(app.DataFilesTable.Data)
                if app.SessionParameters.BatchProcessing.BatchExecution == 0 %case when you the module individually
                    Rereference = questdlg(sprintf('%s\n%s\n\n%s',...
                    'No data files selected.',...
                    'Maybe you want to select files before going to the Rereference module.',...
                    'Otherwise, you can configure the processing parameters for your next batch.'),...
                    'Question','PROCEED ANYWAY','Cancel','Cancel');
                    
                    if strcmpi(Rereference,'Cancel')
                        return
                    end
                else %case when you run a batch
                    msgbox(sprintf('%s\n\n%s\n%s','No data files selected.', ...
                    'The batch process has been interrupted because there is no input data specified in the main EEGpal window',...
                    'Please select input files!'),...
                    'No data specified','warn');
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                    return
                end
            end
            
          
            % Check if electrode setting exists
            if isempty(app.SessionParameters.Electrodes.SettingTable)
                msgbox(sprintf('%s\n\n%s\n%s','No electrode setting.', ...
                    'Please go to the ''Electrode setting'' module and upload an electrode setting file.',...
                    'No rereferencing without it!'),...
                    'No electrodes setting','warn');
                return
            end            


            % Disable Interpolation button while dialog is open
            app.RereferenceButton.Enable = 'off';
            
            % Open Interpolation module
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            app.RereferenceApp = EEGpalCS_mod_Rereference(app);
        end

        % Button pushed function: EpochingButton
        function EpochingButtonPushed(app, event)
            % Check if data files selected
            if isempty(app.DataFilesTable.Data)
                if app.SessionParameters.BatchProcessing.BatchExecution == 0 %case when you the module individually
                    Epoching = questdlg(sprintf('%s\n%s\n\n%s',...
                    'No data files selected.',...
                    'Maybe you want to select files before going to the Epoching module.',...
                    'Otherwise, you can configure the processing parameters for your next batch.'),...
                    'Question','PROCEED ANYWAY','Cancel','Cancel');
                    
                    if strcmpi(Epoching,'Cancel')
                        return
                    end
                else %case when you run a batch
                    msgbox(sprintf('%s\n\n%s\n%s','No data files selected.', ...
                    'The batch process has been interrupted because there is no input data specified in the main EEGpal window',...
                    'Please select input files!'),...
                    'No data specified','warn');
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                    return
                end
            end
            
            % Check if electrode setting exists
            if isempty(app.SessionParameters.Electrodes.SettingTable)
                Epoching = questdlg(sprintf('%s\n\n%s',...
                    'No electrode setting uploaded.',...
                    'In order to enjoy the full EEGpalCS experience, maybe you want to go to the electrode setting module before going to the Epoching module.'),...
                    'Question','PROCEED ANYWAY','Cancel','Cancel');
                
                if strcmpi(Epoching,'Cancel')
                    return
                end
            end
            
            
            % Disable Epoching button while dialog is open
            app.EpochingButton.Enable = 'off';
            
            % Open Epoching module
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            app.EpochingApp = EEGpalCS_mod_Epoching(app);
        end

        % Value changed function: FirstSubjCode
        function FirstSubjCodeValueChanged(app, event)
            if isempty(app.FirstSubjCode.Value)
                app.SubjectSelectionTable.Data  = [];
                app.SelectallButton.Enable      = 0;
                app.DeselectallButton.Enable    = 0;
            elseif ~isempty(app.FirstSubjCode.Value)
                app.SelectallButton.Enable      = 1;
                app.DeselectallButton.Enable    = 1;
                
                % Identify subjects
                app.SUBJ = strings([height(app.FilesTable),1]); % Create array for subject codes
                SubjNameIndex = strfind(app.FilesTable.File{1},app.FirstSubjCode.Value); % Index of Subject code in the file name
                
                % Check subject code could be identified
                if isempty(SubjNameIndex)
                    msgbox(sprintf('%s\n%s\n\n%s%s\n%s%s\n\n%s','Couldn''t find the code of the first subject in the name of the first file.', ...
                        'Please make sure to select the correct files or enter the correct parameters.',...
                        'Name of files: ',app.FilesTable.File{1},...
                        'Code of the first subject entered: ', app.FirstSubjCode.Value,...
                        'Please try again! It must be in the file name (case sensitive)'),...
                        'Subject code not found','warn');
                else
                    % Extract subject codes from files
                    for file = 1:height(app.FilesTable)
                        if SubjNameIndex+strlength(app.FirstSubjCode.Value)-1 > length(app.FilesTable.File{file})
                            continue
                        else
                            app.SUBJ(file,:) = app.FilesTable.File{file}(SubjNameIndex:SubjNameIndex+strlength(app.FirstSubjCode.Value)-1);
                        end
                    end
                    % Updated Subject Column (Files Table)
                    app.FilesTable.Subject = app.SUBJ;
                    
                    % Updated Subject Column (Data Table) and other info
                    toDelete = ~(ismember(app.FilesTable.File,app.DataFilesTable.Data.File) &...
                        ismember(app.FilesTable.Path,app.DataFilesTable.Data.Path));
                    app.SUBJ(toDelete,:) = [];
                    app.DataFilesTable.Data.Subject = app.SUBJ;
                    app.NumFilesFirstSubj.Value = sum(app.SUBJ==app.FirstSubjCode.Value); % Number of files for the first subject
                    app.SUBJ = unique(app.SUBJ(app.SUBJ~='')); % SUBJ list, removing empty rows (added if name of file was shorter than the index of SubjNameIndex)
                    app.NumSubj.Value = length(app.SUBJ); % number of subjects for analysis
                    
                    app.SubjectSelectionTable.Data = [cellstr(app.SUBJ), repmat({true},[size(app.SUBJ,1) 1])];
                end
            end
        end

        % Button pushed function: ElectrodeSettingButton
        function ElectrodeSettingButtonPushed(app, event)
            % Disable Electrodes button while dialog is open
            app.ElectrodeSettingButton.Enable = 'off';
            
            % Open Electrodes module
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            app.ElectrodesApp = EEGpalCS_mod_Electrodes(app);
        end

        % Button pushed function: DesignButton
        function DesignButtonPushed(app, event)
            % Disable Design button while dialog is open
            app.DesignButton.Enable = 'off';
            
            % Open Design module
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            app.DesignSettingApp = EEGpalCS_mod_DesignSetting(app);

            waitfor(app.DesignSettingApp);
            
            if ~isempty(app.StudyName.Value) %check if any parameters has been entered (and the windows has not be close without validation)
                FirstSubjCodeValueChanged(app, event); %update subject liste
                updateDesign(app); %update Condition and Factor_level column 
            end


        end

        % Button pushed function: QUITButton
        function QUITButtonPushed(app, event)
            Exit = questdlg(sprintf('%s\n\n%s',...
                'This will end your session and close all windows.',...
                'Are you sure you want to quit?'),'Exit','QUIT','Cancel','Cancel');
            
            if strcmpi(Exit,'Cancel')
                return
            end
            
            % Delete all apps
            delete(app.ElectrodesApp)
            delete(app.DesignSettingApp)
            delete(app.FilteringApp)
            delete(app.ICA_App)
            delete(app.GEDAI_App)
            delete(app.InterpolationApp)
            delete(app.RereferenceApp)
            delete(app.EpochingApp)
            delete(app.FrequencyingApp)
            delete(app)
        end

        % Close request function: EEGpalCS_Main
        function EEGpalCS_MainCloseRequest(app, event)
            Exit = questdlg(sprintf('%s\n\n%s',...
                'This will end your session and close all windows.',...
                'Are you sure you want to quit?'),'Exit','QUIT','Cancel','Cancel');
            
            if strcmpi(Exit,'Cancel')
                return
            end
            
            % Delete all apps
            delete(app.ElectrodesApp)
            delete(app.DesignSettingApp)
            delete(app.FilteringApp)
            delete(app.ICA_App)
            delete(app.GEDAI_App)
            delete(app.InterpolationApp)
            delete(app.RereferenceApp)
            delete(app.EpochingApp)
            delete(app.FrequencyingApp)
            delete(app)
        end

        % Button pushed function: FrequencyingButton
        function FrequencyingButtonPushed(app, event)
            % Check if data files selected
            if isempty(app.DataFilesTable.Data)
                Frequencying = questdlg(sprintf('%s\n%s\n\n%s',...
                    'No data files selected.',...
                    'Maybe you want to select files before going to the Epoching module.',...
                    'I mean... what am I supposed to epoch if you don''t?'),...
                    'Exit','PROCEED ANYWAY','Cancel','Cancel');
                
                if strcmpi(Frequencying,'Cancel')
                    return
                end
            end
            
            % Check if electrode setting exists
            if isempty(app.SessionParameters.Electrodes.SettingTable)
                Frequencying = questdlg(sprintf('%s\n\n%s',...
                    'No electrode setting uploaded.',...
                    'In order to enjoy the full EEGpalCS experience, maybe you want to go to the electrode setting module before going to the Epoching module.'),...
                    'Exit','PROCEED ANYWAY','Cancel','Cancel');
                
                if strcmpi(Frequencying,'Cancel')
                    return
                end
            end
            
            
            % Disable Frequencying button while dialog is open
            app.FrequencyingButton.Enable = 'off';
            
            % Open Frequencying module
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            app.FrequencyingApp = EEGpalCS_mod_Frequencying(app);
        end

        % Button pushed function: SelectallButton
        function SelectallButtonPushed(app, event)
            if ~isempty(app.SubjectSelectionTable.Data)
                app.SubjectSelectionTable.Data(:,2) = repmat({true},[height(app.SubjectSelectionTable.Data) 1]);
            end
            updateSelection(app)
        end

        % Button pushed function: DeselectallButton
        function DeselectallButtonPushed(app, event)
            if ~isempty(app.SubjectSelectionTable.Data)
                app.SubjectSelectionTable.Data(:,2) = repmat({false},[height(app.SubjectSelectionTable.Data) 1]);
            end
            updateSelection(app)
        end

        % Button pushed function: ICAButton
        function ICAbuttonPushedFunction(app, event)
             % Check if data files selected           
            if isempty(app.DataFilesTable.Data)
                if app.SessionParameters.BatchProcessing.BatchExecution == 0 %case when you the module individually         
                    msgbox(sprintf('%s\n\n%s',...
                        'No data files selected.',...
                        'You need to select files before going to the ICA module.'),...
                        'No data specified','warn');              
                        return
                    
                else %case when you run a batch
                    msgbox(sprintf('%s\n\n%s\n%s','No data files selected.', ...
                    'The batch process has been interrupted because there is no input data specified in the main EEGpal window',...
                    'Please select input files!'),...
                    'No data specified','warn');
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                    return
                end
            end           
 
            % Check if electrode setting exists
            if isempty(app.SessionParameters.Electrodes.SettingTable)
                msgbox(sprintf('%s\n\n%s\n%s','No electrode setting.', ...
                    'Please go to the ''Electrode setting'' module and upload an electrode setting file.',...
                    'No ICA without it!'),...
                    'No electrodes setting','warn');
                return
            end          
            
            
            % Disable ICA+ button while dialog is open
            app.ICAButton.Enable = 'off';
            
            % Open ICA module
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            app.ICA_App = EEGpalCS_mod_ICA(app);
        end

        % Button pushed function: FileCutButton
        function FileCutbuttonPushedFunction(app, event)
            % Check if data files selected
            if isempty(app.DataFilesTable.Data)
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'No data files selected.',...
                    'You need to select files before going to the File Cut module.'),...
                    'Exit','Cancel','Cancel');
                
                if strcmpi(warningBox,'Cancel')
                    return
                end
            end
                                   
            
            % Disable FileCut button while dialog is open
            app.FileCutButton.Enable = 'off';
            
            % Open File Cut module           
            EEGpalCS_mod_FileCut(app);
        end

        % Button pushed function: BridgeDetectionButton
        function BridgeDetectionButtonPushed(app, event)
             % Check if data files selected           
            if isempty(app.DataFilesTable.Data)
                if app.SessionParameters.BatchProcessing.BatchExecution == 0 %case when you the module individually         
                    msgbox(sprintf('%s\n\n%s',...
                        'No data files selected.',...
                        'You need to select files before going to the automatic bridge detection.'),...
                        'No data specified','warn');              
                        return
                    
                else %case when you run a batch
                    msgbox(sprintf('%s\n\n%s\n%s','No data files selected.', ...
                    'The batch process has been interrupted because there is no input data specified in the main EEGpal window',...
                    'Please select input files!'),...
                    'No data specified','warn');
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                    return
                end
            end

            
            % Check if electrode setting exists
            if isempty(app.SessionParameters.Electrodes.SettingTable)
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'No electrode setting uploaded.',...
                    'You need to select the electrode setting module before going to the automatic bridge detection.'),...
                    'Exit','Cancel','Cancel');
                
                if strcmpi(warningBox,'Cancel')
                    return
                end
            end
            
            
            %ask user where to save the result file
            % [file,location] = uiputfile(fullfile('.','eBridge_detection.xlsx'),'Select File where eBridge results will be saved');
            % drawnow; figure(app.EEGpalCS_Main); % Restaure the app window
            
            %recreation of the interpolation table
            if ~isempty(app.DataFilesTable.Data)
                nRows = height(app.DataFilesTable.Data);
            else
                nRows = 1;
            end
            labels      = app.SessionParameters.Electrodes.SettingTable.labels(cell2mat(app.SessionParameters.Electrodes.SettingTable.include));
            nr          = app.SessionParameters.Electrodes.SettingTable.nr(cell2mat(app.SessionParameters.Electrodes.SettingTable.include));
            ColumnNames = ['File','Path','%', cellstr(strcat(labels, ' (',num2str(nr),')'))'];
            eBridgeTable = table('Size',[nRows length(ColumnNames)],...
                'VariableTypes',[{'string','string','single'}, cellstr(repmat('uint8',length(ColumnNames)-3,1))'],'VariableNames',ColumnNames);
            
            %%
            h = waitbar(0,'Processing.....');
            
            %run for each file
            for i=1:nRows
                 waitbar(i/nRows,h, strcat('Processing File ',num2str(i),'/',num2str(nRows)));                 
                 
                 % Load file
                 openfilename = fullfile(app.DataFilesTable.Data.Path{i}(2:end),app.DataFilesTable.Data.File{i}); % full path and name of the file
                 [~,FileName,FileExtension] = fileparts(openfilename);
                 if strcmpi(FileExtension,'.bdf')
                     [header,data,events] = open_bdf(openfilename);
                     events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                     FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                 elseif strcmpi(FileExtension,'.set')
                     [header,data,events] = open_eeglab(openfilename);
                     FileChannels = header.Channels;
                 elseif strcmpi(FileExtension,'.sef')
                     [header,data,events] = open_sef(openfilename);
                     FileChannels = header.Channels;
                 elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                     [header,data,events] = open_eph(openfilename);
                 else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataFilesTable.Data.Path{i}(2:end),app.DataFilesTable.Data.File{i}))])
                    disp('File skipped.')
                    continue
                 end
                 disp(['File loaded: ', app.DataFilesTable.Data.File{i}]);
                
                 % Remove unselected channels from the EEGpalCS Electrode Setting window
                 try
                     data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                     FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                     %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    
                     % Channels check (if electrode setting defined)
                     try
                         Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                         if isequal(char(FileChannels),char(Channels))
                             ChannelsCheck(i,1)   = "OK";
                         else
                             ChannelsCheck(i,1)   = "Selected channels don't match selected channel names in file header";
                         end
                     catch
                         ChannelsCheck(i,1)   = "No channels in file header";
                     end
                 catch
                     ChannelsCheck(i,1)   = "No electrode setting defined";
                 end
                 nChan = size(data,2);
                 
                
                 %convert into an eeglab structure (.set)
                 EEG=struct;
                
                 try
                     ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                     Channels            = ElectrodeSetting(:,2);
                     %Channels.labels     = char(ElectrodeSetting.labels);

                     % Recompute Theta and Radius to match EEGlab orientation
                     [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                     Channels.theta      = -rad2deg(az);
                     Channels.radius     = 0.5 - rad2deg(elev)/180;
                     Channels.sph_theta  = -Channels.theta;
                     Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                     Channels.X          = ElectrodeSetting.y;
                     Channels.Y          = -ElectrodeSetting.x;
                     Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                     Channels            = table2struct(Channels);
                     %Channels            = Channels(app.ChanCompIndices,:); % Keep only channels to process
                 catch
                     Channels = '';
                 end
                 EEG = eeglab_datastruct(openfilename,data,header.SamplingRate,events,Channels);
                 
                 
                 % Run eBridge
                 EB=eBridge(EEG,'PlotMode',0); %execute eBridge        
                 bridges=EB.Bridged.Indices; %output
            
                 
                 %update the eBridgeTable                
                 eBridgeTable.File(i)=app.DataFilesTable.Data.File{i};
                 eBridgeTable.Path(i)=app.DataFilesTable.Data.Path{i};
                 if ~isempty(bridges) %check if any bridge
                     for j=1:length(bridges)                          
                          eBridgeTable{i,bridges(j)+3}=1;
                     end                     
                 end               
             end
            delete(h);
            
            %calucl du pourcentage
            PercentInterp                   = 100 * sum(eBridgeTable{:,4:end},2) / size(eBridgeTable{:,4:end},2);
            %eBridgeTable{:,3}         = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
            eBridgeTable{:,3}         = PercentInterp(:);
            
            %Save the results table in a xlsx file 
            %writetable(eBridgeTable,fullfile(location,file)); % previous version with asking to user where to save the file (replace by automatic due to batch system)
            writetable(eBridgeTable,fullfile(app.RootFolder.Value{:},'Bridge_detection.xlsx'));

            %save in session parameter
            app.SessionParameters.ebridge.eBridgeTable=eBridgeTable;           
            

            if app.SessionParameters.BatchProcessing.BatchExecution == 0
                rz = msgbox('eBridge check done !');
            else %batch mode module successly completed                
                app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=1; %process complited
            end
            
        end

        % Button pushed function: PeaksorTraceexportButton
        function PeaksorTraceexportButtonButtonPushed(app, event)
            % Check if data files selected
            if isempty(app.DataFilesTable.Data)
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'No data files selected.',...
                    'You need to select files ERP files in the main windows before going to the Trace/Peaks export module.'),...
                    'No files selected','Cancel','Cancel');
                
                if or(strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end
            
             % Check if file are not .freq file
            [~,~,FileExtension] = fileparts(app.DataFilesTable.Data.File{1});
            if strcmp(FileExtension, '.freq')
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'File of the type .freq not supported!',...
                    'You have selected .freq files. The Peak/Trace export module does not support this type of file.',...
                    'However, if you want to perform a processing, first export them into 2D files by using EEGpal''s Export tool. Then you can use the Peak/Trace export module on them.'),...
                    'Incompatible file format','Cancel','Cancel');
                
                if or(strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end 
            
            % Test if all files have the same length 
            if length(unique(app.DataFilesTable.Data.nTF)) > 1
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'Input files have inequal length.',...
                    'The Trace/Peak export module required ERP files with same length.'),...
                    'Inequal length of input files','Cancel','Cancel');             
                
                if or (strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end
            
            % Test if all files have the same samplingrate 
            if length(unique(app.DataFilesTable.Data.SamplingRate)) > 1
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'Input files have different sampling rate.',...
                    'The Trace/Peaks export module required ERP files with same sampling rate.'),...
                    'Different sampling rate of input files','Cancel','Cancel');             
                
                if or (strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end

            % Test if all files have the same number of channels 
            if length(unique(app.DataFilesTable.Data.nChan)) > 1
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'Input files have different number of channels.',...
                    'The Trace/Peaks export module required ERP files with same number of channels across files.'),...
                    'Different number of channels across input files','Cancel','Cancel');             
                
                if or (strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end
                        
            % Disable FileCut button while dialog is open
            app.PeaksorTraceexportButton.Enable = 'off';
            
            % Open File Cut module           
            EEGpalCS_mod_PeaksTraceExport(app);


        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.AppPath.value, '\Manuels\Manuel_EEGpal_Main.pdf');
                winopen(filePath);  
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_EEGpal_Main.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_EEGpal_Main.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Button pushed function: StatisticsButton
        function StatisticsButtonPushed(app, event)
            % Check if data files selected
            if isempty(app.DataFilesTable.Data)
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'No data files selected.',...
                    'You need to select ERP, PSD or IS files in the main windows before going to the Statistics module.'),...
                    'No files selected','Cancel','Cancel');
                
                if or(strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end
                       
            % Check if file are not .freq file
            [~,~,FileExtension] = fileparts(app.DataFilesTable.Data.File{1});
            if strcmp(FileExtension, '.freq')
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'File of the type .freq not supported!',...
                    'You have selected .freq files. The Statistics module does not support this type of file.',...
                    'However, if you want to perform an analysis, first export them into 2D files by using EEGpal''s Export tool. Then you can use the Statsical module on them.'),...
                    'Incompatible file format','Cancel','Cancel');
                
                if or(strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end            
            
            % Test if all files have the same length 
            if length(unique(app.DataFilesTable.Data.nTF)) > 1
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'Input files have inequal length.',...
                    'The Statistics module required files with same length.'),...
                    'Inequal length of input files','Cancel','Cancel');             
                
                if or (strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end
            
            % Test if the Design information has been entered
            nElements = length(app.DataFilesTable.Data.FactorLevel);
            conditions = cell(1, nElements);
            for i = 1:nElements
                conditions{i} = sprintf('app.DataFilesTable.Data.FactorLevel(%d)==""', i);
            end
            finalCommand = strjoin(conditions, ' && ');
            if eval(finalCommand)
            %if app.DataFilesTable.Data.FactorLevel(1)=="" && app.DataFilesTable.Data.FactorLevel(2)=="" 
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'Design not specified.',...
                    'Before to run the Statistics module, you need to specify the experimental design. Please use the button"Full Design"'),...
                    'Design not specified','Cancel','Cancel');             
                
                if or (strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end
            
            % Test if all files have the same samplingrate 
            if length(unique(app.DataFilesTable.Data.SamplingRate)) > 1
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'Input files have different sampling rate.',...
                    'The Statistics module required files with same sampling rate.'),...
                    'Different sampling rate of input files','Cancel','Cancel');             
                
                if or (strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end

            % Test if all files have the same number of channels 
            if length(unique(app.DataFilesTable.Data.nChan)) > 1
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'Input files have different number of channels.',...
                    'The Statistics module required files with same number of channels across files.'),...
                    'Different number of channels across input files','Cancel','Cancel');             
                
                if or (strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end

            %check if participant have been specified
            if length(unique(app.DataFilesTable.Data.Subject)) < 2
                 warningBox = questdlg(sprintf('%s\n\n%s',...
                    'No or only one participant specified',...
                    'The Statistics module required to specified at least 2 different participants. Please fill the ''Code of FIRST participant'' field'),...
                    'Need to specify the subjects','Cancel','Cancel');             
                
                if or (strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end

            % Check if Statistics_Toolbox available
            if  ~license('test', 'Statistics_Toolbox') & app.SessionParameters.Design.NumFact == 1 & app.SessionParameters.Design.NumCond == 2 %Test-test only (1 factor and 2 conditions)
                    Statistics = questdlg(sprintf('%s\n\n%s',...
                        'The Statistics and Machine Learning toolbox is not available.',...
                        'You can only use the non-parametric paired or unpaired t-test, with or without cluster analysis, because it uses FieldTrip functions included in EEGpal.'),...
                        'Question','PROCEED ANYWAY','Cancel','Cancel');
                    
                    if strcmpi(Statistics,'Cancel')
                        return
                    end
            elseif ~license('test', 'Statistics_Toolbox')
                   msgbox(sprintf('%s\n\n%s','Statistics_toolbox no installed!', ...
                        'The Statistics and Machine Learning toolbox is not available. Unfortunately, it is not possible to run this module with the design you have specified.'),...
                        'Statistics_toolbox no installed','warn');
                    return                  
            end

            %check if there is serveral files associate per condition per participant            
            participants_conditions = [app.DataFilesTable.Data.Subject(:),app.DataFilesTable.Data.FactorLevel(:)];
            valid_rows = participants_conditions(:,2) ~= ""; 
            filtered_data = participants_conditions(valid_rows, :);
            keys = strcat(filtered_data(:,1), "_", filtered_data(:,2));
            [C, ~, ic] = unique(keys);
            counts = histcounts(ic, 1:max(ic)+1);
            doublons = C(counts > 1, :);
            if ~isempty(doublons)
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'There is serveral files associate per condition.',...
                    'Unfortunately, the Statisticals module can handle only one file per condition for each subject. Please use the Epoching module to average the files in order to have only one file per conditions per subject.'),...
                    'Multiple files per condition','Cancel','Cancel');             
                
                if or (strcmpi(warningBox,'Cancel'), isempty(warningBox))
                    return
                end
            end

             
            % Disable FileCut button while dialog is open
            app.StatisticsButton.Enable = 'off'; %to restaure
            
            % Open File Cut module           
            EEGpalCS_mod_Statistics(app); 
        end

        % Button pushed function: SaveAsButtonSessionParameters
        function SaveAsButtonSessionParametersPushed(app, event)
            % Save parameter file
            [File,Path] = uiputfile('*.mat','Save processing parameter file','processingParameters_EEGpal.mat');
            drawnow; figure(app.EEGpalCS_Main); % Restaure the app window
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.SessionParametersFilePath.Value = fullfile(Path,File);

            Parameters = app.SessionParameters;

            save(app.SessionParametersFilePath.Value,'-struct','Parameters');
            app.BatchButton.Enable = "on"; %allow batch system
        end

        % Button pushed function: OpenButtonSessionParameters
        function OpenButtonSessionParametersPushed(app, event)
            [File,Path] = uigetfile('*.mat','Select processing parameter file','processingParameters_EEGpal.mat');
            drawnow; figure(app.EEGpalCS_Main); % Restaure the app window
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.SessionParametersFilePath.Value = fullfile(Path,File);
            Parameters = load(app.SessionParametersFilePath.Value);


            %retrocompatibility
            %reref before 2.16            
            if isfield(Parameters, 'Rereference') && isfield(Parameters.Rereference, 'FilesExtention') %previous SessionParameter file
                Parameters.Rereference.FilesExtention_1    = Parameters.Rereference.FilesExtention;
                Parameters.Rereference.FilesExtention_2    = Parameters.Rereference.FilesExtention;
                Parameters.Rereference.SaveFolder_1        = Parameters.Rereference.SaveFolder;
                Parameters.Rereference.SaveFolder_2        = Parameters.Rereference.SaveFolder;
                Parameters.Rereference.SuffixString_1      = Parameters.Rereference.SuffixString;
                Parameters.Rereference.SuffixString_2      = Parameters.Rereference.SuffixString;
                Parameters.Rereference.ReferenceItems_1    = Parameters.Rereference.ReferenceItems;
                Parameters.Rereference.ReferenceItems_2    = Parameters.Rereference.ReferenceItems;
                Parameters.Rereference.ReferenceValue_1    = Parameters.Rereference.ReferenceValue;
                Parameters.Rereference.ReferenceValue_2    = Parameters.Rereference.ReferenceValue;
                Parameters.Rereference.ClusterItems_1      = Parameters.Rereference.ClusterItems;
                Parameters.Rereference.ClusterItems_2      = Parameters.Rereference.ClusterItems;
                Parameters.Rereference.ClusterValue_1      = Parameters.Rereference.ClusterValue;
                Parameters.Rereference.ClusterValue_2      = Parameters.Rereference.ClusterValue;
                Parameters.Rereference=rmfield(Parameters.Rereference,'FilesExtention');
                Parameters.Rereference=rmfield(Parameters.Rereference,'SaveFolder');
                Parameters.Rereference=rmfield(Parameters.Rereference,'SuffixString');
                Parameters.Rereference=rmfield(Parameters.Rereference,'ReferenceItems');
                Parameters.Rereference=rmfield(Parameters.Rereference,'ReferenceValue');
                Parameters.Rereference=rmfield(Parameters.Rereference,'ClusterItems');
                Parameters.Rereference=rmfield(Parameters.Rereference,'ClusterValue');
            end
            %Batch parameter with a Reref
            if isfield(Parameters, 'BatchProcessing') && isfield(Parameters.BatchProcessing, 'Processes') %previous SessionParameter file
                for i=1:size(Parameters.BatchProcessing.Processes,1)
                    if strcmp(Parameters.BatchProcessing.Processes{i,1},'Re-reference')
                        Parameters.BatchProcessing.Processes{i,1}='Rereference_Reref1';
                    end
                end
            end            

            % Put Parameter in app.SessionParameters if the file is valid
            if ~isfield(Parameters, 'Electrodes')
                msgbox(sprintf('%s','No processing parameters saved in this file.'),...
                    'No parameters','warn');
            else
                 app.SessionParameters  = Parameters;
                 app.BatchButton.Enable = "on";
                 if isfield(Parameters.BatchProcessing,'Processes')
                    app.RunBatchButton.Enable = 'on';
                 end
            end


        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.SessionParametersFilePath.Value     = ""; % remove selected file
            app.SessionParameters                   = struct (); %vider session parameter           
            app.SessionParameters.Electrodes.SettingTable = [];
            app.SessionParameters.BatchProcessing.BatchExecution = 0;
            app.BatchButton.Enable = "off"; % disable batch system
            app.RunBatchButton.Enable = 'off';
            app.InBatchModeCheckBox.Value = 0;

        end

        % Button pushed function: BatchButton
        function BatchButtonPushed(app, event)
            % Check if there is a Processing/Analysis parameters
            if isempty(app.SessionParametersFilePath.Value)
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'No Processing/Analysis parameters files selected.',...
                    'You need to save or open a Processing/Analysis parameters file before going to the BATCH Processing Tool.'),...
                    'Exit','Cancel','Cancel');
                
                if strcmpi(warningBox,'Cancel')
                    return
                end
            end

            % Check if data files selected
            if isempty(app.DataFilesTable.Data)
                warningBox = questdlg(sprintf('%s\n\n%s',...
                    'No data files selected.',...
                    'You need to select files before going to the BATCH Processing tool.'),...
                    'Exit','Cancel','Cancel');
                
                if strcmpi(warningBox,'Cancel')
                    return
                end
            end
            
            % Check if electrode setting exists
            if isempty(app.SessionParameters.Electrodes.SettingTable)
                msgbox(sprintf('%s\n\n%s\n%s','No electrode setting.', ...
                    'Please go to the ''Electrode setting'' module and upload an electrode setting file.',...
                    'No BATCH Processing without it!'),...
                    'No electrodes setting','warn');
                return
            end

            % Disable Batch button while dialog is open
            app.BatchButton.Enable = 'off';

            % Open Batch tool
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            EEGpalCS_mod_BatchProcessing(app);
        end

        % Button pushed function: RunBatchButton
        function RunBatchButtonPushed(app, event)
            
            nbProcesses = size (app.SessionParameters.BatchProcessing.Processes,1);
            if nbProcesses>0 %check if there is a list of                  
                app.SessionParameters.BatchProcessing.BatchExecution = 1;
                app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                
                %Start of batch
                waitBarBatch = waitbar(0,'Batch Processing.....'); %wait bar
                for k=1:nbProcesses                    
                    if app.SessionParameters.BatchProcessing.Processes{k,3} ~= 1 %don't run the current module if it is already process
                        app.SessionParameters.BatchProcessing.nbCurrentProcess = k; %store the number of process in order the module can retrieve the correct option
                        switch app.SessionParameters.BatchProcessing.Processes{k,1}
                            case 'Bridge Detection'
                                try
                                    waitbar(k/nbProcesses,waitBarBatch, 'Running Batch: Bridge detection');
                                    if k==1
                                        cd(app.RootFolder.Value{1});
                                    end

                                    BridgeDetectionButtonPushed(app); %run module
                                    
                                    if app.SessionParameters.BatchProcessing.Processes{k,3} ~= 1 %test if we can continue the batch or not                               
                                        delete(waitBarBatch);
                                        app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                        app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                        return %interrumpt the batch
                                    end
                                catch ME
                                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                    msgbox(sprintf('%s\n\n%s\n%s','Error in the Bridge detection', ...
                                        'The Batch has been interrupted due to an error in the Bridge detection module.',...
                                        'The error message is displayed in the command window of Matlab. Please solve the issue.'),...
                                        'Batch Error','error');
                                    
                                    disp('interruption of the batch during the Bridge deection.');
                                    rethrow(ME); %display the error message which was supposed to display in the command window
                                    delete(waitBarBatch);
                                    app.InBatchModeCheckBox.Value = false;
                                    return
                                end
                            case 'Filtering'
                                try                            
                                    waitbar(k/nbProcesses,waitBarBatch, 'Running Batch: Filerting module');
                                    if strcmp (app.SessionParameters.BatchProcessing.WhereToSave, 'In the following folder') %change output folder if required
                                        app.SessionParameters.Filtering.SaveFolder = {fullfile(app.SessionParameters.BatchProcessing.SaveFolder{1},strcat('0',num2str(k),'_Filtering'))};
                                        if ~isfolder(app.SessionParameters.Filtering.SaveFolder{1}) 
                                            mkdir(app.SessionParameters.Filtering.SaveFolder{1})
                                        end                                        
                                    end
                                    
                                    FilteringButtonPushed(app); %run the module

                                    if app.SessionParameters.BatchProcessing.Processes{k,3} ~= 1 %break the batch                              
                                        delete(waitBarBatch);
                                        app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                        app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                        return %interrumpt the batch
                                    end
                                catch ME
                                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                    msgbox(sprintf('%s\n\n%s\n%s','Error in the Filtering module', ...
                                        'The Batch has been interrupted due to an error in the Filtering process.',...
                                        'The error message is displayed in the command window of Matlab. Please open the Filtering module and solve the issue.'),...
                                        'Batch Error','error');
                                    
                                    disp('interruption of the batch during the Filtering module');
                                    rethrow(ME); %display the error message which was supposed to display in the command window
                                    delete(waitBarBatch);
                                    app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                    return
                                end
    
                            case 'ICA'
                                try                            
                                    waitbar(k/nbProcesses,waitBarBatch, 'Running Batch: ICA module');
                                    if strcmp (app.SessionParameters.BatchProcessing.WhereToSave, 'In the following folder') %change output folder if required
                                        app.SessionParameters.ICA.SaveFolder = {fullfile(app.SessionParameters.BatchProcessing.SaveFolder{1},strcat('0',num2str(k),'_ICApruned'))};
                                        if ~isfolder(app.SessionParameters.ICA.SaveFolder{1}) 
                                            mkdir(app.SessionParameters.ICA.SaveFolder{1})
                                        end                                        
                                    end
                                    
                                    ICAbuttonPushedFunction(app); %execute the module

                                    if app.SessionParameters.BatchProcessing.Processes{k,3} ~= 1 %break the batch                              
                                        delete(waitBarBatch);
                                        app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                        app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                        return %interrumpt the batch
                                    end
                                catch ME
                                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                    msgbox(sprintf('%s\n\n%s\n%s','Error in the ICA module', ...
                                        'The Batch has been interrupted due to an error in the ICA process.',...
                                        'The error message is displayed in the command window of Matlab. Please open the ICA module and solve the issue.'),...
                                        'Batch Error','error');
                                    
                                    disp('interruption of the batch during the ICA module');
                                    rethrow(ME); %display the error message which was supposed to display in the command window
                                    delete(waitBarBatch);
                                    app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                    return
                                end
                            case 'Interpolation'
                                try                            
                                    waitbar(k/nbProcesses,waitBarBatch, 'Running Batch: Interpolation module');
                                    if strcmp (app.SessionParameters.BatchProcessing.WhereToSave, 'In the following folder') %change output folder if required
                                        app.SessionParameters.Interpolation.SaveFolder = {fullfile(app.SessionParameters.BatchProcessing.SaveFolder{1},strcat('0',num2str(k),'_Interpolation'))};
                                        if ~isfolder(app.SessionParameters.Interpolation.SaveFolder{1}) 
                                            mkdir(app.SessionParameters.Interpolation.SaveFolder{1})
                                        end                                        
                                    end
                                    
                                    InterpolationButtonPushed(app); %execute the module

                                    if app.SessionParameters.BatchProcessing.Processes{k,3} ~= 1 %break the batch
                                        delete(waitBarBatch);
                                        app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                        app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                        return %interrumpt the batch
                                    end
                                catch ME
                                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                    msgbox(sprintf('%s\n\n%s\n%s','Error in the Interpolation module', ...
                                        'The Batch has been interrupted due to an error in the interpolation process.',...
                                        'The error message is displayed in the command window of Matlab. Please open the Interpolation module and solve the issue.'),...
                                        'Batch Error','error');
                                    
                                    disp('interruption of the batch during the Interpolation module');
                                    rethrow(ME); %display the error message which was supposed to display in the command window
                                    delete(waitBarBatch);
                                    app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                    return
                                end
                            case 'Rereference_Reref1'
                                try                            
                                    waitbar(k/nbProcesses,waitBarBatch, 'Running Batch: Re-reference module (Reref1)');
                                    if strcmp (app.SessionParameters.BatchProcessing.WhereToSave, 'In the following folder') %change output folder if required
                                        app.SessionParameters.Rereference.SaveFolder_1 = {fullfile(app.SessionParameters.BatchProcessing.SaveFolder{1},strcat('0',num2str(k),'_Reref'))};
                                        if ~isfolder(app.SessionParameters.Rereference.SaveFolder_1{1}) 
                                            mkdir(app.SessionParameters.Rereference.SaveFolder_1{1})
                                        end                                        
                                    end
                                    
                                    RereferenceButtonPushed(app); %execute the module

                                    if app.SessionParameters.BatchProcessing.Processes{k,3} ~= 1 %break the batch
                                        delete(waitBarBatch);
                                        app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                        app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                        return %interrumpt the batch
                                    end
                                catch ME
                                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                    msgbox(sprintf('%s\n\n%s\n%s','Error in the Re-reference module (Reref1)', ...
                                        'The Batch has been interrupted due to an error in the Re-referencing process (Reref1).',...
                                        'The error message is displayed in the command window of Matlab. Please open the Re-reference module (pannel Reref1) and solve the issue.'),...
                                        'Batch Error','error');
                                    
                                    disp('interruption of the batch during the Re-reference module (Reref1)');
                                    rethrow(ME); %display the error message which was supposed to display in the command window
                                    delete(waitBarBatch);
                                    app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                    return
                                end
                            case 'Rereference_Reref2'
                                try                            
                                    waitbar(k/nbProcesses,waitBarBatch, 'Running Batch: Re-reference module (Reref2)');
                                    if strcmp (app.SessionParameters.BatchProcessing.WhereToSave, 'In the following folder') %change output folder if required
                                        app.SessionParameters.Rereference.SaveFolder_2 = {fullfile(app.SessionParameters.BatchProcessing.SaveFolder{1},strcat('0',num2str(k),'_Reref'))};
                                        if ~isfolder(app.SessionParameters.Rereference.SaveFolder_2{1}) 
                                            mkdir(app.SessionParameters.Rereference.SaveFolder_2{1})
                                        end                                        
                                    end
                                    
                                    RereferenceButtonPushed(app); %execute the module

                                    if app.SessionParameters.BatchProcessing.Processes{k,3} ~= 1 %break the batch
                                        delete(waitBarBatch);
                                        app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                        app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                        return %interrumpt the batch
                                    end
                                catch ME
                                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                    msgbox(sprintf('%s\n\n%s\n%s','Error in the Re-reference module (Reref2)', ...
                                        'The Batch has been interrupted due to an error in the Re-referencing process (Reref2).',...
                                        'The error message is displayed in the command window of Matlab. Please open the Re-reference module (pannel Reref2) and solve the issue.'),...
                                        'Batch Error','error');
                                    
                                    disp('interruption of the batch during the Re-reference module (Reref2)');
                                    rethrow(ME); %display the error message which was supposed to display in the command window
                                    delete(waitBarBatch);
                                    app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                    return
                                end
                            case 'Epoching'
                                try                            
                                    waitbar(k/nbProcesses,waitBarBatch, 'Running Batch: Epoching module');
                                    if strcmp (app.SessionParameters.BatchProcessing.WhereToSave, 'In the following folder') %change output folder if required
                                        app.SessionParameters.Epoching.SaveFolder = {fullfile(app.SessionParameters.BatchProcessing.SaveFolder{1},strcat('0',num2str(k),'_Epoched'))};
                                        if ~isfolder(app.SessionParameters.Epoching.SaveFolder{1}) 
                                            mkdir(app.SessionParameters.Epoching.SaveFolder{1})
                                        end                                        
                                    end
                                    
                                    EpochingButtonPushed(app); %execute the module

                                    if app.SessionParameters.BatchProcessing.Processes{k,3} ~= 1 %break the batch
                                        delete(waitBarBatch);
                                        app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                        app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                        return %interrumpt the batch
                                    end
                                catch ME
                                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                    msgbox(sprintf('%s\n\n%s\n%s','Error in the Epoching module', ...
                                        'The Batch has been interrupted due to an error in the Epoching process.',...
                                        'The error message is displayed in the command window of Matlab. Please open the Epoching module and solve the issue.'),...
                                        'Batch Error','error');
                                    
                                    disp('interruption of the batch during the Epoching module');
                                    rethrow(ME); %display the error message which was supposed to display in the command window
                                    delete(waitBarBatch);
                                    app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                    return
                                end
                            case 'GEDAI'
                                try                            
                                    waitbar(k/nbProcesses,waitBarBatch, 'Running Batch: GEDAI module');
                                    if strcmp (app.SessionParameters.BatchProcessing.WhereToSave, 'In the following folder') %change output folder if required
                                        app.SessionParameters.GEDAI.SaveFolder = {fullfile(app.SessionParameters.BatchProcessing.SaveFolder{1},strcat('0',num2str(k),'_GEDAI'))};
                                        if ~isfolder(app.SessionParameters.GEDAI.SaveFolder{1}) 
                                            mkdir(app.SessionParameters.GEDAI.SaveFolder{1})
                                        end                                        
                                    end
                                    
                                    GEDAIButtonPushed(app); %execute the module

                                    if app.SessionParameters.BatchProcessing.Processes{k,3} ~= 1 %break the batch
                                        delete(waitBarBatch);
                                        app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                        app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                        return %interrumpt the batch
                                    end
                                catch ME
                                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                                    msgbox(sprintf('%s\n\n%s\n%s','Error in the GEDAI module', ...
                                        'The Batch has been interrupted due to an error in the GEDAI process.',...
                                        'The error message is displayed in the command window of Matlab. Please open the GEDAI module and solve the issue.'),...
                                        'Batch Error','error');
                                    
                                    disp('interruption of the batch during the GEDAI module');
                                    rethrow(ME); %display the error message which was supposed to display in the command window
                                    delete(waitBarBatch);
                                    app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                                    return
                                end
                                
                        end
                    end
                end
                delete(waitBarBatch);
                app.SessionParameters.BatchProcessing.BatchExecution = 0;
                app.InBatchModeCheckBox.Value = app.SessionParameters.BatchProcessing.BatchExecution;
                rz = msgbox('Batch processing done.');


            else
                msgbox(sprintf('%s\n\n%s\n%s','No configuration for Batch', ...
                    'You have to specify at least one process in the list specified in ''BATCH parameters''.',...
                    'No BATCH Processing without it!'),...
                    'No Batch list specified!','warn');
                return
            end

        end

        % Value changed function: PresetParametersSwitch
        function PresetParametersSwitchValueChanged(app, event)
            if strcmp(app.PresetParametersSwitch.Value, 'eeglab')
                app.OutputExtension.Value = '.set';
            else
                app.OutputExtension.Value = '.sef';
            end
        end

        % Button pushed function: GEDAIButton
        function GEDAIButtonPushed(app, event)
             % Check if data files selected           
            if isempty(app.DataFilesTable.Data)
                if app.SessionParameters.BatchProcessing.BatchExecution == 0 %case when you the module individually         
                    msgbox(sprintf('%s\n\n%s',...
                        'No data files selected.',...
                        'You need to select files before going to the GEDAI module.'),...
                        'No data specified','warn');              
                        return
                    
                else %case when you run a batch
                    msgbox(sprintf('%s\n\n%s\n%s','No data files selected.', ...
                    'The batch process has been interrupted because there is no input data specified in the main EEGpal window',...
                    'Please select input files!'),...
                    'No data specified','warn');
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.SessionParameters.BatchProcessing.BatchExecution = 0;
                    return
                end
            end           
 
            % Check if electrode setting exists
            if isempty(app.SessionParameters.Electrodes.SettingTable)
                msgbox(sprintf('%s\n\n%s\n%s','No electrode setting.', ...
                    'Please go to the ''Electrode setting'' module and upload an electrode setting file.',...
                    'No GEDAI correction without it!'),...
                    'No electrodes setting','warn');
                return
            end          
            
            
            % Disable GEDAI button while dialog is open
            app.GEDAIButton.Enable = 'off';
            
            % Open GEDAI module
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            app.GEDAI_App = EEGpalCS_mod_GEDAI(app);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEGpalCS_Main.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {908, 908, 908};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {908, 908};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x', 275};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EEGpalCS_Main = uifigure('Visible', 'off');
            app.EEGpalCS_Main.Name = 'EEGpal_Main_Window';
            app.EEGpalCS_Main.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_MainCloseRequest, true);
            app.EEGpalCS_Main.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEGpalCS_Main);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Image = uiimage(app.LeftPanel);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'dialog', 'logoEEGpal.jpg');
            app.EEGpalLabel = uilabel(app.LeftPanel);
            app.EEGpalLabel.Text = 'EEGpal';
            app.InputParametersFile = uilabel(app.LeftPanel);
            app.InputParametersFile.Text = 'Input files parameters';
            app.OpenButton = uibutton(app.LeftPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Tooltip = {'Open existing input file parameter file. Will update the data table'};
            app.OpenButton.Text = 'Open';
            app.SaveAsButton = uibutton(app.LeftPanel, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Tooltip = {'Save a new input parameter file'};
            app.SaveAsButton.Text = 'Save as';
            app.ParameterFilePath = uieditfield(app.LeftPanel, 'text');
            app.ParameterFilePath.Editable = 'off';
            app.FilesSelection = uilabel(app.LeftPanel);
            app.FilesSelection.Text = 'Files selection';
            app.ExtensionofinputfilesCtrlclicktoselectmultipleextensionsLabel = uilabel(app.LeftPanel);
            app.ExtensionofinputfilesCtrlclicktoselectmultipleextensionsLabel.Text = {'Extension of input files:'; '    Ctrl+click to select'; '    multiple extensions'};
            app.FilesExtension = uilistbox(app.LeftPanel);
            app.FilesExtension.Items = {'.bdf', '.ep', '.eph', '.sef', '.set', '.ris', '.freq', '.eeg/.vhdr'};
            app.FilesExtension.Multiselect = 'on';
            app.FilesExtension.Value = {'.bdf'};
            app.InclusionstringforselectingsubsetoffilesoptionalLabel = uilabel(app.LeftPanel);
            app.InclusionstringforselectingsubsetoffilesoptionalLabel.Text = {'Inclusion string for selecting subset of files'; '(optional):'};
            app.FilterString = uieditfield(app.LeftPanel, 'text');
            app.FilterString.Tooltip = {'Currently only works when used before selecting Input Folder.'};
            app.ExclusionstringforexcludingsubsetoffilesoptionalLabel = uilabel(app.LeftPanel);
            app.ExclusionstringforexcludingsubsetoffilesoptionalLabel.Text = {'Exclusion string for excluding subset of files'; '(optional):'};
            app.ExclusionString = uieditfield(app.LeftPanel, 'text');
            app.ExclusionString.Tooltip = {'Currently only works when used before selecting Input Folder.'};
            app.InputUpperFolderLabel = uilabel(app.LeftPanel);
            app.InputUpperFolderLabel.Text = {'Select uppermost folder '; 'containing the files:'};
            app.InputFolderButton = uibutton(app.LeftPanel, 'push');
            app.InputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @InputFolderButtonPushed, true);
            app.InputFolderButton.Text = 'Input Folder';
            app.RootfolderLabel = uilabel(app.LeftPanel);
            app.RootfolderLabel.Text = 'Root folder:';
            app.RootFolder = uitextarea(app.LeftPanel);
            app.RootFolder.Editable = 'off';
            app.SavingOptionsLabel = uilabel(app.LeftPanel);
            app.SavingOptionsLabel.Text = 'Saving options';
            app.ExtensionofoutputfilesLabel = uilabel(app.LeftPanel);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.OutputExtension = uidropdown(app.LeftPanel);
            app.OutputExtension.Items = {'.ep', '.eph', '.sef', '.set', '.ris'};
            app.OutputExtension.Value = '.sef';
            app.SavingUpperFolderLabel = uilabel(app.LeftPanel);
            app.SavingUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.DataSavingButton = uibutton(app.LeftPanel, 'push');
            app.DataSavingButton.ButtonPushedFcn = createCallbackFcn(app, @DataSavingButtonPushed, true);
            app.DataSavingButton.Text = 'Output Folder';
            app.SavingfolderLabel = uilabel(app.LeftPanel);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.LeftPanel);
            app.SaveFolder.Editable = 'off';
            app.QUITButton = uibutton(app.LeftPanel, 'push');
            app.QUITButton.ButtonPushedFcn = createCallbackFcn(app, @QUITButtonPushed, true);
            app.QUITButton.Text = 'QUIT';
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.TOOLSPanel = uipanel(app.LeftPanel);
            app.TOOLSPanel.Title = 'TOOLS';
            app.ExportButton = uibutton(app.TOOLSPanel, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.ConvertLabel = uilabel(app.TOOLSPanel);
            app.ConvertLabel.Text = {'Convert files in the'; 'selected format:'};
            app.FileCutButton = uibutton(app.TOOLSPanel, 'push');
            app.FileCutButton.ButtonPushedFcn = createCallbackFcn(app, @FileCutbuttonPushedFunction, true);
            app.FileCutButton.Text = 'File Cut';
            app.FileCutLabel = uilabel(app.TOOLSPanel);
            app.FileCutLabel.Text = 'Tool to cut your EEG files according the triggers';
            app.CenterPanel = uipanel(app.GridLayout);
            app.DATALabel = uilabel(app.CenterPanel);
            app.DATALabel.Text = 'DATA';
            app.DataFilesTable = uitable(app.CenterPanel);
            app.DataFilesTable.ColumnName = {'File'; 'Path'; 'N. channels'; 'Sampling Rate'; 'N. time-frames'; 'Duration'; 'Subject'; 'Condition'; 'Factor_level'};
            app.DataFilesTable.RowName = {''};
            app.SaveTableButton = uibutton(app.CenterPanel, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save table as csv file';
            app.WarningLabel = uilabel(app.CenterPanel);
            app.WarningLabel.Text = 'Warning:';
            app.Warning = uitextarea(app.CenterPanel);
            app.Warning.Editable = 'off';
            app.Warning.Tooltip = {''};
            app.versionText = uilabel(app.CenterPanel);
            app.versionText.Text = 'Toolbox Version 2.16';
            app.ProcessingAnalysisparametersLabel = uilabel(app.CenterPanel);
            app.ProcessingAnalysisparametersLabel.Text = 'Processing/Analysis parameters';
            app.SessionParametersFilePath = uieditfield(app.CenterPanel, 'text');
            app.SessionParametersFilePath.Editable = 'off';
            app.OpenButtonSessionParameters = uibutton(app.CenterPanel, 'push');
            app.OpenButtonSessionParameters.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonSessionParametersPushed, true);
            app.OpenButtonSessionParameters.Tooltip = {'Open existing parameter file. Will update options in this and other windws of EEGpal'};
            app.OpenButtonSessionParameters.Text = 'Open';
            app.SaveAsButtonSessionParameters = uibutton(app.CenterPanel, 'push');
            app.SaveAsButtonSessionParameters.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonSessionParametersPushed, true);
            app.SaveAsButtonSessionParameters.Tooltip = {'Create a new parameter file.'};
            app.SaveAsButtonSessionParameters.Text = 'Save as';
            app.BatchButton = uibutton(app.CenterPanel, 'push');
            app.BatchButton.ButtonPushedFcn = createCallbackFcn(app, @BatchButtonPushed, true);
            app.BatchButton.Enable = 'off';
            app.BatchButton.Tooltip = {'Parameter the Batch processing'};
            app.BatchButton.Text = 'BATCH parameters';
            app.ResetButton = uibutton(app.CenterPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Remove all parameters from the memory'};
            app.ResetButton.Text = 'Reset';
            app.RunBatchButton = uibutton(app.CenterPanel, 'push');
            app.RunBatchButton.ButtonPushedFcn = createCallbackFcn(app, @RunBatchButtonPushed, true);
            app.RunBatchButton.Enable = 'off';
            app.RunBatchButton.Tooltip = {'Execute the BATCH'};
            app.RunBatchButton.Text = 'RUN BATCH';
            app.InBatchModeCheckBox = uicheckbox(app.CenterPanel);
            app.InBatchModeCheckBox.Enable = 'off';
            app.InBatchModeCheckBox.Text = ' In Batch Mode';
            app.RightPanel = uipanel(app.GridLayout);
            app.FOLDERANDSUBJECTSSELECTIONLabel = uilabel(app.RightPanel);
            app.FOLDERANDSUBJECTSSELECTIONLabel.Text = 'FOLDER AND SUBJECTS SELECTION';
            app.FolderSelectionTable = uitable(app.RightPanel);
            app.FolderSelectionTable.ColumnName = {'Folder'; 'Include'};
            app.FolderSelectionTable.RowName = {};
            app.FolderSelectionTable.ColumnSortable = true;
            app.FolderSelectionTable.ColumnEditable = [false true];
            app.FolderSelectionTable.CellEditCallback = createCallbackFcn(app, @FolderSelectionTableCellEdit, true);
            app.SubjectSelectionTable = uitable(app.RightPanel);
            app.SubjectSelectionTable.ColumnName = {'Subject'; 'Include'};
            app.SubjectSelectionTable.RowName = {};
            app.SubjectSelectionTable.ColumnSortable = true;
            app.SubjectSelectionTable.ColumnEditable = [false true];
            app.SubjectSelectionTable.CellEditCallback = createCallbackFcn(app, @SubjectSelectionTableCellEdit, true);
            app.NumberofsubjectsidentifiedLabel = uilabel(app.RightPanel);
            app.NumberofsubjectsidentifiedLabel.Text = {'Number of subjects '; 'identified:'};
            app.NumSubj = uieditfield(app.RightPanel, 'numeric');
            app.NumSubj.Editable = 'off';
            app.NumberoffilesidentifiedLabel = uilabel(app.RightPanel);
            app.NumberoffilesidentifiedLabel.Text = {'Number of files'; 'identified:'};
            app.NumFiles = uieditfield(app.RightPanel, 'numeric');
            app.NumFiles.Editable = 'off';
            app.NumberoffilesidentifiedforthefirstsubjectLabel = uilabel(app.RightPanel);
            app.NumberoffilesidentifiedforthefirstsubjectLabel.Text = {'Number of files'; 'identified for the first'; 'subject:'};
            app.NumFilesFirstSubj = uieditfield(app.RightPanel, 'numeric');
            app.NumFilesFirstSubj.Editable = 'off';
            app.SelectallButton = uibutton(app.RightPanel, 'push');
            app.SelectallButton.ButtonPushedFcn = createCallbackFcn(app, @SelectallButtonPushed, true);
            app.SelectallButton.Enable = 'off';
            app.SelectallButton.Text = 'Select all';
            app.DeselectallButton = uibutton(app.RightPanel, 'push');
            app.DeselectallButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectallButtonPushed, true);
            app.DeselectallButton.Enable = 'off';
            app.DeselectallButton.Text = 'Deselect all';
            app.Design = uilabel(app.RightPanel);
            app.Design.Text = 'DESIGN';
            app.CodeofFIRSTparticipantLabel = uilabel(app.RightPanel);
            app.CodeofFIRSTparticipantLabel.Text = 'Code of FIRST participant:';
            app.StudyNameEditFieldLabel = uilabel(app.RightPanel);
            app.StudyNameEditFieldLabel.Text = 'Study Name:';
            app.StudyName = uieditfield(app.RightPanel, 'text');
            app.FirstSubjCode = uieditfield(app.RightPanel, 'text');
            app.FirstSubjCode.ValueChangedFcn = createCallbackFcn(app, @FirstSubjCodeValueChanged, true);
            app.FirstSubjCode.Tooltip = {'For EEGpal to detect the code of the participants, it must be in the file names.'};
            app.ElectrodeSettingButton = uibutton(app.RightPanel, 'push');
            app.ElectrodeSettingButton.ButtonPushedFcn = createCallbackFcn(app, @ElectrodeSettingButtonPushed, true);
            app.ElectrodeSettingButton.Text = 'Electrode Setting';
            app.DesignButton = uibutton(app.RightPanel, 'push');
            app.DesignButton.ButtonPushedFcn = createCallbackFcn(app, @DesignButtonPushed, true);
            app.DesignButton.Text = 'Full Design';
            app.PROCESSINGMODULESPanel = uipanel(app.RightPanel);
            app.PROCESSINGMODULESPanel.Title = 'PROCESSING MODULES';
            app.FilteringButton = uibutton(app.PROCESSINGMODULESPanel, 'push');
            app.FilteringButton.ButtonPushedFcn = createCallbackFcn(app, @FilteringButtonPushed, true);
            app.FilteringButton.Text = 'Filtering +';
            app.ICAButton = uibutton(app.PROCESSINGMODULESPanel, 'push');
            app.ICAButton.ButtonPushedFcn = createCallbackFcn(app, @ICAbuttonPushedFunction, true);
            app.ICAButton.Tooltip = {'Artefacts rejection with ICA'};
            app.ICAButton.Text = 'ICA';
            app.InterpolationButton = uibutton(app.PROCESSINGMODULESPanel, 'push');
            app.InterpolationButton.ButtonPushedFcn = createCallbackFcn(app, @InterpolationButtonPushed, true);
            app.InterpolationButton.Tooltip = {'Interpolate bad electrodes'};
            app.InterpolationButton.Text = 'Interpolation';
            app.RereferenceButton = uibutton(app.PROCESSINGMODULESPanel, 'push');
            app.RereferenceButton.ButtonPushedFcn = createCallbackFcn(app, @RereferenceButtonPushed, true);
            app.RereferenceButton.Text = ' Re-reference';
            app.EpochingButton = uibutton(app.PROCESSINGMODULESPanel, 'push');
            app.EpochingButton.ButtonPushedFcn = createCallbackFcn(app, @EpochingButtonPushed, true);
            app.EpochingButton.Text = 'Epoching';
            app.BridgeDetectionButton = uibutton(app.PROCESSINGMODULESPanel, 'push');
            app.BridgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @BridgeDetectionButtonPushed, true);
            app.BridgeDetectionButton.Text = 'Bridge Detection';
            app.GEDAIButton = uibutton(app.PROCESSINGMODULESPanel, 'push');
            app.GEDAIButton.ButtonPushedFcn = createCallbackFcn(app, @GEDAIButtonPushed, true);
            app.GEDAIButton.Tooltip = {'Artefact rejection with GEDAI toolbox'};
            app.GEDAIButton.Text = 'GEDAI';
            app.ANALYSISMODULESPanel = uipanel(app.RightPanel);
            app.ANALYSISMODULESPanel.Title = 'ANALYSIS MODULES';
            app.FrequencyingButton = uibutton(app.ANALYSISMODULESPanel, 'push');
            app.FrequencyingButton.ButtonPushedFcn = createCallbackFcn(app, @FrequencyingButtonPushed, true);
            app.FrequencyingButton.Text = 'Frequencying';
            app.StatisticsButton = uibutton(app.ANALYSISMODULESPanel, 'push');
            app.StatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @StatisticsButtonPushed, true);
            app.StatisticsButton.Text = 'Statistics';
            app.InversesolutionsButton = uibutton(app.ANALYSISMODULESPanel, 'push');
            app.InversesolutionsButton.Enable = 'off';
            app.InversesolutionsButton.Visible = 'off';
            app.InversesolutionsButton.Text = 'Inverse solutions';
            app.PeaksorTraceexportButton = uibutton(app.ANALYSISMODULESPanel, 'push');
            app.PeaksorTraceexportButton.ButtonPushedFcn = createCallbackFcn(app, @PeaksorTraceexportButtonButtonPushed, true);
            app.PeaksorTraceexportButton.Text = 'Peaks or Trace export';
            app.PresetParametersSwitch = uiswitch(app.RightPanel, 'slider');
            app.PresetParametersSwitch.Items = {'Cartool', 'eeglab'};
            app.PresetParametersSwitch.ValueChangedFcn = createCallbackFcn(app, @PresetParametersSwitchValueChanged, true);
            app.PresetParametersSwitch.Value = 'Cartool';
            app.PresetparametersLabel = uilabel(app.RightPanel);
            app.PresetparametersLabel.Text = 'Preset parameters:';
            % Show the figure after all components are created
            app.EEGpalCS_Main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_main_Data

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_Main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_Main)
        end
    end
end