classdef EEGpalCS_dlg_Header < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EPheader                    matlab.ui.Figure
        nChan_UpdateButton          matlab.ui.control.Button
        SR_UpdateButton             matlab.ui.control.Button
        nChan                       matlab.ui.control.NumericEditField
        NchannelsEditFieldLabel     matlab.ui.control.Label
        SR                          matlab.ui.control.NumericEditField
        SamplingrateEditFieldLabel  matlab.ui.control.Label
        Instruction                 matlab.ui.control.Label
        EPFILESHEADERLabel          matlab.ui.control.Label
        FilesTable                  matlab.ui.control.Table
        DoneButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        EEGpalCS_main  % Main app object
        Data
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_main)
            % Store CallingApp object
            app.EEGpalCS_main = EEGpalCS_main;
            
            
            % Update UI with input values
            app.FilesTable.Data = app.EEGpalCS_main.DataFilesTable.Data(:,1:4);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EPheader
        function EPheaderCloseRequest(app, event)
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: SR_UpdateButton
        function SR_UpdateButtonPushed(app, event)
            if app.SR.Value == 0
                app.FilesTable.Data.(4)(:) = nan;
                app.EEGpalCS_main.DataFilesTable.Data.(4)(:) = nan;
                app.EEGpalCS_main.FilesTable.SamplingRate(:) = nan; %correction done by Michael Mouthon 03.10.2024
            else
                app.FilesTable.Data.(4)(:) = app.SR.Value;
                app.EEGpalCS_main.DataFilesTable.Data.(4)(:) = app.SR.Value;
                app.EEGpalCS_main.FilesTable.SamplingRate(:) = app.SR.Value; %correction done by Michael Mouthon 03.10.2024
            end
        end

        % Button pushed function: nChan_UpdateButton
        function nChan_UpdateButtonPushed(app, event)
            if app.nChan.Value == 0
                app.FilesTable.Data.(3)(:) = nan;
                app.EEGpalCS_main.DataFilesTable.Data.(3)(:) = nan;
                app.EEGpalCS_main.FilesTable.nChan(:) = nan; %correction done by Michael Mouthon 03.10.2024
            else
                app.FilesTable.Data.(3)(:) = app.nChan.Value;
                app.EEGpalCS_main.DataFilesTable.Data.(3)(:) = app.nChan.Value;
                app.EEGpalCS_main.FilesTable.nChan(:) = app.nChan.Value; %correction done by Michael Mouthon 03.10.2024
            end
        end

        % Cell edit callback: FilesTable
        function FilesTableCellEdit(app, event)
            %Dev done by Michael Mouthon 03.10.2024
            indices = event.Indices;
            newData = event.NewData;
            
            %edit a sampling rate value
            if indices(2)==4
                if newData == 0
                    app.FilesTable.Data.(4)(indices(1)) = nan;
                    app.EEGpalCS_main.DataFilesTable.Data.(4)(indices(1)) = nan;
                    app.EEGpalCS_main.FilesTable.SamplingRate(indices(1)) = nan;
                else
                    app.FilesTable.Data.(4)(indices(1)) = newData;
                    app.EEGpalCS_main.DataFilesTable.Data.(4)(indices(1)) = newData;
                    app.EEGpalCS_main.FilesTable.SamplingRate(indices(1)) = newData;
                end
            elseif indices(2)==3 %edit a N.Channel value
                 if newData == 0
                    app.FilesTable.Data.(3)(indices(1)) = nan;
                    app.EEGpalCS_main.DataFilesTable.Data.(3)(indices(1)) = nan;
                    app.EEGpalCS_main.FilesTable.nChan(indices(1)) = nan; 
                 else
                    app.FilesTable.Data.(3)(indices(1)) = newData;
                    app.EEGpalCS_main.DataFilesTable.Data.(3)(indices(1)) = newData;
                    app.EEGpalCS_main.FilesTable.nChan(indices(1)) = newData; 
                 end
            
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EPheader = uifigure('Visible', 'off');
            app.EPheader.Name = 'EP Header - EEGpalCS';
            app.EPheader.CloseRequestFcn = createCallbackFcn(app, @EPheaderCloseRequest, true);
            app.DoneButton = uibutton(app.EPheader, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.DoneButton.Text = 'Done';
            app.FilesTable = uitable(app.EPheader);
            app.FilesTable.ColumnName = {'File'; 'Path'; 'N. channels'; 'Sampling rate'};
            app.FilesTable.RowName = {};
            app.FilesTable.ColumnEditable = [false false true true];
            app.FilesTable.CellEditCallback = createCallbackFcn(app, @FilesTableCellEdit, true);
            app.EPFILESHEADERLabel = uilabel(app.EPheader);
            app.EPFILESHEADERLabel.Text = 'EP FILES HEADER';
            app.Instruction = uilabel(app.EPheader);
            app.Instruction.Text = {'As .ep files do not have a header, please '; 'enter the sampling rate here. Either enter '; 'one value for all files in the box below, or '; 'edit the table on the right.'};
            app.SamplingrateEditFieldLabel = uilabel(app.EPheader);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate:';
            app.SR = uieditfield(app.EPheader, 'numeric');
            app.SR.Limits = [0 Inf];
            app.NchannelsEditFieldLabel = uilabel(app.EPheader);
            app.NchannelsEditFieldLabel.Visible = 'off';
            app.NchannelsEditFieldLabel.Text = 'N. channels:';
            app.nChan = uieditfield(app.EPheader, 'numeric');
            app.nChan.Visible = 'off';
            app.SR_UpdateButton = uibutton(app.EPheader, 'push');
            app.SR_UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @SR_UpdateButtonPushed, true);
            app.SR_UpdateButton.Text = 'Update';
            app.nChan_UpdateButton = uibutton(app.EPheader, 'push');
            app.nChan_UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @nChan_UpdateButtonPushed, true);
            app.nChan_UpdateButton.Visible = 'off';
            app.nChan_UpdateButton.Text = 'Update';
            % Show the figure after all components are created
            app.EPheader.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_dlg_Header(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EPheader)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EPheader)
        end
    end
end