classdef EEGpalCS_options_cleanLine < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        cleanLinepopup          matlab.ui.Figure
        fftEditField            matlab.ui.control.NumericEditField
        BandwidthHzLabel_6      matlab.ui.control.Label
        tauEditField            matlab.ui.control.NumericEditField
        BandwidthHzLabel_5      matlab.ui.control.Label
        taperStepSizeEditField  matlab.ui.control.NumericEditField
        BandwidthHzLabel_4      matlab.ui.control.Label
        taperWinSizeEditField   matlab.ui.control.NumericEditField
        BandwidthHzLabel_3      matlab.ui.control.Label
        taperEditField          matlab.ui.control.NumericEditField
        BandwidthHzLabel_2      matlab.ui.control.Label
        BandwidthEditField      matlab.ui.control.NumericEditField
        BandwidthHzLabel        matlab.ui.control.Label
        pEditField              matlab.ui.control.NumericEditField
        lineFreqEditField       matlab.ui.control.NumericEditField
        LinenoisefrequenciestoremoveEditFieldLabel  matlab.ui.control.Label
        pvaluefordetectionofsignificantsinusoidLabel  matlab.ui.control.Label
        HelpButton              matlab.ui.control.Button
        CancelButton            matlab.ui.control.Button
        OkButton                matlab.ui.control.Button
        CleanLineOptionLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Filtering % Parameters of Filting+ module
    end
    
    properties (Access = public)
        CRD_options
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Filtering)
            % Store EEGpalCS_Filtering app object
            app.EEGpalCS_Filtering                  = EEGpalCS_Filtering;

            % Update UI with session parameter input values
            app.CRD_options                         = app.EEGpalCS_Filtering.CRD_options;

            % Apply the current CRD_options into the Clean_rawdatapopup window
            try
                app.driftCheckBox.Value                 = app.CRD_options.Drift;
                driftCheckBoxValueChanged(app);
                app.LowEditField.Value                  = app.CRD_options.Highpass(1);
                app.HigthEditField.Value                = app.CRD_options.Highpass(2);
    
                app.RemovebadchannelsCheckBox.Value     = app.CRD_options.BadChan;
                app.FlatlineCheckBox.Value              = app.CRD_options.Flatline;
                app.BadChannelCriterium1.Value          = app.CRD_options.FlatlineCriterion;
                app.LineNoiseCheckBox.Value             = app.CRD_options.LineNoise;
                app.BadChannelCriterium2.Value          = app.CRD_options.LineNoiseCriterion;
                app.ChannelCheckBox.Value               = app.CRD_options.Channel;
                app.BadChannelCriterium3.Value          = app.CRD_options.ChannelCriterion;
                RemovebadchannelsCheckBoxValueChanged(app);
    
                app.ASRCheckBox.Value                   = app.CRD_options.ASR;
                ASRCheckBoxValueChanged(app);
                app.BurstSTD.Value                      = app.CRD_options.BurstCriterion;
                if strcmp(app.CRD_options.Distance,'euclidian')
                    app.ASRCriterium2.Value = false;
                else
                    app.ASRCriterium2.Value = true;
                end
                app.ASRCriterium3.Value                 = app.CRD_options.BurstRejection;
    
                app.BadPeriodCheckBox.Value             = app.CRD_options.BadPeriods;
                BadPeriodCheckBoxValueChanged(app);
                app.MinEditField.Value                  = app.CRD_options.WindowCriterionTolerances(1);
                app.MaxEditField.Value                  = app.CRD_options.WindowCriterionTolerances(2);
                app.BadRejectionCriterium2.Value        = app.CRD_options.WindowCriterion*100;
            catch
            end


        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % transcribe the GUI value to app.CRD_option
            app.CRD_options.Drift = app.driftCheckBox.Value;
            app.CRD_options.Highpass = [app.LowEditField.Value,app.HigthEditField.Value];            

            app.CRD_options.BadChan = app.RemovebadchannelsCheckBox.Value;
            app.CRD_options.Flatline = app.FlatlineCheckBox.Value;
            app.CRD_options.FlatlineCriterion = app.BadChannelCriterium1.Value;
            app.CRD_options.LineNoise = app.LineNoiseCheckBox.Value;
            app.CRD_options.LineNoiseCriterion = app.BadChannelCriterium2.Value;
            app.CRD_options.Channel = app.ChannelCheckBox.Value;
            app.CRD_options.ChannelCriterion = app.BadChannelCriterium3.Value;

            app.CRD_options.ASR = app.ASRCheckBox.Value;
            app.CRD_options.BurstCriterion = app.BurstSTD.Value;
            if ~app.ASRCriterium2.Value
                app.CRD_options.Distance = 'euclidian';
            else
                app.CRD_options.Distance = 'Riemannian';
            end
            app.CRD_options.BurstRejection = app.ASRCriterium3.Value;

            app.CRD_options.BadPeriods = app.BadPeriodCheckBox.Value;
            app.CRD_options.WindowCriterionTolerances = [app.MinEditField.Value, app.MaxEditField.Value];
            app.CRD_options.WindowCriterion = app.BadRejectionCriterium2.Value/100;
            
            % Update parameters
            app.EEGpalCS_Filtering.CRD_options = app.CRD_options;
            
            %adapte the Filtering UI
            app.EEGpalCS_Filtering.CRD_AllOptionsButton.Enable = 'on';
            app.EEGpalCS_Filtering.CRD_Drift.Value = app.CRD_options.Drift;
            app.EEGpalCS_Filtering.CRD_BadChan.Value = app.CRD_options.BadChan;
            app.EEGpalCS_Filtering.CRD_ASR.Value = app.CRD_options.ASR;
            app.EEGpalCS_Filtering.CRD_BadPeriods.Value = app.CRD_options.BadPeriods;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Filtering.CRD_AllOptionsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: cleanLinepopup
        function cleanLinepopupCloseRequest(app, event)
             % Enable the Electrode Setting button in main app
            app.EEGpalCS_Filtering.CRD_AllOptionsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Filtering.ToolboxDir, '\Manuels\Help_CleanRaw_Data.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.EEGpalCS_Filtering.ToolboxDir, '/Manuels/Help_CleanRaw_Data.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.EEGpalCS_Filtering.ToolboxDir, '/Manuels/Help_CleanRaw_Data.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.cleanLinepopup = uifigure('Visible', 'off');
            app.cleanLinepopup.Name = 'popup';
            app.cleanLinepopup.CloseRequestFcn = createCallbackFcn(app, @cleanLinepopupCloseRequest, true);
            app.CleanLineOptionLabel = uilabel(app.cleanLinepopup);
            app.CleanLineOptionLabel.Text = 'CleanLine Option';
            app.OkButton = uibutton(app.cleanLinepopup, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.cleanLinepopup, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.HelpButton = uibutton(app.cleanLinepopup, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.pvaluefordetectionofsignificantsinusoidLabel = uilabel(app.cleanLinepopup);
            app.pvaluefordetectionofsignificantsinusoidLabel.Text = 'p-value for detection of significant sinusoid';
            app.LinenoisefrequenciestoremoveEditFieldLabel = uilabel(app.cleanLinepopup);
            app.LinenoisefrequenciestoremoveEditFieldLabel.Text = 'Line noise frequencies to remove';
            app.lineFreqEditField = uieditfield(app.cleanLinepopup, 'numeric');
            app.lineFreqEditField.Limits = [0 Inf];
            app.lineFreqEditField.Value = 50;
            app.pEditField = uieditfield(app.cleanLinepopup, 'numeric');
            app.pEditField.Limits = [0 1];
            app.pEditField.Value = 0.01;
            app.BandwidthHzLabel = uilabel(app.cleanLinepopup);
            app.BandwidthHzLabel.Text = 'Bandwidth (Hz)';
            app.BandwidthEditField = uieditfield(app.cleanLinepopup, 'numeric');
            app.BandwidthEditField.Value = 2;
            app.BandwidthHzLabel_2 = uilabel(app.cleanLinepopup);
            app.BandwidthHzLabel_2.Text = 'Taper bandwidth';
            app.taperEditField = uieditfield(app.cleanLinepopup, 'numeric');
            app.taperEditField.Value = 2;
            app.BandwidthHzLabel_3 = uilabel(app.cleanLinepopup);
            app.BandwidthHzLabel_3.Text = 'Sliding windoth length (sec)';
            app.taperWinSizeEditField = uieditfield(app.cleanLinepopup, 'numeric');
            app.taperWinSizeEditField.Value = 4;
            app.BandwidthHzLabel_4 = uilabel(app.cleanLinepopup);
            app.BandwidthHzLabel_4.Text = 'Sliding windoth step size (sec)';
            app.taperStepSizeEditField = uieditfield(app.cleanLinepopup, 'numeric');
            app.taperStepSizeEditField.Value = 1;
            app.BandwidthHzLabel_5 = uilabel(app.cleanLinepopup);
            app.BandwidthHzLabel_5.Text = 'Window overlap smoothing factor';
            app.tauEditField = uieditfield(app.cleanLinepopup, 'numeric');
            app.tauEditField.Limits = [1 Inf];
            app.tauEditField.Value = 100;
            app.BandwidthHzLabel_6 = uilabel(app.cleanLinepopup);
            app.BandwidthHzLabel_6.Text = 'FFT padding factor';
            app.fftEditField = uieditfield(app.cleanLinepopup, 'numeric');
            app.fftEditField.Limits = [-1 Inf];
            app.fftEditField.Value = 2;
            % Show the figure after all components are created
            app.cleanLinepopup.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_options_cleanLine(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.cleanLinepopup)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.cleanLinepopup)
        end
    end
end