classdef EEGpalCS_options_clean_rawdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Clean_rawdatapopup           matlab.ui.Figure
        HelpButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        OkButton                     matlab.ui.control.Button
        BadRejectionCriterium2       matlab.ui.control.NumericEditField
        BadRejectionCriterium2Label  matlab.ui.control.Label
        MaxEditField                 matlab.ui.control.NumericEditField
        MaxEditFieldLabel            matlab.ui.control.Label
        MinEditField                 matlab.ui.control.NumericEditField
        MinEditFieldLabel            matlab.ui.control.Label
        BadRejectionCriterium1       matlab.ui.control.Label
        BadPeriodCheckBox            matlab.ui.control.CheckBox
        ASRCriterium3                matlab.ui.control.CheckBox
        ASRCriterium2                matlab.ui.control.CheckBox
        BurstSTD                     matlab.ui.control.NumericEditField
        ASRCriterium1                matlab.ui.control.Label
        ASRCheckBox                  matlab.ui.control.CheckBox
        BadChannelCriterium3         matlab.ui.control.NumericEditField
        BadChannelCriterium2         matlab.ui.control.NumericEditField
        BadChannelCriterium1         matlab.ui.control.NumericEditField
        ChannelCheckBox              matlab.ui.control.CheckBox
        LineNoiseCheckBox            matlab.ui.control.CheckBox
        FlatlineCheckBox             matlab.ui.control.CheckBox
        RemovebadchannelsCheckBox    matlab.ui.control.CheckBox
        LinearfilterFIRLabel         matlab.ui.control.Label
        HigthEditField               matlab.ui.control.NumericEditField
        HigthEditFieldLabel          matlab.ui.control.Label
        LowEditField                 matlab.ui.control.NumericEditField
        LowEditFieldLabel            matlab.ui.control.Label
        driftCheckBox                matlab.ui.control.CheckBox
        Clean_rawdataOptionLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Filtering % Parameters of Filting+ module
    end
    
    properties (Access = public)
        CRD_options
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Filtering)
            % Store EEGpalCS_Filtering app object
            app.EEGpalCS_Filtering                  = EEGpalCS_Filtering;

            % Update UI with session parameter input values
            app.CRD_options                         = app.EEGpalCS_Filtering.CRD_options;

            % Apply the current CRD_options into the Clean_rawdatapopup window
            try
                app.driftCheckBox.Value                 = app.CRD_options.Drift;
                driftCheckBoxValueChanged(app);
                app.LowEditField.Value                  = app.CRD_options.Highpass(1);
                app.HigthEditField.Value                = app.CRD_options.Highpass(2);
    
                app.RemovebadchannelsCheckBox.Value     = app.CRD_options.BadChan;
                app.FlatlineCheckBox.Value              = app.CRD_options.Flatline;
                app.BadChannelCriterium1.Value          = app.CRD_options.FlatlineCriterion;
                app.LineNoiseCheckBox.Value             = app.CRD_options.LineNoise;
                app.BadChannelCriterium2.Value          = app.CRD_options.LineNoiseCriterion;
                app.ChannelCheckBox.Value               = app.CRD_options.Channel;
                app.BadChannelCriterium3.Value          = app.CRD_options.ChannelCriterion;
                RemovebadchannelsCheckBoxValueChanged(app);
    
                app.ASRCheckBox.Value                   = app.CRD_options.ASR;
                ASRCheckBoxValueChanged(app);
                app.BurstSTD.Value                      = app.CRD_options.BurstCriterion;
                if strcmp(app.CRD_options.Distance,'euclidian')
                    app.ASRCriterium2.Value = false;
                else
                    app.ASRCriterium2.Value = true;
                end
                app.ASRCriterium3.Value                 = app.CRD_options.BurstRejection;
    
                app.BadPeriodCheckBox.Value             = app.CRD_options.BadPeriods;
                BadPeriodCheckBoxValueChanged(app);
                app.MinEditField.Value                  = app.CRD_options.WindowCriterionTolerances(1);
                app.MaxEditField.Value                  = app.CRD_options.WindowCriterionTolerances(2);
                app.BadRejectionCriterium2.Value        = app.CRD_options.WindowCriterion*100;
            catch
            end


        end

        % Value changed function: driftCheckBox
        function driftCheckBoxValueChanged(app, event)
            if app.driftCheckBox.Value
                app.LinearfilterFIRLabel.Enable     = "on";
                app.LowEditField.Enable             = "on";
                app.HigthEditField.Enable           = "on";
            else
                app.LinearfilterFIRLabel.Enable     = "off";
                app.LowEditField.Enable             = "off";
                app.HigthEditField.Enable           = "off";
            end
        end

        % Value changed function: RemovebadchannelsCheckBox
        function RemovebadchannelsCheckBoxValueChanged(app, event)
            if app.RemovebadchannelsCheckBox.Value
                app.FlatlineCheckBox.Enable             = "on";
                FlatlineCheckBoxValueChanged(app);
                app.LineNoiseCheckBox.Enable            = "on";
                LineNoiseCheckBoxValueChanged(app);
                app.ChannelCheckBox.Enable              = "on";
                ChannelCheckBoxValueChanged(app);
            else
                app.FlatlineCheckBox.Enable             = "off";
                app.BadChannelCriterium1.Enable         = "off";
                app.LineNoiseCheckBox.Enable            = "off";
                app.BadChannelCriterium2.Enable         = "off";
                app.ChannelCheckBox.Enable              = "off";
                app.BadChannelCriterium3.Enable         = "off";
            end
                            
        end

        % Value changed function: FlatlineCheckBox
        function FlatlineCheckBoxValueChanged(app, event)
            if app.FlatlineCheckBox.Value
                app.BadChannelCriterium1.Enable = "on";
            else
                app.BadChannelCriterium1.Enable = "off";
            end
            
        end

        % Value changed function: LineNoiseCheckBox
        function LineNoiseCheckBoxValueChanged(app, event)
            if app.LineNoiseCheckBox.Value
                app.BadChannelCriterium2.Enable = "on";
            else
                app.BadChannelCriterium2.Enable = "off";
            end

        end

        % Value changed function: ChannelCheckBox
        function ChannelCheckBoxValueChanged(app, event)
            if app.ChannelCheckBox.Value
                app.BadChannelCriterium3.Enable = "on";
            else
                app.BadChannelCriterium3.Enable = "off";
            end
            
        end

        % Value changed function: ASRCheckBox
        function ASRCheckBoxValueChanged(app, event)
            if app.ASRCheckBox.Value
                app.ASRCriterium1.Enable    = "on";
                app.BurstSTD.Enable         = "on";
                app.ASRCriterium2.Enable    = "on";
                app.ASRCriterium3.Enable    = "on";
            else
                app.ASRCriterium1.Enable    = "off";
                app.BurstSTD.Enable         = "off";
                app.ASRCriterium2.Enable    = "off";
                app.ASRCriterium3.Enable    = "off";
            end
            
        end

        % Value changed function: BadPeriodCheckBox
        function BadPeriodCheckBoxValueChanged(app, event)
            if app.BadPeriodCheckBox.Value
                app.BadRejectionCriterium1.Enable       = "on";
                app.MinEditField.Enable                 = "on";
                app.MaxEditField.Enable                 = "on";
                app.BadRejectionCriterium2Label.Enable  = "on";
                app.BadRejectionCriterium2.Enable       = "on";
            else
                app.BadRejectionCriterium1.Enable       = "off";
                app.MinEditField.Enable                 = "off";
                app.MaxEditField.Enable                 = "off";
                app.BadRejectionCriterium2Label.Enable  = "off";
                app.BadRejectionCriterium2.Enable       = "off";
            end
            
        end

        % Button pushed function: OkButton
        function OkButtonPushed(app, event)
            % transcribe the GUI value to app.CRD_option
            app.CRD_options.Drift = app.driftCheckBox.Value;
            app.CRD_options.Highpass = [app.LowEditField.Value,app.HigthEditField.Value];            

            app.CRD_options.BadChan = app.RemovebadchannelsCheckBox.Value;
            app.CRD_options.Flatline = app.FlatlineCheckBox.Value;
            app.CRD_options.FlatlineCriterion = app.BadChannelCriterium1.Value;
            app.CRD_options.LineNoise = app.LineNoiseCheckBox.Value;
            app.CRD_options.LineNoiseCriterion = app.BadChannelCriterium2.Value;
            app.CRD_options.Channel = app.ChannelCheckBox.Value;
            app.CRD_options.ChannelCriterion = app.BadChannelCriterium3.Value;

            app.CRD_options.ASR = app.ASRCheckBox.Value;
            app.CRD_options.BurstCriterion = app.BurstSTD.Value;
            if ~app.ASRCriterium2.Value
                app.CRD_options.Distance = 'euclidian';
            else
                app.CRD_options.Distance = 'Riemannian';
            end
            app.CRD_options.BurstRejection = app.ASRCriterium3.Value;

            app.CRD_options.BadPeriods = app.BadPeriodCheckBox.Value;
            app.CRD_options.WindowCriterionTolerances = [app.MinEditField.Value, app.MaxEditField.Value];
            app.CRD_options.WindowCriterion = app.BadRejectionCriterium2.Value/100;
            
            % Update parameters
            app.EEGpalCS_Filtering.CRD_options = app.CRD_options;
            
            %adapte the Filtering UI
            app.EEGpalCS_Filtering.CRD_AllOptionsButton.Enable = 'on';
            app.EEGpalCS_Filtering.CRD_Drift.Value = app.CRD_options.Drift;
            app.EEGpalCS_Filtering.CRD_BadChan.Value = app.CRD_options.BadChan;
            app.EEGpalCS_Filtering.CRD_ASR.Value = app.CRD_options.ASR;
            app.EEGpalCS_Filtering.CRD_BadPeriods.Value = app.CRD_options.BadPeriods;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Filtering.CRD_AllOptionsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: Clean_rawdatapopup
        function Clean_rawdatapopupCloseRequest(app, event)
             % Enable the Electrode Setting button in main app
            app.EEGpalCS_Filtering.CRD_AllOptionsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Filtering.ToolboxDir, '\Manuels\Help_CleanRaw_Data.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.EEGpalCS_Filtering.ToolboxDir, '/Manuels/Help_CleanRaw_Data.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.EEGpalCS_Filtering.ToolboxDir, '/Manuels/Help_CleanRaw_Data.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Clean_rawdatapopup = uifigure('Visible', 'off');
            app.Clean_rawdatapopup.Name = 'popup';
            app.Clean_rawdatapopup.CloseRequestFcn = createCallbackFcn(app, @Clean_rawdatapopupCloseRequest, true);
            app.Clean_rawdataOptionLabel = uilabel(app.Clean_rawdatapopup);
            app.Clean_rawdataOptionLabel.Text = 'Clean_rawdata Option';
            app.driftCheckBox = uicheckbox(app.Clean_rawdatapopup);
            app.driftCheckBox.ValueChangedFcn = createCallbackFcn(app, @driftCheckBoxValueChanged, true);
            app.driftCheckBox.Text = 'Remove channel drift (data not already high-pass filtered)';
            app.LowEditFieldLabel = uilabel(app.Clean_rawdatapopup);
            app.LowEditFieldLabel.Text = 'Low';
            app.LowEditField = uieditfield(app.Clean_rawdatapopup, 'numeric');
            app.LowEditField.Value = 0.25;
            app.HigthEditFieldLabel = uilabel(app.Clean_rawdatapopup);
            app.HigthEditFieldLabel.Text = 'Higth';
            app.HigthEditField = uieditfield(app.Clean_rawdatapopup, 'numeric');
            app.HigthEditField.Value = 0.75;
            app.LinearfilterFIRLabel = uilabel(app.Clean_rawdatapopup);
            app.LinearfilterFIRLabel.Text = 'Linear filter (FIR) transition band in Hz';
            app.RemovebadchannelsCheckBox = uicheckbox(app.Clean_rawdatapopup);
            app.RemovebadchannelsCheckBox.ValueChangedFcn = createCallbackFcn(app, @RemovebadchannelsCheckBoxValueChanged, true);
            app.RemovebadchannelsCheckBox.Text = 'Remove bad channels';
            app.FlatlineCheckBox = uicheckbox(app.Clean_rawdatapopup);
            app.FlatlineCheckBox.ValueChangedFcn = createCallbackFcn(app, @FlatlineCheckBoxValueChanged, true);
            app.FlatlineCheckBox.Text = 'Remove channel if it is flat for more than (seconds)';
            app.LineNoiseCheckBox = uicheckbox(app.Clean_rawdatapopup);
            app.LineNoiseCheckBox.ValueChangedFcn = createCallbackFcn(app, @LineNoiseCheckBoxValueChanged, true);
            app.LineNoiseCheckBox.Text = 'Max acceptable high-frequency noise std dev';
            app.ChannelCheckBox = uicheckbox(app.Clean_rawdatapopup);
            app.ChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ChannelCheckBoxValueChanged, true);
            app.ChannelCheckBox.Text = 'Min acceptable correlation with nearby chans [0-1]';
            app.BadChannelCriterium1 = uieditfield(app.Clean_rawdatapopup, 'numeric');
            app.BadChannelCriterium1.Enable = 'off';
            app.BadChannelCriterium1.Value = 5;
            app.BadChannelCriterium2 = uieditfield(app.Clean_rawdatapopup, 'numeric');
            app.BadChannelCriterium2.Enable = 'off';
            app.BadChannelCriterium2.Value = 4;
            app.BadChannelCriterium3 = uieditfield(app.Clean_rawdatapopup, 'numeric');
            app.BadChannelCriterium3.Limits = [0 1];
            app.BadChannelCriterium3.Enable = 'off';
            app.BadChannelCriterium3.Value = 0.8;
            app.ASRCheckBox = uicheckbox(app.Clean_rawdatapopup);
            app.ASRCheckBox.ValueChangedFcn = createCallbackFcn(app, @ASRCheckBoxValueChanged, true);
            app.ASRCheckBox.Text = 'Perform Artifact Subspace Reconstruction bad burst correction';
            app.ASRCriterium1 = uilabel(app.Clean_rawdatapopup);
            app.ASRCriterium1.Text = 'Max acceptable 0.5 second window std dev';
            app.BurstSTD = uieditfield(app.Clean_rawdatapopup, 'numeric');
            app.BurstSTD.Value = 20;
            app.ASRCriterium2 = uicheckbox(app.Clean_rawdatapopup);
            app.ASRCriterium2.Text = 'Use Riemanian distance metric (not Euclidean) - beta';
            app.ASRCriterium3 = uicheckbox(app.Clean_rawdatapopup);
            app.ASRCriterium3.Text = 'Remove bad data periods (instead of correcting them)';
            app.BadPeriodCheckBox = uicheckbox(app.Clean_rawdatapopup);
            app.BadPeriodCheckBox.ValueChangedFcn = createCallbackFcn(app, @BadPeriodCheckBoxValueChanged, true);
            app.BadPeriodCheckBox.Text = 'Additional removal of bad data periods';
            app.BadRejectionCriterium1 = uilabel(app.Clean_rawdatapopup);
            app.BadRejectionCriterium1.Text = 'Acceptable [min max] channel power range (+/- std dev)';
            app.MinEditFieldLabel = uilabel(app.Clean_rawdatapopup);
            app.MinEditFieldLabel.Text = 'Min';
            app.MinEditField = uieditfield(app.Clean_rawdatapopup, 'numeric');
            app.MinEditField.Value = -Inf;
            app.MaxEditFieldLabel = uilabel(app.Clean_rawdatapopup);
            app.MaxEditFieldLabel.Text = 'Max';
            app.MaxEditField = uieditfield(app.Clean_rawdatapopup, 'numeric');
            app.MaxEditField.Value = 7;
            app.BadRejectionCriterium2Label = uilabel(app.Clean_rawdatapopup);
            app.BadRejectionCriterium2Label.Text = 'Maximum out-of-bound channels (%)';
            app.BadRejectionCriterium2 = uieditfield(app.Clean_rawdatapopup, 'numeric');
            app.BadRejectionCriterium2.Limits = [0 100];
            app.BadRejectionCriterium2.Value = 25;
            app.OkButton = uibutton(app.Clean_rawdatapopup, 'push');
            app.OkButton.ButtonPushedFcn = createCallbackFcn(app, @OkButtonPushed, true);
            app.OkButton.Text = 'Ok';
            app.CancelButton = uibutton(app.Clean_rawdatapopup, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.HelpButton = uibutton(app.Clean_rawdatapopup, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            % Show the figure after all components are created
            app.Clean_rawdatapopup.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_options_clean_rawdata(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Clean_rawdatapopup)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Clean_rawdatapopup)
        end
    end
end