classdef EEGpalCS_mod_Epoching < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Epoching                       matlab.ui.Figure
        HelpButton                     matlab.ui.control.Button
        AvgSE                          matlab.ui.control.StateButton
        AvgSD                          matlab.ui.control.StateButton
        BetweenSubjectGrandAverage     matlab.ui.control.Label
        WithinSubjectAveraging         matlab.ui.control.Label
        WithinSubjectAveragingInstruction  matlab.ui.control.Label
        AVERAGINGLabel                 matlab.ui.control.Label
        BetweenPerFactor               matlab.ui.control.StateButton
        BetweenPerCondition            matlab.ui.control.StateButton
        BetweenPerTrigger              matlab.ui.control.StateButton
        BetweenAllTriggers             matlab.ui.control.StateButton
        WithinPerFactor                matlab.ui.control.StateButton
        WithinPerCondition             matlab.ui.control.StateButton
        WithinPerTrigger               matlab.ui.control.StateButton
        WithinAllTriggers              matlab.ui.control.StateButton
        OriginalsamplingrateLabel_2    matlab.ui.control.Label
        OrigSamplingRate               matlab.ui.control.NumericEditField
        InconsistentSR                 matlab.ui.control.Label
        ResampleSwitch                 matlab.ui.control.Switch
        ResampleLabel                  matlab.ui.control.Label
        SamplingRate                   matlab.ui.control.NumericEditField
        SAMPLINGRATELabel              matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        EPOCHSTab                      matlab.ui.container.Tab
        SaveTogether_split_2           matlab.ui.control.StateButton
        EndOfFile                      matlab.ui.control.StateButton
        SubEpochsLabel                 matlab.ui.control.Label
        EVENTSLabel                    matlab.ui.control.Label
        InFileButton                   matlab.ui.control.StateButton
        mrkButton                      matlab.ui.control.StateButton
        FilesEventsLabel               matlab.ui.control.Label
        FilesEvents                    matlab.ui.control.TextArea
        SaveEpochsLabel                matlab.ui.control.Label
        SaveSeparately                 matlab.ui.control.StateButton
        SaveTogether                   matlab.ui.control.StateButton
        SaveTogether_split             matlab.ui.control.StateButton
        CorrectTimingLabel             matlab.ui.control.Label
        msLabel_7                      matlab.ui.control.Label
        tfLabel_7                      matlab.ui.control.Label
        EventsCorrLabel                matlab.ui.control.Label
        EventsCorr_ms                  matlab.ui.control.NumericEditField
        EventsCorr_tf                  matlab.ui.control.NumericEditField
        EpochingLabel                  matlab.ui.control.Label
        EpochEventsLabel               matlab.ui.control.Label
        EpochEvents                    matlab.ui.control.EditField
        Epoch_RelativeToEventLabel     matlab.ui.control.Label
        msLabel                        matlab.ui.control.Label
        tfLabel                        matlab.ui.control.Label
        BLaux_total_tf                 matlab.ui.control.NumericEditField
        BLaux_total_ms                 matlab.ui.control.NumericEditField
        BaselinedurationLabel_2        matlab.ui.control.Label
        BLaux_offset_tf                matlab.ui.control.NumericEditField
        BLaux_offset_ms                matlab.ui.control.NumericEditField
        BaselineoffsetLabel_2          matlab.ui.control.Label
        BLaux_onset_tf                 matlab.ui.control.NumericEditField
        BLaux_onset_ms                 matlab.ui.control.NumericEditField
        BaselineonsetLabel_2           matlab.ui.control.Label
        tfLabel_3                      matlab.ui.control.Label
        msLabel_3                      matlab.ui.control.Label
        BL_RelativeToEventLabel_2      matlab.ui.control.Label
        BaselineAuxPeriod              matlab.ui.control.DropDown
        BaselineperiodDropDownLabel_2  matlab.ui.control.Label
        AuxChannels                    matlab.ui.control.Label
        BLscalp_total_tf               matlab.ui.control.NumericEditField
        BLscalp_total_ms               matlab.ui.control.NumericEditField
        BaselinedurationLabel          matlab.ui.control.Label
        BLscalp_offset_tf              matlab.ui.control.NumericEditField
        BLscalp_offset_ms              matlab.ui.control.NumericEditField
        BaselineoffsetLabel            matlab.ui.control.Label
        BLscalp_onset_tf               matlab.ui.control.NumericEditField
        BLscalp_onset_ms               matlab.ui.control.NumericEditField
        BaselineonsetLabel             matlab.ui.control.Label
        tfLabel_2                      matlab.ui.control.Label
        msLabel_2                      matlab.ui.control.Label
        BL_RelativeToEventLabel        matlab.ui.control.Label
        BaselineScalpPeriod            matlab.ui.control.DropDown
        BaselineperiodDropDownLabel    matlab.ui.control.Label
        ScalpChannels                  matlab.ui.control.Label
        BLinstruction                  matlab.ui.control.Label
        BASELINECORRECTIONLabel        matlab.ui.control.Label
        RejectTotal_tf                 matlab.ui.control.NumericEditField
        RejectTotal_ms                 matlab.ui.control.NumericEditField
        EpochdurationLabel_2           matlab.ui.control.Label
        RejectOffset_tf                matlab.ui.control.NumericEditField
        RejectOffset_ms                matlab.ui.control.NumericEditField
        EpochoffsetLabel_2             matlab.ui.control.Label
        RejectOnset_tf                 matlab.ui.control.NumericEditField
        RejectOnset_ms                 matlab.ui.control.NumericEditField
        EpochonsetLabel_2              matlab.ui.control.Label
        tfLabel_4                      matlab.ui.control.Label
        msLabel_4                      matlab.ui.control.Label
        Reject_RelativeToEventLabel    matlab.ui.control.Label
        RejectTriggers                 matlab.ui.control.EditField
        EventsLabel_2                  matlab.ui.control.Label
        PeriodRejectionLabel           matlab.ui.control.Label
        nSubEpochs                     matlab.ui.control.NumericEditField
        NumberofsubepochsEditFieldLabel  matlab.ui.control.Label
        SubEpochs                      matlab.ui.control.StateButton
        SubDuration_tf                 matlab.ui.control.NumericEditField
        SubDuration_ms                 matlab.ui.control.NumericEditField
        EpochTotal_tf                  matlab.ui.control.NumericEditField
        EpochTotal_ms                  matlab.ui.control.NumericEditField
        EpochdurationLabel             matlab.ui.control.Label
        EpochOffset_tf                 matlab.ui.control.NumericEditField
        EpochOffset_ms                 matlab.ui.control.NumericEditField
        EpochoffsetLabel               matlab.ui.control.Label
        EpochOnset_tf                  matlab.ui.control.NumericEditField
        EpochOnset_ms                  matlab.ui.control.NumericEditField
        EpochonsetLabel                matlab.ui.control.Label
        ARTEFACTREJECTIONTab           matlab.ui.container.Tab
        ARevaluationButton             matlab.ui.control.Button
        UploadTableButton              matlab.ui.control.Button
        IgnoreBadSwitch                matlab.ui.control.Switch
        IgnorebadchannelsLabel         matlab.ui.control.Label
        InterpolationButton            matlab.ui.control.Button
        InterpolationPath              matlab.ui.control.TextArea
        InterpolationtableLabel        matlab.ui.control.Label
        ChanCompInstruction            matlab.ui.control.Label
        InconsistentNumChan_aux        matlab.ui.control.Label
        AR_AuxChanTestTable            matlab.ui.control.Table
        AR_AuxChannelsToTestLabel      matlab.ui.control.Label
        InconsistentNumChan_scalp      matlab.ui.control.Label
        AR_ScalpChanTestTable          matlab.ui.control.Table
        AR_ChannelsToTestLabel         matlab.ui.control.Label
        ScalpCluster                   matlab.ui.control.DropDown
        ScalpelectrodesclusterDropDownLabel  matlab.ui.control.Label
        CHANNELSLabel                  matlab.ui.control.Label
        AR_AuxPost_tf                  matlab.ui.control.NumericEditField
        AR_AuxPost_ms                  matlab.ui.control.NumericEditField
        PosteventLabel_2               matlab.ui.control.Label
        AR_AuxPre_tf                   matlab.ui.control.NumericEditField
        AR_AuxPre_ms                   matlab.ui.control.NumericEditField
        PreeventLabel_2                matlab.ui.control.Label
        tfLabel_6                      matlab.ui.control.Label
        msLabel_6                      matlab.ui.control.Label
        AR_AuxMarkAsBad                matlab.ui.control.Label
        AR_ScalpPost_tf                matlab.ui.control.NumericEditField
        AR_ScalpPost_ms                matlab.ui.control.NumericEditField
        PosteventLabel                 matlab.ui.control.Label
        AR_ScalpPre_tf                 matlab.ui.control.NumericEditField
        AR_ScalpPre_ms                 matlab.ui.control.NumericEditField
        PreeventLabel                  matlab.ui.control.Label
        tfLabel_5                      matlab.ui.control.Label
        msLabel_5                      matlab.ui.control.Label
        AR_ScalpMarkAsBad              matlab.ui.control.Label
        AR_AuxMinRange                 matlab.ui.control.NumericEditField
        MinrangethresholdVLabel_2      matlab.ui.control.Label
        AR_ScalpMinRange               matlab.ui.control.NumericEditField
        MinrangethresholdVLabel        matlab.ui.control.Label
        AR_AuxMinRangeCheckBox         matlab.ui.control.CheckBox
        AR_ScalpMinRangeCheckBox       matlab.ui.control.CheckBox
        AR_AuxMaxRange                 matlab.ui.control.NumericEditField
        MaxrangethresholdVLabel_2      matlab.ui.control.Label
        AR_AuxMaxRangeCheckBox         matlab.ui.control.CheckBox
        AR_AuxStep                     matlab.ui.control.NumericEditField
        MaxstepthresholdVtfLabel_2     matlab.ui.control.Label
        AR_AuxStepCheckBox             matlab.ui.control.CheckBox
        AR_AuxAbsolute                 matlab.ui.control.NumericEditField
        MaxabsolutethresholdVLabel_2   matlab.ui.control.Label
        AR_AuxAbsoluteCheckBox         matlab.ui.control.CheckBox
        AR_AuxChannels                 matlab.ui.control.Label
        AR_ScalpMaxRange               matlab.ui.control.NumericEditField
        MaxrangethresholdVLabel        matlab.ui.control.Label
        AR_ScalpMaxRangeCheckBox       matlab.ui.control.CheckBox
        AR_ScalpStep                   matlab.ui.control.NumericEditField
        MaxstepthresholdVtfLabel       matlab.ui.control.Label
        AR_ScalpStepCheckBox           matlab.ui.control.CheckBox
        AR_ScalpAbsolute               matlab.ui.control.NumericEditField
        MaxabsolutethresholdVLabel     matlab.ui.control.Label
        AR_ScalpAbsoluteCheckBox       matlab.ui.control.CheckBox
        AR_ScalpChannels               matlab.ui.control.Label
        CRITERIALabel                  matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        RetainButton                   matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        APPLYLabel                     matlab.ui.control.Label
        ParameterFilePath              matlab.ui.control.EditField
        ParameterfileEditFieldLabel    matlab.ui.control.Label
        SaveAsButton                   matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        InputParametersFile            matlab.ui.control.Label
        SuffixString                   matlab.ui.control.EditField
        SuffixstringLabel              matlab.ui.control.Label
        SaveFolder                     matlab.ui.control.TextArea
        SavingfolderLabel              matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        SelectUpperFolderLabel         matlab.ui.control.Label
        FilesExtension                 matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel    matlab.ui.control.Label
        OUTPUTOPTIONSLabel             matlab.ui.control.Label
        EPOCHINGLabel                  matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        SettingTable
        %ChanTestIndices
        nChan
        InterpolationTable
    end
    
    properties (Access = public)
        SessionParameters
        Study_Parameters
    end
    
    methods (Access = private)
        
        function applyStudyParam(app)
            % OUTPUT OPTION
            app.FilesExtension.Value            = app.Study_Parameters.Epoching.FilesExtention;
            app.SaveFolder.Value                = app.Study_Parameters.Epoching.SaveFolder;
            app.SuffixString.Value              = app.Study_Parameters.Epoching.SuffixString;
            
            
            % SAMPLING RATE
            
            app.ResampleSwitch.Value            = app.Study_Parameters.Epoching.ResampleSwitch;
            app.SamplingRate.Value              = app.Study_Parameters.Epoching.SamplingRate;
            
            
            % AVERAGING
            
            % Within-subject averaging
            app.WithinAllTriggers.Value         = app.Study_Parameters.Epoching.WithinAllTriggers;
            app.WithinPerTrigger.Value          = app.Study_Parameters.Epoching.WithinPerTrigger;
            app.WithinPerCondition.Value        = app.Study_Parameters.Epoching.WithinPerCondition;
            app.WithinPerFactor.Value           = app.Study_Parameters.Epoching.WithinPerFactor;
            
            % Between-subject averaging
            app.BetweenAllTriggers.Value        = app.Study_Parameters.Epoching.BetweenAllTriggers;
            app.BetweenPerTrigger.Value         = app.Study_Parameters.Epoching.BetweenPerTrigger;
            app.BetweenPerCondition.Value       = app.Study_Parameters.Epoching.BetweenPerCondition;
            app.BetweenPerFactor.Value          = app.Study_Parameters.Epoching.BetweenPerFactor;
            
            app.AvgSD.Value                     = app.Study_Parameters.Epoching.AvgSD;
            app.AvgSE.Value                     = app.Study_Parameters.Epoching.AvgSE;
            
            
            % EPOCHS
            
            % Events
            app.InFileButton.Value              = app.Study_Parameters.Epoching.InFileButton;
            app.mrkButton.Value                 = app.Study_Parameters.Epoching.mrkButton;
            
            % Correction
            app.EventsCorr_ms.Value             = app.Study_Parameters.Epoching.TriggersCorr_ms;
            app.EventsCorr_tf.Value             = app.Study_Parameters.Epoching.TriggersCorr_tf;
            
            % Saving
            app.SaveSeparately.Value            = app.Study_Parameters.Epoching.SaveSeparately;
            app.SaveTogether.Value              = app.Study_Parameters.Epoching.SaveTogether;
            app.SaveTogether_split.Value        = app.Study_Parameters.Epoching.SaveTogether_split;
            
            % Epoching
            app.EpochEvents.Value               = app.Study_Parameters.Epoching.EpochTriggers;
            app.EpochOnset_ms.Value             = app.Study_Parameters.Epoching.EpochOnset_ms;
            app.EpochOnset_tf.Value             = app.Study_Parameters.Epoching.EpochOnset_tf;
            app.EpochOffset_ms.Value            = app.Study_Parameters.Epoching.EpochOffset_ms;
            app.EpochOffset_tf.Value            = app.Study_Parameters.Epoching.EpochOffset_tf;
            updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
            app.SubEpochs.Value                 = app.Study_Parameters.Epoching.SupEpochs;
            app.SubDuration_ms.Value            = app.Study_Parameters.Epoching.SubDuration_ms;
            app.SubDuration_tf.Value            = app.Study_Parameters.Epoching.SubDuration_tf;
            app.nSubEpochs.Value                = app.Study_Parameters.Epoching.nSubEpochs;
            
            % Period rejection
            app.RejectTriggers.Value            = app.Study_Parameters.Epoching.RejectTriggers;
            app.RejectOnset_ms.Value            = app.Study_Parameters.Epoching.RejectOnset_ms;
            app.RejectOnset_tf.Value            = app.Study_Parameters.Epoching.RejectOnset_tf;
            app.RejectOffset_ms.Value           = app.Study_Parameters.Epoching.RejectOffset_ms;
            app.RejectOffset_tf.Value           = app.Study_Parameters.Epoching.RejectOffset_tf;
            updateTotal(app,app.RejectOnset_ms,app.RejectOnset_tf,app.RejectOffset_ms,app.RejectOffset_tf,app.RejectTotal_ms,app.RejectTotal_tf)
            
            % Baseline
            app.BaselineScalpPeriod.Value       = app.Study_Parameters.Epoching.BaselineScalpPeriod;
            app.BLscalp_onset_ms.Value          = app.Study_Parameters.Epoching.BLscalp_onset_ms;
            app.BLscalp_onset_tf.Value          = app.Study_Parameters.Epoching.BLscalp_onset_tf;
            app.BLscalp_offset_ms.Value         = app.Study_Parameters.Epoching.BLscalp_offset_ms;
            app.BLscalp_offset_tf.Value         = app.Study_Parameters.Epoching.BLscalp_offset_tf;
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            
            app.BaselineAuxPeriod.Value         = app.Study_Parameters.Epoching.BaselineAuxPeriod;
            app.BLaux_onset_ms.Value            = app.Study_Parameters.Epoching.BLaux_onset_ms;
            app.BLaux_onset_tf.Value            = app.Study_Parameters.Epoching.BLaux_onset_tf;
            app.BLaux_offset_ms.Value           = app.Study_Parameters.Epoching.BLaux_offset_ms;
            app.BLaux_offset_tf.Value           = app.Study_Parameters.Epoching.BLaux_offset_tf;
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            
            
            % ARTEFACT REJECTION
            
            % Criteria
            app.AR_ScalpAbsoluteCheckBox.Value  = app.Study_Parameters.Epoching.AR_ScalpThresholdCheckBox;
            app.AR_ScalpAbsolute.Value          = app.Study_Parameters.Epoching.AR_ScalpThreshold;
            app.AR_ScalpStepCheckBox.Value      = app.Study_Parameters.Epoching.AR_ScalpStepCheckBox;
            app.AR_ScalpStep.Value              = app.Study_Parameters.Epoching.AR_ScalpStep;
            app.AR_ScalpMaxRangeCheckBox.Value  = app.Study_Parameters.Epoching.AR_ScalpMaxRangeCheckBox;
            app.AR_ScalpMaxRange.Value          = app.Study_Parameters.Epoching.AR_ScalpMaxRange;
            app.AR_ScalpMinRangeCheckBox.Value  = app.Study_Parameters.Epoching.AR_ScalpMinRangeCheckBox;
            app.AR_ScalpMinRange.Value          = app.Study_Parameters.Epoching.AR_ScalpMinRange;
            app.AR_ScalpPre_ms.Value            = app.Study_Parameters.Epoching.AR_ScalpPre_ms;
            app.AR_ScalpPre_tf.Value            = app.Study_Parameters.Epoching.AR_ScalpPre_tf;
            app.AR_ScalpPost_ms.Value           = app.Study_Parameters.Epoching.AR_ScalpPost_ms;
            app.AR_ScalpPost_tf.Value           = app.Study_Parameters.Epoching.AR_ScalpPost_tf;
            
            app.AR_AuxAbsoluteCheckBox.Value    = app.Study_Parameters.Epoching.AR_AuxThresholdCheckBox;
            app.AR_AuxAbsolute.Value            = app.Study_Parameters.Epoching.AR_AuxThreshold;
            app.AR_AuxStepCheckBox.Value        = app.Study_Parameters.Epoching.AR_AuxStepCheckBox;
            app.AR_AuxStep.Value                = app.Study_Parameters.Epoching.AR_AuxStep;
            app.AR_AuxMaxRangeCheckBox.Value    = app.Study_Parameters.Epoching.AR_AuxMaxRangeCheckBox;
            app.AR_AuxMaxRange.Value            = app.Study_Parameters.Epoching.AR_AuxMaxRange;
            app.AR_AuxMinRangeCheckBox.Value    = app.Study_Parameters.Epoching.AR_AuxMinRangeCheckBox;
            app.AR_AuxMinRange.Value            = app.Study_Parameters.Epoching.AR_AuxMinRange;
            app.AR_AuxPre_ms.Value              = app.Study_Parameters.Epoching.AR_AuxPre_ms;
            app.AR_AuxPre_tf.Value              = app.Study_Parameters.Epoching.AR_AuxPre_tf;
            app.AR_AuxPost_ms.Value             = app.Study_Parameters.Epoching.AR_AuxPost_ms;
            app.AR_AuxPost_tf.Value             = app.Study_Parameters.Epoching.AR_AuxPost_tf;
            
            % Channels
%             app.ScalpCluster.Items              = app.Study_Parameters.Epoching.ScalpClusterItems;
            try % if the electrode setting is not defined, will not work...
                app.ScalpCluster.Value              = app.Study_Parameters.Epoching.ScalpClusterValue;
            catch
                % add a warning?
            end
            app.IgnoreBadSwitch.Value           = app.Study_Parameters.Epoching.IgnoreBadSwitch;
            app.InterpolationPath.Value         = app.Study_Parameters.Epoching.InterpolationPath;
        end
        
        function Study_Parameters = updateStudyParam(app,Study_Parameters)
            % OUTPUT OPTION
            Study_Parameters.Epoching.FilesExtention            = app.FilesExtension.Value;
            Study_Parameters.Epoching.SaveFolder                = app.SaveFolder.Value;
            Study_Parameters.Epoching.SuffixString              = app.SuffixString.Value;
            
            
            % SAMPLING RATE
            
            Study_Parameters.Epoching.ResampleSwitch            = app.ResampleSwitch.Value;
            Study_Parameters.Epoching.SamplingRate              = app.SamplingRate.Value;
            
            
            % AVERAGING
            
            % Within-subject averaging
            Study_Parameters.Epoching.WithinAllTriggers         = app.WithinAllTriggers.Value;
            Study_Parameters.Epoching.WithinPerTrigger          = app.WithinPerTrigger.Value;
            Study_Parameters.Epoching.WithinPerCondition        = app.WithinPerCondition.Value;
            Study_Parameters.Epoching.WithinPerFactor           = app.WithinPerFactor.Value;
            
            % Between-subject averaging
            Study_Parameters.Epoching.BetweenAllTriggers        = app.BetweenAllTriggers.Value;
            Study_Parameters.Epoching.BetweenPerTrigger         = app.BetweenPerTrigger.Value;
            Study_Parameters.Epoching.BetweenPerCondition       = app.BetweenPerCondition.Value;
            Study_Parameters.Epoching.BetweenPerFactor          = app.BetweenPerFactor.Value;
            
            Study_Parameters.Epoching.AvgSD                     = app.AvgSD.Value;
            Study_Parameters.Epoching.AvgSE                     = app.AvgSE.Value;
            
            
            % EPOCHS
            
            % Events
            Study_Parameters.Epoching.InFileButton              = app.InFileButton.Value;
            Study_Parameters.Epoching.mrkButton                 = app.mrkButton.Value;
            
            % Correction
            Study_Parameters.Epoching.TriggersCorr_ms           = app.EventsCorr_ms.Value;
            Study_Parameters.Epoching.TriggersCorr_tf           = app.EventsCorr_tf.Value;
            
            % Saving
            Study_Parameters.Epoching.SaveSeparately            = app.SaveSeparately.Value;
            Study_Parameters.Epoching.SaveTogether              = app.SaveTogether.Value;
            Study_Parameters.Epoching.SaveTogether_split        = app.SaveTogether_split.Value;
            
            % Epoching
            Study_Parameters.Epoching.EpochTriggers             = app.EpochEvents.Value;
            Study_Parameters.Epoching.EpochOnset_ms             = app.EpochOnset_ms.Value;
            Study_Parameters.Epoching.EpochOnset_tf             = app.EpochOnset_tf.Value;
            Study_Parameters.Epoching.EpochOffset_ms            = app.EpochOffset_ms.Value;
            Study_Parameters.Epoching.EpochOffset_tf            = app.EpochOffset_tf.Value;
            Study_Parameters.Epoching.SupEpochs                 = app.SubEpochs.Value;
            Study_Parameters.Epoching.SubDuration_ms            = app.SubDuration_ms.Value;
            Study_Parameters.Epoching.SubDuration_tf            = app.SubDuration_tf.Value;
            Study_Parameters.Epoching.nSubEpochs                = app.nSubEpochs.Value;
            
            % Period rejection
            Study_Parameters.Epoching.RejectTriggers            = app.RejectTriggers.Value;
            Study_Parameters.Epoching.RejectOnset_ms            = app.RejectOnset_ms.Value;
            Study_Parameters.Epoching.RejectOnset_tf            = app.RejectOnset_tf.Value;
            Study_Parameters.Epoching.RejectOffset_ms           = app.RejectOffset_ms.Value;
            Study_Parameters.Epoching.RejectOffset_tf           = app.RejectOffset_tf.Value;
            
            % Baseline
            Study_Parameters.Epoching.BaselineScalpPeriod       = app.BaselineScalpPeriod.Value;
            Study_Parameters.Epoching.BLscalp_onset_ms          = app.BLscalp_onset_ms.Value;
            Study_Parameters.Epoching.BLscalp_onset_tf          = app.BLscalp_onset_tf.Value;
            Study_Parameters.Epoching.BLscalp_offset_ms         = app.BLscalp_offset_ms.Value;
            Study_Parameters.Epoching.BLscalp_offset_tf         = app.BLscalp_offset_tf.Value;
            
            Study_Parameters.Epoching.BaselineAuxPeriod         = app.BaselineAuxPeriod.Value;
            Study_Parameters.Epoching.BLaux_onset_ms            = app.BLaux_onset_ms.Value;
            Study_Parameters.Epoching.BLaux_onset_tf            = app.BLaux_onset_tf.Value;
            Study_Parameters.Epoching.BLaux_offset_ms           = app.BLaux_offset_ms.Value;
            Study_Parameters.Epoching.BLaux_offset_tf           = app.BLaux_offset_tf.Value;
            
            
            % ARTEFACT REJECTION
            
            % Criteria
            Study_Parameters.Epoching.AR_ScalpThresholdCheckBox = app.AR_ScalpAbsoluteCheckBox.Value;
            Study_Parameters.Epoching.AR_ScalpThreshold         = app.AR_ScalpAbsolute.Value;
            Study_Parameters.Epoching.AR_ScalpStepCheckBox      = app.AR_ScalpStepCheckBox.Value;
            Study_Parameters.Epoching.AR_ScalpStep              = app.AR_ScalpStep.Value;
            Study_Parameters.Epoching.AR_ScalpMaxRangeCheckBox  = app.AR_ScalpMaxRangeCheckBox.Value;
            Study_Parameters.Epoching.AR_ScalpMaxRange          = app.AR_ScalpMaxRange.Value;
            Study_Parameters.Epoching.AR_ScalpMinRangeCheckBox  = app.AR_ScalpMinRangeCheckBox.Value;
            Study_Parameters.Epoching.AR_ScalpMinRange          = app.AR_ScalpMinRange.Value;
            Study_Parameters.Epoching.AR_ScalpPre_ms            = app.AR_ScalpPre_ms.Value;
            Study_Parameters.Epoching.AR_ScalpPre_tf            = app.AR_ScalpPre_tf.Value;
            Study_Parameters.Epoching.AR_ScalpPost_ms           = app.AR_ScalpPost_ms.Value;
            Study_Parameters.Epoching.AR_ScalpPost_tf           = app.AR_ScalpPost_tf.Value;
            
            Study_Parameters.Epoching.AR_AuxThresholdCheckBox   = app.AR_AuxAbsoluteCheckBox.Value;
            Study_Parameters.Epoching.AR_AuxThreshold           = app.AR_AuxAbsolute.Value;
            Study_Parameters.Epoching.AR_AuxStepCheckBox        = app.AR_AuxStepCheckBox.Value;
            Study_Parameters.Epoching.AR_AuxStep                = app.AR_AuxStep.Value;
            Study_Parameters.Epoching.AR_AuxMaxRangeCheckBox    = app.AR_AuxMaxRangeCheckBox.Value;
            Study_Parameters.Epoching.AR_AuxMaxRange            = app.AR_AuxMaxRange.Value;
            Study_Parameters.Epoching.AR_AuxMinRangeCheckBox    = app.AR_AuxMinRangeCheckBox.Value;
            Study_Parameters.Epoching.AR_AuxMinRange            = app.AR_AuxMinRange.Value;
            Study_Parameters.Epoching.AR_AuxPre_ms              = app.AR_AuxPre_ms.Value;
            Study_Parameters.Epoching.AR_AuxPre_tf              = app.AR_AuxPre_tf.Value;
            Study_Parameters.Epoching.AR_AuxPost_ms             = app.AR_AuxPost_ms.Value;
            Study_Parameters.Epoching.AR_AuxPost_tf             = app.AR_AuxPost_tf.Value;
            
            % Channels
%             Study_Parameters.Epoching.ScalpClusterItems         = app.ScalpCluster.Items;
            try % if the electrode setting is not defined, will not work...
                Study_Parameters.Epoching.ScalpClusterValue         = app.ScalpCluster.Value;
            catch
                % add a warning?
            end
            Study_Parameters.Epoching.IgnoreBadSwitch           = app.IgnoreBadSwitch.Value;
            Study_Parameters.Epoching.InterpolationPath         = app.InterpolationPath.Value;
        end
        
        
        function applySessionParam(app)
            % OUTPUT OPTION
            app.FilesExtension.Value            = app.SessionParameters.Epoching.FilesExtention;
            app.SaveFolder.Value                = app.SessionParameters.Epoching.SaveFolder;
            app.SuffixString.Value              = app.SessionParameters.Epoching.SuffixString;
            
            
            % SAMPLING RATE
            
            app.ResampleSwitch.Value            = app.SessionParameters.Epoching.ResampleSwitch;
            app.SamplingRate.Value              = app.SessionParameters.Epoching.SamplingRate;
            
            
            % AVERAGING
            
            % Within-subject averaging
            app.WithinAllTriggers.Value         = app.SessionParameters.Epoching.WithinAllTriggers;
            app.WithinPerTrigger.Value          = app.SessionParameters.Epoching.WithinPerTrigger;
            app.WithinPerCondition.Value        = app.SessionParameters.Epoching.WithinPerCondition;
            app.WithinPerFactor.Value           = app.SessionParameters.Epoching.WithinPerFactor;
            
            % Between-subject averaging
            app.BetweenAllTriggers.Value        = app.SessionParameters.Epoching.BetweenAllTriggers;
            app.BetweenPerTrigger.Value         = app.SessionParameters.Epoching.BetweenPerTrigger;
            app.BetweenPerCondition.Value       = app.SessionParameters.Epoching.BetweenPerCondition;
            app.BetweenPerFactor.Value          = app.SessionParameters.Epoching.BetweenPerFactor;
            
            app.AvgSD.Value                     = app.SessionParameters.Epoching.AvgSD;
            app.AvgSE.Value                     = app.SessionParameters.Epoching.AvgSE;
            
            
            % EPOCHS
            
            % Events
            app.InFileButton.Value              = app.SessionParameters.Epoching.InFileButton;
            app.mrkButton.Value                 = app.SessionParameters.Epoching.mrkButton;
            
            % Correction
            app.EventsCorr_ms.Value             = app.SessionParameters.Epoching.TriggersCorr_ms;
            app.EventsCorr_tf.Value             = app.SessionParameters.Epoching.TriggersCorr_tf;
            
            % Saving
            app.SaveSeparately.Value            = app.SessionParameters.Epoching.SaveSeparately;
            app.SaveTogether.Value              = app.SessionParameters.Epoching.SaveTogether;
            app.SaveTogether_split.Value        = app.SessionParameters.Epoching.SaveTogether_split;
            
            % Epoching
            app.EpochEvents.Value               = app.SessionParameters.Epoching.EpochTriggers;
            app.EpochOnset_ms.Value             = app.SessionParameters.Epoching.EpochOnset_ms;
            app.EpochOnset_tf.Value             = app.SessionParameters.Epoching.EpochOnset_tf;
            app.EpochOffset_ms.Value            = app.SessionParameters.Epoching.EpochOffset_ms;
            app.EpochOffset_tf.Value            = app.SessionParameters.Epoching.EpochOffset_tf;
            updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
            app.SubEpochs.Value                 = app.SessionParameters.Epoching.SupEpochs;
            app.SubDuration_ms.Value            = app.SessionParameters.Epoching.SubDuration_ms;
            app.SubDuration_tf.Value            = app.SessionParameters.Epoching.SubDuration_tf;
            app.nSubEpochs.Value                = app.SessionParameters.Epoching.nSubEpochs;
            app.EndOfFile.Value                 = app.SessionParameters.Epoching.EndOfFile;

            %update EoF, Spit subEpoch 
            EndOfFileValueChanged(app);            
            SubEpochsValueChanged(app);


            % Period rejection
            app.RejectTriggers.Value            = app.SessionParameters.Epoching.RejectTriggers;
            app.RejectOnset_ms.Value            = app.SessionParameters.Epoching.RejectOnset_ms;
            app.RejectOnset_tf.Value            = app.SessionParameters.Epoching.RejectOnset_tf;
            app.RejectOffset_ms.Value           = app.SessionParameters.Epoching.RejectOffset_ms;
            app.RejectOffset_tf.Value           = app.SessionParameters.Epoching.RejectOffset_tf;
            updateTotal(app,app.RejectOnset_ms,app.RejectOnset_tf,app.RejectOffset_ms,app.RejectOffset_tf,app.RejectTotal_ms,app.RejectTotal_tf)
            
            % Baseline
            app.BaselineScalpPeriod.Value       = app.SessionParameters.Epoching.BaselineScalpPeriod;
            app.BLscalp_onset_ms.Value          = app.SessionParameters.Epoching.BLscalp_onset_ms;
            app.BLscalp_onset_tf.Value          = app.SessionParameters.Epoching.BLscalp_onset_tf;
            app.BLscalp_offset_ms.Value         = app.SessionParameters.Epoching.BLscalp_offset_ms;
            app.BLscalp_offset_tf.Value         = app.SessionParameters.Epoching.BLscalp_offset_tf;
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            
            app.BaselineAuxPeriod.Value         = app.SessionParameters.Epoching.BaselineAuxPeriod;
            app.BLaux_onset_ms.Value            = app.SessionParameters.Epoching.BLaux_onset_ms;
            app.BLaux_onset_tf.Value            = app.SessionParameters.Epoching.BLaux_onset_tf;
            app.BLaux_offset_ms.Value           = app.SessionParameters.Epoching.BLaux_offset_ms;
            app.BLaux_offset_tf.Value           = app.SessionParameters.Epoching.BLaux_offset_tf;
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            
            %update baseline
            BaselineScalpPeriodValueChanged(app);
            BaselineAuxPeriodValueChanged(app);
            
            % ARTEFACT REJECTION
            
            % Criteria
            app.AR_ScalpAbsoluteCheckBox.Value  = app.SessionParameters.Epoching.AR_ScalpThresholdCheckBox;
            app.AR_ScalpAbsolute.Value          = app.SessionParameters.Epoching.AR_ScalpThreshold;
            app.AR_ScalpStepCheckBox.Value      = app.SessionParameters.Epoching.AR_ScalpStepCheckBox;
            app.AR_ScalpStep.Value              = app.SessionParameters.Epoching.AR_ScalpStep;
            app.AR_ScalpMaxRangeCheckBox.Value  = app.SessionParameters.Epoching.AR_ScalpMaxRangeCheckBox;
            app.AR_ScalpMaxRange.Value          = app.SessionParameters.Epoching.AR_ScalpMaxRange;
            app.AR_ScalpMinRangeCheckBox.Value  = app.SessionParameters.Epoching.AR_ScalpMinRangeCheckBox;
            app.AR_ScalpMinRange.Value          = app.SessionParameters.Epoching.AR_ScalpMinRange;
            app.AR_ScalpPre_ms.Value            = app.SessionParameters.Epoching.AR_ScalpPre_ms;
            app.AR_ScalpPre_tf.Value            = app.SessionParameters.Epoching.AR_ScalpPre_tf;
            app.AR_ScalpPost_ms.Value           = app.SessionParameters.Epoching.AR_ScalpPost_ms;
            app.AR_ScalpPost_tf.Value           = app.SessionParameters.Epoching.AR_ScalpPost_tf;
            
            app.AR_AuxAbsoluteCheckBox.Value    = app.SessionParameters.Epoching.AR_AuxThresholdCheckBox;
            app.AR_AuxAbsolute.Value            = app.SessionParameters.Epoching.AR_AuxThreshold;
            app.AR_AuxStepCheckBox.Value        = app.SessionParameters.Epoching.AR_AuxStepCheckBox;
            app.AR_AuxStep.Value                = app.SessionParameters.Epoching.AR_AuxStep;
            app.AR_AuxMaxRangeCheckBox.Value    = app.SessionParameters.Epoching.AR_AuxMaxRangeCheckBox;
            app.AR_AuxMaxRange.Value            = app.SessionParameters.Epoching.AR_AuxMaxRange;
            app.AR_AuxMinRangeCheckBox.Value    = app.SessionParameters.Epoching.AR_AuxMinRangeCheckBox;
            app.AR_AuxMinRange.Value            = app.SessionParameters.Epoching.AR_AuxMinRange;
            app.AR_AuxPre_ms.Value              = app.SessionParameters.Epoching.AR_AuxPre_ms;
            app.AR_AuxPre_tf.Value              = app.SessionParameters.Epoching.AR_AuxPre_tf;
            app.AR_AuxPost_ms.Value             = app.SessionParameters.Epoching.AR_AuxPost_ms;
            app.AR_AuxPost_tf.Value             = app.SessionParameters.Epoching.AR_AuxPost_tf;
            
            % Channels
%             app.ScalpCluster.Items              = app.Study_Parameters.Epoching.ScalpClusterItems;
            try % if the electrode setting is not defined, will not work...
                app.ScalpCluster.Value              = app.SessionParameters.Epoching.ScalpClusterValue;
            catch
                % add a warning?
            end
            app.IgnoreBadSwitch.Value           = app.SessionParameters.Epoching.IgnoreBadSwitch;
            app.InterpolationPath.Value         = app.SessionParameters.Epoching.InterpolationPath;
        end
        
        function SessionParameters   = updateSessionParam(app,SessionParameters)
            % OUTPUT OPTION
            SessionParameters.Epoching.FilesExtention           = app.FilesExtension.Value;
            SessionParameters.Epoching.SaveFolder               = app.SaveFolder.Value;
            SessionParameters.Epoching.SuffixString             = app.SuffixString.Value;
            
            
            % SAMPLING RATE
            
            SessionParameters.Epoching.ResampleSwitch           = app.ResampleSwitch.Value;
            SessionParameters.Epoching.SamplingRate             = app.SamplingRate.Value;
            
            
            % AVERAGING
            
            % Within-subject averaging
            SessionParameters.Epoching.WithinAllTriggers        = app.WithinAllTriggers.Value;
            SessionParameters.Epoching.WithinPerTrigger         = app.WithinPerTrigger.Value;
            SessionParameters.Epoching.WithinPerCondition       = app.WithinPerCondition.Value;
            SessionParameters.Epoching.WithinPerFactor          = app.WithinPerFactor.Value;
            
            % Between-subject averaging
            SessionParameters.Epoching.BetweenAllTriggers       = app.BetweenAllTriggers.Value;
            SessionParameters.Epoching.BetweenPerTrigger        = app.BetweenPerTrigger.Value;
            SessionParameters.Epoching.BetweenPerCondition      = app.BetweenPerCondition.Value;
            SessionParameters.Epoching.BetweenPerFactor         = app.BetweenPerFactor.Value;
            
            SessionParameters.Epoching.AvgSD                    = app.AvgSD.Value;
            SessionParameters.Epoching.AvgSE                    = app.AvgSE.Value;
            
            
            % EPOCHS
            
            % Events
            SessionParameters.Epoching.InFileButton             = app.InFileButton.Value;
            SessionParameters.Epoching.mrkButton                = app.mrkButton.Value;
            
            % Correction
            SessionParameters.Epoching.TriggersCorr_ms          = app.EventsCorr_ms.Value;
            SessionParameters.Epoching.TriggersCorr_tf          = app.EventsCorr_tf.Value;
            
            % Saving
            SessionParameters.Epoching.SaveSeparately           = app.SaveSeparately.Value;
            SessionParameters.Epoching.SaveTogether             = app.SaveTogether.Value;
            SessionParameters.Epoching.SaveTogether_split       = app.SaveTogether_split.Value;
            
            % Epoching
            SessionParameters.Epoching.EpochTriggers            = app.EpochEvents.Value;
            SessionParameters.Epoching.EpochOnset_ms            = app.EpochOnset_ms.Value;
            SessionParameters.Epoching.EpochOnset_tf            = app.EpochOnset_tf.Value;
            SessionParameters.Epoching.EpochOffset_ms           = app.EpochOffset_ms.Value;
            SessionParameters.Epoching.EpochOffset_tf           = app.EpochOffset_tf.Value;
            SessionParameters.Epoching.SupEpochs                = app.SubEpochs.Value;
            SessionParameters.Epoching.SubDuration_ms           = app.SubDuration_ms.Value;
            SessionParameters.Epoching.SubDuration_tf           = app.SubDuration_tf.Value;
            SessionParameters.Epoching.nSubEpochs               = app.nSubEpochs.Value;
            SessionParameters.Epoching.EndOfFile                = app.EndOfFile.Value;
            
            % Period rejection
            SessionParameters.Epoching.RejectTriggers           = app.RejectTriggers.Value;
            SessionParameters.Epoching.RejectOnset_ms           = app.RejectOnset_ms.Value;
            SessionParameters.Epoching.RejectOnset_tf           = app.RejectOnset_tf.Value;
            SessionParameters.Epoching.RejectOffset_ms          = app.RejectOffset_ms.Value;
            SessionParameters.Epoching.RejectOffset_tf          = app.RejectOffset_tf.Value;
            
            % Baseline
            SessionParameters.Epoching.BaselineScalpPeriod      = app.BaselineScalpPeriod.Value;
            SessionParameters.Epoching.BLscalp_onset_ms         = app.BLscalp_onset_ms.Value;
            SessionParameters.Epoching.BLscalp_onset_tf         = app.BLscalp_onset_tf.Value;
            SessionParameters.Epoching.BLscalp_offset_ms        = app.BLscalp_offset_ms.Value;
            SessionParameters.Epoching.BLscalp_offset_tf        = app.BLscalp_offset_tf.Value;
            
            SessionParameters.Epoching.BaselineAuxPeriod        = app.BaselineAuxPeriod.Value;
            SessionParameters.Epoching.BLaux_onset_ms           = app.BLaux_onset_ms.Value;
            SessionParameters.Epoching.BLaux_onset_tf           = app.BLaux_onset_tf.Value;
            SessionParameters.Epoching.BLaux_offset_ms          = app.BLaux_offset_ms.Value;
            SessionParameters.Epoching.BLaux_offset_tf          = app.BLaux_offset_tf.Value;
            
            
            % ARTEFACT REJECTION
            
            % Criteria
            SessionParameters.Epoching.AR_ScalpThresholdCheckBox = app.AR_ScalpAbsoluteCheckBox.Value;
            SessionParameters.Epoching.AR_ScalpThreshold        = app.AR_ScalpAbsolute.Value;
            SessionParameters.Epoching.AR_ScalpStepCheckBox     = app.AR_ScalpStepCheckBox.Value;
            SessionParameters.Epoching.AR_ScalpStep             = app.AR_ScalpStep.Value;
            SessionParameters.Epoching.AR_ScalpMaxRangeCheckBox = app.AR_ScalpMaxRangeCheckBox.Value;
            SessionParameters.Epoching.AR_ScalpMaxRange         = app.AR_ScalpMaxRange.Value;
            SessionParameters.Epoching.AR_ScalpMinRangeCheckBox = app.AR_ScalpMinRangeCheckBox.Value;
            SessionParameters.Epoching.AR_ScalpMinRange         = app.AR_ScalpMinRange.Value;
            SessionParameters.Epoching.AR_ScalpPre_ms           = app.AR_ScalpPre_ms.Value;
            SessionParameters.Epoching.AR_ScalpPre_tf           = app.AR_ScalpPre_tf.Value;
            SessionParameters.Epoching.AR_ScalpPost_ms          = app.AR_ScalpPost_ms.Value;
            SessionParameters.Epoching.AR_ScalpPost_tf          = app.AR_ScalpPost_tf.Value;
            
            SessionParameters.Epoching.AR_AuxThresholdCheckBox  = app.AR_AuxAbsoluteCheckBox.Value;
            SessionParameters.Epoching.AR_AuxThreshold          = app.AR_AuxAbsolute.Value;
            SessionParameters.Epoching.AR_AuxStepCheckBox       = app.AR_AuxStepCheckBox.Value;
            SessionParameters.Epoching.AR_AuxStep               = app.AR_AuxStep.Value;
            SessionParameters.Epoching.AR_AuxMaxRangeCheckBox   = app.AR_AuxMaxRangeCheckBox.Value;
            SessionParameters.Epoching.AR_AuxMaxRange           = app.AR_AuxMaxRange.Value;
            SessionParameters.Epoching.AR_AuxMinRangeCheckBox   = app.AR_AuxMinRangeCheckBox.Value;
            SessionParameters.Epoching.AR_AuxMinRange           = app.AR_AuxMinRange.Value;
            SessionParameters.Epoching.AR_AuxPre_ms             = app.AR_AuxPre_ms.Value;
            SessionParameters.Epoching.AR_AuxPre_tf             = app.AR_AuxPre_tf.Value;
            SessionParameters.Epoching.AR_AuxPost_ms            = app.AR_AuxPost_ms.Value;
            SessionParameters.Epoching.AR_AuxPost_tf            = app.AR_AuxPost_tf.Value;
            
            % Channels
%             SessionParameters.Epoching.ScalpClusterItems        = app.ScalpCluster.Items;
            try % if the electrode setting is not defined, will not work...
                SessionParameters.Epoching.ScalpClusterValue        = app.ScalpCluster.Value;
            catch
                % add a warning?
            end
            SessionParameters.Epoching.IgnoreBadSwitch          = app.IgnoreBadSwitch.Value;
            SessionParameters.Epoching.InterpolationPath        = app.InterpolationPath.Value;
        end
        
        function updateTotal(app,onset_ms,onset_tf,offset_ms,offset_tf,total_ms,total_tf)
            total_ms.Value  = offset_ms.Value - onset_ms.Value;
            total_tf.Value  = (offset_tf.Value - onset_tf.Value) + 1; % +1 because onset and offset included
            
            if total_tf.Value <= 0 % tf in case there is no sampling rate defined
                total_ms.FontColor      = [1.00,0.00,0.00];
                total_ms.FontWeight     = 'bold';
                total_tf.FontColor      = [1.00,0.00,0.00];
                total_tf.FontWeight     = 'bold';
                app.RunButton.Enable    = 'off';
            else
                total_ms.FontColor      = [0.00,0.00,0.00];
                total_ms.FontWeight     = 'normal';
                total_tf.FontColor      = [0.00,0.00,0.00];
                total_tf.FontWeight     = 'normal';
                app.RunButton.Enable    = 'on';
            end
        end
        
        function tf = ms2tf(~,ms,sr)
            tf = round((ms / 1000) * sr, 0); % round() because time-frames are counted as integer numbers
                                             % => requires to recompute tf2ms for inputs, to be more precise
        end
        
        function ms = tf2ms(~,tf,sr)
            ms = (tf / sr) * 1000;
        end
        
        function reverseStr = txtprogressbar(~,nSteps,step,reverseStr)
            % https://undocumentedmatlab.com/articles/command-window-text-manipulation/
            percentDone = 100 * (step) / nSteps;
            msg = sprintf('    Progression: %3.1f', percentDone);
            fprintf([reverseStr, msg,'%%']);
            reverseStr = repmat(sprintf('\b'), 1, length(msg)+1);
        end
        
        function readEvents(app)
            disp('Reading events from input files')
                InputEvents = "";
                for file = 1:height(app.DataTable)
                    openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                    [~,~,FileExtension] = fileparts(openfilename);
                    events = ''; % In case the file contains no events
                    
                    % BDF files
                    if strcmpi(FileExtension,'.bdf')
                        disp('Biosemi .bdf file detected. It might take a bit longer than expected...')
                        if app.InFileButton.Value
                            [~,~,eventsFile] = open_bdf(openfilename);
                            try
                                eventsFile = string(unique(eventsFile(:,2)'));
                            catch % if not events in file
                                eventsFile = "";
                            end
                        end
                        if app.mrkButton.Value
                            MRK_file = [openfilename '.mrk']; % name of MRK file
                            if isfile(MRK_file)
                                eventsMRK = open_mrk(MRK_file);
                                eventsMRK = string(unique([eventsMRK{:,3}]));
                            else
                                disp(['MRK file not found for ' openfilename])
                                eventsMRK = [''];
                            end                            
                        end
                        if app.InFileButton.Value & app.mrkButton.Value
                            events = [eventsFile eventsMRK];                            
                        elseif app.InFileButton.Value & ~app.mrkButton.Value
                            events = [eventsFile];
                        elseif ~app.InFileButton.Value & app.mrkButton.Value
                            events = [eventsMRK];
                        else 
                            events=[];
                        end
                        
                        if ~isempty(events)
                            InputEvents = [InputEvents,unique(events)]; % unique() again in case some codes are the same in 'File' and 'MRK'
                        end
                        
                    % EEGlab files
                    elseif strcmpi(FileExtension,'.set')
                        if app.InFileButton.Value
                            SETfile = load('-mat',openfilename);
                            try
                                eventsFile = string(unique([SETfile.EEG.event.type]));
                            catch % if not events in file
                                eventsFile = "";
                            end
                        end
                        if app.mrkButton.Value
                            MRK_file = [openfilename '.mrk']; % name of MRK file
                            if isfile(MRK_file)
                                eventsMRK = open_mrk(MRK_file);
                                eventsMRK = string(unique([eventsMRK{:,3}]));
                            else
                                disp(['MRK file not found for ' openfilename])
                                eventsMRK = [''];
                            end
                            
                        end
                        if app.InFileButton.Value & app.mrkButton.Value
                            events = [eventsFile eventsMRK];                            
                        elseif app.InFileButton.Value & ~app.mrkButton.Value
                            events = [eventsFile];
                        elseif ~app.InFileButton.Value & app.mrkButton.Value
                            events = [eventsMRK];
                        else 
                            events=[];
                        end
                            
                        
                        if ~isempty(events)
                            InputEvents = [InputEvents,unique(events)]; % unique() again in case some codes are the same in 'File' and 'MRK'
                        end
                        
                    % Cartool files
                    elseif strcmpi(FileExtension,'.sef') || strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                        if app.mrkButton.Value
                            MRK_file = [openfilename,'.mrk'];
                            if isfile(MRK_file)
                                events = open_mrk(MRK_file);
                                if ~isempty(events)
                                    InputEvents = [InputEvents,unique([events{:,3}])];
                                end
                            else
                                disp(['MRK file not found for ' openfilename])
                            end
                        end
                    else
                        if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                            disp('These tools are not intended for .freq or .ris files.')
                        else
                            disp('File extension not recognized.')
                        end
                        disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                        disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                        disp('File ignored.')
                    end
                end
                if ~isempty(events)
                    app.FilesEvents.Value  = strjoin(unique(InputEvents),', ');
                    app.FilesEvents.Value  = app.FilesEvents.Value{1}(3:end); % Remove initial empty string (maybe a better way of doing...)
                else
                    app.FilesEvents.Value  = "";
                end
                disp('Events identified')
        end
        
        function BLlimits(app)
            
            % UPDATE VALUES
            
            if strcmp(app.BaselineScalpPeriod.Value,'Whole epoch')
                app.BLscalp_onset_ms.Value   = app.EpochOnset_ms.Value;
                app.BLscalp_onset_tf.Value   = app.EpochOnset_tf.Value;
                app.BLscalp_offset_ms.Value  = app.EpochOffset_ms.Value;
                app.BLscalp_offset_tf.Value  = app.EpochOffset_tf.Value;
            elseif strcmp(app.BaselineScalpPeriod.Value,'Pre-trigger period')
                app.BLscalp_onset_ms.Value   = app.EpochOnset_ms.Value;
                app.BLscalp_onset_tf.Value   = app.EpochOnset_tf.Value;
            end
            
            if strcmp(app.BaselineAuxPeriod.Value,'Same as scalp')
                app.BLaux_onset_ms.Value   = app.BLscalp_onset_ms.Value;
                app.BLaux_onset_tf.Value   = app.BLscalp_onset_tf.Value;
                app.BLaux_offset_ms.Value  = app.BLscalp_offset_ms.Value;
                app.BLaux_offset_tf.Value  = app.BLscalp_offset_tf.Value;
            elseif strcmp(app.BaselineAuxPeriod.Value,'Whole epoch')
                app.BLaux_onset_ms.Value   = app.EpochOnset_ms.Value;
                app.BLaux_onset_tf.Value   = app.EpochOnset_tf.Value;
                app.BLaux_offset_ms.Value  = app.EpochOffset_ms.Value;
                app.BLaux_offset_tf.Value  = app.EpochOffset_tf.Value;
            elseif strcmp(app.BaselineAuxPeriod.Value,'Pre-trigger period')
                app.BLaux_onset_ms.Value   = app.EpochOnset_ms.Value;
                app.BLaux_onset_tf.Value   = app.EpochOnset_tf.Value;
            end
            
            
            % SET LIMITS
            
            % Scalp
            app.BLscalp_onset_ms.LowerLimitInclusive    = app.EpochOnset_ms.Value;
            app.BLscalp_onset_tf.LowerLimitInclusive    = app.EpochOnset_tf.Value;
            app.BLscalp_onset_ms.UpperLimitInclusive    = app.EpochOffset_ms.Value;
            app.BLscalp_onset_tf.UpperLimitInclusive    = app.EpochOffset_tf.Value;
            
            app.BLscalp_offset_ms.LowerLimitInclusive   = app.EpochOnset_ms.Value;
            app.BLscalp_offset_tf.LowerLimitInclusive   = app.EpochOnset_tf.Value;
            app.BLscalp_offset_ms.UpperLimitInclusive   = app.EpochOffset_ms.Value;
            app.BLscalp_offset_tf.UpperLimitInclusive   = app.EpochOffset_tf.Value;
            
            
            % Aux
            app.BLaux_onset_ms.LowerLimitInclusive      = app.EpochOnset_ms.Value;
            app.BLaux_onset_tf.LowerLimitInclusive      = app.EpochOnset_tf.Value;
            app.BLaux_onset_ms.UpperLimitInclusive      = app.EpochOffset_ms.Value;
            app.BLaux_onset_tf.UpperLimitInclusive      = app.EpochOffset_tf.Value;
            
            app.BLaux_offset_ms.LowerLimitInclusive     = app.EpochOnset_ms.Value;
            app.BLaux_offset_tf.LowerLimitInclusive     = app.EpochOnset_tf.Value;
            app.BLaux_offset_ms.UpperLimitInclusive     = app.EpochOffset_ms.Value;
            app.BLaux_offset_tf.UpperLimitInclusive     = app.EpochOffset_tf.Value;
            
            
            % SUB-EPOCHS
            if app.SubEpochs.Value
                app.BLscalp_onset_ms.LowerLimitInclusive    = 0;
                app.BLscalp_onset_tf.LowerLimitInclusive    = 0;
                if app.BLscalp_onset_tf.Value < 0
                    app.BLscalp_onset_tf.Value = 0;
                    app.BLscalp_onset_ms.Value = 0;
                end
                app.BLscalp_offset_ms.UpperLimitInclusive   = app.SubDuration_ms.Value;
                app.BLscalp_offset_tf.UpperLimitInclusive   = app.SubDuration_tf.Value - 1;
                if app.BLscalp_offset_tf.Value > app.SubDuration_tf.Value
                    app.BLscalp_offset_tf.Value = app.SubDuration_tf.Value - 1;
                    app.BLscalp_offset_ms.Value = app.SubDuration_ms.Value;
                end
                
                app.BLaux_onset_ms.LowerLimitInclusive  = 0;
                app.BLaux_onset_tf.LowerLimitInclusive  = 0;
                if app.BLaux_onset_tf.Value < 0
                    app.BLaux_onset_tf.Value = 0;
                    app.BLaux_onset_ms.Value = 0;
                end
                app.BLaux_offset_ms.UpperLimitInclusive = app.SubDuration_ms.Value;
                app.BLaux_offset_tf.UpperLimitInclusive = app.SubDuration_tf.Value - 1;
                if app.BLaux_offset_tf.Value > app.SubDuration_tf.Value
                    app.BLaux_offset_tf.Value   = app.SubDuration_tf.Value - 1;
                    app.BLaux_offset_ms.Value   = app.SubDuration_ms.Value;
                end
            end
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
        end
        
        function Channels = setChannels(app,Extension)
            if strcmp(Extension,'.sef')
                try
                    Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}],:));
                catch
                    Channels = '';
                end
            elseif strcmp(Extension,'.set')
                try
                    ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    Channels            = ElectrodeSetting(:,2);
                    %Channels.labels     = char(ElectrodeSetting.labels);

                    % Recompute Theta and Radius to match EEGlab orientation
                    [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                    Channels.theta      = -rad2deg(az);
                    Channels.radius     = 0.5 - rad2deg(elev)/180;
                    Channels.sph_theta  = -Channels.theta;
                    Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                    Channels.X          = ElectrodeSetting.y;
                    Channels.Y          = -ElectrodeSetting.x;
                    Channels.Z          = ElectrodeSetting.z;
                    Channels            = table2struct(Channels);
                catch
                    Channels = '';
                end
            else
                Channels = '';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main = EEGpalCS_Main;
            
            
            % Update UI with input values
            
            app.ParameterFilePath.Value     = app.EEGpalCS_Main.ParameterFilePath.Value;
            app.Study_Parameters            = app.EEGpalCS_Main.Study_Parameters;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            
            % Channels
            try
                app.SettingTable                = app.SessionParameters.Electrodes.SettingTable;
                app.SettingTable(~[app.SettingTable.include{:}]',:) = []; % remove unselected channels
                app.SettingTable.nr             = (1:height(app.SettingTable))';
                
                % Scalp cluster (default values)
                app.ScalpCluster.Items          = ['all';unique(app.SettingTable.cluster,'stable')]';
                app.ScalpCluster.Value          = app.ScalpCluster.Items(1);
                
                TestTable                           = app.SettingTable(:,[1 2 8]);
                TestTable.test                      = repmat({true},height(TestTable),1);
                app.AR_ScalpChanTestTable.Data      = TestTable;
                %app.ChanTestIndices                 = 1:height(app.AR_ScalpChanTestTable.Data);
            catch
                app.ScalpCluster.Enable             = 'off';
                app.AR_ScalpChanTestTable.Enable    = 'off';
                app.AR_AuxChanTestTable.Enable      = 'off';
            end
            
            
            if ~isempty(app.DataTable)
                % Check consistency between files
                if length(unique(app.DataTable.SamplingRate)) > 1
                    app.InconsistentSR.Visible      = 'on';
                    app.ResampleSwitch.Value        = 'On';
                    app.ResampleSwitch.Enable       = 'off';
                    app.SamplingRate.Enable         = 'on';
                end
                if length(unique(app.DataTable.nChan)) > 1
                    app.ScalpCluster.Enable                 = 'off';
                    app.InconsistentNumChan_scalp.Visible   = 'on';
                    app.InconsistentNumChan_aux.Visible     = 'on';
                    app.AR_ScalpChanTestTable.Enable        = 'off';
                    app.AR_AuxChanTestTable.Enable          = 'off';
                else
                    % Channel tables
                    app.nChan = app.DataTable.nChan(1);
                end
                
                % Sampling rate
                app.OrigSamplingRate.Value      = app.DataTable.SamplingRate(1);
                app.SamplingRate.Value          = app.OrigSamplingRate.Value;
                
                
%                 % Update according to the default values
%                 app.EpochOnset_ms.Value         = tf2ms(app,app.EpochOnset_tf.Value,app.OrigSamplingRate.Value);
%                 app.EpochOffset_ms.Value        = tf2ms(app,app.EpochOffset_tf.Value,app.OrigSamplingRate.Value);
%                 app.SubDuration_ms.Value        = tf2ms(app,app.SubDuration_tf.Value,app.OrigSamplingRate.Value);
%                 app.BLscalp_onset_ms.Value      = tf2ms(app,app.BLscalp_onset_tf.Value,app.OrigSamplingRate.Value);
%                 app.BLscalp_offset_ms.Value     = tf2ms(app,app.BLscalp_offset_tf.Value,app.OrigSamplingRate.Value);
%                 app.BLaux_onset_ms.Value        = tf2ms(app,app.BLaux_onset_tf.Value,app.OrigSamplingRate.Value);
%                 app.BLaux_offset_ms.Value       = tf2ms(app,app.BLaux_offset_tf.Value,app.OrigSamplingRate.Value);
                
            else % No sampling rate, so no 'ms' fields activated
                % No data, no sampling rate, no 'Run'!
                app.OrigSamplingRate.Enable     = 'off';
                app.OrigSamplingRate.Value      = 1000;
                app.SamplingRate.Value          = 1000;
                app.ResampleSwitch.Enable       = 'off';
                app.EpochOnset_ms.Enable        = 'off';
                app.EpochOffset_ms.Enable       = 'off';
                app.SubDuration_ms.Enable       = 'off';
                app.BLscalp_onset_ms.Enable     = 'off';
                app.BLscalp_offset_ms.Enable    = 'off';
                app.BLaux_onset_ms.Enable       = 'off';
                app.BLaux_offset_ms.Enable      = 'off';
                app.AR_ScalpPre_ms.Enable       = 'off';
                app.AR_ScalpPost_ms.Enable      = 'off';
                app.AR_AuxPre_ms.Enable         = 'off';
                app.AR_AuxPost_ms.Enable        = 'off';
            end
            
            try
                app.InterpolationTable          = app.SessionParameters.Interpolation.InterpolationTable(:,2:end);
                app.IgnoreBadSwitch.Enable      = 'on';
            catch
                app.IgnoreBadSwitch.Enable      = 'off';
                %app.IgnoreBadSwitch.Value       = 'Off';
            end
            
            
            % Saved study parameters
            try
               applyStudyParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.Study_Parameters.Files.SaveFolder;
                catch
                end
            end
            
            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
%                 try
%                     app.ReferenceSelection.Items    = [{'Average'};app.SettingTable.labels];
%                     
%                     app.ScalpCluster.Items       = [{'all'};unique(app.SettingTable.cluster,'stable')];
%                 catch
%                     app.ReferenceSelection.Items    = [{'Average'}; num2cell(1:64)'];
%                     app.ScalpCluster.Items       = 'all';
%                 end
%                 app.ReferenceSelection.Value        = 'Average';
%                 app.ScalpCluster.Value           = 'all';
            end
            
            % Events
            try % try, in case DataTable is empty (needs to be after applying study/session parameters)
                readEvents(app)
            catch
            end
            
            % Update parameters according to the default window size
            if app.EndOfFile.Value
                app.EpochOffset_tf.Enable   = 'off';
                app.EpochOffset_ms.Enable   = 'off';
                try
                    app.EpochOffset_tf.Value    = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                    app.EpochOffset_ms.Value    = tf2ms(app,app.EpochOffset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                catch % if no data selected
                    app.EpochOffset_tf.Value    = 0;
                    app.EpochOffset_ms.Value    = 0;
                end
                if strcmpi(app.BaselineScalpPeriod.Value,'Whole epoch')
                    app.BLscalp_offset_tf.Enable    = 'off';
                    app.BLscalp_offset_ms.Enable    = 'off';
                    % try
                    %     app.BLscalp_offset_tf.Value = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                    %     app.BLscalp_offset_ms.Value = tf2ms(app,app.BLscalp_offset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                    % catch % if no data selected
                    %     app.BLscalp_offset_tf.Value = 0;
                    %     app.BLscalp_offset_ms.Value = 0;
                    % end
                end
                if strcmpi(app.BaselineAuxPeriod.Value,'Whole epoch')
                    app.BLaux_offset_tf.Enable  = 'off';
                    app.BLaux_offset_ms.Enable  = 'off';
                    % try
                    %     app.BLaux_offset_tf.Value   = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                    %     app.BLaux_offset_ms.Value   = tf2ms(app,app.BLaux_offset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                    % catch % if no data selected
                    %     app.BLaux_offset_tf.Value   = 0;
                    %     app.BLaux_offset_ms.Value   = 0;
                    % end
                end
            end
            
            updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
            updateTotal(app,app.RejectOnset_ms,app.RejectOnset_tf,app.RejectOffset_ms,app.RejectOffset_tf,app.RejectTotal_ms,app.RejectTotal_tf)
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end
        end

        % Value changed function: EpochOnset_ms
        function EpochOnset_msValueChanged(app, event)
            app.EpochOnset_tf.Value     = ms2tf(app,app.EpochOnset_ms.Value,app.SamplingRate.Value);
            app.EpochOnset_ms.Value     = tf2ms(app,app.EpochOnset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
            
            app.nSubEpochs.Value        = floor(app.EpochTotal_tf.Value / app.SubDuration_tf.Value); % tf in case there is no sampling rate defined
            
            BLlimits(app)
        end

        % Value changed function: EpochOnset_tf
        function EpochOnset_tfValueChanged(app, event)
            app.EpochOnset_ms.Value     = tf2ms(app,app.EpochOnset_tf.Value,app.SamplingRate.Value);
            updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
            
            app.nSubEpochs.Value        = floor(app.EpochTotal_tf.Value / app.SubDuration_tf.Value); % tf in case there is no sampling rate defined
            
            BLlimits(app)
        end

        % Value changed function: EpochOffset_ms
        function EpochOffset_msValueChanged(app, event)
            app.EpochOffset_tf.Value    = ms2tf(app,app.EpochOffset_ms.Value,app.SamplingRate.Value);
            app.EpochOffset_ms.Value    = tf2ms(app,app.EpochOffset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
            
            app.nSubEpochs.Value        = floor(app.EpochTotal_tf.Value / app.SubDuration_tf.Value); % tf in case there is no sampling rate defined
            
            BLlimits(app)
        end

        % Value changed function: EpochOffset_tf
        function EpochOffset_tfValueChanged(app, event)
            app.EpochOffset_ms.Value    = tf2ms(app,app.EpochOffset_tf.Value,app.SamplingRate.Value);
            updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
            
            app.nSubEpochs.Value        = floor(app.EpochTotal_tf.Value / app.SubDuration_tf.Value); % tf in case there is no sampling rate defined
            
            BLlimits(app)
        end

        % Value changed function: SubEpochs
        function SubEpochsValueChanged(app, event)
            if app.SubEpochs.Value
                app.SubDuration_ms.Enable   = 'on';
                app.SubDuration_tf.Enable   = 'on';
                app.SubEpochsLabel.Enable   = 'on';
            else
                app.SubDuration_ms.Enable   = 'off';
                app.SubDuration_tf.Enable   = 'off';
                app.SubEpochsLabel.Enable   = 'off';
            end
            BLlimits(app)
        end

        % Value changed function: SubDuration_ms
        function SubDuration_msValueChanged(app, event)
            app.SubDuration_tf.Value    = ms2tf(app,app.SubDuration_ms.Value,app.SamplingRate.Value);
            app.nSubEpochs.Value        = floor(app.EpochTotal_tf.Value / app.SubDuration_tf.Value); % tf in case there is no sampling rate defined
        end

        % Value changed function: SubDuration_tf
        function SubDuration_tfValueChanged(app, event)
            app.SubDuration_ms.Value    = tf2ms(app,app.SubDuration_tf.Value,app.SamplingRate.Value);
            app.nSubEpochs.Value        = floor(app.EpochTotal_tf.Value / app.SubDuration_tf.Value); % tf in case there is no sampling rate defined
        end

        % Value changed function: BaselineScalpPeriod
        function BaselineScalpPeriodValueChanged(app, event)
            if strcmp(app.BaselineScalpPeriod.Value,'None')
                app.BLscalp_onset_ms.Value   = 0;
                app.BLscalp_onset_tf.Value   = 0;
                app.BLscalp_offset_ms.Value  = 0;
                app.BLscalp_offset_tf.Value  = 0;
                updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
                app.BLscalp_total_ms.Value   = 0;
                app.BLscalp_total_tf.Value   = 0;
            elseif strcmp(app.BaselineScalpPeriod.Value,'Whole epoch')
                if app.EndOfFile.Value
                    app.BLscalp_offset_tf.Enable    = 'off';
                    app.BLscalp_offset_ms.Enable    = 'off';
                else
                    app.BLscalp_offset_tf.Enable    = 'on';
                    app.BLscalp_offset_ms.Enable    = 'on';
                end
                app.BLscalp_onset_ms.Value   = app.EpochOnset_ms.Value;
                app.BLscalp_onset_tf.Value   = app.EpochOnset_tf.Value;
                app.BLscalp_offset_ms.Value  = app.EpochOffset_ms.Value;
                app.BLscalp_offset_tf.Value  = app.EpochOffset_tf.Value;
                updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            elseif strcmp(app.BaselineScalpPeriod.Value,'Pre-trigger period')
                app.BLscalp_onset_ms.Value   = app.EpochOnset_ms.Value;
                app.BLscalp_onset_tf.Value   = app.EpochOnset_tf.Value;
                app.BLscalp_offset_ms.Value  = 0;
                app.BLscalp_offset_tf.Value  = 0;
                updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            end
            
            if strcmp(app.BaselineAuxPeriod.Value,'Same as scalp')
                app.BLaux_onset_ms.Value   = app.BLscalp_onset_ms.Value;
                app.BLaux_onset_tf.Value   = app.BLscalp_onset_tf.Value;
                app.BLaux_offset_ms.Value  = app.BLscalp_offset_ms.Value;
                app.BLaux_offset_tf.Value  = app.BLscalp_offset_tf.Value;
                app.BLaux_total_ms.Value   = app.BLscalp_total_ms.Value;
                app.BLaux_total_tf.Value   = app.BLscalp_total_tf.Value;
                updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            end
            BLlimits(app)
        end

        % Value changed function: BLscalp_onset_ms
        function BLscalp_onset_msValueChanged(app, event)
            app.BLscalp_onset_tf.Value      = ms2tf(app,app.BLscalp_onset_ms.Value,app.SamplingRate.Value);
            app.BLscalp_onset_ms.Value      = tf2ms(app,app.BLscalp_onset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            app.BaselineScalpPeriod.Value   = 'Custom selection';
            
            if strcmp(app.BaselineAuxPeriod.Value,'Same as scalp')
                app.BLaux_onset_ms.Value    = app.BLscalp_onset_ms.Value;
                app.BLaux_onset_tf.Value    = app.BLscalp_onset_tf.Value;
                app.BLaux_total_ms.Value    = app.BLscalp_total_ms.Value;
                app.BLaux_total_tf.Value    = app.BLscalp_total_tf.Value;
            end
        end

        % Value changed function: BLscalp_onset_tf
        function BLscalp_onset_tfValueChanged(app, event)
            app.BLscalp_onset_ms.Value      = tf2ms(app,app.BLscalp_onset_tf.Value,app.SamplingRate.Value);
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            app.BaselineScalpPeriod.Value   = 'Custom selection';
            
            if strcmp(app.BaselineAuxPeriod.Value,'Same as scalp')
                app.BLaux_onset_ms.Value    = app.BLscalp_onset_ms.Value;
                app.BLaux_onset_tf.Value    = app.BLscalp_onset_tf.Value;
                app.BLaux_total_ms.Value    = app.BLscalp_total_ms.Value;
                app.BLaux_total_tf.Value    = app.BLscalp_total_tf.Value;
            end
        end

        % Value changed function: BLscalp_offset_ms
        function BLscalp_offset_msValueChanged(app, event)
            app.BLscalp_offset_tf.Value     = ms2tf(app,app.BLscalp_offset_ms.Value,app.SamplingRate.Value);
            app.BLscalp_offset_ms.Value     = tf2ms(app,app.BLscalp_offset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            app.BaselineScalpPeriod.Value   = 'Custom selection';
            
            if strcmp(app.BaselineAuxPeriod.Value,'Same as scalp')
                app.BLaux_offset_ms.Value   = app.BLscalp_offset_ms.Value;
                app.BLaux_offset_tf.Value   = app.BLscalp_offset_tf.Value;
                app.BLaux_total_ms.Value    = app.BLscalp_total_ms.Value;
                app.BLaux_total_tf.Value    = app.BLscalp_total_tf.Value;
            end
        end

        % Value changed function: BLscalp_offset_tf
        function BLscalp_offset_tfValueChanged(app, event)
            app.BLscalp_offset_ms.Value     = tf2ms(app,app.BLscalp_offset_tf.Value,app.SamplingRate.Value);
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            app.BaselineScalpPeriod.Value   = 'Custom selection';
            
            if strcmp(app.BaselineAuxPeriod.Value,'Same as scalp')
                app.BLaux_offset_ms.Value   = app.BLscalp_offset_ms.Value;
                app.BLaux_offset_tf.Value   = app.BLscalp_offset_tf.Value;
                app.BLaux_total_ms.Value    = app.BLscalp_total_ms.Value;
                app.BLaux_total_tf.Value    = app.BLscalp_total_tf.Value;
            end
        end

        % Value changed function: ScalpCluster
        function ScalpClusterValueChanged(app, event)
            try % If electrode setting defined
                TestTable                           = app.SettingTable(:,[1 2 8]);
                TestTable.test                      = repmat({true},height(TestTable),1);
            catch
                try % If no electrode setting defined, but data files selected
                    TestTable       = table('Size',[app.nChan 4],'VariableTypes',{'double','string','string','logical'},...
                        'VariableNames',{'nr','lables','cluster','test'});
                    TestTable.nr    = (1:app.nChan)';
                    TestTable.test  = true(app.nChan,1);
                catch
                    disp('No data and no electrode setting defined for me, so no ''Scalp channels to ignore'' table for you')
                    disp('That''s how it goes!')
                end
            end
            
            if strcmpi(app.ScalpCluster.Value,'all')
                try
                    app.AR_ScalpChanTestTable.Data      = TestTable;
                    %app.ChanTestIndices                 = 1:height(app.AR_ScalpChanTestTable.Data);
                catch
                end
                app.AR_AuxChanTestTable.Data  = [];
                
            else % Another value than 'all' is only possible if an electrode setting has been defined
                ScalpElectrodes                         = strcmpi(app.SettingTable.cluster,app.ScalpCluster.Value);
                app.AR_ScalpChanTestTable.Data          = TestTable(ScalpElectrodes,:);
                %app.ChanTestIndices                     = 1:height(app.AR_ScalpChanTestTable.Data);
                app.AR_AuxChanTestTable.Data            = TestTable(~ScalpElectrodes,:);
                app.AR_AuxChanTestTable.Data.test       = repmat({true},height(app.AR_AuxChanTestTable.Data),1);
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.Epoching); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [File,Path] = uigetfile('*.mat','Select parameter file','StudyParameters_EEGpalCS.mat');
            drawnow; figure(app.Epoching); % Restaure the app module
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            load(app.ParameterFilePath.Value);
            
            try
                applyStudyParam(app);
            catch
                msgbox(sprintf('%s','No parameters saved for this module.'),...
                    'No parameters','warn');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save parameter file
            if ~isempty (app.ParameterFilePath.Value)
                app.Study_Parameters = load(app.ParameterFilePath.Value);
                app.Study_Parameters = updateStudyParam(app,app.Study_Parameters.Parameters);
                Parameters = app.Study_Parameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            else
                [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
                drawnow; figure(app.Epoching); % Restaure the app module
                if isequal(File,0) || isequal(Path,0)
                   return; % User pressed cancel
                end
                app.ParameterFilePath.Value = fullfile(Path,File);
                app.SessionParameters = updateSessionParam(app,app.SessionParameters);
                Parameters = app.SessionParameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            Parameters = app.SessionParameters;
            
            % Save parameter file
            [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
            drawnow; figure(app.Epoching); % Restaure the app module
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            save(app.ParameterFilePath.Value,'Parameters');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if isempty(app.DataTable)
                msgbox(sprintf('%s\n\n%s','No data to analyse!', ...
                    'Please make sure to select the files to analyse in the main window.'),...
                    'No data selected','warn');
                return
            end
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            Run = questdlg(sprintf('%s\n%s \n\n%s\n%s%s\n%s%d%s \n\n%s\n%s%s\n%s%.1f%s%d%s\n%s%.1f%s%d%s\n%s%d\n%s%s \n\n%s\n%s%s\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d \n\n%s%d\n%s\n%s',...
                'Gruezi!',...
                'Here are the options you selected. Are you happy with that? I mean, really happy?',...
                ...
                'RESAMPLING',...
                'Resampling: ',app.ResampleSwitch.Value,...
                'Sampling rate: ',app.SamplingRate.Value,'Hz',...
                ...
                'EPOCHS',...
                'Triggers for epoching: ',app.EpochEvents.Value,...
                'Epoch onset: ',app.EpochOnset_ms.Value,'ms, ',app.EpochOnset_tf.Value,'tf',...
                'Epoch offset: ',app.EpochOffset_ms.Value,'ms, ',app.EpochOffset_tf.Value,'tf',...
                'Split into sub-epochs: ',app.SubEpochs.Value,...
                'Baseline period: ',app.BaselineScalpPeriod.Value,...
                ...
                'ARTEFACT REJECTION',...
                'Scalp electrodes cluster: ',app.ScalpCluster.Value,...
                'Scalp channels rejection based on threshold: ',app.AR_ScalpAbsoluteCheckBox.Value,...
                'Scalp channels rejection based on steps: ',app.AR_ScalpStepCheckBox.Value,...
                'Scalp channels rejection based on range: ',app.AR_ScalpMaxRangeCheckBox.Value,...
                'Auxiliary channels rejection based on threshold: ',app.AR_AuxAbsoluteCheckBox.Value,...
                'Auxiliary channels rejection based on steps: ',app.AR_AuxStepCheckBox.Value,...
                'Auxiliary channels rejection based on range: ',app.AR_AuxMaxRangeCheckBox.Value,...
                ...
                'Number of files: ',height(app.DataTable),...
                'A long list will take time to proceed. In order to reduce time, you can select subsets of files in the main window.',...
                'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
            
            if strcmpi(Run,'Cancel')
                return
            end
            
            
            %% PROCESSING INFORMATION SHEET

            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Epoching_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Epoching Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Study parameters file:',app.ParameterFilePath.Value);
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                fprintf(PifFileID,'%s\t\t\t\t%s\r\n','Scalp cluster:',app.ScalpCluster.Value);
                fprintf(PifFileID,'%s\r\n','Scalp channels used for epoching (Channel  /  Cluster  /  tested):');
                fprintf(PifFileID,'\t\t%s\t\t%s\t\t%s\r\n',string(app.AR_ScalpChanTestTable.Data{:,[2 3 4]}'));
                fprintf(PifFileID,'%s\r\n','Auxiliary channels used for epoching (Channel  /  Cluster  /  tested):');
                try
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',app.AR_AuxChanTestTable.Data{:,[2 3 4]}');
                catch
                    fprintf(PifFileID,'\t\t%s\r\n','No auxiliary channels tested');
                end
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
            catch
                if length(unique(app.DataTable.nChan)) > 1
                    fprintf(PifFileID,'%s\r\n','Inconsistent number of channels between files.');
                else
                    fprintf(PifFileID,'%s\r\n','No electrodes setting file. All channels tested.');
                end
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','RESAMPLING');
            if strcmpi(app.InconsistentSR.Visible,'on')
                fprintf(PifFileID,'%s\r\n','Sampling rate variable');
            end
            fprintf(PifFileID,'%s\t%s\r\n','Resampling:',app.ResampleSwitch.Value);
            if strcmpi(app.ResampleSwitch.Value,'on')
                fprintf(PifFileID,'%s\t%d\r\n','New sampling rate:',app.SamplingRate.Value);
            else
                fprintf(PifFileID,'%s\t%d\r\n','No resampling. Original sampling rate:',app.OrigSamplingRate.Value);
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','EPOCHING');
            fprintf(PifFileID,'%s\t%s\r\n','Triggers for epoching:',app.EpochEvents.Value);
            if isempty(app.EpochEvents.Value)
                fprintf(PifFileID,'%s\r\n','No triggers defined. Epoching applied from time-frame 0.');
                fprintf(PifFileID,'%s\r\n','Negative ''onset'' inputs (epoch and baseline) set to time-frame 0.');
                fprintf(PifFileID,'%s\r\n','The values below are the values entered, not the values corrected to time-frame 0.');
            end
            fprintf(PifFileID,'%s\r\n','Epochs, relative to triggers ([ms] / [tf]):');
            fprintf(PifFileID,'\t%s\t\t%.1f %s\t%d %s\r\n','Onset:',app.EpochOnset_ms.Value,'ms',app.EpochOnset_tf.Value,'tf');
            fprintf(PifFileID,'\t%s\t\t%.1f %s\t%d %s\r\n','Offset:',app.EpochOffset_ms.Value,'ms',app.EpochOffset_tf.Value,'tf');
            fprintf(PifFileID,'\t%s\t%.1f %s\t%d %s\r\n','Duration:',app.EpochTotal_ms.Value,'ms',app.EpochTotal_tf.Value,'tf');
            fprintf(PifFileID,'%s\r\n','Split epochs into sub-epochs:');
            if app.SubEpochs.Value
                fprintf(PifFileID,'\t%s\t%.1f %s\t%d %s\r\n','Sub-epochs duration:',app.SubDuration_ms.Value,'ms',app.SubDuration_tf.Value,'tf');
                fprintf(PifFileID,'%s\t%d\r\n','Number of sub-epochs:',app.nSubEpochs.Value);
                Discarded_ms = app.EpochTotal_ms.Value - (app.nSubEpochs.Value * app.SubDuration_ms.Value);
                Discarded_tf = app.EpochTotal_tf.Value - (app.nSubEpochs.Value * app.SubDuration_tf.Value);
                fprintf(PifFileID,'\t%s\t%.1f %s\t%d %s\r\n','Portion of signal discarded at the end of the epoch:',Discarded_ms,'ms',Discarded_tf,'tf');
            else
                fprintf(PifFileID,'\t%s\r\n','No splitting');
            end
            fprintf(PifFileID,'%s\r\n','Baseline correction:');
            if ~strcmp(app.BaselineScalpPeriod.Value,'None')
                fprintf(PifFileID,'\t%s\t%s\r\n','Scalp channels baseline period:',app.BaselineScalpPeriod.Value);
                fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Onset:',app.BLscalp_onset_ms.Value,'ms',app.BLscalp_onset_tf.Value,'tf');
                fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Offset:',app.BLscalp_offset_ms.Value,'ms',app.BLscalp_offset_tf.Value,'tf');
                fprintf(PifFileID,'\t\t%s\t%.1f %s\t%d %s\r\n','Duration:',app.BLscalp_total_ms.Value,'ms',app.BLscalp_total_tf.Value,'tf');
            else
                fprintf(PifFileID,'\t%s\r\n','No baseline correction of scalp channels');
            end
            if ~strcmp(app.BaselineAuxPeriod.Value,'None') && ~isempty(app.AR_AuxChanTestTable.Data)
                fprintf(PifFileID,'\t%s\t%s\r\n','Auxiliary channels baseline period:',app.BaselineAuxPeriod.Value);
                fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Onset:',app.BLaux_onset_ms.Value,'ms',app.BLaux_onset_tf.Value,'tf');
                fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Offset:',app.BLaux_offset_ms.Value,'ms',app.BLaux_offset_tf.Value,'tf');
                fprintf(PifFileID,'\t\t%s\t%.1f %s\t%d %s\r\n','Duration:',app.BLaux_total_ms.Value,'ms',app.BLaux_total_tf.Value,'tf');
            elseif ~isempty(app.AR_AuxChanTestTable.Data)
                fprintf(PifFileID,'\t%s\r\n','No baseline correction of auxiliary channels');
            else
                
            end
            fprintf(PifFileID,'%s\r\n','Saved epochs:');
            if app.SaveSeparately.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Individual epochs saved into separate files:','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Individual epochs saved into separate files:','No');
            end
            if app.SaveTogether.Value
                fprintf(PifFileID,'\t%s\t\t\t%s\r\n','Individual epochs saved into one file:','Yes');
                if app.SaveTogether_split.Value
                    fprintf(PifFileID,'\t\t%s\r\n','Individual epochs files split per event');
                end
            else
                fprintf(PifFileID,'\t%s\t\t\t%s\r\n','Individual epochs saved into one file:','No');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ARTEFACT REJECTION');
            fprintf(PifFileID,'%s\r\n','Scalp channels:');
            if app.AR_ScalpAbsoluteCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum absolute amplitude:',app.AR_ScalpAbsolute.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested against a maximum absolute amplitude');
            end
            if app.AR_ScalpStepCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum adjacent time-frames difference:',app.AR_ScalpStep.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested for amplitude steps');
            end
            if app.AR_ScalpMaxRangeCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum amplitude range:',app.AR_ScalpMaxRange.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested against a maximum range');
            end
            fprintf(PifFileID,'%s\r\n','Time windows for scalp channels testing:');
            fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Pre-event inclusion:',app.AR_ScalpPre_ms.Value,'ms',app.AR_ScalpPre_tf.Value,'tf');
            fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Post-event inclusion:',app.AR_ScalpPost_ms.Value,'ms',app.AR_ScalpPost_tf.Value,'tf');
            fprintf(PifFileID,'%s\r\n','Scalp channels ignored:');
            try
                ScalpIgnored = app.AR_ScalpChanTestTable.Data{~[app.AR_ScalpChanTestTable.Data.test{:}]',2};
                if isempty(ScalpIgnored)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels ignored');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',ScalpIgnored{:});
                end
            catch
                fprintf(PifFileID,'\t%s\r\n','No electrodes setting file.');
            end
            fprintf(PifFileID,'%s\r\n','Auxiliary channels:');
            if app.AR_AuxAbsoluteCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum absolute amplitude:',app.AR_AuxAbsolute.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested against a maximum absolute amplitude');
            end
            if app.AR_AuxStepCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum adjacent time-frames difference:',app.AR_AuxStep.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested for amplitude steps');
            end
            if app.AR_AuxMaxRangeCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum amplitude range:',app.AR_AuxMaxRange.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested against a maximum range');
            end
            fprintf(PifFileID,'%s\r\n','Time windows for auxiliary channels testing:');
            fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Pre-event inclusion:',app.AR_AuxPre_ms.Value,'ms',app.AR_AuxPre_tf.Value,'tf');
            fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Post-event inclusion:',app.AR_AuxPost_ms.Value,'ms',app.AR_AuxPost_tf.Value,'tf');
            fprintf(PifFileID,'%s\r\n','Auxiliary channels tested:');
            try
                AuxTested = app.AR_ScalpChanTestTable.Data{[app.AR_ScalpChanTestTable.Data.test{:}]',2};
                if isempty(AuxTested)
                    fprintf(PifFileID,'\t\t%s\r\n','No auxiliary channels tested');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',AuxTested{:});
                end
            catch
                fprintf(PifFileID,'\t%s\r\n','No auxiliary channels defined.');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','AVERAGING');
            fprintf(PifFileID,'%s\r\n','Within-subject averaging');
            if app.WithinAllTriggers.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','All triggers in one file:','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','All triggers in one file:','No');
            end
            if app.WithinPerTrigger.Value
                fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per trigger:','Yes');
            else
                fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per trigger:','No');
            end
%             if app.WithinPerCondition.Value
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per condition:','Yes');
%             else
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per condition:','No');
%             end
%             if app.WithinPerFactor.Value
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per factor:','Yes');
%             else
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per factor:','No');
%             end
%             fprintf(PifFileID,'%s\r\n','Between-subject averaging');
%             if app.WithinAllTriggers.Value
%                 fprintf(PifFileID,'\t%s\t%s\r\n','All triggers in one file:','Yes');
%             else
%                 fprintf(PifFileID,'\t%s\t%s\r\n','All triggers in one file:','No');
%             end
%             if app.WithinPerTrigger.Value
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per trigger:','Yes');
%             else
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per trigger:','No');
%             end
%             if app.WithinPerCondition.Value
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per condition:','Yes');
%             else
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per condition:','No');
%             end
%             if app.WithinPerFactor.Value
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per factor:','Yes');
%             else
%                 fprintf(PifFileID,'\t%s\t\t%s\r\n','One file per factor:','No');
%             end
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Subject  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';cellstr(app.DataTable.(7))';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN EPOCHING
            
            % Prepare values
            
%             ProcessedDataTable  = app.DataTable; % For updating the Main Window
            ChannelsCheck       = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            FileTriggers        = strings(height(app.DataTable),1); % For reporting triggers found in each file
            
            EpochOnsetTF        = app.EpochOnset_tf.Value;
            EpochOffsetTF       = app.EpochOffset_tf.Value;
            BL_ScalpOnsetTF     = app.BLscalp_onset_tf.Value - EpochOnsetTF; % Convert into all positive values
            BL_ScalpOffsetTF    = app.BLscalp_offset_tf.Value - EpochOnsetTF; % Convert into all positive values
            BL_AuxOnsetTF       = app.BLaux_onset_tf.Value - EpochOnsetTF; % Convert into all positive values
            BL_AuxOffsetTF      = app.BLaux_offset_tf.Value - EpochOnsetTF; % Convert into all positive values
            
            noTargetEventDefine=0;

            if app.SubEpochs.Value
                EpochSize           = app.SubDuration_tf.Value;
            else
                EpochSize           = app.EpochTotal_tf.Value;
            end
            ScalpTestSize       = EpochSize + abs(app.AR_ScalpPre_tf.Value) + abs(app.AR_ScalpPost_tf.Value);
            AuxTestSize         = EpochSize + abs(app.AR_AuxPre_tf.Value) + abs(app.AR_AuxPost_tf.Value);
            
            
            Triggers = strsplit(app.EpochEvents.Value,{' ',',',';'});
            if strcmp(Triggers,'*')
                Triggers = strsplit(app.FilesEvents.Value{:},', ');
            elseif isempty(Triggers{1})
                noTargetEventDefine=1;
                EpochOnsetTF = 0;
                %EpochSize = EpochOffsetTF;
                BL_ScalpOnsetTF = 1;
                BL_AuxOnsetTF = 1;
            end
            if app.EpochOnset_tf.Value < 0
                origin = [{abs(app.EpochOnset_tf.Value)+1} {abs(app.EpochOnset_tf.Value)+1} {'origin'}];
            else
                origin = [0 0 {['origin+' num2str(app.EpochOnset_tf.Value)]}]; % if the origin (i.e., the event) is not included in the epoch, set at TF 1
            end
            
            ScalpTestOnsetTF    = EpochOnsetTF - abs(app.AR_ScalpPre_tf.Value); % abs() so that either positive or negative values can be handled
            AuxTestOnsetTF      = EpochOnsetTF - abs(app.AR_AuxPre_tf.Value); % abs() so that either positive or negative values can be handled
            
            % Channels to test
            try
                ScalpIndices        = app.AR_ScalpChanTestTable.Data.nr;
                ScalpTestIndices    = app.AR_ScalpChanTestTable.Data.nr([app.AR_ScalpChanTestTable.Data.test{:}]);
                app.nChan           = sum([app.AR_ScalpChanTestTable.Data.test{:}]); % Redefine nChan in case not all channels are included
            catch % if not electrode setting, test all channels
                ScalpIndices        = 1:app.nChan;
                ScalpTestIndices    = ScalpIndices;
            end
            try
                AuxIndices          = app.AR_AuxChanTestTable.Data.nr;
                AuxTestIndices      = app.AR_AuxChanTestTable.Data.nr([app.AR_AuxChanTestTable.Data.test{:}]);
                nAux                = height(app.AR_AuxChanTestTable.Data);
            catch
                % Change aux test to no (or disable if no aux)
                disp('No auxiliary channels to test')
                nAux = 0;
            end
            
            
            % Preparing summary for the Excel file
            SummaryTable = table('Size',[height(app.DataTable)*length(Triggers) 15],'VariableTypes',...
                {'string','string','string','string','double','double','double','double','double','double','double','double','double','double','double'},...
                'VariableNames',{'File','Subject','Session','Trigger','TotalTriggers',...
                'nEEGrejected','nAUXrejected','nEEG_AUXrejected','nTOTALrejected','nTOTALaccepted',...
                'pcEEGrejected','pcAUXrejected','pcEEG_AUXrejected','pcTOTALrejected','pcTOTALaccepted'});
            summaryrow = 1;            
            
            disp('Run Epoching...')
            StartTime = datetime('now');
            tStart = tic;
            
            if ~isempty(char(unique(app.DataTable.(7))))
                SUBJ = unique(app.DataTable.(7));
            else % if no subjects identified, process each file separately
                num = arrayfun(@(x) sprintf('%02d',x),1:height(app.DataTable),'UniformOutput',false);
                SUBJ = strcat(repmat('file',height(app.DataTable),1),string(num'));
                app.DataTable.Subject = SUBJ;
            end
            
            
            % Structure for subject data
            num = arrayfun(@(x) sprintf('%02d',x),1:length(Triggers),'UniformOutput',false);
            TRIG = strcat(repmat('Trig',length(Triggers),1),string(num'));
            FieldValue = reshape([TRIG,repmat("",length(TRIG),1)]',[],1);
            Between = struct(FieldValue{:});
            
            % Preallocate fields
            for trig = 1:length(Triggers)
                Between.(TRIG(trig)).SubjEpochs = []; % for data of concateneted epochs
            end
            Between.EpochsAllEvts               = []; % for averaging all triggers together
            
            
            for subj = 1:length(SUBJ)
                disp(['Processing ', char(SUBJ(subj,1)), ' (subject ', num2str(subj), ' out of ', num2str(length(SUBJ)), ')'])
                SubjDataTable   = app.DataTable(strcmp(app.DataTable.Subject,SUBJ(subj,1)),:);
                if strcmpi(app.IgnoreBadSwitch.Value,'On')
                    SubjInterpTable = app.InterpolationTable(strcmp(app.DataTable.Subject,SUBJ(subj,1)),:);
                end
                SUMall          = zeros(EpochSize,app.nChan); % for averaging all triggers together / zeros(EpochSize,size(data,2))
                totEvents       = 0;
                
                
                % Structure for subject data
                SubjData = struct(FieldValue{:});
                
                % Preallocate fields
                for trig = 1:length(Triggers)
                    SubjData.(TRIG(trig)).Rejection     = strings([],3); % for reporting all markers of interest (TF, code) and commenting rejected ones (column 3)
                    SubjData.(TRIG(trig)).EPOCHSdata    = []; % for data of concateneted epochs
                    SubjData.(TRIG(trig)).SUMtrig       = zeros(EpochSize,app.nChan); % for averaging per trigger
                    %SubjData.(TRIG(trig)).epochMRKonset = {}; % for onsets of epochs in concatenated file
                    %SubjData.(TRIG(trig)).epochMRKoffset = {}; % for offsets of epochs in concatenated file
                    %SubjData.(TRIG(trig)).epochMRKname = {}; % for epochs in concatenated file
                    %SubjData.(TRIG(trig)).subjevt = 1; % counts markers of interest
                    SubjData.(TRIG(trig)).nEvt          = 0;
                    SubjData.(TRIG(trig)).nTOTrej       = 0;
                end
                %SubjData.EPOCHSdata                 = []; % for data of concateneted epochs
                SubjData.SUMall                     = zeros(EpochSize,app.nChan); % for averaging all triggers together
                SubjData.AVGall                     = zeros(EpochSize,app.nChan); % for averaging all triggers together
                SubjData.totEvents                  = 0;
                % SubjData.BadScalp                   = zeros(1,app.nChan); % For reporting percent of bad epochs, per channel
                % if app.AR_ScalpAbsoluteCheckBox.Value
                %     SubjData.ScalpAbsolute          = zeros(1,app.nChan); % For reporting percent of epochs above threshold, per channel
                % end
                % if app.AR_ScalpStepCheckBox.Value
                %     SubjData.ScalpStep              = zeros(1,app.nChan); % For reporting percent of epochs with bad step, per channel
                % end
                % if app.AR_ScalpMaxRangeCheckBox.Value
                %     SubjData.ScalpMaxRange          = zeros(1,app.nChan); % For reporting percent of epochs above range, per channel
                % end
                % SubjData.BadAux                     = zeros(1,nAux); % For reporting percent of bad epochs, per AUX channel
                % if app.AR_AuxAbsoluteCheckBox.Value
                %     SubjData.AuxAbsolute          = zeros(1,nAux); % For reporting percent of epochs above threshold, per AUX channel
                % end
                % if app.AR_AuxStepCheckBox.Value
                %     SubjData.AuxStep              = zeros(1,nAux); % For reporting percent of epochs with bad step, per AUX channel
                % end
                % if app.AR_AuxMaxRangeCheckBox.Value
                %     SubjData.AuxMaxRange          = zeros(1,nAux); % For reporting percent of epochs above range, per AUX channel
                % end
                
                h = waitbar(0,'Subject.....');
                nbFile= height(SubjDataTable);
                for file = 1:nbFile
                    waitbar(file/nbFile,h, strcat('Subject ',num2str(file),'/',num2str(nbFile)));
                    % Load file
                    disp(['Loading ', cell2mat(fullfile(SubjDataTable.Path(file),SubjDataTable.File(file)))])
                    disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                    openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(SubjDataTable.Path(file),'..'),SubjDataTable.File(file))); % full path and name of the file
                    [FilePath,FileName,FileExtension] = fileparts(openfilename);
                    
                    % BDF files
                    if strcmpi(FileExtension,'.bdf')
                        if app.InFileButton.Value
                            [header,data,eventsFile] = open_bdf(openfilename);
                            try
                                eventsFile = num2cell([eventsFile(:,1), eventsFile(:,1), eventsFile(:,2)]); % Convert to Cartool MRK format (onset, offset, code), but with origin at TF = 1
                            catch % if not events in file
                                eventsFile = {[] [] []};                                 
                            end
                        else
                            [header,data,~] = open_bdf(openfilename);
                            eventsFile = {[] [] []};                             
                        end
                        if app.mrkButton.Value
                            MRK_file = [openfilename '.mrk']; % name of MRK file
                            if isfile(MRK_file)
                                eventsMRK = open_mrk(MRK_file);
                                eventsMRK(:,1:2) =  cellfun(@(x) x+1,eventsMRK(:,1:2),'UniformOutput',false); % +1 because Cartool counts TF from 0
                            else
                                disp(['MRK file not found for ' openfilename])
                                eventsMRK = {[] [] []};                                
                            end
                        end
                        if app.InFileButton.Value & app.mrkButton.Value
                            events = [eventsFile; eventsMRK]; % Combine events from BDF and MRK files
                        elseif app.InFileButton.Value & ~app.mrkButton.Value
                            events = eventsFile;
                        elseif ~app.InFileButton.Value & app.mrkButton.Value
                            events = eventsMRK;
                        else
                            errordlg('No event has been detected','Error');
                            return                      
                        end
                       
                        events = events(~cellfun(@isempty, events(:,1)),:); % Remove empty rows (if either eventsFile or eventsMRK is empty)
                        events = sortrows(events,1); % Sort according to first column so that the duplicate events are from the file first
                        FileChannels = header.Channel.Labels;
                        
                    % EEGlab files
                    elseif strcmpi(FileExtension,'.set')
                        if app.InFileButton.Value
                            [header,data,events] = open_eeglab(openfilename);
                            try
                                eventsFile=num2cell(events);
                            catch % if not events in file
                                %eventsFile = {[] [] []};
                                app.InFileButton.Value=false;
                            end
                        else
                            [header,data,~] = open_eeglab(openfilename);
                            %eventsFile = {[] [] []};
                            app.InFileButton.Value=false;
                        end
                        if app.mrkButton.Value
                            MRK_file = [openfilename '.mrk']; % name of MRK file
                            if isfile(MRK_file)
                                eventsMRK = open_mrk(MRK_file);
                                eventsMRK(:,1:2) =  cellfun(@(x) x+1,eventsMRK(:,1:2),'UniformOutput',false); % +1 because Cartool counts TF from 0
                            else
                                disp(['MRK file not found for ' openfilename])
                                %eventsMRK = {[] [] []};
                                app.mrkButton.Value=false;
                            end
                        end
                        if app.InFileButton.Value & app.mrkButton.Value
                            events = sortrows([eventsFile; eventsMRK],1); % Sort according to first column so that the duplicate events are from the file first
                        elseif app.InFileButton.Value & ~app.mrkButton.Value
                            events = sortrows(eventsFile,1);
                        elseif ~app.InFileButton.Value & app.mrkButton.Value
                            events = sortrows(eventsMRK,1);
                        else
                            errordlg('No event has been detected','Error');
                            return
                        end
                        
                        FileChannels = header.Channels;
                        
                    % Cartool files
                    elseif strcmpi(FileExtension,'.sef')
                        [header,data,events] = open_sef(openfilename);
                        events=num2cell(events);
                        
                        if ~isempty(events)
                            events(:,1:2) =  cellfun(@(x) x+1,events(:,1:2),'UniformOutput',false); % +1 because Cartool counts TF from 0
                        else
                            events = [{1} {1} {'TF1'}];
                        end
                        FileChannels = header.Channels;
                    elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                        [header,data,events] = open_eph(openfilename);
                        events=num2cell(events);
                        
                        if ~isempty(events)
                            events(:,1:2) =  cellfun(@(x) x+1,events(:,1:2),'UniformOutput',false); % +1 because Cartool counts TF from 0
                        else
                            events = [{1} {1} {'TF1'}];
                        end
                    else
                        if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                            disp('These tools are not intended for .freq or .ris files.')
                        else
                            disp('File extension not recognized.')
                        end
                        disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                        disp(['Root folder: ' app.EEGpalCS_Main.RootFolder.Value{:}])
                        disp('File skipped.')
                        continue
                    end
                    disp('File loaded')
                    
                    % Correct events timing
                    if ~isempty(events) && app.EventsCorr_tf.Value > 0
                        events(:,1:2) = cellfun(@(x) {x+app.EventsCorr_tf.Value},events(:,1:2));
                        disp('Timing of events corrected.')
                        
                        % Remove events if onset is negative
                        neg = ([events{:,1}]<0)';
                        % TO DO: CREATE ARRAY WITH NUMBER OF NEGATIVE EVENTS REMOVED FOR PIF FILE
                        if sum(neg) > 0
                            events(neg,:) = [];
                            disp(['Removed ' num2str(sum(neg)) ' that became negative after correction'])
                        end
                    end
                    
                    
                    % No events defined (apply from time-frame 1)
                    %if isempty(Triggers{1}) %bug because work only for the first file and not the others. Correction apply the 17.12.2024
                    if noTargetEventDefine==1   
                        events = [{1},{1},{'TF1'}]; % Origin at time-fames 1
                        Triggers = "TF1";
                    end


                    % Remove unselected channels from the EEGpalCS Electrode Setting window
                    try
                        data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]',:);
                        %FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    
                        % Channels check (if electrode setting defined)
                        try
                            Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                            if isequal(char(FileChannels),char(Channels))
                                ChannelsCheck(file,1)   = "OK";
                            else
                                ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                            end
                        catch
                            ChannelsCheck(file,1)   = "No channels in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No electrode setting defined";
                    end
                    Channels = setChannels(app,app.FilesExtension.Value); % Set channel names for saving
                    
                    
                    % Remove channels to interpolate
                    if strcmpi(app.IgnoreBadSwitch.Value,'On')
                        toIgnore = find(table2array(SubjInterpTable(file,:))==true);
                        ScalpTestIndices(max(ScalpTestIndices == toIgnore,[],2)) = [];
                    end
                    
                    
                    % Re-sampling
                    if strcmpi(app.ResampleSwitch.Value,'On')
                        if app.DataTable.SamplingRate(file) == app.SamplingRate.Value
                            disp(['New sampling rate (', num2str(app.SamplingRate.Value), ') same as original.'])
                            disp('Resampling not computed.')
                        else
                            disp(['Resampling from ', num2str(app.DataTable.SamplingRate(file)),'Hz to ',num2str(app.SamplingRate.Value),'Hz'])
                            data = resample(data,app.SamplingRate.Value,app.DataTable.SamplingRate(file));
                            
                            % Adapt events time-frames to new sampling rate
                            if ~isempty(events)
                                events(:,1:2) = round(events(:,1:2) * (app.SamplingRate.Value ./ app.DataTable.SamplingRate(file)),0);
                            end
                            disp('Resampling completed')
                        end
                    end
                    
                    % Prepare to process
                    if app.EndOfFile.Value
                        EpochOffsetTF    = SubjDataTable.(5)(file) - 1; % -1 because event is at TF 0
%                         if strcmpi(app.BaselineScalpPeriod.Value,'Whole epoch')
%                             BL_ScalpOffsetTF = app.DataTable.(5)(file);
%                         end
%                         if strcmpi(app.BaselineAuxPeriod.Value,'Whole epoch')
%                             BL_AuxOffsetTF = app.DataTable.(5)(file);
%                         end
                    end

                    if app.SubEpochs.Value
                        nSub = floor(((EpochOffsetTF - EpochOnsetTF) + 1) / app.SubDuration_tf.Value); % tf in case there is no sampling rate defined
                    else
                        nSub = 1;
                    end
                    
                    ScalpTestOffsetTF   = EpochOffsetTF + abs(app.AR_ScalpPost_tf.Value); % abs() shouldn't be necessary here, but better be sure...
                    AuxTestOffsetTF     = EpochOffsetTF + abs(app.AR_AuxPost_tf.Value); % abs() shouldn't be necessary here, but better be sure...
                    
                    
                    % EPOCHS
                    
%                     reverseStr = ''; % For progression text
                    
                    % Process triggers
                    disp(['Subject ''', char(SUBJ(subj,1)), ''': Processing file ', num2str(file), ' out of ', num2str(height(SubjDataTable))])
                    
                    if ~isempty(events{1,3}) % if the first event is empty, all events should be
                        FileTriggers(file,1) = strjoin(unique(string(events(:,3)))',', ');
                        
                        for trig = 1:length(Triggers)
                            nEvt = sum(strcmp(string(events(:,3)),Triggers(1,trig))) * nSub; % Total occurences of this trigger multiplied by the number of sub-epochs
                            Rejection       = strings(nEvt,3); % for reporting all markers of interest (TF, code) and commenting rejected ones (column 3)
                            EPOCHSdata      = []; % for data of concateneted epochs
                            SUMtrig         = zeros(EpochSize,size(data,2)); % for averaging per trigger
                            AcceptedEvents  = {};
                            evt             = 0; % counts events of interest
                            AllSubEvents    = {};
                            
                            for mrk = 1:size(events,1)
                                if strcmpi(string(events(mrk,3)),Triggers(1,trig))
                                    try % the trigger could be too close to the limites of the data for the epoch
                                        EPOCH           = data((events{mrk,1})+EpochOnsetTF:(events{mrk,2})+EpochOffsetTF,:); % the offset of the epoch is calculated based on the trigger offset
                                        
                                        % Testing / Sub-epochs
                                        SCALPtest       = data((events{mrk,1})+ScalpTestOnsetTF:(events{mrk,2})+ScalpTestOffsetTF,ScalpTestIndices); % the offset of the epoch is calculated based on the trigger offset
                                        if ~isempty(app.AR_AuxChanTestTable.Data)
                                            AUXtest         = data((events{mrk,1})+AuxTestOnsetTF:(events{mrk,2})+AuxTestOffsetTF,AuxTestIndices); % the offset of the epoch is calculated based on the trigger offset
                                        end
                                        
                                        for sub = 1:nSub
                                            evt = evt + 1;
                                            
                                            % Sub marker
                                            if app.SubEpochs.Value
                                                AllSubEvents = [AllSubEvents; [{(events{mrk,1})+((sub-1)*EpochSize)}, {(events{mrk,1})+((sub-1)*EpochSize)}, {strcat(string(events(mrk,3)),"_sub")}]];
                                            else
                                                AllSubEvents = [AllSubEvents; [{(events{mrk,1})+((sub-1)*EpochSize)}, {(events{mrk,1})+((sub-1)*EpochSize)}, {string(events(mrk,3))}]];
                                            end
                                            Rejection(evt,1)  = string(AllSubEvents(evt,1));
                                            Rejection(evt,2)  = string(AllSubEvents(evt,3));
                                            
                                            
                                            % SCALP
                                            
                                            SCALPtestSub    = SCALPtest(((sub-1)*EpochSize)+1:((sub-1)*EpochSize)+ScalpTestSize,:);
                                            
                                            %%%%%%%%%%%%%%%%%%%%%%%%
                                            %%% PROBLEM HERE !!! %%% -> Corrected (check if ok)
                                            %%%%%%%%%%%%%%%%%%%%%%%%
                                            
                                            % Baseline correction
                                            if ~strcmp(app.BaselineScalpPeriod.Value,'None')
                                                %BL_ScalpChan    = data((events{mrk,1})+BL_ScalpOnsetTF:(events{mrk,2})+BL_ScalpOffsetTF,ScalpTestIndices); % the offset of the epoch is calculated based on the trigger offset
                                                BL_ScalpChan    = SCALPtestSub(1+abs(app.AR_ScalpPre_tf.Value)+BL_ScalpOnsetTF:1+app.AR_ScalpPre_tf.Value+BL_ScalpOffsetTF,:);
                                                SCALPtestSub    = SCALPtestSub - mean(BL_ScalpChan,1);
                                            end
                                            
                                            % EEG artefact rejection
                                                    % For channel check: max(abs(ScalpSub(:,ScalpTestIndices)),[],1)
                                            if app.AR_ScalpAbsoluteCheckBox.Value && ...
                                                    max(max(abs(SCALPtestSub))) > app.AR_ScalpAbsolute.Value % test if at least one channel has one TF amplitude above the threshold
                                                Rejection(evt,3) = strcat(Rejection(evt,3),'EEG bad amplitude',{'    '});
                                                %SubjData.ScalpAbsolute = SubjData.ScalpAbsolute + (max(abs(SCALPtestSub)) > app.AR_ScalpAbsolute.Value);
                                            end
                                            if app.AR_ScalpStepCheckBox.Value && ...
                                                    max(max(abs(SCALPtestSub(2:end,:)-SCALPtestSub(1:end-1,:)))) > app.AR_ScalpStep.Value % test if at least one channel has a step above the threshold
                                                Rejection(evt,3) = strcat(Rejection(evt,3),'EEG bad step',{'    '});
                                                %SubjData.ScalpStep = SubjData.ScalpStep + (max(abs(SCALPtestSub(2:end,:)-SCALPtestSub(1:end-1,:))) > app.AR_ScalpStep.Value);
                                            end
                                            if app.AR_ScalpMaxRangeCheckBox.Value && ...
                                                    max(max(SCALPtestSub) - min(SCALPtestSub)) > app.AR_ScalpMaxRange.Value % test if at least one channel shows amplitude range above the threshold
                                                Rejection(evt,3) = strcat(Rejection(evt,3),'EEG bad range',{'    '});
                                                %SubjData.ScalpRange = SubjData.ScalpRange + (max(SCALPtestSub) - min(SCALPtestSub) > app.AR_ScalpMaxRange.Value);
                                            end
                                            
                                            
                                            % AUX
                                            
                                            if ~isempty(app.AR_AuxChanTestTable.Data)
                                                AUXtestSub      = AUXtest(((sub-1)*EpochSize)+1:((sub-1)*EpochSize)+AuxTestSize,:);
                                                
                                                % Baseline correction
                                                if ~isempty(app.AR_AuxChanTestTable.Data)
                                                    if ~strcmp(app.BaselineAuxPeriod.Value,'None')
                                                        %BL_AuxChan = data((events{mrk,1})+BL_AuxOnsetTF:(events{mrk,2})+BL_AuxOffsetTF,AuxIndices); % the offset of the epoch is calculated based on the trigger offset
                                                        BL_AuxChan  = AUXtestSub(1+app.AR_AuxPre_tf.Value+BL_AuxOnsetTF:1+app.AR_AuxpPre_tf.Value+BL_AuxOffsetTF,:);
                                                        AUXtestSub  = AUXtestSub - mean(BL_AuxChan,1);
                                                    end
                                                end
                                            end
                                            
                                            % AUX artefact rejection
                                            if app.AR_AuxAbsoluteCheckBox.Value && ...
                                                    max(max(abs(AUXtestSub))) > app.AR_AuxAbsolute.Value % test if at least one TF on one electrode is above the threshold
                                                Rejection(evt,3) = strcat(Rejection(evt,3),'AUX bad amplitude',{'    '});
                                                %SubjData.AuxAbsolute = SubjData.AuxAbsolute + (max(abs(AUXtestSub)) > app.AR_AuxAbsolute.Value);
                                            end
                                            if app.AR_AuxStepCheckBox.Value && ...
                                                    max(max(abs(AUXtestSub(2:end,:)-AUXtestSub(1:end-1,:)))) > app.AR_ScalpStep.Value % test if at least one TF on one electrode is above the threshold
                                                Rejection(evt,3) = strcat(Rejection(evt,3),'AUX bad step',{'    '});
                                                %SubjData.AuxStep = SubjData.AuxStep + (max(abs(AUXtestSub(2:end,:)-AUXtestSub(1:end-1,:))) > app.AR_ScalpStep.Value);
                                            end
                                            if app.AR_AuxMaxRangeCheckBox.Value && ...
                                                    max(max(AUXtestSub) - min(AUXtestSub)) > app.AR_AuxMaxRange.Value % test if at least one channel shows amplitude range above the threshold
                                                Rejection(evt,3) = strcat(Rejection(evt,3),'AUX bad range',{'    '});
                                                %SubjData.AuxRange = SubjData.AuxRange + (max(AUXtestSub) - min(AUXtestSub) > app.AR_AuxMaxRange.Value);
                                            end
                                            
                                            
                                            % GOOD EPOCH
                                            
                                            if strcmp(Rejection(evt,3),"")
                                                EPOCHsub        = EPOCH(((sub-1)*EpochSize)+1:((sub-1)*EpochSize)+EpochSize,:);
                                                
                                                % Baseline correction
                                                if ~strcmp(app.BaselineScalpPeriod.Value,'None')
                                                    BL_ScalpChan                = EPOCHsub(1+BL_ScalpOnsetTF:1+BL_ScalpOffsetTF,ScalpIndices);
                                                    EPOCHsub(:,ScalpIndices)    = EPOCHsub(:,ScalpIndices) - mean(BL_ScalpChan,1);
                                                end
                                                if ~isempty(app.AR_AuxChanTestTable.Data)
                                                    if ~strcmp(app.BaselineAuxPeriod.Value,'None')
                                                        BL_AuxChan              = EPOCHsub(1+BL_AuxOnsetTF:1+BL_AuxOffsetTF,AuxIndices);
                                                        EPOCHsub(:,AuxIndices)  = EPOCHsub(:,AuxIndices) - mean(BL_AuxChan,1);
                                                    end
                                                end
                                                
                                                EPOCHSdata      = [EPOCHSdata;EPOCHsub];
                                                SUMtrig         = SUMtrig + EPOCHsub;
                                                AcceptedEvents  = [AcceptedEvents; [{length(EPOCHSdata)-(EpochSize)}, {length(EPOCHSdata)-1}, {string(events(mrk,3))}]];
    %                                                 epochMRKonset = [epochMRKonset;length(EPOCHSdata)-(EpochSize)];
    %                                                 epochMRKoffset = [epochMRKoffset;length(EPOCHSdata)-1]; % -1 because Cartool starts counting TF at 0
    %                                                 epochMRKname = [epochMRKname;events(mrk,3)];
                                            end
                                        end
                                    catch
                                        %Rejection(evt+1,:)  = [string(events(mrk,1)) string(events(mrk,3)) 'Event too close to the limites of the data, could not extract the epoch'];
                                        Rejection(evt+1,1)  = string(events(mrk,1));
                                        Rejection(evt+1,2)  = string(events(mrk,3));
                                        Rejection(evt+1,3) = 'Event too close to the limites of the data, could not extract the epoch';
                                        % NOTE N TIMES IT HAPPENS...
                                    end
                                end
                            end
                            
                            % compute % rejection
                            nEEGAUXrej      = sum(min(contains(Rejection(:,3),'EEG'),contains(Rejection(:,3),'AUX'))); % total lines containing EEG and AUX
                            nEEGrej         = sum(contains(Rejection(:,3),'EEG')) - nEEGAUXrej; % total lines containing only EEG
                            nAUXrej         = sum(contains(Rejection(:,3),'AUX')) - nEEGAUXrej; % total lines containing only AUX
                            nTOTrej         = nEEGAUXrej + nEEGrej + nAUXrej;
                            
                            EEGAUXpercent   = (nEEGAUXrej / nEvt) * 100;
                            EEGpercent      = (nEEGrej / nEvt) * 100;
                            AUXpercent      = (nAUXrej / nEvt) * 100;
                            TOTpercent      = (nTOTrej / nEvt) * 100;
                            
%                             AVGtrig         = SUMtrig / (nEvt - nTOTrej);
                            
                            
                            %Summary
                            
                            SummaryTable.File(summaryrow)               = openfilename;
                            SummaryTable.Subject(summaryrow)            = SUBJ(subj,1);
%                             SummaryTable.Session(summaryrow) = SESS(sess,1);
                            SummaryTable.Trigger(summaryrow)            = Triggers(1,trig);
                            SummaryTable.TotalTriggers(summaryrow)      = nEvt;
                            SummaryTable.nAUXrejected(summaryrow)       = nAUXrej;
                            SummaryTable.nEEGrejected(summaryrow)       = nEEGrej;
                            SummaryTable.nEEG_AUXrejected(summaryrow)   = nEEGAUXrej;
                            SummaryTable.nTOTALrejected(summaryrow)     = nTOTrej;
                            SummaryTable.nTOTALaccepted(summaryrow)     = nEvt - nTOTrej;
                            SummaryTable.pcAUXrejected(summaryrow)      = AUXpercent;
                            SummaryTable.pcEEGrejected(summaryrow)      = EEGpercent;
                            SummaryTable.pcEEG_AUXrejected(summaryrow)  = EEGAUXpercent;
                            SummaryTable.pcTOTALrejected(summaryrow)    = TOTpercent;
                            SummaryTable.pcTOTALaccepted(summaryrow)    = 100 - TOTpercent;
                            summaryrow = summaryrow + 1;
                            
                            
                            % Save data
                            
                            if ~isempty(app.SuffixString.Value)
                                suffix = ['_', app.SuffixString.Value, '_Trigger_', cell2mat(Triggers(trig))];
                            else
                                suffix = ['_Trigger_', cell2mat(Triggers(trig))];
                            end
                            
                            SubPath = SubjDataTable.Path{file}(3:end);
                            NewPath = fullfile(app.SaveFolder.Value{:},'Epochs',SubPath);
                            % Creating the folder
                            if ~exist(NewPath, 'dir')
                                mkdir(NewPath);
                            end
                            
                            % "verbose"
                            csvfilename = fullfile(NewPath,[FileName, suffix, '.csv']);
                            REJfid = fopen(csvfilename,'w');
                            fprintf(REJfid,'%s\t%s\n','Trigger: ',cell2mat(Triggers(1,trig)));
                            fprintf(REJfid,'%s\t%d\n','Number of events: ',nEvt);
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','TOTAL: ',nEvt - nTOTrej,'epochs accepted (',100 - TOTpercent,'%)');
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','EEG: ',nEEGrej,'epochs rejected (',EEGpercent,'%)');
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','AUX: ',nAUXrej,'epochs rejected (',AUXpercent,'%)');
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','EEG+AUX: ',nEEGAUXrej,'epochs rejected (',EEGAUXpercent,'%)');
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','TOTAL: ',nTOTrej,'epochs rejected (',TOTpercent,'%)');
                            fprintf(REJfid,'\n\n');
                            fprintf(REJfid,'%s\n','Epochs (onset  / event  /  comment');
                            fprintf(REJfid,'%s\t%s\t%s\n',Rejection');
                            fclose(REJfid);
                            
                            % Epochs
                            if (app.SaveTogether.Value || app.SaveSeparately.Value) && ~isempty(AcceptedEvents)
                                disp('Saving epochs')
                                if app.SaveTogether.Value
                                    savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension.Value]);
                                    %disp(['Saving all epoch for trigger ',cell2mat(Triggers(trig)),' into ',savefilename])
                                    num = arrayfun(@(x) sprintf('%02d',x),1:size(AcceptedEvents,1),'UniformOutput',false);
                                    epoch = strcat(repmat('Epoch',size(AcceptedEvents,1),1),string(num'));
                                    saveEvents = [[AcceptedEvents(:,1),AcceptedEvents(:,2),epoch];...
                                        [num2cell([AcceptedEvents{:,1}]'+origin{1}),num2cell([AcceptedEvents{:,1}]'+origin{1}),AcceptedEvents(:,3)]];
                                    if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                                        save_eph(savefilename,EPOCHSdata,header.SamplingRate,saveEvents,[])
                                    elseif strcmp(app.FilesExtension.Value,'.sef')
                                        save_sef(savefilename,EPOCHSdata,header.SamplingRate,Channels,saveEvents,[],nAux)
                                    elseif strcmp(app.FilesExtension.Value,'.set')
                                        save_eeglab(savefilename,EPOCHSdata,header.SamplingRate,saveEvents,[],Channels)
                                    end
                                end
                                if app.SaveSeparately.Value
                                    %disp(['Saving epochs separately for trigger ',cell2mat(Triggers(trig))])
                                    SavePath = fullfile(NewPath,'IndividualEpochs');
                                    % Creating the folder
                                    if ~exist(SavePath, 'dir')
                                        mkdir(SavePath);
                                    end
                                    for epoch = 1:size(AcceptedEvents,1)
                                        savefilename = fullfile(SavePath,[FileName, [suffix,'_epoch',sprintf('%03d',epoch)], app.FilesExtension.Value]);
                                        Epoch = EPOCHSdata(AcceptedEvents{epoch,1}+1:AcceptedEvents{epoch,2}+1,:);
                                        saveEvents = [[AcceptedEvents(1,1),AcceptedEvents(1,2),string(['Epoch',sprintf('%03d',epoch)])];...
                                            [origin(1),origin(2),AcceptedEvents(epoch,3)]];
                                        if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                                            save_eph(savefilename,Epoch,header.SamplingRate,saveEvents,[])
                                        elseif strcmp(app.FilesExtension.Value,'.sef')
                                            save_sef(savefilename,Epoch,header.SamplingRate,Channels,saveEvents,[],nAux)
                                        elseif strcmp(app.FilesExtension.Value,'.set')
                                            save_eeglab(savefilename,Epoch,header.SamplingRate,saveEvents,[],Channels)
                                        end
                                    end
                                end
                            end 
    %                         if app.WithinPerFile
    %                             AVGtrig
    %                             % OPTION TO ADD?
    %                         end
                            
                            
                            % Update subject data
                            SubjData.(TRIG(trig)).Rejection     = [SubjData.(TRIG(trig)).Rejection;Rejection];
                            SubjData.(TRIG(trig)).EPOCHSdata    = [SubjData.(TRIG(trig)).EPOCHSdata;EPOCHSdata];
                            SubjData.(TRIG(trig)).SUMtrig       = SubjData.(TRIG(trig)).SUMtrig + SUMtrig;
                            SubjData.(TRIG(trig)).nEvt          = SubjData.(TRIG(trig)).nEvt + nEvt;
                            SubjData.(TRIG(trig)).nTOTrej       = SubjData.(TRIG(trig)).nTOTrej + nTOTrej;
                            
    %                         % Progression
    %                         nSteps      = length(Triggers) * size(events,1) * nSub;
    %                         Step        = trig * mrk * sub;
    %                         reverseStr  = txtprogressbar(app,nSteps,Step,reverseStr);
                            
                            SUMall      = SUMall + SUMtrig;
                            totEvents   = totEvents + (nEvt - nTOTrej);
                            
                            SubjData.SUMall      = SubjData.SUMall + SUMtrig;
                            SubjData.totEvents   = SubjData.totEvents + (nEvt - nTOTrej);
                        end
                        
                    else
                        FileTriggers(file,1) = 'No triggers in this file'; % /!\ NOT USED YET !!!
                        
                        %Summary
                            
                        SummaryTable.File(summaryrow)               = openfilename;
                        SummaryTable.Subject(summaryrow)            = SUBJ(subj,1);
    %                     SummaryTable.Session(summaryrow) = SESS(sess,1);
                        SummaryTable.Trigger(summaryrow)            = Triggers(1,trig);
                        SummaryTable.TotalTriggers(summaryrow)      = 0;
                        SummaryTable.nAUXrejected(summaryrow)       = 0;
                        SummaryTable.nEEGrejected(summaryrow)       = 0;
                        SummaryTable.nEEG_AUXrejected(summaryrow)   = 0;
                        SummaryTable.nTOTALrejected(summaryrow)     = 0;
                        SummaryTable.nTOTALaccepted(summaryrow)     = 0;
                        SummaryTable.pcAUXrejected(summaryrow)      = 0;
                        SummaryTable.pcEEGrejected(summaryrow)      = 0;
                        SummaryTable.pcEEG_AUXrejected(summaryrow)  = 0;
                        SummaryTable.pcTOTALrejected(summaryrow)    = 0;
                        SummaryTable.pcTOTALaccepted(summaryrow)    = 0;
                        summaryrow = summaryrow + 1;
                    end
                    
                    % Create an .mrk file with events marking the sub-epochs
                    if app.SubEpochs.Value && nSub > 1
                        %AllEvts = sortrows([string(events);string(AllSubEvents)]);
                        AllEvts = [events;AllSubEvents];
                        AllEvts(:,1:2) =  cellfun(@(x) x-1,AllEvts(:,1:2),'UniformOutput',false); % -1 because Cartool counts TF from 0
                        [~,I] = sortrows(cell2mat(AllEvts(:,1)));
                        AllEvts = string(AllEvts(I,:));
                        %AllEvts = [string(events);string(AllSubEvents)];
                        MRKfilename = fullfile(FilePath,[FileName,'_SubEvents',FileExtension,'.mrk']);
                        MRKfid = fopen(MRKfilename,'w');
                        fprintf(MRKfid,'%s\r\n','TL02'); % use \r\n for notepad (not notepad++)
                        fprintf(MRKfid,'%s\t%s\t%s\r\n',AllEvts'); % use \r\n for notepad (not notepad++)
                        fclose(MRKfid);
                    end
                end
                delete(h);
                
                
                % Compute within-subject averages
                for trig = 1:length(Triggers)
                    if (app.WithinPerTrigger.Value || app.BetweenPerTrigger.Value) && SubjData.(TRIG(trig)).nEvt > 0
                        SubjData.(TRIG(trig)).AVGtrig         = SubjData.(TRIG(trig)).SUMtrig / (SubjData.(TRIG(trig)).nEvt - SubjData.(TRIG(trig)).nTOTrej);
                    end
                    if (app.WithinAllTriggers.Value || app.BetweenAllTriggers.Value) && SubjData.totEvents > 0
                        SubjData.AVGall          = SubjData.SUMall / SubjData.totEvents;
                    end
                    
                    
                    % Save within-subject data
                    if app.WithinPerTrigger.Value && SubjData.(TRIG(trig)).nEvt > 0
                        
                        SubjPath = fullfile(app.SaveFolder.Value{:},'Avg_within',char(SUBJ(subj)));
                        % Creating the folder
                        if ~exist(SubjPath, 'dir')
                            mkdir(SubjPath);
                        end
                        
                        disp(['Saving trigger ''', char(TRIG(trig)), ''' average for subject ',char(SUBJ(subj))])
                        if ~isempty(app.SuffixString.Value)
                            suffix = ['_', app.SuffixString.Value, '_Trigger_', cell2mat(Triggers(trig))];
                        else
                            suffix = ['_Trigger_', cell2mat(Triggers(trig))];
                        end
                        savefilename    = fullfile(SubjPath,[char(SUBJ(subj)), suffix, app.FilesExtension.Value]);
                        %AVGtrig         = SubjData.(TRIG(trig)).SUMtrig / (SubjData.(TRIG(trig)).nEvt - SubjData.(TRIG(trig)).nTOTrej);
                        if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                            save_eph(savefilename,SubjData.(TRIG(trig)).AVGtrig,header.SamplingRate,origin,[])
                        elseif strcmp(app.FilesExtension.Value,'.sef')
                            save_sef(savefilename,SubjData.(TRIG(trig)).AVGtrig,header.SamplingRate,Channels,origin,[],nAux)
                        elseif strcmp(app.FilesExtension.Value,'.set')
                            save_eeglab(savefilename,SubjData.(TRIG(trig)).AVGtrig,header.SamplingRate,origin,[],Channels)
                        end
                    end
                    
                    if app.WithinAllTriggers.Value && SubjData.totEvents > 0
                        
                        SubjPath = fullfile(app.SaveFolder.Value{:},'Avg_within',char(SUBJ(subj)));
                        % Creating the folder
                        if ~exist(SubjPath, 'dir')
                            mkdir(SubjPath);
                        end

                        if ~isempty(app.SuffixString.Value)
                            suffix = ['_', app.SuffixString.Value, '_AllTriggers'];
                        else
                            suffix = ['_', char(SUBJ(subj)), '_AllTriggers'];
                        end
                        savefilename    = fullfile(SubjPath,[char(SUBJ(subj)), suffix, app.FilesExtension.Value]);
                        %AVGall          = SubjData.SUMall / SubjData.totEvents;
                        disp(['Saving all triggers average for subject ',char(SUBJ(subj))])
                        if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                            save_eph(savefilename,SubjData.AVGall,header.SamplingRate,origin,[])
                        elseif strcmp(app.FilesExtension.Value,'.sef')
                            save_sef(savefilename,SubjData.AVGall,header.SamplingRate,Channels,origin,[],nAux)
                        elseif strcmp(app.FilesExtension.Value,'.set')
                            save_eeglab(savefilename,SubjData.AVGall,header.SamplingRate,origin,[],Channels)
                        end
                    end
                    
                    
                    % Update between-subject data
                    if app.BetweenPerTrigger.Value && isfield(SubjData.(TRIG(trig)),'AVGtrig')
                        Between.(TRIG(trig)).SubjEpochs = cat(3,Between.(TRIG(trig)).SubjEpochs,SubjData.(TRIG(trig)).AVGtrig); % for averaging per trigger
                    end
                    if app.BetweenAllTriggers.Value
                        Between.EpochsAllEvts           = cat(3,Between.EpochsAllEvts,SubjData.AVGall); % for averaging all triggers together
                    end
                end
            end
            
            
            % SAVE BETWEEN-SUBJECT DATA
            
            if app.BetweenPerTrigger.Value && isfield(Between.(TRIG(trig)),'SubjEpochs')
                
                BetweenPath = fullfile(app.SaveFolder.Value{:},'Avg_between');
                % Creating the folder
                if ~exist(BetweenPath, 'dir')
                    mkdir(BetweenPath);
                end
                
                disp('Saving between-subject grand averages for triggers separately')
                for trig = 1:length(Triggers)
                    if ~isempty(app.SuffixString.Value)
                        suffix = ['GrdAvg_', app.SuffixString.Value, '_Trigger_', cell2mat(Triggers(trig))];
                    else
                        suffix = ['GrdAvg_Trigger_', cell2mat(Triggers(trig))];
                    end
                    savefilename    = fullfile(BetweenPath,[suffix, app.FilesExtension.Value]);
                    AVGtrig         = mean(Between.(TRIG(trig)).SubjEpochs,3);
                    if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                        save_eph(savefilename,AVGtrig,header.SamplingRate,origin,[])
                    elseif strcmp(app.FilesExtension.Value,'.sef')
                        save_sef(savefilename,AVGtrig,header.SamplingRate,Channels,origin,[],nAux)
                    elseif strcmp(app.FilesExtension.Value,'.set')
                        save_eeglab(savefilename,AVGtrig,header.SamplingRate,origin,[],Channels)
                    end
                end
            end
            
            if app.BetweenAllTriggers.Value && isfield(Between,'EpochsAllEvts')
                
                BetweenPath = fullfile(app.SaveFolder.Value{:},'Avg_between');
                % Creating the folder
                if ~exist(BetweenPath, 'dir')
                    mkdir(BetweenPath);
                end
                    
                if ~isempty(app.SuffixString.Value)
                    suffix = ['GrdAvg_', app.SuffixString.Value, '_AllTriggers'];
                else
                    suffix = 'GrdAvg_AllTriggers';
                end
                savefilename    = fullfile(BetweenPath,[suffix, app.FilesExtension.Value]);
                AVGall          = mean(Between.EpochsAllEvts,3);
                disp(['Saving all triggers average for subject ',char(SUBJ(subj))])
                if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                    save_eph(savefilename,AVGall,header.SamplingRate,origin,[])
                elseif strcmp(app.FilesExtension.Value,'.sef')
                    save_sef(savefilename,AVGall,header.SamplingRate,Channels,origin,[],nAux)
                elseif strcmp(app.FilesExtension.Value,'.set')
                    save_eeglab(savefilename,AVGall,header.SamplingRate,origin,[],Channels)
                end
            end


            % UPDATE INFORMATION BASED ON THE PROCESSED DATA
            
            % Data table in the Main Window
%             app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
%             app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
%             app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
%             disp('Data table in the Main Window updated with the processed files')
%             cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(data,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            writetable(SummaryTable,[app.SaveFolder.Value{:} '\Epoching_Summary.xlsx'],'Sheet','Summary'); % Grand Summary!
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
%             fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
%             % fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Triggers found  /  CHANCHECK  /  Folder)');
%             Files = [app.DataTable.File';num2cell(app.DataTable.(3))';num2cell(app.DataTable.(4))';app.DataTable.Path'];
%             fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','SUMMARY (Subject  /  Trigger  /  N. Triggers in file  /  Accepted epochs (n/%)  /  Rejected epochs (n/%)  /  File');
            Slash = repmat("/",1,height(SummaryTable));
            Percent = repmat("%",1,height(SummaryTable));
            Summary = [SummaryTable.Subject';SummaryTable.Trigger';num2cell(SummaryTable.TotalTriggers)';...
                num2cell(SummaryTable.nTOTALaccepted)';Slash;num2cell(SummaryTable.pcTOTALaccepted)';Percent;...
                num2cell(SummaryTable.nTOTALrejected)';Slash;num2cell(SummaryTable.pcTOTALrejected)';Percent;SummaryTable.File'];
            Summary = fillmissing(Summary,'constant',"0");
            fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s %s %s%s\t\t%s %s %s%s\t\t%s\r\n',Summary{:});
            
            
%                         SummaryTable.nAUXrejected(summaryrow)       = nAUXrej;
%                         SummaryTable.nEEGrejected(summaryrow)       = nEEGrej;
%                         SummaryTable.nEEG_AUXrejected(summaryrow)   = nEEGAUXrej;
%                         SummaryTable.nTOTALrejected(summaryrow)     = nTOTrej;
%                         SummaryTable.pcAUXrejected(summaryrow)      = AUXpercent;
%                         SummaryTable.pcEEGrejected(summaryrow)      = EEGpercent;
%                         SummaryTable.pcEEG_AUXrejected(summaryrow)  = EEGAUXpercent;
%                         SummaryTable.pcTOTALrejected(summaryrow)    = TOTpercent;
                        
                        
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Epoching completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            rz = msgbox('Epoching done.');
        end

        % Button pushed function: RetainButton
        function RetainButtonPushed(app, event)
            % Enable the Filtering+ button in main app
            app.EEGpalCS_Main.EpochingButton.Enable = 'on';
            
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            % Update parameters
            app.EEGpalCS_Main.Study_Parameters  = app.Study_Parameters;
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.EpochingButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: Epoching
        function EpochingCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.EpochingButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: BLaux_onset_ms
        function BLaux_onset_msValueChanged(app, event)
            app.BLaux_onset_tf.Value    = ms2tf(app,app.BLaux_onset_ms.Value,app.SamplingRate.Value);
            app.BLaux_onset_ms.Value    = tf2ms(app,app.BLaux_onset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            app.BaselineAuxPeriod.Value = 'Custom selection';
        end

        % Value changed function: BLaux_onset_tf
        function BLaux_onset_tfValueChanged(app, event)
            app.BLaux_onset_ms.Value    = tf2ms(app,app.BLaux_onset_tf.Value,app.SamplingRate.Value);
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            app.BaselineAuxPeriod.Value = 'Custom selection';
        end

        % Value changed function: BLaux_offset_ms
        function BLaux_offset_msValueChanged(app, event)
            app.BLaux_offset_tf.Value   = ms2tf(app,app.BLaux_offset_ms.Value,app.SamplingRate.Value);
            app.BLaux_offset_ms.Value   = tf2ms(app,app.BLaux_offset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            app.BaselineAuxPeriod.Value = 'Custom selection';
        end

        % Value changed function: BLaux_offset_tf
        function BLaux_offset_tfValueChanged(app, event)
            app.BLaux_offset_ms.Value   = tf2ms(app,app.BLaux_offset_tf.Value,app.SamplingRate.Value);
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            app.BaselineAuxPeriod.Value = 'Custom selection';
        end

        % Value changed function: AR_ScalpPre_ms
        function AR_ScalpPre_msValueChanged(app, event)
            app.AR_ScalpPre_tf.Value    = ms2tf(app,app.AR_ScalpPre_ms.Value,app.SamplingRate.Value);
            app.AR_ScalpPre_ms.Value    = tf2ms(app,app.AR_ScalpPre_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
        end

        % Value changed function: AR_ScalpPre_tf
        function AR_ScalpPre_tfValueChanged(app, event)
            app.AR_ScalpPre_ms.Value    = tf2ms(app,app.AR_ScalpPre_tf.Value,app.SamplingRate.Value);
        end

        % Value changed function: AR_ScalpPost_ms
        function AR_ScalpPost_msValueChanged(app, event)
            app.AR_ScalpPost_tf.Value   = ms2tf(app,app.AR_ScalpPost_ms.Value,app.SamplingRate.Value);
            app.AR_ScalpPost_ms.Value   = tf2ms(app,app.AR_ScalpPost_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
        end

        % Value changed function: AR_ScalpPost_tf
        function AR_ScalpPost_tfValueChanged(app, event)
            app.AR_ScalpPost_ms.Value   = tf2ms(app,app.AR_ScalpPost_tf.Value,app.SamplingRate.Value);
        end

        % Value changed function: AR_AuxPre_ms
        function AR_AuxPre_msValueChanged(app, event)
            app.AR_AuxPre_tf.Value      = ms2tf(app,app.AR_AuxPre_ms.Value,app.SamplingRate.Value);
            app.AR_AuxPre_ms.Value      = tf2ms(app,app.AR_AuxPre_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
        end

        % Value changed function: AR_AuxPre_tf
        function AR_AuxPre_tfValueChanged(app, event)
            app.AR_AuxPre_ms.Value      = tf2ms(app,app.AR_AuxPre_tf.Value,app.SamplingRate.Value);
        end

        % Value changed function: AR_AuxPost_ms
        function AR_AuxPost_msValueChanged(app, event)
            app.AR_AuxPost_tf.Value     = ms2tf(app,app.AR_AuxPost_ms.Value,app.SamplingRate.Value);
            app.AR_AuxPost_ms.Value     = tf2ms(app,app.AR_AuxPost_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
        end

        % Value changed function: AR_AuxPost_tf
        function AR_AuxPost_tfValueChanged(app, event)
            app.AR_AuxPost_ms.Value     = tf2ms(app,app.AR_AuxPost_tf.Value,app.SamplingRate.Value);
        end

        % Value changed function: BaselineAuxPeriod
        function BaselineAuxPeriodValueChanged(app, event)
            if strcmp(app.BaselineAuxPeriod.Value,'None')
                app.BLaux_onset_ms.Value   = 0;
                app.BLaux_onset_tf.Value   = 0;
                app.BLaux_offset_ms.Value  = 0;
                app.BLaux_offset_tf.Value  = 0;
                updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
                app.BLaux_total_ms.Value   = 0;
                app.BLaux_total_tf.Value   = 0;
            elseif strcmp(app.BaselineAuxPeriod.Value,'Same as scalp')
                if strcmpi(app.BLscalp_offset_tf.Enable,'off') % Means that EoF is selected
                    app.BLaux_offset_tf.Enable      = 'off';
                    app.BLaux_offset_ms.Enable      = 'off';
                else
                    app.BLaux_offset_tf.Enable      = 'on';
                    app.BLaux_offset_ms.Enable      = 'on';
                end
                app.BLaux_onset_ms.Value   = app.BLscalp_onset_ms.Value;
                app.BLaux_onset_tf.Value   = app.BLscalp_onset_tf.Value;
                app.BLaux_offset_ms.Value  = app.BLscalp_offset_ms.Value;
                app.BLaux_offset_tf.Value  = app.BLscalp_offset_tf.Value;
                app.BLaux_total_ms.Value   = app.BLscalp_total_ms.Value;
                app.BLaux_total_tf.Value   = app.BLscalp_total_tf.Value;
                updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            elseif strcmp(app.BaselineAuxPeriod.Value,'Whole epoch')
                if app.EndOfFile.Value
                    app.BLaux_offset_tf.Enable      = 'off';
                    app.BLaux_offset_ms.Enable      = 'off';
                else
                    app.BLaux_offset_tf.Enable      = 'on';
                    app.BLaux_offset_ms.Enable      = 'on';
                end
                app.BLaux_onset_ms.Value   = app.EpochOnset_ms.Value;
                app.BLaux_onset_tf.Value   = app.EpochOnset_tf.Value;
                app.BLaux_offset_ms.Value  = app.EpochOffset_ms.Value;
                app.BLaux_offset_tf.Value  = app.EpochOffset_tf.Value;
                updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            elseif strcmp(app.BaselineAuxPeriod.Value,'Pre-trigger period')
                app.BLaux_onset_ms.Value   = app.EpochOnset_ms.Value;
                app.BLaux_onset_tf.Value   = app.EpochOnset_tf.Value;
                app.BLaux_offset_ms.Value  = 0;
                app.BLaux_offset_tf.Value  = 0;
                updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            end
            BLlimits(app)
        end

        % Value changed function: InFileButton
        function InFileButtonValueChanged(app, event)
            readEvents(app)
        end

        % Value changed function: mrkButton
        function mrkButtonValueChanged(app, event)
            readEvents(app)
        end

        % Value changed function: ResampleSwitch
        function ResampleSwitchValueChanged(app, event)
            if strcmpi(app.ResampleSwitch.Value,'On')
                app.SamplingRate.Enable = 'On';
            else
                app.SamplingRate.Enable = 'Off';
                app.SamplingRate.Value  = app.OrigSamplingRate.Value;
                
                app.EpochOnset_tf.Value     = ms2tf(app,app.EpochOnset_ms.Value,app.SamplingRate.Value);
                app.EpochOnset_ms.Value     = tf2ms(app,app.EpochOnset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
                app.EpochOffset_tf.Value    = ms2tf(app,app.EpochOffset_ms.Value,app.SamplingRate.Value);
                app.EpochOffset_ms.Value    = tf2ms(app,app.EpochOffset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
                updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
                app.nSubEpochs.Value        = floor(app.EpochTotal_tf.Value / app.SubDuration_tf.Value);
                
                app.BLscalp_onset_tf.Value      = ms2tf(app,app.BLscalp_onset_ms.Value,app.SamplingRate.Value);
                app.BLscalp_onset_ms.Value      = tf2ms(app,app.BLscalp_onset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
                app.BLscalp_offset_tf.Value     = ms2tf(app,app.BLscalp_offset_ms.Value,app.SamplingRate.Value);
                app.BLscalp_offset_ms.Value     = tf2ms(app,app.BLscalp_offset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
                updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
                
                app.BLaux_onset_tf.Value    = ms2tf(app,app.BLaux_onset_ms.Value,app.SamplingRate.Value);
                app.BLaux_onset_ms.Value    = tf2ms(app,app.BLaux_onset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
                app.BLaux_offset_tf.Value   = ms2tf(app,app.BLaux_offset_ms.Value,app.SamplingRate.Value);
                app.BLaux_offset_ms.Value   = tf2ms(app,app.BLaux_offset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
                updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
            end
        end

        % Value changed function: SamplingRate
        function SamplingRateValueChanged(app, event)
            app.EpochOnset_tf.Value     = ms2tf(app,app.EpochOnset_ms.Value,app.SamplingRate.Value);
            app.EpochOnset_ms.Value     = tf2ms(app,app.EpochOnset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            app.EpochOffset_tf.Value    = ms2tf(app,app.EpochOffset_ms.Value,app.SamplingRate.Value);
            app.EpochOffset_ms.Value    = tf2ms(app,app.EpochOffset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
            app.nSubEpochs.Value        = floor(app.EpochTotal_tf.Value / app.SubDuration_tf.Value);
            
            app.BLscalp_onset_tf.Value      = ms2tf(app,app.BLscalp_onset_ms.Value,app.SamplingRate.Value);
            app.BLscalp_onset_ms.Value      = tf2ms(app,app.BLscalp_onset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            app.BLscalp_offset_tf.Value     = ms2tf(app,app.BLscalp_offset_ms.Value,app.SamplingRate.Value);
            app.BLscalp_offset_ms.Value     = tf2ms(app,app.BLscalp_offset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            
            app.BLaux_onset_tf.Value    = ms2tf(app,app.BLaux_onset_ms.Value,app.SamplingRate.Value);
            app.BLaux_onset_ms.Value    = tf2ms(app,app.BLaux_onset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            app.BLaux_offset_tf.Value   = ms2tf(app,app.BLaux_offset_ms.Value,app.SamplingRate.Value);
            app.BLaux_offset_ms.Value   = tf2ms(app,app.BLaux_offset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
        end

        % Value changed function: EndOfFile
        function EndOfFileValueChanged(app, event)
            if app.EndOfFile.Value
                app.EpochOffset_tf.Enable   = 'off';
                app.EpochOffset_ms.Enable   = 'off';
                try
                    app.EpochOffset_tf.Value    = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                    app.EpochOffset_ms.Value    = tf2ms(app,app.EpochOffset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                catch % if no data selected
                    app.EpochOffset_tf.Value    = 0;
                    app.EpochOffset_ms.Value    = 0;
                end
                if strcmpi(app.BaselineScalpPeriod.Value,'Whole epoch')
                    app.BLscalp_offset_tf.Enable    = 'off';
                    app.BLscalp_offset_ms.Enable    = 'off';
                    try
                        app.BLscalp_offset_tf.Value = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                        app.BLscalp_offset_ms.Value = tf2ms(app,app.BLscalp_offset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                    catch % if no data selected
                        app.BLscalp_offset_tf.Value = 0;
                        app.BLscalp_offset_ms.Value = 0;
                    end
                end
                if strcmpi(app.BaselineAuxPeriod.Value,'Whole epoch')
                    app.BLaux_offset_tf.Enable  = 'off';
                    app.BLaux_offset_ms.Enable  = 'off';
                    try
                        app.BLaux_offset_tf.Value   = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                        app.BLaux_offset_ms.Value   = tf2ms(app,app.BLaux_offset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                    catch % if no data selected
                        app.BLaux_offset_tf.Value   = 0;
                        app.BLaux_offset_ms.Value   = 0;
                    end
                end
            else
                app.EpochOffset_tf.Enable       = 'on';
                app.EpochOffset_ms.Enable       = 'on';
                app.BLscalp_offset_tf.Enable    = 'on';
                app.BLscalp_offset_ms.Enable    = 'on';
                app.BLaux_offset_tf.Enable      = 'on';
                app.BLaux_offset_ms.Enable      = 'on';
            end
            updateTotal(app,app.EpochOnset_ms,app.EpochOnset_tf,app.EpochOffset_ms,app.EpochOffset_tf,app.EpochTotal_ms,app.EpochTotal_tf)
            updateTotal(app,app.BLscalp_onset_ms,app.BLscalp_onset_tf,app.BLscalp_offset_ms,app.BLscalp_offset_tf,app.BLscalp_total_ms,app.BLscalp_total_tf)
            updateTotal(app,app.BLaux_onset_ms,app.BLaux_onset_tf,app.BLaux_offset_ms,app.BLaux_offset_tf,app.BLaux_total_ms,app.BLaux_total_tf)
        end

        % Button pushed function: ARevaluationButton
        function ARevaluationButtonPushed(app, event)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %% NOTE: A lot of this script is copy-pasted from the 'Run'  %% 
            %% script. Maybe using functions could make the whole thing  %%
            %% look cleaner. Then again...                               %%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            
            
            %% CHECK
            
            if isempty(app.DataTable)
                msgbox(sprintf('%s\n\n%s','No data to analyse!', ...
                    'Please make sure to select the files to analyse in the main window.'),...
                    'No data selected','warn');
                return
            end
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            Run = questdlg(sprintf('%s\n%s \n\n%s\n%s\n%s \n\n%s\n%s%s\n%s%.1f%s%d%s\n%s%.1f%s%d%s\n%s%d\n%s%s \n\n%s\n%s%s\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d \n\n%s%d\n%s\n%s',...
                'Welcom to the Artefact Rejection evaluation experience!',...
                'Here are the options you selected. Are you happy with that? I mean, really happy?',...
                ...
                'RESAMPLING',...
                'No resampling',...
                'For simplicity, artefact rejection testing is computed on the input data',...
                ...
                'EPOCHS',...
                'Triggers for epoching: ',app.EpochEvents.Value,...
                'Epoch onset: ',app.EpochOnset_ms.Value,'ms, ',app.EpochOnset_tf.Value,'tf',...
                'Epoch offset: ',app.EpochOffset_ms.Value,'ms, ',app.EpochOffset_tf.Value,'tf',...
                'Split into sub-epochs: ',app.SubEpochs.Value,...
                'Baseline period: ',app.BaselineScalpPeriod.Value,...
                ...
                'ARTEFACT REJECTION',...
                'Scalp electrodes cluster: ',app.ScalpCluster.Value,...
                'Scalp channels rejection based on threshold: ',app.AR_ScalpAbsoluteCheckBox.Value,...
                'Scalp channels rejection based on steps: ',app.AR_ScalpStepCheckBox.Value,...
                'Scalp channels rejection based on range: ',app.AR_ScalpMaxRangeCheckBox.Value,...
                'Auxiliary channels rejection based on threshold: ',app.AR_AuxAbsoluteCheckBox.Value,...
                'Auxiliary channels rejection based on steps: ',app.AR_AuxStepCheckBox.Value,...
                'Auxiliary channels rejection based on range: ',app.AR_AuxMaxRangeCheckBox.Value,...
                ...
                'Number of files: ',height(app.DataTable),...
                'A long list will take time to proceed. In order to reduce time, you can select subsets of files in the main window.',...
                'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
            
            if strcmpi(Run,'Cancel')
                return
            end
            
            
            %% PROCESSING INFORMATION SHEET

            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_ARevaluation_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpalCS Artefact Rejection Evaluation Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Study parameters file:',app.ParameterFilePath.Value);
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                fprintf(PifFileID,'%s\t\t\t\t%s\r\n','Scalp cluster:',app.ScalpCluster.Value);
                fprintf(PifFileID,'%s\r\n','Scalp channels used for epoching (Channel  /  Cluster  /  tested):');
                fprintf(PifFileID,'\t\t%s\t\t%s\t\t%s\r\n',string(app.AR_ScalpChanTestTable.Data{:,[2 3 4]}'));
                fprintf(PifFileID,'%s\r\n','Auxiliary channels used for epoching (Channel  /  Cluster  /  tested):');
                try
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',app.AR_AuxChanTestTable.Data{:,[2 3 4]}');
                catch
                    fprintf(PifFileID,'\t\t%s\r\n','No auxiliary channels tested');
                end
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
            catch
                if length(unique(app.DataTable.nChan)) > 1
                    fprintf(PifFileID,'%s\r\n','Inconsistent number of channels between files.');
                else
                    fprintf(PifFileID,'%s\r\n','No electrodes setting file. All channels tested.');
                end
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','RESAMPLING');
            fprintf(PifFileID,'%s\r\n','No resampling.');
            fprintf(PifFileID,'%s\r\n','For simplicity, artefact rejection testing is computed on the input data');
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','EPOCHING');
            fprintf(PifFileID,'%s\t%s\r\n','Triggers for epoching:',app.EpochEvents.Value);
            if isempty(app.EpochEvents.Value)
                fprintf(PifFileID,'%s\r\n','No triggers defined. Epoching applied from time-frame 0.');
                fprintf(PifFileID,'%s\r\n','Negative ''onset'' inputs (epoch and baseline) set to time-frame 0.');
                fprintf(PifFileID,'%s\r\n','The values below are the values entered, not the values corrected to time-frame 0.');
            end
            fprintf(PifFileID,'%s\r\n','Epochs, relative to triggers ([ms] / [tf]):');
            fprintf(PifFileID,'\t%s\t\t%.1f %s\t%d %s\r\n','Onset:',app.EpochOnset_ms.Value,'ms',app.EpochOnset_tf.Value,'tf');
            fprintf(PifFileID,'\t%s\t\t%.1f %s\t%d %s\r\n','Offset:',app.EpochOffset_ms.Value,'ms',app.EpochOffset_tf.Value,'tf');
            fprintf(PifFileID,'\t%s\t%.1f %s\t%d %s\r\n','Duration:',app.EpochTotal_ms.Value,'ms',app.EpochTotal_tf.Value,'tf');
            fprintf(PifFileID,'%s\r\n','Split epochs into sub-epochs:');
            if app.SubEpochs.Value
                fprintf(PifFileID,'\t%s\t%.1f %s\t%d %s\r\n','Sub-epochs duration:',app.SubDuration_ms.Value,'ms',app.SubDuration_tf.Value,'tf');
                fprintf(PifFileID,'%s\t%d\r\n','Number of sub-epochs:',app.nSubEpochs.Value);
                Discarded_ms = app.EpochTotal_ms.Value - (app.nSubEpochs.Value * app.SubDuration_ms.Value);
                Discarded_tf = app.EpochTotal_tf.Value - (app.nSubEpochs.Value * app.SubDuration_tf.Value);
                fprintf(PifFileID,'\t%s\t%.1f %s\t%d %s\r\n','Portion of signal discarded at the end of the epoch:',Discarded_ms,'ms',Discarded_tf,'tf');
            else
                fprintf(PifFileID,'\t%s\r\n','No splitting');
            end
            fprintf(PifFileID,'%s\r\n','Baseline correction:');
            if ~strcmp(app.BaselineScalpPeriod.Value,'None')
                fprintf(PifFileID,'\t%s\t%s\r\n','Scalp channels baseline period:',app.BaselineScalpPeriod.Value);
                fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Onset:',app.BLscalp_onset_ms.Value,'ms',app.BLscalp_onset_tf.Value,'tf');
                fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Offset:',app.BLscalp_offset_ms.Value,'ms',app.BLscalp_offset_tf.Value,'tf');
                fprintf(PifFileID,'\t\t%s\t%.1f %s\t%d %s\r\n','Duration:',app.BLscalp_total_ms.Value,'ms',app.BLscalp_total_tf.Value,'tf');
            else
                fprintf(PifFileID,'\t%s\r\n','No baseline correction of scalp channels');
            end
            if ~strcmp(app.BaselineAuxPeriod.Value,'None') && ~isempty(app.AR_AuxChanTestTable.Data)
                fprintf(PifFileID,'\t%s\t%s\r\n','Auxiliary channels baseline period:',app.BaselineAuxPeriod.Value);
                fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Onset:',app.BLaux_onset_ms.Value,'ms',app.BLaux_onset_tf.Value,'tf');
                fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Offset:',app.BLaux_offset_ms.Value,'ms',app.BLaux_offset_tf.Value,'tf');
                fprintf(PifFileID,'\t\t%s\t%.1f %s\t%d %s\r\n','Duration:',app.BLaux_total_ms.Value,'ms',app.BLaux_total_tf.Value,'tf');
            elseif ~isempty(app.AR_AuxChanTestTable.Data)
                fprintf(PifFileID,'\t%s\r\n','No baseline correction of auxiliary channels');
            else
                
            end
            fprintf(PifFileID,'%s\r\n','Saved epochs:');
            fprintf(PifFileID,'\t%s\r\n','No epochs saved for artefact rejection testing.');
            fprintf(PifFileID,'\t%s\r\n','Artefact rejection testing creates new .mrk file showing good and bad epochs.');
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ARTEFACT REJECTION');
            fprintf(PifFileID,'%s\r\n','Scalp channels:');
            if app.AR_ScalpAbsoluteCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum absolute amplitude:',app.AR_ScalpAbsolute.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested against a maximum absolute amplitude');
            end
            if app.AR_ScalpStepCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum adjacent time-frames difference:',app.AR_ScalpStep.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested for amplitude steps');
            end
            if app.AR_ScalpMaxRangeCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum amplitude range:',app.AR_ScalpMaxRange.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested against a maximum range');
            end
            fprintf(PifFileID,'%s\r\n','Time windows for scalp channels testing:');
            fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Pre-event inclusion:',app.AR_ScalpPre_ms.Value,'ms',app.AR_ScalpPre_tf.Value,'tf');
            fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Post-event inclusion:',app.AR_ScalpPost_ms.Value,'ms',app.AR_ScalpPost_tf.Value,'tf');
            fprintf(PifFileID,'%s\r\n','Scalp channels ignored:');
            try
                ScalpIgnored = app.AR_ScalpChanTestTable.Data{~[app.AR_ScalpChanTestTable.Data.test{:}]',2};
                if isempty(ScalpIgnored)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels ignored');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',ScalpIgnored{:});
                end
            catch
                fprintf(PifFileID,'\t%s\r\n','No electrodes setting file.');
            end
            fprintf(PifFileID,'%s\r\n','Auxiliary channels:');
            if app.AR_AuxAbsoluteCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum absolute amplitude:',app.AR_AuxAbsolute.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested against a maximum absolute amplitude');
            end
            if app.AR_AuxStepCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum adjacent time-frames difference:',app.AR_AuxStep.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested for amplitude steps');
            end
            if app.AR_AuxMaxRangeCheckBox.Value
                fprintf(PifFileID,'\t%s\t%d\r\n','Maximum amplitude range:',app.AR_AuxMaxRange.Value);
            else
                fprintf(PifFileID,'\t%s\r\n','Not tested against a maximum range');
            end
            fprintf(PifFileID,'%s\r\n','Time windows for auxiliary channels testing:');
            fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Pre-event inclusion:',app.AR_AuxPre_ms.Value,'ms',app.AR_AuxPre_tf.Value,'tf');
            fprintf(PifFileID,'\t\t%s\t\t%.1f %s\t%d %s\r\n','Post-event inclusion:',app.AR_AuxPost_ms.Value,'ms',app.AR_AuxPost_tf.Value,'tf');
            fprintf(PifFileID,'%s\r\n','Auxiliary channels tested:');
            try
                AuxTested = app.AR_AuxChanTestTable.Data{[app.AR_AuxChanTestTable.Data.test{:}]',2};
                if isempty(AuxTested)
                    fprintf(PifFileID,'\t\t%s\r\n','No auxiliary channels tested');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',AuxTested{:});
                end
            catch
                fprintf(PifFileID,'\t%s\r\n','No auxiliary channels defined.');
            end
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Subject  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';cellstr(app.DataTable.(7))';num2cell(app.DataTable.(3))';num2cell(app.DataTable.(4))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN EPOCHING
            
            % Prepare values
            
%             ProcessedDataTable  = app.DataTable; % For updating the Main Window
            ChannelsCheck       = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            FileTriggers        = strings(height(app.DataTable),1); % For reporting triggers found in each file
            
            EpochOnsetTF        = app.EpochOnset_tf.Value;
            EpochOffsetTF       = app.EpochOffset_tf.Value;
            BL_ScalpOnsetTF     = app.BLscalp_onset_tf.Value - EpochOnsetTF; % Convert into all positive values
            BL_ScalpOffsetTF    = app.BLscalp_offset_tf.Value - EpochOnsetTF; % Convert into all positive values
            BL_AuxOnsetTF       = app.BLaux_onset_tf.Value - EpochOnsetTF; % Convert into all positive values
            BL_AuxOffsetTF      = app.BLaux_offset_tf.Value - EpochOnsetTF; % Convert into all positive values
            
            noTargetEventDefine=0;

            if app.SubEpochs.Value
                EpochSize           = app.SubDuration_tf.Value;
            else
                EpochSize           = app.EpochTotal_tf.Value;
            end
            ScalpTestSize       = EpochSize + abs(app.AR_ScalpPre_tf.Value) + abs(app.AR_ScalpPost_tf.Value);
            AuxTestSize         = EpochSize + abs(app.AR_AuxPre_tf.Value) + abs(app.AR_AuxPost_tf.Value);
            
            
            Triggers = strsplit(app.EpochEvents.Value,{' ',',',';'});
            if strcmp(Triggers,'*')
                Triggers = strsplit(app.FilesEvents.Value{:},', ');
            elseif isempty(Triggers{1})
                noTargetEventDefine=1;
                EpochOnsetTF = 1;
                %EpochSize = EpochOffsetTF;
                BL_ScalpOnsetTF = 1;
                BL_AuxOnsetTF = 1;
            end
            if app.EpochOnset_tf.Value < 0
                origin = [{abs(app.EpochOnset_tf.Value)+1} {abs(app.EpochOnset_tf.Value)+1} {'origin'}];
            else
                origin = [0 0 {['origin+' num2str(app.EpochOnset_tf.Value)]}]; % if the origin (i.e., the event) is not included in the epoch, set at TF 1
            end
            
            ScalpTestOnsetTF    = EpochOnsetTF - abs(app.AR_ScalpPre_tf.Value); % abs() so that either positive or negative values can be handled
            AuxTestOnsetTF      = EpochOnsetTF - abs(app.AR_AuxPre_tf.Value); % abs() so that either positive or negative values can be handled
            
            % Channels to test
            try
                ScalpIndices        = app.AR_ScalpChanTestTable.Data.nr;
                ScalpTestIndices    = app.AR_ScalpChanTestTable.Data.nr([app.AR_ScalpChanTestTable.Data.test{:}]);
                app.nChan           = sum([app.AR_ScalpChanTestTable.Data.test{:}]); % Redefine nChan in case not all channels are included
            catch % if not electrode setting, test all channels
                ScalpIndices        = 1:app.nChan;
                ScalpTestIndices    = ScalpIndices;
            end
            try
                AuxIndices          = app.AR_AuxChanTestTable.Data.nr;
                AuxTestIndices      = app.AR_AuxChanTestTable.Data.nr([app.AR_AuxChanTestTable.Data.test{:}]);
                nAux                = height(app.AR_AuxChanTestTable.Data);
            catch
                % Change aux test to no (or disable if no aux)
                disp('No auxiliary channels to test')
                nAux = 0;
            end
            
            
            % Preparing summary for the Excel file
            SummaryTable = table('Size',[height(app.DataTable)*length(Triggers) 15],'VariableTypes',...
                {'string','string','string','string','double','double','double','double','double','double','double','double','double','double','double'},...
                'VariableNames',{'File','Subject','Session','Trigger','TotalTriggers',...
                'nEEGrejected','nAUXrejected','nEEG_AUXrejected','nTOTALrejected','nTOTALaccepted',...
                'pcEEGrejected','pcAUXrejected','pcEEG_AUXrejected','pcTOTALrejected','pcTOTALaccepted'});
            summaryrow = 1;
            
            % Variables for checking problematic channels
            SCALP_badamp    = zeros(height(app.DataTable),app.nChan);
            SCALP_badstep   = zeros(height(app.DataTable),app.nChan);
            SCALP_badrange  = zeros(height(app.DataTable),app.nChan);
            SCALP_badall    = zeros(height(app.DataTable),app.nChan);
            AUX_badamp      = zeros(height(app.DataTable),nAux);
            AUX_badstep     = zeros(height(app.DataTable),nAux);
            AUX_badrange    = zeros(height(app.DataTable),nAux);
            AUX_badall      = zeros(height(app.DataTable),nAux);
            badchanrow = 1;

            BadChanTable = table('Size',[height(app.DataTable) app.nChan+2],'VariableTypes',...
                [{'string'},{'string'},repmat("double",1,app.nChan)]); % Variable names will be added at the end (when the name of the channels is known
            

            disp('Run Epoching...')
            StartTime = datetime('now');
            tStart = tic;
            
            if ~isempty(char(unique(app.DataTable.(7))))
                SUBJ = unique(app.DataTable.(7));
            else % if no subjects identified, process each file separately
                num = arrayfun(@(x) sprintf('%02d',x),1:height(app.DataTable),'UniformOutput',false);
                SUBJ = strcat(repmat('file',height(app.DataTable),1),string(num'));
                app.DataTable.Subject = SUBJ;
            end
            
            
            % Structure for subject data
            num = arrayfun(@(x) sprintf('%02d',x),1:length(Triggers),'UniformOutput',false);
            TRIG = strcat(repmat('Trig',length(Triggers),1),string(num'));
            FieldValue = reshape([TRIG,repmat("",length(TRIG),1)]',[],1);
            
            
            for subj = 1:length(SUBJ)
                disp(['Processing ', char(SUBJ(subj,1)), ' (subject ', num2str(subj), ' out of ', num2str(length(SUBJ)), ')'])
                SubjDataTable   = app.DataTable(strcmp(app.DataTable.Subject,SUBJ(subj,1)),:);
                if strcmpi(app.IgnoreBadSwitch.Value,'On')
                    SubjInterpTable = app.InterpolationTable(strcmp(app.DataTable.Subject,SUBJ(subj,1)),:);
                end
                totEvents       = 0;
                
                
                % Structure for subject data
                SubjData = struct(FieldValue{:});
                
                % Preallocate fields
                for trig = 1:length(Triggers)
                    SubjData.(TRIG(trig)).Rejection     = strings([],3); % for reporting all markers of interest (TF, code) and commenting rejected ones (column 3)
                    SubjData.(TRIG(trig)).nEvt          = 0;
                    SubjData.(TRIG(trig)).nTOTrej       = 0;
                end
                SubjData.totEvents                  = 0;
%                 SubjData.BadScalp                   = zeros(1,app.nChan); % For reporting percent of bad epochs, per channel
%                 if app.AR_ScalpAbsoluteCheckBox.Value
%                     SubjData.ScalpAbsolute          = zeros(1,app.nChan); % For reporting percent of epochs above threshold, per channel
%                 end
%                 if app.AR_ScalpStepCheckBox.Value
%                     SubjData.ScalpStep              = zeros(1,app.nChan); % For reporting percent of epochs with bad step, per channel
%                 end
%                 if app.AR_ScalpMaxRangeCheckBox.Value
%                     SubjData.ScalpMaxRange          = zeros(1,app.nChan); % For reporting percent of epochs above range, per channel
%                 end
%                 SubjData.BadAux                     = zeros(1,nAux); % For reporting percent of bad epochs, per AUX channel
%                 if app.AR_AuxAbsoluteCheckBox.Value
%                     SubjData.AuxAbsolute          = zeros(1,nAux); % For reporting percent of epochs above threshold, per AUX channel
%                 end
%                 if app.AR_AuxStepCheckBox.Value
%                     SubjData.AuxStep              = zeros(1,nAux); % For reporting percent of epochs with bad step, per AUX channel
%                 end
%                 if app.AR_AuxMaxRangeCheckBox.Value
%                     SubjData.AuxMaxRange          = zeros(1,nAux); % For reporting percent of epochs above range, per AUX channel
%                 end
                
                
                for file = 1:height(SubjDataTable)
                    % Load file
                    disp(['Loading ', cell2mat(fullfile(SubjDataTable.Path(file),SubjDataTable.File(file)))])
                    disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                    openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(SubjDataTable.Path(file),'..'),SubjDataTable.File(file))); % full path and name of the file
                    [FilePath,FileName,FileExtension] = fileparts(openfilename);
                    
                    % BDF files
                    if strcmpi(FileExtension,'.bdf')
                        if app.InFileButton.Value
                            [header,data,eventsFile] = open_bdf(openfilename);
                            try
                                eventsFile = num2cell([eventsFile(:,1), eventsFile(:,1), eventsFile(:,2)]); % Convert to Cartool MRK format (onset, offset, code), but with origin at TF = 1
                            catch % if not events in file
                                eventsFile = {[] [] []};
                            end
                        else
                            [header,data,~] = open_bdf(openfilename);
                            eventsFile = {[] [] []};
                        end
                        if app.mrkButton.Value
                            MRK_file = [openfilename '.mrk']; % name of MRK file
                            if isfile(MRK_file)
                                eventsMRK = open_mrk(MRK_file);
                                eventsMRK(:,1:2) =  cellfun(@(x) x+1,eventsMRK(:,1:2),'UniformOutput',false); % +1 because Cartool counts TF from 0
                            else
                                disp(['MRK file not found for ' openfilename])
                                eventsMRK = {[] [] []};
                            end
                        end
                        events = sortrows([eventsFile; eventsMRK],1); % Sort according to first column so that the duplicate events are from the file first
                        FileChannels = header.Channels.Labels;
                        
                    % EEGlab files
                    elseif strcmpi(FileExtension,'.set')
                        disp('(Currently) Artefact rejection testing does not work for EEGlab .set files.')
                        disp('This is because artefact rejection testing is meant for visualing the output in Cartool.')
                        disp('Consider converting into a Cartool format and please do come back!')
                        disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                        disp(['Root folder: ' app.EEGpalCS_Main.RootFolder.Value{:}])
                        disp('File skipped.')
                        continue
                        
                        
                    % Cartool files
                    elseif strcmpi(FileExtension,'.sef')
                        [header,data,events] = open_sef(openfilename);
                        events=num2cell(events);
                        if ~isempty(events)
                            events(:,1:2) =  cellfun(@(x) x+1,events(:,1:2),'UniformOutput',false); % +1 because Cartool counts TF from 0
                        else
                            events = [{1} {1} {'TF1'}];
                        end
                        FileChannels = header.Channels;
                    elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                        [header,data,events] = open_eph(openfilename);
                        events=num2cell(events);
                        if ~isempty(events)
                            events(:,1:2) =  cellfun(@(x) x+1,events(:,1:2),'UniformOutput',false); % +1 because Cartool counts TF from 0
                        else
                            events = [{1} {1} {'TF1'}];
                        end
                    else
                        if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                            disp('These tools are not intended for .freq or .ris files.')
                        else
                            disp('File extension not recognized.')
                        end
                        disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                        disp(['Root folder: ' app.EEGpalCS_Main.RootFolder.Value{:}])
                        disp('File skipped.')
                        continue
                    end
                    disp('File loaded')
                    
                    % Correct events timing
                    if ~isempty(events) && app.EventsCorr_tf.Value > 0
                        events(:,1:2) = cellfun(@(x) {x+app.EventsCorr_tf.Value},events(:,1:2));
                        disp('Timing of events corrected.')
                        
                        % Remove events if onset is negative
                        neg = ([events{:,1}]<0)';
                        % TO DO: CREATE ARRAY WITH NUMBER OF NEGATIVE EVENTS REMOVED FOR PIF FILE
                        if sum(neg) > 0
                            events(neg,:) = [];
                            disp(['Removed ' num2str(sum(neg)) ' that became negative after correction'])
                        end
                    end
                    
                    
                    % No events defined (apply from time-frame 1)
                    %if isempty(Triggers{1})
                    if noTargetEventDefine==1
                        events = [{1},{1},{'TF1'}]; % Origin at time-fames 1
                        Triggers = "TF1";
                    end


                    % Remove unselected channels from the EEGpalCS Electrode Setting window
                    try
                        data = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    
                        % Channels check (if electrode setting defined)
                        try
                            Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                            if isequal(char(FileChannels),char(Channels))
                                ChannelsCheck(file,1)   = "OK";
                            else
                                ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                            end
                        catch
                            ChannelsCheck(file,1)   = "No channels in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No electrode setting defined";
                    end
                    
                    
                    % Remove channels to interpolate
                    if strcmpi(app.IgnoreBadSwitch.Value,'On')
                        toIgnore = find(table2array(SubjInterpTable(file,:))==true);
                        ScalpTestIndices(max(ScalpTestIndices == toIgnore,[],2)) = [];
                    end
                    
                    
                    % Re-sampling
                    % No resampling, so not to have to create new files.
                    % Maybe I'll change that one day...
                    
                    
                    % Prepare to process
                    if app.EndOfFile.Value
                        EpochOffsetTF    = SubjDataTable.(5)(file) - 1; % -1 because TF0 is the event
%                         if strcmpi(app.BaselineScalpPeriod.Value,'Whole epoch')
%                             BL_ScalpOffsetTF = app.DataTable.(5)(file);
%                         end
%                         if strcmpi(app.BaselineAuxPeriod.Value,'Whole epoch')
%                             BL_AuxOffsetTF = app.DataTable.(5)(file);
%                         end
                    end
                    if app.SubEpochs.Value
                        nSub = floor(((EpochOffsetTF - EpochOnsetTF) + 1) / app.SubDuration_tf.Value); % tf in case there is no sampling rate defined
                    else
                        nSub = 1;
                    end
                    
                    ScalpTestOffsetTF   = EpochOffsetTF + abs(app.AR_ScalpPost_tf.Value); % abs() shouldn't be necessary here, but better be sure...
                    AuxTestOffsetTF     = EpochOffsetTF + abs(app.AR_AuxPost_tf.Value); % abs() shouldn't be necessary here, but better be sure...
                    
                    %FileReject          = strings(size(events,1),3); % for reporting all markers of interest (TF, code) and commenting rejected ones (column 3)
                    
                    % EPOCHS
                    
%                     reverseStr = ''; % For progression text
                    
                    % Process triggers
                    disp(['Subject ''', char(SUBJ(subj,1)), ''': Processing file ', num2str(file), ' out of ', num2str(height(SubjDataTable))])
                    
                    nEvtFile = 1;
                    
                    if ~isempty(events{1,3}) % if the first event is empty, all events should be
                        FileTriggers(file,1) = strjoin(unique(string(events(:,3)))',', ');
                        
                        %mrkEvents = {};
                        %tvaEvents = {};
                        
                        for trig = 1:length(Triggers)
                            nEvt = sum(strcmp(string(events(:,3)),Triggers(1,trig))) * nSub; % Total occurences of this trigger multiplied by the number of sub-epochs
                            TrigReject       = strings(nEvt,3); % for reporting all markers of interest (TF, code) and commenting rejected ones (column 3)
                            %AcceptedEvents  = {};
                            evt             = 0; % counts events of interest
                            AllSubEvents    = {};
                            
                            for mrk = 1:size(events,1)
                                if strcmpi(string(events(mrk,3)),Triggers(1,trig))
                                    try % the trigger could be too close to the limites of the data for the epoch
                                        %EPOCH           = data((events{mrk,1})+EpochOnsetTF:(events{mrk,2})+EpochOffsetTF,:); % the offset of the epoch is calculated based on the trigger offset
                                        
                                        % Testing / Sub-epochs
                                        SCALPtest       = data((events{mrk,1})+ScalpTestOnsetTF:(events{mrk,2})+ScalpTestOffsetTF,ScalpTestIndices); % the offset of the epoch is calculated based on the trigger offset
                                        if ~isempty(app.AR_AuxChanTestTable.Data)
                                            AUXtest         = data((events{mrk,1})+AuxTestOnsetTF:(events{mrk,2})+AuxTestOffsetTF,AuxTestIndices); % the offset of the epoch is calculated based on the trigger offset
                                        end
                                        
                                        for sub = 1:nSub
                                            evt = evt + 1;
                                            
                                            % Sub marker
                                            if app.SubEpochs.Value
                                                AllSubEvents = [AllSubEvents; [{(events{mrk,1})+((sub-1)*EpochSize)}, {(events{mrk,1})+((sub-1)*EpochSize)}, {strcat(string(events(mrk,3)),"_sub")}]];
                                            else
                                                AllSubEvents = [AllSubEvents; [{(events{mrk,1})+((sub-1)*EpochSize)}, {(events{mrk,1})+((sub-1)*EpochSize)}, {string(events(mrk,3))}]];
                                            end
                                            TrigReject(evt,1)  = string(AllSubEvents(evt,1));
                                            TrigReject(evt,2)  = string(AllSubEvents(evt,3));
                                            
                                            
                                            % SCALP
                                            
                                            SCALPtestSub    = SCALPtest(((sub-1)*EpochSize)+1:((sub-1)*EpochSize)+ScalpTestSize,:);
                                            
                                            % Baseline correction
                                            if ~strcmp(app.BaselineScalpPeriod.Value,'None')
                                                BL_ScalpChan    = SCALPtestSub(1+abs(app.AR_ScalpPre_tf.Value)+BL_ScalpOnsetTF:1+app.AR_ScalpPre_tf.Value+BL_ScalpOffsetTF,:);
                                                SCALPtestSub    = SCALPtestSub - mean(BL_ScalpChan,1);
                                            end
                                            
                                            % EEG artefact rejection
                                            if app.AR_ScalpAbsoluteCheckBox.Value && ...
                                                    max(max(abs(SCALPtestSub))) > app.AR_ScalpAbsolute.Value % test if at least one channel has one TF amplitude above the threshold
                                                TrigReject(evt,3) = strcat(TrigReject(evt,3),'EEG bad amplitude',{'    '});
                                                SCALP_badamp(badchanrow,:)      = SCALP_badamp(badchanrow,:) + (max(abs(SCALPtestSub(:,ScalpTestIndices))) > app.AR_ScalpAbsolute.Value);
                                            end
                                            if app.AR_ScalpStepCheckBox.Value && ...
                                                    max(max(abs(SCALPtestSub(2:end,:)-SCALPtestSub(1:end-1,:)))) > app.AR_ScalpStep.Value % test if at least one channel has a step above the threshold
                                                TrigReject(evt,3) = strcat(TrigReject(evt,3),'EEG bad step',{'    '});
                                                SCALP_badstep(badchanrow,:)     = SCALP_badstep(badchanrow,:) + (max(abs(SCALPtestSub(2:end,ScalpTestIndices)-SCALPtestSub(1:end-1,ScalpTestIndices))) > app.AR_ScalpStep.Value);
                                            end
                                            if app.AR_ScalpMaxRangeCheckBox.Value && ...
                                                    max(max(SCALPtestSub) - min(SCALPtestSub)) > app.AR_ScalpMaxRange.Value % test if at least one channel shows amplitude range above the threshold
                                                TrigReject(evt,3) = strcat(TrigReject(evt,3),'EEG bad range',{'    '});
                                                SCALP_badrange(badchanrow,:)    = SCALP_badrange(badchanrow,:) + (max(SCALPtestSub(:,ScalpTestIndices)) - min(SCALPtestSub(:,ScalpTestIndices)) > app.AR_ScalpMaxRange.Value);
                                            end
                                            
                                            % Bad channels on any checked criteria
                                            if contains(TrigReject(evt,3),'EEG bad')
                                                SCALP_badall(badchanrow,:)      = SCALP_badall(badchanrow,:) + logical(SCALP_badamp(badchanrow,:) + SCALP_badstep(badchanrow,:) + SCALP_badrange(badchanrow,:));
                                            end
                                            
                                            
                                            % AUX
                                            
                                            if ~isempty(app.AR_AuxChanTestTable.Data)
                                                AUXtestSub      = AUXtest(((sub-1)*EpochSize)+1:((sub-1)*EpochSize)+AuxTestSize,:);
                                                
                                                % Baseline correction
                                                if ~isempty(app.AR_AuxChanTestTable.Data)
                                                    if ~strcmp(app.BaselineAuxPeriod.Value,'None')
                                                        BL_AuxChan  = AUXtestSub(1+app.AR_AuxPre_tf.Value+BL_AuxOnsetTF:1+app.AR_AuxpPre_tf.Value+BL_AuxOffsetTF,:);
                                                        AUXtestSub  = AUXtestSub - mean(BL_AuxChan,1);
                                                    end
                                                end
                                            end
                                            
                                            % AUX artefact rejection
                                            if app.AR_AuxAbsoluteCheckBox.Value && ...
                                                    max(max(abs(AUXtestSub))) > app.AR_AuxAbsolute.Value % test if at least one TF on one electrode is above the threshold
                                                TrigReject(evt,3) = strcat(TrigReject(evt,3),'AUX bad amplitude',{'    '});
                                                AUX_badamp(badchanrow,:)    = AUX_badamp(badchanrow,:) + (max(abs(AUXtestSub(:,AuxTestIndices))) > app.AR_AuxAbsolute.Value);
                                            end
                                            if app.AR_AuxStepCheckBox.Value && ...
                                                    max(max(abs(AUXtestSub(2:end,:)-AUXtestSub(1:end-1,:)))) > app.AR_ScalpStep.Value % test if at least one TF on one electrode is above the threshold
                                                TrigReject(evt,3) = strcat(TrigReject(evt,3),'AUX bad step',{'    '});
                                                AUX_badstep(badchanrow,:)   = AUX_badstep(badchanrow,:) + (max(abs(AUXtestSub(2:end,AuxTestIndices)-AUXtestSub(1:end-1,AuxTestIndices))) > app.AR_ScalpStep.Value);
                                            end
                                            if app.AR_AuxMaxRangeCheckBox.Value && ...
                                                    max(max(AUXtestSub) - min(AUXtestSub)) > app.AR_AuxMaxRange.Value % test if at least one channel shows amplitude range above the threshold
                                                TrigReject(evt,3) = strcat(TrigReject(evt,3),'AUX bad range',{'    '});
                                                AUX_badrange(badchanrow,:)  = AUX_badrange(badchanrow,:) + (max(AUXtestSub(:,AuxTestIndices)) - min(AUXtestSub(:,AuxTestIndices)) > app.AR_AuxMaxRange.Value);
                                            end
                                            
                                            % Bad AUX channels on any checked criteria
                                            if ~isempty(app.AR_AuxChanTestTable.Data) && contains(TrigReject(evt,3),'AUX bad')
                                                AUX_badall(badchanrow,:)    = AUX_badall(badchanrow,:) + logical(AUX_badamp(badchanrow,:) + AUX_badstep(badchanrow,:) + AUX_badrange(badchanrow,:));
                                            end


                                            % UPDATE EVENTS
                                            
                                            if strcmp(TrigReject(evt,3),"")
                                                events = [events; ...
                                                    [{(events{mrk,1}) + EpochOnsetTF + ((sub-1)*EpochSize)+1}, ...
                                                    {(events{mrk,1}) + EpochOnsetTF + ((sub-1)*EpochSize)+EpochSize}, ...
                                                    {'ACCEPTED'}]];
                                            else
                                                events = [events; ...
                                                    [{(events{mrk,1}) + EpochOnsetTF + ((sub-1)*EpochSize)+1}, ...
                                                    {(events{mrk,1}) + EpochOnsetTF + ((sub-1)*EpochSize)+EpochSize}, ...
                                                    {'REJECTED'}]];
                                            end

                                            nEvtFile = nEvtFile + 1;

                                        end
                                    catch
                                        %Rejection(evt+1,:)  = [string(events(mrk,1)) string(events(mrk,3)) 'Event too close to the limites of the data, could not extract the epoch'];
                                        TrigReject(evt+1,1)  = string(events(mrk,1));
                                        TrigReject(evt+1,2)  = string(events(mrk,3));
                                        TrigReject(evt+1,3) = 'Event too close to the limites of the data, could not extract the epoch';
                                        % NOTE N TIMES IT HAPPENS...
                                    end
                                end
                            end
                            
                            % compute % rejection
                            nEEGAUXrej      = sum(min(contains(TrigReject(:,3),'EEG'),contains(TrigReject(:,3),'AUX'))); % total lines containing EEG and AUX
                            nEEGrej         = sum(contains(TrigReject(:,3),'EEG')) - nEEGAUXrej; % total lines containing only EEG
                            nAUXrej         = sum(contains(TrigReject(:,3),'AUX')) - nEEGAUXrej; % total lines containing only AUX
                            nTOTrej         = nEEGAUXrej + nEEGrej + nAUXrej;
                            
                            EEGAUXpercent   = (nEEGAUXrej / nEvt) * 100;
                            EEGpercent      = (nEEGrej / nEvt) * 100;
                            AUXpercent      = (nAUXrej / nEvt) * 100;
                            TOTpercent      = (nTOTrej / nEvt) * 100;
                            
%                             AVGtrig         = SUMtrig / (nEvt - nTOTrej);
                            
                            
                            %Summary
                            
                            SummaryTable.File(summaryrow)               = openfilename;
                            SummaryTable.Subject(summaryrow)            = SUBJ(subj,1);
%                             SummaryTable.Session(summaryrow) = SESS(sess,1);
                            SummaryTable.Trigger(summaryrow)            = Triggers(1,trig);
                            SummaryTable.TotalTriggers(summaryrow)      = nEvt;
                            SummaryTable.nAUXrejected(summaryrow)       = nAUXrej;
                            SummaryTable.nEEGrejected(summaryrow)       = nEEGrej;
                            SummaryTable.nEEG_AUXrejected(summaryrow)   = nEEGAUXrej;
                            SummaryTable.nTOTALrejected(summaryrow)     = nTOTrej;
                            SummaryTable.nTOTALaccepted(summaryrow)     = nEvt - nTOTrej;
                            SummaryTable.pcAUXrejected(summaryrow)      = AUXpercent;
                            SummaryTable.pcEEGrejected(summaryrow)      = EEGpercent;
                            SummaryTable.pcEEG_AUXrejected(summaryrow)  = EEGAUXpercent;
                            SummaryTable.pcTOTALrejected(summaryrow)    = TOTpercent;
                            SummaryTable.pcTOTALaccepted(summaryrow)    = 100 - TOTpercent;
                            summaryrow = summaryrow + 1;
                            
                            
                            % Save data

                            if ~isempty(app.SuffixString.Value)
                                suffix = ['_', app.SuffixString.Value, '_Trigger_', cell2mat(Triggers(trig))];
                            else
                                suffix = ['_Trigger_', cell2mat(Triggers(trig))];
                            end

                            SubPath = SubjDataTable.Path{file}(3:end);
                            NewPath = fullfile(app.SaveFolder.Value{:},'Epochs',SubPath);
                            % Creating the folder
                            if ~exist(NewPath, 'dir')
                                mkdir(NewPath);
                            end
                            
                            % "verbose"
                            csvfilename = fullfile(NewPath,[FileName, suffix, '_ARtesting.csv']);
                            REJfid = fopen(csvfilename,'w');
                            fprintf(REJfid,'%s\t%s\n','Trigger: ',cell2mat(Triggers(1,trig)));
                            fprintf(REJfid,'%s\t%d\n','Number of events: ',nEvt);
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','TOTAL: ',nEvt - nTOTrej,'epochs accepted (',100 - TOTpercent,'%)');
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','EEG: ',nEEGrej,'epochs rejected (',EEGpercent,'%)');
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','AUX: ',nAUXrej,'epochs rejected (',AUXpercent,'%)');
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','EEG+AUX: ',nEEGAUXrej,'epochs rejected (',EEGAUXpercent,'%)');
                            fprintf(REJfid,'%s\t%d %s%.2f%s\n','TOTAL: ',nTOTrej,'epochs rejected (',TOTpercent,'%)');
                            fprintf(REJfid,'\n\n');
                            fprintf(REJfid,'%s\n','Epochs (onset  / event  /  comment');
                            fprintf(REJfid,'%s\t%s\t%s\n',TrigReject');
                            fclose(REJfid);
                            
                            % Epochs
%                             if (app.SaveTogether.Value || app.SaveSeparately.Value) && ~isempty(AcceptedEvents)
%                                 disp('Saving epochs')
%                                 if app.SaveTogether.Value
%                                     savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension.Value]);
%                                     %disp(['Saving all epoch for trigger ',cell2mat(Triggers(trig)),' into ',savefilename])
%                                     num = arrayfun(@(x) sprintf('%02d',x),1:size(AcceptedEvents,1),'UniformOutput',false);
%                                     epoch = strcat(repmat('Epoch',size(AcceptedEvents,1),1),string(num'));
%                                     saveEvents = [[AcceptedEvents(:,1),AcceptedEvents(:,2),epoch];...
%                                         [num2cell([AcceptedEvents{:,1}]'+origin{1}),num2cell([AcceptedEvents{:,1}]'+origin{1}),AcceptedEvents(:,3)]];
%                                     if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
%                                         save_eph(savefilename,EPOCHSdata,header.SamplingRate,saveEvents,[])
%                                     elseif strcmp(app.FilesExtension.Value,'.sef')
%                                         save_sef(savefilename,EPOCHSdata,header.SamplingRate,Channels,saveEvents,[],nAux)
%                                     elseif strcmp(app.FilesExtension.Value,'.set')
%                                         save_eeglab(savefilename,EPOCHSdata,header.SamplingRate,saveEvents,[],Channels)
%                                     end
%                                 end
%                             end
                            
                            
                            % Update subject data
                            SubjData.(TRIG(trig)).Rejection     = [SubjData.(TRIG(trig)).Rejection;TrigReject];
                            SubjData.(TRIG(trig)).nEvt          = SubjData.(TRIG(trig)).nEvt + nEvt;
                            SubjData.(TRIG(trig)).nTOTrej       = SubjData.(TRIG(trig)).nTOTrej + nTOTrej;
                            
    %                         % Progression
    %                         nSteps      = length(Triggers) * size(events,1) * nSub;
    %                         Step        = trig * mrk * sub;
    %                         reverseStr  = txtprogressbar(app,nSteps,Step,reverseStr);
                            
                            totEvents   = totEvents + (nEvt - nTOTrej);
                            
                            SubjData.totEvents   = SubjData.totEvents + (nEvt - nTOTrej);
                        end

                        % Problematic channels
                        
                        SCALP_badamp(badchanrow,:)      = (SCALP_badamp(badchanrow,:) / nEvtFile) * 100;
                        SCALP_badstep(badchanrow,:)     = (SCALP_badstep(badchanrow,:) / nEvtFile) * 100;
                        SCALP_badrange(badchanrow,:)    = (SCALP_badrange(badchanrow,:) / nEvtFile) * 100;
                        SCALP_badall(badchanrow,:)      = (SCALP_badall(badchanrow,:) / nEvtFile) * 100;

                        AUX_badamp(badchanrow,:)        = (AUX_badamp(badchanrow,:) / nEvtFile) * 100;
                        AUX_badstep(badchanrow,:)       = (AUX_badstep(badchanrow,:) / nEvtFile) * 100;
                        AUX_badrange(badchanrow,:)      = (AUX_badrange(badchanrow,:) / nEvtFile) * 100;
                        AUX_badall(badchanrow,:)        = (AUX_badall(badchanrow,:) / nEvtFile) * 100;

                        BadChanTable.(1)(badchanrow)    = openfilename;
                        BadChanTable.(2)(badchanrow)    = SUBJ(subj,1);
                        badchanrow = badchanrow + 1;

                    else
                        FileTriggers(file,1) = 'No triggers in this file'; % /!\ NOT USED YET !!!
                        
                        %Summary
                            
                        SummaryTable.File(summaryrow)               = openfilename;
                        SummaryTable.Subject(summaryrow)            = SUBJ(subj,1);
    %                     SummaryTable.Session(summaryrow) = SESS(sess,1);
                        SummaryTable.Trigger(summaryrow)            = Triggers(1,trig);
                        SummaryTable.TotalTriggers(summaryrow)      = 0;
                        SummaryTable.nAUXrejected(summaryrow)       = 0;
                        SummaryTable.nEEGrejected(summaryrow)       = 0;
                        SummaryTable.nEEG_AUXrejected(summaryrow)   = 0;
                        SummaryTable.nTOTALrejected(summaryrow)     = 0;
                        SummaryTable.nTOTALaccepted(summaryrow)     = 0;
                        SummaryTable.pcAUXrejected(summaryrow)      = 0;
                        SummaryTable.pcEEGrejected(summaryrow)      = 0;
                        SummaryTable.pcEEG_AUXrejected(summaryrow)  = 0;
                        SummaryTable.pcTOTALrejected(summaryrow)    = 0;
                        SummaryTable.pcTOTALaccepted(summaryrow)    = 0;
                        summaryrow = summaryrow + 1;
                    end
                    
                    % Create an .mrk file with events marking the sub-epochs
                    % if app.SubEpochs.Value && nSub > 1
                    %     %AllEvts = sortrows([string(events);string(AllSubEvents)]);
                    %     AllEvts = [events;AllSubEvents];
                    %     AllEvts(:,1:2) =  cellfun(@(x) x-1,AllEvts(:,1:2),'UniformOutput',false); % -1 because Cartool counts TF from 0
                    %     [~,I] = sortrows(cell2mat(AllEvts(:,1)));
                    %     AllEvts = string(AllEvts(I,:));
                    %     %AllEvts = [string(events);string(AllSubEvents)];
                    %     MRKfilename = fullfile(FilePath,[FileName,'_SubEvents_',FileExtension,'.mrk']);
                    %     MRKfid = fopen(MRKfilename,'w');
                    %     fprintf(MRKfid,'%s\r\n','TL02'); % use \r\n for notepad (not notepad++)
                    %     fprintf(MRKfid,'%s\t%s\t%s\r\n',AllEvts'); % use \r\n for notepad (not notepad++)
                    %     fclose(MRKfid);
                    % else
                    %     events(:,1:2) =  cellfun(@(x) x-1,events(:,1:2),'UniformOutput',false); % -1 because Cartool counts TF from 0
                    %     [~,I] = sortrows(cell2mat(events(:,1)));
                    %     events = string(events(I,:));
                    % 
                    %     movefile(fullfile(FilePath,[FileName,FileExtension,'.mrk']),fullfile(FilePath,[FileName,'_ORIG',FileExtension,'.mrk']));
                    %     MRKfilename = fullfile(FilePath,[FileName,FileExtension,'.mrk']);
                    %     MRKfid = fopen(MRKfilename,'w');
                    %     fprintf(MRKfid,'%s\r\n','TL02'); % use \r\n for notepad (not notepad++)
                    %     fprintf(MRKfid,'%s\t%s\t%s\r\n',events'); % use \r\n for notepad (not notepad++)
                    %     fclose(MRKfid);
                    % end
                end
            end

            if ispc
                folderseparator='\'
            else
                folderseparator='/'
            end
            BadChanTable.Properties.VariableNames = [{'File'},{'Subject'},cellstr(header.Channels)'];
%             writetable(BadChanTable,[app.SaveFolder.Value{:},folderseparator,'BadChannels_Summary.xlsx'],'Sheet','Summary');
            if app.AR_ScalpAbsoluteCheckBox.Value
                BadChanTable{:,3:end} = SCALP_badamp;
                writetable(BadChanTable,[app.SaveFolder.Value{:},folderseparator,'BadChannels_Summary.xlsx'],'Sheet','SCALP_badamp');
            end
            if app.AR_ScalpStepCheckBox.Value
                BadChanTable{:,3:end} = SCALP_badstep;
                writetable(BadChanTable,[app.SaveFolder.Value{:},folderseparator,'BadChannels_Summary.xlsx'],'Sheet','SCALP_badstep');
            end
            if app.AR_ScalpMaxRangeCheckBox.Value
                BadChanTable{:,3:end} = SCALP_badrange;
                writetable(BadChanTable,[app.SaveFolder.Value{:}, folderseparator, 'BadChannels_Summary.xlsx'],'Sheet','SCALP_badrange');
            end
            if app.AR_ScalpAbsoluteCheckBox.Value || app.AR_ScalpStepCheckBox.Value || app.AR_ScalpMaxRangeCheckBox.Value
                BadChanTable{:,3:end} = SCALP_badall;
                writetable(BadChanTable,[app.SaveFolder.Value{:},folderseparator,'BadChannels_Summary.xlsx'],'Sheet','SCALP_badall');
            else
                disp('No artefact rejection criteria selected for scalp channels')
            end

            if app.AR_AuxAbsoluteCheckBox.Value
                BadChanTable{:,3:end} = AUX_badamp;
                writetable(BadChanTable,[app.SaveFolder.Value{:},folderseparator,'BadChannels_Summary.xlsx'],'Sheet','AUX_badamp');
            end
            if app.AR_AuxStepCheckBox.Value
                BadChanTable{:,3:end} = AUX_badstep;
                writetable(BadChanTable,[app.SaveFolder.Value{:},folderseparator,'BadChannels_Summary.xlsx'],'Sheet','AUX_badstep');
            end
            if app.AR_AuxMaxRangeCheckBox.Value
                BadChanTable{:,3:end} = AUX_badrange;
                writetable(BadChanTable,[app.SaveFolder.Value{:},folderseparator,'BadChannels_Summary.xlsx'],'Sheet','AUX_badrange');
            end
            if app.AR_AuxAbsoluteCheckBox.Value || app.AR_AuxStepCheckBox.Value || app.AR_AuxMaxRangeCheckBox.Value
                BadChanTable{:,3:end} = AUX_badall;
                writetable(BadChanTable,[app.SaveFolder.Value{:},folderseparator, 'BadChannels_Summary.xlsx'],'Sheet','AUX_badall');
            else
                disp('No artefact rejection criteria selected for AUX channels')
            end

            
            % UPDATE INFORMATION BASED ON THE PROCESSED DATA
            
            % Data table in the Main Window
%             app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
%             app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
%             app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
%             disp('Data table in the Main Window updated with the processed files')
%             cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(data,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            writetable(SummaryTable,[app.SaveFolder.Value{:},folderseparator, 'ARtesting_Summary.xlsx'],'Sheet','Summary'); % Grand Summary!
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
%             fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
%             % fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Triggers found  /  CHANCHECK  /  Folder)');
%             Files = [app.DataTable.File';num2cell(app.DataTable.(3))';num2cell(app.DataTable.(4))';app.DataTable.Path'];
%             fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','SUMMARY (Subject  /  Trigger  /  N. Triggers in file  /  Accepted epochs (n/%)  /  Rejected epochs (n/%)  /  File');
            Slash = repmat("/",1,height(SummaryTable));
            Percent = repmat("%",1,height(SummaryTable));
            Summary = [SummaryTable.Subject';SummaryTable.Trigger';num2cell(SummaryTable.TotalTriggers)';...
                num2cell(SummaryTable.nTOTALaccepted)';Slash;num2cell(SummaryTable.pcTOTALaccepted)';Percent;...
                num2cell(SummaryTable.nTOTALrejected)';Slash;num2cell(SummaryTable.pcTOTALrejected)';Percent;SummaryTable.File'];
            Summary = fillmissing(Summary,'constant',"0");
            fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s %s %s%s\t\t%s %s %s%s\t\t%s\r\n',Summary{:});
            
            
%                         SummaryTable.nAUXrejected(summaryrow)       = nAUXrej;
%                         SummaryTable.nEEGrejected(summaryrow)       = nEEGrej;
%                         SummaryTable.nEEG_AUXrejected(summaryrow)   = nEEGAUXrej;
%                         SummaryTable.nTOTALrejected(summaryrow)     = nTOTrej;
%                         SummaryTable.pcAUXrejected(summaryrow)      = AUXpercent;
%                         SummaryTable.pcEEGrejected(summaryrow)      = EEGpercent;
%                         SummaryTable.pcEEG_AUXrejected(summaryrow)  = EEGAUXpercent;
%                         SummaryTable.pcTOTALrejected(summaryrow)    = TOTpercent;
                        
                        
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('AR testing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_Epoching_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Epoching_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Epoching_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Epoching = uifigure('Visible', 'off');
            app.Epoching.Name = 'EEGpal_Epoching';
            app.Epoching.CloseRequestFcn = createCallbackFcn(app, @EpochingCloseRequest, true);
            app.EPOCHINGLabel = uilabel(app.Epoching);
            app.EPOCHINGLabel.Text = 'EPOCHING';
            app.OUTPUTOPTIONSLabel = uilabel(app.Epoching);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.Epoching);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.Epoching);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.Epoching);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.Epoching, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.Epoching);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.Epoching);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.Epoching);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.Epoching, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'epoched';
            app.InputParametersFile = uilabel(app.Epoching);
            app.InputParametersFile.Text = 'Parameters';
            app.OpenButton = uibutton(app.Epoching, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.Epoching, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Update an existing file.'; 'Will erase preexisting Interpolation options.'};
            app.SaveButton.Text = 'Save';
            app.SaveAsButton = uibutton(app.Epoching, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Tooltip = {'Create a new file.'; 'Will erase data if file already exists.'};
            app.SaveAsButton.Text = 'Save as';
            app.ParameterfileEditFieldLabel = uilabel(app.Epoching);
            app.ParameterfileEditFieldLabel.Text = 'Parameter file:';
            app.ParameterFilePath = uieditfield(app.Epoching, 'text');
            app.ParameterFilePath.Editable = 'off';
            app.APPLYLabel = uilabel(app.Epoching);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.Epoching, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.RetainButton = uibutton(app.Epoching, 'push');
            app.RetainButton.ButtonPushedFcn = createCallbackFcn(app, @RetainButtonPushed, true);
            app.RetainButton.Tooltip = {'Return to the main window'};
            app.RetainButton.Text = 'Retain';
            app.CancelButton = uibutton(app.Epoching, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.TabGroup = uitabgroup(app.Epoching);
            app.EPOCHSTab = uitab(app.TabGroup);
            app.EPOCHSTab.Title = 'EPOCHS';
            app.EpochonsetLabel = uilabel(app.EPOCHSTab);
            app.EpochonsetLabel.Text = 'Epoch onset:';
            app.EpochOnset_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.EpochOnset_ms.ValueDisplayFormat = '%.1f';
            app.EpochOnset_ms.ValueChangedFcn = createCallbackFcn(app, @EpochOnset_msValueChanged, true);
            app.EpochOnset_ms.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.EpochOnset_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.EpochOnset_tf.ValueDisplayFormat = '%.0f';
            app.EpochOnset_tf.ValueChangedFcn = createCallbackFcn(app, @EpochOnset_tfValueChanged, true);
            app.EpochOnset_tf.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.EpochoffsetLabel = uilabel(app.EPOCHSTab);
            app.EpochoffsetLabel.Text = 'Epoch offset:';
            app.EpochOffset_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.EpochOffset_ms.ValueDisplayFormat = '%.1f';
            app.EpochOffset_ms.ValueChangedFcn = createCallbackFcn(app, @EpochOffset_msValueChanged, true);
            app.EpochOffset_ms.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.EpochOffset_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.EpochOffset_tf.ValueDisplayFormat = '%.0f';
            app.EpochOffset_tf.ValueChangedFcn = createCallbackFcn(app, @EpochOffset_tfValueChanged, true);
            app.EpochOffset_tf.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.EpochdurationLabel = uilabel(app.EPOCHSTab);
            app.EpochdurationLabel.Text = 'Epoch duration:';
            app.EpochTotal_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.EpochTotal_ms.ValueDisplayFormat = '%.1f';
            app.EpochTotal_ms.Editable = 'off';
            app.EpochTotal_ms.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.EpochTotal_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.EpochTotal_tf.ValueDisplayFormat = '%.0f';
            app.EpochTotal_tf.Editable = 'off';
            app.EpochTotal_tf.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.EpochTotal_tf.Value = 1;
            app.SubDuration_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.SubDuration_ms.ValueDisplayFormat = '%.1f';
            app.SubDuration_ms.ValueChangedFcn = createCallbackFcn(app, @SubDuration_msValueChanged, true);
            app.SubDuration_ms.Enable = 'off';
            app.SubDuration_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.SubDuration_tf.ValueDisplayFormat = '%.0f';
            app.SubDuration_tf.ValueChangedFcn = createCallbackFcn(app, @SubDuration_tfValueChanged, true);
            app.SubDuration_tf.Enable = 'off';
            app.SubEpochs = uibutton(app.EPOCHSTab, 'state');
            app.SubEpochs.ValueChangedFcn = createCallbackFcn(app, @SubEpochsValueChanged, true);
            app.SubEpochs.Tooltip = {'Split the epoch into sub-epochs of length equal to the input.'; 'if the epoch duration is not a multiple of the sub-epochs duration, the remaining last bit of the epoch will be discarded.'};
            app.SubEpochs.Text = 'Split into sub-epochs:';
            app.NumberofsubepochsEditFieldLabel = uilabel(app.EPOCHSTab);
            app.NumberofsubepochsEditFieldLabel.Text = 'Number of sub-epochs:';
            app.nSubEpochs = uieditfield(app.EPOCHSTab, 'numeric');
            app.nSubEpochs.ValueDisplayFormat = '%.0f';
            app.nSubEpochs.Editable = 'off';
            app.PeriodRejectionLabel = uilabel(app.EPOCHSTab);
            app.PeriodRejectionLabel.Enable = 'off';
            app.PeriodRejectionLabel.Text = 'Period rejection';
            app.EventsLabel_2 = uilabel(app.EPOCHSTab);
            app.EventsLabel_2.Enable = 'off';
            app.EventsLabel_2.Text = 'Events:';
            app.RejectTriggers = uieditfield(app.EPOCHSTab, 'text');
            app.RejectTriggers.Enable = 'off';
            app.RejectTriggers.Tooltip = {'Enter trigger codes separated by space, comma, or semi-colon.'; 'Use ''*'' for all triggers.'; 'Period removal applied BEFORE epoching.'};
            app.Reject_RelativeToEventLabel = uilabel(app.EPOCHSTab);
            app.Reject_RelativeToEventLabel.Enable = 'off';
            app.Reject_RelativeToEventLabel.Text = 'Relative to event';
            app.msLabel_4 = uilabel(app.EPOCHSTab);
            app.msLabel_4.Enable = 'off';
            app.msLabel_4.Text = '[ms]';
            app.tfLabel_4 = uilabel(app.EPOCHSTab);
            app.tfLabel_4.Enable = 'off';
            app.tfLabel_4.Text = '[tf]';
            app.EpochonsetLabel_2 = uilabel(app.EPOCHSTab);
            app.EpochonsetLabel_2.Enable = 'off';
            app.EpochonsetLabel_2.Text = 'Epoch onset:';
            app.RejectOnset_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.RejectOnset_ms.Enable = 'off';
            app.RejectOnset_ms.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.RejectOnset_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.RejectOnset_tf.Enable = 'off';
            app.RejectOnset_tf.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.EpochoffsetLabel_2 = uilabel(app.EPOCHSTab);
            app.EpochoffsetLabel_2.Enable = 'off';
            app.EpochoffsetLabel_2.Text = 'Epoch offset';
            app.RejectOffset_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.RejectOffset_ms.Enable = 'off';
            app.RejectOffset_ms.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.RejectOffset_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.RejectOffset_tf.Enable = 'off';
            app.RejectOffset_tf.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.EpochdurationLabel_2 = uilabel(app.EPOCHSTab);
            app.EpochdurationLabel_2.Enable = 'off';
            app.EpochdurationLabel_2.Text = 'Epoch duration:';
            app.RejectTotal_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.RejectTotal_ms.Editable = 'off';
            app.RejectTotal_ms.Enable = 'off';
            app.RejectTotal_ms.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.RejectTotal_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.RejectTotal_tf.Editable = 'off';
            app.RejectTotal_tf.Enable = 'off';
            app.RejectTotal_tf.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.RejectTotal_tf.Value = 1;
            app.BASELINECORRECTIONLabel = uilabel(app.EPOCHSTab);
            app.BASELINECORRECTIONLabel.Text = 'BASELINE CORRECTION';
            app.BLinstruction = uilabel(app.EPOCHSTab);
            app.BLinstruction.Text = 'Scalp and auxiliary channels can be selected in the ''artefact rejection'' tab.';
            app.ScalpChannels = uilabel(app.EPOCHSTab);
            app.ScalpChannels.Text = 'Scalp channels';
            app.BaselineperiodDropDownLabel = uilabel(app.EPOCHSTab);
            app.BaselineperiodDropDownLabel.Text = 'Baseline period:';
            app.BaselineScalpPeriod = uidropdown(app.EPOCHSTab);
            app.BaselineScalpPeriod.Items = {'None', 'Whole epoch', 'Pre-trigger period', 'Custom selection'};
            app.BaselineScalpPeriod.ValueChangedFcn = createCallbackFcn(app, @BaselineScalpPeriodValueChanged, true);
            app.BaselineScalpPeriod.Value = 'None';
            app.BL_RelativeToEventLabel = uilabel(app.EPOCHSTab);
            app.BL_RelativeToEventLabel.Text = 'Relative to event';
            app.msLabel_2 = uilabel(app.EPOCHSTab);
            app.msLabel_2.Text = '[ms]';
            app.tfLabel_2 = uilabel(app.EPOCHSTab);
            app.tfLabel_2.Text = '[tf]';
            app.BaselineonsetLabel = uilabel(app.EPOCHSTab);
            app.BaselineonsetLabel.Text = 'Baseline onset:';
            app.BLscalp_onset_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLscalp_onset_ms.ValueDisplayFormat = '%.1f';
            app.BLscalp_onset_ms.ValueChangedFcn = createCallbackFcn(app, @BLscalp_onset_msValueChanged, true);
            app.BLscalp_onset_ms.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.BLscalp_onset_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLscalp_onset_tf.ValueDisplayFormat = '%.0f';
            app.BLscalp_onset_tf.ValueChangedFcn = createCallbackFcn(app, @BLscalp_onset_tfValueChanged, true);
            app.BLscalp_onset_tf.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.BaselineoffsetLabel = uilabel(app.EPOCHSTab);
            app.BaselineoffsetLabel.Text = 'Baseline offset:';
            app.BLscalp_offset_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLscalp_offset_ms.ValueDisplayFormat = '%.1f';
            app.BLscalp_offset_ms.ValueChangedFcn = createCallbackFcn(app, @BLscalp_offset_msValueChanged, true);
            app.BLscalp_offset_ms.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.BLscalp_offset_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLscalp_offset_tf.ValueDisplayFormat = '%.0f';
            app.BLscalp_offset_tf.ValueChangedFcn = createCallbackFcn(app, @BLscalp_offset_tfValueChanged, true);
            app.BLscalp_offset_tf.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.BaselinedurationLabel = uilabel(app.EPOCHSTab);
            app.BaselinedurationLabel.Text = 'Baseline duration:';
            app.BLscalp_total_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLscalp_total_ms.ValueDisplayFormat = '%.1f';
            app.BLscalp_total_ms.Editable = 'off';
            app.BLscalp_total_ms.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.BLscalp_total_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLscalp_total_tf.ValueDisplayFormat = '%.0f';
            app.BLscalp_total_tf.Editable = 'off';
            app.BLscalp_total_tf.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.BLscalp_total_tf.Value = 1;
            app.AuxChannels = uilabel(app.EPOCHSTab);
            app.AuxChannels.Text = 'Auxiliary channels';
            app.BaselineperiodDropDownLabel_2 = uilabel(app.EPOCHSTab);
            app.BaselineperiodDropDownLabel_2.Text = 'Baseline period:';
            app.BaselineAuxPeriod = uidropdown(app.EPOCHSTab);
            app.BaselineAuxPeriod.Items = {'None', 'Same as scalp', 'Whole epoch', 'Pre-trigger period', 'Custom selection'};
            app.BaselineAuxPeriod.ValueChangedFcn = createCallbackFcn(app, @BaselineAuxPeriodValueChanged, true);
            app.BaselineAuxPeriod.Value = 'None';
            app.BL_RelativeToEventLabel_2 = uilabel(app.EPOCHSTab);
            app.BL_RelativeToEventLabel_2.Text = 'Relative to event';
            app.msLabel_3 = uilabel(app.EPOCHSTab);
            app.msLabel_3.Text = '[ms]';
            app.tfLabel_3 = uilabel(app.EPOCHSTab);
            app.tfLabel_3.Text = '[tf]';
            app.BaselineonsetLabel_2 = uilabel(app.EPOCHSTab);
            app.BaselineonsetLabel_2.Text = 'Baseline onset:';
            app.BLaux_onset_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLaux_onset_ms.ValueDisplayFormat = '%.1f';
            app.BLaux_onset_ms.ValueChangedFcn = createCallbackFcn(app, @BLaux_onset_msValueChanged, true);
            app.BLaux_onset_ms.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.BLaux_onset_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLaux_onset_tf.ValueDisplayFormat = '%.0f';
            app.BLaux_onset_tf.ValueChangedFcn = createCallbackFcn(app, @BLaux_onset_tfValueChanged, true);
            app.BLaux_onset_tf.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.BaselineoffsetLabel_2 = uilabel(app.EPOCHSTab);
            app.BaselineoffsetLabel_2.Text = 'Baseline offset:';
            app.BLaux_offset_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLaux_offset_ms.ValueDisplayFormat = '%.1f';
            app.BLaux_offset_ms.ValueChangedFcn = createCallbackFcn(app, @BLaux_offset_msValueChanged, true);
            app.BLaux_offset_ms.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.BLaux_offset_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLaux_offset_tf.ValueDisplayFormat = '%.0f';
            app.BLaux_offset_tf.ValueChangedFcn = createCallbackFcn(app, @BLaux_offset_tfValueChanged, true);
            app.BLaux_offset_tf.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.BaselinedurationLabel_2 = uilabel(app.EPOCHSTab);
            app.BaselinedurationLabel_2.Text = 'Baseline duration:';
            app.BLaux_total_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLaux_total_ms.ValueDisplayFormat = '%.1f';
            app.BLaux_total_ms.Editable = 'off';
            app.BLaux_total_ms.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.BLaux_total_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.BLaux_total_tf.ValueDisplayFormat = '%.0f';
            app.BLaux_total_tf.Editable = 'off';
            app.BLaux_total_tf.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.BLaux_total_tf.Value = 1;
            app.tfLabel = uilabel(app.EPOCHSTab);
            app.tfLabel.Text = '[tf]';
            app.msLabel = uilabel(app.EPOCHSTab);
            app.msLabel.Text = '[ms]';
            app.Epoch_RelativeToEventLabel = uilabel(app.EPOCHSTab);
            app.Epoch_RelativeToEventLabel.Text = 'Relative to event';
            app.EpochEvents = uieditfield(app.EPOCHSTab, 'text');
            app.EpochEvents.Tooltip = {'Enter trigger codes separated by space, comma, or semi-colon.'; 'Use ''*'' for all triggers.'; 'If empty, epoching applied from time-frame 1; '; 'negative ''onset'' inputs ignored.'};
            app.EpochEventsLabel = uilabel(app.EPOCHSTab);
            app.EpochEventsLabel.Text = 'Events:';
            app.EpochingLabel = uilabel(app.EPOCHSTab);
            app.EpochingLabel.Text = 'Epoching';
            app.EventsCorr_tf = uieditfield(app.EPOCHSTab, 'numeric');
            app.EventsCorr_tf.ValueDisplayFormat = '%.0f';
            app.EventsCorr_tf.Tooltip = {'Enter positive or negative value.'};
            app.EventsCorr_ms = uieditfield(app.EPOCHSTab, 'numeric');
            app.EventsCorr_ms.ValueDisplayFormat = '%.1f';
            app.EventsCorr_ms.Tooltip = {'Enter positive or negative value.'};
            app.EventsCorrLabel = uilabel(app.EPOCHSTab);
            app.EventsCorrLabel.Tooltip = {''};
            app.EventsCorrLabel.Text = 'Move events by:';
            app.tfLabel_7 = uilabel(app.EPOCHSTab);
            app.tfLabel_7.Text = '[tf]';
            app.msLabel_7 = uilabel(app.EPOCHSTab);
            app.msLabel_7.Text = '[ms]';
            app.CorrectTimingLabel = uilabel(app.EPOCHSTab);
            app.CorrectTimingLabel.Text = 'Correct timing';
            app.SaveTogether_split = uibutton(app.EPOCHSTab, 'state');
            app.SaveTogether_split.Enable = 'off';
            app.SaveTogether_split.Text = 'Split according to events';
            app.SaveTogether_split.Value = true;
            app.SaveTogether = uibutton(app.EPOCHSTab, 'state');
            app.SaveTogether.Text = 'Merge epochs into one file';
            app.SaveTogether.Value = true;
            app.SaveSeparately = uibutton(app.EPOCHSTab, 'state');
            app.SaveSeparately.Text = 'One file per epoch';
            app.SaveEpochsLabel = uilabel(app.EPOCHSTab);
            app.SaveEpochsLabel.Text = 'Save epochs';
            app.FilesEvents = uitextarea(app.EPOCHSTab);
            app.FilesEvents.Editable = 'off';
            app.FilesEvents.Tooltip = {'Triggers found in the first file of the list, FYI. You''re welcome!'};
            app.FilesEventsLabel = uilabel(app.EPOCHSTab);
            app.FilesEventsLabel.Text = 'Events found in input files';
            app.mrkButton = uibutton(app.EPOCHSTab, 'state');
            app.mrkButton.ValueChangedFcn = createCallbackFcn(app, @mrkButtonValueChanged, true);
            app.mrkButton.Text = '.mrk';
            app.mrkButton.Value = true;
            app.InFileButton = uibutton(app.EPOCHSTab, 'state');
            app.InFileButton.ValueChangedFcn = createCallbackFcn(app, @InFileButtonValueChanged, true);
            app.InFileButton.Text = 'In file';
            app.InFileButton.Value = true;
            app.EVENTSLabel = uilabel(app.EPOCHSTab);
            app.EVENTSLabel.Text = 'EVENTS';
            app.SubEpochsLabel = uilabel(app.EPOCHSTab);
            app.SubEpochsLabel.Enable = 'off';
            app.SubEpochsLabel.Text = {'Sub-epochs will be treated as epochs (e.g.,'; 'for baseline correction). A virtual event will '; 'be positionned relative to ''Epoch onset''.'};
            app.EndOfFile = uibutton(app.EPOCHSTab, 'state');
            app.EndOfFile.ValueChangedFcn = createCallbackFcn(app, @EndOfFileValueChanged, true);
            app.EndOfFile.Tooltip = {'End of File'; 'When selected, ''Epoch offset'', ''Epoch duration'', and ''Number of sub-epochs'' values based on the first file of the list in the main window.,When pressing run, these values will be adapted to the file being processed.'};
            app.EndOfFile.Text = 'EoF';
            app.SaveTogether_split_2 = uibutton(app.EPOCHSTab, 'state');
            app.SaveTogether_split_2.Text = 'Concatenate according to subject/file';
            app.SaveTogether_split_2.Value = true;
            app.ARTEFACTREJECTIONTab = uitab(app.TabGroup);
            app.ARTEFACTREJECTIONTab.Title = 'ARTEFACT REJECTION';
            app.CRITERIALabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.CRITERIALabel.Text = 'CRITERIA';
            app.AR_ScalpChannels = uilabel(app.ARTEFACTREJECTIONTab);
            app.AR_ScalpChannels.Text = 'Scalp channels';
            app.AR_ScalpAbsoluteCheckBox = uicheckbox(app.ARTEFACTREJECTIONTab);
            app.AR_ScalpAbsoluteCheckBox.Text = '';
            app.MaxabsolutethresholdVLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.MaxabsolutethresholdVLabel.Tooltip = {''};
            app.MaxabsolutethresholdVLabel.Text = 'Max absolute threshold [µV]:';
            app.AR_ScalpAbsolute = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_ScalpAbsolute.Tooltip = {'Rejects the epoch if one time-frame is above this threshold.'};
            app.AR_ScalpAbsolute.Value = 80;
            app.AR_ScalpStepCheckBox = uicheckbox(app.ARTEFACTREJECTIONTab);
            app.AR_ScalpStepCheckBox.Text = '';
            app.MaxstepthresholdVtfLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.MaxstepthresholdVtfLabel.Tooltip = {''};
            app.MaxstepthresholdVtfLabel.Text = 'Max step threshold [µV/tf]:';
            app.AR_ScalpStep = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_ScalpStep.Tooltip = {'Rejects the epoch if the difference between any two successive time-frames is above this threshold.'};
            app.AR_ScalpStep.Value = 30;
            app.AR_ScalpMaxRangeCheckBox = uicheckbox(app.ARTEFACTREJECTIONTab);
            app.AR_ScalpMaxRangeCheckBox.Text = '';
            app.MaxrangethresholdVLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.MaxrangethresholdVLabel.Tooltip = {''};
            app.MaxrangethresholdVLabel.Text = 'Max range threshold [µV]:';
            app.AR_ScalpMaxRange = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_ScalpMaxRange.Tooltip = {'Rejects the epoch if the difference between the maxima and minima within the epoch is above this threshold.'};
            app.AR_ScalpMaxRange.Value = 200;
            app.AR_AuxChannels = uilabel(app.ARTEFACTREJECTIONTab);
            app.AR_AuxChannels.Text = 'Auxiliary channels';
            app.AR_AuxAbsoluteCheckBox = uicheckbox(app.ARTEFACTREJECTIONTab);
            app.AR_AuxAbsoluteCheckBox.Text = '';
            app.MaxabsolutethresholdVLabel_2 = uilabel(app.ARTEFACTREJECTIONTab);
            app.MaxabsolutethresholdVLabel_2.Tooltip = {''};
            app.MaxabsolutethresholdVLabel_2.Text = 'Max absolute threshold [µV]:';
            app.AR_AuxAbsolute = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_AuxAbsolute.Tooltip = {'Rejects the epoch if one time-frame is above this threshold.'};
            app.AR_AuxAbsolute.Value = 80;
            app.AR_AuxStepCheckBox = uicheckbox(app.ARTEFACTREJECTIONTab);
            app.AR_AuxStepCheckBox.Text = '';
            app.MaxstepthresholdVtfLabel_2 = uilabel(app.ARTEFACTREJECTIONTab);
            app.MaxstepthresholdVtfLabel_2.Tooltip = {''};
            app.MaxstepthresholdVtfLabel_2.Text = 'Max step threshold [µV/tf]:';
            app.AR_AuxStep = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_AuxStep.Tooltip = {'Rejects the epoch if the difference between any two successive time-frames is above this threshold.'};
            app.AR_AuxStep.Value = 30;
            app.AR_AuxMaxRangeCheckBox = uicheckbox(app.ARTEFACTREJECTIONTab);
            app.AR_AuxMaxRangeCheckBox.Text = '';
            app.MaxrangethresholdVLabel_2 = uilabel(app.ARTEFACTREJECTIONTab);
            app.MaxrangethresholdVLabel_2.Tooltip = {''};
            app.MaxrangethresholdVLabel_2.Text = 'Max range threshold [µV]:';
            app.AR_AuxMaxRange = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_AuxMaxRange.Tooltip = {'Rejects the epoch if the difference between the maxima and minima within the epoch is above this threshold.'};
            app.AR_AuxMaxRange.Value = 60;
            app.AR_ScalpMinRangeCheckBox = uicheckbox(app.ARTEFACTREJECTIONTab);
            app.AR_ScalpMinRangeCheckBox.Enable = 'off';
            app.AR_ScalpMinRangeCheckBox.Text = '';
            app.AR_AuxMinRangeCheckBox = uicheckbox(app.ARTEFACTREJECTIONTab);
            app.AR_AuxMinRangeCheckBox.Enable = 'off';
            app.AR_AuxMinRangeCheckBox.Text = '';
            app.MinrangethresholdVLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.MinrangethresholdVLabel.Enable = 'off';
            app.MinrangethresholdVLabel.Tooltip = {''};
            app.MinrangethresholdVLabel.Text = 'Min range threshold [µV]:';
            app.AR_ScalpMinRange = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_ScalpMinRange.Enable = 'off';
            app.AR_ScalpMinRange.Tooltip = {'Rejects the epoch if one time-frame is above this threshold.'};
            app.MinrangethresholdVLabel_2 = uilabel(app.ARTEFACTREJECTIONTab);
            app.MinrangethresholdVLabel_2.Enable = 'off';
            app.MinrangethresholdVLabel_2.Tooltip = {''};
            app.MinrangethresholdVLabel_2.Text = 'Min range threshold [µV]:';
            app.AR_AuxMinRange = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_AuxMinRange.Enable = 'off';
            app.AR_AuxMinRange.Tooltip = {'Rejects the epoch if one time-frame is above this threshold.'};
            app.AR_ScalpMarkAsBad = uilabel(app.ARTEFACTREJECTIONTab);
            app.AR_ScalpMarkAsBad.Text = 'Mark as bad';
            app.msLabel_5 = uilabel(app.ARTEFACTREJECTIONTab);
            app.msLabel_5.Text = '[ms]';
            app.tfLabel_5 = uilabel(app.ARTEFACTREJECTIONTab);
            app.tfLabel_5.Text = '[tf]';
            app.PreeventLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.PreeventLabel.Text = 'Pre-event:';
            app.AR_ScalpPre_ms = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_ScalpPre_ms.ValueDisplayFormat = '%.1f';
            app.AR_ScalpPre_ms.ValueChangedFcn = createCallbackFcn(app, @AR_ScalpPre_msValueChanged, true);
            app.AR_ScalpPre_ms.Tooltip = {'Period preceding the epoch included in the artefact rejection procedure.'; 'Positive and negative values give the same result.'};
            app.AR_ScalpPre_tf = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_ScalpPre_tf.ValueDisplayFormat = '%.0f';
            app.AR_ScalpPre_tf.ValueChangedFcn = createCallbackFcn(app, @AR_ScalpPre_tfValueChanged, true);
            app.AR_ScalpPre_tf.Tooltip = {'Period preceding the epoch included in the artefact rejection procedure.'; 'Positive and negative values give the same result.'};
            app.PosteventLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.PosteventLabel.Text = 'Post-event:';
            app.AR_ScalpPost_ms = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_ScalpPost_ms.ValueDisplayFormat = '%.1f';
            app.AR_ScalpPost_ms.ValueChangedFcn = createCallbackFcn(app, @AR_ScalpPost_msValueChanged, true);
            app.AR_ScalpPost_ms.Tooltip = {'Period following the epoch included in the artefact rejection procedure.'; '(Positive and negative values give the same result.)'};
            app.AR_ScalpPost_tf = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_ScalpPost_tf.ValueDisplayFormat = '%.0f';
            app.AR_ScalpPost_tf.ValueChangedFcn = createCallbackFcn(app, @AR_ScalpPost_tfValueChanged, true);
            app.AR_ScalpPost_tf.Tooltip = {'Period following the epoch included in the artefact rejection procedure.'; '(Positive and negative values give the same result.)'};
            app.AR_AuxMarkAsBad = uilabel(app.ARTEFACTREJECTIONTab);
            app.AR_AuxMarkAsBad.Text = 'Mark as bad';
            app.msLabel_6 = uilabel(app.ARTEFACTREJECTIONTab);
            app.msLabel_6.Text = '[ms]';
            app.tfLabel_6 = uilabel(app.ARTEFACTREJECTIONTab);
            app.tfLabel_6.Text = '[tf]';
            app.PreeventLabel_2 = uilabel(app.ARTEFACTREJECTIONTab);
            app.PreeventLabel_2.Text = 'Pre-event:';
            app.AR_AuxPre_ms = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_AuxPre_ms.ValueDisplayFormat = '%.1f';
            app.AR_AuxPre_ms.ValueChangedFcn = createCallbackFcn(app, @AR_AuxPre_msValueChanged, true);
            app.AR_AuxPre_ms.Tooltip = {'Period preceding the epoch included in the artefact rejection procedure.'; 'Positive and negative values give the same result.'};
            app.AR_AuxPre_tf = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_AuxPre_tf.ValueDisplayFormat = '%.0f';
            app.AR_AuxPre_tf.ValueChangedFcn = createCallbackFcn(app, @AR_AuxPre_tfValueChanged, true);
            app.AR_AuxPre_tf.Tooltip = {'Period preceding the epoch included in the artefact rejection procedure.'; 'Positive and negative values give the same result.'};
            app.PosteventLabel_2 = uilabel(app.ARTEFACTREJECTIONTab);
            app.PosteventLabel_2.Text = 'Post-event:';
            app.AR_AuxPost_ms = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_AuxPost_ms.ValueDisplayFormat = '%.1f';
            app.AR_AuxPost_ms.ValueChangedFcn = createCallbackFcn(app, @AR_AuxPost_msValueChanged, true);
            app.AR_AuxPost_ms.Tooltip = {'Period following the epoch included in the artefact rejection procedure.'; '(Positive and negative values give the same result.)'};
            app.AR_AuxPost_tf = uieditfield(app.ARTEFACTREJECTIONTab, 'numeric');
            app.AR_AuxPost_tf.ValueDisplayFormat = '%.0f';
            app.AR_AuxPost_tf.ValueChangedFcn = createCallbackFcn(app, @AR_AuxPost_tfValueChanged, true);
            app.AR_AuxPost_tf.Tooltip = {'Period following the epoch included in the artefact rejection procedure.'; '(Positive and negative values give the same result.)'};
            app.CHANNELSLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.CHANNELSLabel.Text = 'CHANNELS';
            app.ScalpelectrodesclusterDropDownLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.ScalpelectrodesclusterDropDownLabel.Enable = 'off';
            app.ScalpelectrodesclusterDropDownLabel.Text = 'Scalp electrodes cluster:';
            app.ScalpCluster = uidropdown(app.ARTEFACTREJECTIONTab);
            app.ScalpCluster.Items = {'all', 'Option 2', 'Option 3', 'Option 4'};
            app.ScalpCluster.ValueChangedFcn = createCallbackFcn(app, @ScalpClusterValueChanged, true);
            app.ScalpCluster.Enable = 'off';
            app.ScalpCluster.Value = 'all';
            app.AR_ChannelsToTestLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.AR_ChannelsToTestLabel.Text = 'Scalp channels to test:';
            app.AR_ScalpChanTestTable = uitable(app.ARTEFACTREJECTIONTab);
            app.AR_ScalpChanTestTable.ColumnName = {'nr'; 'labels'; 'cluster'; 'test'};
            app.AR_ScalpChanTestTable.RowName = {};
            app.AR_ScalpChanTestTable.ColumnEditable = [false false false true];
            app.AR_ScalpChanTestTable.Tooltip = {'Selected channels will NOT be considered for the rejection process.'};
            app.AR_ScalpChanTestTable.Enable = 'off';
            app.InconsistentNumChan_scalp = uilabel(app.ARTEFACTREJECTIONTab);
            app.InconsistentNumChan_scalp.Visible = 'off';
            app.InconsistentNumChan_scalp.Text = {'Inconsistent number of '; 'channels between files'};
            app.AR_AuxChannelsToTestLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.AR_AuxChannelsToTestLabel.Text = 'Auxiliary channels to test:';
            app.AR_AuxChanTestTable = uitable(app.ARTEFACTREJECTIONTab);
            app.AR_AuxChanTestTable.ColumnName = {'nr'; 'labels'; 'cluster'; 'test'};
            app.AR_AuxChanTestTable.RowName = {};
            app.AR_AuxChanTestTable.ColumnEditable = [false false false true];
            app.AR_AuxChanTestTable.Tooltip = {'Selected channels WILL be tested against rejection thresholds.'};
            app.AR_AuxChanTestTable.Enable = 'off';
            app.InconsistentNumChan_aux = uilabel(app.ARTEFACTREJECTIONTab);
            app.InconsistentNumChan_aux.Visible = 'off';
            app.InconsistentNumChan_aux.Text = {'Inconsistent number of '; 'channels between files'};
            app.ChanCompInstruction = uilabel(app.ARTEFACTREJECTIONTab);
            app.ChanCompInstruction.Tooltip = {'Resampling is not affected, as to remain in the data, all channels have to have the same sampling rate.'};
            app.ChanCompInstruction.Text = {'Unselected channels will not be tested, but'; 'will not be removed from the data.'; 'This will be applied to all files'};
            app.InterpolationtableLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.InterpolationtableLabel.Enable = 'off';
            app.InterpolationtableLabel.Text = 'Interpolation table:';
            app.InterpolationPath = uitextarea(app.ARTEFACTREJECTIONTab);
            app.InterpolationPath.Editable = 'off';
            app.InterpolationPath.Enable = 'off';
            app.InterpolationButton = uibutton(app.ARTEFACTREJECTIONTab, 'push');
            app.InterpolationButton.Enable = 'off';
            app.InterpolationButton.Text = 'Interpolation module';
            app.IgnorebadchannelsLabel = uilabel(app.ARTEFACTREJECTIONTab);
            app.IgnorebadchannelsLabel.Enable = 'off';
            app.IgnorebadchannelsLabel.Text = 'Ignore bad channels:';
            app.IgnoreBadSwitch = uiswitch(app.ARTEFACTREJECTIONTab, 'slider');
            app.IgnoreBadSwitch.Enable = 'off';
            app.IgnoreBadSwitch.Tooltip = {'Ignores the channels marked as ''to interpolate'' in the Interpolation module.'};
            app.UploadTableButton = uibutton(app.ARTEFACTREJECTIONTab, 'push');
            app.UploadTableButton.Enable = 'off';
            app.UploadTableButton.Text = 'Upload table';
            app.ARevaluationButton = uibutton(app.ARTEFACTREJECTIONTab, 'push');
            app.ARevaluationButton.ButtonPushedFcn = createCallbackFcn(app, @ARevaluationButtonPushed, true);
            app.ARevaluationButton.Text = 'AR evaluation';
            app.SAMPLINGRATELabel = uilabel(app.Epoching);
            app.SAMPLINGRATELabel.Text = 'SAMPLING RATE';
            app.SamplingRate = uieditfield(app.Epoching, 'numeric');
            app.SamplingRate.ValueChangedFcn = createCallbackFcn(app, @SamplingRateValueChanged, true);
            app.SamplingRate.Enable = 'off';
            app.SamplingRate.Value = 1024;
            app.ResampleLabel = uilabel(app.Epoching);
            app.ResampleLabel.Text = 'Resample:';
            app.ResampleSwitch = uiswitch(app.Epoching, 'slider');
            app.ResampleSwitch.ValueChangedFcn = createCallbackFcn(app, @ResampleSwitchValueChanged, true);
            app.InconsistentSR = uilabel(app.Epoching);
            app.InconsistentSR.Visible = 'off';
            app.InconsistentSR.Text = 'Inconsistent!';
            app.OrigSamplingRate = uieditfield(app.Epoching, 'numeric');
            app.OrigSamplingRate.Editable = 'off';
            app.OrigSamplingRate.Value = 1000;
            app.OriginalsamplingrateLabel_2 = uilabel(app.Epoching);
            app.OriginalsamplingrateLabel_2.Text = 'Original sampling rate:';
            app.WithinAllTriggers = uibutton(app.Epoching, 'state');
            app.WithinAllTriggers.Text = 'All triggers';
            app.WithinPerTrigger = uibutton(app.Epoching, 'state');
            app.WithinPerTrigger.Text = 'Per trigger';
            app.WithinPerTrigger.Value = true;
            app.WithinPerCondition = uibutton(app.Epoching, 'state');
            app.WithinPerCondition.Enable = 'off';
            app.WithinPerCondition.Text = 'Per condition';
            app.WithinPerFactor = uibutton(app.Epoching, 'state');
            app.WithinPerFactor.Enable = 'off';
            app.WithinPerFactor.Text = 'Per factor';
            app.BetweenAllTriggers = uibutton(app.Epoching, 'state');
            app.BetweenAllTriggers.Text = 'All triggers';
            app.BetweenPerTrigger = uibutton(app.Epoching, 'state');
            app.BetweenPerTrigger.Text = 'Per trigger';
            app.BetweenPerCondition = uibutton(app.Epoching, 'state');
            app.BetweenPerCondition.Enable = 'off';
            app.BetweenPerCondition.Text = 'Per condition';
            app.BetweenPerFactor = uibutton(app.Epoching, 'state');
            app.BetweenPerFactor.Enable = 'off';
            app.BetweenPerFactor.Text = 'Per factor';
            app.AVERAGINGLabel = uilabel(app.Epoching);
            app.AVERAGINGLabel.Text = 'AVERAGING';
            app.WithinSubjectAveragingInstruction = uilabel(app.Epoching);
            app.WithinSubjectAveragingInstruction.Text = {'If no subject identified in the Main Window,'; 'averaging will be computed per file.'};
            app.WithinSubjectAveraging = uilabel(app.Epoching);
            app.WithinSubjectAveraging.Text = 'Within-subject';
            app.BetweenSubjectGrandAverage = uilabel(app.Epoching);
            app.BetweenSubjectGrandAverage.Tooltip = {'Grand-Average'};
            app.BetweenSubjectGrandAverage.Text = 'Between-subject';
            app.AvgSD = uibutton(app.Epoching, 'state');
            app.AvgSD.Enable = 'off';
            app.AvgSD.Text = 'Standard Deviation';
            app.AvgSE = uibutton(app.Epoching, 'state');
            app.AvgSE.Enable = 'off';
            app.AvgSE.Text = 'Standard Error';
            app.HelpButton = uibutton(app.Epoching, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            % Show the figure after all components are created
            app.Epoching.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Epoching(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Epoching)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Epoching)
        end
    end
end