classdef EEGpalCS_mod_FileCut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_Rereference         matlab.ui.Figure
        FileCutLabel                 matlab.ui.control.Label
        OUTPUTOPTIONSLabel           matlab.ui.control.Label
        ExtensionofoutputfilesLabel  matlab.ui.control.Label
        FilesExtension               matlab.ui.control.DropDown
        SelectUpperFolderLabel       matlab.ui.control.Label
        SelectFolderButton           matlab.ui.control.Button
        SavingfolderLabel            matlab.ui.control.Label
        SaveFolder                   matlab.ui.control.TextArea
        APPLYLabel                   matlab.ui.control.Label
        RunButton                    matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        ButtonGroup                  matlab.ui.container.ButtonGroup
        SaveinseparatefoldersButton  matlab.ui.control.RadioButton
        SaveinthesamefolderButton    matlab.ui.control.RadioButton
        CutTable                     matlab.ui.control.Table
        Instruct1                    matlab.ui.control.Label
        Instruct2                    matlab.ui.control.Label
        SelectUpperFolderLabel_2     matlab.ui.control.Label
        concatenate                  matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        SettingTable
    end
    
    properties (Access = public)
        SessionParameters
        Study_Parameters
    end
    
    methods (Access = private)


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            try
                app.SettingTable            = app.SessionParameters.Electrodes.SettingTable;
            catch
            end
            
            %create of the app.CutTable to enter the parameters
            labels      = string({'Start_Trigger','End_Trigger','End_Duration','End_Value','Suffix_String'});
            app.CutTable.Data = table('Size',[15 length(labels)],...
                'VariableTypes',["single", "logical", "logical","single","string"],'VariableNames',labels);
            
            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %length table
            [CutTableLength,~]=size(app.CutTable.Data); %length of table
            workingIndices =[]; %to save the indice where a start trigger has been specified
            
            %% CHECK
            
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            indice=0;
            for i=1:CutTableLength
                if app.CutTable.Data.Start_Trigger(i)~=0 %case to process
                    indice=indice+1;
                    workingIndices(indice)=i;
                    
                    if app.CutTable.Data.End_Value(i)==0  %check if no End Point
                        msgbox(sprintf('%s\n\n%s','Missing End Point!', ...
                            'You have forget to specify a End_Point in the table. Please make sure to have specify an End_Point to every line where you specified a Start_Trigger.'),...
                        'Missing End Point','warn');
                        return
                    end
                    
                    if and (~app.CutTable.Data.End_Trigger(i),~app.CutTable.Data.End_Duration(i))
                        msgbox(sprintf('%s\n\n%s','Missing End Point Type!', ...
                        'You have forget to specify if the End_Point is a Trigger or a Duration to the Start_trigger . Please make sure to have tick either the option "End_Option_Trigger" or "End_Option_Duration" to every line where you specified a Start_Trigger.'),...
                        'Missing End Point Type','warn');
                        return
                    end
                    
                end
            end   
            
            if indice==0
                msgbox(sprintf('%s','Sorry but you did not provide me any start point insctuction'),...
                'Missing Start Trigger','warn');
                return
            end
            
            Run = questdlg(sprintf('%s\n%s\n\n%s%d\n\n%s',...
                'Hello!',...
                'Me about to split the eeg files into subfiles the data. Ready for the ride?',...
                'Number of subfiles which will be generated per files: ',length(workingIndices),...
                'Do you wish to proceed? (Warning: after computation the results will be not transfer to the main window of EEGpal. You will need to manually import the files you want for next steps of processing)'),'Run checked options','OK','Cancel','Cancel');
            
            if strcmpi(Run,'Cancel')
                return
            end
            
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_FileCut_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpalCS File Cut Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
          
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            
            %cutting instruction 
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','CUTTING INSTRUCTIONS (Start Trigger  /  End Option(Trigger or Duration)  /  End Point  /  File suffix)');
            for i=1:indice
                fprintf(PifFileID,'%d\t\t', app.CutTable.Data.Start_Trigger(workingIndices(i)));
                if app.CutTable.Data.End_Trigger(workingIndices(i))
                    fprintf(PifFileID,'%s\t\t','Trigger');
                else
                    fprintf(PifFileID,'%s\t\t','Duration');
                end
                fprintf(PifFileID,'%d\t\t', app.CutTable.Data.End_Value(workingIndices(i)));
                
                if ~ismissing(app.CutTable.Data.Suffix_String(workingIndices(i)))
                    fprintf(PifFileID,'%s\r\n',app.CutTable.Data.Suffix_String(workingIndices(i)));
                else
                    fprintf(PifFileID,'%s\r\n','missing');
                end
            end
            
            %input files
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            %output files
            if app.SaveinseparatefoldersButton.Value
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: File saved in separate sub-folder');
            else
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: All files saved in the target directory');
            end
            if app.concatenate.Value
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: Concatenate signal of the same event');
            else
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: No concatenation of signal');
            end
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name)');
            %the outputfile file be contiousely write in the PIF file
            %during processing

            
            %% RUN CUT File
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run File Cut...')
            StartTime = datetime('now');
            tStart = tic;
            h = waitbar(0,'Processing.....');
            
            for file = 1:height(app.DataTable)  % Load file by file
                waitbar(file/height(app.DataTable),h, strcat('Processing File ',num2str(file),'/',num2str(height(app.DataTable))));
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
%                     events = [events.latency(:)-1, events.latency(:)-1, events{:,2}]; % Convert to Cartool MRK format (onset, offset, code)
%                     if ismember('duration', events.Properties.VariableNames)
%                         events(:,2) = events.latency(:)-1 + events.duration(:);
%                     end
%                     FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    %continue
                end
                disp('File loaded!')
                
                %check if event are cell
                if iscell(events)
                    events=cell2mat(events);
                end
                
                %correct the event indice from Cartool (0) to Matlab (1)
                if header.firstindex == 0
                    events(:,1)=events(:,1)+1;
                    events(:,2)=events(:,2)+1;
                end
                
                
                %run the File Cut
                for j=1:length(workingIndices) %loop over the segments
                    
                    %set the start position for the current segment
                    posTriggerStart=find(events(:,3)==app.CutTable.Data.Start_Trigger(workingIndices(j)));
                    
                    if length(posTriggerStart)>1 %problem of several occurence of the same trigger
                        disp('The start trigger has several occurence')
                        NewDataConcatenate=[]; %need to concatenate files
                    end
                    startTFPosition=events(posTriggerStart,1);
                    
                    %set the end position for the current segment
                    if app.CutTable.Data.End_Trigger(workingIndices(j)) %case where the end is specified by a trigger                   
                        posTriggerEnd=find(events(:,3)==app.CutTable.Data.End_Value(workingIndices(j)));
                        if length(posTriggerEnd) ~= length(posTriggerStart) %problem of several occurence of the same trigger
                            msgbox(sprintf('%s\n\n%s\n\n%s','The number of occurences of the specified End_Point trigger is different that the number of occurences of Start_Trigger for the file:', FileName, 'This case is not manage by this module. Please fix manually your marker file or use a fix duration instead'),...
                            'Problem of inequal number of Start Trigger and End Point Trigger','error');
                           disp('Processing Abort!')
                           delete (h);
                           return
                        end
                        endTFPosition=events(posTriggerEnd,1);  
                      
                    else %case where the end if specified by a fix duration in second
                        durationComputeNbTF=round(app.CutTable.Data.End_Value(j)/(1/app.DataTable.SamplingRate(file)));
                        endTFPosition=startTFPosition+durationComputeNbTF; %this line code work also if the startTFPosition dimension is bigger than 1 (serveral occurence of Start_Trigger)     
                    end
                        
                    
                    for k=1:length(posTriggerStart) %manage if serveral occurence of Start_Trigger    
                          
                        if endTFPosition(k)>size(data,1) %check if the endPoint is outside of the eegfile
                            msgbox(sprintf('%s\n\n%s\n\n%s','The specified End_Point duration is outside of the EEG file (duration value too big) for the file:', FileName, 'Please check the entered values.'),...
                                'End Point outside of the eeg file','error'); 
                            disp('Processing Abort!')
                            delete (h);
                            return
                        end
                    
                    
                        %preparation of the cut file
                        
                        MRK=zeros(1,3); %creation MRK factice (nécessaire pour appeler la fonction save_sef
                        
                        NewData = [];
                        NewData = [NewData; data(startTFPosition(k):endTFPosition(k),:)];
                        MRK(1,:)= [1,1,app.CutTable.Data.Start_Trigger(workingIndices(j))];
                        
                        %look if there is any other trigger/event if the period of interest
                        tempPos=1;
                        for z=1:size(events,1)
                            if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
                                tempPos=tempPos+1;
                                MRK(tempPos,1)=events(z,1)-startTFPosition(k);
                                MRK(tempPos,2)=events(z,1)-startTFPosition(k);
                                MRK(tempPos,3)=events(z,3);
                            end
                        end
                        
                        %concatenate if the option is activated
                        if and(app.concatenate.Value, length(posTriggerStart)>1)
                            NewDataConcatenate=vertcat(NewDataConcatenate,NewData);
                            
                            %management of the trigger/event inside when concatenate
                            %NEED TO BE PROGRAM IN THE FUTUR (for now, no trigger/event are saved in the concatenate file)    
                        end
                        
                        % Save processed file
                        
                        %suffix filename
                        if ~ismissing(app.CutTable.Data.Suffix_String(workingIndices(j))) %case the user specified a suffix string
                            suffix = app.CutTable.Data.Suffix_String(workingIndices(j));
                        else %case the user did not specify a suffix
                            suffix = strcat("Tigger",num2str(app.CutTable.Data.Start_Trigger(workingIndices(j))));
                        end
                        
                        %Generate the outputfilename
                        if length(posTriggerStart)>1
                            outputfilename=strcat(app.DataTable.File{file}(1:end-4),'_',suffix,'_',num2str(k), app.FilesExtension.Value);
                        else
                            outputfilename=strcat(app.DataTable.File{file}(1:end-4),'_',suffix,app.FilesExtension.Value);
                        end
                        
                        %complete path of recording
                        if app.SaveinseparatefoldersButton.Value %case of subdirectory
                            SubPath=strcat(app.SaveFolder.Value,'\',suffix);
                            if ~exist(SubPath, 'dir') %create folder
                                mkdir(SubPath);
                            end
                            savefilename=strcat(SubPath,'\',outputfilename);
                        else %case save in one folder 
                            savefilename=strcat(app.SaveFolder.Value,'\',outputfilename);
                        end
    
                        %saving
                        disp(['Saving ',savefilename])
                        if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph') %manage the output format
                            save_eph(savefilename,NewData,header.SamplingRate,MRK,1) %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.sef')
                            try
                                Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                            catch
                                Channels = '';
                            end
                            save_sef(savefilename,NewData,header.SamplingRate,Channels,MRK,1) %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.set')
                            try
                                ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                                Channels            = ElectrodeSetting(:,[6 7 2]);
                                Channels.labels     = char(Channels.labels);
                                Channels.sph_theta  = -Channels.theta;
                                Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                                Channels.X          = ElectrodeSetting.x;
                                Channels.Y          = ElectrodeSetting.y;
                                Channels.Z          = ElectrodeSetting.z;
        %                         Channels.ref        = char(repmat({''},height(Channels),1));
        %                         Channels.sph_radius = repmat({[]},height(Channels),1);
        %                         Channels.types      = char(repmat({''},height(Channels),1));
        %                         Channels.urchan     = repmat({[]},height(Channels),1);
                                Channels            = table2struct(Channels);
                            catch
                                Channels = '';
                            end
                            savefilename = convertStringsToChars(savefilename); %need to be char for save_eeglab
                            
                            save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1,Channels) %event start at 1
                        end
                        
                        
                        %saving concatenate file
                        if app.concatenate.Value & length(posTriggerStart)>1 & k==length(posTriggerStart)
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',suffix,'_CONCATENATE', app.FilesExtension.Value);
                            if app.SaveinseparatefoldersButton.Value %case of subdirectory
                                SubPath=strcat(app.SaveFolder.Value,'\',suffix);
                                savefilenameConcatenate=strcat(SubPath,'\',outputfilenameConcatenate);
                            else %case save in one folder 
                                savefilenameConcatenate=strcat(app.SaveFolder.Value,'\',outputfilenameConcatenate);
                            end
                            
                            MRK(1,:)= [1,1,app.CutTable.Data.Start_Trigger(workingIndices(j))]; %TO REMOVE WHEN I will program the trigger concatenate management
                            
                            if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph') %manage the output format
                                save_eph(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1) %event start at 1
                            elseif strcmp(app.FilesExtension.Value,'.sef')
                                try
                                    Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                                catch
                                    Channels = '';
                                end
                                save_sef(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,Channels,MRK,1) %event start at 1
                            elseif strcmp(app.FilesExtension.Value,'.set')
                                try
                                    ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                                    Channels            = ElectrodeSetting(:,[6 7 2]);
                                    Channels.labels     = char(Channels.labels);
                                    Channels.sph_theta  = -Channels.theta;
                                    Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                                    Channels.X          = ElectrodeSetting.x;
                                    Channels.Y          = ElectrodeSetting.y;
                                    Channels.Z          = ElectrodeSetting.z;
            %                         Channels.ref        = char(repmat({''},height(Channels),1));
            %                         Channels.sph_radius = repmat({[]},height(Channels),1);
            %                         Channels.types      = char(repmat({''},height(Channels),1));
            %                         Channels.urchan     = repmat({[]},height(Channels),1);
                                    Channels            = table2struct(Channels);
                                catch
                                    Channels = '';
                                end
                                 savefilenameConcatenate = convertStringsToChars(savefilenameConcatenate); %nned to be char for save_eeglab
                                save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1,Channels) %event start at 1
                            end
                        end


                        %update the PIF (section output file)
                        fprintf(PifFileID,'%s\r\n',outputfilename);
                    end
                end
            end
            
            
%             % UPDATE DATA TABLE IN MAIN WINDOW
%             
%             % Data table in the Main Window
%             app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
%             app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
%             app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
%             app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
%             disp('Data table in the Main Window updated with the processed files')
%             cd(app.SaveFolder.Value{:})
%                      
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])

            
            %Close the PIF file 
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('File cut processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            delete (h);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.FileCutButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FileCutButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpalCS_Rereference
        function EEGpalCS_RereferenceCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FileCutButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Cell edit callback: CutTable
        function CutTableEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if(indices(2)==2)
                if app.CutTable.Data.End_Duration(indices(1))
                    app.CutTable.Data.End_Duration(indices(1))=false;
                end
            elseif (indices(2)==3)
                if app.CutTable.Data.End_Trigger(indices(1))
                    app.CutTable.Data.End_Trigger(indices(1))=false;
                end                
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_Rereference = uifigure('Visible', 'off');
            app.EEGpalCS_Rereference.Name = 'EEGpalCS Re-reference';
            app.EEGpalCS_Rereference.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_RereferenceCloseRequest, true);
            app.FileCutLabel = uilabel(app.EEGpalCS_Rereference);
            app.FileCutLabel.Text = 'File Cut';
            app.OUTPUTOPTIONSLabel = uilabel(app.EEGpalCS_Rereference);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.EEGpalCS_Rereference);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.EEGpalCS_Rereference);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.EEGpalCS_Rereference);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.EEGpalCS_Rereference);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.EEGpalCS_Rereference);
            app.SaveFolder.Editable = 'off';
            app.APPLYLabel = uilabel(app.EEGpalCS_Rereference);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.DoneButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tooltip = {'Return to the main window'};
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.ButtonGroup = uibuttongroup(app.EEGpalCS_Rereference);
            app.SaveinseparatefoldersButton = uiradiobutton(app.ButtonGroup);
            app.SaveinseparatefoldersButton.Text = 'Save in separate folders';
            app.SaveinseparatefoldersButton.Value = true;
            app.SaveinthesamefolderButton = uiradiobutton(app.ButtonGroup);
            app.SaveinthesamefolderButton.Text = 'Save in the same folder';
            app.CutTable = uitable(app.EEGpalCS_Rereference);
            app.CutTable.ColumnName = {'Start_Trigger'; 'End_Option_Trigger'; 'End_Option_Duration'; 'End_Point(tigger number or s)'; 'Suffix_String'};
            app.CutTable.RowName = {};
            app.CutTable.ColumnEditable = true;
            app.CutTable.CellEditCallback = createCallbackFcn(app, @CutTableEdit, true);
            app.Instruct1 = uilabel(app.EEGpalCS_Rereference);
            app.Instruct1.Text = 'Module to cut EEG files into several subfiles. Please fill the following table to instruct the module about the borders (instructions below the table):';
            app.Instruct2 = uilabel(app.EEGpalCS_Rereference);
            app.Instruct2.Text = {'Start_trigger : Enter the value of the trigger which will mark the beginning of the subfile (can have several occurence)'; 'End_Option_trigger: Select if the End_Point is a trigger'; 'End_option_Duration: Select if the End_Point is fix duration (in s) after to the Strat_Trigger'; 'Suffix_String: Custom suffix identifier in the output file name'};
            app.SelectUpperFolderLabel_2 = uilabel(app.EEGpalCS_Rereference);
            app.SelectUpperFolderLabel_2.Text = 'Saving option:';
            app.concatenate = uicheckbox(app.EEGpalCS_Rereference);
            app.concatenate.Text = {'If there is several time the same Start_trigger, '; 'concatenate the signal'};
            % Show the figure after all components are created
            app.EEGpalCS_Rereference.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_FileCut(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_Rereference)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_Rereference)
        end
    end
end