classdef EEGpalCS_mod_Filtering < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_Filtering             matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        HelpButton                     matlab.ui.control.Button
        SwitchResample                 matlab.ui.control.Switch
        RESAMPLELabel_2                matlab.ui.control.Label
        OriginalSamplingRate           matlab.ui.control.NumericEditField
        OriginalsamplingrateLabel      matlab.ui.control.Label
        NewSamplingRate                matlab.ui.control.NumericEditField
        NewsamplingrateEditFieldLabel  matlab.ui.control.Label
        ReSamplingWarningLabel         matlab.ui.control.Label
        SamplingRateInstruction        matlab.ui.control.Label
        DeselectAll                    matlab.ui.control.Button
        SelectAll                      matlab.ui.control.Button
        ChanCompTable                  matlab.ui.control.Table
        ChanCompInstruction            matlab.ui.control.Label
        ChannelsToProcessLabel         matlab.ui.control.Label
        FILTERINGLabel_2               matlab.ui.control.Label
        CenterPanel                    matlab.ui.container.Panel
        FilterType_2                   matlab.ui.control.DropDown
        SingalProcessingtoolboxLabel   matlab.ui.control.Label
        SwitchFilteringAlgo            matlab.ui.control.Switch
        ScipttouseforFILTERINGLabel    matlab.ui.control.Label
        SwitchFiltering                matlab.ui.control.Switch
        FILTERINGLabel_3               matlab.ui.control.Label
        CleanLinePanel                 matlab.ui.container.Panel
        CL_AllOptionsButton            matlab.ui.control.Button
        CL_Bandwidth                   matlab.ui.control.NumericEditField
        BandwidthHzLabel_2             matlab.ui.control.Label
        CL_LineFrequenciesInfo         matlab.ui.control.Label
        CL_LineFrequencies             matlab.ui.control.NumericEditField
        LineFrequenciesHzLabel_2       matlab.ui.control.Label
        LineNoise                      matlab.ui.control.DropDown
        LINENOISEREMOVALLabel          matlab.ui.control.Label
        LinenoiseInstruction           matlab.ui.control.Label
        FilteredPowerSpectra           matlab.ui.control.CheckBox
        VizualizeoriginalandcleanedspectraLabel_2  matlab.ui.control.Label
        LPorder                        matlab.ui.control.NumericEditField
        LowpassfilterorderEditFieldLabel  matlab.ui.control.Label
        HPorder                        matlab.ui.control.NumericEditField
        HighpassfilterorderEditFieldLabel  matlab.ui.control.Label
        FilterType                     matlab.ui.control.DropDown
        FiltertypeDropDownLabel        matlab.ui.control.Label
        LPfreq                         matlab.ui.control.NumericEditField
        LowpassfrequencyHzEditFieldLabel  matlab.ui.control.Label
        HPfreq                         matlab.ui.control.NumericEditField
        HighpassfrequencyHzEditFieldLabel  matlab.ui.control.Label
        FilteringInstruction           matlab.ui.control.Label
        UIAxesNotch                    matlab.ui.control.UIAxes
        UIAxesLPfreq                   matlab.ui.control.UIAxes
        UIAxesHPfreq                   matlab.ui.control.UIAxes
        RightPanel                     matlab.ui.container.Panel
        CRD_Switch                     matlab.ui.control.Switch
        CLEANRAWDATAeeglabpluginLabel  matlab.ui.control.Label
        CancelButton                   matlab.ui.control.Button
        RetainButton                   matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        APPLYLabel                     matlab.ui.control.Label
        ParameterFilePath              matlab.ui.control.EditField
        ParameterfileEditFieldLabel    matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        OpenButton                     matlab.ui.control.Button
        InputParametersFile            matlab.ui.control.Label
        SuffixString                   matlab.ui.control.EditField
        SuffixstringLabel              matlab.ui.control.Label
        SaveFolder                     matlab.ui.control.TextArea
        SavingfolderLabel              matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        SelectUpperFolderLabel         matlab.ui.control.Label
        FilesExtension                 matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel    matlab.ui.control.Label
        OUTPUTOPTIONSLabel             matlab.ui.control.Label
        CRD_AllOptionsButton           matlab.ui.control.Button
        CRD_BadPeriods                 matlab.ui.control.CheckBox
        AdditionalremovalofbaddataperiodsLabel  matlab.ui.control.Label
        CRD_ASR                        matlab.ui.control.CheckBox
        ASRbadburstcorrectionLabel     matlab.ui.control.Label
        CRD_BadChan                    matlab.ui.control.CheckBox
        FlagbadchannelsLabel           matlab.ui.control.Label
        CRD_Drift                      matlab.ui.control.CheckBox
        RemovechanneldriftifnotalreadyhighpassedLabel  matlab.ui.control.Label
        CRD_WarningLabel               matlab.ui.control.Label
        CleanRawDataInstructionLabel   matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        FilterNotch
        NyquistFreq
        SamplingRate
        nChan
        
        ChanCompIndices
        CL_options
        CRD_options
    end
    
    properties (Access = public)
        Study_Parameters
        SessionParameters
    end
    
    methods (Access = private)
        
        function FilterPlots(app)
            
            app.NyquistFreq = app.SamplingRate / 2;
            
%             % Plots for Band-pass
%             Band = [app.HPfreq.Value app.LPfreq.Value];
%             FilterOrderBP = app.BPorder.Value * round(app.SamplingRate / app.HPfreq.Value);
%             app.FilterNotch.FilterKernelBP = fir1(FilterOrderBP,Band ./ app.NyquistFreq);
%             
%             %plot(app.UIAxesBPkernel,app.FilterNotch.FilterKernelBP) %,'linew',2
%             
%             HzH = linspace(0,app.NyquistFreq,floor(length(app.FilterNotch.FilterKernelBP)/2)+1);
%             FilterPowerBP = abs(fft(app.FilterNotch.FilterKernelBP)).^2;
%             plot(app.UIAxesBPfreq,HzH,FilterPowerBP(1:length(HzH)),'k',[1 1] * app.LPfreq.Value,get(app.UIAxesBPfreq,'ylim'),'k:') %,'linew',2
%             set(app.UIAxesBPfreq,'xlim',[0 app.LPfreq.Value*1.5],'ylim',[-.05 1.05])
            
            % Plots for High-pass
            FilterOrderHP = app.HPorder.Value * round(app.SamplingRate / app.HPfreq.Value);
            app.FilterNotch.FilterKernelHP = fir1(FilterOrderHP,app.HPfreq.Value ./ app.NyquistFreq,'high');
            
            %plot(app.UIAxesHPkernel,app.FilterNotch.FilterKernelHP) %,'linew',2
            
            HzHP = linspace(0,app.NyquistFreq,floor(length(app.FilterNotch.FilterKernelHP)/2)+1);
            FilterPowerHP = abs(fft(app.FilterNotch.FilterKernelHP)).^2;
            plot(app.UIAxesHPfreq,HzHP,FilterPowerHP(1:length(HzHP)),'k',[1 1] * app.HPfreq.Value,get(app.UIAxesHPfreq,'ylim'),'k:') %,'linew',2
            set(app.UIAxesHPfreq,'xlim',[0 app.HPfreq.Value*4],'ylim',[-.05 1.05])
            
            % Plots for Low-pass
            FilterOrderLP = app.LPorder.Value * round(app.SamplingRate / app.LPfreq.Value);
            app.FilterNotch.FilterKernelLP = fir1(FilterOrderLP,app.LPfreq.Value ./ app.NyquistFreq,'low');
            
            %plot(app.UIAxesLPkernel,app.FilterNotch.FilterKernelLP) %,'linew',2
            
            HzLP = linspace(0,app.NyquistFreq,floor(length(app.FilterNotch.FilterKernelLP)/2)+1);
            FilterPowerLP = abs(fft(app.FilterNotch.FilterKernelLP)).^2;
            plot(app.UIAxesLPfreq,HzLP,FilterPowerLP(1:length(HzLP)),'k',[1 1] * app.LPfreq.Value,get(app.UIAxesLPfreq,'ylim'),'k:') %,'linew',2
            set(app.UIAxesLPfreq,'xlim',[0 app.LPfreq.Value*2],'ylim',[-.05 1.05])
        end
        
        function out = str2vec(~,str)
            cln = find(str==':');
            if length(cln) == 1
                out = str2double(str(1:cln-1)) : str2double(str(cln+1:end));
            elseif length(cln) == 2
                out = str2double(str(1:cln(1)-1)) : str2double(str(cln(1)+1:cln(2)-1)) : str2double(str(cln(2)+1:end));
            else
                disp('did not understand the string, sorry!')
            end
        end
        
        function applyStudyParam(app)
            % Channels to process
            app.ChanCompTable.Data          = app.Study_Parameters.Filtering.ChanCompTable;
            
            
            % OUTPUT OPTION
            app.FilesExtension.Value        = app.Study_Parameters.Filtering.FilesExtention;
            app.SaveFolder.Value            = app.Study_Parameters.Filtering.SaveFolder;
            app.SuffixString.Value          = app.Study_Parameters.Filtering.SuffixString;
            
            
            % RESAMPLE
            app.SwitchResample.Value        = app.Study_Parameters.Filtering.Resample;
            app.NewSamplingRate.Value       = app.Study_Parameters.Filtering.NewSamplingRate;
            
            
            % FILTERING
            app.SwitchFiltering.Value       = app.Study_Parameters.Filtering.Filter;
            app.SwitchFilteringAlgo.Value   = app.Study_Parameters.Filtering.FilterAlgo;
            app.HPfreq.Value                = app.Study_Parameters.Filtering.HPfreq;
            app.LPfreq.Value                = app.Study_Parameters.Filtering.LPfreq;
            app.FilterType.Value            = app.Study_Parameters.Filtering.FilterType;
%             app.BPorder.Value               = app.Study_Parameters.Filtering.BPorder;
            app.HPorder.Value               = app.Study_Parameters.Filtering.HPorder;
            app.LPorder.Value               = app.Study_Parameters.Filtering.LPorder;
            %app.FilteredPowerSpectra.Value  = app.Study_Parameters.Filtering.VizualizeSpctra;
            
            % Line Noise
            app.LineNoise.Value             = app.Study_Parameters.Filtering.LineNoise;
            app.CL_options                  = app.Study_Parameters.Filtering.CleanLineNoise;
            app.CL_LineFrequencies.Value    = app.CL_options.lineFrequenciesF0;
            app.CL_Bandwidth.Value          = app.CL_options.fScanBandWidth;
            
            
            % CLEAN RAWDATA
            app.CRD_options                 = app.Study_Parameters.Filtering.CleanRawData;
            app.CRD_Drift.Value             = app.CRD_options.Drift;
            app.CRD_BadChan.Value           = app.CRD_options.BadChan;
            app.CRD_ASR.Value               = app.CRD_options.ASR;
            app.CRD_BadPeriods.Value        = app.CRD_options.BadPeriods;
        end
        
        function Study_Parameters = updateStudyParam(app,Study_Parameters)
            % Channels to process
            Study_Parameters.Filtering.ChanCompTable        = app.ChanCompTable.Data;
            
            
            % OUTPUT OPTION
            Study_Parameters.Filtering.FilesExtention       = app.FilesExtension.Value;
            Study_Parameters.Filtering.SaveFolder           = app.SaveFolder.Value;
            Study_Parameters.Filtering.SuffixString         = app.SuffixString.Value;
            
            
            % RESAMPLE
            Study_Parameters.Filtering.Resample             = app.SwitchResample.Value;
            Study_Parameters.Filtering.NewSamplingRate      = app.NewSamplingRate.Value;
            
            
            % FILTERING
            Study_Parameters.Filtering.Filter               = app.SwitchFiltering.Value;
            Study_Parameters.Filtering.FilterAlgo           = app.SwitchFilteringAlgo.Value;
            Study_Parameters.Filtering.HPfreq               = app.HPfreq.Value;
            Study_Parameters.Filtering.LPfreq               = app.LPfreq.Value;
            Study_Parameters.Filtering.FilterType           = app.FilterType.Value;
%             Study_Parameters.Filtering.BPorder              = app.BPorder.Value;
            Study_Parameters.Filtering.HPorder              = app.HPorder.Value;
            Study_Parameters.Filtering.LPorder              = app.LPorder.Value;
            %Study_Parameters.Filtering.VizualizeSpctra      = app.FilteredPowerSpectra.Value;
            
            % Line Noise
            Study_Parameters.Filtering.LineNoise            = app.LineNoise.Value;
            Study_Parameters.Filtering.CleanLineNoise       = app.CL_options;
            
            
            % CLEAN RAWDATA
            Study_Parameters.Filtering.CleanRawData         = app.CRD_options;
            try
                Study_Parameters.Interpolation.CRD              = app.SessionParameters.Interpolation.CRD;
            catch
            end
        end
        
        function applySessionParam(app)
            % Channels to process
            app.ChanCompTable.Data          = app.SessionParameters.Filtering.ChanCompTable;
            
            
            % OUTPUT OPTION
            app.FilesExtension.Value        = app.SessionParameters.Filtering.FilesExtention;
            app.SaveFolder.Value            = app.SessionParameters.Filtering.SaveFolder;
            app.SuffixString.Value          = app.SessionParameters.Filtering.SuffixString;
            
            
            % RESAMPLE
            app.SwitchResample.Value        = app.SessionParameters.Filtering.Resample;
            app.NewSamplingRate.Value       = app.SessionParameters.Filtering.NewSamplingRate;
            
            
            % FILTERING
            app.SwitchFiltering.Value       = app.SessionParameters.Filtering.Filter;
            SwitchFilteringValueChanged(app); % Restaure visual aspect of the module
            app.SwitchFilteringAlgo.Value   = app.SessionParameters.Filtering.FilterAlgo;
            SwitchFilteringAlgoValueChanged(app); % Restaure visual aspect of the module
            app.HPfreq.Value                = app.SessionParameters.Filtering.HPfreq;
            app.LPfreq.Value                = app.SessionParameters.Filtering.LPfreq;
            app.HPorder.Value               = app.SessionParameters.Filtering.HPorder;
            app.LPorder.Value               = app.SessionParameters.Filtering.LPorder;
            
            if strcmp(app.SwitchFilteringAlgo.Value, 'eeglab')
                 app.FilterType_2.Value     =  app.SessionParameters.Filtering.FilterType_2;
                 FilterType_2ValueChanged(app);
            else
                app.FilterType.Value            = app.SessionParameters.Filtering.FilterType;
                FilterPlots(app); % Restaure visual aspect of the module
            end
            
            % Line Noise
            app.LineNoise.Value             = app.SessionParameters.Filtering.LineNoise;
            app.CL_options                  = app.SessionParameters.Filtering.CleanLineNoise;
            app.CL_LineFrequencies.Value    = app.CL_options.lineFrequenciesF0;
            app.CL_Bandwidth.Value          = app.CL_options.fScanBandWidth;
            LineNoiseValueChanged(app); % Restaure visual aspect of the module
            
            
            % CLEAN RAWDATA
            app.CRD_options                 = app.SessionParameters.Filtering.CleanRawData;
            app.CRD_Drift.Value             = app.SessionParameters.Filtering.CRD_Drift;
            app.CRD_BadChan.Value           = app.SessionParameters.Filtering.CRD_BadChan;
            app.CRD_ASR.Value               = app.SessionParameters.Filtering.CRD_ASR;
            app.CRD_BadPeriods.Value        = app.SessionParameters.Filtering.CRD_BadPeriods;           
            
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            % Channels to process
            SessionParameters.Filtering.ChanCompTable   = app.ChanCompTable.Data;
            
            
            % OUTPUT OPTION
            SessionParameters.Filtering.FilesExtention  = app.FilesExtension.Value;
            SessionParameters.Filtering.SaveFolder      = app.SaveFolder.Value;
            SessionParameters.Filtering.SuffixString    = app.SuffixString.Value;
            
            
            % RESAMPLE
            SessionParameters.Filtering.Resample        = app.SwitchResample.Value;
            SessionParameters.Filtering.NewSamplingRate = app.NewSamplingRate.Value;
            
            
            % FILTERING
            SessionParameters.Filtering.Filter          = app.SwitchFiltering.Value;
            SessionParameters.Filtering.FilterAlgo      = app.SwitchFilteringAlgo.Value;
            SessionParameters.Filtering.HPfreq          = app.HPfreq.Value;
            SessionParameters.Filtering.LPfreq          = app.LPfreq.Value;
            SessionParameters.Filtering.FilterType      = app.FilterType.Value;
            SessionParameters.Filtering.FilterType_2    = app.FilterType_2.Value;
%             SessionParameters.Filtering.BPorder         = app.BPorder.Value;
            SessionParameters.Filtering.HPorder         = app.HPorder.Value;
            SessionParameters.Filtering.LPorder         = app.LPorder.Value;
            %SessionParameters.Filtering.VizualizeSpctra = app.FilteredPowerSpectra.Value;
            
            % Line Noise
            SessionParameters.Filtering.LineNoise       = app.LineNoise.Value;
            SessionParameters.Filtering.CleanLineNoise  = app.CL_options;
            app.CL_options.lineFrequenciesF0            = app.CL_LineFrequencies.Value;
            app.CL_options.fScanBandWidth               = app.CL_Bandwidth.Value;
            
            
            % CLEAN RAWDATA
            SessionParameters.Filtering.CleanRawData    = app.CRD_options;
            SessionParameters.Filtering.CRD_Drift       = app.CRD_Drift.Value;
            SessionParameters.Filtering.CRD_BadChan     = app.CRD_BadChan.Value;
            SessionParameters.Filtering.CRD_ASR         = app.CRD_ASR.Value;
            SessionParameters.Filtering.CRD_BadPeriods  = app.CRD_BadPeriods.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main = EEGpalCS_Main;
            
            % Update UI with input values
            app.DataTable = app.EEGpalCS_Main.DataFilesTable.Data;
            if ~isempty(app.DataTable)
                app.nChan = min(app.DataTable.nChan);
                app.SamplingRate =  app.DataTable.SamplingRate(1);
                app.OriginalSamplingRate.Value = app.SamplingRate;
            else
                app.nChan = 64;
                app.SamplingRate = 1024;
                %app.OriginalSamplingRate.Value = 1;
            end
            app.NyquistFreq = app.SamplingRate / 2;
            
            
            % Update UI with input values
            app.ParameterFilePath.Value     = app.EEGpalCS_Main.ParameterFilePath.Value;
            app.Study_Parameters            = app.EEGpalCS_Main.Study_Parameters;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            % Saved study parameters
            try
                applyStudyParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.Study_Parameters.Files.SaveFolder;
                catch
                end
            end
            
            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end
            
            
            % Electrodes table
            try
                ProcessTable = app.SessionParameters.Electrodes.SettingTable(:,[1 2]);
                ProcessTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}]',:) = []; % remove unselected channels
            catch
                ProcessTable = table('Size',[app.nChan 3],...
                    'VariableTypes',[{'double'}, {'string'}, {'logical'}],'VariableNames',app.ChanCompTable.ColumnName);
                ProcessTable.nr     = (1:height(ProcessTable))';
                ProcessTable.labels = cellfun(@(x) strcat("e",num2str(x)),num2cell(ProcessTable.nr));
            end
            ProcessTable.process    = repmat({true},height(ProcessTable),1);
            app.ChanCompTable.Data  = ProcessTable;
            app.ChanCompIndices     = 1:height(app.ChanCompTable.Data);
            
            
            % Plot the filter parameters
            %FilterPlots(app);
            
            
            % CleanLine options
            app.CL_options.lineNoiseMethod      = 'clean';
            app.CL_options.fPassBand            = [0 app.NyquistFreq];
            app.CL_options.Fs                   = app.SamplingRate;
            app.CL_options.lineFrequenciesF0    = app.CL_LineFrequencies.Value; % For edit field
            app.CL_options.lineFrequencies      = app.CL_LineFrequencies.Value;
            app.CL_options.fScanBandWidth       = app.CL_Bandwidth.Value;
            try
                app.CL_options.lineNoiseChannels    = app.ChanCompIndices;
            catch
                app.CL_options.lineNoiseChannels    = '1:64';
            end
            app.CL_options.maximumIterations    = 10;
            app.CL_options.p                    = 0.01;
            app.CL_options.pad                  = 2;
            app.CL_options.taperBandWidth       = 2;
            app.CL_options.taperWindowSize      = 4;
            app.CL_options.taperWindowStep      = 1 ;
            app.CL_options.tau                  = 100;
            
            
            % Clean RawData options
            app.CRD_options.Drift                       = app.CRD_Drift.Value;      % normally already taken care of
            app.CRD_options.Highpass                    = 'off'; %[0.25 0.75];
            
            app.CRD_options.BadChan                     = app.CRD_BadChan.Value;
            %app.CRD_options.Flatline                    = 'on';
            app.CRD_options.FlatlineCriterion           = 5;
            %app.CRD_options.LineNoise                   = 'off';                    % normally already taken care of
            app.CRD_options.LineNoiseCriterion          = 'off'; %4;                % normally already taken care of
            %app.CRD_options.Channel                     = 'on';
            app.CRD_options.ChannelCriterion            = 0.8;
            
            app.CRD_options.ASR                         = app.CRD_ASR.Value;
            %app.CRD_options.Burst                       = 'on';
            app.CRD_options.BurstCriterion              = 20;
            app.CRD_options.Distance                    = 'euclidian';
            app.CRD_options.BurstRejection              = 'off';
            
            app.CRD_options.BadPeriods                  = app.CRD_BadPeriods.Value;
            app.CRD_options.WindowCriterionTolerances   = [-inf 7];
            app.CRD_options.WindowCriterion             = 'off'; % 0.25;

        end

        % Cell edit callback: ChanCompTable
        function ChanCompTableCellEdit(app, event)
            app.ChanCompIndices = table2array(app.ChanCompTable.Data([app.ChanCompTable.Data.process{:}]',1))';
            app.CL_options.lineNoiseChannels = num2str(app.ChanCompIndices);
        end

        % Value changed function: NewSamplingRate
        function NewSamplingRateValueChanged(app, event)
            if strcmpi(app.SwitchResample.Value,'On')
                app.SamplingRate    = app.NewSamplingRate.Value;
            elseif strcmpi(app.SwitchResample.Value,'Off')
                app.SamplingRate    = app.OriginalSamplingRate.Value;
            end
            app.CL_options.Fs       = app.SamplingRate;
            
            % Plot the data
            FilterPlots(app)
        end

        % Value changed function: HPfreq
        function HPfreqValueChanged(app, event)
            % Plot the data
            if strcmp(app.SwitchFilteringAlgo.Value,'Matlab')
                FilterPlots(app);
            end
        end

        % Value changed function: LPfreq
        function LPfreqValueChanged(app, event)
            % Plot the data
            if strcmp(app.SwitchFilteringAlgo.Value,'Matlab')
                FilterPlots(app);
            end
        end

        % Value changed function: FilterType
        function FilterTypeValueChanged(app, event)
            if strcmp(app.FilterType.Value,'High+Low-pass')
                app.HPfreq.Enable       = 'on';
                app.HPorder.Enable      = 'on';
                app.LPfreq.Enable       = 'on';
                app.LPorder.Enable      = 'on';
            elseif strcmp(app.FilterType.Value,'High-pass')
                app.HPfreq.Enable       = 'on';
                app.HPorder.Enable      = 'on';
                app.LPfreq.Enable       = 'off';
                app.LPorder.Enable      = 'off';
            elseif strcmp(app.FilterType.Value,'Low-pass')
                app.HPfreq.Enable       = 'off';
                app.HPorder.Enable      = 'off';
                app.LPfreq.Enable       = 'on';
                app.LPorder.Enable      = 'on';
            end
        end

        % Value changed function: HPorder
        function HPorderValueChanged(app, event)
            % Plot the data
            if strcmp(app.SwitchFilteringAlgo.Value,'Matlab')
                FilterPlots(app);
            end
        end

        % Value changed function: LPorder
        function LPorderValueChanged(app, event)
            % Plot the data
            if strcmp(app.SwitchFilteringAlgo.Value,'Matlab')
                FilterPlots(app);
            end
        end

        % Value changed function: LineNoise
        function LineNoiseValueChanged(app, event)
            if contains(app.LineNoise.Value,'notch',"IgnoreCase",true)
                app.CleanLinePanel.Visible      = 'off';
                app.CL_LineFrequencies.Enable   = 'off';
                app.CL_Bandwidth.Enable         = 'off';
                app.CL_AllOptionsButton.Enable  = 'off';
                
                % create filter kernel using fir1
                app.FilterNotch.NotchFreq = str2double(app.LineNoise.Value(1:2));
                app.FilterNotch.NotchFreqRange = [app.FilterNotch.NotchFreq-0.5 app.FilterNotch.NotchFreq+0.5];
                app.FilterNotch.NotchOrder = round(150 * app.SamplingRate / app.FilterNotch.NotchFreqRange(1));
                app.FilterNotch.NotchKernel = fir1(app.FilterNotch.NotchOrder,app.FilterNotch.NotchFreqRange / app.NyquistFreq,'stop');
                
                % Plot notch
                app.UIAxesNotch.Visible         = "on";
                HzNotch = linspace(0,app.SamplingRate,floor(length(app.FilterNotch.NotchKernel)/2)+1);
                FilterPowerNotch = abs(fft(app.FilterNotch.NotchKernel,floor(length(app.FilterNotch.NotchKernel)/2)+1)).^2;
                plot(app.UIAxesNotch,HzNotch,FilterPowerNotch,'k',[1 1] * app.FilterNotch.NotchFreq,get(app.UIAxesNotch,'ylim'),'k:') %,'linew',2
                set(app.UIAxesNotch,'xlim',[app.FilterNotch.NotchFreq-20 app.FilterNotch.NotchFreq+20],'ylim',[-.05 1.05])
                
                %deactivate Cleanline Option
                app.CL_LineFrequencies.Enable   = 'off';
                app.CL_Bandwidth.Enable         = 'off';
                app.CL_AllOptionsButton.Enable  = 'off';
                
            elseif contains(app.LineNoise.Value,'CleanLine',"IgnoreCase",true)
                %app.UIAxesNotch.Visible = 'off';
                cla(app.UIAxesNotch)
                app.CleanLinePanel.Visible      = 'on';
                app.CL_LineFrequencies.Enable   = 'on';
                app.CL_Bandwidth.Enable         = 'on';
                app.CL_AllOptionsButton.Enable  = 'on';
                app.UIAxesNotch.Visible         = "off";
                
            else %contains(app.LineNoise.Value,'Off',"IgnoreCase",true)
                app.UIAxesNotch.Visible = 'on';
                cla(app.UIAxesNotch)
                app.CleanLinePanel.Visible          = 'off';
                app.CL_LineFrequencies.Enable   = 'off';
                app.CL_Bandwidth.Enable         = 'off';
                app.CL_AllOptionsButton.Enable  = 'off';
            end
        end

        % Value changed function: CL_LineFrequencies
        function CL_LineFrequenciesValueChanged(app, event)
            app.CL_options.lineFrequencies = app.CL_LineFrequencies.Value;
        end

        % Value changed function: CL_Bandwidth
        function CL_BandwidthValueChanged(app, event)
            app.CL_options.fScanBandWidth = app.CL_Bandwidth.Value;
        end

        % Button pushed function: CL_AllOptionsButton
        function CL_AllOptionsButtonPushed(app, event)
            app.CL_AllOptionsButton.Enable = 'off';
            
            app.CL_options = EEGpalCS_pop_cleanline(app.CL_options);
            
            app.CL_LineFrequencies.Value        = app.CL_options.lineFrequencies(1);
            app.CL_Bandwidth.Value              = app.CL_options.fScanBandWidth;
            
            app.CL_AllOptionsButton.Enable = 'on';
        end

        % Value changed function: CRD_Drift
        function CRD_DriftValueChanged(app, event)
            app.CRD_options.Drift = app.CRD_Drift.Value;
            if app.CRD_Drift.Value
                app.CRD_options.Highpass = [0.25 0.75];
            else
                app.CRD_options.Highpass = 'off';
            end
        end

        % Value changed function: CRD_BadChan
        function CRD_BadChanValueChanged(app, event)
            app.CRD_options.BadChan = app.CRD_BadChan.Value;
            if app.CRD_BadChan.Value
                app.CRD_options.FlatlineCriterion  = 5;
                app.CRD_options.ChannelCriterion   = 0.8;
            else
                app.CRD_options.FlatlineCriterion  = 'off';
                app.CRD_options.ChannelCriterion   = 'off';
            end
        end

        % Value changed function: CRD_ASR
        function CRD_ASRValueChanged(app, event)
            app.CRD_options.ASR = app.CRD_ASR.Value;
            if app.CRD_ASR.Value
                app.CRD_options.BurstCriterion = 20;
            else
                app.CRD_options.BurstCriterion = 'off';
            end
        end

        % Value changed function: CRD_BadPeriods
        function CRD_BadPeriodsValueChanged(app, event)
            app.CRD_options.BadPeriods = app.CRD_BadPeriods.Value;
            if app.CRD_BadPeriods.Value
                app.CRD_options.WindowCriterion = 0.25;
            else
                app.CRD_options.WindowCriterion = 'off';
            end
        end

        % Button pushed function: CRD_AllOptionsButton
        function CRD_AllOptionsButtonPushed(app, event)
            app.CRD_AllOptionsButton.Enable = 'off';
            
            app.CRD_options = EEGpalCS_pop_clean_rawdata(app.CRD_options);
            
            app.CRD_Drift.Value = app.CRD_options.Drift;
            app.CRD_BadChan.Value = app.CRD_options.BadChan;
            app.CRD_ASR.Value = app.CRD_options.ASR;
            app.CRD_BadPeriods.Value = app.CRD_options.BadPeriods;
            
            app.CRD_AllOptionsButton.Enable = 'on';
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_Filtering); % Restaure the app window
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [File,Path] = uigetfile('*.mat','Select parameter file','StudyParameters_EEGpalCS.mat');
            drawnow; figure(app.EEGpalCS_Filtering); % Restaure the app window
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            app.Study_Parameters=load(app.ParameterFilePath.Value);
            
            try
                applyStudyParam(app);
            catch
                msgbox(sprintf('%s','No parameters saved for this module.'),...
                    'No parameters','warn');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save parameter file
            if ~isempty (app.ParameterFilePath.Value)
                app.Study_Parameters = load(app.ParameterFilePath.Value);
                app.Study_Parameters = updateStudyParam(app,app.Study_Parameters);
                Parameters = app.Study_Parameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            else
                [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
                if isequal(File,0) || isequal(Path,0)
                   return; % User pressed cancel
                end
                app.ParameterFilePath.Value = fullfile(Path,File);
                app.SessionParameters = updateSessionParam(app,app.SessionParameters);
                Parameters = app.SessionParameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if isempty(app.DataTable)
                msgbox(sprintf('%s\n\n%s','No data to analyse!', ...
                    'Please make sure to select the files to analyse in the main window.'),...
                    'No data selected','warn');
                return
            end
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output flôlder selected','warn');
                return
            end
            if and(strcmp(app.SwitchFilteringAlgo.Value,'Matlab'),~license('test', 'Signal_Toolbox')) %test if Signal processing toolbox is installed
                msgbox(sprintf('%s\n\n%s','Signal Processing toolbox no installed!', ...
                    'The Signal Processing toolbox is not available. Installed it or use eeglab filtering mode.'),...
                    'filtfilt not available','warn');
                return
            end
            
            if strcmpi(app.CRD_Switch.Value,'On')
                Run = questdlg(sprintf('%s \n\n%s\n%s%s\n%s%s\n%s%s\n%s%d\n%s%d\n%s%d\n%s%d \n\n%s%d \n\n%s \n\n%s',...
                    'I (me, the script) am ready to process the selected data.', ...
                    'Selected processes:',...
                    'Re-sampling: ',app.SwitchResample.Value,...
                    'Filtering: ',app.SwitchFiltering.Value,...
                    'Line Noise: ',app.LineNoise.Value,...
                    'Clean RawData - remove drift: ',app.CRD_Drift.Value,...
                    'Clean RawData - flag bad channels: ',app.CRD_BadChan.Value,...
                    'Clean RawData - ASR: ',app.CRD_ASR.Value,...
                    'Clean RawData - remove bad periods: ',app.CRD_BadPeriods.Value,...
                    'Number of files: ',height(app.DataTable),...
                    'A long list might take time to proceed. In order to reduce time, you can select subsets of files in the main window.',...
                    'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
            else
                Run = questdlg(sprintf('%s \n\n%s\n%s%s\n%s%s\n%s%s\n%s%s \n\n%s%d \n\n%s \n\n%s',...
                    'I (me, the script) am ready to process the selected data.', ...
                    'Selected processes:',...
                    'Re-sampling: ',app.SwitchResample.Value,...
                    'Filtering: ',app.SwitchFiltering.Value,...
                    'Line Noise: ',app.LineNoise.Value,...
                    'Clean RawData: ',app.CRD_Switch.Value,...
                    'Number of files: ',height(app.DataTable),...
                    'A long list might take time to proceed. In order to reduce time, you can select subsets of files in the main window.',...
                    'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
            end
            
            if strcmpi(Run,'Cancel')
                return
            end
            
            
            %% PROCESSING INFORMATION SHEET

            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
%             % Last official update
%             fileID = fopen(strcat(currentfile.folder,'\',currentfile.name)); % open this file
%             update = char(string(textscan(fileID,'%s',1,'HeaderLines',1,'delimiter','\n'))); % Extract 'Update' line
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Filtering+_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Filtering+ Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            if strcmp(app.SwitchFilteringAlgo.Value,'eeglab')
                fprintf(PifFileID,'%s\t%f\r\n','Current version of eeglab:',app.EEGpalCS_Main.Versions.eeglab);
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Study parameters file:',app.ParameterFilePath.Value);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                fprintf(PifFileID,'%s\r\n','Processed channels:');
                fprintf(PifFileID,'\t\t%s\t\t%s\t\t%s\r\n',app.ChanCompTable.Data{:,:}');
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    if app.nChan > height(app.SessionParameters.Electrodes.SettingTable) % Extra channels not added to the electrode setting
                        fprintf(PifFileID,'\t\t%s\r\n',string(height(app.SessionParameters.Electrodes.SettingTable)+1:app.nChan));
                    else
                        fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                    end
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
%                 Included = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
%                 fprintf(PifFileID,'%s\r\n','Channels left in file:');
%                 fprintf(PifFileID,'\t\t%s\r\n',Included{:});
                if length(unique(app.DataTable.nChan)) > 1
                    fprintf(PifFileID,'\t\t%s\r\n','NOTE: number of channels inconsistent between files. The information above are based on the first file.');
                end
            catch
                fprintf(PifFileID,'%s\r\n','No electrodes setting file. Might reduce the accuracy of CLEAN RAW DATA (if used).');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','RESAMPLING');
            if strcmpi(app.SwitchResample.Value,'On')
                fprintf(PifFileID,'%s\t%d\r\n','Original sampling rate (first file):',app.OriginalSamplingRate.Value);
                fprintf(PifFileID,'%s\t\t\t\t\t\t%d\r\n','New sampling rate:',app.NewSamplingRate.Value);
                fprintf(PifFileID,'\r\n%s\r\n','All files with a sampling rate different from the original one were re-sampled.');
            else
                fprintf(PifFileID,'%s\r\n','No resampling');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','FILTERING');
            if strcmpi(app.SwitchFiltering.Value,'On')
                if strcmpi(app.SwitchFilteringAlgo.Value,'Matlab')
                    fprintf(PifFileID,'%s\t\t%s\r\n','Filtering Algorithm:','Use of the filtfilt function from the Signal Processing Toolbox of Matlab.');
                    fprintf(PifFileID,'%s\t\t%s\r\n','Filtering method:',app.FilterType.Value);
                    if strcmp(app.FilterType.Value,'High+Low-pass')
                        fprintf(PifFileID,'%s\t%g\r\n','High-pass frequency:',app.HPfreq.Value);
                        fprintf(PifFileID,'%s\t%d\r\n','High-pass filter order:',app.HPorder.Value);
                        fprintf(PifFileID,'%s\t\t%d\r\n','Low-pass frequency:',app.LPfreq.Value);
                        fprintf(PifFileID,'%s\t%d\r\n','Low-pass filter order:',app.LPorder.Value);
                    elseif strcmp(app.FilterType.Value,'High-pass')
                        fprintf(PifFileID,'%s\t%g\r\n','High-pass frequency:',app.HPfreq.Value);
                        fprintf(PifFileID,'%s\t%d\r\n','High-pass filter order:',app.HPorder.Value);
                    elseif strcmp(app.FilterType.Value,'Low-pass')
                        fprintf(PifFileID,'%s\t\t%d\r\n','Low-pass frequency:',app.LPfreq.Value);
                        fprintf(PifFileID,'%s\t%d\r\n','Low-pass filter order:',app.LPorder.Value);
                    end
                else
                    fprintf(PifFileID,'%s\t\t%s\r\n','Filtering Algorithm:','Use of the pop_eegfiltnew function from eeglab.');
                    fprintf(PifFileID,'%s\t\t%s\r\n','Filtering method:','Basic FIR filter');
                    if contains(app.FilterType_2.Value,'High')
                        fprintf(PifFileID,'%s\t%g\r\n','High-pass frequency:',app.HPfreq.Value);
                    elseif contains(app.FilterType_2.Value,'Low')
                        fprintf(PifFileID,'%s\t\t%d\r\n','Low-pass frequency:',app.LPfreq.Value);
                    else
                        fprintf(PifFileID,'%s\t%g\r\n','High-pass frequency:',app.HPfreq.Value);
                        fprintf(PifFileID,'%s\t\t%d\r\n','Low-pass frequency:',app.LPfreq.Value);
                    end
                    fprintf(PifFileID,'%s\t\t%s\r\n','Filter order:','The Filter order is automatically determine by eeglab. Filter order/transition is computed in order transition band width is 25% of the lower passband edge, but not lower than 2 Hz, where possible (for bandpass, highpass, and bandstop) and distance from passband edge to critical frequency (DC, Nyquist) otherwise.');
                    fprintf(PifFileID,'%s\r\n','Look at the command windows of Matlab during the execition for values: ');
                    fprintf(PifFileID,'%s\r\n','"pop_eegfiltnew() - performing 22529 point bandpass filtering." -> For example: 22529 is the order of filter ');
                    fprintf(PifFileID,'%s\r\n','"pop_eegfiltnew() - cutoff frequency(ies) (-6 dB): [0.15 40.15] Hz" -> transition speed. For example, attunuation of 6 dB from 0.15 for a cut-off frquency of 0.3 Hz');
                end
            else
                fprintf(PifFileID,'%s\r\n','No filtering');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','LINE NOISE');
            if ~strcmpi(app.LineNoise.Value,'No')
                fprintf(PifFileID,'%s\t%s\r\n\r\n','Line noise cleaning method:',app.LineNoise.Value);
                if contains(app.LineNoise.Value,'notch')
                    fprintf(PifFileID,'%s\t\t%d\r\n','Notch frequency:',app.FilterNotch.NotchFreq);
                    fprintf(PifFileID,'%s\t%g %g\r\n','Notch frequency range:',app.FilterNotch.NotchFreqRange);
                    fprintf(PifFileID,'%s\t\t%d\r\n','Notch filter order:',app.FilterNotch.NotchOrder);
                elseif contains(app.LineNoise.Value,'CleanLine')
                    fprintf(PifFileID,'%s\r\n','CleanLineNoise is an eeglab plugin developed for the PREP pipeline.');
                    fprintf(PifFileID,'%s\t%f\r\n','Current version of Cleanline:',app.EEGpalCS_Main.Versions.cleanline);
                    CLoptions = fieldnames(app.CL_options);
                    CLoptions(:,2) = {': '};
                    CLoptions(:,3) = cellfun(@num2str,struct2cell(app.CL_options),'UniformOutput',false);
                    CLoptions = CLoptions';
                    fprintf(PifFileID,'\r\n%s\r\n','CleanLineNoise options:');
                    fprintf(PifFileID,'\t%s%s%s\r\n',CLoptions{:});
                end
            else
                fprintf(PifFileID,'%s\r\n','No line noise cleaning');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','CLEAN RAW DATA (eeglab plugin)');
            if strcmp(app.CRD_Switch.Value,'On')
                fprintf(PifFileID,'%s\r\n','Used the clean_artifacts() eeglab plugin.');
                fprintf(PifFileID,'%s\t%f\r\n','Current version of Clean Raw data:',app.EEGpalCS_Main.Versions.clean_rawdata);
                CRDoptions = fieldnames(app.CRD_options);
                CRDoptions(:,2) = {': '};
                CRDoptions(:,3) = cellfun(@num2str,struct2cell(app.CRD_options),'UniformOutput',false);
                CRDoptions = CRDoptions';
                
                fprintf(PifFileID,'\r\n%s\r\n','Channel drift removal:');
                if app.CRD_Drift.Value
                    fprintf(PifFileID,'\t%s%s%s\r\n',CRDoptions{:,2});
                else
                    fprintf(PifFileID,'\t%s\r\n','Not computed');
                end
                
                fprintf(PifFileID,'\r\n%s\r\n','Bad channels detection:');
                if app.CRD_BadChan.Value
                    fprintf(PifFileID,'\t%s%s%s\r\n',CRDoptions{:,4:6});
                    fprintf(PifFileID,'%s\r\n','In the original script, bad channels are automatically removed for interpolation.');
                    fprintf(PifFileID,'%s\r\n','Here, bad channels are flagged, and it''s up to the user to decide wether to interpolate them or not.');
                else
                    fprintf(PifFileID,'\t%s\r\n','Not computed');
                end
                
                fprintf(PifFileID,'\r\n%s\r\n','Artifact Subspace Reconstruction (ASR):');
                if app.CRD_ASR.Value
                    fprintf(PifFileID,'\t%s%s%s\r\n',CRDoptions{:,8:10});
                else
                    fprintf(PifFileID,'\t%s\r\n','Not computed');
                end
                
                fprintf(PifFileID,'\r\n%s\r\n','Bad periods removal:');
                if app.CRD_BadPeriods.Value
                    fprintf(PifFileID,'\t%s%s%s\r\n',CRDoptions{:,12:13});
                else
                    fprintf(PifFileID,'\t%s\r\n','Not computed');
                end
            else
                fprintf(PifFileID,'%s\r\n','CleanRawData not used');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN FILTERING+
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run Filtering+...')
            StartTime = datetime('now');
            tStart = tic;
            
            h = waitbar(0,'Filtering.....');
            nbFile= height(app.DataTable);
            
            for file = 1:nbFile
                waitbar(file/nbFile,h, strcat('Filtering File ',num2str(file),'/',num2str(nbFile)));
                
                
                % Load file                
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                
                
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    if ~isempty(events)
                        events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    end
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    continue
                end
                disp(['File loaded (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                              
                
                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    
                    % Channels check (if electrode setting defined)
                    try
                        Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        if isequal(char(FileChannels),char(Channels))
                            ChannelsCheck(file,1)   = "OK";
                        else
                            ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No channels in file header";
                    end
                catch
                    ChannelsCheck(file,1)   = "No electrode setting defined";
                end
                
                
                % Re-sampling
                if strcmpi(app.SwitchResample.Value,'On')
                    app.SamplingRate = app.NewSamplingRate.Value;
                    if app.DataTable.SamplingRate(file) == app.NewSamplingRate.Value
                        disp(['New sampling rate (', num2str(app.NewSamplingRate.Value), ') same as original.'])
                        disp('Resampling not computed.')
                    else
                        disp(['Resampling from ', num2str(app.DataTable.SamplingRate(file)),'Hz to ',num2str(app.NewSamplingRate.Value),'Hz'])
                        data = resample(data,app.NewSamplingRate.Value,app.DataTable.SamplingRate(file));
                        
                        % Adapt events time-frames to new sampling rate
                        if ~isempty(events)
                            events(:,1:2) = round(events(:,1:2) * (app.NewSamplingRate.Value ./ app.DataTable.SamplingRate(file)),0);
                        end
                        disp('Resampling completed')
                    end
                else
                    app.SamplingRate = app.OriginalSamplingRate.Value; % was: "app.OriginalSamplingRate.Value(1)" (IF NO ERROR, CAN BE DELETED)
                end
                
                % Data to process
                NewData = data; % After re-sampling (which is fast) so to be able to add back the unprocessed channels
                
                % Remove unselected channels from ChanCompTable
%                 if isempty(app.ChanCompIndices)
%                     app.ChanCompIndices = 1:header.NumChan;
%                 end
%                 NewData = NewData(:,app.ChanCompIndices);
                if ~isempty(app.ChanCompIndices)
                    NewData = NewData(:,app.ChanCompIndices);
                end
                
                
                % Filtering
                if strcmpi(app.SwitchFiltering.Value,'On')
                    if strcmpi(app.SwitchFilteringAlgo.Value,'Matlab') %Matlab filtering using Signal processing toolbox (MichaelDP)
                        % Update filter parameters (just to make sure...)
                        FilterPlots(app);
                        
                        % High-pass filter
                        if contains(app.FilterType.Value,'High')
                            disp(['Applying high-pass filter (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                            NewData = filtfilt(app.FilterNotch.FilterKernelHP,1,NewData);
                        end
                        
                        % Low-Pass Filter
                        if contains(app.FilterType.Value,'Low')
                            disp(['Applying low-pass filter (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                            NewData = filtfilt(app.FilterNotch.FilterKernelLP,1,NewData);
                        end
                        disp('Filtering completed')
                        
                    else %Filtering using eeglab algoritm
                        
                        %convert into an eeglab structure (.set)
                        ALLEEG=struct; EEG=struct;
                        
                        try
                            ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                            Channels            = ElectrodeSetting(:,2);
                            %Channels.labels     = char(ElectrodeSetting.labels);
    
                            % Recompute Theta and Radius to match EEGlab orientation
                            [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                            Channels.theta      = -rad2deg(az);
                            Channels.radius     = 0.5 - rad2deg(elev)/180;
                            Channels.sph_theta  = -Channels.theta;
                            Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                            Channels.X          = ElectrodeSetting.y;
                            Channels.Y          = -ElectrodeSetting.x;
                            Channels.Z          = ElectrodeSetting.z;
    %                         Channels.ref        = char(repmat({''},height(Channels),1));
    %                         Channels.sph_radius = repmat({[]},height(Channels),1);
    %                         Channels.types      = char(repmat({''},height(Channels),1));
    %                         Channels.urchan     = repmat({[]},height(Channels),1);
                            Channels            = table2struct(Channels);
                            Channels            = Channels(app.ChanCompIndices,:); % Keep only channels to process
                        catch
                            Channels = '';
                        end
                        EEG = eeglab_datastruct(openfilename,NewData,app.SamplingRate,events,Channels);
                        %[ALLEEG, EEG, CURRENTSET] = eeg_store( ALLEEG, EEG, 0 ); 
                        
                        %run eeglab filter
                        if contains(app.FilterType_2.Value,'High')
                            EEG = pop_eegfiltnew(EEG, 'locutoff',app.HPfreq.Value);
                        elseif contains(app.FilterType_2.Value,'Low')
                            EEG = pop_eegfiltnew(EEG, 'hicutoff',app.LPfreq.Value);
                        else
                            EEG = pop_eegfiltnew(EEG, 'locutoff',app.HPfreq.Value,'hicutoff',app.LPfreq.Value);
                        end
    
                        NewData = EEG.data';
                    
                    end
                end
                
                
                % Line Noise
                if ~strcmpi(app.LineNoise.Value,'Off')
                    
                    % Notch filter
                    if contains(app.LineNoise.Value,'notch')
                        disp(['Applying ', app.LineNoise.Value])
                        NewData = filtfilt(app.FilterNotch.NotchKernel,1,NewData);
                    
                    % CleanLineNoise
                    elseif contains(app.LineNoise.Value,'CleanLine')
                        disp(['Applying CleanLineNoise (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                        if isstring(app.CL_options.lineNoiseChannels)
                            app.CL_options.lineNoiseChannels = str2vec(app,app.CL_options.lineNoiseChannels);
                        end
                        signal.data = NewData'; % EEGlab organizes EEG data with rows representing the electrodes
                        signal.srate = app.SamplingRate;
                        signal = cleanLineNoise(signal,app.CL_options); % [signal, lineNoiseOut]
                        NewData = signal.data';
                    end
                    disp('Line noise cleaning completed')
                end
                
                
                % CleanRawData
                if strcmp(app.CRD_Switch.Value,'On')
                    disp(['Applying CleanRawData (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,2);
                        %Channels.labels     = char(ElectrodeSetting.labels);

                        % Recompute Theta and Radius to match EEGlab orientation
                        [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                        Channels.theta      = -rad2deg(az);
                        Channels.radius     = 0.5 - rad2deg(elev)/180;
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.y;
                        Channels.Y          = -ElectrodeSetting.x;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                        Channels            = Channels(app.ChanCompIndices,:); % Keep only channels to process
                    catch
                        Channels = '';
                    end
                    EEG = eeglab_datastruct(openfilename,NewData,app.SamplingRate,events,Channels);
                    % convert structure to cell
                    options = fieldnames(app.CRD_options);
                    options(:,2) = struct2cell(app.CRD_options);
                    options = options([2 4:6 8:10 12:13],:);
                    options = options';
                    options = options(:)';
                    
                    % Compute clean_artifact() on all channels
                    optionsAllChan = options;
                    optionsAllChan(4) = {'off'};
                    optionsAllChan(6) = {'off'};
                    optionsAllChan(8) = {'off'};
                    EEG = clean_artifacts(EEG,optionsAllChan{:});
                    NewData = EEG.data';
                    
                    % Flag bad channels
                    if app.CRD_options.BadChan
                        disp(['Applying CleanRawData bad channels detection (file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ')'])
                        optionsBadChan = options;
                        optionsBadChan(2) = {'off'};    % Highpass off
                        optionsBadChan(10) = {'off'};   % BurstCriterion off
                        optionsBadChan(14) = {'off'};   % BurstRejection off
                        optionsBadChan(18) = {'off'};   % WindowCriterion off
                        [~,~,~,removed_channels] = clean_artifacts(EEG,optionsBadChan{:});
                        
                        try % Interpolation table already exists
                            app.SessionParameters.Interpolation.CRD{file,2:end} = removed_channels';
                            app.SessionParameters.Interpolation.CRD{file,1}     = 100 * (sum(removed_channels) / length(removed_channels));
                            PercentInterp = 100 * (sum(removed_channels) / length(removed_channels));
                            app.SessionParameters.Interpolation.CRD{file,1} = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
                            %app.SessionParameters.Interpolation.CRD{file,1} = arrayfun(@ (n) sprintf("%.1f", n),app.SessionParameters.Interpolation.CRD);
                        catch
                            %app.SessionParameters.Interpolation.CRD = app.DataTable(:,[1 2]);
                            try
                                ColumnNames = ['%', cellstr(strcat(ElectrodeSetting.labels, ' (',num2str(ElectrodeSetting.nr),')'))'];
                            catch
                                ColumnNames = ['%', cellstr(strcat('e',num2str((1:header.NumChan)')))'];
                            end
                            app.SessionParameters.Interpolation.CRD = table('Size',[height(app.DataTable) length(ColumnNames)],...
                                'VariableTypes',[{'string'}, cellstr(repmat('logical',length(ColumnNames)-1,1))'],'VariableNames',ColumnNames);
                            app.SessionParameters.Interpolation.CRD{file,2:end} = removed_channels';
                            PercentInterp = 100 * (sum(removed_channels) / length(removed_channels));
                            app.SessionParameters.Interpolation.CRD{file,1} = arrayfun(@ (n) sprintf("%.1f", n),PercentInterp);
                            %app.SessionParameters.Interpolation.CRD{file,1} = arrayfun(@ (n) sprintf("%.1f", n),app.SessionParameters.Interpolation.CRD{file,:});
                            
                            % For the Interpolation module
                            app.SessionParameters.Interpolation.CleanRawDataSwitch = 'Yes';
                        end
                    end
                end
                
                
                % Replace unselected channels from ChanCompTable (unprocessed)
                if ~isempty(app.ChanCompIndices)
                    UnprocessedIndices = table2array(app.ChanCompTable.Data(~[app.ChanCompTable.Data.process{:}]',1))';
                    for i = 1:length(UnprocessedIndices)
                        if UnprocessedIndices(i) == 1
                            NewData = [data(:,1), NewData];
                        elseif UnprocessedIndices(i) > size(NewData,2)
                            NewData = [NewData, data(:,UnprocessedIndices(i))];
                        else
                            NewData = [NewData(:,1:UnprocessedIndices(i)-1), data(:,UnprocessedIndices(i)), NewData(:,UnprocessedIndices(i):end)];
                        end
                    end
                end
                
                
                % Save processed file
                if ~isempty(app.SuffixString.Value)
                    suffix = ['_', app.SuffixString.Value];
                else
                    suffix = app.SuffixString.Value; % = '';
                end
                
                SubPath = app.DataTable.Path{file}(3:end);
                NewPath = [app.SaveFolder.Value{:} SubPath];
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end
                
                savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension.Value]);
                disp(['Saving ',savefilename])
                if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                    save_eph(savefilename,NewData,app.SamplingRate,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.sef')
                    try
                        %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                        Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                    catch
                        Channels = '';
                    end
                    save_sef(savefilename,NewData,app.SamplingRate,Channels,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.set')
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,[6 7 2]);
                        Channels.labels     = char(Channels.labels);
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.x;
                        Channels.Y          = ElectrodeSetting.y;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                    catch
                        Channels = '';
                    end
                    save_eeglab(savefilename,NewData,app.SamplingRate,events,header.firstindex,Channels)
                end

                if strcmpi(app.CRD_Switch.Value,'On') && app.CRD_options.BadChan
                    % Save the bad channels detected by CRD (in case processing is interrupted)
                    BadChanFileName = fullfile(app.SaveFolder.Value{:},[FileName,'_ASR_BadChannels.csv']);
                    writematrix(app.SessionParameters.Interpolation.CRD{file,:},BadChanFileName)
                end
                
                % Update Table
                ProcessedDataTable.(1)(file)        = {[FileName, suffix, app.FilesExtension.Value]};
                ProcessedDataTable.(3)(file)        = size(NewData,2);
                ProcessedDataTable.(4)(file)        = app.SamplingRate;
                ProcessedDataTable.(5)(file)        = size(NewData,1);
                
                
                % File completed
                tMid = toc(tStart);
                disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                disp('...............................................................')
            end
            delete(h);
            
            % UPDATE INFORMATION BASED ON THE PROCESSED DATA
            
            if strcmpi(app.SwitchResample.Value,'On')
                app.OriginalSamplingRate.Value = app.SamplingRate;
            end
            
            % Data table in the Main Window
            app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
            app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
            app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
            app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
            disp('Data table in the Main Window updated with the processed files')
            cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(NewData,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Channel names check  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';ChannelsCheck';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Filtering+ processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            rz = msgbox('The Filtering is done');
        end

        % Button pushed function: RetainButton
        function RetainButtonPushed(app, event)
            % Enable the Filtering+ button in main app
            app.EEGpalCS_Main.FilteringButton.Enable = 'on';
            
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            % Update parameters
            app.EEGpalCS_Main.Study_Parameters  = app.Study_Parameters;
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FilteringButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpalCS_Filtering
        function EEGpalCS_FilteringCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FilteringButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: SwitchFiltering
        function SwitchFilteringValueChanged(app, event)
            if strcmpi(app.SwitchFiltering.Value,'On')
                app.FilterType.Enable   = 'on';
                app.FilterType.Visible   = true;
                app.FilterType_2.Visible   = false;
                app.HPfreq.Enable       = 'on';
                app.HPorder.Enable      = 'on';
                app.LPfreq.Enable       = 'on';
                app.LPorder.Enable      = 'on';
                app.SwitchFilteringAlgo.Enable = 'on';
                
                if strcmp(app.SwitchFilteringAlgo.Value,'eeglab')
                    SwitchFilteringAlgoValueChanged(app);
                else
                    FilterPlots(app);
                end

                %app.SwitchFilteringAlgo.Value   = 'Matlab';
                app.SingalProcessingtoolboxLabel.Enable='on';
                
            else
                app.FilterType.Enable   = 'off';
                app.FilterType.Visible   = true;
                app.FilterType_2.Enable   = 'off';
                app.FilterType_2.Visible   = false;
                app.HPfreq.Enable       = 'off';
                app.HPorder.Enable      = 'off';
                app.LPfreq.Enable       = 'off';
                app.LPorder.Enable      = 'off';
                app.SwitchFilteringAlgo.Enable = 'off';
                app.SingalProcessingtoolboxLabel.Enable='off';
                cla(app.UIAxesHPfreq);
                cla(app.UIAxesLPfreq);
            end
        end

        % Value changed function: SwitchResample
        function SwitchResampleValueChanged(app, event)
            if strcmpi(app.SwitchResample.Value,'On')
                app.NewSamplingRate.Enable = 'on';
                app.SamplingRate = app.NewSamplingRate.Value;
            else
                app.NewSamplingRate.Enable = 'off';
                app.SamplingRate = app.OriginalSamplingRate.Value(1);
            end
            
            % Plot the data
            FilterPlots(app);
        end

        % Value changed function: CRD_Switch
        function CRD_SwitchValueChanged(app, event)
            if strcmpi(app.CRD_Switch.Value,'On')
                app.CRD_Drift.Enable            = 'on';
                app.CRD_BadChan.Enable          = 'on';
                app.CRD_ASR.Enable              = 'on';
                app.CRD_BadPeriods.Enable       = 'on';
                app.CRD_AllOptionsButton.Enable = 'on';
            else
                app.CRD_Drift.Enable            = 'off';
                app.CRD_BadChan.Enable          = 'off';
                app.CRD_ASR.Enable              = 'off';
                app.CRD_BadPeriods.Enable       = 'off';
                app.CRD_AllOptionsButton.Enable = 'off';
            end
        end

        % Value changed function: SwitchFilteringAlgo
        function SwitchFilteringAlgoValueChanged(app, event)
            if strcmpi(app.SwitchFilteringAlgo.Value,'eeglab')
                app.FilterType.Enable   = 'off';
                app.FilterType.Visible   = false;
                app.FilterType_2.Enable   = 'on';
                app.FilterType_2.Visible   = true;
                
                if strcmp(app.FilterType_2.Value,'Band-pass')
                    app.HPfreq.Enable       = 'on';
                    app.HPorder.Enable      = 'off';
                    app.LPfreq.Enable       = 'on';
                    app.LPorder.Enable      = 'off';
                elseif strcmp(app.FilterType_2.Value,'High-pass')
                    app.HPfreq.Enable       = 'on';
                    app.HPorder.Enable      = 'off';
                    app.LPfreq.Enable       = 'off';
                    app.LPorder.Enable      = 'off';
                else
                    app.HPfreq.Enable       = 'off';
                    app.HPorder.Enable      = 'off';
                    app.LPfreq.Enable       = 'on';
                    app.LPorder.Enable      = 'off';
                end

                app.UIAxesHPfreq.Visible = 'off'; cla(app.UIAxesHPfreq);
                app.UIAxesLPfreq.Visible = 'off'; cla(app.UIAxesLPfreq);
                
                app.LineNoise.Items = [{'Off'},{'CleanLine'}]; %desactivate the Notch in eeglab mode
                app.LineNoise.Value = 'Off';
                app.CL_LineFrequencies.Enable   = 'off';
                app.CL_Bandwidth.Enable         = 'off';
                app.CL_AllOptionsButton.Enable  = 'off';
                cla(app.UIAxesNotch);
                
            else %Matlab/Signal processing toolbox case
                app.FilterType.Enable   = 'on';
                app.FilterType.Visible   = true;
                app.FilterType_2.Enable   = 'off';
                app.FilterType_2.Visible   = false;
                if strcmp(app.FilterType.Value, 'High+Low-pass')                
                    app.HPfreq.Enable       = 'on';
                    app.HPorder.Enable      = 'on';
                    app.LPfreq.Enable       = 'on';
                    app.LPorder.Enable      = 'on';
                elseif strcmp(app.FilterType.Value, 'High-pass')
                    app.HPfreq.Enable       = 'on';
                    app.HPorder.Enable      = 'on';
                    app.LPfreq.Enable       = 'off';
                    app.LPorder.Enable      = 'off';
                else
                    app.HPfreq.Enable       = 'off';
                    app.HPorder.Enable      = 'off';
                    app.LPfreq.Enable       = 'on';
                    app.LPorder.Enable      = 'on';
                end
                app.UIAxesHPfreq.Visible = 'on'; 
                app.UIAxesLPfreq.Visible = 'on'; 
                FilterPlots(app);
                
                app.LineNoise.Items = [{'Off'},{'50 Hz notch'},{'60 Hz notch'},{'CleanLine'}];
                app.LineNoise.Value = 'Off';
                app.CL_LineFrequencies.Enable   = 'off';
                app.CL_Bandwidth.Enable         = 'off';
                app.CL_AllOptionsButton.Enable  = 'off';
                cla(app.UIAxesNotch);

            end
        end

        % Value changed function: FilterType_2
        function FilterType_2ValueChanged(app, event)
             if strcmp(app.FilterType_2.Value,'Band-pass')
                app.HPfreq.Enable       = 'on';                
                app.LPfreq.Enable       = 'on';                
             elseif strcmp(app.FilterType_2.Value,'High-pass')
                app.HPfreq.Enable       = 'on';             
                app.LPfreq.Enable       = 'off';                
             elseif strcmp(app.FilterType_2.Value,'Low-pass')
                app.HPfreq.Enable       = 'off';                
                app.LPfreq.Enable       = 'on';               
            end
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_Filtering_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Filtering_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Filtering_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEGpalCS_Filtering.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {760, 760, 760};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {760, 760};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {272, '1x', 272};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_Filtering = uifigure('Visible', 'off');
            app.EEGpalCS_Filtering.Name = 'EEGpal_Filtering';
            app.EEGpalCS_Filtering.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_FilteringCloseRequest, true);
            app.EEGpalCS_Filtering.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEGpalCS_Filtering);
            app.LeftPanel = uipanel(app.GridLayout);
            app.FILTERINGLabel_2 = uilabel(app.LeftPanel);
            app.FILTERINGLabel_2.Text = 'FILTERING+';
            app.ChannelsToProcessLabel = uilabel(app.LeftPanel);
            app.ChannelsToProcessLabel.Text = 'Channels to process';
            app.ChanCompInstruction = uilabel(app.LeftPanel);
            app.ChanCompInstruction.Tooltip = {'Resampling is not affected, as to remain in the data, all channels have to have the same sampling rate.'};
            app.ChanCompInstruction.Text = {'Unselected will not be processed, but will'; 'not be removed from the data'};
            app.ChanCompTable = uitable(app.LeftPanel);
            app.ChanCompTable.ColumnName = {'nr'; 'labels'; 'process'};
            app.ChanCompTable.RowName = {};
            app.ChanCompTable.ColumnEditable = [false false true];
            app.ChanCompTable.CellEditCallback = createCallbackFcn(app, @ChanCompTableCellEdit, true);
            app.SelectAll = uibutton(app.LeftPanel, 'push');
            app.SelectAll.Enable = 'off';
            app.SelectAll.Text = 'Select all';
            app.DeselectAll = uibutton(app.LeftPanel, 'push');
            app.DeselectAll.Enable = 'off';
            app.DeselectAll.Text = 'Deselect all';
            app.SamplingRateInstruction = uilabel(app.LeftPanel);
            app.SamplingRateInstruction.Text = {'If the new sampling rate is the same as the '; 'old one, I will do nothing, I promise!'};
            app.ReSamplingWarningLabel = uilabel(app.LeftPanel);
            app.ReSamplingWarningLabel.Text = {'/!\ CHANGING THE SAMPLING RATE'; '    WILL CHANGE THE FILTER '; '    FREQUENCY RESPONSES'; '    Make sure to verify the filter graphs '; '    when changing the sampling rate'};
            app.NewsamplingrateEditFieldLabel = uilabel(app.LeftPanel);
            app.NewsamplingrateEditFieldLabel.Text = 'New sampling rate:';
            app.NewSamplingRate = uieditfield(app.LeftPanel, 'numeric');
            app.NewSamplingRate.ValueChangedFcn = createCallbackFcn(app, @NewSamplingRateValueChanged, true);
            app.NewSamplingRate.Enable = 'off';
            app.NewSamplingRate.Value = 512;
            app.OriginalsamplingrateLabel = uilabel(app.LeftPanel);
            app.OriginalsamplingrateLabel.Text = 'Original sampling rate:';
            app.OriginalSamplingRate = uieditfield(app.LeftPanel, 'numeric');
            app.OriginalSamplingRate.Editable = 'off';
            app.OriginalSamplingRate.Tooltip = {'Sampling Rate of the first file of the first participant.'; 'If sampling rate is inconsistent between subjects, reseample the data.'};
            app.OriginalSamplingRate.Value = 1;
            app.RESAMPLELabel_2 = uilabel(app.LeftPanel);
            app.RESAMPLELabel_2.Text = 'RESAMPLE';
            app.SwitchResample = uiswitch(app.LeftPanel, 'slider');
            app.SwitchResample.ValueChangedFcn = createCallbackFcn(app, @SwitchResampleValueChanged, true);
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxesHPfreq = uiaxes(app.CenterPanel);
            title(app.UIAxesHPfreq, 'High-pass filter frequency response')
            xlabel(app.UIAxesHPfreq, 'Frequency [Hz]')
            ylabel(app.UIAxesHPfreq, 'Filter gain')
            app.UIAxesHPfreq.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxesLPfreq = uiaxes(app.CenterPanel);
            title(app.UIAxesLPfreq, 'Low-pass filter frequency response')
            xlabel(app.UIAxesLPfreq, 'Frequency [Hz]')
            ylabel(app.UIAxesLPfreq, 'Filter gain')
            app.UIAxesLPfreq.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxesNotch = uiaxes(app.CenterPanel);
            title(app.UIAxesNotch, 'Notch')
            xlabel(app.UIAxesNotch, 'Frequency [Hz]')
            ylabel(app.UIAxesNotch, 'Filter gain')
            app.UIAxesNotch.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.FilteringInstruction = uilabel(app.CenterPanel);
            app.FilteringInstruction.Text = {'For Matlab mode: '; 'check on the graphs to make sure that the filter will remove correctly '; 'the undesired frequencies without '; 'modulating the desired ones.'; 'Adjust the filter order if necessary. Higher'; 'order (usually) gives better results, but is '; 'slower to compute.'; ''; 'For EEGlab mode:'; 'filter order are automatically compute by pop_eegfiltnew (see help of that function). '};
            app.HighpassfrequencyHzEditFieldLabel = uilabel(app.CenterPanel);
            app.HighpassfrequencyHzEditFieldLabel.Text = 'High-pass frequency [Hz]:';
            app.HPfreq = uieditfield(app.CenterPanel, 'numeric');
            app.HPfreq.ValueChangedFcn = createCallbackFcn(app, @HPfreqValueChanged, true);
            app.HPfreq.Enable = 'off';
            app.HPfreq.Value = 0.5;
            app.LowpassfrequencyHzEditFieldLabel = uilabel(app.CenterPanel);
            app.LowpassfrequencyHzEditFieldLabel.Text = 'Low-pass frequency [Hz]:';
            app.LPfreq = uieditfield(app.CenterPanel, 'numeric');
            app.LPfreq.ValueChangedFcn = createCallbackFcn(app, @LPfreqValueChanged, true);
            app.LPfreq.Enable = 'off';
            app.LPfreq.Value = 40;
            app.FiltertypeDropDownLabel = uilabel(app.CenterPanel);
            app.FiltertypeDropDownLabel.Text = 'Filter type:';
            app.FilterType = uidropdown(app.CenterPanel);
            app.FilterType.Items = {'High+Low-pass', 'High-pass', 'Low-pass'};
            app.FilterType.ValueChangedFcn = createCallbackFcn(app, @FilterTypeValueChanged, true);
            app.FilterType.Enable = 'off';
            app.FilterType.Value = 'High+Low-pass';
            app.HighpassfilterorderEditFieldLabel = uilabel(app.CenterPanel);
            app.HighpassfilterorderEditFieldLabel.Text = 'High-pass filter order:';
            app.HPorder = uieditfield(app.CenterPanel, 'numeric');
            app.HPorder.ValueChangedFcn = createCallbackFcn(app, @HPorderValueChanged, true);
            app.HPorder.Enable = 'off';
            app.HPorder.Value = 3;
            app.LowpassfilterorderEditFieldLabel = uilabel(app.CenterPanel);
            app.LowpassfilterorderEditFieldLabel.Text = 'Low-pass filter order:';
            app.LPorder = uieditfield(app.CenterPanel, 'numeric');
            app.LPorder.ValueChangedFcn = createCallbackFcn(app, @LPorderValueChanged, true);
            app.LPorder.Enable = 'off';
            app.LPorder.Value = 16;
            app.VizualizeoriginalandcleanedspectraLabel_2 = uilabel(app.CenterPanel);
            app.VizualizeoriginalandcleanedspectraLabel_2.Enable = 'off';
            app.VizualizeoriginalandcleanedspectraLabel_2.Visible = 'off';
            app.VizualizeoriginalandcleanedspectraLabel_2.Text = {'Vizualize original and '; 'cleaned spectra:'};
            app.FilteredPowerSpectra = uicheckbox(app.CenterPanel);
            app.FilteredPowerSpectra.Enable = 'off';
            app.FilteredPowerSpectra.Visible = 'off';
            app.FilteredPowerSpectra.Text = '';
            app.LinenoiseInstruction = uilabel(app.CenterPanel);
            app.LinenoiseInstruction.Text = {'If you are not interested in the signal '; 'around line frequency, a notch is fine.'; 'Otherwise, CleanLine is better.'};
            app.LINENOISEREMOVALLabel = uilabel(app.CenterPanel);
            app.LINENOISEREMOVALLabel.Text = 'LINE NOISE REMOVAL';
            app.LineNoise = uidropdown(app.CenterPanel);
            app.LineNoise.Items = {'Off', '50 Hz notch', '60 Hz notch', 'CleanLine'};
            app.LineNoise.ValueChangedFcn = createCallbackFcn(app, @LineNoiseValueChanged, true);
            app.LineNoise.Value = 'Off';
            app.CleanLinePanel = uipanel(app.CenterPanel);
            app.CleanLinePanel.Title = 'CleanLineNoise (eeglab plugin)';
            app.CleanLinePanel.Visible = 'off';
            app.LineFrequenciesHzLabel_2 = uilabel(app.CleanLinePanel);
            app.LineFrequenciesHzLabel_2.Text = 'Line Frequencies [Hz]:';
            app.CL_LineFrequencies = uieditfield(app.CleanLinePanel, 'numeric');
            app.CL_LineFrequencies.ValueChangedFcn = createCallbackFcn(app, @CL_LineFrequenciesValueChanged, true);
            app.CL_LineFrequencies.Enable = 'off';
            app.CL_LineFrequencies.Value = 50;
            app.CL_LineFrequenciesInfo = uilabel(app.CleanLinePanel);
            app.CL_LineFrequenciesInfo.Text = {'CleanLineNoise will clean the line '; 'frequency above and the first three '; 'harmonics (e.g., 50, 100, 150, 200)'};
            app.BandwidthHzLabel_2 = uilabel(app.CleanLinePanel);
            app.BandwidthHzLabel_2.Text = 'Bandwidth [Hz]:';
            app.CL_Bandwidth = uieditfield(app.CleanLinePanel, 'numeric');
            app.CL_Bandwidth.ValueChangedFcn = createCallbackFcn(app, @CL_BandwidthValueChanged, true);
            app.CL_Bandwidth.Enable = 'off';
            app.CL_Bandwidth.Value = 2;
            app.CL_AllOptionsButton = uibutton(app.CleanLinePanel, 'push');
            app.CL_AllOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @CL_AllOptionsButtonPushed, true);
            app.CL_AllOptionsButton.Enable = 'off';
            app.CL_AllOptionsButton.Text = 'All options';
            app.FILTERINGLabel_3 = uilabel(app.CenterPanel);
            app.FILTERINGLabel_3.Text = 'FILTERING';
            app.SwitchFiltering = uiswitch(app.CenterPanel, 'slider');
            app.SwitchFiltering.ValueChangedFcn = createCallbackFcn(app, @SwitchFilteringValueChanged, true);
            app.ScipttouseforFILTERINGLabel = uilabel(app.CenterPanel);
            app.ScipttouseforFILTERINGLabel.Text = 'Scipt to use for FILTERING';
            app.SwitchFilteringAlgo = uiswitch(app.CenterPanel, 'slider');
            app.SwitchFilteringAlgo.Items = {'Matlab', 'eeglab'};
            app.SwitchFilteringAlgo.ValueChangedFcn = createCallbackFcn(app, @SwitchFilteringAlgoValueChanged, true);
            app.SwitchFilteringAlgo.Enable = 'off';
            app.SwitchFilteringAlgo.Value = 'Matlab';
            app.SingalProcessingtoolboxLabel = uilabel(app.CenterPanel);
            app.SingalProcessingtoolboxLabel.Enable = 'off';
            app.SingalProcessingtoolboxLabel.Text = 'Singal Processing toolbox';
            app.FilterType_2 = uidropdown(app.CenterPanel);
            app.FilterType_2.Items = {'Band-pass', 'High-pass', 'Low-pass'};
            app.FilterType_2.ValueChangedFcn = createCallbackFcn(app, @FilterType_2ValueChanged, true);
            app.FilterType_2.Enable = 'off';
            app.FilterType_2.Visible = 'off';
            app.FilterType_2.Value = 'Band-pass';
            app.RightPanel = uipanel(app.GridLayout);
            app.CleanRawDataInstructionLabel = uilabel(app.RightPanel);
            app.CleanRawDataInstructionLabel.Text = {'Removal of non-stationnary artefacts. '; 'Bad channels will be flagged (and not '; 'removed) in the interpolation window.'};
            app.CRD_WarningLabel = uilabel(app.RightPanel);
            app.CRD_WarningLabel.Text = {'/!\ USE ONLY ON CONTINUOUS DATA'; '    Before segmenting the data into epochs.'; '    The same is recommended for the other'; '    options in this window.'};
            app.RemovechanneldriftifnotalreadyhighpassedLabel = uilabel(app.RightPanel);
            app.RemovechanneldriftifnotalreadyhighpassedLabel.Text = {'Remove channel drift '; '(if not already high-passed):'};
            app.CRD_Drift = uicheckbox(app.RightPanel);
            app.CRD_Drift.ValueChangedFcn = createCallbackFcn(app, @CRD_DriftValueChanged, true);
            app.CRD_Drift.Enable = 'off';
            app.CRD_Drift.Tooltip = {'When check here, high-pass transition band set to default values: [0.25 0.75].'; 'To change it, go to ''All options''.'};
            app.CRD_Drift.Text = '';
            app.FlagbadchannelsLabel = uilabel(app.RightPanel);
            app.FlagbadchannelsLabel.Text = 'Flag bad channels:';
            app.CRD_BadChan = uicheckbox(app.RightPanel);
            app.CRD_BadChan.ValueChangedFcn = createCallbackFcn(app, @CRD_BadChanValueChanged, true);
            app.CRD_BadChan.Enable = 'off';
            app.CRD_BadChan.Tooltip = {'When check here, flat line and channel correlation criteria set to default values: [5 0.8].'; 'By default, line noise detection is always set to ''off''.'; 'To change it, go to ''All options''.'};
            app.CRD_BadChan.Text = '';
            app.CRD_BadChan.Value = true;
            app.ASRbadburstcorrectionLabel = uilabel(app.RightPanel);
            app.ASRbadburstcorrectionLabel.Text = 'ASR bad burst correction:';
            app.CRD_ASR = uicheckbox(app.RightPanel);
            app.CRD_ASR.ValueChangedFcn = createCallbackFcn(app, @CRD_ASRValueChanged, true);
            app.CRD_ASR.Enable = 'off';
            app.CRD_ASR.Tooltip = {'When check here, max acceptable 0.5 window std set to default value: 20.'; 'To change it, go to ''All options''.'};
            app.CRD_ASR.Text = '';
            app.CRD_ASR.Value = true;
            app.AdditionalremovalofbaddataperiodsLabel = uilabel(app.RightPanel);
            app.AdditionalremovalofbaddataperiodsLabel.Text = {'Additional removal of bad '; 'data periods:'};
            app.CRD_BadPeriods = uicheckbox(app.RightPanel);
            app.CRD_BadPeriods.ValueChangedFcn = createCallbackFcn(app, @CRD_BadPeriodsValueChanged, true);
            app.CRD_BadPeriods.Enable = 'off';
            app.CRD_BadPeriods.Tooltip = {'When check here, maximal out-of-bounds channels set to default value: 25%.'; 'To change it, go to ''All options''.'};
            app.CRD_BadPeriods.Text = '';
            app.CRD_AllOptionsButton = uibutton(app.RightPanel, 'push');
            app.CRD_AllOptionsButton.ButtonPushedFcn = createCallbackFcn(app, @CRD_AllOptionsButtonPushed, true);
            app.CRD_AllOptionsButton.Enable = 'off';
            app.CRD_AllOptionsButton.Text = 'All options';
            app.OUTPUTOPTIONSLabel = uilabel(app.RightPanel);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.RightPanel);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.RightPanel);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.RightPanel);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.RightPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.RightPanel);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.RightPanel);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.RightPanel);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.RightPanel, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'filtered';
            app.InputParametersFile = uilabel(app.RightPanel);
            app.InputParametersFile.Text = 'Parameters';
            app.OpenButton = uibutton(app.RightPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.RightPanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Update an existing file.'; 'Will erase preexisting Filtering options.'};
            app.SaveButton.Text = 'Save';
            app.ParameterfileEditFieldLabel = uilabel(app.RightPanel);
            app.ParameterfileEditFieldLabel.Text = 'Parameter file:';
            app.ParameterFilePath = uieditfield(app.RightPanel, 'text');
            app.ParameterFilePath.Editable = 'off';
            app.APPLYLabel = uilabel(app.RightPanel);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.RightPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.RetainButton = uibutton(app.RightPanel, 'push');
            app.RetainButton.ButtonPushedFcn = createCallbackFcn(app, @RetainButtonPushed, true);
            app.RetainButton.Tooltip = {'Return to the main window'};
            app.RetainButton.Text = 'Retain';
            app.CancelButton = uibutton(app.RightPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.CLEANRAWDATAeeglabpluginLabel = uilabel(app.RightPanel);
            app.CLEANRAWDATAeeglabpluginLabel.Text = {'CLEAN RAWDATA'; '(eeglab plugin)'};
            app.CRD_Switch = uiswitch(app.RightPanel, 'slider');
            app.CRD_Switch.ValueChangedFcn = createCallbackFcn(app, @CRD_SwitchValueChanged, true);
            % Show the figure after all components are created
            app.EEGpalCS_Filtering.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Filtering(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_Filtering)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_Filtering)
        end
    end
end