classdef EEGpalCS_mod_FindPeaks < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpal_FindPeak                 matlab.ui.Figure
        FindpeaksinERPsorAveragevalueextractionLabel  matlab.ui.control.Label
        OUTPUTOPTIONSLabel              matlab.ui.control.Label
        InstructionFolderLabel          matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        SavingfolderLabel               matlab.ui.control.Label
        SaveFolder                      matlab.ui.control.TextArea
        APPLYLabel                      matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        InstructModule                  matlab.ui.control.Label
        InstructElements1               matlab.ui.control.Label
        Epoch_RelativeInterval1         matlab.ui.control.Label
        Epoch_RelativeInterval1_2       matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        tfLabel                         matlab.ui.control.Label
        IntervalOnset_tf                matlab.ui.control.NumericEditField
        IntervalonsetLabel              matlab.ui.control.Label
        IntervalOnset_ms                matlab.ui.control.NumericEditField
        IntervalOffset_tf               matlab.ui.control.NumericEditField
        EndOfFile                       matlab.ui.control.StateButton
        IntervaloffsetLabel             matlab.ui.control.Label
        IntervalOffset_ms               matlab.ui.control.NumericEditField
        ElectrodesEditFieldLabel        matlab.ui.control.Label
        ElectrodesEditField             matlab.ui.control.EditField
        Epoch_RelativeInterval1_3       matlab.ui.control.Label
        AverageselectedelectrodesCheckBox  matlab.ui.control.CheckBox
        GFPCheckBox                     matlab.ui.control.CheckBox
        DissCheckBox                    matlab.ui.control.CheckBox
        SamplingRateinHzEditFieldLabel  matlab.ui.control.Label
        SamplingRate                    matlab.ui.control.NumericEditField
        Instruct_timeinter1             matlab.ui.control.Label
        PeakDropDownLabel               matlab.ui.control.Label
        PeakDropDown                    matlab.ui.control.DropDown
        InstructPeak1                   matlab.ui.control.Label
        InstructWarning                 matlab.ui.control.Label
        SelectOutputTime                matlab.ui.control.DropDown
        InstructionOutputTime           matlab.ui.control.Label
        SuffixinoutputfilenameEditFieldLabel  matlab.ui.control.Label
        SuffixinoutputfilenameEditField  matlab.ui.control.EditField
        DoyouwanttosavealsotheCheckfileDropDownLabel  matlab.ui.control.Label
        CheckfileDropDown               matlab.ui.control.DropDown
        InstructionOutputTime_2         matlab.ui.control.Label
        WhatprocessingshouldIdoDropDownLabel  matlab.ui.control.Label
        ProcessingChoice                matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        SettingTable
    end
    
    properties (Access = public)
        SessionParameters
        Study_Parameters
    end
    
    methods (Access = private)


        function tf = ms2tf(~,ms,sr)
            tf = round((ms / 1000) * sr, 0); % round() because time-frames are counted as integer numbers
                                             % => requires to recompute tf2ms for inputs, to be more precise
        end
       
        
        function ms = tf2ms(~,tf,sr)
            ms = (tf / sr) * 1000;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            %Sampling Rate
            app.SamplingRate.Value          = app.DataTable.SamplingRate(1);

        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %number of files
            [nbFiles,~]=size(app.DataTable); 
            
            
            %% CHECK
            
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            if app.SamplingRate.Value == 1 || app.SamplingRate.Value == 0
                 msgbox(sprintf('%s\n\n%s','Missing Sampling rate!', ...
                        'The sampling rate is not specified. Please specify it before to continue'),...
                        'Missing Sampling rate','warn');
                 return     
            end
            
            if app.IntervalOffset_tf.Value == 0
                 msgbox(sprintf('%s\n\n%s','Incorrect interval!', ...
                        'Interval is incorrect. Please specify on offset value'),...
                        'Missing Sampling rate','warn');
                        return 
            end
            
                        
            Run = questdlg(sprintf('%s\n%s\n\n%s\n\n%s',...
                'Guten Tag!',...
                'Me about to detect the peak (global maxima) in the specified interval and electrodes.',...
                'I will record an .xlsx Excel file at the specified location',...
                'Do you wish to proceed? '),'Run checked options','OK','Cancel','Cancel');
            
            if strcmpi(Run,'Cancel')
                return
            end
            
           
            %% PROCESSING INFORMATION SHEET
            
            currentVersionModule = dir([mfilename('fullpath') '.mlapp']); % Information about current file


            %% RUN MAX AMPLITUDE 
            
            %initivalisation of output
            output_nbColomn=0;
            output=cell(nbFiles,output_nbColomn); %will contains values
            outputHeader=cell(1,output_nbColomn); %will contains headers
            
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run Max Amplitude detection...')
            StartTime = datetime('now');
            tStart = tic;
            h = waitbar(0,'Processing.....');
            
            for file = 1:nbFiles  % Load file by file
                waitbar(file/nbFiles,h, strcat('Processing File ',num2str(file),'/',num2str(nbFiles)));
                
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
%                     events = [events.latency(:)-1, events.latency(:)-1, events{:,2}]; % Convert to Cartool MRK format (onset, offset, code)
%                     if ismember('duration', events.Properties.VariableNames)
%                         events(:,2) = events.latency(:)-1 + events.duration(:);
%                     end
%                     FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                elseif strcmpi(FileExtension,'.ris')
                    [header,data] = open_ris(openfilename);
                else
                    if strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')                       
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    %continue
                end
                disp('File loaded!')
                                
                %check if event are cell
                if iscell(events)
                    events=cell2mat(events);
                end
                
                %correct the event indice from Cartool (0) to Matlab (1)
                if header.firstindex == 0
                    events(:,1)=events(:,1)+1;
                    events(:,2)=events(:,2)+1;
                end


                %check the number of specified electrodes
                electrodesSpec=0;
                if ~isempty(app.ElectrodesEditField.Value)
                    electrodesSpec=str2num(app.ElectrodesEditField.Value);
                end
                
                %Initiate average computation
                sumElectrodes=0;
                
                
                %Go through each electrodes
                for j=1:length(electrodesSpec) %loop over electrodes
                    if strcmp (app.PeakDropDown.Value,'Positive')                    
                        [peakValue,peakPos]= findpeaks(data(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,electrodesSpec(j)),app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1); %positive peak
                    else
                        [peakValue,peakPos]= findpeaks(-data(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,electrodesSpec(j)),app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1); %negative peak
                        peakValue=-peakValue;
                    end
                end


%                     %set the start position for the current segment
%                     posTriggerStart=find(events(:,3)==app.CutTable.Data.Start_Trigger(workingIndices(j)));
%                     
%                     if length(posTriggerStart)>1 %problem of several occurence of the same trigger
%                         disp('The start trigger has several occurence')
%                         NewDataConcatenate=[]; %need to concatenate files
%                         eventsConcatenated0=[];
%                     end
%                     startTFPosition=events(posTriggerStart,1);
%                     
%                     %set the end position for the current segment
%                     if isnan(app.CutTable.Data.End_Value(workingIndices(j)))
%                         endTFPosition=size(data,1); %case where you go until the end of the file. 
%                     elseif app.CutTable.Data.End_Trigger(workingIndices(j)) %case where the end is specified by a trigger                   
%                         posTriggerEnd=find(events(:,3)==app.CutTable.Data.End_Value(workingIndices(j)));
%                         if length(posTriggerEnd) ~= length(posTriggerStart) %problem of several occurence of the same trigger
%                             msgbox(sprintf('%s\n\n%s\n\n%s','The number of occurences of the specified End_Point trigger is different that the number of occurences of Start_Trigger for the file:', FileName, 'This case is not manage by this module. Please fix manually your marker file or use a fix duration instead'),...
%                             'Problem of inequal number of Start Trigger and End Point Trigger','error');
%                            disp('Processing Abort!')
%                            delete (h);
%                            return
%                         end
%                         endTFPosition=events(posTriggerEnd,1)-1;  
%                       
%                     else %case where the end if specified by a fix duration in second
%                         durationComputeNbTF=round(app.CutTable.Data.End_Value(j)/(1/app.DataTable.SamplingRate(file)));
%                         endTFPosition=startTFPosition+durationComputeNbTF; %this line code work also if the startTFPosition dimension is bigger than 1 (serveral occurence of Start_Trigger)     
%                     end
%                         
%                     
%                     for k=1:length(posTriggerStart) %manage if serveral occurence of Start_Trigger    
%                           
%                         if endTFPosition(k)>size(data,1) %check if the endPoint is outside of the eegfile
%                             msgbox(sprintf('%s\n\n%s\n\n%s','The specified End_Point duration is outside of the EEG file (duration value too big) for the file:', FileName, 'Please check the entered values.'),...
%                                 'End Point outside of the eeg file','error'); 
%                             disp('Processing Abort!')
%                             delete (h);
%                             return
%                         end
%                     
%                     
%                         %preparation of the cut file
%                         
%                         MRK=zeros(1,3); %creation MRK factice (nécessaire pour appeler la fonction save_sef
%                         
%                         NewData = [];
%                         NewData = [NewData; data(startTFPosition(k):endTFPosition(k),:)];
%                         MRK(1,:)= [1,1,app.CutTable.Data.Start_Trigger(workingIndices(j))];
%                         
%                         %look if there is any other trigger/event if the period of interest
%                         tempPos=1;
%                         for z=1:size(events,1)
%                             if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
%                                 tempPos=tempPos+1;
%                                 MRK(tempPos,1)=events(z,1)-startTFPosition(k)+1; %need to correct for +1 because of correction header.first index at recording
%                                 MRK(tempPos,2)=events(z,1)-startTFPosition(k)+1;
%                                 MRK(tempPos,3)=events(z,3);
%                             end
%                         end
%                         
%                         % Concatenation between segments
%                         if app.CutTable.Data.Concatenate(j)==1 & app.concatenate.Value %concatenate1
%                             indiceConcatenate1=indiceConcatenate1+1;
%                             eventsConcatenated1(indiceConcatenate1,:)=[size(EEGconcatenated1,1)+1, size(EEGconcatenated1,1)+1, app.CutTable.Data.Start_Trigger(workingIndices(j))];                           
%                             
%                             %look if there is any other trigger/event if the period of interest
%                             for z=1:size(events,1)
%                                 if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
%                                     indiceConcatenate1=indiceConcatenate1+1;
%                                     eventsConcatenated1(indiceConcatenate1,1)=events(z,1)-startTFPosition(k)+size(EEGconcatenated1,1)+1;
%                                     eventsConcatenated1(indiceConcatenate1,2)=events(z,1)-startTFPosition(k)+size(EEGconcatenated1,1)+1;
%                                     eventsConcatenated1(indiceConcatenate1,3)=events(z,3);
%                                 end
%                             end
%                             
%                             EEGconcatenated1=vertcat(EEGconcatenated1,NewData);
%                         end
%                         if app.CutTable.Data.Concatenate(j)==2 & app.concatenate.Value %concatenate2
%                             indiceConcatenate2=indiceConcatenate2+1;
%                             eventsConcatenated2(indiceConcatenate2,:)=[size(EEGconcatenated2,1)+1, size(EEGconcatenated2,1)+1, app.CutTable.Data.Start_Trigger(workingIndices(j))];
%                             
%                             %look if there is any other trigger/event if the period of interest
%                             for z=1:size(events,1)
%                                 if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
%                                     indiceConcatenate2=indiceConcatenate2+1;
%                                     eventsConcatenated2(indiceConcatenate2,1)=events(z,1)-startTFPosition(k)+size(EEGconcatenated2,1)+1;
%                                     eventsConcatenated2(indiceConcatenate2,2)=events(z,1)-startTFPosition(k)+size(EEGconcatenated2,1)+1;
%                                     eventsConcatenated2(indiceConcatenate2,3)=events(z,3);
%                                 end
%                             end
%                             
%                             EEGconcatenated2=vertcat(EEGconcatenated2,NewData);
%                         end
%                         if app.CutTable.Data.Concatenate(j)==3 & app.concatenate.Value %concatenate3
%                             indiceConcatenate3=indiceConcatenate3+1;
%                             eventsConcatenated3(indiceConcatenate3,:)=[size(EEGconcatenated3,1)+1, size(EEGconcatenated3,1)+1, app.CutTable.Data.Start_Trigger(workingIndices(j))];
%                             
%                             %look if there is any other trigger/event if the period of interest
%                             for z=1:size(events,1)
%                                 if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
%                                     indiceConcatenate3=indiceConcatenate3+1;
%                                     eventsConcatenated3(indiceConcatenate3,1)=events(z,1)-startTFPosition(k)+size(EEGconcatenated3,1)+1;
%                                     eventsConcatenated3(indiceConcatenate3,2)=events(z,1)-startTFPosition(k)+size(EEGconcatenated3,1)+1;
%                                     eventsConcatenated3(indiceConcatenate3,3)=events(z,3);
%                                 end
%                             end
%                             
%                             EEGconcatenated3=vertcat(EEGconcatenated3,NewData);
%                         end
%                         
%                         
%                         
%                         % Save processed file
%                         
%                         %suffix filename
%                         if ~ismissing(app.CutTable.Data.Suffix_String(workingIndices(j))) %case the user specified a suffix string
%                             suffix = app.CutTable.Data.Suffix_String(workingIndices(j));
%                         else %case the user did not specify a suffix
%                             suffix = strcat("Tigger",num2str(app.CutTable.Data.Start_Trigger(workingIndices(j))));
%                         end
%                         
%                         %Generate the outputfilename
%                         if length(posTriggerStart)>1
%                             outputfilename=strcat(app.DataTable.File{file}(1:end-4),'_',suffix,'_',num2str(k), app.FilesExtension.Value);
%                         else
%                             outputfilename=strcat(app.DataTable.File{file}(1:end-4),'_',suffix,app.FilesExtension.Value);
%                         end
%                         
%                         %complete path of recording
%                         if app.SaveinseparatefoldersButton.Value %case of subdirectory
%                             SubPath=strcat(app.SaveFolder.Value{1},'\',suffix);
%                             if ~exist(SubPath, 'dir') %create folder
%                                 mkdir(SubPath);
%                             end
%                             savefilename=strcat(SubPath,'\',outputfilename);
%                         else %case save in one folder 
%                             savefilename=strcat(app.SaveFolder.Value{1},'\',outputfilename);
%                         end
%        
%                         %saving
%                         disp(['Saving ',savefilename])
%                         if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph') %manage the output format
%                             save_eph(savefilename,NewData,header.SamplingRate,MRK,1) %event start at 1
%                         elseif strcmp(app.FilesExtension.Value,'.sef')
%                             try
%                                 %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
%                                 Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
%                             catch
%                                 Channels = '';
%                             end
%                             save_sef(savefilename,NewData,header.SamplingRate,Channels,MRK,1) %event start at 1
%                         elseif strcmp(app.FilesExtension.Value,'.set')
%                             savefilename = convertStringsToChars(savefilename); %need to be char for save_eeglab
%                             try
%                                 ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
%                                 Channels            = ElectrodeSetting(:,2);
%                                 %Channels.labels     = char(ElectrodeSetting.labels);
%     
%                                 % Recompute Theta and Radius to match EEGlab orientation
%                                 [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
%                                 Channels.theta      = -rad2deg(az);
%                                 Channels.radius     = 0.5 - rad2deg(elev)/180;
%                                 Channels.sph_theta  = -Channels.theta;
%                                 Channels.sph_phi    = (0.5 - Channels.radius) * 180;
%                                 Channels.X          = ElectrodeSetting.y;
%                                 Channels.Y          = -ElectrodeSetting.x;
%                                 Channels.Z          = ElectrodeSetting.z;
%         %                         Channels.ref        = char(repmat({''},height(Channels),1));
%         %                         Channels.sph_radius = repmat({[]},height(Channels),1);
%         %                         Channels.types      = char(repmat({''},height(Channels),1));
%         %                         Channels.urchan     = repmat({[]},height(Channels),1);
%                                 Channels            = table2struct(Channels);
%                                 save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1,Channels) %event start at 1
%                             catch
%                                 save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1) %event start at 1
%                             end
%                             
% %                             try
% %                                 ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
% %                                 Channels            = ElectrodeSetting(:,[6 7 2]);
% %                                 Channels.labels     = char(Channels.labels);
% %                                 Channels.sph_theta  = -Channels.theta;
% %                                 Channels.sph_phi    = (0.5 - Channels.radius) * 180;
% %                                 Channels.X          = ElectrodeSetting.x;
% %                                 Channels.Y          = ElectrodeSetting.y;
% %                                 Channels.Z          = ElectrodeSetting.z;
% %         %                         Channels.ref        = char(repmat({''},height(Channels),1));
% %         %                         Channels.sph_radius = repmat({[]},height(Channels),1);
% %         %                         Channels.types      = char(repmat({''},height(Channels),1));
% %         %                         Channels.urchan     = repmat({[]},height(Channels),1);
% %                                 Channels            = table2struct(Channels);
% %                             catch
% %                                 Channels = '';
% %                             end
% %                             savefilename = convertStringsToChars(savefilename); %need to be char for save_eeglab
% %                             
% %                             save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1,Channels) %event start at 1
%                         end                  
%                         
%                         %update the PIF (section output file)
%                         fprintf(PifFileID,'%s\r\n',outputfilename);
%                     end
%                 end
                
                %saving concatenate file
                if app.concatenate.Value 
                    for t=1:length(concatenateArgument) %loop according the number of concatenate file to record
                        if concatenateArgument(t)==1
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',app.concatenateSuffix1.Value{1}, app.FilesExtension.Value);
                            NewDataConcatenate=EEGconcatenated1;                            
                            MRK = eventsConcatenated1;
                        elseif concatenateArgument(t)==2
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',app.concatenateSuffix2.Value{1}, app.FilesExtension.Value);
                            NewDataConcatenate=EEGconcatenated2;                            
                            MRK = eventsConcatenated2;
                        elseif concatenateArgument(t)==3
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',app.concatenateSuffix3.Value{1}, app.FilesExtension.Value);
                            NewDataConcatenate=EEGconcatenated3;                            
                            MRK = eventsConcatenated3;
                        end
                      
                        %filling the complete path
                        savefilenameConcatenate=strcat(app.SaveFolder.Value{1},'\',outputfilenameConcatenate);
                       
                        %log file
                        fprintf(PifFileID,'%s\r\n',outputfilenameConcatenate);
                                    
                        
                        if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph') %manage the output format
                            save_eph(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1) %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.sef')
                            try
                                Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                            catch
                                Channels = '';
                            end
                            save_sef(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,Channels,MRK,1); %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.set')
                            savefilenameConcatenate = convertStringsToChars(savefilenameConcatenate); %nned to be char for save_eeglab
                            try
                                ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                                Channels            = ElectrodeSetting(:,2);
                                %Channels.labels     = char(ElectrodeSetting.labels);
    
                                % Recompute Theta and Radius to match EEGlab orientation
                                [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                                Channels.theta      = -rad2deg(az);
                                Channels.radius     = 0.5 - rad2deg(elev)/180;
                                Channels.sph_theta  = -Channels.theta;
                                Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                                Channels.X          = ElectrodeSetting.y;
                                Channels.Y          = -ElectrodeSetting.x;
                                Channels.Z          = ElectrodeSetting.z;
        %                         Channels.ref        = char(repmat({''},height(Channels),1));
        %                         Channels.sph_radius = repmat({[]},height(Channels),1);
        %                         Channels.types      = char(repmat({''},height(Channels),1));
        %                         Channels.urchan     = repmat({[]},height(Channels),1);
                                Channels            = table2struct(Channels);
                                save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1,Channels) %event start at 1
                            catch
                                save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1) %event start at 1
                            end
%                             try
%                                 ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
%                                 Channels            = ElectrodeSetting(:,[6 7 2]);
%                                 Channels.labels     = char(Channels.labels);
%                                 Channels.sph_theta  = -Channels.theta;
%                                 Channels.sph_phi    = (0.5 - Channels.radius) * 180;
%                                 Channels.X          = ElectrodeSetting.x;
%                                 Channels.Y          = ElectrodeSetting.y;
%                                 Channels.Z          = ElectrodeSetting.z;
%         %                         Channels.ref        = char(repmat({''},height(Channels),1));
%         %                         Channels.sph_radius = repmat({[]},height(Channels),1);
%         %                         Channels.types      = char(repmat({''},height(Channels),1));
%         %                         Channels.urchan     = repmat({[]},height(Channels),1);
%                                 Channels            = table2struct(Channels);
%                             catch
%                                 Channels = '';
%                             end
%                             savefilenameConcatenate = convertStringsToChars(savefilenameConcatenate); %nned to be char for save_eeglab
%                             save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1,Channels) %event start at 1
                        end
                   end
                end
                    
            end
            
            
%             % UPDATE DATA TABLE IN MAIN WINDOW
%             
%             % Data table in the Main Window
%             app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
%             app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
%             app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
%             app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
%             disp('Data table in the Main Window updated with the processed files')
%             cd(app.SaveFolder.Value{:})
%                      
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])

            
            %Close the PIF file 
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('File cut processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            delete (h);
            rz = msgbox('File cut processing done.');
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.FindPeaksButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FindPeaksButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpal_FindPeak
        function EEGpal_FindPeakCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FileCutButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Callback function
        function CutTableEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if(indices(2)==2)
                if app.CutTable.Data.End_Duration(indices(1))
                    app.CutTable.Data.End_Duration(indices(1))=false;
                end
            elseif (indices(2)==3)
                if app.CutTable.Data.End_Trigger(indices(1))
                    app.CutTable.Data.End_Trigger(indices(1))=false;
                end                
            end
            
        end

        % Callback function
        function concatenateValueChanged(app, event)
            value = app.concatenate.Value;
            if value
                app.concatenateSuffix1.Enable=true;
                app.concatenateSuffix2.Enable=true;
                app.concatenateSuffix3.Enable=true;
            else
                app.concatenateSuffix1.Enable=false;
                app.concatenateSuffix2.Enable=false;
                app.concatenateSuffix3.Enable=false; 
            end
        end

        % Callback function
        function SavetableasButtonPushed(app, event)
            [FileData,PathData] = uiputfile({'*.csv';'*.xlsx'},'Save File cut table',[app.EEGpalCS_Main.StudyName.Value '_FileCutTable']);
            if FileData==0 %user press on cancel
                return
            end
            writetable(app.CutTable.Data,fullfile(PathData,FileData));
        end

        % Callback function
        function LoadtableButtonPushed(app, event)
            [FileData,PathData] = uigetfile({'*.csv';'*.xlsx'},'Select the Interpolation table to load');
            if FileData==0 %user press on cancel
                return
            end
            FileName            = fullfile(PathData,FileData);
            uploadTable         = readtable(FileName);

            if or(size(uploadTable,1)~=15,size(uploadTable,2)~=6)
                 msgbox(sprintf('%s\n\n%s\n%s','Table in the file don''t match.', ...
                        'The file you selected do not contain a proper table with a dimention 15x6',...
                        'Please check!'),...
                        'Invalid file','warn');
                  return
            end
            
            labels      = string({'Start_Trigger','End_Trigger','End_Duration','End_Value','Suffix_String','Concatenate'});
            transfertTable = table('Size',[15 length(labels)],...
            'VariableTypes',["single", "logical", "logical","single","string","single"],'VariableNames',labels);
             
            %convert variable type
            for i=1:15
                 transfertTable{i,1}=uploadTable{i,1}; %Start_Trigger
                 transfertTable{i,2}=logical(uploadTable{i,2}); %End_Trigger
                 transfertTable{i,3}=logical(uploadTable{i,3}); %End_DUration
                 transfertTable{i,4}=uploadTable{i,4}; %End_Value
                 if ~ismissing(uploadTable{i,5})
                    transfertTable{i,5}=string(uploadTable{i,5}{1}); %Suffix_String
                 end
                 transfertTable{i,6}=uploadTable{i,6}; %Concatenate
            end
             
            app.CutTable.Data=transfertTable;
        end

        % Value changed function: IntervalOnset_ms
        function IntervalOnset_msValueChanged(app, event)
            app.IntervalOnset_tf.Value     = ms2tf(app,app.IntervalOnset_ms.Value,app.SamplingRate.Value);
            app.IntervalOnset_ms.Value     = tf2ms(app,app.IntervalOnset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
        end

        % Value changed function: IntervalOnset_tf
        function IntervalOnset_tfValueChanged(app, event)
            app.IntervalOnset_ms.Value     = tf2ms(app,app.IntervalOnset_tf.Value,app.SamplingRate.Value);
        end

        % Value changed function: IntervalOffset_ms
        function IntervalOffset_msValueChanged(app, event)
            app.IntervalOffset_tf.Value    = ms2tf(app,app.IntervalOffset_ms.Value,app.SamplingRate.Value);
            app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision            
        end

        % Value changed function: IntervalOffset_tf
        function IntervalOffset_tfValueChanged(app, event)
            app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value,app.SamplingRate.Value);            
        end

        % Value changed function: EndOfFile
        function EndOfFileValueChanged(app, event)
            if app.EndOfFile.Value
                app.IntervalOffset_tf.Enable   = 'off';
                app.IntervalOffset_ms.Enable   = 'off';
                try
                    app.IntervalOffset_tf.Value    = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                    app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                catch % if no data selected
                    app.IntervalOffset_tf.Value    = 0;
                    app.IntervalOffset_ms.Value    = 0;
                end
            else
                app.IntervalOffset_tf.Enable       = 'on';
                app.IntervalOffset_ms.Enable       = 'on';
            end        
        end

        % Value changed function: ElectrodesEditField
        function ElectrodesEditFieldValueChanged(app, event)
            if ~isempty(app.ElectrodesEditField.Value)
                app.AverageselectedelectrodesCheckBox.Enable  = 'on';
            else
                app.AverageselectedelectrodesCheckBox.Enable  = 'off';
                app.AverageselectedelectrodesCheckBox.Value  = 0;
            end
        end

        % Value changed function: ProcessingChoice
        function ProcessingChoiceValueChanged(app, event)
            if strcmp(app.ProcessingChoice.Value,'Extraction time range')
                app.SuffixinoutputfilenameEditField.Value='Extraction';
                app.PeakDropDown.Enable = 0;
                app.CheckfileDropDown.Enable = 0;
                app.SelectOutputTime.Enable = 0;
            else
                app.SuffixinoutputfilenameEditField.Value='Peak';
                app.PeakDropDown.Enable = 1;
                app.CheckfileDropDown.Enable = 1;
                app.SelectOutputTime.Enable = 1;
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpal_FindPeak = uifigure('Visible', 'off');
            app.EEGpal_FindPeak.Name = 'EEGpalCS Re-reference';
            app.EEGpal_FindPeak.CloseRequestFcn = createCallbackFcn(app, @EEGpal_FindPeakCloseRequest, true);
            app.FindpeaksinERPsorAveragevalueextractionLabel = uilabel(app.EEGpal_FindPeak);
            app.FindpeaksinERPsorAveragevalueextractionLabel.Text = 'Find peaks in ERPs or Average value extraction';
            app.OUTPUTOPTIONSLabel = uilabel(app.EEGpal_FindPeak);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.InstructionFolderLabel = uilabel(app.EEGpal_FindPeak);
            app.InstructionFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.EEGpal_FindPeak, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.EEGpal_FindPeak);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.EEGpal_FindPeak);
            app.SaveFolder.Editable = 'off';
            app.APPLYLabel = uilabel(app.EEGpal_FindPeak);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.EEGpal_FindPeak, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.DoneButton = uibutton(app.EEGpal_FindPeak, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tooltip = {'Return to the main window'};
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.EEGpal_FindPeak, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.InstructModule = uilabel(app.EEGpal_FindPeak);
            app.InstructModule.Text = 'This module permit to detect peak value and position in ERP files or extract the average value for the specified time interval.';
            app.InstructElements1 = uilabel(app.EEGpal_FindPeak);
            app.InstructElements1.Text = 'Specify the channel number separate by space (e.g 1 2 64). ';
            app.Epoch_RelativeInterval1 = uilabel(app.EEGpal_FindPeak);
            app.Epoch_RelativeInterval1.Text = 'Relative to file start';
            app.Epoch_RelativeInterval1_2 = uilabel(app.EEGpal_FindPeak);
            app.Epoch_RelativeInterval1_2.Text = 'Time interval';
            app.msLabel = uilabel(app.EEGpal_FindPeak);
            app.msLabel.Text = '[ms]';
            app.tfLabel = uilabel(app.EEGpal_FindPeak);
            app.tfLabel.Text = '[tf]';
            app.IntervalOnset_tf = uieditfield(app.EEGpal_FindPeak, 'numeric');
            app.IntervalOnset_tf.ValueDisplayFormat = '%.0f';
            app.IntervalOnset_tf.ValueChangedFcn = createCallbackFcn(app, @IntervalOnset_tfValueChanged, true);
            app.IntervalOnset_tf.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.IntervalonsetLabel = uilabel(app.EEGpal_FindPeak);
            app.IntervalonsetLabel.Text = 'Interval onset:';
            app.IntervalOnset_ms = uieditfield(app.EEGpal_FindPeak, 'numeric');
            app.IntervalOnset_ms.ValueDisplayFormat = '%.1f';
            app.IntervalOnset_ms.ValueChangedFcn = createCallbackFcn(app, @IntervalOnset_msValueChanged, true);
            app.IntervalOnset_ms.Tooltip = {'Enter negative value for onset preceding the trigger.'};
            app.IntervalOffset_tf = uieditfield(app.EEGpal_FindPeak, 'numeric');
            app.IntervalOffset_tf.ValueDisplayFormat = '%.0f';
            app.IntervalOffset_tf.ValueChangedFcn = createCallbackFcn(app, @IntervalOffset_tfValueChanged, true);
            app.IntervalOffset_tf.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.EndOfFile = uibutton(app.EEGpal_FindPeak, 'state');
            app.EndOfFile.ValueChangedFcn = createCallbackFcn(app, @EndOfFileValueChanged, true);
            app.EndOfFile.Tooltip = {'End of File'; 'When selected, ''Epoch offset'', ''Epoch duration'', and ''Number of sub-epochs'' values based on the first file of the list in the main window.,When pressing run, these values will be adapted to the file being processed.'};
            app.EndOfFile.Text = 'EoF';
            app.IntervaloffsetLabel = uilabel(app.EEGpal_FindPeak);
            app.IntervaloffsetLabel.Text = 'Interval offset:';
            app.IntervalOffset_ms = uieditfield(app.EEGpal_FindPeak, 'numeric');
            app.IntervalOffset_ms.ValueDisplayFormat = '%.1f';
            app.IntervalOffset_ms.ValueChangedFcn = createCallbackFcn(app, @IntervalOffset_msValueChanged, true);
            app.IntervalOffset_ms.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.ElectrodesEditFieldLabel = uilabel(app.EEGpal_FindPeak);
            app.ElectrodesEditFieldLabel.Text = 'Electrodes';
            app.ElectrodesEditField = uieditfield(app.EEGpal_FindPeak, 'text');
            app.ElectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @ElectrodesEditFieldValueChanged, true);
            app.Epoch_RelativeInterval1_3 = uilabel(app.EEGpal_FindPeak);
            app.Epoch_RelativeInterval1_3.Text = 'Elements to process';
            app.AverageselectedelectrodesCheckBox = uicheckbox(app.EEGpal_FindPeak);
            app.AverageselectedelectrodesCheckBox.Enable = 'off';
            app.AverageselectedelectrodesCheckBox.Text = 'Average selected electrode(s)';
            app.GFPCheckBox = uicheckbox(app.EEGpal_FindPeak);
            app.GFPCheckBox.Text = 'GFP';
            app.DissCheckBox = uicheckbox(app.EEGpal_FindPeak);
            app.DissCheckBox.Text = 'Diss';
            app.SamplingRateinHzEditFieldLabel = uilabel(app.EEGpal_FindPeak);
            app.SamplingRateinHzEditFieldLabel.Text = 'Sampling Rate (in Hz)';
            app.SamplingRate = uieditfield(app.EEGpal_FindPeak, 'numeric');
            app.Instruct_timeinter1 = uilabel(app.EEGpal_FindPeak);
            app.Instruct_timeinter1.Text = 'Time must be specified in the Cartool scale (first time fame is tf=0).';
            app.PeakDropDownLabel = uilabel(app.EEGpal_FindPeak);
            app.PeakDropDownLabel.Text = 'Peak';
            app.PeakDropDown = uidropdown(app.EEGpal_FindPeak);
            app.PeakDropDown.Items = {'Positive', 'Negative'};
            app.PeakDropDown.Value = 'Positive';
            app.InstructPeak1 = uilabel(app.EEGpal_FindPeak);
            app.InstructPeak1.Text = 'The script searches for the max value if it is positive or the min value if it is negative.';
            app.InstructWarning = uilabel(app.EEGpal_FindPeak);
            app.InstructWarning.Text = {'WARNING for peak detection: Use at your own risk. Peak analysis is not reliable because it depends on the reference (except for GFP and Diss). Automatic peak detection is also challenging because of the high variability of individual EEG signals. '; 'This script uses the Matlab function ''findpeaks''. If serval peaks are detected, only the global maxima/minima are recorded in the main output file. If no peaks are detected, no value is recorded. A visual check of the peaks in these cases is preferable (in Cartool) using the Check file.'};
            app.SelectOutputTime = uidropdown(app.EEGpal_FindPeak);
            app.SelectOutputTime.Items = {'in ms', 'in TF'};
            app.SelectOutputTime.Value = 'in ms';
            app.InstructionOutputTime = uilabel(app.EEGpal_FindPeak);
            app.InstructionOutputTime.Text = 'Peak position need to by saved in ms or TF ?';
            app.SuffixinoutputfilenameEditFieldLabel = uilabel(app.EEGpal_FindPeak);
            app.SuffixinoutputfilenameEditFieldLabel.Text = 'Suffix in output file name';
            app.SuffixinoutputfilenameEditField = uieditfield(app.EEGpal_FindPeak, 'text');
            app.SuffixinoutputfilenameEditField.Value = 'Peak';
            app.DoyouwanttosavealsotheCheckfileDropDownLabel = uilabel(app.EEGpal_FindPeak);
            app.DoyouwanttosavealsotheCheckfileDropDownLabel.Text = 'Do you want to save also the Check file ?';
            app.CheckfileDropDown = uidropdown(app.EEGpal_FindPeak);
            app.CheckfileDropDown.Items = {'Yes', 'No', ''};
            app.CheckfileDropDown.Value = 'Yes';
            app.InstructionOutputTime_2 = uilabel(app.EEGpal_FindPeak);
            app.InstructionOutputTime_2.Text = 'Save another .xlsx informing if the findpeaks script is unsure of the result. You could check the position manually with a value different from 0 (-1: no peak detected, 1: several peaks detected).';
            app.WhatprocessingshouldIdoDropDownLabel = uilabel(app.EEGpal_FindPeak);
            app.WhatprocessingshouldIdoDropDownLabel.Text = 'What processing should I do ?';
            app.ProcessingChoice = uidropdown(app.EEGpal_FindPeak);
            app.ProcessingChoice.Items = {'Peaks detection', 'Extraction time range'};
            app.ProcessingChoice.ValueChangedFcn = createCallbackFcn(app, @ProcessingChoiceValueChanged, true);
            app.ProcessingChoice.Value = 'Peaks detection';
            % Show the figure after all components are created
            app.EEGpal_FindPeak.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_FindPeaks(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpal_FindPeak)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpal_FindPeak)
        end
    end
end