classdef EEGpalCS_mod_Frequencying < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Frequencying                    matlab.ui.Figure
        HelpButton                      matlab.ui.control.Button
        SAVINGLabel                     matlab.ui.control.Label
        SwitchResample                  matlab.ui.control.Switch
        ResampleLabel                   matlab.ui.control.Label
        SamplingRate                    matlab.ui.control.NumericEditField
        PSD_FileAvgBands                matlab.ui.control.CheckBox
        PerfileaveragedtimewindowsLabel_2  matlab.ui.control.Label
        PerfileseparatetimewindowsLabel_2  matlab.ui.control.Label
        PSD_FileSepBands                matlab.ui.control.CheckBox
        PSD_FileSepWhole                matlab.ui.control.CheckBox
        PerfileseparatetimewindowsLabel  matlab.ui.control.Label
        PSD_FileAvgWhole                matlab.ui.control.CheckBox
        PerfileaveragedtimewindowsLabel  matlab.ui.control.Label
        PSD_BetweenWhole                matlab.ui.control.CheckBox
        BetweensubjectaveragingLabel    matlab.ui.control.Label
        WithinsubjectaveragingLabel     matlab.ui.control.Label
        PSD_WithinWhole                 matlab.ui.control.CheckBox
        PSD_BetweenBands                matlab.ui.control.CheckBox
        BetweensubjectaveragingLabel_2  matlab.ui.control.Label
        WithinsubjectaveragingLabel_2   matlab.ui.control.Label
        PSD_WithinBands                 matlab.ui.control.CheckBox
        GPS_FileSepBands                matlab.ui.control.CheckBox
        GPS_FileAvgBands                matlab.ui.control.CheckBox
        GPS_BetweenBands                matlab.ui.control.CheckBox
        GPS_BetweenWhole                matlab.ui.control.CheckBox
        GPS_WithinBands                 matlab.ui.control.CheckBox
        GPS_WithinWhole                 matlab.ui.control.CheckBox
        FrequencyBandsLabel             matlab.ui.control.Label
        GPS_FileSepWhole                matlab.ui.control.CheckBox
        GPS_FileAvgWhole                matlab.ui.control.CheckBox
        GPSLabel                        matlab.ui.control.Label
        PSDLabel                        matlab.ui.control.Label
        PowerSpectraLabel               matlab.ui.control.Label
        WindowingGroup                  matlab.ui.container.ButtonGroup
        WIND_Hanning                    matlab.ui.control.ToggleButton
        WIND_Hamming                    matlab.ui.control.ToggleButton
        WIND_None                       matlab.ui.control.ToggleButton
        NormalizationGroup              matlab.ui.container.ButtonGroup
        NROM_MeanGlobal                 matlab.ui.control.ToggleButton
        NORM_NeighChan                  matlab.ui.control.DropDown
        ChannelDropDownLabel            matlab.ui.control.Label
        NORM_nNeigh                     matlab.ui.control.NumericEditField
        nNeighEditFieldLabel            matlab.ui.control.Label
        NORM_Neighbourg                 matlab.ui.control.ToggleButton
        NORM_LogTransform               matlab.ui.control.ToggleButton
        NROM_Mean                       matlab.ui.control.ToggleButton
        NORM_None                       matlab.ui.control.ToggleButton
        FreqMethod                      matlab.ui.control.ListBox
        FreqMethodInstruction           matlab.ui.control.Label
        EndOfFile                       matlab.ui.control.StateButton
        InconsistentNumChan             matlab.ui.control.Label
        InconsistentSR                  matlab.ui.control.Label
        INDIVIDUALPEAKFREQUENCYPanel    matlab.ui.container.Panel
        iFP_FrequencyBands              matlab.ui.control.EditField
        Frequencybandseg4675102030Label_2  matlab.ui.control.Label
        SGF_PolyOrder                   matlab.ui.control.NumericEditField
        PolynomialorderLabel            matlab.ui.control.Label
        SGF_FrameWidth                  matlab.ui.control.NumericEditField
        FramewidthLabel                 matlab.ui.control.Label
        SGF_CheckBox                    matlab.ui.control.CheckBox
        SGF_Label                       matlab.ui.control.Label
        Corcoranetal2018DOI101111psyp13064Label  matlab.ui.control.Label
        iPF_Table                       matlab.ui.control.Table
        SGF_RuniFP                      matlab.ui.control.Button
        SGF_AllOptions                  matlab.ui.control.Button
        iPF_Signal                      matlab.ui.control.DropDown
        SignaltoanalyseLabel            matlab.ui.control.Label
        iPF_Windowing                   matlab.ui.control.DropDown
        WindowingfunctionLabel_2        matlab.ui.control.Label
        iPF_Method                      matlab.ui.control.DropDown
        MethodLabel                     matlab.ui.control.Label
        iPF_FrequencyComputation        matlab.ui.control.Label
        GPS_FilesExtension              matlab.ui.control.DropDown
        ExtensionofGPSoutputfilesLabel  matlab.ui.control.Label
        Method                          matlab.ui.control.Label
        FREQUENCYCOMPUTATIONLabel       matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        APPLYLabel                      matlab.ui.control.Label
        ParameterFilePath               matlab.ui.control.EditField
        ParameterfileEditFieldLabel     matlab.ui.control.Label
        SaveAsButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        OpenButton                      matlab.ui.control.Button
        InputParametersFile             matlab.ui.control.Label
        SuffixString                    matlab.ui.control.EditField
        SuffixstringLabel               matlab.ui.control.Label
        SaveFolder                      matlab.ui.control.TextArea
        SavingfolderLabel               matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        SelectUpperFolderLabel          matlab.ui.control.Label
        PSD_FilesExtension              matlab.ui.control.DropDown
        ExtensionofPSDoutputfilesLabel  matlab.ui.control.Label
        OUTPUTOPTIONSLabel              matlab.ui.control.Label
        ChannelsCluster                 matlab.ui.control.DropDown
        ChannelsclusterLabel            matlab.ui.control.Label
        FrequencyBands                  matlab.ui.control.EditField
        Frequencybandseg4675102030Label  matlab.ui.control.Label
        HzLabel_3                       matlab.ui.control.Label
        MaxFreq                         matlab.ui.control.NumericEditField
        MaxLabel                        matlab.ui.control.Label
        HzLabel_2                       matlab.ui.control.Label
        UpperFreq                       matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        LowerFreq                       matlab.ui.control.NumericEditField
        RangeLabel                      matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        Resolution                      matlab.ui.control.NumericEditField
        FrequencyresolutionLabel        matlab.ui.control.Label
        nWindows                        matlab.ui.control.NumericEditField
        nWindowsLabel                   matlab.ui.control.Label
        tfOverlap                       matlab.ui.control.Label
        Overlap                         matlab.ui.control.NumericEditField
        OverlapLabel                    matlab.ui.control.Label
        Size_tf                         matlab.ui.control.NumericEditField
        Size_ms                         matlab.ui.control.NumericEditField
        WindowssizeLabel                matlab.ui.control.Label
        Duration_tf                     matlab.ui.control.NumericEditField
        Duration_ms                     matlab.ui.control.NumericEditField
        DurationLabel                   matlab.ui.control.Label
        Offset_tf                       matlab.ui.control.NumericEditField
        Offset_ms                       matlab.ui.control.NumericEditField
        OffsetLabel                     matlab.ui.control.Label
        Onset_tf                        matlab.ui.control.NumericEditField
        Onset_ms                        matlab.ui.control.NumericEditField
        OnsetLabel                      matlab.ui.control.Label
        tfLabel                         matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        AnalysisPeriodLabel             matlab.ui.control.Label
        OrigSamplingRate                matlab.ui.control.NumericEditField
        OriginalsamplingrateLabel       matlab.ui.control.Label
        SAMPLINGRATELabel               matlab.ui.control.Label
        FREQUENCYINGLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        SettingTable
        %nChan
    end
    
    properties (Access = public)
        SessionParameters
        Study_Parameters
    end
    
    methods (Access = private)
        
        function applyStudyParam(app)
            % OUTPUT OPTION
            app.SaveFolder.Value                = app.Study_Parameters.Frequency.SaveFolder;
            app.PSD_FilesExtension.Value        = app.Study_Parameters.Frequency.PSD_FilesExtension;
            app.GPS_FilesExtension.Value        = app.Study_Parameters.Frequency.GPS_FilesExtension;
            app.SuffixString.Value              = app.Study_Parameters.Frequency.SuffixString;
            
            
            % SAMPLING RATE
            
            app.SwitchResample.Value            = app.Study_Parameters.Frequency.SwitchResample;
            app.SamplingRate.Value              = app.Study_Parameters.Frequency.SamplingRate;
            
            
            % SAVING
            
            % Saving whole power spectra
            app.PSD_FileSepWhole.Value          = app.Study_Parameters.Frequency.PSD_FileSepWhole;
            app.GPS_FileSepWhole.Value          = app.Study_Parameters.Frequency.GPS_FileSepWhole;
            app.PSD_FileAvgWhole.Value          = app.Study_Parameters.Frequency.PSD_FileAvgWhole;
            app.GPS_FileAvgWhole.Value          = app.Study_Parameters.Frequency.GPS_FileAvgWhole;
            app.PSD_WithinWhole.Value           = app.Study_Parameters.Frequency.PSD_WithinWhole;
            app.GPS_WithinWhole.Value           = app.Study_Parameters.Frequency.GPS_WithinWhole;
            app.PSD_BetweenWhole.Value          = app.Study_Parameters.Frequency.PSD_BetweenWhole;
            app.GPS_BetweenWhole.Value          = app.Study_Parameters.Frequency.GPS_BetweenWhole;
            
            % Saving frequency bands
            app.PSD_FileSepBands.Value          = app.Study_Parameters.Frequency.PSD_FileSepBands;
            app.GPS_FileSepBands.Value          = app.Study_Parameters.Frequency.GPS_FileSepBands;
            app.PSD_FileAvgBands.Value          = app.Study_Parameters.Frequency.PSD_FileAvgBands;
            app.GPS_FileAvgBands.Value          = app.Study_Parameters.Frequency.GPS_FileAvgBands;
            app.PSD_WithinBands.Value           = app.Study_Parameters.Frequency.PSD_WithinBands;
            app.GPS_WithinBands.Value           = app.Study_Parameters.Frequency.GPS_WithinBands;
            app.PSD_BetweenBands.Value          = app.Study_Parameters.Frequency.PSD_BetweenBands;
            app.GPS_BetweenBands.Value          = app.Study_Parameters.Frequency.GPS_BetweenBands;
            
            
            % FREQUENCY ANALYSIS
            
            % Method
            app.FreqMethod.Value                = app.Study_Parameters.Frequency.FreqMethod;
            app.WindowingGroup.SelectedObject.Text      = app.Study_Parameters.Frequency.Windowing;
            app.NormalizationGroup.SelectedObject.Text  = app.Study_Parameters.Frequency.Normalization;
            app.NORM_nNeigh.Value               = app.Study_Parameters.Frequency.NORM_nNeigh;
            
            % Analysis period
            app.Onset_ms.Value                  = app.Study_Parameters.Frequency.Onset_ms;
            app.Onset_tf.Value                  = app.Study_Parameters.Frequency.Onset_tf;
            app.Offset_ms.Value                 = app.Study_Parameters.Frequency.Offset_ms;
            app.Offset_tf.Value                 = app.Study_Parameters.Frequency.Offset_tf;
            app.Size_ms.Value                   = app.Study_Parameters.Frequency.Size_ms;
            app.Size_tf.Value                   = app.Study_Parameters.Frequency.Size_tf;
            app.Overlap.Value                   = app.Study_Parameters.Frequency.Overlap;
            
            % Frequencies
            app.LowerFreq.Value                 = app.Study_Parameters.Frequency.LowerFreq;
            app.UpperFreq.Value                 = app.Study_Parameters.Frequency.UpperFreq;
            app.FrequencyBands.Value            = app.Study_Parameters.Frequency.FrequencyBands;
            
            try % if the electrode setting is not defined, will not work...
                app.ChannelsCluster.Value           = app.Study_Parameters.Frequency.ElectrodesCluster;
            catch
                % add a warning?
            end
            
            
%             % INDIVIDUAL PEAK FREQUENCY
%             
%             % Frequency computation
%             app.iPF_Method.Items                = app.Study_Parameters.Frequency.iAF.iAF_Method;
%             app.iPF_Windowing.Value             = app.Study_Parameters.Frequency.iAF.iAF_Windowing;
%             app.iPF_Signal.Value                = app.Study_Parameters.Frequency.iAF.iAF_Signal;
%             
%             % Savitzky-Golay filter
%             app.SGF_CheckBox.Value              = app.Study_Parameters.Frequency.iAF.SGF_CheckBox;
%             app.SGF_FrameWidth.Value            = app.Study_Parameters.Frequency.iAF.SGF_FrameWidth;
%             app.SGF_PolyOrder.Value             = app.Study_Parameters.Frequency.iAF.SGF_PolyOrder;
        end
        
        function Study_Parameters = updateStudyParam(app,Study_Parameters)
            % OUTPUT OPTION
            Study_Parameters.Frequency.SaveFolder           = app.SaveFolder.Value;
            Study_Parameters.Frequency.PSD_FilesExtension   = app.PSD_FilesExtension.Value;
            Study_Parameters.Frequency.GPS_FilesExtension   = app.GPS_FilesExtension.Value;
            Study_Parameters.Frequency.SuffixString         = app.SuffixString.Value;
            
            
            % SAMPLING RATE
            
            Study_Parameters.Frequency.SwitchResample       = app.SwitchResample.Value;
            Study_Parameters.Frequency.SamplingRate         = app.SamplingRate.Value;
            
            
            % SAVING
            
            % Saving whole power spectra
            Study_Parameters.Frequency.PSD_FileSepWhole     = app.PSD_FileSepWhole.Value;
            Study_Parameters.Frequency.GPS_FileSepWhole     = app.GPS_FileSepWhole.Value;
            Study_Parameters.Frequency.PSD_FileAvgWhole     = app.PSD_FileAvgWhole.Value;
            Study_Parameters.Frequency.GPS_FileAvgWhole     = app.GPS_FileAvgWhole.Value;
            Study_Parameters.Frequency.PSD_WithinWhole      = app.PSD_WithinWhole.Value;
            Study_Parameters.Frequency.GPS_WithinWhole      = app.GPS_WithinWhole.Value;
            Study_Parameters.Frequency.PSD_BetweenWhole     = app.PSD_BetweenWhole.Value;
            Study_Parameters.Frequency.GPS_BetweenWhole     = app.GPS_BetweenWhole.Value;
            
            % Saving frequency bands
            Study_Parameters.Frequency.PSD_FileSepBands     = app.PSD_FileSepBands.Value;
            Study_Parameters.Frequency.GPS_FileSepBands     = app.GPS_FileSepBands.Value;
            Study_Parameters.Frequency.PSD_FileAvgBands     = app.PSD_FileAvgBands.Value;
            Study_Parameters.Frequency.GPS_FileAvgBands     = app.GPS_FileAvgBands.Value;
            Study_Parameters.Frequency.PSD_WithinBands      = app.PSD_WithinBands.Value;
            Study_Parameters.Frequency.GPS_WithinBands      = app.GPS_WithinBands.Value;
            Study_Parameters.Frequency.PSD_BetweenBands     = app.PSD_BetweenBands.Value;
            Study_Parameters.Frequency.GPS_BetweenBands     = app.GPS_BetweenBands.Value;
            
            
            % FREQUENCY ANALYSIS
            
            % Method
            Study_Parameters.Frequency.FreqMethod           = app.FreqMethod.Value;
            Study_Parameters.Frequency.Windowing            = app.WindowingGroup.SelectedObject.Text;
            Study_Parameters.Frequency.Normalization        = app.NormalizationGroup.SelectedObject.Text;
            Study_Parameters.Frequency.NORM_nNeigh          = app.NORM_nNeigh.Value;
            
            % Analysis period
            Study_Parameters.Frequency.Onset_ms             = app.Onset_ms.Value;
            Study_Parameters.Frequency.Onset_tf             = app.Onset_tf.Value;
            Study_Parameters.Frequency.Offset_ms            = app.Offset_ms.Value;
            Study_Parameters.Frequency.Offset_tf            = app.Offset_tf.Value;
            Study_Parameters.Frequency.Size_ms              = app.Size_ms.Value;
            Study_Parameters.Frequency.Size_tf              = app.Size_tf.Value;
            Study_Parameters.Frequency.Overlap              = app.Overlap.Value;
            
            % Frequencies
            Study_Parameters.Frequency.LowerFreq            = app.LowerFreq.Value;
            Study_Parameters.Frequency.UpperFreq            = app.UpperFreq.Value;
            Study_Parameters.Frequency.FrequencyBands       = app.FrequencyBands.Value;
            
            Study_Parameters.Frequency.ElectrodesCluster    = app.ChannelsCluster.Value;
            
            
%             % INDIVIDUAL PEAK FREQUENCY
%             
%             % Frequency computation
%             Study_Parameters.Frequency.iAF.iAF_Method       = app.iPF_Method.Items;
%             Study_Parameters.Frequency.iAF.iAF_Windowing    = app.iPF_Windowing.Value;
%             Study_Parameters.Frequency.iAF.iAF_Signal       = app.iPF_Signal.Value;
%             
%             % Savitzky-Golay filter
%             Study_Parameters.Frequency.iAF.SGF_CheckBox     = app.SGF_CheckBox.Value;
%             Study_Parameters.Frequency.iAF.SGF_FrameWidth   = app.SGF_FrameWidth.Value;
%             Study_Parameters.Frequency.iAF.SGF_PolyOrder    = app.SGF_PolyOrder.Value;
        end
        
        
        function applySessionParam(app)
            % OUTPUT OPTION
            app.SaveFolder.Value                = app.SessionParameters.Frequency.SaveFolder;
            app.PSD_FilesExtension.Value        = app.SessionParameters.Frequency.PSD_FilesExtension;
            app.GPS_FilesExtension.Value        = app.SessionParameters.Frequency.GPS_FilesExtension;
            app.SuffixString.Value              = app.SessionParameters.Frequency.SuffixString;
            
            
            % SAMPLING RATE
            
            app.SwitchResample.Value            = app.SessionParameters.Frequency.SwitchResample;
            app.SamplingRate.Value              = app.SessionParameters.Frequency.SamplingRate;
            
            
            % SAVING
            
            % Saving whole power spectra
            app.PSD_FileSepWhole.Value          = app.SessionParameters.Frequency.PSD_FileSepWhole;
            app.GPS_FileSepWhole.Value          = app.SessionParameters.Frequency.GPS_FileSepWhole;
            app.PSD_FileAvgWhole.Value          = app.SessionParameters.Frequency.PSD_FileAvgWhole;
            app.GPS_FileAvgWhole.Value          = app.SessionParameters.Frequency.GPS_FileAvgWhole;
            app.PSD_WithinWhole.Value           = app.SessionParameters.Frequency.PSD_WithinWhole;
            app.GPS_WithinWhole.Value           = app.SessionParameters.Frequency.GPS_WithinWhole;
            app.PSD_BetweenWhole.Value          = app.SessionParameters.Frequency.PSD_BetweenWhole;
            app.GPS_BetweenWhole.Value          = app.SessionParameters.Frequency.GPS_BetweenWhole;
            
            % Saving frequency bands
            app.PSD_FileSepBands.Value          = app.SessionParameters.Frequency.PSD_FileSepBands;
            app.GPS_FileSepBands.Value          = app.SessionParameters.Frequency.GPS_FileSepBands;
            app.PSD_FileAvgBands.Value          = app.SessionParameters.Frequency.PSD_FileAvgBands;
            app.GPS_FileAvgBands.Value          = app.SessionParameters.Frequency.GPS_FileAvgBands;
            app.PSD_WithinBands.Value           = app.SessionParameters.Frequency.PSD_WithinBands;
            app.GPS_WithinBands.Value           = app.SessionParameters.Frequency.GPS_WithinBands;
            app.PSD_BetweenBands.Value          = app.SessionParameters.Frequency.PSD_BetweenBands;
            app.GPS_BetweenBands.Value          = app.SessionParameters.Frequency.GPS_BetweenBands;
            
            
            % FREQUENCY ANALYSIS
            
            % Method
            app.FreqMethod.Value                = app.SessionParameters.Frequency.FreqMethod;
            % Windowins
            if strcmp(app.SessionParameters.Frequency.Windowing,'None')
                app.WIND_None.Value    = 1
                app.WIND_Hamming.Value  = 0;
                app.WIND_Hanning.Value    = 0;
            elseif strcmp(app.SessionParameters.Frequency.Windowing,'Hamming')
                app.WIND_None.Value    = 0;
                app.WIND_Hamming.Value    = 1;
                app.WIND_Hanning.Value    = 0;
            elseif strcmp(app.SessionParameters.Frequency.Windowing,'Hanning')
                app.WIND_None.Value    = 0;
                app.WIND_Hamming.Value    = 0;
                app.WIND_Hanning.Value    = 1;
            end            
            app.WindowingGroup.SelectedObject.Text      = app.SessionParameters.Frequency.Windowing;
            % Normalization
            if strcmp(app.SessionParameters.Frequency.Normalization,'None')
                app.NORM_None.Value     = 1;
                app.NROM_MeanGlobal.Value     = 0;
                app.NROM_Mean.Value     = 0;
                app.NORM_LogTransform.Value     = 0;
                app.NORM_Neighbourg.Value     = 0;
                app.NORM_nNeigh.Enable     = 'off';
                app.NORM_NeighChan.Enable     = 'off';
            elseif strcmp(app.SessionParameters.Frequency.Normalization,'by global averaged Power')
                app.NORM_None.Value     = 0;
                app.NROM_MeanGlobal.Value     = 1;
                app.NROM_Mean.Value     = 0;
                app.NORM_LogTransform.Value     = 0;
                app.NORM_Neighbourg.Value     = 0;
                app.NORM_nNeigh.Enable     = 'off';
                app.NORM_NeighChan.Enable     = 'off';
            elseif strcmp(app.SessionParameters.Frequency.Normalization,'by mean per frequency (1/f correction)')
                app.NORM_None.Value     = 0;
                app.NROM_MeanGlobal.Value     = 0;
                app.NROM_Mean.Value     = 1;
                app.NORM_LogTransform.Value     = 0;
                app.NORM_Neighbourg.Value     = 0;
                app.NORM_nNeigh.Enable     = 'off';
                app.NORM_NeighChan.Enable     = 'off';
            elseif strcmp(app.SessionParameters.Frequency.Normalization,'Log transf. (NOT RECOMMENDED)')
                app.NORM_None.Value     = 0;
                app.NROM_MeanGlobal.Value     = 0;
                app.NROM_Mean.Value     = 0;
                app.NORM_LogTransform.Value     = 1;
                app.NORM_Neighbourg.Value     = 0;
                app.NORM_nNeigh.Enable     = 'off';
                app.NORM_NeighChan.Enable     = 'off';
            elseif strcmp(app.SessionParameters.Frequency.Normalization,'Neighbourg averaging')
                app.NORM_None.Value     = 0;
                app.NROM_MeanGlobal.Value     = 0;
                app.NROM_Mean.Value     = 0;
                app.NORM_LogTransform.Value     = 0;
                app.NORM_Neighbourg.Value     = 1;
                app.NORM_nNeigh.Enable     = 'on';
                app.NORM_NeighChan.Enable     = 'on';
            end             
            app.NormalizationGroup.SelectedObject.Text  = app.SessionParameters.Frequency.Normalization;
            app.NORM_nNeigh.Value               = app.SessionParameters.Frequency.NORM_nNeigh;
            
            % Analysis period
            app.Onset_ms.Value                  = app.SessionParameters.Frequency.Onset_ms;
            app.Onset_tf.Value                  = app.SessionParameters.Frequency.Onset_tf;
            app.Offset_ms.Value                 = app.SessionParameters.Frequency.Offset_ms;
            app.Offset_tf.Value                 = app.SessionParameters.Frequency.Offset_tf;
            app.Size_ms.Value                   = app.SessionParameters.Frequency.Size_ms;
            app.Size_tf.Value                   = app.SessionParameters.Frequency.Size_tf;
            app.Overlap.Value                   = app.SessionParameters.Frequency.Overlap;
            
            % Frequencies
            app.LowerFreq.Value                 = app.SessionParameters.Frequency.LowerFreq;
            app.UpperFreq.Value                 = app.SessionParameters.Frequency.UpperFreq;
            app.FrequencyBands.Value            = app.SessionParameters.Frequency.FrequencyBands;
            
            try % if the electrode setting has changed, might not work...
                app.ChannelsCluster.Value           = app.SessionParameters.Frequency.ElectrodesCluster;
            catch
                % add a warning?
            end
            
            
%             % INDIVIDUAL PEAK FREQUENCY
%             
%             % Frequency computation
%             app.iPF_Method.Items                = app.SessionParameters.Frequency.iAF.iAF_Method;
%             app.iPF_Windowing.Value             = app.SessionParameters.Frequency.iAF.iAF_Windowing;
%             app.iPF_Signal.Value                = app.SessionParameters.Frequency.iAF.iAF_Signal;
%             
%             % Savitzky-Golay filter
%             app.SGF_CheckBox.Value              = app.SessionParameters.Frequency.iAF.SGF_CheckBox;
%             app.SGF_FrameWidth.Value            = app.SessionParameters.Frequency.iAF.SGF_FrameWidth;
%             app.SGF_PolyOrder.Value             = app.SessionParameters.Frequency.iAF.SGF_PolyOrder;
        end
        
        function SessionParameters   = updateSessionParam(app,SessionParameters)
            % OUTPUT OPTION
            SessionParameters.Frequency.SaveFolder              = app.SaveFolder.Value;
            SessionParameters.Frequency.PSD_FilesExtension      = app.PSD_FilesExtension.Value;
            SessionParameters.Frequency.GPS_FilesExtension      = app.GPS_FilesExtension.Value;
            SessionParameters.Frequency.SuffixString            = app.SuffixString.Value;
            
            
            % SAMPLING RATE
            
            SessionParameters.Frequency.SwitchResample          = app.SwitchResample.Value;
            SessionParameters.Frequency.SamplingRate            = app.SamplingRate.Value;
            
            
            % SAVING
            
            % Saving whole power spectra
            SessionParameters.Frequency.PSD_FileSepWhole        = app.PSD_FileSepWhole.Value;
            SessionParameters.Frequency.GPS_FileSepWhole        = app.GPS_FileSepWhole.Value;
            SessionParameters.Frequency.PSD_FileAvgWhole        = app.PSD_FileAvgWhole.Value;
            SessionParameters.Frequency.GPS_FileAvgWhole        = app.GPS_FileAvgWhole.Value;
            SessionParameters.Frequency.PSD_WithinWhole         = app.PSD_WithinWhole.Value;
            SessionParameters.Frequency.GPS_WithinWhole         = app.GPS_WithinWhole.Value;
            SessionParameters.Frequency.PSD_BetweenWhole        = app.PSD_BetweenWhole.Value;
            SessionParameters.Frequency.GPS_BetweenWhole        = app.GPS_BetweenWhole.Value;
            
            % Saving frequency bands
            SessionParameters.Frequency.PSD_FileSepBands        = app.PSD_FileSepBands.Value;
            SessionParameters.Frequency.GPS_FileSepBands        = app.GPS_FileSepBands.Value;
            SessionParameters.Frequency.PSD_FileAvgBands        = app.PSD_FileAvgBands.Value;
            SessionParameters.Frequency.GPS_FileAvgBands        = app.GPS_FileAvgBands.Value;
            SessionParameters.Frequency.PSD_WithinBands         = app.PSD_WithinBands.Value;
            SessionParameters.Frequency.GPS_WithinBands         = app.GPS_WithinBands.Value;
            SessionParameters.Frequency.PSD_BetweenBands        = app.PSD_BetweenBands.Value;
            SessionParameters.Frequency.GPS_BetweenBands        = app.GPS_BetweenBands.Value;
            
            
            % FREQUENCY ANALYSIS
            
            % Method
            SessionParameters.Frequency.FreqMethod              = app.FreqMethod.Value;
            SessionParameters.Frequency.Windowing               = app.WindowingGroup.SelectedObject.Text;
            SessionParameters.Frequency.Normalization           = app.NormalizationGroup.SelectedObject.Text;
            SessionParameters.Frequency.NORM_nNeigh             = app.NORM_nNeigh.Value;
            
            % Analysis period
            SessionParameters.Frequency.Onset_ms                = app.Onset_ms.Value;
            SessionParameters.Frequency.Onset_tf                = app.Onset_tf.Value;
            SessionParameters.Frequency.Offset_ms               = app.Offset_ms.Value;
            SessionParameters.Frequency.Offset_tf               = app.Offset_tf.Value;
            SessionParameters.Frequency.Size_ms                 = app.Size_ms.Value;
            SessionParameters.Frequency.Size_tf                 = app.Size_tf.Value;
            SessionParameters.Frequency.Overlap                 = app.Overlap.Value;
            
            % Frequencies
            SessionParameters.Frequency.LowerFreq               = app.LowerFreq.Value;
            SessionParameters.Frequency.UpperFreq               = app.UpperFreq.Value;
            SessionParameters.Frequency.FrequencyBands          = app.FrequencyBands.Value;
            
            SessionParameters.Frequency.ElectrodesCluster       = app.ChannelsCluster.Value;
            
            
%             % INDIVIDUAL PEAK FREQUENCY
%             
%             % Frequency computation
%             SessionParameters.Frequency.iAF.iAF_Method          = app.iPF_Method.Items;
%             SessionParameters.Frequency.iAF.iAF_Windowing       = app.iPF_Windowing.Value;
%             SessionParameters.Frequency.iAF.iAF_Signal          = app.iPF_Signal.Value;
%             
%             % Savitzky-Golay filter
%             SessionParameters.Frequency.iAF.SGF_CheckBox        = app.SGF_CheckBox.Value;
%             SessionParameters.Frequency.iAF.SGF_FrameWidth      = app.SGF_FrameWidth.Value;
%             SessionParameters.Frequency.iAF.SGF_PolyOrder       = app.SGF_PolyOrder.Value;
        end
        
        function updateTotal(app,onset_ms,onset_tf,offset_ms,offset_tf,total_ms,total_tf)
            % Could be simplified by removing the 'total_ms' and 'total_tf'
            % inputs and replacing in the function by 'app.Duration_ms' and
            % 'app.Duration_tf'. This was taken from the 'Epoching' module,
            % where more than one 'Duration' fields are used...
            
            total_ms.Value  = offset_ms.Value - onset_ms.Value;
            total_tf.Value  = (offset_tf.Value - onset_tf.Value) + 1; % +1 because onset and offset included
            
            if total_tf.Value <= 0 % tf in case there is no sampling rate defined
                total_ms.FontColor      = [1.00,0.00,0.00];
                total_ms.FontWeight     = 'bold';
                total_tf.FontColor      = [1.00,0.00,0.00];
                total_tf.FontWeight     = 'bold';
                app.RunButton.Enable    = 'off';
            else
                total_ms.FontColor      = [0.00,0.00,0.00];
                total_ms.FontWeight     = 'normal';
                total_tf.FontColor      = [0.00,0.00,0.00];
                total_tf.FontWeight     = 'normal';
                
                app.RunButton.Enable    = 'on';
                
                if total_tf.Value < app.Size_tf.Value % if the length of the file is smaller than the window size
                    app.Size_tf.Value = total_tf.Value;
                    app.Size_ms.Value = total_ms.Value;
                end
            end
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
        end
        
        function tf = ms2tf(~,ms,sr)
            tf = round((ms / 1000) * sr, 0); % round() because time-frames are counted as integer numbers
                                             % => requires to recompute tf2ms for inputs, to be more precise
        end
        
        function ms = tf2ms(~,tf,sr)
            ms = (tf / sr) * 1000;
        end
        
%         function out = str2vec(~,str)
%             cln = find(str==':');
%             if length(cln) == 1
%                 out = str2double(str(1:cln-1)) : str2double(str(cln+1:end));
%             elseif length(cln) == 2
%                 out = str2double(str(1:cln(1)-1)) : str2double(str(cln(1)+1:cln(2)-1)) : str2double(str(cln(2)+1:end));
%             end
%         end
        
        function reverseStr = txtprogressbar(~,nSteps,step,reverseStr)
            % https://undocumentedmatlab.com/articles/command-window-text-manipulation/
            percentDone = 100 * (step) / nSteps;
            msg = sprintf('    Progression: %3.1f', percentDone);
            fprintf([reverseStr, msg,'%%']);
            reverseStr = repmat(sprintf('\b'), 1, length(msg)+1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main = EEGpalCS_Main;
            
            
            % UPDATE UI with input values
            
            app.ParameterFilePath.Value     = app.EEGpalCS_Main.ParameterFilePath.Value;
            app.Study_Parameters            = app.EEGpalCS_Main.Study_Parameters;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            
            % Channels
            try
                app.SettingTable                    = app.SessionParameters.Electrodes.SettingTable;
                app.SettingTable(~[app.SettingTable.include{:}]',:) = []; % remove unselected channels
                app.SettingTable.nr                 = (1:height(app.SettingTable))';
                
                % Scalp cluster (default values)
                app.ChannelsCluster.Items           = ['all';unique(app.SettingTable.cluster,'stable')]';
                app.ChannelsCluster.Value           = app.ChannelsCluster.Items(1);
            catch
                app.ChannelsCluster.Enable          = 'off';
            end
            
            % Saved study parameters
            try
               applyStudyParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.Study_Parameters.Files.SaveFolder;
                catch
                end
            end
            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end
            
            % Check data consistency
            if ~isempty(app.DataTable)
                % Check consistency between files
                if length(unique(app.DataTable.SamplingRate)) > 1
                    app.InconsistentSR.Visible      = 'on';
                    app.SwitchResample.Value        = 'On';
                    app.SwitchResample.Enable       = 'off';
                    app.SamplingRate.Enable         = 'on';
                end
                if length(unique(app.DataTable.nChan)) > 1
                    app.InconsistentNumChan.Visible = 'on';
                    app.ChannelsCluster.Enable      = 'off';
                    app.GPS_FilesExtension.Enable   = 'off';
                    app.GPS_FileAvgWhole.Enable     = 'off';
                    app.GPS_FileSepWhole.Enable     = 'off';
                    app.GPS_FileAvgBands.Enable     = 'off';
                    app.GPS_FileSepBands.Enable     = 'off';
                    app.GPS_WithinWhole.Enable      = 'off';
                    app.GPS_WithinBands.Enable      = 'off';
                    app.GPS_BetweenWhole.Enable     = 'off';
                    app.GPS_BetweenBands.Enable     = 'off';
                else
%                     % Channel tables
%                     app.nChan = app.DataTable.nChan(1);
                end

                %Check if frequency band not empty
                if ~isempty(app.FrequencyBands.Value)
                    app.PSD_FileAvgBands.Enable   = 'on';
                    app.GPS_FileAvgBands.Enable   = 'on';
                    app.PSD_FileSepBands.Enable   = 'on';
                    app.GPS_FileSepBands.Enable   = 'on';
                    app.PSD_WithinBands.Enable   = 'on';
                    app.GPS_WithinBands.Enable   = 'on';
                    app.PSD_BetweenBands.Enable   = 'on';
                    app.GPS_BetweenBands.Enable   = 'on';
                end


                % Sampling rate
                app.OrigSamplingRate.Value      = app.DataTable.SamplingRate(1);
                app.SamplingRate.Value          = app.OrigSamplingRate.Value;
                
                % Update according to the default values
                app.Size_tf.Value       = ms2tf(app,app.Size_ms.Value,app.SamplingRate.Value);
                app.Size_ms.Value       = tf2ms(app,app.Size_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
                app.Resolution.Value    = app.SamplingRate.Value / app.Size_tf.Value;
                app.MaxFreq.Value       = app.SamplingRate.Value / 2;
                app.UpperFreq.Value     = app.MaxFreq.Value;
                
            else % No data, no sampling rate, no 'Run'!
                app.OrigSamplingRate.Enable     = 'off';
                app.SamplingRate.Value          = 1000;
                app.SwitchResample.Enable       = 'off';
                app.RunButton.Enable            = 'off';
            end
            
            
            % Update parameters according to the default window size
            if app.EndOfFile.Value
                app.Offset_tf.Enable    = 'off';
                app.Offset_ms.Enable    = 'off';
                try
                    app.Offset_tf.Value     = app.DataTable.(5)(1);
                    app.Offset_ms.Value     = tf2ms(app,app.Offset_tf.Value,app.SamplingRate.Value);
                catch % if no data selected
                    app.Offset_tf.Value     = 1;
                    app.Offset_ms.Value     = 0;
                end
            end
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
            
%             PeriodLength        = app.Duration_tf.Value;
%             WindowSize          = app.Size_tf.Value;
%             Steps               = WindowSize - app.Overlap.Value; % Windows step
%             Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
%             app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.Frequencying); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [File,Path] = uigetfile('*.mat','Select parameter file','StudyParameters_EEGpalCS.mat');
            drawnow; figure(app.Frequencying); % Restaure the app module
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            load(app.ParameterFilePath.Value);
            
            try
                applyStudyParam(app);
            catch
                msgbox(sprintf('%s','No parameters saved for this module.'),...
                    'No parameters','warn');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save parameter file
            if ~isempty (app.ParameterFilePath.Value)
                app.Study_Parameters = load(app.ParameterFilePath.Value);
                app.Study_Parameters = updateStudyParam(app,app.Study_Parameters.Parameters);
                Parameters = app.Study_Parameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            else
                [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
                drawnow; figure(app.Frequencying); % Restaure the app module
                if isequal(File,0) || isequal(Path,0)
                   return; % User pressed cancel
                end
                app.ParameterFilePath.Value = fullfile(Path,File);
                app.SessionParameters = updateSessionParam(app,app.SessionParameters);
                Parameters = app.SessionParameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            Parameters = app.SessionParameters;
            
            % Save parameter file
            [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
            drawnow; figure(app.Frequencying); % Restaure the app module
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            save(app.ParameterFilePath.Value,'Parameters');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if isempty(app.DataTable)
                msgbox(sprintf('%s\n\n%s','No data to analyse!', ...
                    'Please make sure to select the files to analyse in the main window.'),...
                    'No data selected','warn');
                return
            end
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            Run = questdlg(sprintf('%s\n%s \n\n%s\n%s%s\n%s%d%s \n\n%s\n%s%s\n%s%.1f%s%d%s\n%s%d%s\n%s%s \n\n%s%.3f%s\n%s%.3f%s%.3f%s\n%s%s\n%s%s \n\n%s%d\n%s\n%s',...
                'Olà!',...
                'If you don''t check the options below properly, you will most likely end up cursing to the sky, but it will be too late...', ...
                ...
                'RESAMPLING',...
                'Resampling: ',app.SwitchResample.Value,...
                'Sampling rate: ',app.SamplingRate.Value,'Hz',...
                ...
                'FREQUENCY ANALYSIS',...
                'Frequency computation: ',app.FreqMethod.Value,...
                'Window size: ',app.Size_ms.Value,'ms, ',app.Size_tf.Value,'tf',...
                'Overlap: ',app.Overlap.Value,'tf',...
                'Windowing function: ',app.WindowingGroup.SelectedObject.Text,...
                ...
                'Frequency resolution: ',app.Resolution.Value,'Hz',...
                'Frequency range: ',app.LowerFreq.Value,' to ',app.UpperFreq.Value,' Hz',...
                'Frequency bands: ',app.FrequencyBands.Value,...
                'Normalization method: ',app.NormalizationGroup.SelectedObject.Text,...
                ...
                'Number of files: ',height(app.DataTable),...
                'A long list will take time to proceed. In order to reduce time, you can select subsets of files in the main window.',...
                'Do you wish to proceed?'),'Just a quick check before we go','OK','Cancel','Cancel');
            if strcmpi(Run,'Cancel')
                return
            end
            
            
            %% PROCESSING INFORMATION SHEET

            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Frequency_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Frequency Analysis Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            if strcmp(app.FreqMethod.Value,'eeglab (FFT)')
                fprintf(PifFileID,'%s\t%f\r\n','Current version of eeglab:',app.EEGpalCS_Main.Versions.eeglab);
            end
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.PSD_FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Study parameters file:',app.ParameterFilePath.Value);
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                fprintf(PifFileID,'%s\t\t\t%s\r\n','Channels cluster:',app.ChannelsCluster.Value);
                fprintf(PifFileID,'%s\r\n','Channels processed (Channel  /  Cluster):');
                fprintf(PifFileID,'\t\t%s\t\t%s\r\n',app.SessionParameters.Electrodes.SettingTable{:,[2 8]}');
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
            catch
                fprintf(PifFileID,'%s\r\n','No electrodes setting file. All channels processed.');
            end
            if length(unique(app.DataTable.nChan)) > 1
                fprintf(PifFileID,'%s\r\n','Inconsistent number of channels between files. GPS not computed');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','RESAMPLING');
            if strcmpi(app.InconsistentSR.Visible,'on')
                fprintf(PifFileID,'%s\r\n','Sampling rate variable');
            end
            fprintf(PifFileID,'%s\t%s\r\n','Resampling:',app.SwitchResample.Value);
            if strcmpi(app.SwitchResample.Value,'on')
                fprintf(PifFileID,'%s\t%d\r\n','New sampling rate:',app.SamplingRate.Value);
            else
                fprintf(PifFileID,'%s\t%d\r\n','No resampling. Original sampling rate:',app.OrigSamplingRate.Value);
            end
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','FREQUENCY ANALYSIS');
            fprintf(PifFileID,'%s\r\n','Method:');
            if strcmp(app.FreqMethod.Value,'Fast Fourier Transform (FFT)')
                fprintf(PifFileID,'\t%s\t\t%s\r\n','FFT computation:','Yes');
            else
                fprintf(PifFileID,'\t%s\t\t%s\r\n','FFT computation:','No');
            end
            if strcmp(app.FreqMethod.Value,'FFT Approximation (FFTA)')
                fprintf(PifFileID,'\t%s\t\t%s\r\n','FFTA computation:','Yes');
            else
                fprintf(PifFileID,'\t%s\t\t%s\r\n','FFTA computation:','No');
            end
            if strcmp(app.FreqMethod.Value,'eeglab (FFT)')
                fprintf(PifFileID,'\t%s\t\t%s\r\n','use of eeglab (FFT):','Yes');
            else
                fprintf(PifFileID,'\t%s\t\t%s\r\n','use of eeglab (FFT):','No');
            end
            
            fprintf(PifFileID,'%s\r\n','Frequencies:');
            fprintf(PifFileID,'\t%s\t%.3f\r\n','Frequency resolution:',app.Resolution.Value);
            fprintf(PifFileID,'\t%s\t\t%.2f %s\t%.2f %s\r\n','Frequency range:',app.LowerFreq.Value,'to',app.UpperFreq.Value,'Hz');
            fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:',app.FrequencyBands.Value);
            fprintf(PifFileID,'\t%s\r\n','Frequency bins:');
            FreqBins        = (0:app.Resolution.Value:app.MaxFreq.Value)';
            Range           = min(FreqBins >= app.LowerFreq.Value,FreqBins <= app.UpperFreq.Value);
            RangeBins       = FreqBins(Range);
            fprintf(PifFileID,'\t\t%.4f\r\n',RangeBins);
            
            fprintf(PifFileID,'%s\r\n','Analysis period ([ms] / [tf]):');
            fprintf(PifFileID,'\t%s\t\t%.1f %s\t%d %s\r\n','Onset:',app.Onset_ms.Value,'ms',app.Onset_tf.Value,'tf');
            if app.EndOfFile.Value
                fprintf(PifFileID,'\t%s\t\t%s\r\n','Offset:','End of file');
            else
                fprintf(PifFileID,'\t%s\t\t%.1f %s\t%d %s\r\n','Offset:',app.Offset_ms.Value,'ms',app.Offset_tf.Value,'tf');
            end
            fprintf(PifFileID,'\t%s\t%.1f %s\t%d %s\r\n','Duration:',app.Duration_ms.Value,'ms',app.Duration_tf.Value,'tf');
            fprintf(PifFileID,'%s\r\n','Windows:');
            fprintf(PifFileID,'\t%s\t\t%.1f %s\t%d %s\r\n','Size:',app.Size_ms.Value,'ms',app.Size_tf.Value,'tf');
            fprintf(PifFileID,'\t%s\t%d %s\r\n','Overlap:',app.Overlap.Value,'tf');
            fprintf(PifFileID,'%s\t%d\r\n','Number of windows:',app.nWindows.Value);
%             PeriodLength    = app.Duration_tf.Value;
%             WindowSize      = app.Size_tf.Value;
%             Steps           = WindowSize - app.Overlap.Value; % Windows step
%             Start           = 1:Steps:WindowSize; % Starting points in the period by steps
%             nWinArray       = arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start); % -WindowsSize so that only complete Windows are counted
%             Longest         = max(nWinArray * WindowSize + Start); % Period for windowing ending the latest, taking into account the delay at the start
%             Discarded_tf    = PeriodLength - Longest;
%             Discarded_ms    = tf2ms(app,Discarded_tf,app.OrigSamplingRate.Value);
%             fprintf(PifFileID,'\t%s\t%d %s\t%d %s\r\n','Portion of signal discarded at the end of the period:',Discarded_ms,'ms',Discarded_tf,'tf');
            
            fprintf(PifFileID,'\t%s\t\t%s\r\n','Windowing function:',app.WindowingGroup.SelectedObject.Text);
            fprintf(PifFileID,'\t%s\t%s\r\n','Normalization:',app.NormalizationGroup.SelectedObject.Text);
            % fprintf(PifFileID,'\t%s\r\n','Currently, only the ''Remove mean'' option does something, sorry!');
            
            if length(unique(app.DataTable.nChan)) > 1
                fprintf(PifFileID,'%s\r\n','GPS:');
                fprintf(PifFileID,'\t%s\r\n','Inconsistent number of channels between files. GPS not computed');
            else
                
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','SAVING');
            fprintf(PifFileID,'%s\r\n','Individual files saving options, channels-wise PSD:');
            if app.PSD_FileAvgWhole.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra (averaged time windows):','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra (averaged time windows):','No');
            end
            if app.PSD_FileSepWhole.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra (separate time windows):','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra (separate time windows):','No');
            end
            if ~isempty(app.FrequencyBands.Value)
                if app.PSD_FileAvgBands.Value
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands (averaged time windows):','Yes');
                else
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands (averaged time windows):','No');
                end
                if app.PSD_FileSepBands.Value
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands (separate time windows):','Yes');
                else
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands (separate time windows):','No');
                end
            else
                fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
            end
            if length(unique(app.DataTable.nChan)) == 1
                fprintf(PifFileID,'%s\r\n','Individual files saving options, GPS:');
                if app.GPS_FileAvgWhole.Value
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra (averaged time windows):','Yes');
                else
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra (averaged time windows):','No');
                end
                if app.GPS_FileSepWhole.Value
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra (separate time windows):','Yes');
                else
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra (separate time windows):','No');
                end
                if ~isempty(app.FrequencyBands.Value)
                    if app.GPS_FileAvgBands.Value
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands (averaged time windows):','Yes');
                    else
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands (averaged time windows):','No');
                    end
                    if app.GPS_FileSepBands.Value
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands (separate time windows):','Yes');
                    else
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands (separate time windows):','No');
                    end
                else
                    fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
                end
            end
            
            fprintf(PifFileID,'\r\n%s\r\n','Within-subject averaging, channels-wise PSD');
            if app.PSD_WithinWhole.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
            end
            if ~isempty(app.FrequencyBands.Value)
                if app.PSD_WithinBands.Value
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','No');
                end
            else
                fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
            end
            if length(unique(app.DataTable.nChan)) == 1
                fprintf(PifFileID,'%s\r\n','Within-subject averaging, GPS');
                if app.GPS_WithinWhole.Value
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
                end
                if ~isempty(app.FrequencyBands.Value)
                    if app.GPS_WithinBands.Value
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','Yes');
                    else
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','No');
                    end
                else
                    fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
                end
            end
            
            fprintf(PifFileID,'\r\n%s\r\n','Between-subject averaging, channels-wise PSD');
            if app.PSD_BetweenWhole.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
            end
            if ~isempty(app.FrequencyBands.Value)
                if app.PSD_BetweenBands.Value
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','No');
                end
            else
                fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
            end
            if length(unique(app.DataTable.nChan)) == 1
                fprintf(PifFileID,'%s\r\n','Between-subject averaging, GPS');
                if app.GPS_BetweenWhole.Value
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
                end
                if ~isempty(app.FrequencyBands.Value)
                    if app.GPS_BetweenBands.Value
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','Yes');
                    else
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','No');
                    end
                else
                    fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
                end
            end
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Subject  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';cellstr(app.DataTable.(7))';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN FREQUENCYING
            
            % Prepare values
            
%             ProcessedDataTable  = app.DataTable; % For updating the Main Window
            ChannelsCheck       = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            PeriodLength_ALL    = zeros(height(app.DataTable),1); % For 'OUTPUT FILES' in PIF
            nWindows_ALL        = zeros(height(app.DataTable),1); % For 'OUTPUT FILES' in PIF
            DiscardedTF_ALL     = zeros(height(app.DataTable),1); % For 'OUTPUT FILES' in PIF
            
            if strcmp(app.PSD_FilesExtension.Value,'.freq') || strcmp(app.PSD_FilesExtension.Value,'.sef')
                try
                    Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                catch
                    Channels = '';
                end
            elseif strcmp(app.PSD_FilesExtension.Value,'.set')
                try
                    ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    Channels            = ElectrodeSetting(:,2);
                    %Channels.labels     = char(ElectrodeSetting.labels);

                    % Recompute Theta and Radius to match EEGlab orientation
                    [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                    Channels.theta      = -rad2deg(az);
                    Channels.radius     = 0.5 - rad2deg(elev)/180;
                    Channels.sph_theta  = -Channels.theta;
                    Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                    Channels.X          = ElectrodeSetting.y;
                    Channels.Y          = -ElectrodeSetting.x;
                    Channels.Z          = ElectrodeSetting.z;
                    Channels            = table2struct(Channels);
                catch
                    Channels = '';
                end
            end
            
            FrequencyNames  = strcat(num2str(RangeBins,'%.2f'),repmat('Hz',length(RangeBins),1));
            if ~isempty(app.FrequencyBands.Value)
                Bands       = strrep(app.FrequencyBands.Value,':','-');
                Bands       = strsplit(Bands,{' ',',',';'});
                BandNames   = strcat(char(Bands'),repmat('Hz',length(Bands),1));
            end         
            
            
            disp('Run Frequencying...')
            StartTime = datetime('now');
            tStart = tic;
            
            h = waitbar(0,'Subject.....');
           
            
            if ~isempty(char(unique(app.DataTable.(7))))
                SUBJ = unique(app.DataTable.(7));
            else % if no subjects identified, process each file separately
                num = arrayfun(@(x) sprintf('%02d',x),1:height(app.DataTable),'UniformOutput',false);
                SUBJ = strcat(repmat('file',height(app.DataTable),1),string(num'));
                app.DataTable.Subject = SUBJ;
            end
            
            
            % Structure for between-subject data
            Between.nSubj           = length(SUBJ); % number of subjects or files
            Between.FFTpsd          = []; % for FFT of each subject, range-wise
            Between.FFTpsd_GPS      = []; % for FFT GPS of each subject, range-wise
            Between.FFTApsd         = []; % for FFTA of each subject, range-wise
            Between.FFTApsd_GPS     = []; % for FFTA GPS of each subject, range-wise
            Between.FFTbands        = []; % for FFT of each subject, band-wise
            Between.FFTbands_GPS    = []; % for FFT GPS of each subject, band-wise
            Between.FFTAbands       = []; % for FFTA of each subject, band-wise
            Between.FFTAbands_GPS   = []; % for FFTA GPS of each subject, band-wise
            
            nbSubj= length(SUBJ);
            for subj = 1:nbSubj
                waitbar(subj/nbSubj,h, strcat('Subject ',num2str(subj),'/',num2str(nbSubj)));
                disp(['Processing ', char(SUBJ(subj,1)), ' (', num2str(subj), ' out of ', num2str(length(SUBJ)), ')'])
                SubjDataTable   = app.DataTable(strcmp(app.DataTable.Subject,SUBJ(subj,1)),:);
                
                % Structure for within-subject data
                SubjData.FFTpsd     = []; % for data of concateneted FFT, subject-wise
                SubjData.FFTApsd    = []; % for data of concateneted FFTA, subject-wise
                
                
                for file = 1:height(SubjDataTable)
                    % Load file
                    disp(['Loading ', cell2mat(fullfile(SubjDataTable.Path(file),SubjDataTable.File(file)))])
                    disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                    openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(SubjDataTable.Path(file),'..'),SubjDataTable.File(file))); % full path and name of the file
                    [~,FileName,FileExtension] = fileparts(openfilename);
                    if strcmpi(FileExtension,'.bdf')
                        [header,data] = open_bdf(openfilename);
                        FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                    elseif strcmpi(FileExtension,'.set')
                        [header,data] = open_eeglab(openfilename);
                        FileChannels = header.Channels;
                    elseif strcmpi(FileExtension,'.sef')
                        [header,data] = open_sef(openfilename);
                        FileChannels = header.Channels;
                    elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                        [header,data] = open_eph(openfilename);
                    else
                        if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                            disp('These tools are not intended for .freq or .ris files.')
                        else
                            disp('File extension not recognized.')
                        end
                        disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                        disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                        disp('File skipped.')
                        continue
                    end
                    disp('File loaded')
                    
                    
                    % Remove unselected channels from the EEGpalCS Electrode Setting window
                    try
                        data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                    
                        % Channels check (if electrode setting defined)
                        try
                            Chan = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                            if isequal(char(FileChannels),char(Chan))
                                ChannelsCheck(file,1)   = "OK";
                            else
                                ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                            end
                        catch
                            ChannelsCheck(file,1)   = "No channels in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No electrode setting defined";
                    end
                    
                    
                    % Re-sampling
                    if strcmpi(app.SwitchResample.Value,'On')
                        if app.DataTable.SamplingRate(file) == app.SamplingRate.Value
                            disp(['New sampling rate (', num2str(app.SamplingRate.Value), ') same as original.'])
                            disp('Resampling not computed.')
                        else
                            disp(['Resampling from ', num2str(app.DataTable.SamplingRate(file)),'Hz to ',num2str(app.SamplingRate.Value),'Hz'])
                            data = resample(data,app.SamplingRate.Value,app.DataTable.SamplingRate(file));
                            disp('Resampling completed')
                        end
                    end
                    
                    
                    % Prepare to process
                    OnsetTF         = app.Onset_tf.Value; % (to delete)
                    if app.EndOfFile.Value
                        %OffsetTF    = SubjDataTable.(5)(file);
                        OffsetTF    = size(data,1); % In case of .ep file, column 5 in the data table will be empty
                    else
                        OffsetTF    = app.Offset_tf.Value;
                    end
                    data = data(OnsetTF:OffsetTF,:);
                    PeriodLength = size(data,1);
                    
                    % For 'OUTPUT FILES' in PIF
                    PeriodLength_ALL(file+(subj-1),1)   = PeriodLength;
                    WindowSize                          = app.Size_tf.Value;
                    Steps                               = WindowSize - app.Overlap.Value; % Windows step
                    Start                               = 1:Steps:WindowSize; % Starting points in the period by steps
                    nWindows_ALL(file+(subj-1),1)       = arrayfun(@(x) length(x:WindowSize:PeriodLength-(WindowSize-1)),Start); % -(WindowsSize-1) so that only complete Windows are counted
                    Longest                             = max(nWindows_ALL(file+(subj-1),1) * WindowSize + Start); % Period for windowing ending the latest, taking into account the delay at the start
                    DiscardedTF_ALL(file+(subj-1),1)    = PeriodLength - (Longest-1);
                    
                    
                    % Frequency computation
                    disp(['Processing subject ', num2str(subj), ' out of ', num2str(length(SUBJ))])
                    disp(['Processing file ', num2str(file), ' out of ', num2str(height(SubjDataTable)),' for this subject'])
                    
                    FFTpsd      = []; % for data of concateneted FFT, file-wise
                    FFTApsd     = []; % for data of concateneted FFTA, file-wise
                    nWin        = 0; % the number of time windows
                    
                    reverseStr  = ''; % For progression text
                    Steps_tf    = WindowSize - app.Overlap.Value;
                    nSteps      = length(1:Steps_tf:(PeriodLength-(WindowSize-1)));
                    
                    if ~max(contains(app.FreqMethod.Value,'eeglab'))
                        %% compute matlab FFT or FFTA (loop across windows)
                        
                        for step = 1:Steps_tf:(PeriodLength-(WindowSize-1)) %compute of FFT for each EPOCH separetaly
                            WINDOW = data(step:step+WindowSize-1,:); %extract the data from the current Epochs
                            
                            if max(contains(app.FreqMethod.Value,'Fast Fourier Transform (FFT)'))
                                [FFTdata,~]             = frequency_computation('FFT',WINDOW,app.SamplingRate.Value,app.WindowingGroup.SelectedObject.Text); %FFTA contains already the power for each frequency bin. It is not the FFT itself
                                FFTdata                 = FFTdata(Range,:); %remove frequency bin that the user don't want (for example 2-30 Hz instead of the whole range 0-512 Hz).
                                
    %                             % Savitzky-Golay filter (smoothing)
    %                             if app.SGF_CheckBox.Value
    %                                 SGFpsd = zeros(size(FFTdata));
    %                                 for chan = 1:size(FFTdata,2)
    %                                     [SGFpsd(:,chan),~,~] = ...
    %                                         sgfDiff(FFTdata(:,chan),app.SGF_FrameWidth.Value,app.SGF_PolyOrder.Value,app.SamplingRate.Value,app.Size_tf.Value);
    %                                 end
    %                                 FFTdata = SGFpsd;
    %                             end
                                FFTpsd                  = cat(3,FFTpsd,FFTdata); %include frequency decomposition for each Epoch/windows
                            end
                            
                            if max(contains(app.FreqMethod.Value,'FFT Approximation (FFTA)'))
                                [FFTAdata,~]            = frequency_computation('FFTA',WINDOW,app.SamplingRate.Value,app.WindowingGroup.SelectedObject.Text);
                                FFTAdata                = FFTAdata(Range,:);  
                                
    %                             % Savitzky-Golay filter (normalization)
    %                             if app.SGF_CheckBox.Value
    %                                 SGFpsd = zeros(size(FFTAdata));
    %                                 for chan = 1:size(FFTAdata,2)
    %                                     [SGFpsd(:,chan),~,~] = ...
    %                                         sgfDiff(FFTAdata(:,chan),app.SGF_FrameWidth.Value,app.SGF_PolyOrder.Value,app.SamplingRate.Value,app.Size_tf.Value);
    %                                 end
    %                                 FFTAdata = SGFpsd;
    %                             end
                                FFTApsd                  = cat(3,FFTApsd,FFTAdata); %concatenate to have the data of each Epoch in one variable
                                
                            end       
                            
                            nWin = nWin + 1;
                            
                            % Progression
                            reverseStr  = txtprogressbar(app,nSteps,nWin,reverseStr);
                        end
                    else
                        %% compute eeglab FFT (loop across channels)
                        %convert into an eeglab structure (.set)
                        ALLEEG=struct; EEG=struct;
                        
                        try
                            ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                            Channels            = ElectrodeSetting(:,2);
                            %Channels.labels     = char(ElectrodeSetting.labels);
    
                            % Recompute Theta and Radius to match EEGlab orientation
                            [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                            Channels.theta      = -rad2deg(az);
                            Channels.radius     = 0.5 - rad2deg(elev)/180;
                            Channels.sph_theta  = -Channels.theta;
                            Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                            Channels.X          = ElectrodeSetting.y;
                            Channels.Y          = -ElectrodeSetting.x;
                            Channels.Z          = ElectrodeSetting.z;
    %                         Channels.ref        = char(repmat({''},height(Channels),1));
    %                         Channels.sph_radius = repmat({[]},height(Channels),1);
    %                         Channels.types      = char(repmat({''},height(Channels),1));
    %                         Channels.urchan     = repmat({[]},height(Channels),1);
                            Channels            = table2struct(Channels);
                            Channels            = Channels(app.ChanCompIndices,:); % Keep only channels to process
                        catch
                            Channels = '';
                        end
                        EEG = eeglab_datastruct(openfilename,data,app.SamplingRate.Value,[],Channels);
                        

                        %initialisation 
                        frequenciesToStudy=[app.LowerFreq.Value:app.Resolution.Value:app.UpperFreq.Value];
                        ersp=zeros(length(frequenciesToStudy),app.nWindows.Value,EEG.nbchan); %ersp=event-related spectral perturbation (correspond to FFT) (frequency X windows X channels)
                        
                        for i=1:EEG.nbchan
                            %calcul de la puissance moyenne pour chaque électrodes
                            ersp(:,:,i) = pop_newtimef( EEG, 1, i, [app.Onset_ms.Value  app.Offset_ms.Value], 0 , 'baseline',NaN, 'freqs', frequenciesToStudy, 'scale', 'abs', 'ntimesout', app.nWindows.Value, 'winsize', app.Size_tf.Value, 'plotersp', 'off', 'plotitc' , 'off', 'plotphase', 'off'); %for details about the arguments, please read the FAQ of the help file
                        end
                        
                        %reorganisation de la matrice ersp (frequency X windows X channels) en PSD (frequency X channels X windows)
                        FFTpsd=permute(ersp, [1, 3, 2]);

                    end


                    % Normalization only for FFT
                    if ~strcmp(app.NormalizationGroup.SelectedObject.Text,'None')
                        disp('Normalization')
                        reverseStr      = ''; % For progression text
                        nFreq           = size(FFTpsd,1); %number of frequency bin
                        freqRobustMean  = ones(size(FFTpsd,1),1);
                        for freq = 1:size(FFTpsd,1)
                            freqSpectra             = abs(FFTpsd(freq,:,:)); % extacrt the power for each electrod (vector) and each Epochs in the current frequency bin
                            freqOutliersDist        = (mad(freqSpectra,1,'all') * 1.4826) * 2.5; % 1.4826 = constant, 2.5 = level of decision
                            freqInfLim              = median(freqSpectra,'all') - freqOutliersDist;
                            freqSupLim              = median(freqSpectra,'all') + freqOutliersDist;
                            freqIncluded            = freqSpectra(max(freqSpectra>=freqInfLim,freqSpectra<=freqSupLim)); % rejection of outlier electrod power value
                            freqRobustMean(freq,1)  = mean(freqIncluded,'all'); %compute the average power for this frequency bin
                                                    
                            % Progression
                            reverseStr  = txtprogressbar(app,nFreq,freq,reverseStr);
                        end
                        
                        if contains(app.NormalizationGroup.SelectedObject.Text,'global averaged Power')
                            % normalization by the mean power to make the
                            % values more comparable between participants
                            FFTpsd          = FFTpsd ./ mean(freqRobustMean);
                        elseif contains(app.NormalizationGroup.SelectedObject.Text,'mean per frequency')
                            %normalization for each frequency bin by it's
                            %average power. It correct the 1/f difference
                            %of power between low and high frequency
                            freqRobustMean  = freqRobustMean / mean(freqRobustMean);
                            FFTpsd          = FFTpsd ./ freqRobustMean;
                        elseif contains(app.NormalizationGroup.SelectedObject.Text,'Log transf.')
                            %transform the signal in dB
                            FFTpsd  = 10 .* log10(FFTpsd ./ freqRobustMean);
                        end
                    end
                    %
                    
                    % Save data
                    
                    if ~isempty(app.SuffixString.Value)
                        suffix = ['_', app.SuffixString.Value];
                    end
                    
                    SubPath = SubjDataTable.Path{file}(3:end);
                    NewPath = fullfile(app.SaveFolder.Value{:},SubPath,'Frequencying');
                    % Creating the folder
                    if ~exist(NewPath, 'dir')
                        mkdir(NewPath);
                    end


                    %% FFT
                    
                    if max(contains(app.FreqMethod.Value,'(FFT)'))
                        
                        % Prepare Bands
                        if ~isempty(app.FrequencyBands.Value)
                            FFTbands    = zeros(length(Bands),size(FFTpsd,2),size(FFTpsd,3));
                            for band = 1:length(Bands)
                                BAND    = char(Bands(1,band));
                                hphn    = find(BAND=='-');
                                Lower   = str2double(BAND(1:hphn-1));
                                Upper   = str2double(BAND(hphn+1:end));
                                BANDidx = min(RangeBins >= Lower,RangeBins <= Upper);
                                if max(contains(app.FreqMethod.Value,'(FFT)'))
                                    FFTbands(band,:,:)  = mean(FFTpsd(BANDidx,:,:),1);
                                end
                            end
                        end
                        
                        disp('Saving individual FFT')
                        
                        if app.PSD_FileAvgWhole.Value || app.GPS_FileAvgWhole.Value
                            FFTpsd_mean = mean(FFTpsd,3); %mean over windows
                        end
                        if app.PSD_FileAvgBands.Value || app.GPS_FileAvgBands.Value
                            FFTbands_mean = mean(FFTbands,3); %mean over windows
                        end
                        
                        
                        % PSD
                        
                        %disp('Saving individual FFT power spectra files')
                        if app.PSD_FileSepWhole.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_spectra', app.PSD_FilesExtension.Value]);
                            %disp(['Saving individual FFT power spectra to ',savefilename])
                            if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                save_freq(savefilename,FFTpsd,header.SamplingRate,app.Resolution.Value,FrequencyNames,Channels);                            
                            elseif strcmp(app.PSD_FilesExtension.Value,'.mat')
                                save(savefilename,'FFTpsd');                             
                            end
                        end
                        if app.PSD_FileSepBands.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_bands', app.PSD_FilesExtension.Value]);
                            %disp(['Saving individual FFT frequency bands to ',savefilename])
                            if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                save_freq(savefilename,FFTbands,header.SamplingRate,app.Resolution.Value,BandNames,Channels);                             
                            elseif strcmp(app.PSD_FilesExtension.Value,'.mat')
                                save(savefilename,'FFTbands');                             
                            end
                        end
                        if app.PSD_FileAvgWhole.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_avgspectra', app.PSD_FilesExtension.Value]);
                            %disp(['Saving individual FFT averaged power spectra to ',savefilename])
                            if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                save_freq(savefilename,FFTpsd_mean,header.SamplingRate,app.Resolution.Value,FrequencyNames,Channels);
                            elseif strcmp(app.PSD_FilesExtension.Value,'.mat')
                                save(savefilename,'FFTpsd_mean');                             
                            end
                        end
                        if app.PSD_FileAvgBands.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_avgbands', app.PSD_FilesExtension.Value]);
                            %disp(['Saving individual FFT averaged frequency bands to ',savefilename])
                            if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                save_freq(savefilename,FFTbands_mean,header.SamplingRate,app.Resolution.Value,BandNames,Channels);
                            elseif strcmp(app.PSD_FilesExtension.Value,'.mat')
                                save(savefilename,'FFTbands_mean');
                            end
                        end
                        
                        % GPS
                        
                        %disp('Saving individual FFT GPS files')
                        if app.GPS_FileSepWhole.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_GPSspectra', app.GPS_FilesExtension.Value]);
                            disp(['Saving individual FFT power spectra GPS to ',savefilename])
                            FFTpsd_gps = compute_gfp(FFTpsd,'GPS'); % Frequency x Window
                            GPStable = array2table(FFTpsd_gps','VariableNames',string(FrequencyNames),...
                                'RowNames',string([strcat(repmat('Window',size(FFTpsd_gps,2),1)),num2str((1:size(FFTpsd_gps,2))')]));
                            writetable(GPStable,savefilename,'WriteRowNames',true)
                        end
                        if app.GPS_FileSepBands.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_GPSbands', app.GPS_FilesExtension.Value]);
                            disp(['Saving individual FFT frequency bands GPS to ',savefilename])
                            FFTbands_gps = compute_gfp(FFTbands,'GPS'); % Band x Window
                            GPStable = array2table(FFTbands_gps','VariableNames',string(BandNames),...
                                'RowNames',string([strcat(repmat('Window',size(FFTbands_gps,2),1)),num2str((1:size(FFTbands_gps,2))')]));
                            writetable(GPStable,savefilename,'WriteRowNames',true)
                        end
                        if app.GPS_FileAvgWhole.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_GPSavgspectra', app.GPS_FilesExtension.Value]);
                            disp(['Saving individual FFT averaged power spectra GPS to ',savefilename])
                            FFTpsd_mean_gps = compute_gfp(FFTpsd_mean,'GPS'); % Frequency x Window
                            GPStable = array2table(FFTpsd_mean_gps','VariableNames',string(FrequencyNames),...
                                'RowNames',string([strcat(repmat('Window',size(FFTpsd_mean_gps,2),1)),num2str((1:size(FFTpsd_mean_gps,2))')]));
                            writetable(GPStable,savefilename,'WriteRowNames',true)
                        end
                        if app.GPS_FileAvgBands.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_GPSavgbands', app.GPS_FilesExtension.Value]);
                            disp(['Saving individual FFT averaged frequency bands GPS to ',savefilename])
                            FFTbands_mean_gps = compute_gfp(FFTbands_mean,'GPS'); % Band x Window
                            GPStable = array2table(FFTbands_mean_gps','VariableNames',string(BandNames),...
                                'RowNames',string([strcat(repmat('Window',size(FFTbands_mean_gps,2),1)),num2str((1:size(FFTbands_mean_gps,2))')]));
                            writetable(GPStable,savefilename,'WriteRowNames',true)
                        end
                    end
                    
                    
                    %% FFTA
                    
                    if max(contains(app.FreqMethod.Value,'(FFTA)'))
                        % Adjust polartiy
                        disp('Adjusting FFTA polarity')
                        for freq = 1:length(RangeBins)
                            DataMatrix = squeeze(FFTApsd(freq,:,:)); % Data matrix for one frequency with Channels x Window
                            CorrMatrix = corrcoef(DataMatrix); % Correlation matrix (Window x Window)
                            AvgCorr = mean(abs(CorrMatrix),2); % Average correlations of one window with all others (including itself)
                            [~,I] = max(AvgCorr); % I is the index if the max value
                            
                            % Adjust polartiy so that all files and frequencies have the same polarity
                            if ~isempty(Between.FFTApsd) % adjusts the polarity of the reference epoch of the current file with the average of the previous file
                                AVG = mean(Between.FFTApsd,3);
                                CorrToRefFile = corrcoef(DataMatrix(:,I),AVG(freq,:)); % computes the correlation of the current file and frequency for the epoch with max correlation with the average of the previous file
                                if CorrToRefFile(2,1) < 0
                                    DataMatrix = DataMatrix * (-1);
                                end
                            elseif freq == 1 % check polarity of the first frequency of the first file
                                RefPolarity = DataMatrix(:,I);
                            elseif freq > 1 % adjust the polartiy of subsequent frequencies of the first file
                                CorrToRefFile = corrcoef(DataMatrix(:,I),RefPolarity); % computes the correlation of the current file and frequency for the epoch with max correlation with the average of the previous file
                                if CorrToRefFile(2,1) < 0
                                    DataMatrix = DataMatrix * (-1);
                                end
                            end
                            
                            for window = 1:length(CorrMatrix)
                                if CorrMatrix(window,I) > 0
                                    FFTApsd(freq,:,window) = DataMatrix(:,window);
                                else
                                    FFTApsd(freq,:,window) = DataMatrix(:,window) * (-1);
                                end
                            end
                        end


                        % Prepare Bands
                        if ~isempty(app.FrequencyBands.Value)
                            FFTAbands   = zeros(length(Bands),size(FFTApsd,2),size(FFTApsd,3));
                            for band = 1:length(Bands)
                                BAND    = char(Bands(1,band));
                                hphn    = find(BAND=='-');
                                Lower   = str2double(BAND(1:hphn-1));
                                Upper   = str2double(BAND(hphn+1:end));
                                BANDidx = min(RangeBins >= Lower,RangeBins <= Upper);
                                if max(contains(app.FreqMethod.Value,'(FFTA)'))
                                    FFTAbands(band,:,:) = mean(FFTApsd(BANDidx,:,:),1);
                                end
                            end
                        end
                        
                        disp('Saving individual FFTA')
                        
                        if app.PSD_FileAvgWhole.Value || app.GPS_FileAvgWhole.Value
                            FFTApsd_mean = mean(FFTApsd,3);
                        end
                        if app.PSD_FileAvgBands.Value || app.GPS_FileAvgBands.Value
                            FFTAbands_mean = mean(FFTAbands,3);
                        end
                        
                        % PSD
                        %disp('Saving individual FFTA power spectra files')
                        if app.PSD_FileSepWhole.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_spectra', app.PSD_FilesExtension.Value]);
                            %disp(['Saving individual FFTA power spectra to ',savefilename])
                            if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                save_freq(savefilename,FFTApsd,header.SamplingRate,app.Resolution.Value,FrequencyNames,Channels);                             
                            elseif strcmp(app.PSD_FilesExtension.Value,'.mat')
                                save(savefilename,'FFTApsd');
                            end
                        end
                        if app.PSD_FileSepBands.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_bands', app.PSD_FilesExtension.Value]);
                            %disp(['Saving individual FFTA frequency bands to ',savefilename])
                            if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                save_freq(savefilename,FFTAbands,header.SamplingRate,app.Resolution.Value,BandNames,Channels);
                            elseif strcmp(app.PSD_FilesExtension.Value,'.mat')
                                save(savefilename,'FFTAbands');                             
                            end
                        end
                        if app.PSD_FileAvgWhole.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_avgspectra', app.PSD_FilesExtension.Value]);
                            %disp(['Saving individual FFTA averaged power spectra to ',savefilename])
                            if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                save_freq(savefilename,FFTApsd_mean,header.SamplingRate,app.Resolution.Value,FrequencyNames,Channels);
                            elseif strcmp(app.PSD_FilesExtension.Value,'.mat')
                                save(savefilename,'FFTApsd_mean');
                            end
                        end
                        if app.PSD_FileAvgBands.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_avgbands', app.PSD_FilesExtension.Value]);
                            %disp(['Saving individual FFTA averaged frequency bands to ',savefilename])
                            if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                save_freq(savefilename,FFTAbands_mean,header.SamplingRate,app.Resolution.Value,BandNames,Channels);
                            elseif strcmp(app.PSD_FilesExtension.Value,'.mat')
                                save(savefilename,'FFTAbands_mean');
                            end
                        end
                        
                        % GPS
                        %disp('Saving individual FFTA GPS files')
                        if app.GPS_FileSepWhole.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_GPSspectra', app.GPS_FilesExtension.Value]);
                            %disp(['Saving individual FFTA power spectra GPS to ',savefilename])
                            FFTApsd_gps = compute_gfp(FFTApsd,'GPS'); % Frequency x Window
                            GPStable = array2table(FFTApsd_gps','VariableNames',string(FrequencyNames),...
                                'RowNames',string([strcat(repmat('Window',size(FFTApsd_gps,2),1)),num2str((1:size(FFTApsd_gps,2))')]));
                            writetable(GPStable,savefilename,'WriteRowNames',true)
                        end
                        if app.GPS_FileSepBands.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_GPSbands', app.GPS_FilesExtension.Value]);
                            %disp(['Saving individual FFTA frequency bands GPS to ',savefilename])
                            FFTAbands_gps = compute_gfp(FFTAbands,'GPS'); % Band x Window
                            GPStable = array2table(FFTAbands_gps','VariableNames',string(BandNames),...
                                'RowNames',string([strcat(repmat('Window',size(FFTAbands_gps,2),1)),num2str((1:size(FFTAbands_gps,2))')]));
                            writetable(GPStable,savefilename,'WriteRowNames',true)
                        end
                        if app.GPS_FileAvgWhole.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_GPSavgspectra', app.GPS_FilesExtension.Value]);
                            %disp(['Saving individual FFTA averaged power spectra GPS to ',savefilename])
                            FFTApsd_mean_gps = compute_gfp(FFTApsd_mean,'GPS'); % Frequency x Window
                            GPStable = array2table(FFTApsd_mean_gps','VariableNames',string(FrequencyNames),...
                                'RowNames',string([strcat(repmat('Window',size(FFTApsd_mean_gps,2),1)),num2str((1:size(FFTApsd_mean_gps,2))')]));
                            writetable(GPStable,savefilename,'WriteRowNames',true)
                        end
                        if app.GPS_FileAvgBands.Value
                            savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_GPSavgbands', app.GPS_FilesExtension.Value]);
                            %disp(['Saving individual FFT averaged frequency bands GPS to ',savefilename])
                            FFTAbands_mean_gps = compute_gfp(FFTAbands_mean,'GPS'); % Band x Window
                            GPStable = array2table(FFTAbands_mean_gps','VariableNames',string(BandNames),...
                                'RowNames',string([strcat(repmat('Window',size(FFTAbands_mean_gps,2),1)),num2str((1:size(FFTAbands_mean_gps,2))')]));
                            writetable(GPStable,savefilename,'WriteRowNames',true)
                        end
                    end
                    
                    

                    % Update within-subject data
                    if max(contains(app.FreqMethod.Value,'(FFT)'))
                        SubjData.FFTpsd     = cat(3,SubjData.FFTpsd,FFTpsd);
                    end
                    if max(contains(app.FreqMethod.Value,'(FFTA)'))
                        SubjData.FFTApsd    = cat(3,SubjData.FFTApsd,FFTApsd);
                    end
                end
                
                
                %% Study of the mean within or between

% this section of the code has been develop by MichaelDP for the first version
% of the frequency module. It need a recheck after update of the other section of the module. 
% This code has been deactivated for you because it is not
% interresting for our analysis now.
% 
% 
%                 %comment by Michael Mouthon because it is exactely the same
%                 %code as in line 1225. Need an update from MichaelDP.
% %                 % Normalization (removal of 1/f noise)
% %                 if ~strcmp(app.NormalizationGroup.SelectedObject.Text,'None')
% %                     disp('Removing 1/f noise')
% %                     reverseStr      = ''; % For progression text
% %                     nFreq           = size(SubjData.FFTpsd,1);
% %                     freqRobustMean  = ones(size(SubjData.FFTpsd,1),1);
% %                     for freq = 1:size(SubjData.FFTpsd,1)
% %                         freqSpectra             = abs(SubjData.FFTpsd(freq,:,:)); % could squeeze it...
% %                         freqOutliersDist        = (mad(freqSpectra,1,'all') * 1.4826) * 2.5; % 1.4826 = constant, 2.5 = level of decision
% %                         freqInfLim              = median(freqSpectra,'all') - freqOutliersDist;
% %                         freqSupLim              = median(freqSpectra,'all') + freqOutliersDist;
% %                         freqIncluded            = freqSpectra(max(freqSpectra>=freqInfLim,freqSpectra<=freqSupLim));
% %                         freqRobustMean(freq,1)  = mean(freqIncluded,'all');
% %                         %FFTpsd(freq,:,:)        = FFTpsd(freq,:,:) ./ (1 / freqRobustMean);
% %                         
% %                         % Progression
% %                         reverseStr  = txtprogressbar(app,nFreq,freq,reverseStr);
% %                     end
% %                     if contains(app.NormalizationGroup.SelectedObject.Text,'Log transform')
% %                         %disp('Removing 1/f noise (log transform)')
% %                         SubjData.FFTpsd  = 10 .* log10(SubjData.FFTpsd ./ freqRobustMean);
% %                     end
% %                     if contains(app.NormalizationGroup.SelectedObject.Text,'Remove mean')
% %                         %disp('Removing 1/f noise by subtracting the mean power over each channel and window, per frequency')
% %                         freqRobustMean  = freqRobustMean / mean(freqRobustMean);
% %                         SubjData.FFTpsd          = SubjData.FFTpsd ./ freqRobustMean;
% %                     end
% %                 end
% 
% 
%                 % Saving within/between Subject data Averaging
% 
%                 if app.PSD_WithinWhole.Value || app.PSD_WithinBands.Value || app.GPS_WithinWhole.Value || app.GPS_WithinBands.Value
%                     SubjPath = fullfile(app.SaveFolder.Value{:},'Subject averages',char(SUBJ(subj)));
%                     % Creating the folder
%                     if ~exist(SubjPath, 'dir')
%                         mkdir(SubjPath);
%                     end
%                 end
% 
%                 if ~isempty(app.FrequencyBands.Value)
%                     SubjData.FFTbands   = zeros(length(Bands),size(SubjData.FFTpsd,2),size(SubjData.FFTpsd,3));
%                     SubjData.FFTAbands  = zeros(length(Bands),size(SubjData.FFTApsd,2),size(SubjData.FFTApsd,3));
%                     for band = 1:length(Bands)
%                         BAND    = char(Bands(1,band));
%                         hphn    = find(BAND=='-');
%                         Lower   = str2double(BAND(1:hphn-1));
%                         Upper   = str2double(BAND(hphn+1:end));
%                         BANDidx = min(RangeBins >= Lower,RangeBins <= Upper);
%                         if max(contains(app.FreqMethod.Value,'(FFT)'))
%                             SubjData.FFTbands(band,:,:)     = mean(SubjData.FFTpsd(BANDidx,:,:),1);
%                         end
%                         if max(contains(app.FreqMethod.Value,'(FFTA)'))
%                             SubjData.FFTAbands(band,:,:)    = mean(SubjData.FFTApsd(BANDidx,:,:),1);
%                         end
%                     end
%                 end
% 
% 
%                 % FFT
% 
%                 if max(contains(app.FreqMethod.Value,'(FFT)'))
%                     disp(['Saving FFT files for subject ',char(SUBJ(subj))])
% 
%                     if app.PSD_WithinWhole.Value || app.GPS_WithinWhole.Value
%                         SubjData.FFTpsd_mean = mean(SubjData.FFTpsd,3);
%                     end
%                     if app.PSD_WithinBands.Value || app.GPS_WithinBands.Value
%                         SubjData.FFTbands_mean = mean(SubjData.FFTbands,3);
%                     end
% 
% 
%                     % PSD
% 
%                     %disp(['Saving FFT power spectra files for subject ',char(SUBJ(subj))])
%                     if app.PSD_WithinWhole.Value
%                         savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFT_avgspectra', app.PSD_FilesExtension.Value]);
%                         %disp(['Saving FFT power spectra for ',char(SUBJ(subj))])
%                         if strcmp(app.PSD_FilesExtension.Value,'.freq')
%                             save_freq(savefilename,SubjData.FFTpsd_mean,app.SamplingRate.Value,app.Resolution.Value,FrequencyNames,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
% %                                     save_eph(savefilename,FFTpsd_mean,header.SamplingRate)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
% %                                     save_sef(savefilename,FFTpsd_mean,header.SamplingRate,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
% %                                     save_eeglab(savefilename,FFTpsd_mean,header.SamplingRate,[],[],Channels)
%                         end
%                     end
%                     if app.PSD_WithinBands.Value
%                         savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFT_avgbands', app.PSD_FilesExtension.Value]);
%                         %disp(['Saving FFT frequency bands for ',char(SUBJ(subj))])
%                         if strcmp(app.PSD_FilesExtension.Value,'.freq')
%                             save_freq(savefilename,SubjData.FFTbands_mean,app.SamplingRate.Value,app.Resolution.Value,BandNames,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
% %                                     save_eph(savefilename,FFTbands_mean,header.SamplingRate)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
% %                                     save_sef(savefilename,FFTbands_mean,header.SamplingRate,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
% %                                     save_eeglab(savefilename,FFTbands_mean,header.SamplingRate,[],[],Channels)
%                         end
%                     end
% 
% 
%                     % GPS
% 
%                     %disp(['Saving FFT GPS files for subject ',char(SUBJ(subj))])
%                     if app.GPS_WithinWhole.Value
%                         savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFT_GPSspectra', app.GPS_FilesExtension.Value]);
%                         %disp(['Saving FFT power spectra GPS for ',char(SUBJ(subj))])
%                         SubjData.FFTpsd_mean_gps = compute_gfp(SubjData.FFTpsd_mean,'GPS'); % Frequency
%                         GPStable = array2table(SubjData.FFTpsd_mean_gps','VariableNames',string(FrequencyNames));
%                         writetable(GPStable,savefilename)
%                     end
%                     if app.GPS_WithinBands.Value
%                         savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFT_GPSbands', app.GPS_FilesExtension.Value]);
%                         %disp(['Saving FFT frequency bands GPS for ',char(SUBJ(subj))])
%                         SubjData.FFTbands_mean_gps = compute_gfp(SubjData.FFTbands_mean,'GPS'); % Band
%                         GPStable = array2table(SubjData.FFTbands_mean_gps','VariableNames',string(BandNames));
%                         writetable(GPStable,savefilename)
%                     end
%                 end
% 
% 
%                 % FFTA
% 
%                 if max(contains(app.FreqMethod.Value,'(FFTA)'))
%                     disp(['Saving FFTA files for subject ',char(SUBJ(subj))])
% 
%                     if app.PSD_WithinWhole.Value || app.GPS_WithinWhole.Value
%                         SubjData.FFTApsd_mean = mean(SubjData.FFTApsd,3);
%                     end
%                     if app.PSD_WithinBands.Value || app.GPS_WithinBands.Value
%                         SubjData.FFTAbands_mean = mean(SubjData.FFTAbands,3);
%                     end
% 
% 
%                     % PSD
% 
%                     %disp(['Saving FFTA power spectra files for subject ',char(SUBJ(subj))])
%                     if app.PSD_WithinWhole.Value
%                         savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFTA_avgspectra', app.PSD_FilesExtension.Value]);
%                         %disp(['Saving FFTA power spectra for ',char(SUBJ(subj))])
%                         if strcmp(app.PSD_FilesExtension.Value,'.freq')
%                             save_freq(savefilename,SubjData.FFTApsd_mean,app.SamplingRate.Value,app.Resolution.Value,FrequencyNames,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
% %                                     save_eph(savefilename,FFTpsd_mean,header.SamplingRate)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
% %                                     save_sef(savefilename,FFTpsd_mean,header.SamplingRate,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
% %                                     save_eeglab(savefilename,FFTpsd_mean,header.SamplingRate,[],[],Channels)
%                         end
%                     end
%                     if app.PSD_WithinBands.Value
%                         savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFTA_avgbands', app.PSD_FilesExtension.Value]);
%                         %disp(['Saving FFTA frequency bands for ',char(SUBJ(subj))])
%                         if strcmp(app.PSD_FilesExtension.Value,'.freq')
%                             save_freq(savefilename,SubjData.FFTAbands_mean,app.SamplingRate.Value,app.Resolution.Value,BandNames,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
% %                                     save_eph(savefilename,FFTbands_mean,header.SamplingRate)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
% %                                     save_sef(savefilename,FFTbands_mean,header.SamplingRate,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
% %                                     save_eeglab(savefilename,FFTbands_mean,header.SamplingRate,[],[],Channels)
%                         end
%                     end
% 
% 
%                     % GPS
% 
%                     %disp(['Saving FFTA GPS files for subject ',char(SUBJ(subj))])
%                     if app.GPS_WithinWhole.Value
%                         savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFTA_GPSspectra', app.GPS_FilesExtension.Value]);
%                         %disp(['Saving FFTA power spectra GPS for ',char(SUBJ(subj))])
%                         SubjData.FFTApsd_mean_gps = compute_gfp(SubjData.FFTApsd_mean,'GPS'); % Frequency x Window
%                         GPStable = array2table(SubjData.FFTApsd_mean_gps','VariableNames',string(FrequencyNames));
%                         writetable(GPStable,savefilename)
%                     end
%                     if app.GPS_WithinBands.Value
%                         savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFTA_GPSbands', app.GPS_FilesExtension.Value]);
%                         %disp(['Saving FFTA frequency bands GPS for ',char(SUBJ(subj))])
%                         SubjData.FFTAbands_mean_gps = compute_gfp(SubjData.FFTAbands_mean,'GPS'); % Frequency x Window
%                         GPStable = array2table(SubjData.FFTAbands_mean_gps','VariableNames',string(BandNames));
%                         writetable(GPStable,savefilename)
%                     end
%                 end
% 
% 
%                 % Update between-subject data
% 
% 
%                 % FFT
% 
%                 if max(contains(app.FreqMethod.Value,'(FFT)'))
%                     if app.PSD_BetweenWhole.Value
%                         Between.FFTpsd          = cat(3,Between.FFTpsd,SubjData.FFTpsd);
%                     end
%                     if app.PSD_BetweenBands.Value
%                         Between.FFTbands        = cat(3,Between.FFTbands,SubjData.FFTbands);
%                     end
%                     if app.GPS_BetweenWhole.Value
%                         Between.FFTpsd_GPS      = cat(3,Between.FFTpsd_GPS,SubjData.FFTpsd_mean_gps);
%                     end
%                     if app.GPS_BetweenBands.Value
%                         Between.FFTbands_GPS    = cat(3,Between.FFTbands_GPS,SubjData.FFTbands_mean_gps);
%                     end
%                 end
% 
% 
%                 % FFTA
% 
%                 if max(contains(app.FreqMethod.Value,'(FFTA)'))
%                     if app.PSD_BetweenWhole.Value
%                         Between.FFTApsd         = cat(3,Between.FFTApsd,SubjData.FFTApsd);
%                     end
%                     if app.PSD_BetweenBands.Value
%                         Between.FFTAbands       = cat(3,Between.FFTAbands,SubjData.FFTAbands);
%                     end
%                     if app.GPS_BetweenWhole.Value || app.GPS_BetweenBands.Value
%                         Between.FFTApsd_GPS     = cat(3,Between.FFTApsd_GPS,SubjData.FFTApsd_mean_gps);
%                     end
%                     if app.GPS_BetweenBands.Value
%                         Between.FFTAbands_GPS   = cat(3,Between.FFTAbands_GPS,SubjData.FFTAbands_mean_gps);
%                     end
%                 end
%             end
% 
% 
% 
%             % Saving grand-averages
% 
%             if app.PSD_BetweenWhole.Value || app.PSD_BetweenBands.Value || app.GPS_BetweenWhole.Value || app.GPS_BetweenBands.Value
%                 BetweenPath = fullfile(app.SaveFolder.Value{:},'GrandAverages');
%                 % Creating the folder
%                 if ~exist(BetweenPath, 'dir')
%                     mkdir(BetweenPath);
%                 end
%             end
% 
% 
%             % FFT
%             if max(contains(app.FreqMethod.Value,'(FFT)')) && ...
%                     (app.PSD_BetweenWhole.Value || app.PSD_BetweenBands.Value || app.GPS_BetweenWhole.Value || app.GPS_BetweenBands.Value)
%                 disp('Saving FFT grand-averages')
% 
% 
%                 % PSD
% 
%                 %disp('Saving FFT grand-average files')
%                 if app.PSD_BetweenWhole.Value
%                     savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFT_avgspectra', app.PSD_FilesExtension.Value]);
%                     %disp('Saving FFT power spectra grand-average')
%                     Between.FFTpsd_mean = mean(Between.FFTpsd,3);
%                     if strcmp(app.PSD_FilesExtension.Value,'.freq')
%                         save_freq(savefilename,Between.FFTpsd_mean,app.SamplingRate.Value,app.Resolution.Value,FrequencyNames,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
% %                                     save_eph(savefilename,FFTpsd_mean,header.SamplingRate)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
% %                                     save_sef(savefilename,FFTpsd_mean,header.SamplingRate,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
% %                                     save_eeglab(savefilename,FFTpsd_mean,header.SamplingRate,[],[],Channels)
%                     end
%                 end
%                 if app.PSD_BetweenBands.Value
%                     savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFT_avgbands', app.PSD_FilesExtension.Value]);
%                     %disp('Saving FFT frequency bands grand-average')
%                     Between.FFTbands_mean = mean(Between.FFTbands,3);
%                     if strcmp(app.PSD_FilesExtension.Value,'.freq')
%                         save_freq(savefilename,Between.FFTbands_mean,app.SamplingRate.Value,app.Resolution.Value,BandNames,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
% %                                     save_eph(savefilename,FFTbands_mean,header.SamplingRate)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
% %                                     save_sef(savefilename,FFTbands_mean,header.SamplingRate,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
% %                                     save_eeglab(savefilename,FFTbands_mean,header.SamplingRate,[],[],Channels)
%                     end
%                 end
% 
% 
%                 % GPS
% 
%                 %disp('Saving FFT GPS grand-average files')
%                 if app.GPS_BetweenWhole.Value
%                     savefilename = fullfile(BetweenPath,['rdAvg', suffix, '_FFT_GPSspectra', app.GPS_FilesExtension.Value]);
%                     %disp('Saving FFT power spectra GPS grand-average')
%                     Between.FFTpsd_GPS_mean = mean(Between.FFTpsd_GPS,3);
%                     GPStable = array2table(Between.FFTpsd_GPS_mean','VariableNames',string(FrequencyNames));
%                     writetable(GPStable,savefilename)
%                 end
%                 if app.GPS_BetweenBands.Value
%                     savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFT_GPSbands', app.GPS_FilesExtension.Value]);
%                     %disp('Saving FFT frequency bands GPS grand-average')
%                     Between.FFTbands_GPS_mean = mean(Between.FFTbands_GPS,3);
%                     GPStable = array2table(Between.FFTbands_GPS_mean','VariableNames',string(BandNames));
%                     writetable(GPStable,savefilename)
%                 end
%             end
% 
% 
%             % FFTA
% 
%             if max(contains(app.FreqMethod.Value,'(FFTA)')) && ...
%                     (app.PSD_BetweenWhole.Value || app.PSD_BetweenBands.Value || app.GPS_BetweenWhole.Value || app.GPS_BetweenBands.Value)
%                 disp('Saving FFTA grand-averages')
% 
% 
%                 % PSD
% 
%                 %disp('Saving FFTA grand-average files')
%                 if app.PSD_BetweenWhole.Value
%                     savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFTA_avgspectra', app.PSD_FilesExtension.Value]);
%                     %disp('Saving FFTA power spectra grand-average')
%                     Between.FFTApsd_mean = mean(Between.FFTApsd,3);
%                     if strcmp(app.PSD_FilesExtension.Value,'.freq')
%                         save_freq(savefilename,Between.FFTApsd_mean,app.SamplingRate.Value,app.Resolution.Value,FrequencyNames,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
% %                                     save_eph(savefilename,FFTpsd_mean,header.SamplingRate)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
% %                                     save_sef(savefilename,FFTpsd_mean,header.SamplingRate,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
% %                                     save_eeglab(savefilename,FFTpsd_mean,header.SamplingRate,[],[],Channels)
%                     end
%                 end
%                 if app.PSD_BetweenBands.Value
%                     savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFTA_avgbands', app.PSD_FilesExtension.Value]);
%                     %disp('Saving FFTA frequency bands grand-average')
%                     Between.FFTAbands_mean = mean(Between.FFTAbands,3);
%                     if strcmp(app.PSD_FilesExtension.Value,'.freq')
%                         save_freq(savefilename,Between.FFTAbands_mean,app.SamplingRate.Value,app.Resolution.Value,BandNames,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
% %                                     save_eph(savefilename,FFTbands_mean,header.SamplingRate)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
% %                                     save_sef(savefilename,FFTbands_mean,header.SamplingRate,Channels)
% %                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
% %                                     save_eeglab(savefilename,FFTbands_mean,header.SamplingRate,[],[],Channels)
%                     end
%                 end
% 
% 
%                 % GPS
% 
%                 %disp('Saving FFTA GPS grand-average files')
%                 if app.GPS_BetweenWhole.Value
%                     savefilename = fullfile(BetweenPath,['rdAvg', suffix, '_FFTA_GPSspectra', app.GPS_FilesExtension.Value]);
%                     %disp('Saving FFTA power spectra GPS grand-average')
%                     Between.FFTApsd_GPS_mean = mean(Between.FFTApsd_GPS,3);
%                     GPStable = array2table(Between.FFTApsd_GPS_mean','VariableNames',string(FrequencyNames));
%                     writetable(GPStable,savefilename)
%                 end
%                 if app.GPS_BetweenBands.Value
%                     savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFTA_GPSbands', app.GPS_FilesExtension.Value]);
%                     %disp('Saving FFTA frequency bands GPS grand-average')
%                     Between.FFTAbands_GPS_mean = mean(Between.FFTAbands_GPS,3);
%                     GPStable = array2table(Between.FFTAbands_GPS_mean','VariableNames',string(BandNames));
%                     writetable(GPStable,savefilename)
%                 end
            end
            
            
            % UPDATE INFORMATION BASED ON THE PROCESSED DATA
            
            % Data table in the Main Window
%             app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
%             app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
%             app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
%             disp('Data table in the Main Window updated with the processed files')
%             cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(data,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT (Input file name  /  N. time-frames  /  N. windows  /  Discarded TFs  /  N. channels  /  Folder)');
            % fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Triggers found  /  CHANCHECK  /  Folder)');
            Files = [app.DataTable.File';num2cell(PeriodLength_ALL)';num2cell(nWindows_ALL)';num2cell(DiscardedTF_ALL)';...
                num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
%             fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','SUMMARY (File  /  Subject  /  Trigger  /  N. Triggers  /  Accepted epochs (n/%)  /  EEG rejected (n/%)');
%             Slash = repmat("/",1,height(SummaryTable));
%             Percent = repmat("%",1,height(SummaryTable));
%             Summary = [SummaryTable.File';SummaryTable.Subject';SummaryTable.Trigger';num2cell(SummaryTable.TotalTriggers)';...
%                 num2cell(SummaryTable.nTOTALaccepted)';Slash;num2cell(SummaryTable.pcTOTALaccepted)';Percent];
%             fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s\t\t%s %s %s%s\r\n',Summary{:});
            
                        
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Epoching completed on:',{' '},datestr(EndTime))));
            delete(h);
            fclose(PifFileID);
            rz = msgbox('Frequency processing done.');
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Enable the Filtering+ button in main app
            app.EEGpalCS_Main.FrequencyingButton.Enable = 'on';
            
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            % Update parameters
            app.EEGpalCS_Main.Study_Parameters  = app.Study_Parameters;
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FrequencyingButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: Frequencying
        function FrequencyingCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FrequencyingButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: Onset_ms
        function Onset_msValueChanged(app, event)
            app.Onset_tf.Value  = ms2tf(app,app.Onset_ms.Value,app.SamplingRate.Value) + 1; % +1 because 0 ms is at tf 1
            app.Onset_ms.Value  = tf2ms(app,app.Onset_tf.Value-1,app.SamplingRate.Value); % recompute from tf for precision (-1 because 0 ms is at tf 1)
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
        end

        % Value changed function: Onset_tf
        function Onset_tfValueChanged(app, event)
            app.Onset_ms.Value  = tf2ms(app,app.Onset_tf.Value-1,app.SamplingRate.Value); % -1 because 0 ms is at tf 1
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
        end

        % Value changed function: Offset_ms
        function Offset_msValueChanged(app, event)
            app.Offset_tf.Value = ms2tf(app,app.Offset_ms.Value,app.SamplingRate.Value); % no +1 because the last TF is included
            app.Offset_ms.Value = tf2ms(app,app.Offset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
        end

        % Value changed function: Offset_tf
        function Offset_tfValueChanged(app, event)
            app.Offset_ms.Value = tf2ms(app,app.Offset_tf.Value,app.SamplingRate.Value); % no +1 because the last TF is included
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
        end

        % Value changed function: Size_ms
        function Size_msValueChanged(app, event)
            app.Size_tf.Value   = ms2tf(app,app.Size_ms.Value,app.SamplingRate.Value);
            app.Size_ms.Value   = tf2ms(app,app.Size_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
            
            app.Resolution.Value    = app.SamplingRate.Value / app.Size_tf.Value;
        end

        % Value changed function: Size_tf
        function Size_tfValueChanged(app, event)
            app.Size_ms.Value   = tf2ms(app,app.Size_tf.Value,app.SamplingRate.Value);
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
            
            app.Resolution.Value    = app.SamplingRate.Value / app.Size_tf.Value;
        end

        % Value changed function: Overlap
        function OverlapValueChanged(app, event)
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
        end

        % Value changed function: FrequencyBands
        function FrequencyBandsValueChanged(app, event)
            if ~isempty(app.FrequencyBands.Value)
                app.PSD_FileAvgBands.Enable = 'on';
                app.GPS_FileAvgBands.Enable = 'on';
                app.PSD_FileSepBands.Enable = 'on';
                app.GPS_FileSepBands.Enable = 'on';
                app.PSD_WithinBands.Enable  = 'on';
                app.GPS_WithinBands.Enable  = 'on';
                app.PSD_BetweenBands.Enable = 'on';
                app.GPS_BetweenBands.Enable = 'on';
            else
                app.PSD_FileAvgBands.Enable = 'off';
                app.GPS_FileAvgBands.Enable = 'off';
                app.PSD_FileSepBands.Enable = 'off';
                app.GPS_FileSepBands.Enable = 'off';
                app.PSD_WithinBands.Enable  = 'off';
                app.GPS_WithinBands.Enable  = 'off';
                app.PSD_BetweenBands.Enable = 'off';
                app.GPS_BetweenBands.Enable = 'off';
            end
        end

        % Value changed function: EndOfFile
        function EndOfFileValueChanged(app, event)
            if app.EndOfFile.Value
                app.Offset_tf.Enable    = 'off';
                app.Offset_ms.Enable    = 'off';
                try
                    app.Offset_tf.Value = app.DataTable.(5)(1);
                    app.Offset_ms.Value = tf2ms(app,app.Offset_tf.Value,app.SamplingRate.Value); 
                catch % if no data selected
                    app.Offset_tf.Value = 1;
                    app.Offset_ms.Value = 0;
                end
            else
                app.Offset_tf.Enable    = 'on';
                app.Offset_ms.Enable    = 'on';
            end
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
        end

        % Value changed function: SwitchResample
        function SwitchResampleValueChanged(app, event)
            if strcmpi(app.SwitchResample.Value,'On')
                app.SamplingRate.Enable  = 'on';
            else
                app.SamplingRate.Enable  = 'off';
                app.SamplingRate.Value = app.OrigSamplingRate.Value;
                SamplingRateValueChanged(app, event);
            end
        end

        % Value changed function: SamplingRate
        function SamplingRateValueChanged(app, event)
            app.Onset_tf.Value      = ms2tf(app,app.Onset_ms.Value,app.SamplingRate.Value) + 1; % +1 because 0 ms is at tf 1
            app.Onset_ms.Value      = tf2ms(app,app.Onset_tf.Value-1,app.SamplingRate.Value); % recompute from tf for precision (-1 because 0 ms is at tf 1)
            app.Offset_tf.Value     = ms2tf(app,app.Offset_ms.Value,app.SamplingRate.Value);
            app.Offset_ms.Value     = tf2ms(app,app.Offset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            
            app.Size_tf.Value       = ms2tf(app,app.Size_ms.Value,app.SamplingRate.Value);
            app.Size_ms.Value       = tf2ms(app,app.Size_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
            
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
            
            app.Resolution.Value    = app.SamplingRate.Value / app.Size_tf.Value;
            app.MaxFreq.Value       = app.SamplingRate.Value / 2;
            app.UpperFreq.Value     = app.MaxFreq.Value;
        end

        % Value changed function: FreqMethod
        function FreqMethodValueChanged(app, event)
            if strcmp(app.FreqMethod.Value,'FFT Approximation (FFTA)')
                app.NormalizationGroup.Enable = 'off';
                app.NORM_None.Value = true;
                app.NROM_MeanGlobal.Value = false;
                app.NROM_Mean.Value = false;
                app.NORM_LogTransform.Value = false;
                app.WindowingGroup.Enable = 'on';
                app.Overlap.Enable='on';
            elseif strcmp(app.FreqMethod.Value,'eeglab (FFT)')
                app.WindowingGroup.Enable = 'off';
                app.WIND_None.Value = 0;
                app.WIND_Hamming.Value=0;
                app.WIND_Hanning.Value=1;
                app.NormalizationGroup.Enable = 'on';
                app.Overlap.Enable='off';
                app.Overlap.Value=0;
                OverlapValueChanged(app, event); % to restore number of windows
            else
                app.NormalizationGroup.Enable = 'on';
                app.WindowingGroup.Enable = 'on';
                app.Overlap.Enable='on';                
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_Frequencying_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Frequencying_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Frequencying_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Frequencying = uifigure('Visible', 'off');
            app.Frequencying.Name = 'EEGpal_Frequencying';
            app.Frequencying.CloseRequestFcn = createCallbackFcn(app, @FrequencyingCloseRequest, true);
            app.FREQUENCYINGLabel = uilabel(app.Frequencying);
            app.FREQUENCYINGLabel.Text = 'FREQUENCYING';
            app.SAMPLINGRATELabel = uilabel(app.Frequencying);
            app.SAMPLINGRATELabel.Text = 'SAMPLING RATE';
            app.OriginalsamplingrateLabel = uilabel(app.Frequencying);
            app.OriginalsamplingrateLabel.Text = 'Original sampling rate:';
            app.OrigSamplingRate = uieditfield(app.Frequencying, 'numeric');
            app.OrigSamplingRate.Editable = 'off';
            app.OrigSamplingRate.Value = 1000;
            app.AnalysisPeriodLabel = uilabel(app.Frequencying);
            app.AnalysisPeriodLabel.Text = 'Analysis period';
            app.msLabel = uilabel(app.Frequencying);
            app.msLabel.Text = '[ms]';
            app.tfLabel = uilabel(app.Frequencying);
            app.tfLabel.Text = '[tf]';
            app.OnsetLabel = uilabel(app.Frequencying);
            app.OnsetLabel.Text = 'Onset:';
            app.Onset_ms = uieditfield(app.Frequencying, 'numeric');
            app.Onset_ms.Limits = [0 Inf];
            app.Onset_ms.ValueDisplayFormat = '%.1f';
            app.Onset_ms.ValueChangedFcn = createCallbackFcn(app, @Onset_msValueChanged, true);
            app.Onset_ms.Tooltip = {''};
            app.Onset_tf = uieditfield(app.Frequencying, 'numeric');
            app.Onset_tf.Limits = [1 Inf];
            app.Onset_tf.ValueDisplayFormat = '%.0f';
            app.Onset_tf.ValueChangedFcn = createCallbackFcn(app, @Onset_tfValueChanged, true);
            app.Onset_tf.Tooltip = {''};
            app.Onset_tf.Value = 1;
            app.OffsetLabel = uilabel(app.Frequencying);
            app.OffsetLabel.Text = 'Offset:';
            app.Offset_ms = uieditfield(app.Frequencying, 'numeric');
            app.Offset_ms.Limits = [0 Inf];
            app.Offset_ms.ValueDisplayFormat = '%.1f';
            app.Offset_ms.ValueChangedFcn = createCallbackFcn(app, @Offset_msValueChanged, true);
            app.Offset_ms.Enable = 'off';
            app.Offset_ms.Tooltip = {''};
            app.Offset_tf = uieditfield(app.Frequencying, 'numeric');
            app.Offset_tf.Limits = [1 Inf];
            app.Offset_tf.ValueDisplayFormat = '%.0f';
            app.Offset_tf.ValueChangedFcn = createCallbackFcn(app, @Offset_tfValueChanged, true);
            app.Offset_tf.Enable = 'off';
            app.Offset_tf.Tooltip = {''};
            app.Offset_tf.Value = 1;
            app.DurationLabel = uilabel(app.Frequencying);
            app.DurationLabel.Text = 'Duration:';
            app.Duration_ms = uieditfield(app.Frequencying, 'numeric');
            app.Duration_ms.ValueDisplayFormat = '%.1f';
            app.Duration_ms.Editable = 'off';
            app.Duration_ms.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.Duration_tf = uieditfield(app.Frequencying, 'numeric');
            app.Duration_tf.ValueDisplayFormat = '%.0f';
            app.Duration_tf.Editable = 'off';
            app.Duration_tf.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.Duration_tf.Value = 1;
            app.WindowssizeLabel = uilabel(app.Frequencying);
            app.WindowssizeLabel.Text = 'Windows size:';
            app.Size_ms = uieditfield(app.Frequencying, 'numeric');
            app.Size_ms.Limits = [0 Inf];
            app.Size_ms.ValueDisplayFormat = '%.1f';
            app.Size_ms.ValueChangedFcn = createCallbackFcn(app, @Size_msValueChanged, true);
            app.Size_ms.Tooltip = {''};
            app.Size_ms.Value = 2000;
            app.Size_tf = uieditfield(app.Frequencying, 'numeric');
            app.Size_tf.Limits = [1 Inf];
            app.Size_tf.ValueDisplayFormat = '%.0f';
            app.Size_tf.ValueChangedFcn = createCallbackFcn(app, @Size_tfValueChanged, true);
            app.Size_tf.Tooltip = {''};
            app.Size_tf.Value = 2000;
            app.OverlapLabel = uilabel(app.Frequencying);
            app.OverlapLabel.Text = 'Overlap:';
            app.Overlap = uieditfield(app.Frequencying, 'numeric');
            app.Overlap.Limits = [0 Inf];
            app.Overlap.ValueDisplayFormat = '%.0f';
            app.Overlap.ValueChangedFcn = createCallbackFcn(app, @OverlapValueChanged, true);
            app.Overlap.Tooltip = {''};
            app.tfOverlap = uilabel(app.Frequencying);
            app.tfOverlap.Text = 'tf';
            app.nWindowsLabel = uilabel(app.Frequencying);
            app.nWindowsLabel.Text = 'nWindows:';
            app.nWindows = uieditfield(app.Frequencying, 'numeric');
            app.nWindows.ValueDisplayFormat = '%.0f';
            app.nWindows.Editable = 'off';
            app.nWindows.Tooltip = {''};
            app.FrequencyresolutionLabel = uilabel(app.Frequencying);
            app.FrequencyresolutionLabel.Text = 'Frequency resolution:';
            app.Resolution = uieditfield(app.Frequencying, 'numeric');
            app.Resolution.Editable = 'off';
            app.Resolution.Tooltip = {''};
            app.HzLabel = uilabel(app.Frequencying);
            app.HzLabel.Text = 'Hz';
            app.RangeLabel = uilabel(app.Frequencying);
            app.RangeLabel.Tooltip = {''};
            app.RangeLabel.Text = 'Range:';
            app.LowerFreq = uieditfield(app.Frequencying, 'numeric');
            app.LowerFreq.Limits = [0 Inf];
            app.LowerFreq.Tooltip = {''};
            app.toLabel = uilabel(app.Frequencying);
            app.toLabel.Tooltip = {''};
            app.toLabel.Text = 'to';
            app.UpperFreq = uieditfield(app.Frequencying, 'numeric');
            app.UpperFreq.Limits = [0 Inf];
            app.UpperFreq.Tooltip = {''};
            app.HzLabel_2 = uilabel(app.Frequencying);
            app.HzLabel_2.Tooltip = {''};
            app.HzLabel_2.Text = 'Hz';
            app.MaxLabel = uilabel(app.Frequencying);
            app.MaxLabel.Text = 'Max:';
            app.MaxFreq = uieditfield(app.Frequencying, 'numeric');
            app.MaxFreq.Editable = 'off';
            app.MaxFreq.Tooltip = {''};
            app.HzLabel_3 = uilabel(app.Frequencying);
            app.HzLabel_3.Text = 'Hz';
            app.Frequencybandseg4675102030Label = uilabel(app.Frequencying);
            app.Frequencybandseg4675102030Label.Text = 'Frequency bands (e.g., 4-6; 7.5:10 20-30):';
            app.FrequencyBands = uieditfield(app.Frequencying, 'text');
            app.FrequencyBands.ValueChangedFcn = createCallbackFcn(app, @FrequencyBandsValueChanged, true);
            app.FrequencyBands.Tooltip = {'define range using hyphen(-) or semi-colon(:)'; 'separate bands using '};
            app.ChannelsclusterLabel = uilabel(app.Frequencying);
            app.ChannelsclusterLabel.Text = 'Channels cluster:';
            app.ChannelsCluster = uidropdown(app.Frequencying);
            app.ChannelsCluster.Items = {'all', 'Option 2', 'Option 3', 'Option 4'};
            app.ChannelsCluster.Value = 'all';
            app.OUTPUTOPTIONSLabel = uilabel(app.Frequencying);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofPSDoutputfilesLabel = uilabel(app.Frequencying);
            app.ExtensionofPSDoutputfilesLabel.Text = 'Extension of PSD output files:';
            app.PSD_FilesExtension = uidropdown(app.Frequencying);
            app.PSD_FilesExtension.Items = {'.freq', '.mat'};
            app.PSD_FilesExtension.Tooltip = {'Currently, you can only save as .freq'; 'Use Cartool if you want then to convert into another format.'};
            app.PSD_FilesExtension.Value = '.freq';
            app.SelectUpperFolderLabel = uilabel(app.Frequencying);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.Frequencying, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.Frequencying);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.Frequencying);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.Frequencying);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.Frequencying, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'frequency';
            app.InputParametersFile = uilabel(app.Frequencying);
            app.InputParametersFile.Text = 'Parameters';
            app.OpenButton = uibutton(app.Frequencying, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.Frequencying, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Update an existing file.'; 'Will erase preexisting Interpolation options.'};
            app.SaveButton.Text = 'Save';
            app.SaveAsButton = uibutton(app.Frequencying, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Tooltip = {'Create a new file.'; 'Will erase data if file already exists.'};
            app.SaveAsButton.Text = 'Save as';
            app.ParameterfileEditFieldLabel = uilabel(app.Frequencying);
            app.ParameterfileEditFieldLabel.Text = 'Parameter file:';
            app.ParameterFilePath = uieditfield(app.Frequencying, 'text');
            app.ParameterFilePath.Editable = 'off';
            app.APPLYLabel = uilabel(app.Frequencying);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.Frequencying, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.DoneButton = uibutton(app.Frequencying, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tooltip = {'Return to the main window'};
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.Frequencying, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.FREQUENCYCOMPUTATIONLabel = uilabel(app.Frequencying);
            app.FREQUENCYCOMPUTATIONLabel.Text = 'FREQUENCY COMPUTATION';
            app.Method = uilabel(app.Frequencying);
            app.Method.Text = 'Method';
            app.ExtensionofGPSoutputfilesLabel = uilabel(app.Frequencying);
            app.ExtensionofGPSoutputfilesLabel.Text = 'Extension of GPS output files:';
            app.GPS_FilesExtension = uidropdown(app.Frequencying);
            app.GPS_FilesExtension.Items = {'.ep', '.csv', '.xlsx'};
            app.GPS_FilesExtension.Value = '.xlsx';
            app.INDIVIDUALPEAKFREQUENCYPanel = uipanel(app.Frequencying);
            app.INDIVIDUALPEAKFREQUENCYPanel.Enable = 'off';
            app.INDIVIDUALPEAKFREQUENCYPanel.Title = 'INDIVIDUAL PEAK FREQUENCY';
            app.INDIVIDUALPEAKFREQUENCYPanel.Visible = 'off';
            app.iPF_FrequencyComputation = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_FrequencyComputation.Text = 'Frequency computation';
            app.MethodLabel = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.MethodLabel.Text = 'Method:';
            app.iPF_Method = uidropdown(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_Method.Items = {'FFT', 'pWelch'};
            app.iPF_Method.Enable = 'off';
            app.iPF_Method.Value = 'FFT';
            app.WindowingfunctionLabel_2 = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.WindowingfunctionLabel_2.Text = 'Windowing function:';
            app.iPF_Windowing = uidropdown(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_Windowing.Items = {'None', 'Hamming', 'Hanning'};
            app.iPF_Windowing.Enable = 'off';
            app.iPF_Windowing.Value = 'None';
            app.SignaltoanalyseLabel = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.SignaltoanalyseLabel.Text = 'Signal to analyse:';
            app.iPF_Signal = uidropdown(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_Signal.Items = {'GPS', '[No other option yet]'};
            app.iPF_Signal.Enable = 'off';
            app.iPF_Signal.Value = 'GPS';
            app.SGF_AllOptions = uibutton(app.INDIVIDUALPEAKFREQUENCYPanel, 'push');
            app.SGF_AllOptions.Enable = 'off';
            app.SGF_AllOptions.Text = 'All iFP options';
            app.SGF_RuniFP = uibutton(app.INDIVIDUALPEAKFREQUENCYPanel, 'push');
            app.SGF_RuniFP.Enable = 'off';
            app.SGF_RuniFP.Text = 'Run iFP';
            app.iPF_Table = uitable(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_Table.ColumnName = {'Subject/File'; 'Freq. range'; 'iPF'};
            app.iPF_Table.RowName = {};
            app.iPF_Table.ColumnSortable = true;
            app.iPF_Table.ColumnEditable = [false false true];
            app.Corcoranetal2018DOI101111psyp13064Label = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.Corcoranetal2018DOI101111psyp13064Label.Text = '(Corcoran et al. 2018; DOI: 10.1111/psyp.13064)';
            app.SGF_Label = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.SGF_Label.Text = 'Savitzky-Golay filter (smoothing)';
            app.SGF_CheckBox = uicheckbox(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.SGF_CheckBox.Enable = 'off';
            app.SGF_CheckBox.Text = '';
            app.FramewidthLabel = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.FramewidthLabel.Text = 'Frame width:';
            app.SGF_FrameWidth = uieditfield(app.INDIVIDUALPEAKFREQUENCYPanel, 'numeric');
            app.SGF_FrameWidth.Limits = [0 Inf];
            app.SGF_FrameWidth.Enable = 'off';
            app.SGF_FrameWidth.Tooltip = {''};
            app.SGF_FrameWidth.Value = 7;
            app.PolynomialorderLabel = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.PolynomialorderLabel.Text = 'Polynomial order:';
            app.SGF_PolyOrder = uieditfield(app.INDIVIDUALPEAKFREQUENCYPanel, 'numeric');
            app.SGF_PolyOrder.Limits = [0 Inf];
            app.SGF_PolyOrder.Enable = 'off';
            app.SGF_PolyOrder.Tooltip = {''};
            app.SGF_PolyOrder.Value = 5;
            app.Frequencybandseg4675102030Label_2 = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.Frequencybandseg4675102030Label_2.Text = 'Frequency bands (e.g., 4-6; 7.5:10 20-30):';
            app.iFP_FrequencyBands = uieditfield(app.INDIVIDUALPEAKFREQUENCYPanel, 'text');
            app.iFP_FrequencyBands.Enable = 'off';
            app.iFP_FrequencyBands.Tooltip = {'define range using hyphen(-) or semi-colon(:)'; 'separate bands using '};
            app.InconsistentSR = uilabel(app.Frequencying);
            app.InconsistentSR.Visible = 'off';
            app.InconsistentSR.Tooltip = {'Not all files have the same sampling rate.'; 'Information below are based on the sampling rate (and other info) from the first file of the list.'};
            app.InconsistentSR.Text = 'Inconsistent!';
            app.InconsistentNumChan = uilabel(app.Frequencying);
            app.InconsistentNumChan.Visible = 'off';
            app.InconsistentNumChan.Tooltip = {'Not all files have the same number of channels.'; 'GPS options are disabled'};
            app.InconsistentNumChan.Text = 'Inconsistent number of channels!';
            app.EndOfFile = uibutton(app.Frequencying, 'state');
            app.EndOfFile.ValueChangedFcn = createCallbackFcn(app, @EndOfFileValueChanged, true);
            app.EndOfFile.Tooltip = {'When selected, ''Offset'', ''Duration'', and ''nWindows'' values based on the first file of the list in the main window.'; 'When pressing run, these values will be adapted to the file being processed.'};
            app.EndOfFile.Text = 'End of file';
            app.EndOfFile.Value = true;
            app.FreqMethodInstruction = uilabel(app.Frequencying);
            app.FreqMethodInstruction.Text = '';
            app.FreqMethod = uilistbox(app.Frequencying);
            app.FreqMethod.Items = {'Fast Fourier Transform (FFT)', 'FFT Approximation (FFTA)', 'eeglab (FFT)'};
            app.FreqMethod.ValueChangedFcn = createCallbackFcn(app, @FreqMethodValueChanged, true);
            app.FreqMethod.Value = 'Fast Fourier Transform (FFT)';
            app.NormalizationGroup = uibuttongroup(app.Frequencying);
            app.NormalizationGroup.Title = 'Normalization';
            app.NORM_None = uitogglebutton(app.NormalizationGroup);
            app.NORM_None.Text = 'None';
            app.NORM_None.Value = true;
            app.NROM_Mean = uitogglebutton(app.NormalizationGroup);
            app.NROM_Mean.Tooltip = {'For each frequency bin, computes de average power across all period windows.'; 'Normalization is achieved by dividing this average from the individual values.'};
            app.NROM_Mean.Text = 'by mean per frequency (1/f correction)';
            app.NORM_LogTransform = uitogglebutton(app.NormalizationGroup);
            app.NORM_LogTransform.Text = 'Log transf. (NOT RECOMMENDED)';
            app.NORM_Neighbourg = uitogglebutton(app.NormalizationGroup);
            app.NORM_Neighbourg.Enable = 'off';
            app.NORM_Neighbourg.Visible = 'off';
            app.NORM_Neighbourg.Text = 'Neighbourg averaging';
            app.nNeighEditFieldLabel = uilabel(app.NormalizationGroup);
            app.nNeighEditFieldLabel.Enable = 'off';
            app.nNeighEditFieldLabel.Visible = 'off';
            app.nNeighEditFieldLabel.Text = 'nNeigh:';
            app.NORM_nNeigh = uieditfield(app.NormalizationGroup, 'numeric');
            app.NORM_nNeigh.Enable = 'off';
            app.NORM_nNeigh.Visible = 'off';
            app.NORM_nNeigh.Value = 4;
            app.ChannelDropDownLabel = uilabel(app.NormalizationGroup);
            app.ChannelDropDownLabel.Enable = 'off';
            app.ChannelDropDownLabel.Visible = 'off';
            app.ChannelDropDownLabel.Text = 'Channel:';
            app.NORM_NeighChan = uidropdown(app.NormalizationGroup);
            app.NORM_NeighChan.Items = {'GPS', 'Own'};
            app.NORM_NeighChan.Enable = 'off';
            app.NORM_NeighChan.Visible = 'off';
            app.NORM_NeighChan.Value = 'GPS';
            app.NROM_MeanGlobal = uitogglebutton(app.NormalizationGroup);
            app.NROM_MeanGlobal.Tooltip = {'For each frequency bin, computes de average power across all period windows.'; 'Normalization is achieved by dividing this average from the individual values.'};
            app.NROM_MeanGlobal.Text = 'by global averaged Power';
            app.WindowingGroup = uibuttongroup(app.Frequencying);
            app.WindowingGroup.Title = 'Windowing function';
            app.WIND_None = uitogglebutton(app.WindowingGroup);
            app.WIND_None.Text = 'None';
            app.WIND_None.Value = true;
            app.WIND_Hamming = uitogglebutton(app.WindowingGroup);
            app.WIND_Hamming.Text = 'Hamming';
            app.WIND_Hanning = uitogglebutton(app.WindowingGroup);
            app.WIND_Hanning.Text = 'Hanning';
            app.PowerSpectraLabel = uilabel(app.Frequencying);
            app.PowerSpectraLabel.Text = 'Whole range power spectra';
            app.PSDLabel = uilabel(app.Frequencying);
            app.PSDLabel.Tooltip = {'Power Spectral Density'};
            app.PSDLabel.Text = 'PSD';
            app.GPSLabel = uilabel(app.Frequencying);
            app.GPSLabel.Tooltip = {'Global Power Spectra'};
            app.GPSLabel.Text = 'GPS';
            app.GPS_FileAvgWhole = uicheckbox(app.Frequencying);
            app.GPS_FileAvgWhole.Text = '';
            app.GPS_FileSepWhole = uicheckbox(app.Frequencying);
            app.GPS_FileSepWhole.Text = '';
            app.FrequencyBandsLabel = uilabel(app.Frequencying);
            app.FrequencyBandsLabel.Text = 'Frequency bands (if defined)';
            app.GPS_WithinWhole = uicheckbox(app.Frequencying);
            app.GPS_WithinWhole.Enable = 'off';
            app.GPS_WithinWhole.Visible = 'off';
            app.GPS_WithinWhole.Text = '';
            app.GPS_WithinBands = uicheckbox(app.Frequencying);
            app.GPS_WithinBands.Enable = 'off';
            app.GPS_WithinBands.Visible = 'off';
            app.GPS_WithinBands.Text = '';
            app.GPS_BetweenWhole = uicheckbox(app.Frequencying);
            app.GPS_BetweenWhole.Enable = 'off';
            app.GPS_BetweenWhole.Visible = 'off';
            app.GPS_BetweenWhole.Text = '';
            app.GPS_BetweenBands = uicheckbox(app.Frequencying);
            app.GPS_BetweenBands.Enable = 'off';
            app.GPS_BetweenBands.Visible = 'off';
            app.GPS_BetweenBands.Text = '';
            app.GPS_FileAvgBands = uicheckbox(app.Frequencying);
            app.GPS_FileAvgBands.Enable = 'off';
            app.GPS_FileAvgBands.Text = '';
            app.GPS_FileSepBands = uicheckbox(app.Frequencying);
            app.GPS_FileSepBands.Enable = 'off';
            app.GPS_FileSepBands.Text = '';
            app.PSD_WithinBands = uicheckbox(app.Frequencying);
            app.PSD_WithinBands.Enable = 'off';
            app.PSD_WithinBands.Visible = 'off';
            app.PSD_WithinBands.Text = '';
            app.WithinsubjectaveragingLabel_2 = uilabel(app.Frequencying);
            app.WithinsubjectaveragingLabel_2.Visible = 'off';
            app.WithinsubjectaveragingLabel_2.Text = 'Within-subject averaging:';
            app.BetweensubjectaveragingLabel_2 = uilabel(app.Frequencying);
            app.BetweensubjectaveragingLabel_2.Visible = 'off';
            app.BetweensubjectaveragingLabel_2.Text = 'Between-subject averaging:';
            app.PSD_BetweenBands = uicheckbox(app.Frequencying);
            app.PSD_BetweenBands.Enable = 'off';
            app.PSD_BetweenBands.Visible = 'off';
            app.PSD_BetweenBands.Text = '';
            app.PSD_WithinWhole = uicheckbox(app.Frequencying);
            app.PSD_WithinWhole.Enable = 'off';
            app.PSD_WithinWhole.Visible = 'off';
            app.PSD_WithinWhole.Text = '';
            app.WithinsubjectaveragingLabel = uilabel(app.Frequencying);
            app.WithinsubjectaveragingLabel.Enable = 'off';
            app.WithinsubjectaveragingLabel.Visible = 'off';
            app.WithinsubjectaveragingLabel.Text = 'Within-subject averaging:';
            app.BetweensubjectaveragingLabel = uilabel(app.Frequencying);
            app.BetweensubjectaveragingLabel.Enable = 'off';
            app.BetweensubjectaveragingLabel.Visible = 'off';
            app.BetweensubjectaveragingLabel.Text = 'Between-subject averaging:';
            app.PSD_BetweenWhole = uicheckbox(app.Frequencying);
            app.PSD_BetweenWhole.Enable = 'off';
            app.PSD_BetweenWhole.Visible = 'off';
            app.PSD_BetweenWhole.Text = '';
            app.PerfileaveragedtimewindowsLabel = uilabel(app.Frequencying);
            app.PerfileaveragedtimewindowsLabel.Text = 'Per file (averaged time windows):';
            app.PSD_FileAvgWhole = uicheckbox(app.Frequencying);
            app.PSD_FileAvgWhole.Text = '';
            app.PerfileseparatetimewindowsLabel = uilabel(app.Frequencying);
            app.PerfileseparatetimewindowsLabel.Text = 'Per file (separate time windows):';
            app.PSD_FileSepWhole = uicheckbox(app.Frequencying);
            app.PSD_FileSepWhole.Text = '';
            app.PSD_FileSepBands = uicheckbox(app.Frequencying);
            app.PSD_FileSepBands.Enable = 'off';
            app.PSD_FileSepBands.Text = '';
            app.PerfileseparatetimewindowsLabel_2 = uilabel(app.Frequencying);
            app.PerfileseparatetimewindowsLabel_2.Text = 'Per file (separate time windows):';
            app.PerfileaveragedtimewindowsLabel_2 = uilabel(app.Frequencying);
            app.PerfileaveragedtimewindowsLabel_2.Text = 'Per file (averaged time windows):';
            app.PSD_FileAvgBands = uicheckbox(app.Frequencying);
            app.PSD_FileAvgBands.Enable = 'off';
            app.PSD_FileAvgBands.Text = '';
            app.SamplingRate = uieditfield(app.Frequencying, 'numeric');
            app.SamplingRate.ValueChangedFcn = createCallbackFcn(app, @SamplingRateValueChanged, true);
            app.SamplingRate.Enable = 'off';
            app.SamplingRate.Value = 1024;
            app.ResampleLabel = uilabel(app.Frequencying);
            app.ResampleLabel.Text = 'Resample:';
            app.SwitchResample = uiswitch(app.Frequencying, 'slider');
            app.SwitchResample.ValueChangedFcn = createCallbackFcn(app, @SwitchResampleValueChanged, true);
            app.SAVINGLabel = uilabel(app.Frequencying);
            app.SAVINGLabel.Text = 'SAVING';
            app.HelpButton = uibutton(app.Frequencying, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            % Show the figure after all components are created
            app.Frequencying.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Frequencying(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Frequencying)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Frequencying)
        end
    end
end