classdef EEGpalCS_mod_Frequencying_bkup < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Frequencying                    matlab.ui.Figure
        FREQUENCYINGLabel               matlab.ui.control.Label
        GENERALOPTIONSLabel             matlab.ui.control.Label
        SamplingrateEditFieldLabel      matlab.ui.control.Label
        SamplingRate                    matlab.ui.control.NumericEditField
        AnalysisPeriodLabel             matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        tfLabel                         matlab.ui.control.Label
        OnsetLabel                      matlab.ui.control.Label
        Onset_ms                        matlab.ui.control.NumericEditField
        Onset_tf                        matlab.ui.control.NumericEditField
        OffsetLabel                     matlab.ui.control.Label
        Offset_ms                       matlab.ui.control.NumericEditField
        Offset_tf                       matlab.ui.control.NumericEditField
        DurationLabel                   matlab.ui.control.Label
        Duration_ms                     matlab.ui.control.NumericEditField
        Duration_tf                     matlab.ui.control.NumericEditField
        WindowssizeLabel                matlab.ui.control.Label
        Size_ms                         matlab.ui.control.NumericEditField
        Size_tf                         matlab.ui.control.NumericEditField
        OverlapLabel                    matlab.ui.control.Label
        Overlap                         matlab.ui.control.NumericEditField
        tfOverlap                       matlab.ui.control.Label
        nWindowsLabel                   matlab.ui.control.Label
        nWindows                        matlab.ui.control.NumericEditField
        FrequencyresolutionLabel        matlab.ui.control.Label
        Resolution                      matlab.ui.control.NumericEditField
        HzLabel                         matlab.ui.control.Label
        RangeLabel                      matlab.ui.control.Label
        LowerFreq                       matlab.ui.control.NumericEditField
        toLabel                         matlab.ui.control.Label
        UpperFreq                       matlab.ui.control.NumericEditField
        HzLabel_2                       matlab.ui.control.Label
        MaxLabel                        matlab.ui.control.Label
        MaxFreq                         matlab.ui.control.NumericEditField
        HzLabel_3                       matlab.ui.control.Label
        Frequencybandseg4675102030Label  matlab.ui.control.Label
        FrequencyBands                  matlab.ui.control.EditField
        ChannelsclusterLabel            matlab.ui.control.Label
        ChannelsCluster                 matlab.ui.control.DropDown
        OUTPUTOPTIONSLabel              matlab.ui.control.Label
        ExtensionofPSDoutputfilesLabel  matlab.ui.control.Label
        PSD_FilesExtension              matlab.ui.control.DropDown
        SelectUpperFolderLabel          matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        SavingfolderLabel               matlab.ui.control.Label
        SaveFolder                      matlab.ui.control.TextArea
        SuffixstringLabel               matlab.ui.control.Label
        SuffixString                    matlab.ui.control.EditField
        InputParametersFile             matlab.ui.control.Label
        OpenButton                      matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        SaveAsButton                    matlab.ui.control.Button
        ParameterfileEditFieldLabel     matlab.ui.control.Label
        ParameterFilePath               matlab.ui.control.EditField
        APPLYLabel                      matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        DoneButton                      matlab.ui.control.Button
        CancelButton                    matlab.ui.control.Button
        FREQUENCYCOMPUTATIONLabel       matlab.ui.control.Label
        Method                          matlab.ui.control.Label
        AVERAGING                       matlab.ui.control.Label
        ExtensionofGPSoutputfilesLabel  matlab.ui.control.Label
        GPS_FilesExtension              matlab.ui.control.DropDown
        WholerangepowerspectraLabel_2   matlab.ui.control.Label
        PSD_FilesBands                  matlab.ui.control.CheckBox
        FrequencybandsifdefinedLabel_3  matlab.ui.control.Label
        PSD_FilesWhole                  matlab.ui.control.CheckBox
        PerfileLabel                    matlab.ui.control.Label
        PSDLabel                        matlab.ui.control.Label
        GPSLabel                        matlab.ui.control.Label
        AveragedtimewindowsLabel        matlab.ui.control.Label
        PSD_AvgWindows                  matlab.ui.control.CheckBox
        GPS_AvgWindows                  matlab.ui.control.CheckBox
        SeparatetimewindowsLabel        matlab.ui.control.Label
        PSD_SepWindows                  matlab.ui.control.CheckBox
        GPS_SepWindows                  matlab.ui.control.CheckBox
        WithinSubjectAveraging          matlab.ui.control.Label
        WholerangepowerspectraLabel_3   matlab.ui.control.Label
        PSD_WithinWhole                 matlab.ui.control.CheckBox
        GPS_WithinWhole                 matlab.ui.control.CheckBox
        FrequencybandsifdefinedLabel    matlab.ui.control.Label
        PSD_WithinBands                 matlab.ui.control.CheckBox
        GPS_WithinBands                 matlab.ui.control.CheckBox
        BetweenSubjectAveraging         matlab.ui.control.Label
        WholerangepowerspectraLabel     matlab.ui.control.Label
        PSD_BetweenWhole                matlab.ui.control.CheckBox
        GPS_BetweenWhole                matlab.ui.control.CheckBox
        FrequencybandsifdefinedLabel_2  matlab.ui.control.Label
        PSD_BetweenBands                matlab.ui.control.CheckBox
        GPS_BetweenBands                matlab.ui.control.CheckBox
        INDIVIDUALPEAKFREQUENCYPanel    matlab.ui.container.Panel
        iPF_FrequencyComputation        matlab.ui.control.Label
        MethodLabel                     matlab.ui.control.Label
        iPF_Method                      matlab.ui.control.DropDown
        WindowingfunctionLabel_2        matlab.ui.control.Label
        iPF_Windowing                   matlab.ui.control.DropDown
        SignaltoanalyseLabel            matlab.ui.control.Label
        iPF_Signal                      matlab.ui.control.DropDown
        SGF_AllOptions                  matlab.ui.control.Button
        SGF_RuniFP                      matlab.ui.control.Button
        iPF_Table                       matlab.ui.control.Table
        Corcoranetal2018DOI101111psyp13064Label  matlab.ui.control.Label
        SGF_Label                       matlab.ui.control.Label
        SGF_CheckBox                    matlab.ui.control.CheckBox
        FramewidthLabel                 matlab.ui.control.Label
        SGF_FrameWidth                  matlab.ui.control.NumericEditField
        PolynomialorderLabel            matlab.ui.control.Label
        SGF_PolyOrder                   matlab.ui.control.NumericEditField
        GPS_FilesWhole                  matlab.ui.control.CheckBox
        GPS_FilesBands                  matlab.ui.control.CheckBox
        InconsistentSR                  matlab.ui.control.Label
        InconsistentNumChan             matlab.ui.control.Label
        EndOfFile                       matlab.ui.control.StateButton
        FreqMethodInstruction           matlab.ui.control.Label
        FreqMethod                      matlab.ui.control.ListBox
        NormalizationGroup              matlab.ui.container.ButtonGroup
        NORM_None                       matlab.ui.control.ToggleButton
        NROM_Mean                       matlab.ui.control.ToggleButton
        NORM_LogTransform               matlab.ui.control.ToggleButton
        NORM_Neighbourg                 matlab.ui.control.ToggleButton
        nNeighEditFieldLabel            matlab.ui.control.Label
        NORM_nNeigh                     matlab.ui.control.NumericEditField
        ChannelDropDownLabel            matlab.ui.control.Label
        NORM_NeighChan                  matlab.ui.control.DropDown
        WindowingGroup                  matlab.ui.container.ButtonGroup
        WIND_None                       matlab.ui.control.ToggleButton
        WIND_Hamming                    matlab.ui.control.ToggleButton
        WIND_Hanning                    matlab.ui.control.ToggleButton
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        SettingTable
        %nChan
    end
    
    properties (Access = public)
        SessionParameters
        Study_Parameters
    end
    
    methods (Access = private)
        
        function applyStudyParam(app)
            % Save folder
            app.SaveFolder.Value                = app.Study_Parameters.Frequency.SaveFolder;
            app.PSD_FilesExtension.Value        = app.Study_Parameters.Frequency.PSD_FilesExtension;
            app.GPS_FilesExtension.Value         = app.Study_Parameters.Frequency.GPS_FilesExtension;
            
            % GENERAL OPTIONS
            
            % Analysis period
            app.Onset_ms.Value                  = app.Study_Parameters.Frequency.Onset_ms;
            app.Onset_tf.Value                  = app.Study_Parameters.Frequency.Onset_tf;
            app.Offset_ms.Value                 = app.Study_Parameters.Frequency.Offset_ms;
            app.Offset_tf.Value                 = app.Study_Parameters.Frequency.Offset_tf;
            app.Size_ms.Value                   = app.Study_Parameters.Frequency.Size_ms;
            app.Size_tf.Value                   = app.Study_Parameters.Frequency.Size_tf;
            app.Overlap.Value                   = app.Study_Parameters.Frequency.Overlap;
            
            % Frequencies
            app.LowerFreq.Value                 = app.Study_Parameters.Frequency.LowerFreq;
            app.UpperFreq.Value                 = app.Study_Parameters.Frequency.UpperFreq;
            app.FrequencyBands.Value            = app.Study_Parameters.Frequency.FrequencyBands;
            
            app.ChannelsCluster.Value           = app.Study_Parameters.Frequency.ElectrodesCluster;
            
            % FREQUENCY ANALYSIS
            
            % Method
            app.FreqMethod.Value                = app.Study_Parameters.Frequency.FreqMethod;
            app.WindowingGroup.SelectedObject   = app.Study_Parameters.Frequency.Windowing;
            app.NormalizationGroup.SelectedObject = app.Study_Parameters.Frequency.Normalization;
            app.NORM_nNeigh.Value               = app.Study_Parameters.Frequency.NORM_nNeigh;
            
            % Saving
            app.PSD_SepWindows.Value            = app.Study_Parameters.Frequency.PSD_SepWindows;
            app.GPS_SepWindows.Value            = app.Study_Parameters.Frequency.GPS_SepWindows;
            app.PSD_AvgWindows.Value            = app.Study_Parameters.Frequency.PSD_AvgWindows;
            app.GPS_AvgWindows.Value            = app.Study_Parameters.Frequency.GPS_AvgWindows;
            app.PSD_FilesWhole.Value            = app.Study_Parameters.Frequency.PSD_FilesWhole;
            app.GPS_FilesWhole.Value            = app.Study_Parameters.Frequency.GPS_FilesWhole;
            app.PSD_FilesBands.Value            = app.Study_Parameters.Frequency.PSD_FilesBands;
            app.GPS_FilesBands.Value            = app.Study_Parameters.Frequency.GPS_FilesBands;
            
            % Averaging
            app.PSD_WithinWhole.Value           = app.Study_Parameters.Frequency.PSD_WithinWhole;
            app.GPS_WithinWhole.Value           = app.Study_Parameters.Frequency.GPS_WithinWhole;
            app.PSD_WithinBands.Value           = app.Study_Parameters.Frequency.PSD_WithinBands;
            app.GPS_WithinBands.Value           = app.Study_Parameters.Frequency.GPS_WithinBands;
            app.PSD_BetweenWhole.Value          = app.Study_Parameters.Frequency.PSD_BetweenWhole;
            app.GPS_BetweenWhole.Value          = app.Study_Parameters.Frequency.GPS_BetweenWhole;
            app.PSD_BetweenBands.Value          = app.Study_Parameters.Frequency.PSD_BetweenBands;
            app.GPS_BetweenBands.Value          = app.Study_Parameters.Frequency.GPS_BetweenBands;
            
            % INDIVIDUAL PEAK FREQUENCY
            
            % Frequency computation
            app.iPF_Method.Items                = app.Study_Parameters.Frequency.iAF.iAF_Method;
            app.iPF_Windowing.Value             = app.Study_Parameters.Frequency.iAF.iAF_Windowing;
            app.iPF_Signal.Value                = app.Study_Parameters.Frequency.iAF.iAF_Signal;
            
            % Savitzky-Golay filter
            app.SGF_CheckBox.Value              = app.Study_Parameters.Frequency.iAF.SGF_CheckBox;
            app.SGF_FrameWidth.Value            = app.Study_Parameters.Frequency.iAF.SGF_FrameWidth;
            app.SGF_PolyOrder.Value             = app.Study_Parameters.Frequency.iAF.SGF_PolyOrder;
        end
        
        function Study_Parameters = updateStudyParam(app,Study_Parameters)
            % Save folder
            app.Study_Parameters.Frequency.SaveFolder           = app.SaveFolder.Value;
            app.Study_Parameters.Frequency.PSD_FilesExtension   = app.PSD_FilesExtension.Value;
            app.Study_Parameters.Frequency.GPS_FilesExtension    = app.GPS_FilesExtension.Value;
            
            % GENERAL OPTIONS
            
            % Analysis period
            app.Study_Parameters.Frequency.Onset_ms             = app.Onset_ms.Value;
            app.Study_Parameters.Frequency.Onset_tf             = app.Onset_tf.Value;
            app.Study_Parameters.Frequency.Offset_ms            = app.Offset_ms.Value;
            app.Study_Parameters.Frequency.Offset_tf            = app.Offset_tf.Value;
            app.Study_Parameters.Frequency.Size_ms              = app.Size_ms.Value;
            app.Study_Parameters.Frequency.Size_tf              = app.Size_tf.Value;
            app.Study_Parameters.Frequency.Overlap              = app.Overlap.Value;
            
            % Frequencies
            app.Study_Parameters.Frequency.LowerFreq            = app.LowerFreq.Value;
            app.Study_Parameters.Frequency.UpperFreq            = app.UpperFreq.Value;
            app.Study_Parameters.Frequency.FrequencyBands       = app.FrequencyBands.Value;
            
            app.Study_Parameters.Frequency.ElectrodesCluster    = app.ChannelsCluster.Value;
            
            % FREQUENCY ANALYSIS
            
            % Method
            app.Study_Parameters.Frequency.FreqMethod           = app.FreqMethod.Value;
            app.Study_Parameters.Frequency.Windowing            = app.WindowingGroup.SelectedObject;
            app.Study_Parameters.Frequency.Normalization        = app.NormalizationGroup.SelectedObject;
            app.Study_Parameters.Frequency.NORM_nNeigh          = app.NORM_nNeigh.Value;
            
            % Saving
            app.Study_Parameters.Frequency.PSD_SepWindows       = app.PSD_SepWindows.Value;
            app.Study_Parameters.Frequency.GPS_SepWindows       = app.GPS_SepWindows.Value;
            app.Study_Parameters.Frequency.PSD_AvgWindows       = app.PSD_AvgWindows.Value;
            app.Study_Parameters.Frequency.GPS_AvgWindows       = app.GPS_AvgWindows.Value;
            app.Study_Parameters.Frequency.PSD_FilesWhole       = app.PSD_FilesWhole.Value;
            app.Study_Parameters.Frequency.GPS_FilesWhole       = app.GPS_FilesWhole.Value;
            app.Study_Parameters.Frequency.PSD_FilesBands       = app.PSD_FilesBands.Value;
            app.Study_Parameters.Frequency.GPS_FilesBands       = app.GPS_FilesBands.Value;
            
            % Averaging
            app.Study_Parameters.Frequency.PSD_WithinWhole      = app.PSD_WithinWhole.Value;
            app.Study_Parameters.Frequency.GPS_WithinWhole      = app.GPS_WithinWhole.Value;
            app.Study_Parameters.Frequency.PSD_WithinBands      = app.PSD_WithinBands.Value;
            app.Study_Parameters.Frequency.GPS_WithinBands      = app.GPS_WithinBands.Value;
            app.Study_Parameters.Frequency.PSD_BetweenWhole     = app.PSD_BetweenWhole.Value;
            app.Study_Parameters.Frequency.GPS_BetweenWhole     = app.GPS_BetweenWhole.Value;
            app.Study_Parameters.Frequency.PSD_BetweenBands     = app.PSD_BetweenBands.Value;
            app.Study_Parameters.Frequency.GPS_BetweenBands     = app.GPS_BetweenBands.Value;
            
            % INDIVIDUAL PEAK FREQUENCY
            
            % Frequency computation
            app.Study_Parameters.Frequency.iAF.iAF_Method       = app.iPF_Method.Items;
            app.Study_Parameters.Frequency.iAF.iAF_Windowing    = app.iPF_Windowing.Value;
            app.Study_Parameters.Frequency.iAF.iAF_Signal       = app.iPF_Signal.Value;
            
            % Savitzky-Golay filter
            app.Study_Parameters.Frequency.iAF.SGF_CheckBox     = app.SGF_CheckBox.Value;
            app.Study_Parameters.Frequency.iAF.SGF_FrameWidth   = app.SGF_FrameWidth.Value;
            app.Study_Parameters.Frequency.iAF.SGF_PolyOrder    = app.SGF_PolyOrder.Value;
        end
        
        
        function applySessionParam(app)
            % Save folder
            app.SaveFolder.Value                = app.Study_Parameters.Frequency.SaveFolder;
            app.PSD_FilesExtension.Value        = app.SessionParameters.Frequency.PSD_FilesExtension;
            app.GPS_FilesExtension.Value        = app.SessionParameters.Frequency.GPS_FilesExtension;
            
            % GENERAL OPTIONS
            
            % Analysis period
            app.Onset_ms.Value                  = app.SessionParameters.Frequency.Onset_ms;
            app.Onset_tf.Value                  = app.SessionParameters.Frequency.Onset_tf;
            app.Offset_ms.Value                 = app.SessionParameters.Frequency.Offset_ms;
            app.Offset_tf.Value                 = app.SessionParameters.Frequency.Offset_tf;
            app.Size_ms.Value                   = app.SessionParameters.Frequency.Size_ms;
            app.Size_tf.Value                   = app.SessionParameters.Frequency.Size_tf;
            app.Overlap.Value                   = app.SessionParameters.Frequency.Overlap;
            
            % Frequencies
            app.LowerFreq.Value                 = app.SessionParameters.Frequency.LowerFreq;
            app.UpperFreq.Value                 = app.SessionParameters.Frequency.UpperFreq;
            app.FrequencyBands.Value            = app.SessionParameters.Frequency.FrequencyBands;
            
            app.ChannelsCluster.Value           = app.SessionParameters.Frequency.ElectrodesCluster;
            
            % FREQUENCY ANALYSIS
            
            % Method
            app.FreqMethod.Value                = app.SessionParameters.Frequency.FreqMethod;
            app.WindowingGroup.SelectedObject   = app.SessionParameters.Frequency.Windowing;
            app.NormalizationGroup.SelectedObject = app.SessionParameters.Frequency.Normalization;
            app.NORM_nNeigh.Value               = app.SessionParameters.Frequency.NORM_nNeigh;
            
            % Saving
            app.PSD_SepWindows.Value            = app.SessionParameters.Frequency.PSD_SepWindows;
            app.GPS_SepWindows.Value            = app.SessionParameters.Frequency.GPS_SepWindows;
            app.PSD_AvgWindows.Value            = app.SessionParameters.Frequency.PSD_AvgWindows;
            app.GPS_AvgWindows.Value            = app.SessionParameters.Frequency.GPS_AvgWindows;
            app.PSD_FilesWhole.Value            = app.SessionParameters.Frequency.PSD_FilesWhole;
            app.GPS_FilesWhole.Value            = app.SessionParameters.Frequency.GPS_FilesWhole;
            app.PSD_FilesBands.Value            = app.SessionParameters.Frequency.PSD_FilesBands;
            app.GPS_FilesBands.Value            = app.SessionParameters.Frequency.GPS_FilesBands;
            
            % Averaging
            app.PSD_WithinWhole.Value           = app.SessionParameters.Frequency.PSD_WithinWhole;
            app.GPS_WithinWhole.Value           = app.SessionParameters.Frequency.GPS_WithinWhole;
            app.PSD_WithinBands.Value           = app.SessionParameters.Frequency.PSD_WithinBands;
            app.GPS_WithinBands.Value           = app.SessionParameters.Frequency.GPS_WithinBands;
            app.PSD_BetweenWhole.Value          = app.SessionParameters.Frequency.PSD_BetweenWhole;
            app.GPS_BetweenWhole.Value          = app.SessionParameters.Frequency.GPS_BetweenWhole;
            app.PSD_BetweenBands.Value          = app.SessionParameters.Frequency.PSD_BetweenBands;
            app.GPS_BetweenBands.Value          = app.SessionParameters.Frequency.GPS_BetweenBands;
            
            % INDIVIDUAL PEAK FREQUENCY
            
            % Frequency computation
            app.iPF_Method.Items                = app.SessionParameters.Frequency.iAF.iAF_Method;
            app.iPF_Windowing.Value             = app.SessionParameters.Frequency.iAF.iAF_Windowing;
            app.iPF_Signal.Value                = app.SessionParameters.Frequency.iAF.iAF_Signal;
            
            % Savitzky-Golay filter
            app.SGF_CheckBox.Value              = app.SessionParameters.Frequency.iAF.SGF_CheckBox;
            app.SGF_FrameWidth.Value            = app.SessionParameters.Frequency.iAF.SGF_FrameWidth;
            app.SGF_PolyOrder.Value             = app.SessionParameters.Frequency.iAF.SGF_PolyOrder;
        end
        
        function SessionParameters   = updateSessionParam(app,SessionParameters)
            % Save folder
            SessionParameters.Frequency.SaveFolder              = app.SaveFolder.Value;
            SessionParameters.Frequency.PSD_FilesExtension      = app.PSD_FilesExtension.Value;
            SessionParameters.Frequency.GPS_FilesExtension      = app.GPS_FilesExtension.Value;
            
            % GENERAL OPTIONS
            
            % Analysis period
            SessionParameters.Frequency.Onset_ms                = app.Onset_ms.Value;
            SessionParameters.Frequency.Onset_tf                = app.Onset_tf.Value;
            SessionParameters.Frequency.Offset_ms               = app.Offset_ms.Value;
            SessionParameters.Frequency.Offset_tf               = app.Offset_tf.Value;
            SessionParameters.Frequency.Size_ms                 = app.Size_ms.Value;
            SessionParameters.Frequency.Size_tf                 = app.Size_tf.Value;
            SessionParameters.Frequency.Overlap                 = app.Overlap.Value;
            
            % Frequencies
            SessionParameters.Frequency.LowerFreq               = app.LowerFreq.Value;
            SessionParameters.Frequency.UpperFreq               = app.UpperFreq.Value;
            SessionParameters.Frequency.FrequencyBands          = app.FrequencyBands.Value;
            
            SessionParameters.Frequency.ElectrodesCluster       = app.ChannelsCluster.Value;
            
            % FREQUENCY ANALYSIS
            
            % Method
            SessionParameters.Frequency.FreqMethod              = app.FreqMethod.Value;
            SessionParameters.Frequency.Windowing               = app.WindowingGroup.SelectedObject;
            SessionParameters.Frequency.Normalization           = app.NormalizationGroup.SelectedObject;
            SessionParameters.Frequency.NORM_nNeigh             = app.NORM_nNeigh.Value;
            
            % Saving
            SessionParameters.Frequency.PSD_SepWindows          = app.PSD_SepWindows.Value;
            SessionParameters.Frequency.GPS_SepWindows          = app.GPS_SepWindows.Value;
            SessionParameters.Frequency.PSD_AvgWindows          = app.PSD_AvgWindows.Value;
            SessionParameters.Frequency.GPS_AvgWindows          = app.GPS_AvgWindows.Value;
            SessionParameters.Frequency.PSD_FilesWhole          = app.PSD_FilesWhole.Value;
            SessionParameters.Frequency.GPS_FilesWhole          = app.GPS_FilesWhole.Value;
            SessionParameters.Frequency.PSD_FilesBands          = app.PSD_FilesBands.Value;
            SessionParameters.Frequency.GPS_FilesBands          = app.GPS_FilesBands.Value;
            
            % Averaging
            SessionParameters.Frequency.PSD_WithinWhole         = app.PSD_WithinWhole.Value;
            SessionParameters.Frequency.GPS_WithinWhole         = app.GPS_WithinWhole.Value;
            SessionParameters.Frequency.PSD_WithinBands         = app.PSD_WithinBands.Value;
            SessionParameters.Frequency.GPS_WithinBands         = app.GPS_WithinBands.Value;
            SessionParameters.Frequency.PSD_BetweenWhole        = app.PSD_BetweenWhole.Value;
            SessionParameters.Frequency.GPS_BetweenWhole        = app.GPS_BetweenWhole.Value;
            SessionParameters.Frequency.PSD_BetweenBands        = app.PSD_BetweenBands.Value;
            SessionParameters.Frequency.GPS_BetweenBands        = app.GPS_BetweenBands.Value;
            
            % INDIVIDUAL PEAK FREQUENCY
            
            % Frequency computation
            SessionParameters.Frequency.iAF.iAF_Method          = app.iPF_Method.Items;
            SessionParameters.Frequency.iAF.iAF_Windowing       = app.iPF_Windowing.Value;
            SessionParameters.Frequency.iAF.iAF_Signal          = app.iPF_Signal.Value;
            
            % Savitzky-Golay filter
            SessionParameters.Frequency.iAF.SGF_CheckBox        = app.SGF_CheckBox.Value;
            SessionParameters.Frequency.iAF.SGF_FrameWidth      = app.SGF_FrameWidth.Value;
            SessionParameters.Frequency.iAF.SGF_PolyOrder       = app.SGF_PolyOrder.Value;
        end
        
        function updateTotal(app,onset_ms,onset_tf,offset_ms,offset_tf,total_ms,total_tf)
            total_ms.Value  = offset_ms.Value - onset_ms.Value; % 1 tf is counted as 0 ms
            total_tf.Value  = offset_tf.Value - onset_tf.Value + 1; % +1 because onset and offset included
            
            if total_tf.Value <= 0 % tf in case there is no sampling rate defined
                total_ms.FontColor      = [1.00,0.00,0.00];
                total_ms.FontWeight     = 'bold';
                total_tf.FontColor      = [1.00,0.00,0.00];
                total_tf.FontWeight     = 'bold';
                app.RunButton.Enable    = 'off';
            else
                total_ms.FontColor      = [0.00,0.00,0.00];
                total_ms.FontWeight     = 'normal';
                total_tf.FontColor      = [0.00,0.00,0.00];
                total_tf.FontWeight     = 'normal';
                
                app.RunButton.Enable    = 'on';
                
                if total_tf.Value < app.Size_tf.Value
                    app.Size_tf.Value = total_tf.Value;
                    app.Size_ms.Value = total_ms.Value;
                end
            end
        end
        
        function tf = ms2tf(~,ms,sr)
            tf = round((ms / 1000) * sr, 0) + 1; % +1 because the first time-frame at 0 ms is... the first (not as in Cartool!)
        end
        
        function ms = tf2ms(~,tf,sr)
            ms = round((tf-1 / sr) * 1000, 0); % tf-1 because the first time-frame is at 0 ms (not as in Cartool!)
        end
        
%         function out = str2vec(~,str)
%             cln = find(str==':');
%             if length(cln) == 1
%                 out = str2double(str(1:cln-1)) : str2double(str(cln+1:end));
%             elseif length(cln) == 2
%                 out = str2double(str(1:cln(1)-1)) : str2double(str(cln(1)+1:cln(2)-1)) : str2double(str(cln(2)+1:end));
%             end
%         end
        
        function reverseStr = txtprogressbar(~,nSteps,step,reverseStr)
            % https://undocumentedmatlab.com/articles/command-window-text-manipulation/
            percentDone = 100 * (step) / nSteps;
            msg = sprintf('    Progression: %3.1f', percentDone);
            fprintf([reverseStr, msg,'%%']);
            reverseStr = repmat(sprintf('\b'), 1, length(msg)+1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main = EEGpalCS_Main;
            
            
            % UPDATE UI with input values
            
            app.ParameterFilePath.Value     = app.EEGpalCS_Main.ParameterFilePath.Value;
            app.Study_Parameters            = app.EEGpalCS_Main.Study_Parameters;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            
            % Channels
            try
                app.SettingTable                    = app.SessionParameters.Electrodes.SettingTable;
                app.SettingTable(~[app.SettingTable.include{:}]',:) = []; % remove unselected channels
                app.SettingTable.nr                 = (1:height(app.SettingTable))';
                
                % Scalp cluster (default values)
                app.ChannelsCluster.Items           = ['all';unique(app.SettingTable.cluster,'stable')]';
                app.ChannelsCluster.Value           = app.ChannelsCluster.Items(1);
            catch
                app.ChannelsCluster.Enable          = 'off';
            end
            
            % Saved study parameters
            try
               applyStudyParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.Study_Parameters.Files.SaveFolder;
                catch
                end
            end
            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end
            
            % Check data consistency
            if ~isempty(app.DataTable)
                % Check consistency between files
                if length(unique(app.DataTable.SamplingRate)) > 1
                    app.InconsistentSR.Visible      = 'on';
                    app.Onset_tf.Enable             = 'off';
                    app.Offset_tf.Enable            = 'off';
                    app.Size_tf.Enable              = 'off';
                    app.SamplingRate.Value          = 1000;
                    %app.RunButton.Enable            = 'off';
                else
                    app.SamplingRate.Value  = app.DataTable.SamplingRate(1);
                end
                if length(unique(app.DataTable.nChan)) > 1
                    app.InconsistentNumChan.Visible = 'on';
                    app.ChannelsCluster.Enable      = 'off';
                    app.GPS_FilesExtension.Enable    = 'off';
                    app.PSD_FilesBands.Enable       = 'off';
                    app.PSD_FilesWhole.Enable       = 'off';
                    app.GPS_AvgWindows.Enable       = 'off';
                    app.GPS_SepWindows.Enable       = 'off';
                    app.GPS_WithinWhole.Enable      = 'off';
                    app.GPS_WithinBands.Enable      = 'off';
                    app.GPS_BetweenWhole.Enable     = 'off';
                    app.GPS_BetweenBands.Enable     = 'off';
                else
%                     % Channel tables
%                     app.nChan = app.DataTable.nChan(1);
                end
                
                % Update according to the default values
                app.Size_tf.Value       = ms2tf(app,app.Size_ms.Value,app.SamplingRate.Value);
                app.Resolution.Value    = app.SamplingRate.Value / app.Size_tf.Value;
                app.MaxFreq.Value       = app.SamplingRate.Value / 2;
                app.UpperFreq.Value     = app.MaxFreq.Value;
                
                % IF NO DATA, THEN EXIT (WITH 'DONE') AND COME BACK, SHOULD
                % BE ENABLED. IF NOT, UNCOMMENT THESE LINES (IF SO, DELETE)
%                 app.Onset_ms.Enable             = 'on';
%                 app.Offset_ms.Enable            = 'on';
%                 app.Size_tf.Enable              = 'on';
                app.Onset_ms.Value              = tf2ms(app,app.Onset_tf.Value,app.SamplingRate.Value);
                app.Offset_ms.Value             = tf2ms(app,app.Offset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                app.Size_ms.Value               = tf2ms(app,app.Size_tf.Value,app.SamplingRate.Value);
            else % No sampling rate, so no 'ms' fields activated
                app.SamplingRate.Value          = 1000;
                app.SamplingRate.Enable         = 'off';
                app.RunButton.Enable            = 'off';
                app.Onset_ms.Enable             = 'off';
                app.Offset_ms.Enable            = 'off';
                app.Size_ms.Enable              = 'off';
            end
            
            
            % Update parameters according to the default window size
            if app.EndOfFile.Value
                app.Offset_tf.Enable    = 'off';
                app.Offset_ms.Enable    = 'off';
                app.Offset_tf.Value     = app.DataTable.(5)(1);
                app.Offset_ms.Value     = tf2ms(app,app.Offset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
            end
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [File,Path] = uigetfile('*.mat','Select parameter file','StudyParameters_EEGpalCS.mat');
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            load(app.ParameterFilePath.Value);
            
            try
                applyStudyParam(app);
            catch
                msgbox(sprintf('%s','No parameters saved for this module.'),...
                    'No parameters','warn');
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Save parameter file
            if ~isempty (app.ParameterFilePath.Value)
                app.Study_Parameters = load(app.ParameterFilePath.Value);
                app.Study_Parameters = updateStudyParam(app,app.Study_Parameters.Parameters);
                Parameters = app.Study_Parameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            else
                [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
                if isequal(File,0) || isequal(Path,0)
                   return; % User pressed cancel
                end
                app.ParameterFilePath.Value = fullfile(Path,File);
                app.SessionParameters = updateSessionParam(app,app.SessionParameters);
                Parameters = app.SessionParameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            end
        end

        % Button pushed function: SaveAsButton
        function SaveAsButtonPushed(app, event)
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            Parameters = app.SessionParameters;
            
            % Save parameter file
            [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            save(app.ParameterFilePath.Value,'Parameters');
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if isempty(app.DataTable)
                msgbox(sprintf('%s\n\n%s','No data to analyse!', ...
                    'Please make sure to select the files to analyse in the main window.'),...
                    'No data selected','warn');
                return
            end
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            if strcmp(app.InconsistentNumChan.Visible,'off')
                Run = questdlg(sprintf('%s\n%s\n\n%s\n%s%d%s%d%s\n%s%d%s%d%s\n%s%d%s%d%s\n%s%d%s\n%s%d%s%d%s\n%s%s\n%s%s\n\n%s\n%s%s\n%s%s\n%s%d\n%s%d\n\n%s\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n\n%s%d\n%s\n%s',...
                    'Olà!',...
                    'If you don''t check the options below properly, you will most likely end up cursing to the sky, but it will be too late...', ...
                    'GENERAL OPTIONS',...
                    'Period onset: ',app.Onset_ms.Value,'ms, ',app.Onset_tf.Value,'tf',...
                    'Period offset: ',app.Offset_ms.Value,'ms, ',app.Offset_tf.Value,'tf',...
                    'Window size: ',app.Size_ms.Value,'ms, ',app.Size_tf.Value,'tf',...
                    'Overlap: ',app.Overlap.Value,'tf',...
                    'Frequency range: ',app.LowerFreq.Value,' to ',app.UpperFreq.Value,' Hz',...
                    'Frequency bands: ',app.FrequencyBands.Value,...
                    'Channels cluster: ',app.ChannelsCluster.Value,...
                    'FREQUENCY ANALYSIS',...
                    'Frequency computation: ',strjoin(app.FreqMethod.Value,', '),...
                    'Windowing function: ',app.WindowingGroup.SelectedObject,...
                    'Group GPS output per subject/file: ',app.PSD_FilesBands.Value,...
                    'Split GPS output per frequency band: ',app.GPS_FilesBands.Value,...
                    'AVERAGING',...
                    'Per file, Averaged PSD time windows: ',app.PSD_AvgWindows.Value,...
                    'Per file, Averaged GPS time windows: ',app.GPS_AvgWindows.Value,...
                    'Per file, Separate PSD time windows: ',app.PSD_SepWindows.Value,...
                    'Per file, Separate GPS time windows: ',app.GPS_SepWindows.Value,...
                    'Within-subject, whole PSD frequency range: ',app.PSD_WithinWhole.Value,...
                    'Within-subject, whole GPS frequency range: ',app.GPS_WithinWhole.Value,...
                    'Within-subject, PSD frequency bands: ',app.PSD_WithinBands.Value,...
                    'Within-subject, GPS frequency bands: ',app.GPS_WithinBands.Value,...
                    'Between-subject, whole PSD frequency range: ',app.PSD_BetweenWhole.Value,...
                    'Between-subject, whole GPS frequency range: ',app.GPS_BetweenWhole.Value,...
                    'Between-subject, PSD frequency bands: ',app.PSD_BetweenBands.Value,...
                    'Between-subject, GPS frequency bands: ',app.GPS_BetweenBands.Value,...
                    ...
                    'Number of files: ',height(app.DataTable),...
                    'A long list will take time to proceed. In order to reduce time, you can select subsets of files in the main window.',...
                    'Do you wish to proceed?'),'Just a quick check before we go','OK','Cancel','Cancel');
            else
                Run = questdlg(sprintf('%s\n%s\n\n%s\n%s%d%s%d%s\n%s%d%s%d%s\n%s%d%s%d%s\n%s%d%s\n%s%d%s%d%s\n%s%s\n\n%s\n%s%s\n%s%s\n\n%s\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n%s%d\n\n%s%d\n%s\n%s',...
                    'Olà!',...
                    'If you don''t check the options below properly, you will most likely end up cursing to the sky, but it will be too late...', ...
                    'GENERAL OPTIONS',...
                    'Period onset: ',app.Onset_ms.Value,'ms, ',app.Onset_tf.Value,'tf',...
                    'Period offset: ',app.Offset_ms.Value,'ms, ',app.Offset_tf.Value,'tf',...
                    'Window size: ',app.Size_ms.Value,'ms, ',app.Size_tf.Value,'tf',...
                    'Overlap: ',app.Overlap.Value,'tf',...
                    'Frequency range: ',app.LowerFreq.Value,' to ',app.UpperFreq.Value,' Hz',...
                    'Frequency bands: ',app.FrequencyBands.Value,...
                    'FREQUENCY ANALYSIS',...
                    'Frequency computation: ',strjoin(app.FreqMethod.Value,', '),...
                    'Windowing function: ',app.WindowingGroup.SelectedObject,...
                    'AVERAGING',...
                    'Per file, Averaged PSD time windows: ',app.PSD_AvgWindows.Value,...
                    'Per file, Separate PSD time windows: ',app.PSD_SepWindows.Value,...
                    'Within-subject, whole PSD frequency range: ',app.PSD_WithinWhole.Value,...
                    'Within-subject, PSD frequency bands: ',app.PSD_WithinBands.Value,...
                    'Between-subject, whole PSD frequency range: ',app.PSD_BetweenWhole.Value,...
                    'Between-subject, PSD frequency bands: ',app.PSD_BetweenBands.Value,...
                    ...
                    'Number of files: ',height(app.DataTable),...
                    'A long list will take time to proceed. In order to reduce time, you can select subsets of files in the main window.',...
                    'Do you wish to proceed?'),'Just a quick check before we go','OK','Cancel','Cancel');
            end
            if strcmpi(Run,'Cancel')
                return
            end
            
            
            %% PROCESSING INFORMATION SHEET

            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Frequency_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpalCS Frequency Analysis Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.PSD_FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Study parameters file:',app.ParameterFilePath.Value);
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                fprintf(PifFileID,'%s\t\t\t\t%s\r\n','Channels cluster:',app.ChannelsCluster.Value);
                fprintf(PifFileID,'%s\r\n','Channels processed (Channel  /  Cluster):');
                fprintf(PifFileID,'\t\t%s\t\t%s\r\n',app.SessionParameters.Electrodes.SettingTable{:,[2 8]}');
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
            catch
                fprintf(PifFileID,'%s\r\n','No electrodes setting file. All channels processed.');
            end
            if length(unique(app.DataTable.nChan)) > 1
                fprintf(PifFileID,'%s\r\n','Inconsistent number of channels between files. GPS not computed');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','GENERAL OPTIONS');
            fprintf(PifFileID,'%s\t%d\r\n','Sampling Rate:',app.SamplingRate.Value);
            fprintf(PifFileID,'%s\r\n','Analysis period ([ms] / [tf]):');
            fprintf(PifFileID,'\t%s\t\t%d %s\t%d %s\r\n','Onset:',app.Onset_ms.Value,'ms',app.Onset_tf.Value,'tf');
            fprintf(PifFileID,'\t%s\t\t%d %s\t%d %s\r\n','Offset:',app.Offset_ms.Value,'ms',app.Offset_tf.Value,'tf');
            fprintf(PifFileID,'\t%s\t%d %s\t%d %s\r\n','Duration:',app.Duration_ms.Value,'ms',app.Duration_tf.Value,'tf');
            fprintf(PifFileID,'%s\r\n','Windows:');
            fprintf(PifFileID,'\t%s\t\t%d %s\t%d %s\r\n','Size:',app.Onset_ms.Value,'ms',app.Onset_tf.Value,'tf');
            fprintf(PifFileID,'\t%s\t%d %s\r\n','Overlap:',app.Overlap.Value,'tf');
%             fprintf(PifFileID,'%s\t%d\r\n','Number of windows:',app.nWindows.Value);
%             PeriodLength    = app.Duration_tf.Value;
%             WindowSize      = app.Size_tf.Value;
%             Steps           = WindowSize - app.Overlap.Value; % Windows step
%             Start           = 1:Steps:WindowSize; % Starting points in the period by steps
%             nWinArray       = arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start); % -WindowsSize so that only complete Windows are counted
%             Longest         = max(nWinArray * WindowSize + Start); % Period for windowing ending the latest, taking into account the delay at the start
%             Discarded_tf    = PeriodLength - Longest;
%             Discarded_ms    = tf2ms(app,Discarded_tf,app.SamplingRate.Value);
%             fprintf(PifFileID,'\t%s\t%d %s\t%d %s\r\n','Portion of signal discarded at the end of the period:',Discarded_ms,'ms',Discarded_tf,'tf');
            fprintf(PifFileID,'%s\r\n','Frequencies:');
            fprintf(PifFileID,'\t%s\t%d\r\n','Frequency resolution:',app.Resolution.Value);
            fprintf(PifFileID,'\t%s\t\t%d %s\t%d %s\r\n','Frequency range:',app.LowerFreq.Value,'to',app.UpperFreq.Value,'Hz');
            fprintf(PifFileID,'\t%s\t%s\r\n','Frequency bands:',app.FrequencyBands.Value);
            fprintf(PifFileID,'\t%s\r\n','Frequency bins:');
            FreqBins        = (0:app.Resolution.Value:app.MaxFreq.Value)';
            Range           = min(FreqBins >= app.LowerFreq.Value,FreqBins <= app.UpperFreq.Value);
            RangeBins       = FreqBins(Range);
            fprintf(PifFileID,'\t\t%.4f\r\n',RangeBins);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','FREQUENCY ANALYSIS');
            fprintf(PifFileID,'%s\r\n','Method:');
            if contains(app.FreqMethod.Value,'(FFT)')
                fprintf(PifFileID,'\t%s\t\t%s\r\n','FFT computation:','Yes');
            else
                fprintf(PifFileID,'\t%s\t\t%s\r\n','FFT computation:','No');
            end
            if contains(app.FreqMethod.Value,'(FFTA)')
                fprintf(PifFileID,'\t%s\t\t%s\r\n','FFTA computation:','Yes');
            else
                fprintf(PifFileID,'\t%s\t\t%s\r\n','FFTA computation:','No');
            end
            fprintf(PifFileID,'\t%s\t\t%s\r\n','Windowing function:',app.WindowingGroup.SelectedObject);
            fprintf(PifFileID,'\t%s\t%s\r\n','Removal of 1/f noise:',app.NormalizationGroup.SelectedObject);
            % fprintf(PifFileID,'\t%s\r\n','Currently, only the ''Remove mean'' option does something, sorry!');
            fprintf(PifFileID,'%s\r\n','GPS:');
            if length(unique(app.DataTable.nChan)) > 1
                fprintf(PifFileID,'\t%s\r\n','Inconsistent number of channels between files. GPS not computed');
            else
                
            end
            fprintf(PifFileID,'%s\r\n','Individual files saving options, channels-wise PSD:');
            if app.PSD_AvgWindows.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Averaged time windows:','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Averaged time windows:','No');
            end
            if app.PSD_SepWindows.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Separate time windows:','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Separate time windows:','No');
            end
            if app.PSD_FilesWhole.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
            end
            if ~isempty(app.FrequencyBands.Value)
                if app.PSD_FilesBands.Value
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','No');
                end
            else
                fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
            end
            if length(unique(app.DataTable.nChan)) == 1
                fprintf(PifFileID,'%s\r\n','Individual files saving options, GPS:');
                if app.GPS_AvgWindows.Value
                    fprintf(PifFileID,'\t%s\t%s\r\n','Averaged time windows:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t%s\r\n','Averaged time windows:','No');
                end
                if app.GPS_SepWindows.Value
                    fprintf(PifFileID,'\t%s\t%s\r\n','Separate time windows:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t%s\r\n','Separate time windows:','No');
                end
                if app.GPS_FilesWhole.Value
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
                end
                if ~isempty(app.FrequencyBands.Value)
                    if app.GPS_FilesBands.Value
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','Yes');
                    else
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','No');
                    end
                else
                    fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
                end
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','AVERAGING');
            fprintf(PifFileID,'%s\r\n','Within-subject averaging, channels-wise PSD');
            if app.PSD_WithinWhole.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
            end
            if ~isempty(app.FrequencyBands.Value)
                if app.PSD_WithinBands.Value
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','No');
                end
            else
                fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
            end
            if length(unique(app.DataTable.nChan)) == 1
                fprintf(PifFileID,'%s\r\n','Within-subject averaging, GPS');
                if app.GPS_WithinWhole.Value
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
                end
                if ~isempty(app.FrequencyBands.Value)
                    if app.GPS_WithinBands.Value
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','Yes');
                    else
                        fprintf(PifFileID,'\t%s\t\t%s\r\n','Frequency bands:','No');
                    end
                else
                    fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
                end
            end
            
            fprintf(PifFileID,'\r\n%s\r\n','Between-subject averaging, channels-wise PSD');
            if app.PSD_BetweenWhole.Value
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
            else
                fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
            end
            if ~isempty(app.FrequencyBands.Value)
                if app.PSD_BetweenBands.Value
                    fprintf(PifFileID,'\t%s\t\t\t%s\r\n','Frequency bands:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t\t\t%s\r\n','Frequency bands:','No');
                end
            else
                fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
            end
            if length(unique(app.DataTable.nChan)) == 1
                fprintf(PifFileID,'%s\r\n','Within-subject averaging, GPS');
                if app.GPS_BetweenWhole.Value
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','Yes');
                else
                    fprintf(PifFileID,'\t%s\t%s\r\n','Whole power spectra:','No');
                end
                if ~isempty(app.FrequencyBands.Value)
                    if app.GPS_BetweenBands.Value
                        fprintf(PifFileID,'\t%s\t\t\t%s\r\n','Frequency bands:','Yes');
                    else
                        fprintf(PifFileID,'\t%s\t\t\t%s\r\n','Frequency bands:','No');
                    end
                else
                    fprintf(PifFileID,'\t%s\r\n','No frequency bands defined');
                end
            end
            
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Subject  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';cellstr(app.DataTable.(7))';num2cell(app.DataTable.(3))';num2cell(app.DataTable.(4))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN FREQUENCYING
            
            % Prepare values
            
%             ProcessedDataTable  = app.DataTable; % For updating the Main Window
            ChannelsCheck       = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            PeriodLength_ALL    = zeros(height(app.DataTable),1); % For 'OUTPUT FILES' in PIF
            nWindows_ALL        = zeros(height(app.DataTable),1); % For 'OUTPUT FILES' in PIF
            DiscardedTF_ALL     = zeros(height(app.DataTable),1); % For 'OUTPUT FILES' in PIF
            
            if strcmp(app.PSD_FilesExtension.Value,'.freq') || strcmp(app.PSD_FilesExtension.Value,'.sef')
                try
                    Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                catch
                    Channels = '';
                end
            elseif strcmp(app.PSD_FilesExtension.Value,'.set')
                try
                    ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    Channels            = ElectrodeSetting(:,[6 7 2]);
                    Channels.labels     = char(Channels.labels);
                    Channels.sph_theta  = -Channels.theta;
                    Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                    Channels.X          = ElectrodeSetting.x;
                    Channels.Y          = ElectrodeSetting.y;
                    Channels.Z          = ElectrodeSetting.z;
                    Channels            = table2struct(Channels);
                catch
                    Channels = '';
                end
            end
            
            FrequencyNames  = strcat(num2str(RangeBins,'%.2f'),repmat('Hz',length(RangeBins),1));
            if ~isempty(app.FrequencyBands.Value)
                Bands       = strrep(app.FrequencyBands.Value,':','-');
                Bands       = strsplit(Bands,{' ',',',';'});
                BandNames   = strcat(char(Bands'),repmat('Hz',length(Bands),1));
            end         
            
            
            disp('Run Frequencying...')
            StartTime = datetime('now');
            tStart = tic;
            
            if ~isempty(char(unique(app.DataTable.(7))))
                SUBJ = unique(app.DataTable.(7));
            else % if no subjects identified, process each file separately
                num = arrayfun(@(x) sprintf('%02d',x),1:height(app.DataTable),'UniformOutput',false);
                SUBJ = strcat(repmat('file',height(app.DataTable),1),string(num'));
                app.DataTable.Subject = SUBJ;
            end
            
            
            % Structure for between-subject data
            Between.nSubj           = length(SUBJ); % number of subjects or files
            Between.FFTpsd          = []; % for FFT of each subject, range-wise
            Between.FFTpsd_GPS      = []; % for FFT GPS of each subject, range-wise
            Between.FFTApsd         = []; % for FFTA of each subject, range-wise
            Between.FFTApsd_GPS     = []; % for FFTA GPS of each subject, range-wise
            Between.FFTbands        = []; % for FFT of each subject, band-wise
            Between.FFTbands_GPS    = []; % for FFT GPS of each subject, band-wise
            Between.FFTAbands       = []; % for FFTA of each subject, band-wise
            Between.FFTAbands_GPS   = []; % for FFTA GPS of each subject, band-wise
            
            
            for subj = 1:length(SUBJ)
                disp(['Processing ', char(SUBJ(subj,1)), ' (', num2str(subj), ' out of ', num2str(length(SUBJ)), ')'])
                SubjDataTable   = app.DataTable(strcmp(app.DataTable.Subject,SUBJ(subj,1)),:);
                
                % Structure for within-subject data
                SubjData.FFTpsd     = []; % for data of concateneted FFT, subject-wise
                SubjData.FFTApsd    = []; % for data of concateneted FFTA, subject-wise
                
                
                for file = 1:height(SubjDataTable)
                    % Load file
                    disp(['Loading ', cell2mat(fullfile(SubjDataTable.Path(file),SubjDataTable.File(file)))])
                    disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                    openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(SubjDataTable.Path(file),'..'),SubjDataTable.File(file))); % full path and name of the file
                    [~,FileName,FileExtension] = fileparts(openfilename);
                    if strcmpi(FileExtension,'.bdf')
                        [header,data] = open_bdf(openfilename);
                        FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                    elseif strcmpi(FileExtension,'.set')
                        [header,data] = open_eeglab(openfilename);
                        FileChannels = header.Channels;
                    elseif strcmpi(FileExtension,'.sef')
                        [header,data] = open_sef(openfilename);
                        FileChannels = header.Channels;
                    elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                        [header,data] = open_eph(openfilename);
                    else
                        if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                            disp('These tools are not intended for .freq or .ris files.')
                        else
                            disp('File extension not recognized.')
                        end
                        disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                        disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                        disp('File skipped.')
                        continue
                    end
                    disp('File loaded')
                    
                    
                    % Remove unselected channels from the EEGpalCS Electrode Setting window
                    try
                        data = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    
                        % Channels check (if electrode setting defined)
                        try
                            Chan = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                            if isequal(char(FileChannels),char(Chan))
                                ChannelsCheck(file,1)   = "OK";
                            else
                                ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                            end
                        catch
                            ChannelsCheck(file,1)   = "No channels in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No electrode setting defined";
                    end
                    
                    OnsetTF         = app.Onset_tf.Value + 1; % +1 to use as the row indices
                    if app.EndOfFile.Value
                        OffsetTF    = SubjDataTable.(5)(file);
                    else
                        OffsetTF    = app.Offset_tf.Value + 1; % +1 to use as the row indices
                    end
                    data = data(OnsetTF:OffsetTF,:);
                    PeriodLength = size(data,1);
                    
                    % For 'OUTPUT FILES' in PIF
                    PeriodLength_ALL(file+(subj-1),1)   = PeriodLength;
                    WindowSize                          = app.Size_tf.Value;
                    Steps                               = WindowSize - app.Overlap.Value; % Windows step
                    Start                               = 1:Steps:WindowSize; % Starting points in the period by steps
                    nWindows_ALL(file+(subj-1),1)       = arrayfun(@(x) length(x:WindowSize:PeriodLength-(WindowSize-1)),Start); % -(WindowsSize-1) so that only complete Windows are counted
                    Longest                             = max(nWindows_ALL(file+(subj-1),1) * WindowSize + Start); % Period for windowing ending the latest, taking into account the delay at the start
                    DiscardedTF_ALL(file+(subj-1),1)    = PeriodLength - (Longest-1);
                    
                    
                    % Frequency computation
                    disp(['Processing subject ', num2str(subj), ' out of ', num2str(length(SUBJ))])
                    disp(['Processing file ', num2str(file), ' out of ', num2str(height(SubjDataTable)),' for this subject'])
                    
                    FFTpsd      = []; % for data of concateneted FFT, file-wise
                    FFTApsd     = []; % for data of concateneted FFTA, file-wise
                    nWin        = 0; % the number of time windows
                    
                    reverseStr  = ''; % For progression text
                    Steps_tf    = WindowSize - app.Overlap.Value;
                    nSteps      = length(1:Steps_tf:(PeriodLength-(WindowSize-1)));
                    
                    for step = 1:Steps_tf:(PeriodLength-(WindowSize-1))
                        WINDOW = data(step:step+WindowSize-1,:);
                        
                        if contains(app.FreqMethod.Value,'(FFT)')
                            [FFTdata,~]             = frequency_computation('FFT',WINDOW,app.SamplingRate.Value,app.WindowingGroup.SelectedObject);
                            FFTdata                 = FFTdata(Range,:);
                            
                            % Savitzky-Golay filter (smoothing)
                            if app.SGF_CheckBox.Value
                                SGFpsd = zeros(size(FFTdata));
                                for chan = 1:size(FFTdata,2)
                                    [SGFpsd(:,chan),~,~] = ...
                                        sgfDiff(FFTdata(:,chan),app.SGF_FrameWidth.Value,app.SGF_PolyOrder.Value,app.SamplingRate.Value,app.Size_tf.Value);
                                end
                                FFTdata = SGFpsd;
                            end
                            FFTpsd                  = cat(3,FFTpsd,FFTdata);
                        end
                        
                        if contains(app.FreqMethod.Value,'(FFTA)')
                            [FFTAdata,~]            = frequency_computation('FFTA',WINDOW,app.SamplingRate.Value,app.WindowingGroup.SelectedObject);
                            FFTAdata                = FFTAdata(Range,:);
                            
                            % Savitzky-Golay filter (normalization)
                            if app.SGF_CheckBox.Value
                                SGFpsd = zeros(size(FFTAdata));
                                for chan = 1:size(FFTAdata,2)
                                    [SGFpsd(:,chan),~,~] = ...
                                        sgfDiff(FFTAdata(:,chan),app.SGF_FrameWidth.Value,app.SGF_PolyOrder.Value,app.SamplingRate.Value,app.Size_tf.Value);
                                end
                                FFTAdata = SGFpsd;
                            end
                            FFTApsd                  = cat(3,FFTApsd,FFTAdata);
                        end
                        nWin = nWin + 1;
                        
                        % Progression
                        reverseStr  = txtprogressbar(app,nSteps,nWin,reverseStr);
                    end
                    
%                     % Normalization (removal of 1/f noise)
%                     if ~strcmp(app.NormalizationGroup.SelectedObject,'None')
%                         disp('Removing 1/f noise')
%                         reverseStr      = ''; % For progression text
%                         nFreq           = size(FFTpsd,1);
%                         freqRobustMean  = ones(size(FFTpsd,1),1);
%                         for freq = 1:size(FFTpsd,1)
%                             freqSpectra             = abs(FFTpsd(freq,:,:)); % could squeeze it...
%                             freqOutliersDist        = (mad(freqSpectra,1,'all') * 1.4826) * 2.5; % 1.4826 = constant, 2.5 = level of decision
%                             freqInfLim              = median(freqSpectra,'all') - freqOutliersDist;
%                             freqSupLim              = median(freqSpectra,'all') + freqOutliersDist;
%                             freqIncluded            = freqSpectra(max(freqSpectra>=freqInfLim,freqSpectra<=freqSupLim));
%                             freqRobustMean(freq,1)  = mean(freqIncluded,'all');
%                             %FFTpsd(freq,:,:)        = FFTpsd(freq,:,:) ./ (1 / freqRobustMean);
%                             
%                             % Progression
%                             reverseStr  = txtprogressbar(app,nFreq,freq,reverseStr);
%                         end
%                         if contains(app.NormalizationGroup.SelectedObject,'Log transform')
%                             %disp('Removing 1/f noise (log transform)')
%                             FFTpsd  = 10 .* log10(FFTpsd ./ freqRobustMean);
%                         end
%                         if contains(app.NormalizationGroup.SelectedObject,'Remove mean')
%                             %disp('Removing 1/f noise by subtracting the mean power over each channel and window, per frequency')
%                             freqRobustMean  = freqRobustMean / mean(freqRobustMean);
%                             FFTpsd          = FFTpsd ./ freqRobustMean;
%                         end
%                     end
                    
                    
                    % Save data
                    
                    if ~isempty(app.SuffixString.Value)
                        suffix = ['_', app.SuffixString.Value];
                    end
                    
                    SubPath = SubjDataTable.Path{file}(3:end);
                    NewPath = fullfile(app.SaveFolder.Value{:},SubPath,'Frequencying');
                    % Creating the folder
                    if ~exist(NewPath, 'dir')
                        mkdir(NewPath);
                    end
                    
                    if ~isempty(app.FrequencyBands.Value)
                        FFTbands    = zeros(length(Bands),size(FFTpsd,2),size(FFTpsd,3));
                        FFTAbands   = zeros(length(Bands),size(FFTpsd,2),size(FFTpsd,3));
                        for band = 1:length(Bands)
                            BAND    = char(Bands(1,band));
                            hphn    = find(BAND=='-');
                            Lower   = str2double(BAND(1:hphn-1));
                            Upper   = str2double(BAND(hphn+1:end));
                            BANDidx = min(RangeBins >= Lower,RangeBins <= Upper);
                            if contains(app.FreqMethod.Value,'(FFT)')
                                FFTbands(band,:,:)  = mean(FFTpsd(BANDidx,:,:),1);
                            end
                            if contains(app.FreqMethod.Value,'(FFTA)')
                                FFTAbands(band,:,:) = mean(FFTApsd(BANDidx,:,:),1);
                            end
                        end
                    end
                    
                    % FFT
                    if contains(app.FreqMethod.Value,'(FFT)')
                        disp('Saving individual FFT')
                        
                        % PSD
                        %disp('Saving individual FFT power spectra files')
                        if app.PSD_SepWindows.Value
                            if app.PSD_FilesWhole.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_spectra', app.PSD_FilesExtension.Value]);
                                %disp(['Saving individual FFT power spectra to ',savefilename])
                                if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                    save_freq(savefilename,FFTpsd,header.SamplingRate,app.Resolution.Value,FrequencyNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTpsd,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTpsd,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTpsd,header.SamplingRate,[],Channels)
                                end
                            end
                            if app.PSD_FilesBands.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_bands', app.PSD_FilesExtension.Value]);
                                %disp(['Saving individual FFT frequency bands to ',savefilename])
                                if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                    save_freq(savefilename,FFTbands,header.SamplingRate,app.Resolution.Value,BandNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTbands,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTbands,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTbands,header.SamplingRate,[],Channels)
                                end
                            end
                        end
                        if app.PSD_AvgWindows.Value
                            if app.PSD_FilesWhole.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_avgspectra', app.PSD_FilesExtension.Value]);
                                %disp(['Saving individual FFT averaged power spectra to ',savefilename])
                                FFTpsd_mean = mean(FFTpsd,3);
                                if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                    save_freq(savefilename,FFTpsd_mean,header.SamplingRate,app.Resolution.Value,FrequencyNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTpsd_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTpsd_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTpsd_mean,header.SamplingRate,[],Channels)
                                end
                            end
                            if app.PSD_FilesBands.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_avgbands', app.PSD_FilesExtension.Value]);
                                %disp(['Saving individual FFT averaged frequency bands to ',savefilename])
                                FFTbands_mean = mean(FFTbands,3);
                                if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                    save_freq(savefilename,FFTbands_mean,header.SamplingRate,app.Resolution.Value,BandNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTbands_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTbands_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTbands_mean,header.SamplingRate,[],Channels)
                                end
                            end
                        end
                        
                        % GPS
                        %disp('Saving individual FFT GPS files')
                        if app.GPS_SepWindows.Value
                            if app.GPS_FilesWhole.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_GPSspectra', app.GPS_FilesExtension.Value]);
                                disp(['Saving individual FFT power spectra GPS to ',savefilename])
                                FFTpsd_gps = compute_gfp(FFTpsd,'GPS'); % Frequency x Window
                                GPStable = array2table(FFTpsd_gps','VariableNames',string(FrequencyNames),...
                                    'RowNames',string([strcat(repmat('Window',size(FFTpsd_gps,2),1)),num2str((1:size(FFTpsd_gps,2))')]));
                                writetable(GPStable,savefilename,'WriteRowNames',true)
                            end
                            if app.GPS_FilesBands.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_GPSbands', app.GPS_FilesExtension.Value]);
                                disp(['Saving individual FFT frequency bands GPS to ',savefilename])
                                FFTbands_gps = compute_gfp(FFTbands,'GPS'); % Band x Window
                                GPStable = array2table(FFTbands_gps','VariableNames',string(BandNames),...
                                    'RowNames',string([strcat(repmat('Window',size(FFTbands_gps,2),1)),num2str((1:size(FFTbands_gps,2))')]));
                                writetable(GPStable,savefilename,'WriteRowNames',true)
                            end
                        end
                        if app.GPS_AvgWindows.Value
                            if app.GPS_FilesWhole.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_GPSavgspectra', app.GPS_FilesExtension.Value]);
                                disp(['Saving individual FFT averaged power spectra GPS to ',savefilename])
                                FFTpsd_mean = mean(FFTpsd,3);
                                FFTpsd_mean_gps = compute_gfp(FFTpsd_mean,'GPS'); % Frequency x Window
                                GPStable = array2table(FFTpsd_mean_gps','VariableNames',string(FrequencyNames),...
                                    'RowNames',string([strcat(repmat('Window',size(FFTpsd_mean_gps,2),1)),num2str((1:size(FFTpsd_mean_gps,2))')]));
                                writetable(GPStable,savefilename,'WriteRowNames',true)
                            end
                            if app.GPS_FilesBands.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFT_GPSavgbands', app.GPS_FilesExtension.Value]);
                                disp(['Saving individual FFT averaged frequency bands GPS to ',savefilename])
                                FFTbands_mean = mean(FFTbands,3);
                                FFTbands_mean_gps = compute_gfp(FFTbands_mean,'GPS'); % Band x Window
                                GPStable = array2table(FFTbands_mean_gps','VariableNames',string(BandNames),...
                                    'RowNames',string([strcat(repmat('Window',size(FFTbands_mean_gps,2),1)),num2str((1:size(FFTbands_mean_gps,2))')]));
                                writetable(GPStable,savefilename,'WriteRowNames',true)
                            end
                        end
                    end
                    
                    % FFTA
                    if contains(app.FreqMethod.Value,'(FFTA)')
                        % Adjust polartiy
                        disp('Adjusting FFTA polarity')
                        for freq = 1:length(RangeBins)
                            DataMatrix = squeeze(FFTApsd(freq,:,:)); % Data matrix for one frequency with Channels x Window
                            CorrMatrix = corrcoef(DataMatrix); % Correlation matrix (Window x Window)
                            AvgCorr = mean(abs(CorrMatrix),2); % Average correlations of one window with all others (including itself)
                            [~,I] = max(AvgCorr); % I is the index if the max value
                            
                            % Adjust polartiy so that all files and frequencies have the same polarity
                            if ~isempty(Between.FFTApsd) % adjusts the polarity of the reference epoch of the current file with the average of the previous file
                                AVG = mean(Between.FFTApsd,3);
                                CorrToRefFile = corrcoef(DataMatrix(:,I),AVG(freq,:)); % computes the correlation of the current file and frequency for the epoch with max correlation with the average of the previous file
                                if CorrToRefFile(2,1) < 0
                                    DataMatrix = DataMatrix * (-1);
                                end
                            elseif freq == 1 % check polarity of the first frequency of the first file
                                FirstPolarityRef = DataMatrix(:,I);
                            elseif freq > 1 % adjust the polartiy of subsequent frequencies of the first file
                                CorrToRefFile = corrcoef(DataMatrix(:,I),FirstPolarityRef); % computes the correlation of the current file and frequency for the epoch with max correlation with the average of the previous file
                                if CorrToRefFile(2,1) < 0
                                    DataMatrix = DataMatrix * (-1);
                                end
                            end
                            
                            for window = 1:length(CorrMatrix)
                                if CorrMatrix(window,I) > 0
                                    FFTApsd(freq,:,window) = DataMatrix(:,window);
                                else
                                    FFTApsd(freq,:,window) = DataMatrix(:,window) * (-1);
                                end
                            end
                        end
                        disp('Saving individual FFTA')
                        
                        % PSD
                        %disp('Saving individual FFTA power spectra files')
                        if app.PSD_SepWindows.Value
                            if app.PSD_FilesWhole.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_spectra', app.PSD_FilesExtension.Value]);
                                %disp(['Saving individual FFTA power spectra to ',savefilename])
                                if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                    save_freq(savefilename,FFTApsd,header.SamplingRate,app.Resolution.Value,FrequencyNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTApsd,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTApsd,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTApsd,header.SamplingRate,[],Channels)
                                end
                            end
                        end
                        if app.PSD_FilesBands.Value
                            if contains(app.FreqMethod.Value,'(FFTA)')
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_bands', app.PSD_FilesExtension.Value]);
                                %disp(['Saving individual FFTA frequency bands to ',savefilename])
                                if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                    save_freq(savefilename,FFTAbands,header.SamplingRate,app.Resolution.Value,BandNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTAbands,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTAbands,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTAbands,header.SamplingRate,[],Channels)
                                end
                            end
                        end
                        if app.PSD_AvgWindows.Value
                            if app.PSD_FilesWhole.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_avgspectra', app.PSD_FilesExtension.Value]);
                                %disp(['Saving individual FFTA averaged power spectra to ',savefilename])
                                FFTApsd_mean = mean(FFTApsd,3);
                                if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                    save_freq(savefilename,FFTApsd_mean,header.SamplingRate,app.Resolution.Value,FrequencyNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTApsd_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTApsd_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTApsd_mean,header.SamplingRate,[],Channels)
                                end
                            end
                            if app.PSD_FilesBands.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_avgbands', app.PSD_FilesExtension.Value]);
                                %disp(['Saving individual FFTA averaged frequency bands to ',savefilename])
                                FFTAbands_mean = mean(FFTAbands,3);
                                if strcmp(app.PSD_FilesExtension.Value,'.freq')
                                    save_freq(savefilename,FFTAbands_mean,header.SamplingRate,app.Resolution.Value,BandNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTAbands_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTAbands_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTAbands_mean,header.SamplingRate,[],Channels)
                                end
                            end
                        end
                        
                        % GPS
                        %disp('Saving individual FFTA GPS files')
                        if app.GPS_SepWindows.Value
                            if app.GPS_FilesWhole.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_GPSspectra', app.GPS_FilesExtension.Value]);
                                %disp(['Saving individual FFTA power spectra GPS to ',savefilename])
                                FFTApsd_gps = compute_gfp(FFTApsd,'GPS'); % Frequency x Window
                                GPStable = array2table(FFTApsd_gps','VariableNames',string(FrequencyNames),...
                                    'RowNames',string([strcat(repmat('Window',size(FFTApsd_gps,2),1)),num2str((1:size(FFTApsd_gps,2))')]));
                                writetable(GPStable,savefilename,'WriteRowNames',true)
                            end
                            if app.GPS_FilesBands.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_GPSbands', app.GPS_FilesExtension.Value]);
                                %disp(['Saving individual FFTA frequency bands GPS to ',savefilename])
                                FFTAbands_gps = compute_gfp(FFTAbands,'GPS'); % Band x Window
                                GPStable = array2table(FFTAbands_gps','VariableNames',string(BandNames),...
                                    'RowNames',string([strcat(repmat('Window',size(FFTAbands_gps,2),1)),num2str((1:size(FFTAbands_gps,2))')]));
                                writetable(GPStable,savefilename,'WriteRowNames',true)
                            end
                        end
                        if app.GPS_AvgWindows.Value
                            if app.GPS_FilesWhole.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_GPSavgspectra', app.GPS_FilesExtension.Value]);
                                %disp(['Saving individual FFTA averaged power spectra GPS to ',savefilename])
                                FFTApsd_mean = mean(FFTApsd,3);
                                FFTApsd_mean_gps = compute_gfp(FFTApsd_mean,'GPS'); % Frequency x Window
                                GPStable = array2table(FFTApsd_mean_gps','VariableNames',string(FrequencyNames),...
                                    'RowNames',string([strcat(repmat('Window',size(FFTApsd_mean_gps,2),1)),num2str((1:size(FFTApsd_mean_gps,2))')]));
                                writetable(GPStable,savefilename,'WriteRowNames',true)
                            end
                            if app.GPS_FilesBands.Value
                                savefilename = fullfile(NewPath,[FileName, suffix, '_FFTA_GPSavgbands', app.GPS_FilesExtension.Value]);
                                %disp(['Saving individual FFT averaged frequency bands GPS to ',savefilename])
                                FFTAbands_mean = mean(FFTAbands,3);
                                FFTAbands_mean_gps = compute_gfp(FFTAbands_mean,'GPS'); % Band x Window
                                GPStable = array2table(FFTAbands_mean_gps','VariableNames',string(BandNames),...
                                    'RowNames',string([strcat(repmat('Window',size(FFTAbands_mean_gps,2),1)),num2str((1:size(FFTAbands_mean_gps,2))')]));
                                writetable(GPStable,savefilename,'WriteRowNames',true)
                            end
                        end
                    end
                    
                    
                    % Update within-subject data
                    if contains(app.FreqMethod.Value,'(FFT)')
                        SubjData.FFTpsd     = cat(3,SubjData.FFTpsd,FFTpsd);
                    end
                    if contains(app.FreqMethod.Value,'(FFTA)')
                        SubjData.FFTApsd    = cat(3,SubjData.FFTpsd,FFTpsd);
                    end
                end
                
                
                % Normalization (removal of 1/f noise)
                if ~strcmp(app.NormalizationGroup.SelectedObject,'None')
                    disp('Removing 1/f noise')
                    reverseStr      = ''; % For progression text
                    nFreq           = size(SubjData.FFTpsd,1);
                    freqRobustMean  = ones(size(SubjData.FFTpsd,1),1);
                    for freq = 1:size(SubjData.FFTpsd,1)
                        freqSpectra             = abs(SubjData.FFTpsd(freq,:,:)); % could squeeze it...
                        freqOutliersDist        = (mad(freqSpectra,1,'all') * 1.4826) * 2.5; % 1.4826 = constant, 2.5 = level of decision
                        freqInfLim              = median(freqSpectra,'all') - freqOutliersDist;
                        freqSupLim              = median(freqSpectra,'all') + freqOutliersDist;
                        freqIncluded            = freqSpectra(max(freqSpectra>=freqInfLim,freqSpectra<=freqSupLim));
                        freqRobustMean(freq,1)  = mean(freqIncluded,'all');
                        %FFTpsd(freq,:,:)        = FFTpsd(freq,:,:) ./ (1 / freqRobustMean);
                        
                        % Progression
                        reverseStr  = txtprogressbar(app,nFreq,freq,reverseStr);
                    end
                    if contains(app.NormalizationGroup.SelectedObject,'Log transform')
                        %disp('Removing 1/f noise (log transform)')
                        SubjData.FFTpsd  = 10 .* log10(SubjData.FFTpsd ./ freqRobustMean);
                    end
                    if contains(app.NormalizationGroup.SelectedObject,'Remove mean')
                        %disp('Removing 1/f noise by subtracting the mean power over each channel and window, per frequency')
                        freqRobustMean  = freqRobustMean / mean(freqRobustMean);
                        SubjData.FFTpsd          = SubjData.FFTpsd ./ freqRobustMean;
                    end
                end
                
                
                % Saving Subject data
                
                if app.PSD_WithinWhole.Value || app.PSD_WithinBands.Value || app.GPS_WithinWhole.Value || app.GPS_WithinBands.Value
                    SubjPath = fullfile(app.SaveFolder.Value{:},'Subject averages',char(SUBJ(subj)));
                    % Creating the folder
                    if ~exist(SubjPath, 'dir')
                        mkdir(SubjPath);
                    end
                end
                
                if ~isempty(app.FrequencyBands.Value)
                    SubjData.FFTbands   = zeros(length(Bands),size(SubjData.FFTpsd,2),size(SubjData.FFTpsd,3));
                    SubjData.FFTAbands  = zeros(length(Bands),size(SubjData.FFTApsd,2),size(SubjData.FFTApsd,3));
                    for band = 1:length(Bands)
                        BAND    = char(Bands(1,band));
                        hphn    = find(BAND=='-');
                        Lower   = str2double(BAND(1:hphn-1));
                        Upper   = str2double(BAND(hphn+1:end));
                        BANDidx = min(RangeBins >= Lower,RangeBins <= Upper);
                        if contains(app.FreqMethod.Value,'(FFT)')
                            SubjData.FFTbands(band,:,:)     = mean(SubjData.FFTpsd(BANDidx,:,:),1);
                        end
                        if contains(app.FreqMethod.Value,'(FFTA)')
                            SubjData.FFTAbands(band,:,:)    = mean(SubjData.FFTApsd(BANDidx,:,:),1);
                        end
                    end
                end
                
                % FFT
                if contains(app.FreqMethod.Value,'(FFT)')
                    disp(['Saving FFT files for subject ',char(SUBJ(subj))])
                    
                    % PSD
                    %disp(['Saving FFT power spectra files for subject ',char(SUBJ(subj))])
                    if app.PSD_WithinWhole.Value
                        savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFT_avgspectra', app.PSD_FilesExtension.Value]);
                        %disp(['Saving FFT power spectra for ',char(SUBJ(subj))])
                        SubjData.FFTpsd_mean = mean(SubjData.FFTpsd,3);
                        if strcmp(app.PSD_FilesExtension.Value,'.freq')
                            save_freq(savefilename,SubjData.FFTpsd_mean,app.SamplingRate.Value,app.Resolution.Value,FrequencyNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTpsd_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTpsd_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTpsd_mean,header.SamplingRate,[],Channels)
                        end
                    end
                    if app.PSD_WithinBands.Value
                        savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFT_avgbands', app.PSD_FilesExtension.Value]);
                        %disp(['Saving FFT frequency bands for ',char(SUBJ(subj))])
                        SubjData.FFTbands_mean = mean(SubjData.FFTbands,3);
                        if strcmp(app.PSD_FilesExtension.Value,'.freq')
                            save_freq(savefilename,SubjData.FFTbands_mean,app.SamplingRate.Value,app.Resolution.Value,BandNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTbands_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTbands_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTbands_mean,header.SamplingRate,[],Channels)
                        end
                    end
                    

                    % GPS
                    %disp(['Saving FFT GPS files for subject ',char(SUBJ(subj))])
                    if app.GPS_WithinWhole.Value
                        savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFT_GPSspectra', app.GPS_FilesExtension.Value]);
                        %disp(['Saving FFT power spectra GPS for ',char(SUBJ(subj))])
                        SubjData.FFTpsd_mean = mean(SubjData.FFTpsd,3);
                        SubjData.FFTpsd_mean_gps = compute_gfp(SubjData.FFTpsd_mean,'GPS'); % Frequency
                        GPStable = array2table(SubjData.FFTpsd_mean_gps','VariableNames',string(FrequencyNames));
                        writetable(GPStable,savefilename)
                    end
                    if app.GPS_WithinBands.Value
                        savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFT_GPSbands', app.GPS_FilesExtension.Value]);
                        %disp(['Saving FFT frequency bands GPS for ',char(SUBJ(subj))])
                        SubjData.FFTbands_mean = mean(SubjData.FFTbands,3);
                        SubjData.FFTbands_mean_gps = compute_gfp(SubjData.FFTbands_mean,'GPS'); % Band
                        GPStable = array2table(SubjData.FFTbands_mean_gps','VariableNames',string(BandNames));
                        writetable(GPStable,savefilename)
                    end
                end
                
                % FFTA
                if contains(app.FreqMethod.Value,'(FFTA)')
                    disp(['Saving FFTA files for subject ',char(SUBJ(subj))])
                    
                    % PSD
                    %disp(['Saving FFTA power spectra files for subject ',char(SUBJ(subj))])
                    if app.PSD_WithinWhole.Value
                        savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFTA_avgspectra', app.PSD_FilesExtension.Value]);
                        %disp(['Saving FFTA power spectra for ',char(SUBJ(subj))])
                        SubjData.FFTApsd_mean = mean(SubjData.FFTApsd,3);
                        if strcmp(app.PSD_FilesExtension.Value,'.freq')
                            save_freq(savefilename,SubjData.FFTApsd_mean,app.SamplingRate.Value,app.Resolution.Value,FrequencyNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTpsd_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTpsd_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTpsd_mean,header.SamplingRate,[],Channels)
                        end
                    end
                    if app.PSD_WithinBands.Value
                        savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFTA_avgbands', app.PSD_FilesExtension.Value]);
                        %disp(['Saving FFTA frequency bands for ',char(SUBJ(subj))])
                        SubjData.FFTAbands_mean = mean(SubjData.FFTAbands,3);
                        if strcmp(app.PSD_FilesExtension.Value,'.freq')
                            save_freq(savefilename,SubjData.FFTAbands_mean,app.SamplingRate.Value,app.Resolution.Value,BandNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTbands_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTbands_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTbands_mean,header.SamplingRate,[],Channels)
                        end
                    end
                    
                    % GPS
                    %disp(['Saving FFTA GPS files for subject ',char(SUBJ(subj))])
                    if app.GPS_WithinWhole.Value
                        savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFTA_GPSspectra', app.GPS_FilesExtension.Value]);
                        %disp(['Saving FFTA power spectra GPS for ',char(SUBJ(subj))])
                        SubjData.FFTApsd_mean = mean(SubjData.FFTApsd,3);
                        SubjData.FFTApsd_mean_gps = compute_gfp(SubjData.FFTApsd_mean,'GPS'); % Frequency x Window
                        GPStable = array2table(SubjData.FFTApsd_mean_gps','VariableNames',string(FrequencyNames));
                        writetable(GPStable,savefilename)
                    end
                    if app.GPS_WithinBands.Value
                        savefilename = fullfile(SubjPath,[char(SUBJ(subj)), suffix, '_FFTA_GPSbands', app.GPS_FilesExtension.Value]);
                        %disp(['Saving FFTA frequency bands GPS for ',char(SUBJ(subj))])
                        SubjData.FFTAbands_mean = mean(SubjData.FFTAbands,3);
                        SubjData.FFTAbands_mean_gps = compute_gfp(SubjData.FFTAbands_mean,'GPS'); % Frequency x Window
                        GPStable = array2table(SubjData.FFTAbands_mean_gps','VariableNames',string(BandNames));
                        writetable(GPStable,savefilename)
                    end
                end
                
                
                % Update between-subject data
                if app.PSD_BetweenWhole.Value || app.PSD_BetweenBands.Value || app.GPS_BetweenWhole.Value || app.GPS_BetweenBands.Value
                    if contains(app.FreqMethod.Value,'(FFT)')
                        Between.FFTpsd          = cat(3,Between.FFTpsd,SubjData.FFTpsd);
                        Between.FFTpsd_GPS      = cat(3,Between.FFTpsd_GPS,SubjData.FFTpsd_mean_gps);
                        Between.FFTbands        = cat(3,Between.FFTbands,SubjData.FFTbands);
                        Between.FFTbands_GPS    = cat(3,Between.FFTbands_GPS,SubjData.FFTbands_mean_gps);
                    end
                    if contains(app.FreqMethod.Value,'(FFTA)')
                        Between.FFTApsd         = cat(3,Between.FFTApsd,SubjData.FFTApsd);
                        Between.FFTApsd_GPS     = cat(3,Between.FFTApsd_GPS,SubjData.FFTApsd_mean_gps);
                        Between.FFTAbands       = cat(3,Between.FFTAbands,SubjData.FFTAbands);
                        Between.FFTAbands_GPS   = cat(3,Between.FFTAbands_GPS,SubjData.FFTAbands_mean_gps);
                    end
                end
            end
            
            
            % Saving grand-averages
            
            if app.PSD_BetweenWhole.Value || app.PSD_BetweenBands.Value || app.GPS_BetweenWhole.Value || app.GPS_BetweenBands.Value
                BetweenPath = fullfile(app.SaveFolder.Value{:},'GrandAverages');
                % Creating the folder
                if ~exist(BetweenPath, 'dir')
                    mkdir(BetweenPath);
                end
            end
            
            
            % FFT
            if contains(app.FreqMethod.Value,'(FFT)') && ...
                    (app.PSD_BetweenWhole.Value || app.PSD_BetweenBands.Value || app.GPS_BetweenWhole.Value || app.GPS_BetweenBands.Value)
                disp('Saving FFT grand-averages')
                
                % PSD
                %disp('Saving FFT grand-average files')
                if app.PSD_BetweenWhole.Value
                    savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFT_avgspectra', app.PSD_FilesExtension.Value]);
                    %disp('Saving FFT power spectra grand-average')
                    Between.FFTpsd_mean = mean(Between.FFTpsd,3);
                    if strcmp(app.PSD_FilesExtension.Value,'.freq')
                        save_freq(savefilename,Between.FFTpsd_mean,app.SamplingRate.Value,app.Resolution.Value,FrequencyNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTpsd_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTpsd_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTpsd_mean,header.SamplingRate,[],Channels)
                    end
                end
                if app.PSD_BetweenBands.Value
                    savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFT_avgbands', app.PSD_FilesExtension.Value]);
                    %disp('Saving FFT frequency bands grand-average')
                    Between.FFTbands_mean = mean(Between.FFTbands,3);
                    if strcmp(app.PSD_FilesExtension.Value,'.freq')
                        save_freq(savefilename,Between.FFTbands_mean,app.SamplingRate.Value,app.Resolution.Value,BandNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTbands_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTbands_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTbands_mean,header.SamplingRate,[],Channels)
                    end
                end
                
                % GPS
                %disp('Saving FFT GPS grand-average files')
                if app.GPS_BetweenWhole.Value
                    savefilename = fullfile(BetweenPath,['rdAvg', suffix, '_FFT_GPSspectra', app.GPS_FilesExtension.Value]);
                    %disp('Saving FFT power spectra GPS grand-average')
                    Between.FFTpsd_GPS_mean = mean(Between.FFTpsd_GPS,3);
                    GPStable = array2table(Between.FFTpsd_GPS_mean','VariableNames',string(FrequencyNames));
                    writetable(GPStable,savefilename)
                end
                if app.GPS_BetweenBands.Value
                    savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFT_GPSbands', app.GPS_FilesExtension.Value]);
                    %disp('Saving FFT frequency bands GPS grand-average')
                    Between.FFTbands_GPS_mean = mean(Between.FFTbands_GPS,3);
                    GPStable = array2table(Between.FFTbands_GPS_mean','VariableNames',string(BandNames));
                    writetable(GPStable,savefilename)
                end
            end
            
            % FFTA
            if contains(app.FreqMethod.Value,'(FFTA)') && ...
                    (app.PSD_BetweenWhole.Value || app.PSD_BetweenBands.Value || app.GPS_BetweenWhole.Value || app.GPS_BetweenBands.Value)
                disp('Saving FFTA grand-averages')
                
                % PSD
                %disp('Saving FFTA grand-average files')
                if app.PSD_BetweenWhole.Value
                    savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFTA_avgspectra', app.PSD_FilesExtension.Value]);
                    %disp('Saving FFTA power spectra grand-average')
                    Between.FFTApsd_mean = mean(Between.FFTApsd,3);
                    if strcmp(app.PSD_FilesExtension.Value,'.freq')
                        save_freq(savefilename,Between.FFTApsd_mean,app.SamplingRate.Value,app.Resolution.Value,FrequencyNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTpsd_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTpsd_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTpsd_mean,header.SamplingRate,[],Channels)
                    end
                end
                if app.PSD_BetweenBands.Value
                    savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFTA_avgbands', app.PSD_FilesExtension.Value]);
                    %disp('Saving FFTA frequency bands grand-average')
                    Between.FFTAbands_mean = mean(Between.FFATbands,3);
                    if strcmp(app.PSD_FilesExtension.Value,'.freq')
                        save_freq(savefilename,Between.FFTAbands_mean,app.SamplingRate.Value,app.Resolution.Value,BandNames,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.ep') || strcmp(app.PSD_FilesExtension.Value,'.eph')
%                                     save_eph(savefilename,FFTbands_mean,header.SamplingRate)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.sef')
%                                     save_sef(savefilename,FFTbands_mean,header.SamplingRate,Channels)
%                                 elseif strcmp(app.PSD_FilesExtension.Value,'.set')
%                                     save_eeglab(savefilename,FFTbands_mean,header.SamplingRate,[],Channels)
                    end
                end
                
                % GPS
                %disp('Saving FFTA GPS grand-average files')
                if app.GPS_BetweenWhole.Value
                    savefilename = fullfile(BetweenPath,['rdAvg', suffix, '_FFTA_GPSspectra', app.GPS_FilesExtension.Value]);
                    %disp('Saving FFTA power spectra GPS grand-average')
                    Between.FFTApsd_GPS_mean = mean(Between.FFTApsd_GPS,3);
                    GPStable = array2table(Between.FFTApsd_GPS_mean','VariableNames',string(FrequencyNames));
                    writetable(GPStable,savefilename)
                end
                if app.GPS_BetweenBands.Value
                    savefilename = fullfile(BetweenPath,['GrdAvg', suffix, '_FFTA_GPSbands', app.GPS_FilesExtension.Value]);
                    %disp('Saving FFTA frequency bands GPS grand-average')
                    Between.FFTAbands_GPS_mean = mean(Between.FFTAbands_GPS,3);
                    GPStable = array2table(Between.FFTAbands_GPS_mean','VariableNames',string(BandNames));
                    writetable(GPStable,savefilename)
                end
            end
            
            
            % UPDATE INFORMATION BASED ON THE PROCESSED DATA
            
            % Data table in the Main Window
%             app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
%             app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
%             app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
%             disp('Data table in the Main Window updated with the processed files')
%             cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(data,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT (Input file name  /  N. time-frames  /  N. windows  /  Discarded TFs  /  N. channels  /  Folder)');
            % fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Triggers found  /  CHANCHECK  /  Folder)');
            Files = [app.DataTable.File';num2cell(PeriodLength_ALL)';num2cell(nWindows_ALL)';num2cell(DiscardedTF_ALL)';...
                num2cell(app.DataTable.(4))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
%             fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','SUMMARY (File  /  Subject  /  Trigger  /  N. Triggers  /  Accepted epochs (n/%)  /  EEG rejected (n/%)');
%             Slash = repmat("/",1,height(SummaryTable));
%             Percent = repmat("%",1,height(SummaryTable));
%             Summary = [SummaryTable.File';SummaryTable.Subject';SummaryTable.Trigger';num2cell(SummaryTable.TotalTriggers)';...
%                 num2cell(SummaryTable.nTOTALaccepted)';Slash;num2cell(SummaryTable.pcTOTALaccepted)';Percent];
%             fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s\t\t%s %s %s%s\r\n',Summary{:});
            
            
%                         SummaryTable.nAUXrejected(summaryrow)       = nAUXrej;
%                         SummaryTable.nEEGrejected(summaryrow)       = nEEGrej;
%                         SummaryTable.nEEG_AUXrejected(summaryrow)   = nEEGAUXrej;
%                         SummaryTable.nTOTALrejected(summaryrow)     = nTOTrej;
%                         SummaryTable.pcAUXrejected(summaryrow)      = AUXpercent;
%                         SummaryTable.pcEEGrejected(summaryrow)      = EEGpercent;
%                         SummaryTable.pcEEG_AUXrejected(summaryrow)  = EEGAUXpercent;
%                         SummaryTable.pcTOTALrejected(summaryrow)    = TOTpercent;
                        
                        
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Epoching completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Enable the Filtering+ button in main app
            app.EEGpalCS_Main.FrequencyingButton.Enable = 'on';
            
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            % Update parameters
            app.EEGpalCS_Main.Study_Parameters  = app.Study_Parameters;
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FrequencyingButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: Frequencying
        function FrequencyingCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FrequencyingButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: Onset_ms
        function Onset_msValueChanged(app, event)
            app.Onset_tf.Value  = ms2tf(app,app.Onset_ms.Value,app.SamplingRate.Value);
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
        end

        % Value changed function: Onset_tf
        function Onset_tfValueChanged(app, event)
            app.Onset_ms.Value  = tf2ms(app,app.Onset_tf.Value,app.SamplingRate.Value);
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
        end

        % Value changed function: Offset_ms
        function Offset_msValueChanged(app, event)
            app.Offset_tf.Value = ms2tf(app,app.Offset_ms.Value,app.SamplingRate.Value) - 1; % -1 because the onset TF is included
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
        end

        % Value changed function: Offset_tf
        function Offset_tfValueChanged(app, event)
            app.Offset_ms.Value = tf2ms(app,app.Offset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
            updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
        end

        % Value changed function: Size_ms
        function Size_msValueChanged(app, event)
            app.Size_tf.Value   = ms2tf(app,app.Size_ms.Value,app.SamplingRate.Value);
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
            
            app.Resolution.Value    = app.SamplingRate.Value / app.Size_tf.Value;
        end

        % Value changed function: Size_tf
        function Size_tfValueChanged(app, event)
            app.Size_ms.Value   = tf2ms(app,app.Size_tf.Value,app.SamplingRate.Value);
            
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
            
            app.Resolution.Value    = app.SamplingRate.Value / app.Size_tf.Value;
        end

        % Value changed function: Overlap
        function OverlapValueChanged(app, event)
            PeriodLength        = app.Duration_tf.Value;
            WindowSize          = app.Size_tf.Value;
            Steps               = WindowSize - app.Overlap.Value; % Windows step
            Start               = 0:Steps:WindowSize-1; % Starting points in the period by steps
            app.nWindows.Value  = sum(arrayfun(@(x) length(x:WindowSize:PeriodLength-WindowSize),Start));
        end

        % Value changed function: FrequencyBands
        function FrequencyBandsValueChanged(app, event)
            if ~isempty(app.FrequencyBands.Value)
                app.PSD_FilesBands.Enable   = 'on';
                app.GPS_FilesBands.Enable   = 'on';
                app.PSD_WithinBands.Enable  = 'on';
                app.GPS_WithinBands.Enable  = 'on';
                app.PSD_BetweenBands.Enable = 'on';
                app.GPS_BetweenBands.Enable = 'on';
            else
                app.PSD_FilesBands.Enable   = 'off';
                app.GPS_FilesBands.Enable   = 'off';
                app.PSD_WithinBands.Enable  = 'off';
                app.GPS_WithinBands.Enable  = 'off';
                app.PSD_BetweenBands.Enable = 'off';
                app.GPS_BetweenBands.Enable = 'off';
            end
        end

        % Value changed function: EndOfFile
        function EndOfFileValueChanged(app, event)
            if app.EndOfFile.Value
                app.Offset_tf.Enable    = 'off';
                app.Offset_ms.Enable    = 'off';
                app.Offset_tf.Value     = app.DataTable.(5)(1) - 1; % -1 because first time-frame is 0
                app.Offset_ms.Value     = tf2ms(app,app.Offset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                updateTotal(app,app.Onset_ms,app.Onset_tf,app.Offset_ms,app.Offset_tf,app.Duration_ms,app.Duration_tf)
            else
                app.Offset_tf.Enable    = 'on';
                app.Offset_ms.Enable    = 'on';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Frequencying = uifigure('Visible', 'off');
            app.Frequencying.Name = 'EEGpalCS Frequencying';
            app.Frequencying.CloseRequestFcn = createCallbackFcn(app, @FrequencyingCloseRequest, true);
            app.FREQUENCYINGLabel = uilabel(app.Frequencying);
            app.FREQUENCYINGLabel.Text = 'FREQUENCYING';
            app.GENERALOPTIONSLabel = uilabel(app.Frequencying);
            app.GENERALOPTIONSLabel.Text = 'GENERAL OPTIONS';
            app.SamplingrateEditFieldLabel = uilabel(app.Frequencying);
            app.SamplingrateEditFieldLabel.Text = 'Sampling rate:';
            app.SamplingRate = uieditfield(app.Frequencying, 'numeric');
            app.SamplingRate.Editable = 'off';
            app.SamplingRate.Value = 1000;
            app.AnalysisPeriodLabel = uilabel(app.Frequencying);
            app.AnalysisPeriodLabel.Text = 'Analysis period';
            app.msLabel = uilabel(app.Frequencying);
            app.msLabel.Text = '[ms]';
            app.tfLabel = uilabel(app.Frequencying);
            app.tfLabel.Text = '[tf]';
            app.OnsetLabel = uilabel(app.Frequencying);
            app.OnsetLabel.Text = 'Onset:';
            app.Onset_ms = uieditfield(app.Frequencying, 'numeric');
            app.Onset_ms.Limits = [0 Inf];
            app.Onset_ms.ValueDisplayFormat = '%.0f';
            app.Onset_ms.ValueChangedFcn = createCallbackFcn(app, @Onset_msValueChanged, true);
            app.Onset_ms.Tooltip = {''};
            app.Onset_tf = uieditfield(app.Frequencying, 'numeric');
            app.Onset_tf.Limits = [0 Inf];
            app.Onset_tf.ValueDisplayFormat = '%.0f';
            app.Onset_tf.ValueChangedFcn = createCallbackFcn(app, @Onset_tfValueChanged, true);
            app.Onset_tf.Tooltip = {''};
            app.Onset_tf.Value = 1;
            app.OffsetLabel = uilabel(app.Frequencying);
            app.OffsetLabel.Text = 'Offset:';
            app.Offset_ms = uieditfield(app.Frequencying, 'numeric');
            app.Offset_ms.Limits = [0 Inf];
            app.Offset_ms.ValueDisplayFormat = '%.0f';
            app.Offset_ms.ValueChangedFcn = createCallbackFcn(app, @Offset_msValueChanged, true);
            app.Offset_ms.Tooltip = {''};
            app.Offset_tf = uieditfield(app.Frequencying, 'numeric');
            app.Offset_tf.Limits = [0 Inf];
            app.Offset_tf.ValueDisplayFormat = '%.0f';
            app.Offset_tf.ValueChangedFcn = createCallbackFcn(app, @Offset_tfValueChanged, true);
            app.Offset_tf.Tooltip = {''};
            app.Offset_tf.Value = 1;
            app.DurationLabel = uilabel(app.Frequencying);
            app.DurationLabel.Text = 'Duration:';
            app.Duration_ms = uieditfield(app.Frequencying, 'numeric');
            app.Duration_ms.ValueDisplayFormat = '%.0f';
            app.Duration_ms.Editable = 'off';
            app.Duration_ms.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.Duration_tf = uieditfield(app.Frequencying, 'numeric');
            app.Duration_tf.ValueDisplayFormat = '%.0f';
            app.Duration_tf.Editable = 'off';
            app.Duration_tf.Tooltip = {'Negative values indicate that the offset precedes the onset.'; 'It will be flagged in red and will disable the Run button.'};
            app.Duration_tf.Value = 1;
            app.WindowssizeLabel = uilabel(app.Frequencying);
            app.WindowssizeLabel.Text = 'Windows size:';
            app.Size_ms = uieditfield(app.Frequencying, 'numeric');
            app.Size_ms.Limits = [0 Inf];
            app.Size_ms.ValueDisplayFormat = '%.0f';
            app.Size_ms.ValueChangedFcn = createCallbackFcn(app, @Size_msValueChanged, true);
            app.Size_ms.Tooltip = {''};
            app.Size_ms.Value = 2000;
            app.Size_tf = uieditfield(app.Frequencying, 'numeric');
            app.Size_tf.Limits = [0 Inf];
            app.Size_tf.ValueDisplayFormat = '%.0f';
            app.Size_tf.ValueChangedFcn = createCallbackFcn(app, @Size_tfValueChanged, true);
            app.Size_tf.Tooltip = {''};
            app.Size_tf.Value = 2000;
            app.OverlapLabel = uilabel(app.Frequencying);
            app.OverlapLabel.Text = 'Overlap:';
            app.Overlap = uieditfield(app.Frequencying, 'numeric');
            app.Overlap.Limits = [0 Inf];
            app.Overlap.ValueDisplayFormat = '%.0f';
            app.Overlap.ValueChangedFcn = createCallbackFcn(app, @OverlapValueChanged, true);
            app.Overlap.Tooltip = {''};
            app.tfOverlap = uilabel(app.Frequencying);
            app.tfOverlap.Text = 'tf';
            app.nWindowsLabel = uilabel(app.Frequencying);
            app.nWindowsLabel.Text = 'nWindows:';
            app.nWindows = uieditfield(app.Frequencying, 'numeric');
            app.nWindows.ValueDisplayFormat = '%.0f';
            app.nWindows.Editable = 'off';
            app.nWindows.Tooltip = {''};
            app.FrequencyresolutionLabel = uilabel(app.Frequencying);
            app.FrequencyresolutionLabel.Text = 'Frequency resolution:';
            app.Resolution = uieditfield(app.Frequencying, 'numeric');
            app.Resolution.Editable = 'off';
            app.Resolution.Tooltip = {''};
            app.HzLabel = uilabel(app.Frequencying);
            app.HzLabel.Text = 'Hz';
            app.RangeLabel = uilabel(app.Frequencying);
            app.RangeLabel.Tooltip = {''};
            app.RangeLabel.Text = 'Range:';
            app.LowerFreq = uieditfield(app.Frequencying, 'numeric');
            app.LowerFreq.Limits = [0 Inf];
            app.LowerFreq.Tooltip = {''};
            app.toLabel = uilabel(app.Frequencying);
            app.toLabel.Tooltip = {''};
            app.toLabel.Text = 'to';
            app.UpperFreq = uieditfield(app.Frequencying, 'numeric');
            app.UpperFreq.Limits = [0 Inf];
            app.UpperFreq.Tooltip = {''};
            app.HzLabel_2 = uilabel(app.Frequencying);
            app.HzLabel_2.Tooltip = {''};
            app.HzLabel_2.Text = 'Hz';
            app.MaxLabel = uilabel(app.Frequencying);
            app.MaxLabel.Text = 'Max:';
            app.MaxFreq = uieditfield(app.Frequencying, 'numeric');
            app.MaxFreq.Editable = 'off';
            app.MaxFreq.Tooltip = {''};
            app.HzLabel_3 = uilabel(app.Frequencying);
            app.HzLabel_3.Text = 'Hz';
            app.Frequencybandseg4675102030Label = uilabel(app.Frequencying);
            app.Frequencybandseg4675102030Label.Text = 'Frequency bands (e.g., 4-6; 7.5:10 20-30):';
            app.FrequencyBands = uieditfield(app.Frequencying, 'text');
            app.FrequencyBands.ValueChangedFcn = createCallbackFcn(app, @FrequencyBandsValueChanged, true);
            app.FrequencyBands.Tooltip = {'define range using hyphen(-) or semi-colon(:)'; 'separate bands using '};
            app.ChannelsclusterLabel = uilabel(app.Frequencying);
            app.ChannelsclusterLabel.Text = 'Channels cluster:';
            app.ChannelsCluster = uidropdown(app.Frequencying);
            app.ChannelsCluster.Items = {'all', 'Option 2', 'Option 3', 'Option 4'};
            app.ChannelsCluster.Value = 'all';
            app.OUTPUTOPTIONSLabel = uilabel(app.Frequencying);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofPSDoutputfilesLabel = uilabel(app.Frequencying);
            app.ExtensionofPSDoutputfilesLabel.Text = 'Extension of PSD output files:';
            app.PSD_FilesExtension = uidropdown(app.Frequencying);
            app.PSD_FilesExtension.Items = {'.freq', '.ep', '.eph', '.sef', '.set'};
            app.PSD_FilesExtension.Enable = 'off';
            app.PSD_FilesExtension.Tooltip = {'Currently, you can only save as .freq'; 'Use Cartool if you want then to convert into another format.'};
            app.PSD_FilesExtension.Value = '.freq';
            app.SelectUpperFolderLabel = uilabel(app.Frequencying);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.Frequencying, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.Frequencying);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.Frequencying);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.Frequencying);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.Frequencying, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'frequency';
            app.InputParametersFile = uilabel(app.Frequencying);
            app.InputParametersFile.Text = 'Parameters';
            app.OpenButton = uibutton(app.Frequencying, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveButton = uibutton(app.Frequencying, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Tooltip = {'Update an existing file.'; 'Will erase preexisting Interpolation options.'};
            app.SaveButton.Text = 'Save';
            app.SaveAsButton = uibutton(app.Frequencying, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButtonPushed, true);
            app.SaveAsButton.Tooltip = {'Create a new file.'; 'Will erase data if file already exists.'};
            app.SaveAsButton.Text = 'Save as';
            app.ParameterfileEditFieldLabel = uilabel(app.Frequencying);
            app.ParameterfileEditFieldLabel.Text = 'Parameter file:';
            app.ParameterFilePath = uieditfield(app.Frequencying, 'text');
            app.ParameterFilePath.Editable = 'off';
            app.APPLYLabel = uilabel(app.Frequencying);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.Frequencying, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.DoneButton = uibutton(app.Frequencying, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tooltip = {'Return to the main window'};
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.Frequencying, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.FREQUENCYCOMPUTATIONLabel = uilabel(app.Frequencying);
            app.FREQUENCYCOMPUTATIONLabel.Text = 'FREQUENCY COMPUTATION';
            app.Method = uilabel(app.Frequencying);
            app.Method.Text = 'Method';
            app.AVERAGING = uilabel(app.Frequencying);
            app.AVERAGING.Tooltip = {'Global Power Spectra'};
            app.AVERAGING.Text = 'SAVING';
            app.ExtensionofGPSoutputfilesLabel = uilabel(app.Frequencying);
            app.ExtensionofGPSoutputfilesLabel.Text = 'Extension of GPS output files:';
            app.GPS_FilesExtension = uidropdown(app.Frequencying);
            app.GPS_FilesExtension.Items = {'.ep', '.csv', '.xlsx'};
            app.GPS_FilesExtension.Value = '.xlsx';
            app.WholerangepowerspectraLabel_2 = uilabel(app.Frequencying);
            app.WholerangepowerspectraLabel_2.Text = 'Whole range power spectra:';
            app.PSD_FilesBands = uicheckbox(app.Frequencying);
            app.PSD_FilesBands.Enable = 'off';
            app.PSD_FilesBands.Text = '';
            app.FrequencybandsifdefinedLabel_3 = uilabel(app.Frequencying);
            app.FrequencybandsifdefinedLabel_3.Text = 'Frequency bands (if defined):';
            app.PSD_FilesWhole = uicheckbox(app.Frequencying);
            app.PSD_FilesWhole.Text = '';
            app.PerfileLabel = uilabel(app.Frequencying);
            app.PerfileLabel.Text = 'Per file';
            app.PSDLabel = uilabel(app.Frequencying);
            app.PSDLabel.Tooltip = {'Power Spectral Density'};
            app.PSDLabel.Text = 'PSD';
            app.GPSLabel = uilabel(app.Frequencying);
            app.GPSLabel.Tooltip = {'Global Power Spectra'};
            app.GPSLabel.Text = 'GPS';
            app.AveragedtimewindowsLabel = uilabel(app.Frequencying);
            app.AveragedtimewindowsLabel.Text = 'Averaged time windows:';
            app.PSD_AvgWindows = uicheckbox(app.Frequencying);
            app.PSD_AvgWindows.Text = '';
            app.GPS_AvgWindows = uicheckbox(app.Frequencying);
            app.GPS_AvgWindows.Text = '';
            app.SeparatetimewindowsLabel = uilabel(app.Frequencying);
            app.SeparatetimewindowsLabel.Text = 'Separate time windows:';
            app.PSD_SepWindows = uicheckbox(app.Frequencying);
            app.PSD_SepWindows.Text = '';
            app.GPS_SepWindows = uicheckbox(app.Frequencying);
            app.GPS_SepWindows.Text = '';
            app.WithinSubjectAveraging = uilabel(app.Frequencying);
            app.WithinSubjectAveraging.Text = 'Within-subject averaging';
            app.WholerangepowerspectraLabel_3 = uilabel(app.Frequencying);
            app.WholerangepowerspectraLabel_3.Text = 'Whole range power spectra:';
            app.PSD_WithinWhole = uicheckbox(app.Frequencying);
            app.PSD_WithinWhole.Text = '';
            app.GPS_WithinWhole = uicheckbox(app.Frequencying);
            app.GPS_WithinWhole.Text = '';
            app.FrequencybandsifdefinedLabel = uilabel(app.Frequencying);
            app.FrequencybandsifdefinedLabel.Text = 'Frequency bands (if defined):';
            app.PSD_WithinBands = uicheckbox(app.Frequencying);
            app.PSD_WithinBands.Enable = 'off';
            app.PSD_WithinBands.Text = '';
            app.GPS_WithinBands = uicheckbox(app.Frequencying);
            app.GPS_WithinBands.Enable = 'off';
            app.GPS_WithinBands.Text = '';
            app.BetweenSubjectAveraging = uilabel(app.Frequencying);
            app.BetweenSubjectAveraging.Text = 'Between-subject averaging';
            app.WholerangepowerspectraLabel = uilabel(app.Frequencying);
            app.WholerangepowerspectraLabel.Text = 'Whole range power spectra:';
            app.PSD_BetweenWhole = uicheckbox(app.Frequencying);
            app.PSD_BetweenWhole.Text = '';
            app.GPS_BetweenWhole = uicheckbox(app.Frequencying);
            app.GPS_BetweenWhole.Text = '';
            app.FrequencybandsifdefinedLabel_2 = uilabel(app.Frequencying);
            app.FrequencybandsifdefinedLabel_2.Text = 'Frequency bands (if defined):';
            app.PSD_BetweenBands = uicheckbox(app.Frequencying);
            app.PSD_BetweenBands.Enable = 'off';
            app.PSD_BetweenBands.Text = '';
            app.GPS_BetweenBands = uicheckbox(app.Frequencying);
            app.GPS_BetweenBands.Enable = 'off';
            app.GPS_BetweenBands.Text = '';
            app.INDIVIDUALPEAKFREQUENCYPanel = uipanel(app.Frequencying);
            app.INDIVIDUALPEAKFREQUENCYPanel.Title = 'INDIVIDUAL PEAK FREQUENCY';
            app.iPF_FrequencyComputation = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_FrequencyComputation.Text = 'Frequency computation';
            app.MethodLabel = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.MethodLabel.Text = 'Method:';
            app.iPF_Method = uidropdown(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_Method.Items = {'FFT', 'pWelch'};
            app.iPF_Method.Value = 'FFT';
            app.WindowingfunctionLabel_2 = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.WindowingfunctionLabel_2.Text = 'Windowing function:';
            app.iPF_Windowing = uidropdown(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_Windowing.Items = {'None', 'Hamming', 'Hanning'};
            app.iPF_Windowing.Value = 'None';
            app.SignaltoanalyseLabel = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.SignaltoanalyseLabel.Text = 'Signal to analyse:';
            app.iPF_Signal = uidropdown(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_Signal.Items = {'GPS', '[No other option yet]'};
            app.iPF_Signal.Value = 'GPS';
            app.SGF_AllOptions = uibutton(app.INDIVIDUALPEAKFREQUENCYPanel, 'push');
            app.SGF_AllOptions.Enable = 'off';
            app.SGF_AllOptions.Text = 'All iFP options';
            app.SGF_RuniFP = uibutton(app.INDIVIDUALPEAKFREQUENCYPanel, 'push');
            app.SGF_RuniFP.Enable = 'off';
            app.SGF_RuniFP.Text = 'Run iFP';
            app.iPF_Table = uitable(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.iPF_Table.ColumnName = {'Subject/File'; 'Freq. range'; 'iAF'};
            app.iPF_Table.RowName = {};
            app.iPF_Table.ColumnSortable = true;
            app.iPF_Table.ColumnEditable = [false false true];
            app.Corcoranetal2018DOI101111psyp13064Label = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.Corcoranetal2018DOI101111psyp13064Label.Text = '(Corcoran et al. 2018; DOI: 10.1111/psyp.13064)';
            app.SGF_Label = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.SGF_Label.Text = 'Savitzky-Golay filter (smoothing)';
            app.SGF_CheckBox = uicheckbox(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.SGF_CheckBox.Text = '';
            app.FramewidthLabel = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.FramewidthLabel.Text = 'Frame width:';
            app.SGF_FrameWidth = uieditfield(app.INDIVIDUALPEAKFREQUENCYPanel, 'numeric');
            app.SGF_FrameWidth.Limits = [0 Inf];
            app.SGF_FrameWidth.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.SGF_FrameWidth.Value = 7;
            app.PolynomialorderLabel = uilabel(app.INDIVIDUALPEAKFREQUENCYPanel);
            app.PolynomialorderLabel.Text = 'Polynomial order:';
            app.SGF_PolyOrder = uieditfield(app.INDIVIDUALPEAKFREQUENCYPanel, 'numeric');
            app.SGF_PolyOrder.Limits = [0 Inf];
            app.SGF_PolyOrder.Tooltip = {'Enter negative value for offset preceding the trigger.'};
            app.SGF_PolyOrder.Value = 5;
            app.GPS_FilesWhole = uicheckbox(app.Frequencying);
            app.GPS_FilesWhole.Text = '';
            app.GPS_FilesBands = uicheckbox(app.Frequencying);
            app.GPS_FilesBands.Enable = 'off';
            app.GPS_FilesBands.Text = '';
            app.InconsistentSR = uilabel(app.Frequencying);
            app.InconsistentSR.Visible = 'off';
            app.InconsistentSR.Tooltip = {'Not all files have the same sampling rate.'; 'Information below are based on the sampling rate (and other info) from the first file of the list.'};
            app.InconsistentSR.Text = 'Inconsistent!';
            app.InconsistentNumChan = uilabel(app.Frequencying);
            app.InconsistentNumChan.Visible = 'off';
            app.InconsistentNumChan.Tooltip = {'Not all files have the same number of channels.'; 'GPS options are disabled'};
            app.InconsistentNumChan.Text = 'Inconsistent number of channels!';
            app.EndOfFile = uibutton(app.Frequencying, 'state');
            app.EndOfFile.ValueChangedFcn = createCallbackFcn(app, @EndOfFileValueChanged, true);
            app.EndOfFile.Text = 'End of file';
            app.EndOfFile.Value = true;
            app.FreqMethodInstruction = uilabel(app.Frequencying);
            app.FreqMethodInstruction.Text = 'Ctrl+click for multiple selection';
            app.FreqMethod = uilistbox(app.Frequencying);
            app.FreqMethod.Items = {'Fast Fourier Transform (FFT)', 'FFT Approximation (FFTA)'};
            app.FreqMethod.Multiselect = 'on';
            app.FreqMethod.Value = {'Fast Fourier Transform (FFT)'};
            app.NormalizationGroup = uibuttongroup(app.Frequencying);
            app.NormalizationGroup.Title = 'Removing 1/f noise method';
            app.NORM_None = uitogglebutton(app.NormalizationGroup);
            app.NORM_None.Text = 'None';
            app.NROM_Mean = uitogglebutton(app.NormalizationGroup);
            app.NROM_Mean.Tooltip = {'For each frequency bin, computes de average power across all period windows.'; 'Normalization is achieved by dividing this average from the individual values.'};
            app.NROM_Mean.Text = 'Remove mean as in Cartool (almost)';
            app.NROM_Mean.Value = true;
            app.NORM_LogTransform = uitogglebutton(app.NormalizationGroup);
            app.NORM_LogTransform.Text = 'Log transf. (NOT RECOMMENDED)';
            app.NORM_Neighbourg = uitogglebutton(app.NormalizationGroup);
            app.NORM_Neighbourg.Enable = 'off';
            app.NORM_Neighbourg.Text = 'Neighbourg averaging';
            app.nNeighEditFieldLabel = uilabel(app.NormalizationGroup);
            app.nNeighEditFieldLabel.Enable = 'off';
            app.nNeighEditFieldLabel.Text = 'nNeigh:';
            app.NORM_nNeigh = uieditfield(app.NormalizationGroup, 'numeric');
            app.NORM_nNeigh.Enable = 'off';
            app.NORM_nNeigh.Value = 4;
            app.ChannelDropDownLabel = uilabel(app.NormalizationGroup);
            app.ChannelDropDownLabel.Enable = 'off';
            app.ChannelDropDownLabel.Text = 'Channel:';
            app.NORM_NeighChan = uidropdown(app.NormalizationGroup);
            app.NORM_NeighChan.Items = {'GPS', 'Own'};
            app.NORM_NeighChan.Enable = 'off';
            app.NORM_NeighChan.Value = 'GPS';
            app.WindowingGroup = uibuttongroup(app.Frequencying);
            app.WindowingGroup.Title = 'Windowing function';
            app.WIND_None = uitogglebutton(app.WindowingGroup);
            app.WIND_None.Text = 'None';
            app.WIND_None.Value = true;
            app.WIND_Hamming = uitogglebutton(app.WindowingGroup);
            app.WIND_Hamming.Text = 'Hamming';
            app.WIND_Hanning = uitogglebutton(app.WindowingGroup);
            app.WIND_Hanning.Text = 'Hanning';
            % Show the figure after all components are created
            app.Frequencying.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Frequencying_bkup(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Frequencying)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Frequencying)
        end
    end
end