classdef EEGpalCS_mod_GEDAI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_GEDAI                 matlab.ui.Figure
        Image                          matlab.ui.control.Image
        EpochssizewavecyclesLabel_2    matlab.ui.control.Label
        ExtensionofoutputfilesLabel_3  matlab.ui.control.Label
        HelpButton                     matlab.ui.control.Button
        Denoising                      matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel_2  matlab.ui.control.Label
        LeadfieldMatrix                matlab.ui.control.DropDown
        EpochsSizeEditField            matlab.ui.control.NumericEditField
        EpochssizewavecyclesLabel      matlab.ui.control.Label
        Lowpass                        matlab.ui.control.NumericEditField
        OptionalparametersLabel        matlab.ui.control.Label
        SlindingWindowLabel            matlab.ui.control.Label
        SlidingWindowCheckbox          matlab.ui.control.CheckBox
        SlidingWindowDuration          matlab.ui.control.NumericEditField
        SlidingWindowDurationLabel     matlab.ui.control.Label
        RejectbadepochsLabel           matlab.ui.control.Label
        RejectbadepochsCheckbox        matlab.ui.control.CheckBox
        ENOVAthreshold                 matlab.ui.control.NumericEditField
        ENOVAThresholdLabel            matlab.ui.control.Label
        ENOVAThresholdChannelsLabel    matlab.ui.control.Label
        ENOVAthresholdChannels         matlab.ui.control.NumericEditField
        RejectbadchannelsLabel         matlab.ui.control.Label
        RejectchannelCheckbox          matlab.ui.control.CheckBox
        ParallelprocessingLabel        matlab.ui.control.Label
        ParallelprocessingCheckBox     matlab.ui.control.CheckBox
        ArtifactvisualizationLabel     matlab.ui.control.Label
        ArtifactvisualizationCheckBox  matlab.ui.control.CheckBox
        CancelButton                   matlab.ui.control.Button
        SaveinmemoryButton             matlab.ui.control.Button
        RunButton                      matlab.ui.control.Button
        APPLYLabel                     matlab.ui.control.Label
        SuffixString                   matlab.ui.control.EditField
        SuffixstringLabel              matlab.ui.control.Label
        SaveFolder                     matlab.ui.control.TextArea
        SavingfolderLabel              matlab.ui.control.Label
        SelectFolderButton             matlab.ui.control.Button
        SelectUpperFolderLabel         matlab.ui.control.Label
        FilesExtension                 matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel    matlab.ui.control.Label
        OUTPUTOPTIONSLabel             matlab.ui.control.Label
        GeneralizedEigenvalueDeArtifactingInstrumentGEDAILabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object        
        DataTable        
        SettingTable
        batchHasCrashed 
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function applySessionParam(app)
            
            % OUTPUT OPTION
            app.FilesExtension.Value        = app.SessionParameters.GEDAI.FilesExtention;
            app.SaveFolder.Value            = app.SessionParameters.GEDAI.SaveFolder;
            app.SuffixString.Value          = app.SessionParameters.GEDAI.SuffixString;
            
            
            % GEDAI            
            app.Denoising.Value                     = app.SessionParameters.GEDAI.Denoising;
            app.LeadfieldMatrix.Value               = app.SessionParameters.GEDAI.LeadfieldMatrix;
            app.EpochsSizeEditField.Value           = app.SessionParameters.GEDAI.EpochsSize;
            app.Lowpass.Value                       = app.SessionParameters.GEDAI.Lowpass;
            
            %option GEDAI
            app.SlidingWindowCheckbox.Value         = app.SessionParameters.GEDAI.SlidingWindow;
            SlidingWindowCheckboxValueChanged(app);
            app.SlidingWindowDuration.Value         = app.SessionParameters.GEDAI.SlidingWindowDuration;
            app.RejectbadepochsCheckbox.Value       = app.SessionParameters.GEDAI.Rejectbadepochs;
            RejectbadepochsCheckboxValueChanged(app);
            app.ENOVAthreshold.Value                = app.SessionParameters.GEDAI.ENOVAthreshold;
            app.RejectchannelCheckbox.Value         = app.SessionParameters.GEDAI.RejectbadChannels;
            RejectchannelCheckboxValueChanged(app);
            app.ENOVAthresholdChannels.Value        = app.SessionParameters.GEDAI.ENOVAthresholdChannels;            
            app.ParallelprocessingCheckBox.Value    = app.SessionParameters.GEDAI.Parallelprocessing;
            app.ArtifactvisualizationCheckBox.Value = app.SessionParameters.GEDAI.Artifactvisualization;
            
           
        end
        
        function SessionParameters   = updateSessionParam(app,SessionParameters)
            
            % OUTPUT OPTION
            SessionParameters.GEDAI.FilesExtention    = app.FilesExtension.Value;
            SessionParameters.GEDAI.SaveFolder        = app.SaveFolder.Value;
            SessionParameters.GEDAI.SuffixString      = app.SuffixString.Value;
            
            
            % GEDAI            
            SessionParameters.GEDAI.Denoising               = app.Denoising.Value;
            SessionParameters.GEDAI.LeadfieldMatrix         = app.LeadfieldMatrix.Value;
            SessionParameters.GEDAI.EpochsSize              = app.EpochsSizeEditField.Value;
            SessionParameters.GEDAI.Lowpass                 = app.Lowpass.Value;
            
            % GEDAI options
            SessionParameters.GEDAI.SlidingWindow           = app.SlidingWindowCheckbox.Value;
            SessionParameters.GEDAI.SlidingWindowDuration   = app.SlidingWindowDuration.Value;
            SessionParameters.GEDAI.Rejectbadepochs         = app.RejectbadepochsCheckbox.Value;
            SessionParameters.GEDAI.ENOVAthreshold          = app.ENOVAthreshold.Value;
            SessionParameters.GEDAI.RejectbadChannels       = app.RejectchannelCheckbox.Value;
            SessionParameters.GEDAI.ENOVAthresholdChannels  = app.ENOVAthresholdChannels.Value;
            SessionParameters.GEDAI.Parallelprocessing      = app.ParallelprocessingCheckBox.Value;
            SessionParameters.GEDAI.Artifactvisualization   = app.ArtifactvisualizationCheckBox.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values          
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            try
                app.SettingTable            = app.SessionParameters.Electrodes.SettingTable;
            catch
            end          
            
            
            % Session parameters override saved parameters
            try
                applySessionParam(app);
            catch
                
            end            
            
            %check if the parallel processing toolbox is available
            hasPCT = license('test','Distrib_Computing_Toolbox');

            if ~hasPCT %in case of no parrallel toolbox available
                app.ParallelprocessingCheckBox.Value = false;
                app.ParallelprocessingCheckBox.Enable = "off";
                app.ParallelprocessingLabel.Enable = "off";           
            end
            
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end

            %Execute the batch System
            app.batchHasCrashed = false;
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                RunButtonPushed(app);
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_GEDAI); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if app.SessionParameters.BatchProcessing.BatchExecution == 0 %normal mode
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                        'Please make sure to select the output folder where to put the processed files.'),...
                        'No output folder selected','warn');
                    return
                end
                 
                
                Run = questdlg(sprintf('%s\n%s\n\n%s%s\n%s%d\n\n%s\n\n%s',...
                    'On y va !',...
                    'I will use the GEDAI toolbox to correct artefact in your data.',...                    
                    'A long list may take time to proceed (though not that much, let''s be honnest). In order to reduce time, you can select subsets of files in the main window.',...
                    'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
                
                if strcmpi(Run,'Cancel')
                    return
                end
            else %Batch mode
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s\n%s','No output folder selected.', ...
                    'The batch process has been interrupted because there is no output folder specified in the GEDAI module',...
                    'Please specify a output folder and press on run. To continue the batch after that, press the "RUN BATCH" button again from the main window!'),...
                    'No output folder specified','warn');
                    app.batchHasCrashed = true;
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert the batch status in the main EEGpal windows.
                    return
                end
            
            end
            
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_GEDAItoolbox_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal GEDAI toolbox Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of GEDAI toolbox:',app.EEGpalCS_Main.Versions.GEDAI);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Session parameters file:',app.EEGpalCS_Main.SessionParametersFilePath.Value);
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','This processing was performed in Batch mode.');                
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','No');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','GEDAI toolbox option');
            fprintf(PifFileID,'%s\t\t%s\r\n','Denoising strength:',app.Denoising.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Leadfield matrix:',app.LeadfieldMatrix.Value);
            fprintf(PifFileID,'%s\t\t%d\r\n','Epochs size (wave cycles):',app.EpochsSizeEditField.Value);
            fprintf(PifFileID,'%s\t\t%f\r\n','Low-cut frequency (Hz):',app.Lowpass.Value);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','GEDAI optional parameters');
            if app.RejectbadepochsCheckbox.Value
                fprintf(PifFileID,'%s\t\t%f\r\n','Rejection of bad Epochs with an ENOVA thresholf of :',app.ENOVAthreshold.Value);
            else
                fprintf(PifFileID,'%s\r\n','Not rejection of bad Epochs');
            end
            if app.RejectchannelCheckbox.Value
                fprintf(PifFileID,'%s\t\t%f\r\n','Rejection of bad Channels with an ENOVA thresholf of :',app.ENOVAthresholdChannels.Value);
            else
                fprintf(PifFileID,'%s\r\n','Not rejection of bad Channels');
            end
            if app.SlidingWindowCheckbox.Value
                fprintf(PifFileID,'%s\t\t%f%s\r\n','Adaptation of the artefact threshold on a sliding window of ',app.SlidingWindowDuration.Value,'s');
            else
                fprintf(PifFileID,'%s\r\n','Artefact threshold determined on the whole file');
            end

            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN GEDAI toolbox
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run GEDAI toolbox...')
            StartTime = datetime('now');
            tStart = tic;
            
            h = waitbar(0,'GEDAI toolbox processing.....');
            nbFile= height(app.DataTable);
            
            % GEDAI processing file by file
            for file = 1:nbFile
                waitbar(file/nbFile,h, ['GEDAI toolbox processing ',num2str(file),'/',num2str(nbFile)]);
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    continue
                end
                disp('File loaded!')
                
                
                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    % FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    
                    % Channels check (if electrode setting defined)
                    try
                        Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        if isequal(char(FileChannels),char(Channels))
                            ChannelsCheck(file,1)   = "OK";
                        else
                            ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No channels in file header";
                    end
                catch
                    ChannelsCheck(file,1)   = "No electrode setting defined";
                end
                
                % GEDAI
                disp(['GEDAI is processing file ', num2str(file), ' out of ', num2str(height(app.DataTable))])
                
                %convert into an eeglab structure (.set)
                ALLEEG=struct; EEG=struct;CURRENTSET=0;    
                try
                    ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    Channels            = ElectrodeSetting(:,2);
                    %Channels.labels     = char(ElectrodeSetting.labels);

                    % Recompute Theta and Radius to match EEGlab orientation
                    [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                    Channels.theta      = -rad2deg(az);
                    Channels.radius     = 0.5 - rad2deg(elev)/180;
                    Channels.sph_theta  = -Channels.theta;
                    Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                    Channels.X          = ElectrodeSetting.y;
                    Channels.Y          = -ElectrodeSetting.x;
                    Channels.Z          = ElectrodeSetting.z;
                    Channels.ref        = repmat({[]},height(Channels),1);
                    Channels.type       = repmat({[]},height(Channels),1);
                    Channels.urchan     = repmat({[]},height(Channels),1);
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
                    Channels            = table2struct(Channels);
                catch
                    % Channels = '';
                    errordlg("Problem to convert the Electrode coordinate file into eeglab EEG.chanlocs!", "Electrode Channel Problem");
                    return
                end
                EEG = eeglab_datastruct(openfilename,data,header.SamplingRate,events,Channels);
                %Modification de EEG.chanlocs pour qu'il soit conforme à ce que GEDAI attend pour l'interpolation (conversion EEG.chanlocs de string vers char)                 
                fields = fieldnames(EEG.chanlocs);
                for iChan = 1:numel(EEG.chanlocs)
                    for iField = 1:numel(fields)
                        val = EEG.chanlocs(iChan).(fields{iField});
                        if isstring(val)
                            EEG.chanlocs(iChan).(fields{iField}) = char(val);
                        end
                    end
                end

                %Apply the optional parameters
                if app.RejectbadepochsCheckbox.Value %Epoch rejection
                    option_RejectionEpochs=app.ENOVAthreshold.Value;
                else
                    option_RejectionEpochs=Inf;
                end

                if app.RejectchannelCheckbox.Value %Channel rejection
                    option_RejectionChannels=app.ENOVAthresholdChannels.Value;
                else
                    option_RejectionChannels=Inf;
                end

                if app.SlidingWindowCheckbox.Value %Sliding threshold computation
                    option_SlidingThreshold=app.SlidingWindowDuration.Value;
                else
                    option_SlidingThreshold=Inf;
                end
                
                %Apply GEDAI
                EEGclean = GEDAI(EEG, app.Denoising.Value, app.EpochsSizeEditField.Value, app.Lowpass.Value, app.LeadfieldMatrix.Value, app.ParallelprocessingCheckBox.Value, app.ArtifactvisualizationCheckBox.Value, option_RejectionEpochs, option_RejectionChannels,'eeg',option_SlidingThreshold);
                
                %Correct events file if bad Epoch rejection choose
                if app.RejectbadepochsCheckbox.Value %remove bad epochs 
                    indicesToKeep=[EEGclean.event.urevent]; %the indices indiced from original event to keep
                    eventsCleaned=events(indicesToKeep,:); %keep only the indicesToKeep from the original events matrix
                    
                    % update the onset in eventsCleaned with corrected values                    
                    indice=0;
                    for zz=1:size(EEGclean.event,1)
                        if ~isempty(EEGclean.event(zz).urevent)
                            indice=indice+1;
                            eventsCleaned(indice,1)=EEGclean.event(zz).latency; 
                            eventsCleaned(indice,2)=EEGclean.event(zz).latency;
                        end
                    end
                end
                % Note on GEDAI arguments:Input data, 'artifact_threshold','epoch_size_in_cycles', 'lowcut_frequency', 'ref_matrix_type', 'parallel_processing', 'visualization_A', 'ENOVA_threshold_per_epoch', 'ENOVA_threshold_per_channel', 'signal_type', 'smoothing_window_seconds', 'varargin')
                % ==> [EEG] = GEDAI(EEG, 'auto', 12, 0.5, 'interpolated', true, false, Inf); 

                %Store the new data
                NewData = EEGclean.data';
                
                disp('GEDAI processing completed')
                
                % Save processed file
                if ~isempty(app.SuffixString.Value)
                    suffix = ['_', app.SuffixString.Value];
                end
                
                SubPath = app.DataTable.Path{file}(3:end);
                NewPath = [app.SaveFolder.Value{:} SubPath];
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end
                
                savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension.Value]);
                disp(['Saving ',savefilename])
                if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                    save_eph(savefilename,NewData,header.SamplingRate,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.sef')
                    try
                        %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                        Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                    catch
                        Channels = '';
                    end
                    save_sef(savefilename,NewData,header.SamplingRate,Channels,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.set')
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,2);
                        %Channels.labels     = char(ElectrodeSetting.labels);

                        % Recompute Theta and Radius to match EEGlab orientation
                        [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                        Channels.theta      = -rad2deg(az);
                        Channels.radius     = 0.5 - rad2deg(elev)/180;
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.y;
                        Channels.Y          = -ElectrodeSetting.x;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                    catch
                        Channels = '';
                    end
                    save_eeglab(savefilename,NewData,header.SamplingRate,events,header.firstindex,Channels)
                end
                
                % Update Table
                ProcessedDataTable.(1)(file)        = {[FileName, suffix, app.FilesExtension.Value]};
                ProcessedDataTable.(3)(file)        = size(NewData,2);
                %ProcessedDataTable.(4)(file)        = app.SamplingRate;
                ProcessedDataTable.(5)(file)        = size(NewData,1);
                
                
                % File completed
                tMid = toc(tStart);
                disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                disp('...............................................................')
            
            %libération d'espace mémoire
            clearvars EEG ALLEEG CURRENTSET          
            end

            delete(h);
            
            % UPDATE DATA TABLE IN MAIN WINDOW
            
            % Data table in the Main Window
            app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
            app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
            app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
            app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
            disp('Data table in the Main Window updated with the processed files')
            cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(NewData,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Channel names check  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';ChannelsCheck';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('GEDAI toolbox processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            %Close
            if app.SessionParameters.BatchProcessing.BatchExecution == 0
                rz = msgbox('GEDAI processing done.');
            else %batch mode module successly completed                
                if app.batchHasCrashed
                    app.SessionParameters.BatchProcessing.BatchExecution = 0; %break the batch
                end
                
                app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=1; %process complited
                app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert only Batch variable to the main window
                CancelButtonPushed(app); %to close the module without saving Session Parameters
            end
        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.GEDAIButton.Enable = 'on';
            
            % Interpolation parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            %batch
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                app.SessionParameters.BatchProcessing.BatchExecution = 0; % If the participant was able to click on this button, be sure to break the batch
            end

            % Update parameters          
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.GEDAIButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpalCS_GEDAI
        function EEGpalCS_GEDAICloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.GEDAIButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_GEDAI_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_GEDAI_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_GEDAI_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Value changed function: RejectbadepochsCheckbox
        function RejectbadepochsCheckboxValueChanged(app, event)
            if app.RejectbadepochsCheckbox.Value
                app.ENOVAthreshold.Enable = "on";
                app.ENOVAThresholdLabel.Enable = "on";
            else
                app.ENOVAthreshold.Enable = "off";
                app.ENOVAThresholdLabel.Enable = "off";
            end
        end

        % Value changed function: SlidingWindowCheckbox
        function SlidingWindowCheckboxValueChanged(app, event)
            if app.SlidingWindowCheckbox.Value
                app.SlidingWindowDuration.Enable = "on";
                app.SlidingWindowDurationLabel.Enable = "on";
            else
                app.SlidingWindowDuration.Enable = "off";
                app.SlidingWindowDurationLabel.Enable = "off";
            end
            
        end

        % Value changed function: RejectchannelCheckbox
        function RejectchannelCheckboxValueChanged(app, event)
            if app.RejectchannelCheckbox.Value
                app.ENOVAthresholdChannels.Enable = "on";
                app.ENOVAThresholdChannelsLabel.Enable = "on";
            else
                app.ENOVAthresholdChannels.Enable = "off";
                app.ENOVAThresholdChannelsLabel.Enable ="off";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_GEDAI = uifigure('Visible', 'off');
            app.EEGpalCS_GEDAI.Name = 'EEGpal_GEDAI';
            app.EEGpalCS_GEDAI.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_GEDAICloseRequest, true);
            app.GeneralizedEigenvalueDeArtifactingInstrumentGEDAILabel = uilabel(app.EEGpalCS_GEDAI);
            app.GeneralizedEigenvalueDeArtifactingInstrumentGEDAILabel.Text = 'Generalized Eigenvalue De-Artifacting Instrument (GEDAI)';
            app.OUTPUTOPTIONSLabel = uilabel(app.EEGpalCS_GEDAI);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.EEGpalCS_GEDAI);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.EEGpalCS_GEDAI);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.EEGpalCS_GEDAI);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.EEGpalCS_GEDAI, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.EEGpalCS_GEDAI);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.EEGpalCS_GEDAI);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.EEGpalCS_GEDAI);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.EEGpalCS_GEDAI, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'GEDAIcorrected';
            app.APPLYLabel = uilabel(app.EEGpalCS_GEDAI);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.EEGpalCS_GEDAI, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.SaveinmemoryButton = uibutton(app.EEGpalCS_GEDAI, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window without applying the processing and save the parameters.'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.EEGpalCS_GEDAI, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.ArtifactvisualizationCheckBox = uicheckbox(app.EEGpalCS_GEDAI);
            app.ArtifactvisualizationCheckBox.Tooltip = {'Open an additional window overlaying the original and corrected signals for visualisation.'};
            app.ArtifactvisualizationCheckBox.Text = '';
            app.ArtifactvisualizationLabel = uilabel(app.EEGpalCS_GEDAI);
            app.ArtifactvisualizationLabel.Text = 'Artifact visualization:';
            app.ParallelprocessingCheckBox = uicheckbox(app.EEGpalCS_GEDAI);
            app.ParallelprocessingCheckBox.Text = '';
            app.ParallelprocessingCheckBox.Value = true;
            app.ParallelprocessingLabel = uilabel(app.EEGpalCS_GEDAI);
            app.ParallelprocessingLabel.Text = 'Parallel processing:';
            app.RejectchannelCheckbox = uicheckbox(app.EEGpalCS_GEDAI);
            app.RejectchannelCheckbox.ValueChangedFcn = createCallbackFcn(app, @RejectchannelCheckboxValueChanged, true);
            app.RejectchannelCheckbox.Tooltip = {'This option allows you to interpolate automatically some channels.'};
            app.RejectchannelCheckbox.Text = '';
            app.RejectbadchannelsLabel = uilabel(app.EEGpalCS_GEDAI);
            app.RejectbadchannelsLabel.Text = 'Reject bad channels';
            app.ENOVAthresholdChannels = uieditfield(app.EEGpalCS_GEDAI, 'numeric');
            app.ENOVAthresholdChannels.Limits = [0 1];
            app.ENOVAthresholdChannels.Enable = 'off';
            app.ENOVAthresholdChannels.Tooltip = {'Setting a threshold of 0.9 will interpolate all channels with more than 90% of the signal identified as noise. A smaller value leads to more conservative processing.'};
            app.ENOVAthresholdChannels.Value = 0.9;
            app.ENOVAThresholdChannelsLabel = uilabel(app.EEGpalCS_GEDAI);
            app.ENOVAThresholdChannelsLabel.Enable = 'off';
            app.ENOVAThresholdChannelsLabel.Text = 'Channel ENOVA Threshold (0-1)    ';
            app.ENOVAThresholdLabel = uilabel(app.EEGpalCS_GEDAI);
            app.ENOVAThresholdLabel.Enable = 'off';
            app.ENOVAThresholdLabel.Text = 'Epoch ENOVA Threshold (0-1)    ';
            app.ENOVAthreshold = uieditfield(app.EEGpalCS_GEDAI, 'numeric');
            app.ENOVAthreshold.Limits = [0 1];
            app.ENOVAthreshold.Enable = 'off';
            app.ENOVAthreshold.Tooltip = {'Specifying a threshold of 0.9 will reject all epochs in which more than 90% of the signal is identified as noise. A smaller value will lead to more conservative processing.'};
            app.ENOVAthreshold.Value = 0.9;
            app.RejectbadepochsCheckbox = uicheckbox(app.EEGpalCS_GEDAI);
            app.RejectbadepochsCheckbox.ValueChangedFcn = createCallbackFcn(app, @RejectbadepochsCheckboxValueChanged, true);
            app.RejectbadepochsCheckbox.Tooltip = {'This option allows you to remove part of the signal rather than denoise it. NOT RECOMMENDED FOR AN ERP STUDY'};
            app.RejectbadepochsCheckbox.Text = '';
            app.RejectbadepochsLabel = uilabel(app.EEGpalCS_GEDAI);
            app.RejectbadepochsLabel.Text = 'Reject bad epochs';
            app.SlidingWindowDurationLabel = uilabel(app.EEGpalCS_GEDAI);
            app.SlidingWindowDurationLabel.Enable = 'off';
            app.SlidingWindowDurationLabel.Text = 'Window duration (in seconds)';
            app.SlidingWindowDuration = uieditfield(app.EEGpalCS_GEDAI, 'numeric');
            app.SlidingWindowDuration.Limits = [0 Inf];
            app.SlidingWindowDuration.Enable = 'off';
            app.SlidingWindowDuration.Tooltip = {'Size of the window in s on which the artefact rejection threshold is computed.'};
            app.SlidingWindowDuration.Value = 1200;
            app.SlidingWindowCheckbox = uicheckbox(app.EEGpalCS_GEDAI);
            app.SlidingWindowCheckbox.ValueChangedFcn = createCallbackFcn(app, @SlidingWindowCheckboxValueChanged, true);
            app.SlidingWindowCheckbox.Tooltip = {'Sliding threshold adaptation to account for signal non-stationarities over time (for long recording).'};
            app.SlidingWindowCheckbox.Text = '';
            app.SlindingWindowLabel = uilabel(app.EEGpalCS_GEDAI);
            app.SlindingWindowLabel.Text = 'Sliding threshold adaptation (only for long recording)';
            app.OptionalparametersLabel = uilabel(app.EEGpalCS_GEDAI);
            app.OptionalparametersLabel.Text = 'Optional parameters';
            app.Lowpass = uieditfield(app.EEGpalCS_GEDAI, 'numeric');
            app.Lowpass.Limits = [0 Inf];
            app.Lowpass.Tooltip = {'If you have already applied a high-pass filter to your signal using the Filtering+ module, please specify the same cut-off frequency here.'};
            app.Lowpass.Value = 0.5;
            app.EpochssizewavecyclesLabel = uilabel(app.EEGpalCS_GEDAI);
            app.EpochssizewavecyclesLabel.Text = 'Epochs size (wave cycles)';
            app.EpochsSizeEditField = uieditfield(app.EEGpalCS_GEDAI, 'numeric');
            app.EpochsSizeEditField.Limits = [1 Inf];
            app.EpochsSizeEditField.RoundFractionalValues = 'on';
            app.EpochsSizeEditField.ValueDisplayFormat = '%.0f';
            app.EpochsSizeEditField.Tooltip = {'default value is a good compromise (see FAQ for more details)'};
            app.EpochsSizeEditField.Value = 12;
            app.LeadfieldMatrix = uidropdown(app.EEGpalCS_GEDAI);
            app.LeadfieldMatrix.Items = {'interpolated', 'precomputed'};
            app.LeadfieldMatrix.Tooltip = {'Use the option precomputed only if the electrode names load in Electrode Setting (main EEGpal window) respect the international convention (e.g. Fp1, Cz, …) with a standard electrode configuration.'};
            app.LeadfieldMatrix.Value = 'interpolated';
            app.ExtensionofoutputfilesLabel_2 = uilabel(app.EEGpalCS_GEDAI);
            app.ExtensionofoutputfilesLabel_2.Text = 'Denoising strength';
            app.Denoising = uidropdown(app.EEGpalCS_GEDAI);
            app.Denoising.Items = {'auto', 'auto+', 'auto-'};
            app.Denoising.Value = 'auto';
            app.HelpButton = uibutton(app.EEGpalCS_GEDAI, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.ExtensionofoutputfilesLabel_3 = uilabel(app.EEGpalCS_GEDAI);
            app.ExtensionofoutputfilesLabel_3.Text = 'Leadfield matrix';
            app.EpochssizewavecyclesLabel_2 = uilabel(app.EEGpalCS_GEDAI);
            app.EpochssizewavecyclesLabel_2.Text = 'Low-cut frequency (Hz)     ';
            app.Image = uiimage(app.EEGpalCS_GEDAI);
            app.Image.ImageSource = 'Logo-CIBM.png';
            % Show the figure after all components are created
            app.EEGpalCS_GEDAI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_GEDAI(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_GEDAI)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_GEDAI)
        end
    end
end