classdef EEGpalCS_mod_ICA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_ICA                  matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        BridgedetectedSwitch          matlab.ui.control.Switch
        BridgedetectedSwitchLabel     matlab.ui.control.Label
        HelpButton                    matlab.ui.control.Button
        Step0WarningLabel             matlab.ui.control.Label
        BadChannelTable               matlab.ui.control.Table
        TitleStep0                    matlab.ui.control.Label
        Step1WarningLabel_2           matlab.ui.control.Label
        ConcanenateSubject            matlab.ui.control.CheckBox
        Step1DoneCheckBox             matlab.ui.control.CheckBox
        StartICAdecompositionButton   matlab.ui.control.Button
        TitleStep1                    matlab.ui.control.Label
        ICALabel                      matlab.ui.control.Label
        Step1WarningLabel             matlab.ui.control.Label
        CenterPanel                   matlab.ui.container.Panel
        NotethresholdLabel            matlab.ui.control.Label
        MaxnumberofcomponentstEditField  matlab.ui.control.NumericEditField
        MaxnumberofcomponentstoinspectperfileEditFieldLabel  matlab.ui.control.Label
        NOTRECOMMANDEDLabel           matlab.ui.control.Label
        AutomaticInspectionButton     matlab.ui.control.Button
        LineNoiseEditField            matlab.ui.control.NumericEditField
        LineNoiseEditFieldLabel       matlab.ui.control.Label
        HeartEditField                matlab.ui.control.NumericEditField
        HeartEditFieldLabel           matlab.ui.control.Label
        MuscleEditField               matlab.ui.control.NumericEditField
        MuscleEditFieldLabel          matlab.ui.control.Label
        EyeEditField                  matlab.ui.control.NumericEditField
        EyeEditFieldLabel             matlab.ui.control.Label
        ChannelNoiseEditField         matlab.ui.control.NumericEditField
        ChannelNoiseEditFieldLabel    matlab.ui.control.Label
        AutomaticICAlabelSuggestion   matlab.ui.control.CheckBox
        TitleAssistance               matlab.ui.control.Label
        InspectionTable               matlab.ui.control.Table
        FilteringInstruction_2        matlab.ui.control.Label
        TitleStep2                    matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        ICAintroduction               matlab.ui.control.Label
        deleteIntermediateFile        matlab.ui.control.DropDown
        DeleteintermediatefilesLabel  matlab.ui.control.Label
        RunButton                     matlab.ui.control.Button
        TitleStep3                    matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        SaveinmemoryButton            matlab.ui.control.Button
        APPLYLabel                    matlab.ui.control.Label
        SuffixString                  matlab.ui.control.EditField
        SuffixstringLabel             matlab.ui.control.Label
        SaveFolder                    matlab.ui.control.TextArea
        SavingfolderLabel             matlab.ui.control.Label
        SelectFolderButton            matlab.ui.control.Button
        SelectUpperFolderLabel        matlab.ui.control.Label
        FilesExtension                matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel   matlab.ui.control.Label
        OUTPUTOPTIONSLabel            matlab.ui.control.Label
        CleanRawDataInstructionLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

   
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        NewDataCell
        rootfolder
        savePathElectrodCoordinateFile_EEGlab
        DecompositionDone
        InputFilesConverted % Contain the path of file converted in .ep
        isAlreadyDecomposed
        automaticInspection % to know if the inspection must be done automatically
        hasUsedAutomaticDetection % Description
        batchHasCrashed 
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)
               
        function out = str2vec(~,str)
            cln = find(str==':');
            if length(cln) == 1
                out = str2double(str(1:cln-1)) : str2double(str(cln+1:end));
            elseif length(cln) == 2
                out = str2double(str(1:cln(1)-1)) : str2double(str(cln(1)+1:cln(2)-1)) : str2double(str(cln(2)+1:end));
            else
                disp('did not understand the string, sorry!')
            end
        end


        function SessionParameters = updateSessionParam(app,SessionParameters) 
            
            % Step 0 Bad channel
            SessionParameters.ICA.ConcanenateSubject    = app.ConcanenateSubject.Value;
            SessionParameters.ICA.BridgedetectedSwitch  = app.BridgedetectedSwitch.Value;
            SessionParameters.ICA.BadChannelTable       = app.BadChannelTable.Data;

            %step 1 Decomposition
            SessionParameters.ICA.DecompositionDone    = app.Step1DoneCheckBox.Value;
            SessionParameters.ICA.NewDataCell          = app.NewDataCell;
            
            % Step 2 Inspection
            SessionParameters.ICA.InspectionTable      = app.InspectionTable.Data;
            SessionParameters.ICA.MaxnumberofcomponentstEditField = app.MaxnumberofcomponentstEditField.Value;
            SessionParameters.ICA.AutomaticICAlabelSuggestion = app.AutomaticICAlabelSuggestion.Value;
            SessionParameters.ICA.EyeThreshold = app.EyeEditField.Value;
            SessionParameters.ICA.MuscleThreshold = app.MuscleEditField.Value;
            SessionParameters.ICA.HeartThreshold = app.HeartEditField.Value;
            SessionParameters.ICA.ChannelNoiseThreshold = app.ChannelNoiseEditField.Value;
            SessionParameters.ICA.LineNoiseThreshold = app.LineNoiseEditField.Value;

            % OUTPUT Recomposition
            SessionParameters.ICA.FilesExtension       = app.FilesExtension.Value;
            SessionParameters.ICA.SaveFolder           = app.SaveFolder.Value;
            SessionParameters.ICA.SuffixString         = app.SuffixString.Value;
            SessionParameters.ICA.deleteIntermediateFile    = app.deleteIntermediateFile.Value;
        end
        
        function componentsToRemove = inspectionComponents(app,fileName,filePath, indices)
            
            %initialisation EEGLAB variable
            ALLEEG=struct; CURRENTSET=0;
            
            %load dataset
            EEG = pop_loadset('filename',fileName,'filepath',filePath);
            [ALLEEG, EEG, CURRENTSET] = eeg_store( ALLEEG, EEG, 0 );
            
            
            if (~isempty(EEG.icawinv)) %check if ica decomposition has been done
                  % code to specify the number of components to show. I
                  % consider max the 24 first component and it is why I
                  % decativate this input. However you can reactivate it if
                  % you want that the user decide how many components to
                  % display
%                 p = inputdlg(['How many ICA component you want to display (max value is the number of channels'],'Number of component to display');
%                 p=str2double(p{1})
%                 if or(isnan(p), p>size(EEG.data,1))
%                     return
%                 end
                %Tag the EEG component
                f = msgbox("Wait a short time (Labels are currentely process)","","help");
                EEG = pop_iclabel(EEG, 'default'); 
                [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                close(f)
                
                % Automatic suggestion by ICAlabel
                suggestedComponentsToRemove='';
                if app.AutomaticICAlabelSuggestion.Value
                    if app.MaxnumberofcomponentstEditField.Value <= size(EEG.etc.ic_classification.ICLabel.classifications,1)
                        limitEvaluation=app.MaxnumberofcomponentstEditField.Value;
                    else
                        limitEvaluation=size(EEG.etc.ic_classification.ICLabel.classifications,1);
                    end
                    temp=EEG.etc.ic_classification.ICLabel.classifications;
                    ToRemove=[];
                    for z=1:limitEvaluation
                        if temp(z,3)>=app.EyeEditField.Value/100 || temp(z,2)>=app.MuscleEditField.Value/100 || temp(z,4)>=app.HeartEditField.Value/100 || temp(z,6)>=app.ChannelNoiseEditField.Value/100 || temp(z,5)>=app.LineNoiseEditField.Value/100
                            ToRemove=horzcat(ToRemove, z);
                        end
                    end
                    if ~isempty(ToRemove)
                        suggestedComponentsToRemove=num2str(ToRemove);
                    end
                end        

              
                if ~app.automaticInspection %manual inspection
                    %display the ICA components
                    pop_selectcomps(EEG, [1:app.MaxnumberofcomponentstEditField.Value] );
                    [ALLEEG EEG] = eeg_store(ALLEEG, EEG, CURRENTSET);
                    
                    %display the trace of component              
                    if isempty(EEG.icaact)
                        warndlg(["If you want to see the ICA trace, please make sure to activate the following option in eeglab:",...
                            "1. open eeglab GUI by tapping the commande 'eeglab' in the command windows of Matlab", ...
                            "2. Go to the menu File->Preferences",...
                            "3. Be sure that the option 'If set, save not one but two files for each dataset (header and data). No longer set by default as of 2021' is activated.",...
                            "4. Be sure that the option 'If set, precompute ICA activation. This requires more RAM but allows faster plotting of component activation' is activated.",...
                            "5. Press on Ok in the eeglab preference window to validate the changes."], "Impossible to show the ICA decomposition trace.");
                    else                               
                        comps_to_display = 1:app.MaxnumberofcomponentstEditField.Value; % Indices des composantes à afficher
                        max_amplitude = max(abs(EEG.icaact(comps_to_display, :, :)), [], 'all')/4; % Calculer l'amplitude maximale des composantes affichées               
                        eegplot(EEG.icaact(comps_to_display, :, :), 'srate', EEG.srate, ... % Adapter l'espacement à l'amplitude maximale
                            'spacing', max_amplitude, 'events', EEG.event,'title','ICA components');
                    end
                    
                    %TEMP (Dangerous: to remove if any problem)
                    if ~isdeployed
                        assignin('base', 'EEG', EEG); %transféré la variable EEG dans le worksapce de Matlab pour que eeglab puisse y accédé et premettre d'afficher le détail d'une composante en cliquant sur le nombre au dessus de sa topo.                 
                    end

                    % Création fenêtre input user
                    global userInput;
                    userInput=0;                    
                    fig = uifigure('Name', 'Components to remove', 'Position', [500 500 400 200]);
            
                    lbl = uilabel(fig, 'Position', [20 140 360 50], ...
                        'Text', ['Enter manually here the number of the component to remove',newline,'(do not use the button on the windows Reject components by map).',newline,'Separate each number by a space (e.g. 2 5)']);
                    editField = uieditfield(fig, 'text', 'Position', [20 90 350 30],'Value',suggestedComponentsToRemove);   
                    
                    uibutton(fig, 'Position', [170 50 100 30], 'Text', 'OK', ... % Ajouter un bouton pour valider l'entrée et fermer la fenêtre
                        'ButtonPushedFcn', @(btn, event) processInput(app,editField,fig));
                    uiwait(fig);
                                    
                    if userInput==0 %case cancel
                        componentsToRemove=[];
                        app.InspectionTable.Data.Inspection(indices(1))=false;
                    elseif (isempty(userInput)) %case no component to remove                     
                        componentsToRemove=[];                    
                    else
                        componentsToRemove = str2num(userInput);
                    end
                    
                    %TEMP (Dangerous: to remove if any problem) - link to line 155
                    if ~isdeployed
                        evalin('base', 'clear EEG'); %Supprimer cet variable du workspace principale de Matlab pour ne pas généré de conflit. 
                    end
                    close all
                else %automatic component selection
                    componentsToRemove = str2num(suggestedComponentsToRemove);
                end

            else
                f = msgbox("It seems that no ICA decomposition has been performed with this file. Please run first the step1 on it","","warn");
                uiwait(f);
                componentsToRemove=[];
                app.InspectionTable.Data.Inspection(indices(1))=false;
                close f
            end
                         
        end
        
        function processInput(app, editField,fig)
            global userInput; %pour accéder à la variable global 
            userInput = editField.Value;
            close(fig);
        end
        
        function updateBadChannelTableWithEbridge(app)
            for i=1:size(app.SessionParameters.ebridge.eBridgeTable,1)
                bridgeChannels=""; 
                for k=4:size(app.SessionParameters.ebridge.eBridgeTable,2)
                    if app.SessionParameters.ebridge.eBridgeTable{i,k}==1
                        if strcmp(bridgeChannels,"")
                            bridgeChannels=num2str(k-3);
                        else
                            bridgeChannels=strcat(bridgeChannels," ",num2str(k-3));
                        end
                    end
                end

                if ~strcmp(bridgeChannels,"") %apply in app.BadChannelTable
                    app.BadChannelTable.Data.Bad_channels(i)=bridgeChannels;
                    fakeEvent=struct;
                    fakeEvent.Indices = [i,3];
                    BadChannelTableCellEdit(app, fakeEvent); %apply concatenate participant
                end
            end          
        end


        function applySessionParam(app)
            % Step 0 OPTION
            app.ConcanenateSubject.Value        = app.SessionParameters.ICA.ConcanenateSubject;
            
            % %activate of not             
            % if strcmp(app.SessionParameters.ICA.BridgedetectedSwitch, 'On') %activate or not ebridge
            %      if isfield(app.SessionParameters,'ebridge')
            %          app.BridgedetectedSwitch.Value='On';
            %          app.BridgedetectedSwitch.Enable="on";
            %      else
            %          app.BridgedetectedSwitch.Value='On';
            %          app.BridgedetectedSwitch.Enable="off";
            %      end
            % else
            %     app.BridgedetectedSwitch.Value='Off';
            %     app.BridgedetectedSwitch.Enable="off";
            % end
            %load or not the bad channel
            filesIN=app.EEGpalCS_Main.DataFilesTable.Data.File; 
            filesSAVED=app.SessionParameters.ICA.BadChannelTable.FileNames;
            isEqual=false;
            if size(filesSAVED,1)==size(filesIN,1) %take accont the bug when there is not the name number of file between the batch parameter and the current file selection            
                isEqual = all(cellfun(@(x,y) isequal(x,y), filesIN, filesSAVED)); % check if the input files are the same as the file saved otherwise leave the default Interpolation matrix
                if isEqual                     
                    for z=1:size(app.SessionParameters.ICA.BadChannelTable,1) %parcourir chaque ligne
                        %code pour fusionner la liste déjà présente (ebrige) et ce qui est enregistré dans session parameter
                        % Récupération
                        str1 = app.BadChannelTable.Data{z,3};
                        str2 = app.SessionParameters.ICA.BadChannelTable{z,3};
                        % Normalisation : convertir les missing/vides en ""
                        if ismissing(str1) || isempty(str1)
                            str1 = "";
                        end
                        if ismissing(str2) || isempty(str2)
                            str2 = "";
                        end
                        % Conversion en tableau numérique (ignore les NaN dus aux vides)
                        nums1 = str2double(strsplit(strtrim(str1)));
                        nums1(isnan(nums1)) = [];
                        nums2 = str2double(strsplit(strtrim(str2)));
                        nums2(isnan(nums2)) = [];
                        % Fusion + suppression doublons
                        allNums = unique([nums1, nums2]);
                        % Conversion en string final
                        if isempty(allNums)
                            app.BadChannelTable.Data{z,3} = "";
                        else
                            app.BadChannelTable.Data{z,3} = strjoin(string(allNums), ' ');
                        end
                    end
    
                    app.Step1DoneCheckBox.Value    =  app.SessionParameters.ICA.DecompositionDone;
                end            
            end

            %step 1 Decomposition            
            if app.Step1DoneCheckBox.Value
                app.InspectionTable.Enable = "on";
                app.RunButton.Enable       = "on";                
            end
            if and(app.Step1DoneCheckBox.Value,isEqual)
                app.NewDataCell            = app.SessionParameters.ICA.NewDataCell;
            end

            % Step 2 Inspection
            if isEqual
                app.InspectionTable.Data = app.SessionParameters.ICA.InspectionTable;
            end

            app.MaxnumberofcomponentstEditField.Value = app.SessionParameters.ICA.MaxnumberofcomponentstEditField;
            app.AutomaticICAlabelSuggestion.Value     = app.SessionParameters.ICA.AutomaticICAlabelSuggestion;
            AutomaticICAlabelSuggestionValueChanged(app);
            app.EyeEditField.Value                    = app.SessionParameters.ICA.EyeThreshold;
            app.MuscleEditField.Value                 = app.SessionParameters.ICA.MuscleThreshold;
            app.HeartEditField.Value                  = app.SessionParameters.ICA.HeartThreshold;
            app.ChannelNoiseEditField.Value           = app.SessionParameters.ICA.ChannelNoiseThreshold;
            app.LineNoiseEditField.Value              = app.SessionParameters.ICA.LineNoiseThreshold;

            % OUTPUT OPTION
            app.FilesExtension.Value            = app.SessionParameters.ICA.FilesExtension;
            app.SaveFolder.Value                = app.SessionParameters.ICA.SaveFolder;
            app.SuffixString.Value              = app.SessionParameters.ICA.SuffixString;
            app.deleteIntermediateFile.Value    = app.SessionParameters.ICA.deleteIntermediateFile;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
           
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main = EEGpalCS_Main;
            
            % Update UI with input values
            app.DataTable = app.EEGpalCS_Main.DataFilesTable.Data;            
            
            % Update UI with input values
            app.SessionParameters = app.EEGpalCS_Main.SessionParameters;    
             
            % Activate the concatenate if Subject specified
            if ~isempty(app.EEGpalCS_Main.FirstSubjCode.Value)
                app.ConcanenateSubject.Value = true;
            end
            
            % Initiate the automatic inspection table
            app.automaticInspection = false;
            app.hasUsedAutomaticDetection = false;

            % Adapte the limit of the number of component to display
            app.MaxnumberofcomponentstEditField.Limits=[1,app.DataTable.nChan(1)];
            if app.DataTable.nChan(1) < 24
                app.MaxnumberofcomponentstEditField.Value = app.DataTable.nChan(1);
            end
            
            %convert table of input file into a cell
            app.NewDataCell=table2cell(app.DataTable);            
           
            temp=app.NewDataCell{1,1}; 
%             temp=temp(end-3:end); %extraction of the extension
%             if ~strcmp (temp,".set")
%                 f = errordlg('The input files need to be in .set format to be able to use this module. Please use the export tool in main windows to convert your files in .set format'); 
%                 uiwait(f);
%                 
%                 app.EEGpalCS_Main.ICAButton.Enable = 'on';
%                 delete(app)
%             end
            
            %check if the decomposition has already be done based only on
            %the first file
            if contains(temp,'ICAdecomposed')
                app.InspectionTable.Enable = "on";
                app.Step1DoneCheckBox.Value=true;
                app.RunButton.Enable = "on";                
                app.isAlreadyDecomposed=true;            
            else
                % Create folder for intermediate files
                %mkdir(fullfile('.', 'ICAtemp'));
                app.isAlreadyDecomposed=false;
            end
            
            %Creation of the Inspection table 
            if ~isempty(app.DataTable)
                nRows = height(app.DataTable);
            else
                nRows = 1;
            end
            labels      = string({'FileNames','Subject','Component_to_remove','Inspection'});
            app.InspectionTable.Data = table('Size',[nRows length(labels)],...
                'VariableTypes',["string", "string", "cell","logical"],'VariableNames',labels);
            
            %Fill this table Inspection table
            for z=1:nRows
                app.InspectionTable.Data.FileNames(z)=app.NewDataCell{z,1}; %fill the file name
                app.InspectionTable.Data.Subject(z)=app.NewDataCell{z,7}; %fill the file name
            end
            
             %Creation of the Bad channels table 
            if ~isempty(app.DataTable)
                nRows = height(app.DataTable);
            else
                nRows = 1;
            end
            labels      = string({'FileNames','Subject','Bad_channels'});
            app.BadChannelTable.Data = table('Size',[nRows length(labels)],...
                'VariableTypes',["string", "string", "string"],'VariableNames',labels);
            
            %Fill this table Bad channels table
            for z=1:nRows
                app.BadChannelTable.Data.FileNames(z)=app.NewDataCell{z,1}; %fill the file name
                app.BadChannelTable.Data.Subject(z)=app.NewDataCell{z,7}; %fill the file name
            end

            %Fill with eBridge
            if isfield(app.SessionParameters,'ebridge') %check if ebridge has been estimate
                %check if same files
                if size(app.SessionParameters.ebridge.eBridgeTable,1) == nRows
                    sameFiles=true; 
                    for q=1:nRows
                        [~,filename1,~]=fileparts(app.BadChannelTable.Data.FileNames(q));
                        [~,filename2,~]=fileparts(app.SessionParameters.ebridge.eBridgeTable.File(q));
                        if ~contains(filename1,filename2)
                            sameFiles=false;
                        end
                    end
                else
                    sameFiles=false;
                end

                %fill with detected bridges
                if sameFiles
                    app.BridgedetectedSwitch.Enable = "on";
                    app.BridgedetectedSwitchLabel.Enable = "on";
                    app.BridgedetectedSwitch.Value = 'On';

                    updateBadChannelTableWithEbridge(app);
                end
            end
            
            %Fill with a flat channel due to a preceding Reref
            if isfield(app.SessionParameters,'Rereference')
                try
                    if ~strcmp(app.SessionParameters.Rereference.ReferenceValue_1{1},'Average') & numel(app.SessionParameters.Rereference.ReferenceValue_1) == 1
                        referenceChan=app.SessionParameters.Rereference.ReferenceValue_1{1};
                        %find the indice for the electrode
                        for z=1:size(app.SessionParameters.Electrodes.SettingTable,1)
                            if strcmp(app.SessionParameters.Electrodes.SettingTable.labels(z),referenceChan)
                                referenceChanIndice=num2str(z);
                            end
                        end

                        %remplissage avec la bad channel
                        for z=1:size(app.BadChannelTable.Data,1)
                            if ~ismissing(app.BadChannelTable.Data.Bad_channels(z))
                                app.BadChannelTable.Data.Bad_channels(z) = app.BadChannelTable.Data.Bad_channels(z) + " " + referenceChanIndice;
                            else
                                app.BadChannelTable.Data.Bad_channels(z)=referenceChanIndice;
                            end
                        end

                    end
                end
            end
            
            
            %load bad channels if the ICA decomposition has already by done
            for z=1:nRows
                [~,temp2,~] = fileparts(app.NewDataCell{z,1});
                logFilename=fullfile('.',[temp2,'_BadChannels.txt']);
                if isfile(logFilename)
                    BADchannels2=importdata(logFilename);
                    app.BadChannelTable.Data.Bad_channels(z)=num2str(BADchannels2);
                end
            end
            
            
            %save the current folder path
            app.rootfolder=pwd;

            %initiate save decomposition Electrode coordinate file
            app.savePathElectrodCoordinateFile_EEGlab='';
            
            %intitiave the DecompositionDone variable
            app.DecompositionDone=false;

            %Preset Cartool/eeglab parameters
            if strcmp(app.EEGpalCS_Main.PresetParametersSwitch.Value,'eeglab')
                app.FilesExtension.Value = '.set';
            end
            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
            end

            %Execute the batch System
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                app.AutomaticICAlabelSuggestion.Value = true;
                app.batchHasCrashed = false;

                if ~app.Step1DoneCheckBox.Value %need to run the ICA decomposition
                    RunICAdecomposition(app);
                elseif strcmp(app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,2},'Automatic Bad Components Rejection') %directely run the components automatic rejection
                    AutomaticInspectionButtonPushed(app);
                end
            end

            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_ICA); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;            
        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);

            %batch
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                app.SessionParameters.BatchProcessing.BatchExecution = 0; % If the participant was able to click on this button, be sure to break the batch
            end

            % Update parameters
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;

            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.ICAButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
            

        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            
            
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.ICAButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpalCS_ICA
        function EEGpalCS_ICACloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.ICAButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: StartICAdecompositionButton
        function RunICAdecomposition(app, event)
            sizeDataTable=size(app.NewDataCell);
            
            %initialisation
            mkdir (fullfile('.','ICAtemp','decomposed')); %create the temp folder for intermediate files         
                       
            %user input
            % defaultLocation=fullfile(app.EEGpalCS_Main.AppPath.value, 'Resources');
            % 
            % [fileLoc,pathLoc] = uigetfile('*.locs','Select the EEGlab equivalent of coordinate file *.loc (for example biosemi64AB.locs located in the folder *\eeglab_plugins\coordinateFile\ from your EEGpal directory', defaultLocation); %coordinate file
            % drawnow; figure(app.EEGpalCS_ICA); % Restaure the app module
            % app.savePathElectrodCoordinateFile_EEGlab=strcat(pathLoc,fileLoc);
            
            
            %check if the files are in .set format and export in .set if
            %not the case            
            f = waitbar(0,'Check format.....');
            nbConverted=0;
            for i=1:size(app.NewDataCell,1)
                [~,~,FileExtension] = fileparts(app.NewDataCell{i,1});
                
                if ~strcmp(FileExtension,'.set') %export in .set if needed
                    waitbar(i/size(app.NewDataCell,1),f, strcat('Check format for file ',num2str(i),'/',num2str(size(app.NewDataCell,1)))); 
                    export_in_set(app, fullfile(app.DataTable.Path{i}(2:end),app.DataTable.File{i})); %export in .set
                    app.NewDataCell{i,1}=strcat(app.DataTable.File{i}(1:end-4), '_exported.set'); %update the input table
                    
                    %Save path of file converted
                    nbConverted=nbConverted+1;
                    app.InputFilesConverted{nbConverted}=fullfile(app.DataTable.Path{i}(2:end),[app.DataTable.File{i}(1:end-4), '_exported.set']);
                end
            end
            close(f);           
            
            h = waitbar(0,'Processing.....');
            
            %main loop
            if app.ConcanenateSubject.Value
                %processing by concatenate the subject's file
                
                Subject=[app.NewDataCell{:,7}]; %convert to string array for subjects
                
                if strcmp(Subject(1),"") %check if there is a Subject List
                    f=errordlg("There is no Subject defined! Please go back in the main window to define a Subject list.","Impossible to concatenate subject files");
                    uiwait(f);
                    app.EEGpalCS_Main.ICAButton.Enable = 'on';
                    delete(h);
                    delete(app); 
                end

                [~,FirstIndiceSubjects,~] = unique(Subject); %determine the number of Subject
                
                for j=1:length(FirstIndiceSubjects) %j is the participant indice
                    waitbar(j/length(FirstIndiceSubjects),h, strcat('Processing Subject ',num2str(j),'/',num2str(length(FirstIndiceSubjects))));
                    
                    FirstIndiceFile=FirstIndiceSubjects(j);
                    if FirstIndiceSubjects(j)==FirstIndiceSubjects(end) 
                        TargetIndiceFile=sizeDataTable(1); %special case last subject
                    else
                        TargetIndiceFile=FirstIndiceSubjects(j+1)-1; %normal case
                    end
                    nbfileInSubject=TargetIndiceFile-FirstIndiceFile+1; 
                    
                    %initialisation EEGLAB variable
                    ALLEEG=struct; EEG=struct;CURRENTSET=0; 
                    
                    %load all subject dataset
                    for i=1:nbfileInSubject 
                        currentIndiceFile=FirstIndiceFile+i-1; %current indice in the cell app.NewDataCell
                        
                        EEG = pop_loadset('filename',app.NewDataCell{currentIndiceFile,1},'filepath',app.NewDataCell{currentIndiceFile,2}(2:end));
                        [ALLEEG, EEG, CURRENTSET] = eeg_store( ALLEEG, EEG, i );
                        % EEG = pop_editset(EEG, 'chanlocs', strcat(pathLoc,fileLoc));
                        % [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, i);
                    end
                    
                    multipleSelection=[1:1:nbfileInSubject]; 
                    [ALLEEG EEG CURRENTSET] = pop_newset(ALLEEG, EEG, multipleSelection,'retrieve',multipleSelection ,'study',0); %selection all subject dataset
                    
                    textbadChannels="";
                    
                    %run ICA
                    if ismissing(app.BadChannelTable.Data.Bad_channels(FirstIndiceSubjects(j))) %no channel to ignore 
                        EEG = pop_runica(EEG, 'icatype', 'runica', 'extended',1,'interrupt','on','concatenate','on');
                    
                    else %bad_Channels to ignore
                        badChannels="";
                        badChannels=split(app.BadChannelTable.Data.Bad_channels(FirstIndiceSubjects(j)),[" ",",",";"]);
                        goodChannels=[1:1:EEG(1).nbchan];
                        for t=1:size(badChannels,1)
                            if  ~strcmp(badChannels(t),"") %check to avoid empty space
                                goodChannels=goodChannels(goodChannels~=str2num(badChannels(t)));
                                textbadChannels=strcat(textbadChannels," " , badChannels(t)); %use only for the log file
                            end
                        end
                        EEG = pop_runica(EEG, 'icatype', 'runica', 'extended',1,'interrupt','on','concatenate','on','chanind',goodChannels);
                    end
                    
                    
                    [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                    
                    %save all subject dataset separetely
                    for i=1:nbfileInSubject
                         currentIndiceFile=FirstIndiceFile+i-1; %current indice in the cell app.NewDataCell
                         outputfileICA=strcat(app.NewDataCell{currentIndiceFile,1}(1:end-4),'_ICAdecomposed.set');
                        
                         [ALLEEG EEG CURRENTSET] = pop_newset(ALLEEG, EEG, i,'retrieve',i,'study',0); %retrieve set
                         EEG = pop_saveset( EEG, 'filename',outputfileICA,'filepath',fullfile('.','ICAtemp','decomposed'),'savemode','twofiles');
                         [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, i);
                         app.NewDataCell{currentIndiceFile,1}=outputfileICA;
                         app.NewDataCell{currentIndiceFile,2}=fullfile('..','ICAtemp','decomposed'); %path location of the new temp file
                         app.InspectionTable.Data.FileNames(currentIndiceFile)=outputfileICA; %fill inspection table with the new file name
                         
                         %save a txt file with the bad channels ignored
                         [~,temp2,~] = fileparts(outputfileICA);
                         logoutputfile=fullfile('.','ICAtemp','decomposed', [temp2,'_BadChannels.txt']);
                         logFile = fopen(logoutputfile,'w');
                         if ismissing(app.BadChannelTable.Data.Bad_channels(FirstIndiceSubjects(j)))
                            fprintf(logFile,'%s',"");
                         else
                             fprintf(logFile,'%s',textbadChannels);
                         end
                         fclose(logFile);
                    end
                
                    
                end
                
            else 
                %processing each files separetly
                for i=1:sizeDataTable(1)
                    waitbar(i/sizeDataTable(1),h, strcat('Processing files ',num2str(i),'/',num2str(sizeDataTable(1))));
                    
                    %initialisation EEGLAB variable
                    ALLEEG=struct; EEG=struct; CURRENTSET=0; 
                    
                    %Load dataset with EEGlab
                    EEG = pop_loadset('filename',app.NewDataCell{i,1},'filepath',app.NewDataCell{i,2}(2:end));
                    [ALLEEG, EEG, CURRENTSET] = eeg_store( ALLEEG, EEG, 0 );
                    
                    %Set EEGlab coordinate file
                    % EEG = pop_editset(EEG, 'chanlocs', strcat(pathLoc,fileLoc));
                    % [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);

                    
                    textbadChannels="";
                    
                    %run ICA
                    if ismissing(app.BadChannelTable.Data.Bad_channels(i)) %no channel to ignore                   
                        EEG = pop_runica(EEG, 'icatype', 'runica', 'extended',1,'interrupt','on');                        
                    
                    else %bad_Channels to ignore
                        badChannels="";
                        badChannels=split(app.BadChannelTable.Data.Bad_channels(i),[" ",",",";"]);
                        goodChannels=[1:1:EEG.nbchan];
                        for t=1:size(badChannels,1)
                            if  ~strcmp(badChannels(t),"") %check to avoid empty space
                                goodChannels=goodChannels(goodChannels~=str2num(badChannels(t)));
                                textbadChannels=strcat(textbadChannels," " , badChannels(t)); %use only for the log file
                            end
                        end
                        EEG = pop_runica(EEG, 'icatype', 'runica', 'extended',1,'interrupt','on', 'chanind',goodChannels);
                    end
                    [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                    
                    %save dataset
                    outputfileICA=strcat(app.NewDataCell{i,1}(1:end-4),'_ICAdecomposed.set');
                    EEG = pop_saveset( EEG, 'filename',outputfileICA,'filepath',fullfile('.','ICAtemp','decomposed'),'savemode','twofiles');
                    [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                    app.NewDataCell{i,1}=outputfileICA;
                    app.NewDataCell{i,2}=fullfile('..','ICAtemp','decomposed'); %path location of the new temp file  
                    app.InspectionTable.Data.FileNames(i)=outputfileICA; %fill inspection table with the new file name 
                    
                     %save a txt file with the bad channels ignored
                     [~,temp2,~] = fileparts(outputfileICA);
                     logoutputfile=fullfile('.','ICAtemp','decomposed', [temp2,'_BadChannels.txt']);
                     logFile = fopen(logoutputfile,'w');
                     if ismissing((app.BadChannelTable.Data.Bad_channels(i)))
                        fprintf(logFile,'%s',"");
                     else
                         fprintf(logFile,'%s',textbadChannels);
                     end
                     fclose(logFile);
                    
                end
            end
            
            %delete the InputFile converted in .set to gain space
            for dot=1:size(app.InputFilesConverted,2)
                temp=app.InputFilesConverted{dot}; 
                delete(app.InputFilesConverted{dot}); %supress .set
                delete(strcat(app.InputFilesConverted{dot}(1:end-3),'fdt')); %supress .fdt
            end            
            delete (h); 
            
            app.InspectionTable.Enable = "on";
            app.Step1DoneCheckBox.Value=true;
            app.DecompositionDone=true;
            app.ConcanenateSubject.Enable=false;
            app.RunButton.Enable = "on";

            %save a temporary PIF with decompsition info
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file            
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile('.',['PIF_ICA_decompisition' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal ICA Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            fprintf(PifFileID,'%s\r\n','ICA decomposition/recomposition use EEGLAB tools and components labeling use ICLabel tool.');
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGLAB:',app.EEGpalCS_Main.Versions.eeglab);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of ICLabel:',app.EEGpalCS_Main.Versions.ICLabel);
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','This processing was performed in Batch mode.');
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','No');
            end
            fprintf(PifFileID,'%s\t\t%s\r\n\r\n','These INPUT files has been decomposed in the temporary folder:',strcat(app.rootfolder,'\ICAtemp\decomposed'));
                
            fprintf(PifFileID,'\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            %bad channel
            fprintf(PifFileID,'\r\n%s\r\n\r\n','INPUT FILES (File name  /  Subject  /  Bad channels');
            for p=1:size(app.BadChannelTable.Data,1)
                if ismissing(app.BadChannelTable.Data.Bad_channels(p))
                    fprintf(PifFileID,'%s\t\t%s\t\t%s\r\n',app.BadChannelTable.Data.FileNames(p),app.BadChannelTable.Data.Subject(p),"");
                else
                    fprintf(PifFileID,'%s\t\t%s\t\t%s\r\n',app.BadChannelTable.Data.FileNames(p),app.BadChannelTable.Data.Subject(p),app.BadChannelTable.Data.Bad_channels(p));
                end
            end
            fclose(PifFileID);
            
            %batch processing
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                if strcmp(app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,2},'Automatic Bad Components Rejection') %directely run the components automatic rejection
                    AutomaticInspectionButtonPushed(app);
                else
                    rz = msgbox('Decomposition done. Perform the manual components inspection (step2) and click on Run when you have finished. To continue the batch after that, press the "RUN BATCH" button again from the main window!');
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3} = 2; %prevent the execution of the next module
                    %app.SessionParameters.BatchProcessing.BatchExecution = 0; 
                    app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
                end
            else
                rz = msgbox('Decomposition done. Go to step 2 !');
            end
        end

        % Cell edit callback: InspectionTable
        function InspectionTableCellEdit(app, event)
            indices = event.Indices;
        
            %newData = event.NewData;
            if or(app.InspectionTable.Data.Inspection(indices(1)),app.automaticInspection)             
                %function which call the component visualisation
                UnwantedComponents=inspectionComponents(app, app.NewDataCell{indices(1),1}, app.NewDataCell{indices(1),2}(2:end),indices); %argument2=filename, argument3=filepath
                
                app.InspectionTable.Data.Component_to_remove{indices(1)}=num2str(UnwantedComponents);
                
                %fill the table if concatenate subject is active
                if app.ConcanenateSubject.Value
                    Subject=[app.NewDataCell{:,7}]; %convert to string array for subjects
                    for i=1:length(Subject)
                        if strcmp(Subject(i),Subject(indices(1)))
                            app.InspectionTable.Data.Component_to_remove{i}=num2str(UnwantedComponents);
                            app.InspectionTable.Data.Inspection(i)=true;
                        end
                    end
                end   
            end

            disp('Inspection validated')
        end

        % Button pushed function: RunButton
        function RunICArecomposition(app, event)
             %% CHECK
            
            if app.SessionParameters.BatchProcessing.BatchExecution == 0  
                if isempty(app.DataTable)
                    msgbox(sprintf('%s\n\n%s','No data to analyse!', ...
                        'Please make sure to select the files to analyse in the main window.'),...
                        'No data selected','warn');
                    return
                end
                
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                        'Please make sure to select the output folder where to put the processed files.'),...
                        'No output flôlder selected','warn');
                    return
                end
            else %Batch mode
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s\n%s','No output folder selected.', ...
                    'The batch process has been interrupted because there is no output folder specified in the ICA module',...
                    'Please specify a output folder and press on run. To continue the batch after that, press the "RUN BATCH" button again from the main window!'),...
                    'No output folder specified','warn');
                    app.batchHasCrashed = true;
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert the batch status in the main EEGpal windows.
                    return
                end
            end


            %% PROCESSING INFORMATION SHEET

            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file            
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_ICA_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal ICA Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            fprintf(PifFileID,'%s\r\n','ICA decomposition/recomposition use EEGLAB tools and components labeling use ICLabel tool.');
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGLAB:',app.EEGpalCS_Main.Versions.eeglab);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of ICLabel:',app.EEGpalCS_Main.Versions.ICLabel);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','This processing was performed in Batch mode.');
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Bad component selection:',app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,2});
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','No');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            % if ~isempty(app.savePathElectrodCoordinateFile_EEGlab)
            %     fprintf(PifFileID,'%s\t%s\r\n','EEGlab Electrodes setting file used during Inspection:',app.savePathElectrodCoordinateFile_EEGlab);
            % end
           
            % Next line to be used in the futur when the conversion from
            % the Electrod setting from module 'Electrod setting' will be
            % automatically converted into a .locs file instead of asking
            % manually to select it.
            fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:}); 
 

            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ICA DECOMPOSITION');
            
            if app.DecompositionDone
                fprintf(PifFileID,'%s\t\t%s\r\n\r\n','These INPUT files has been decomposed in the temporary folder:',strcat(app.rootfolder,'\ICAtemp\decomposed'));
                
                fprintf(PifFileID,'\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
                Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
                fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            else
                fprintf(PifFileID,'%s\r\n','No decomposition has been compute for this run');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n','BAD CHANNELS IGNORE DURING ICA DECOMPOSITION');         
                            
            fprintf(PifFileID,'\r\n%s\r\n\r\n','INPUT FILES (File name  /  Subject  /  Bad channels');
            for p=1:size(app.BadChannelTable.Data,1)
                if ismissing(app.BadChannelTable.Data.Bad_channels(p))
                    fprintf(PifFileID,'%s\t\t%s\t\t%s\r\n',app.BadChannelTable.Data.FileNames(p),app.BadChannelTable.Data.Subject(p),"");
                else
                    fprintf(PifFileID,'%s\t\t%s\t\t%s\r\n',app.BadChannelTable.Data.FileNames(p),app.BadChannelTable.Data.Subject(p),app.BadChannelTable.Data.Bad_channels(p));
                end
            end


            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','COMPONENTS INSPECTION');
            
            fprintf(PifFileID,'%s\t%d\r\n','the maximum number of components inspected per file:',app.MaxnumberofcomponentstEditField.Value);
            fprintf(PifFileID,'\r\n%s\r\n','These files were inspected and the following components has been choosen to be removed:');
            
            fprintf(PifFileID,'\r\n%s\r\n\r\n','File name  /  Components choosen to be removed');
            sizeInspectionTable=size(app.InspectionTable.Data);
            for q=1:sizeInspectionTable(1)
                if (app.InspectionTable.Data.Inspection(q))
                    fprintf(PifFileID,'%s\t\t',app.InspectionTable.Data.FileNames{q});
                    if ~isempty(app.InspectionTable.Data.Component_to_remove{q})
                        sizeRejected=length(str2num(app.InspectionTable.Data.Component_to_remove{q}));
                        for r=1:sizeRejected
                            temp=str2num(app.InspectionTable.Data.Component_to_remove{q});
                            fprintf(PifFileID,'%d\t',temp(r));
                        end
                    end
                    fprintf(PifFileID,'\r\n');
                end
            end

            if app.hasUsedAutomaticDetection
                fprintf(PifFileID,'\r\n%s\t%s\r\n','Strategy used to inspect compounds:', 'Full automatic according ICAlabel');
            elseif and(app.AutomaticICAlabelSuggestion.Value,~app.hasUsedAutomaticDetection)
                fprintf(PifFileID,'\r\n%s\t%s\r\n','Strategy used to inspect compounds:', 'Visual inspection with the help of ICAlabel suggestions');
            else
                fprintf(PifFileID,'\r\n%s\t%s\r\n','Strategy used to inspect compounds:', 'Manual visual inspection (your are tough)');
            end

            if app.AutomaticICAlabelSuggestion.Value
                 fprintf(PifFileID,'\r\n%s\r\n','Threshold used by ICAlabel to tag bad compounds:');
                 fprintf(PifFileID,'%s\t%d\r\n','Eye (%):',app.EyeEditField.Value);
                 fprintf(PifFileID,'%s\t%d\r\n','Muscle (%):',app.MuscleEditField.Value);
                 fprintf(PifFileID,'%s\t%d\r\n','Heart (%):',app.HeartEditField.Value);
                 fprintf(PifFileID,'%s\t%d\r\n','Channel Noise (%):',app.ChannelNoiseEditField.Value);
                 fprintf(PifFileID,'%s\t%d\r\n','Line Noise (%):',app.LineNoiseEditField.Value);
            end
        
            
            %% RUN
            h = waitbar(0,'Recomposition of signal.....');

            sizeDataCell=size(app.NewDataCell);
            StartTime = datetime('now');
            tStart = tic;            
            
            NewDataCellAjusted = cell(1,sizeDataCell(2)); %cell to take account the file not inspected (remove them)
            AjustedIndice=0;
            
            %main loop
  
            for file = 1:sizeDataCell(1)
                waitbar(file/sizeDataCell(1),h, strcat('Saving results for file ',num2str(file),'/',num2str(sizeDataCell(1))));
                                
                %Recomposition of the EEG signal without bad selected components
                ALLEEG=struct; CURRENTSET=0;              

                if (app.InspectionTable.Data.Inspection(file)) %check if the file has been inspected and then recomposed
                    %Load dataset with EEGlab
                    EEG = pop_loadset('filename',app.NewDataCell{file,1},'filepath',fullfile(app.rootfolder,app.NewDataCell{file,2}(4:end)));
                    [ALLEEG, EEG, CURRENTSET] = eeg_store( ALLEEG, EEG, 0 );
                    
                    %restore xyz coordinate file for next steps (instead of
                    %.locs coordinate file used for inspection)
                    EEG.chanlocs=EEG.urchanlocs;
                    ALLEEG.chanlocs=ALLEEG.urchanlocs;
                    
                    %remove unwanted component and recompose the EEG signal
                    if ~isempty(app.InspectionTable.Data.Component_to_remove{file})
                        EEG = pop_subcomp( EEG,str2num(app.InspectionTable.Data.Component_to_remove{file}), 0);
                    end
                   
                    [ALLEEG, EEG, CURRENTSET] = eeg_store(ALLEEG, EEG, CURRENTSET);
                                       
                    %load the .set
                    [header,data,events] = open_eeglab_struct(EEG);                  

                       
                    % Save processed file
                    if ~isempty(app.SuffixString.Value)
                        suffix = ['_', app.SuffixString.Value];
                    else
                        suffix = app.SuffixString.Value; % = '';
                    end
                    
                    SubPath = app.DataTable.Path{file}(3:end);
                    NewPath = [app.SaveFolder.Value{:} SubPath];
                    % Creating the folder
                    if ~exist(NewPath, 'dir')
                        mkdir(NewPath);
                    end
                    
                    if ~app.isAlreadyDecomposed
                        savefilename = fullfile(NewPath,[app.DataTable.File{file}(1:end-4), suffix, app.FilesExtension.Value]);
                    else
                        savefilename = fullfile(NewPath,[app.DataTable.File{file}(1:end-27), suffix, app.FilesExtension.Value]);
                    end

                    disp(['Saving ',savefilename])
                    if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                        save_eph(savefilename,data,header.SamplingRate,events,header.firstindex)
                    elseif strcmp(app.FilesExtension.Value,'.sef')
                        try
                            Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                        catch
                            Channels = '';
                        end
                        save_sef(savefilename,data,header.SamplingRate,Channels,events,header.firstindex)
                    elseif strcmp(app.FilesExtension.Value,'.set')
                        try 
                            ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                            Channels            = ElectrodeSetting(:,2);
                            %Channels.labels     = char(ElectrodeSetting.labels);

                            % Recompute Theta and Radius to match EEGlab orientation
                            [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                            Channels.theta      = -rad2deg(az);
                            Channels.radius     = 0.5 - rad2deg(elev)/180;
                            Channels.sph_theta  = -Channels.theta;
                            Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                            Channels.X          = ElectrodeSetting.y;
                            Channels.Y          = -ElectrodeSetting.x;
                            Channels.Z          = ElectrodeSetting.z;
    %                         Channels.ref        = char(repmat({''},height(Channels),1));
    %                         Channels.sph_radius = repmat({[]},height(Channels),1);
    %                         Channels.types      = char(repmat({''},height(Channels),1));
    %                         Channels.urchan     = repmat({[]},height(Channels),1);
                            Channels            = table2struct(Channels);
                            save_eeglab(savefilename,data,header.SamplingRate,events,header.firstindex,Channels)
                        catch
                            save_eeglab(savefilename,data,header.SamplingRate,events,header.firstindex)
                        end
                     
                    end 
                    
                    %update Datatable
                    [~,FileNameOut,FileExtensionOut] = fileparts(savefilename);
                    app.NewDataCell{file,1}=strcat(FileNameOut,FileExtensionOut);
                    app.NewDataCell{file,2}=app.DataTable.Path{file};
                    
                    %ajust table for the main windows
                    AjustedIndice=AjustedIndice+1;
                    NewDataCellAjusted(AjustedIndice,:)=app.NewDataCell(file,:);  
                else
                    disp('one file has been ignore because not inspected')
                end
                
            end
            delete (h);           
            
            
            % Supression of the temporary files
            finalDecisionToDeletTEMP=false;
            if (strcmp(app.deleteIntermediateFile.Value,'yes'))
                finalDecision = questdlg(sprintf('%s\n\n%s',...
                    'You have choosen to supress temporary files generated during ICA processing',...
                    'Are you sure ? It is definitive'),...
                    'Delete temp files','YES','NO','NO');
                if strcmp(finalDecision,'YES')
                    if ~app.isAlreadyDecomposed
                        rmdir(fullfile(app.rootfolder,'ICAtemp'),'s');
                    else
                        rmdir(app.rootfolder,'s');
                    end
                    finalDecisionToDeletTEMP=true;
                end                
            end
            
            
            % UPDATE INFORMATION BASED ON THE PROCESSED DATA
                       
            % Data table in the Main Window
            app.EEGpalCS_Main.DataFilesTable.Data   = cell2table(NewDataCellAjusted,"VariableNames",["File" "Path" "nChan" "SamplingRate" "nTF" "Duration" "Subject" "Condition" "Factor_level"]);
            app.EEGpalCS_Main.FilesTable            = cell2table(NewDataCellAjusted,"VariableNames",["File" "Path" "nChan" "SamplingRate" "nTF" "Duration" "Subject" "Condition" "Factor_level"]);
            app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
            disp('Data table in the Main Window updated with the processed files')
            cd(app.SaveFolder.Value{:})

            
            % Finalization of PROCESSING INFORMATION SHEET
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','RECOMPOSITION OUTPUT');
            if finalDecisionToDeletTEMP
                fprintf(PifFileID,'%s\t\t%s\r\n\r\n','The temporary ''.\ICAtemp'' with intermediate results as been deleted:','YES');
            else
                fprintf(PifFileID,'%s\t\t%s\r\n\r\n','The temporary ''.\ICAtemp'' with intermediate results as been deleted:','NO');
            end
            
            fprintf(PifFileID,'\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Folder)');
            Files = [app.EEGpalCS_Main.FilesTable.File'; app.EEGpalCS_Main.FilesTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('ICA processing completed on:',{' '},datestr(datetime('now')))));
            fclose(PifFileID);

            
            %% END
            delete (h); 
            disp('ICA Finished and closed')           

            if app.SessionParameters.BatchProcessing.BatchExecution == 0 %close the module after the execution of batch               
                rz = msgbox('Data are recomposed and saved.');                
            else %batch mode module successly completed
                if or(app.batchHasCrashed, strcmp(app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,2},'Run only the ICA decomposition'))
                    app.SessionParameters.BatchProcessing.BatchExecution = 0; %break the batch
                end
                
                app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=1; %process complited
                app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert only Batch variable to the main window
                CancelButtonPushed(app); %to close the module without saving Session Parameters
            end            
      

        end

        % Cell edit callback: BadChannelTable
        function BadChannelTableCellEdit(app, event)
            indices = event.Indices;

            %fill the table if concatenate subject is active
            if app.ConcanenateSubject.Value
                Subject=[app.NewDataCell{:,7}]; %convert to string array for subjects
                for i=1:length(Subject)
                    if strcmp(Subject(i),Subject(indices(1)))
                        app.BadChannelTable.Data.Bad_channels(i)=app.BadChannelTable.Data.Bad_channels(indices(1));
                    end
                end
            end   

        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_ICA_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '\Manuels\Manuel_ICA_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '\Manuels\Manuel_ICA_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Value changed function: BridgedetectedSwitch
        function BridgedetectedSwitchValueChanged(app, event)
            if strcmp(app.BridgedetectedSwitch.Value,'On')
                updateBadChannelTableWithEbridge(app);
            else
                for i=1:size(app.BadChannelTable.Data,1)
                    app.BadChannelTable.Data.Bad_channels(i)=""; %erase all the table
                end
            end
            
        end

        % Value changed function: AutomaticICAlabelSuggestion
        function AutomaticICAlabelSuggestionValueChanged(app, event)
            if app.AutomaticICAlabelSuggestion.Value
                app.EyeEditField.Enable = "on"; app.EyeEditFieldLabel.Enable = "on";
                app.MuscleEditField.Enable = "on"; app.MuscleEditFieldLabel.Enable = "on";
                app.HeartEditField.Enable = "on"; app.HeartEditFieldLabel.Enable = "on";
                app.ChannelNoiseEditField.Enable = "on"; app.ChannelNoiseEditFieldLabel.Enable = "on";
                app.LineNoiseEditField.Enable = "on"; app.LineNoiseEditFieldLabel.Enable = "on";            
                app.NotethresholdLabel.Enable = "on";
                if app.Step1DoneCheckBox.Value
                    app.AutomaticInspectionButton.Enable = "on";
                    app.NOTRECOMMANDEDLabel.Enable = "on";
                end
            else
                app.EyeEditField.Enable = "off"; app.EyeEditFieldLabel.Enable = "off";
                app.MuscleEditField.Enable = "off"; app.MuscleEditFieldLabel.Enable = "off";
                app.HeartEditField.Enable = "off"; app.HeartEditFieldLabel.Enable = "off";
                app.ChannelNoiseEditField.Enable = "off"; app.ChannelNoiseEditFieldLabel.Enable = "off";
                app.LineNoiseEditField.Enable = "off"; app.LineNoiseEditFieldLabel.Enable = "off";
                app.AutomaticInspectionButton.Enable = "off";
                app.NOTRECOMMANDEDLabel.Enable = "off";
                app.NotethresholdLabel.Enable = "off";
            end            
        end

        % Button pushed function: AutomaticInspectionButton
        function AutomaticInspectionButtonPushed(app, event)
            app.automaticInspection = true;
            nbfile=size(app.InspectionTable.Data,1);
            h = waitbar(0,'Automatic components rejection of file: ');

            for i=1:nbfile
                waitbar(i/nbfile,h, ['Automatic components rejection of file ',num2str(i),'/',num2str(nbfile)]);
                eventFake.Indices = [i,4];
                InspectionTableCellEdit(app, eventFake);
                app.InspectionTable.Data.Inspection(i)=true;
            end

            delete (h); 
            app.automaticInspection = false;
            app.hasUsedAutomaticDetection = true;

            %In Batch mode
             if app.SessionParameters.BatchProcessing.BatchExecution == 1
                if strcmp(app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,2},'Automatic Bad Components Rejection') %directely run the components automatic rejection
                    RunICArecomposition(app);               
                end            
            end

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEGpalCS_ICA.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {760, 760, 760};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {760, 760};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {346, '1x', 269};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_ICA = uifigure('Visible', 'off');
            app.EEGpalCS_ICA.Name = 'EEGpal_ICA';
            app.EEGpalCS_ICA.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_ICACloseRequest, true);
            app.EEGpalCS_ICA.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEGpalCS_ICA);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Step1WarningLabel = uilabel(app.LeftPanel);
            app.Step1WarningLabel.Text = 'This button will start the ICA decomposition of all the files listed in the main windows. This step could take serval hours depending on the number of files. ';
            app.ICALabel = uilabel(app.LeftPanel);
            app.ICALabel.Text = 'ICA';
            app.TitleStep1 = uilabel(app.LeftPanel);
            app.TitleStep1.Text = 'Step1: Decomposition';
            app.StartICAdecompositionButton = uibutton(app.LeftPanel, 'push');
            app.StartICAdecompositionButton.ButtonPushedFcn = createCallbackFcn(app, @RunICAdecomposition, true);
            app.StartICAdecompositionButton.Text = 'Start ICA decomposition';
            app.Step1DoneCheckBox = uicheckbox(app.LeftPanel);
            app.Step1DoneCheckBox.Enable = 'off';
            app.Step1DoneCheckBox.Text = {'Step1 '; 'Done'};
            app.ConcanenateSubject = uicheckbox(app.LeftPanel);
            app.ConcanenateSubject.Text = 'Concatenate files from the same subjects';
            app.Step1WarningLabel_2 = uilabel(app.LeftPanel);
            app.Step1WarningLabel_2.Text = 'If you have multiple runs/files within each participant, it is strongly recommended that you run a single ICA decomposition (concatenate files) to avoid introducing processing variability into your results (spetially if experimental conditions are in different files).';
            app.TitleStep0 = uilabel(app.LeftPanel);
            app.TitleStep0.Text = 'Step0: Ignore bad channel(s)';
            app.BadChannelTable = uitable(app.LeftPanel);
            app.BadChannelTable.ColumnName = {'FileNames'; 'Subject'; 'Bad_channels'};
            app.BadChannelTable.RowName = {};
            app.BadChannelTable.ColumnEditable = [false false true];
            app.BadChannelTable.CellEditCallback = createCallbackFcn(app, @BadChannelTableCellEdit, true);
            app.Step0WarningLabel = uilabel(app.LeftPanel);
            app.Step0WarningLabel.Text = 'Specity in Bad_channels colomn the indice(s) to ignore, separate by space (e.g 1 2 64). Leave <missing> if there is no bad channels to ignore. Use this option to removed bridged channels (detect with Bridge Detection module) and noisy electrodes determined after visual inspection of the signal. ';
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.BridgedetectedSwitchLabel = uilabel(app.LeftPanel);
            app.BridgedetectedSwitchLabel.Enable = 'off';
            app.BridgedetectedSwitchLabel.Text = 'Bridge detected';
            app.BridgedetectedSwitch = uiswitch(app.LeftPanel, 'slider');
            app.BridgedetectedSwitch.ValueChangedFcn = createCallbackFcn(app, @BridgedetectedSwitchValueChanged, true);
            app.BridgedetectedSwitch.Enable = 'off';
            app.CenterPanel = uipanel(app.GridLayout);
            app.CenterPanel.Tooltip = {'This value should be smaller than the number of decomposed electrodes minus one.'};
            app.TitleStep2 = uilabel(app.CenterPanel);
            app.TitleStep2.Text = 'Step2: Components inspection';
            app.FilteringInstruction_2 = uilabel(app.CenterPanel);
            app.FilteringInstruction_2.Text = {'Click on the checkbox to inspect the ICA components for each file individually. '; ''; 'Please enter manually the indices of the component to be removed. Don’t use the classical buttons on the ‘Reject components by map’ windows of eeglab (it does not work). '; ''; 'If the option ''Concatenare files from same subjects'' is activated, the Component_to_remove choice will spread to all files of the same participant. '};
            app.InspectionTable = uitable(app.CenterPanel);
            app.InspectionTable.ColumnName = {'FileNames'; 'Subject'; 'Component_to_remove'; 'Inspection'};
            app.InspectionTable.RowName = {};
            app.InspectionTable.ColumnEditable = [false false false true];
            app.InspectionTable.CellEditCallback = createCallbackFcn(app, @InspectionTableCellEdit, true);
            app.InspectionTable.Enable = 'off';
            app.TitleAssistance = uilabel(app.CenterPanel);
            app.TitleAssistance.Text = 'ICAlabel ASSISTANCE';
            app.AutomaticICAlabelSuggestion = uicheckbox(app.CenterPanel);
            app.AutomaticICAlabelSuggestion.ValueChangedFcn = createCallbackFcn(app, @AutomaticICAlabelSuggestionValueChanged, true);
            app.AutomaticICAlabelSuggestion.Text = 'Suggest noise components automatically according to the specified threshold below';
            app.ChannelNoiseEditFieldLabel = uilabel(app.CenterPanel);
            app.ChannelNoiseEditFieldLabel.Enable = 'off';
            app.ChannelNoiseEditFieldLabel.Text = 'Channel Noise (%)';
            app.ChannelNoiseEditField = uieditfield(app.CenterPanel, 'numeric');
            app.ChannelNoiseEditField.Limits = [0 100];
            app.ChannelNoiseEditField.RoundFractionalValues = 'on';
            app.ChannelNoiseEditField.Enable = 'off';
            app.ChannelNoiseEditField.Value = 100;
            app.EyeEditFieldLabel = uilabel(app.CenterPanel);
            app.EyeEditFieldLabel.Enable = 'off';
            app.EyeEditFieldLabel.Text = 'Eye (%)';
            app.EyeEditField = uieditfield(app.CenterPanel, 'numeric');
            app.EyeEditField.Limits = [0 100];
            app.EyeEditField.RoundFractionalValues = 'on';
            app.EyeEditField.Enable = 'off';
            app.EyeEditField.Value = 90;
            app.MuscleEditFieldLabel = uilabel(app.CenterPanel);
            app.MuscleEditFieldLabel.Enable = 'off';
            app.MuscleEditFieldLabel.Text = 'Muscle (%)';
            app.MuscleEditField = uieditfield(app.CenterPanel, 'numeric');
            app.MuscleEditField.Limits = [0 100];
            app.MuscleEditField.RoundFractionalValues = 'on';
            app.MuscleEditField.Enable = 'off';
            app.MuscleEditField.Value = 90;
            app.HeartEditFieldLabel = uilabel(app.CenterPanel);
            app.HeartEditFieldLabel.Enable = 'off';
            app.HeartEditFieldLabel.Text = 'Heart (%)';
            app.HeartEditField = uieditfield(app.CenterPanel, 'numeric');
            app.HeartEditField.Limits = [0 100];
            app.HeartEditField.RoundFractionalValues = 'on';
            app.HeartEditField.Enable = 'off';
            app.HeartEditField.Value = 100;
            app.LineNoiseEditFieldLabel = uilabel(app.CenterPanel);
            app.LineNoiseEditFieldLabel.Enable = 'off';
            app.LineNoiseEditFieldLabel.Text = 'Line Noise (%)';
            app.LineNoiseEditField = uieditfield(app.CenterPanel, 'numeric');
            app.LineNoiseEditField.Limits = [0 100];
            app.LineNoiseEditField.RoundFractionalValues = 'on';
            app.LineNoiseEditField.Enable = 'off';
            app.LineNoiseEditField.Value = 100;
            app.AutomaticInspectionButton = uibutton(app.CenterPanel, 'push');
            app.AutomaticInspectionButton.ButtonPushedFcn = createCallbackFcn(app, @AutomaticInspectionButtonPushed, true);
            app.AutomaticInspectionButton.Enable = 'off';
            app.AutomaticInspectionButton.Text = 'Automatic selection of bad compound with ICAlabel suggestions ';
            app.NOTRECOMMANDEDLabel = uilabel(app.CenterPanel);
            app.NOTRECOMMANDEDLabel.Enable = 'off';
            app.NOTRECOMMANDEDLabel.Text = {'! NOT RECOMMANDED !'; 'Better to check visually'};
            app.MaxnumberofcomponentstoinspectperfileEditFieldLabel = uilabel(app.CenterPanel);
            app.MaxnumberofcomponentstoinspectperfileEditFieldLabel.Text = 'Max number of components to inspect per file';
            app.MaxnumberofcomponentstEditField = uieditfield(app.CenterPanel, 'numeric');
            app.MaxnumberofcomponentstEditField.Limits = [1 Inf];
            app.MaxnumberofcomponentstEditField.RoundFractionalValues = 'on';
            app.MaxnumberofcomponentstEditField.Value = 24;
            app.NotethresholdLabel = uilabel(app.CenterPanel);
            app.NotethresholdLabel.Enable = 'off';
            app.NotethresholdLabel.Text = {'Note: '; 'threshold 100 = not tested'};
            app.RightPanel = uipanel(app.GridLayout);
            app.CleanRawDataInstructionLabel = uilabel(app.RightPanel);
            app.CleanRawDataInstructionLabel.Text = {'Reconstruct your EEG signal without the components tagged during step2. '; 'Only the files tick as inspected will be considered. '; ''};
            app.OUTPUTOPTIONSLabel = uilabel(app.RightPanel);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.RightPanel);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.RightPanel);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.RightPanel);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.RightPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.RightPanel);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.RightPanel);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.RightPanel);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.RightPanel, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'ICApruned';
            app.APPLYLabel = uilabel(app.RightPanel);
            app.APPLYLabel.Text = 'APPLY';
            app.SaveinmemoryButton = uibutton(app.RightPanel, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window without applying the processing and save the parameters.'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.RightPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.TitleStep3 = uilabel(app.RightPanel);
            app.TitleStep3.Text = 'Step3: Components rejection';
            app.RunButton = uibutton(app.RightPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunICArecomposition, true);
            app.RunButton.Enable = 'off';
            app.RunButton.Text = 'Run';
            app.DeleteintermediatefilesLabel = uilabel(app.RightPanel);
            app.DeleteintermediatefilesLabel.Text = 'Delete ICA decomposition intermediate file';
            app.deleteIntermediateFile = uidropdown(app.RightPanel);
            app.deleteIntermediateFile.Items = {'no', 'yes'};
            app.deleteIntermediateFile.Value = 'no';
            app.ICAintroduction = uilabel(app.RightPanel);
            app.ICAintroduction.Text = {'Independent Compound Analysis (ICA) allows the EEG signal to be decomposed into several compounds (step 1), the bad compounds to be chosen (step 2), and the EEG signal to be rebuilt without the noise components (step 3). Perform these three steps consecutively without closing the window, or click the ''Save in memory + save processing parameter'' button in the main window.'; 'Use eeglab tools.'};
            % Show the figure after all components are created
            app.EEGpalCS_ICA.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_ICA(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_ICA)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_ICA)
        end
    end
end