classdef EEGpalCS_mod_PeaksTraceExport < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpal_PeaksTraceExport         matlab.ui.Figure
        OutputformatDropDown            matlab.ui.control.DropDown
        OutputformatDropDownLabel       matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        InstructionPeak                 matlab.ui.control.Label
        InstructionCheck                matlab.ui.control.Label
        InstructElements1_2             matlab.ui.control.Label
        OptionalSuffix                  matlab.ui.control.EditField
        OptionalsuffixinoutputfilenameEditFieldLabel  matlab.ui.control.Label
        ProcessingChoice                matlab.ui.control.DropDown
        WhatprocessingshouldIdoDropDownLabel  matlab.ui.control.Label
        InstructionOutputTime_2         matlab.ui.control.Label
        CheckfileDropDown               matlab.ui.control.DropDown
        InstructionOutputTime           matlab.ui.control.Label
        SelectOutputTime                matlab.ui.control.DropDown
        InstructWarning                 matlab.ui.control.Label
        InstructPeak1                   matlab.ui.control.Label
        PeakDropDown                    matlab.ui.control.DropDown
        Instruct_timeinter1             matlab.ui.control.Label
        SamplingRate                    matlab.ui.control.NumericEditField
        SamplingRateinHzEditFieldLabel  matlab.ui.control.Label
        GMDCheckBox                     matlab.ui.control.CheckBox
        GFPCheckBox                     matlab.ui.control.CheckBox
        AverageCheckBox                 matlab.ui.control.CheckBox
        Epoch_RelativeInterval1_3       matlab.ui.control.Label
        ElectrodesEditField             matlab.ui.control.EditField
        ElectrodesEditFieldLabel        matlab.ui.control.Label
        IntervalOffset_ms               matlab.ui.control.NumericEditField
        IntervaloffsetLabel             matlab.ui.control.Label
        EndOfFile                       matlab.ui.control.StateButton
        IntervalOffset_tf               matlab.ui.control.NumericEditField
        IntervalOnset_ms                matlab.ui.control.NumericEditField
        IntervalonsetLabel              matlab.ui.control.Label
        IntervalOnset_tf                matlab.ui.control.NumericEditField
        tfLabel                         matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        Epoch_RelativeInterval1_2       matlab.ui.control.Label
        Epoch_RelativeInterval1         matlab.ui.control.Label
        InstructElements1               matlab.ui.control.Label
        InstructModule                  matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        SaveinmemoryButton              matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        APPLYLabel                      matlab.ui.control.Label
        SaveFolder                      matlab.ui.control.TextArea
        SavingfolderLabel               matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        InstructionFolderLabel          matlab.ui.control.Label
        OUTPUTOPTIONSLabel              matlab.ui.control.Label
        PeakDetectionorTraceExportLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        SettingTable
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function tf = ms2tf(~,ms,sr)
            tf = round((ms / 1000) * sr, 0); % round() because time-frames are counted as integer numbers
                                             % => requires to recompute tf2ms for inputs, to be more precise
        end
       
        
        function ms = tf2ms(~,tf,sr)
            ms = (tf / sr) * 1000;
        end
        
        function [AmpValue,PosValue,checkValue] = peakDetection(app,data,onset_TF,offset_TF,channel,GfpGmd)
            
            %Function to detect peaks
            
            %detection of peaks
            if or(strcmp (app.PeakDropDown.Value,'Positive'),GfpGmd) %variable GfpGmd permit to force the detecton of Max pour GFP and Dis                    
                [peakValue,peakPos]= findpeaks(data(onset_TF+1:offset_TF+1,channel),onset_TF+1:offset_TF+1); %positive peak
            else
                [peakValue,peakPos]= findpeaks(-data(onset_TF+1:offset_TF+1,channel),onset_TF+1:offset_TF+1); %negative peak
                peakValue=-peakValue;
            end
             
            %Management of special case             
            if isempty(peakValue) %no peak detected
                checkValue=1;
                AmpValue=[];
                PosValue=[];
                
            elseif length(peakValue)>1 %several peak detected
                checkValue=2;
                
                %save gobal amplitude
                if or(strcmp (app.PeakDropDown.Value,'Positive'),GfpGmd) 
                    AmpValue=max(peakValue); 
                else
                    AmpValue=min(peakValue); 
                end
                
                %save position in ms of TF    
                if strcmp(app.SelectOutputTime.Value,'ms') 
                     PosValue=tf2ms(app,peakPos(find(peakValue==AmpValue))-1,app.SamplingRate.Value);
                else
                     PosValue=peakPos(find(peakValue==AmpValue))-1;
                end

            else %normal case
                checkValue=0;
                
                %save amplitude
                AmpValue=peakValue; 
                
                %save position in ms of TF
                if strcmp(app.SelectOutputTime.Value,'ms') 
                    PosValue=tf2ms(app,peakPos-1,app.SamplingRate.Value);
                else
                    PosValue=peakPos-1;
                end
                
            end
        end
        
        function applySessionParam(app)
            % PROCESSING CHOICE            
            app.ProcessingChoice.Value             = app.SessionParameters.PeaksTraceExport.ProcessingChoice;
            ProcessingChoiceValueChanged(app);

            % TIMING OPTION
            app.IntervalOnset_tf.Value             = app.SessionParameters.PeaksTraceExport.IntervalOnset_tf;
            IntervalOnset_tfValueChanged(app);
            app.IntervalOffset_tf.Value            = app.SessionParameters.PeaksTraceExport.IntervalOffset_tf;
            IntervalOffset_tfValueChanged(app);

            % ELECTRODES
            app.ElectrodesEditField.Value          = app.SessionParameters.PeaksTraceExport.ElectrodesEditField;
            ElectrodesEditFieldValueChanged(app);
            app.PeakDropDown.Value                 = app.SessionParameters.PeaksTraceExport.PeakDropDown;
            app.AverageCheckBox.Value              = app.SessionParameters.PeaksTraceExport.AverageCheckBox;
            app.GFPCheckBox.Value                  = app.SessionParameters.PeaksTraceExport.GFPCheckBox;
            app.GMDCheckBox.Value                  = app.SessionParameters.PeaksTraceExport.GMDCheckBox;

            % OUTPUT OPTION
            app.OutputformatDropDown.Value         = app.SessionParameters.PeaksTraceExport.OutputformatDropDown;
            app.SelectOutputTime.Value             = app.SessionParameters.PeaksTraceExport.SelectOutputTime;
            SelectOutputTimeValueChanged(app);
            app.SaveFolder.Value                   = app.SessionParameters.PeaksTraceExport.SaveFolder;
            app.OptionalSuffix.Value               = app.SessionParameters.PeaksTraceExport.OptionalSuffix;
            app.CheckfileDropDown.Value            = app.SessionParameters.PeaksTraceExport.CheckfileDropDown;
            
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            % PROCESSING CHOICE            
            SessionParameters.PeaksTraceExport.ProcessingChoice             = app.ProcessingChoice.Value;

            % TIMING OPTION
            SessionParameters.PeaksTraceExport.IntervalOnset_tf            = app.IntervalOnset_tf.Value;
            SessionParameters.PeaksTraceExport.IntervalOffset_tf           = app.IntervalOffset_tf.Value;

            % ELECTRODES
            SessionParameters.PeaksTraceExport.ElectrodesEditField          = app.ElectrodesEditField.Value;
            SessionParameters.PeaksTraceExport.PeakDropDown                 = app.PeakDropDown.Value;
            SessionParameters.PeaksTraceExport.AverageCheckBox              = app.AverageCheckBox.Value;
            SessionParameters.PeaksTraceExport.GFPCheckBox                  = app.GFPCheckBox.Value;
            SessionParameters.PeaksTraceExport.GMDCheckBox                  = app.GMDCheckBox.Value;

            % OUTPUT OPTION
            SessionParameters.PeaksTraceExport.OutputformatDropDown         = app.OutputformatDropDown.Value;
            SessionParameters.PeaksTraceExport.SelectOutputTime             = app.SelectOutputTime.Value;
            SessionParameters.PeaksTraceExport.SaveFolder                   = app.SaveFolder.Value;
            SessionParameters.PeaksTraceExport.OptionalSuffix               = app.OptionalSuffix.Value;
            SessionParameters.PeaksTraceExport.CheckfileDropDown            = app.CheckfileDropDown.Value;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            %Note: All function and variable are called GMD for the Dis. It
            %was a mistake because the Dis from Cartool is not the same
            %value as the GMD from RAGU. I have changed all the output
            %texte but I have keep the GMD name for the code for safety
            %reasons. 
            
            
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            %Sampling Rate
            app.SamplingRate.Value          = app.DataTable.SamplingRate(1);

            % Apply Session Parameters
            try
                applySessionParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end

        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpal_PeaksTraceExport); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %number of files
            [nbFiles,~]=size(app.DataTable); 
            
            
            %% CHECK
            
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','error');
                return
            end
            
            if app.SamplingRate.Value == 1 || app.SamplingRate.Value == 0 
                 msgbox(sprintf('%s\n\n%s','Missing Sampling rate!', ...
                        'The sampling rate is not specified. Please specify it before to continue'),...
                        'Missing Sampling rate','error');
                 return     
            end
            
            if and (app.IntervalOffset_tf.Value == 0, strcmp(app.ProcessingChoice.Value, 'Peaks detection'))
                 msgbox(sprintf('%s\n\n%s','Incorrect interval!', ...
                        'Interval is incorrect. Please specify on offset value'),...
                        'Incorrect Interval','error');
                        return 
            end
            
            if  app.IntervalOffset_tf.Value<app.IntervalOnset_tf.Value
                 msgbox(sprintf('%s\n\n%s','Incorrect Offset time!', ...
                        'The specified offset time is smaller than the onset time. Please select a bigger or equal value.'),...
                        'Incorrect offset','error');
                        return 
            end

            % check that the time interval has at least 2TF if Dis is activated
            if app.GMDCheckBox.Value && (app.IntervalOffset_tf.Value-app.IntervalOnset_tf.Value+1)<2
                 msgbox(sprintf('%s\n\n%s','Incompatible time interval with the Dis!', ...
                        'Your time interval lasts only one TF which is incompatible with the computation of the Dis. It required at least two TF. Please desactivate the Dis or change the time interval.'),...
                        'Incompatible time interval with the Dis','error');
                        return 
            end
            
                        
            if strcmp(app.ProcessingChoice.Value, 'Peaks detection')
                Run = questdlg(sprintf('%s\n%s\n\n%s\n\n%s',...
                'Guten Tag!',...
                'Me about to detect the peak (global maxima) in the specified interval and electrodes.',...
                'I will record an file at the specified location',...
                'Do you wish to proceed? '),'Run checked options','OK','Cancel','Cancel');
            elseif strcmp(app.ProcessingChoice.Value, 'Average time interval')
                Run = questdlg(sprintf('%s\n%s\n\n%s\n\n%s',...
                'Get Ready!',...
                'Me about to export the average time in the specified interval for the specified electrodes',...
                'I will record an file at the specified location with all participants''result',...
                'Do you wish to proceed? '),'Run checked options','OK','Cancel','Cancel');
            else
                Run = questdlg(sprintf('%s\n%s\n\n%s\n\n%s',...
                'Get Ready!',...
                'Me about to export the timecourse in the specified interval for the specified electrodes',...
                'I will record an file at the specified location with all participants''result',...
                'Do you wish to proceed? '),'Run checked options','OK','Cancel','Cancel');
            end
            
            if strcmpi(Run,'Cancel')
                return
            end
            
           
            %% PROCESSING INFORMATION SHEET
            
            currentVersionModule = dir([mfilename('fullpath') '.mlapp']); % Information about current file


            %% RUN MODULE
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            
            %check the number of specified electrodes
            electrodesSpec=[];
            if ~isempty(app.ElectrodesEditField.Value)
                electrodesSpec=str2num(app.ElectrodesEditField.Value);
            end
            
            %check if specified all electrodes
            if strcmp(app.ElectrodesEditField.Value,'*')
                electrodesSpec=[1:1:ProcessedDataTable.nChan(1)];
            end


            %compute the number of element which will generated/studied
            nbValue=length(electrodesSpec); 
            if app.AverageCheckBox.Value
                nbValue=nbValue+1;
            end
            if app.GFPCheckBox.Value
                nbValue=nbValue+1;
            end
            if app.GMDCheckBox.Value
                nbValue=nbValue+1;
            end
            
            %initivalisation of output 
            if strcmp(app.ProcessingChoice.Value, 'Peaks detection') %for peak detection              
                output=cell(nbFiles,nbValue*2+2); %will contains values
                outputHeader=cell(1,nbValue*2+2); %will contains headers
                check=cell(nbFiles,nbValue+1);
                checkHeader=cell(1,nbValue+1); 
                
                outputHeader{1,1}='Files'; checkHeader{1,1}='Files';
 
                disp('Run peaks detection detection...')
            elseif strcmp(app.ProcessingChoice.Value, 'Average time interval') %for value extraction detection               
                output=cell(nbFiles,nbValue+1); %will contains values
                outputHeader=cell(1,nbValue+1); %will contains headers
                
                outputHeader{1,1}='Files';
                
                disp('Run Average value extraction...')
            else
                nbTF=(app.IntervalOffset_tf.Value+1)-(app.IntervalOnset_tf.Value+1)+1;
                
                if strcmp(app.SelectOutputTime.Value,'2D files')                    
                    output=cell(nbTF,nbValue+1); %will contains values
                    outputHeader=cell(1,nbValue+1); %will contains headers                
                    outputHeader{1,1}='TF';
                    tempsavefileNameOutput=strings([nbFiles,1]);
                else
                    output=zeros(nbTF,nbValue,nbFiles);
                end
            
            end


            StartTime = datetime('now');
            tStart = tic;
            h = waitbar(0,'Processing.....');
            
            for file = 1:nbFiles  % Load file by file
                waitbar(file/nbFiles,h, strcat('Processing File ',num2str(file),'/',num2str(nbFiles)));
                
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                    for k=1:header.NumChan
                        header.Channels(k,1)=strcat("e",num2str(k));
                    end
                elseif strcmpi(FileExtension,'.ris')
                    [header,data] = open_ris(openfilename);
                    for k=1:header.NumSP
                        header.Channels(k,1)=strcat("sp",num2str(k));
                    end
                else
                    if strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq files.')                       
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    %continue
                end
                if exist('data','var')                                
                    disp('File loaded!')
                else
                    msgbox(sprintf('%s\n\n%s','Invalid file format!', ...
                    'The processing can not continue with the current file selection. The file format is porbabely not supported.'),...
                    'File format not supported','error');
                    return
                end

                
                %% Run peak detection             
                if strcmp(app.ProcessingChoice.Value, 'Peaks detection')
    
                    output{file,1}=FileName;              
                    output_nbColomn=1;
                    check{file,1}=FileName;
                    
                    %Go through each electrodes
                    for j=1:length(electrodesSpec) %loop over electrodes
                        
                        %detection of peaks for the electrodes
                        output_nbColomn=output_nbColomn+1;
                        [output{file,output_nbColomn},output{file,output_nbColomn+nbValue+1},check{file,output_nbColomn}] = peakDetection(app,data,app.IntervalOnset_tf.Value,app.IntervalOffset_tf.Value,electrodesSpec(j),logical(0));
                        
                        %headers                        
                        outputHeader{1,output_nbColomn}=strcat('Amp_',header.Channels(electrodesSpec(j),:)); %header amp
                        outputHeader{1,output_nbColomn+nbValue+1}=strcat('Pos_',app.SelectOutputTime.Value,'_',header.Channels(electrodesSpec(j),:)); %header position
                        checkHeader{1,output_nbColomn}=strcat('Check_',header.Channels(electrodesSpec(j),:)); %header check
                                               
                        %compute of the sum of selected electrodes
                        if j==1
                            sumElectrodes=data(:,electrodesSpec(j));
                        else
                            sumElectrodes=sumElectrodes+data(:,electrodesSpec(j));
                        end
                        
                    end
                    
                    %Look at the Average
                    if app.AverageCheckBox.Value
                        avgElectrode=sumElectrodes./length(electrodesSpec);
                        
                        output_nbColomn=output_nbColomn+1;
                        [output{file,output_nbColomn},output{file,output_nbColomn+nbValue+1},check{file,output_nbColomn}] = peakDetection(app,avgElectrode,app.IntervalOnset_tf.Value,app.IntervalOffset_tf.Value,1,logical(0));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='Amp_Average'; %header amp
                        outputHeader{1,output_nbColomn+nbValue+1}=strcat('Pos_',app.SelectOutputTime.Value,'_Average'); %header position
                        checkHeader{1,output_nbColomn}='Check_Average'; %header check
                    end
                    
                    %Look at the GFP
                    if app.GFPCheckBox.Value
                        GFP=compute_gfp(data);
                        
                        output_nbColomn=output_nbColomn+1;
                        [output{file,output_nbColomn},output{file,output_nbColomn+nbValue+1},check{file,output_nbColomn}] = peakDetection(app,GFP,app.IntervalOnset_tf.Value,app.IntervalOffset_tf.Value,1,logical(1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='Amp_GFP'; %header amp
                        outputHeader{1,output_nbColomn+nbValue+1}=strcat('Pos_',app.SelectOutputTime.Value,'_GFP'); %header position
                        checkHeader{1,output_nbColomn}='Check_GFP'; %header check
                    end
                    
                    %Look at the Dis
                    if app.GMDCheckBox.Value
                        GMD=compute_gmd(data);
                        
                        output_nbColomn=output_nbColomn+1;
                        [output{file,output_nbColomn},output{file,output_nbColomn+nbValue+1},check{file,output_nbColomn}] = peakDetection(app,GMD,app.IntervalOnset_tf.Value,app.IntervalOffset_tf.Value,1,logical(1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='Amp_Dis'; %header amp
                        outputHeader{1,output_nbColomn+nbValue+1}=strcat('Pos_',app.SelectOutputTime.Value,'_Dis'); %header position
                        checkHeader{1,output_nbColomn}='Check_Dis'; %header check
                    end
                    
                    %Save the output file
                    if strcmp(app.OutputformatDropDown.Value,'.xlsx')
                        if strcmp(app.SelectOutputTime.Value,'ms')
                            fileNameOutput=fullfile(app.SaveFolder.Value{1},['PeakDetection_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.xlsx']);
                        else
                            fileNameOutput=fullfile(app.SaveFolder.Value{1},['PeakDetection_',app.PeakDropDown.Value,'_',num2str(app.IntervalOnset_tf.Value),'-',num2str(app.IntervalOffset_tf.Value),'tf_',app.OptionalSuffix.Value,'.xlsx']);                      
                        end
                        writecell([outputHeader;output],fileNameOutput);
                    else
                        if strcmp(app.SelectOutputTime.Value,'ms')
                            fileNameOutput=fullfile(app.SaveFolder.Value{1},['PeakDetection_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.txt']);
                        else
                            fileNameOutput=fullfile(app.SaveFolder.Value{1},['PeakDetection_',app.PeakDropDown.Value,'_',num2str(app.IntervalOnset_tf.Value),'-',num2str(app.IntervalOffset_tf.Value),'tf_',app.OptionalSuffix.Value,'.txt']);                      
                        end
                        writecell([outputHeader;output],fileNameOutput,'Delimiter','tab');
                    end
                    
                    %Save the check file
                    if strcmp(app.OutputformatDropDown.Value,'.xlsx')
                        if strcmp(app.CheckfileDropDown.Value,'Yes')
                            if strcmp(app.SelectOutputTime.Value,'ms')
                                fileNameCheck=fullfile(app.SaveFolder.Value{1},['PeakDetectionToCheck_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.xlsx']);
                            else
                                fileNameCheck=fullfile(app.SaveFolder.Value{1},['PeakDetectionToCheck_',app.PeakDropDown.Value,'_',num2str(app.IntervalOnset_tf.Value),'-',num2str(app.IntervalOffset_tf.Value),'tf_',app.OptionalSuffix.Value,'.xlsx']);                      
                            end
                            writecell([checkHeader;check],fileNameCheck);
                        end
                    else
                        if strcmp(app.CheckfileDropDown.Value,'Yes')
                            if strcmp(app.SelectOutputTime.Value,'ms')
                                fileNameCheck=fullfile(app.SaveFolder.Value{1},['PeakDetectionToCheck_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.txt']);
                            else
                                fileNameCheck=fullfile(app.SaveFolder.Value{1},['PeakDetectionToCheck_',app.PeakDropDown.Value,'_',num2str(app.IntervalOnset_tf.Value),'-',num2str(app.IntervalOffset_tf.Value),'tf_',app.OptionalSuffix.Value,'.txt']);                      
                            end
                            writecell([checkHeader;check],fileNameCheck,'Delimiter','tab');
                        end
                    end

                %% Average Time interval    
                elseif strcmp(app.ProcessingChoice.Value, 'Average time interval')
                
                    output{file,1}=FileName;              
                    output_nbColomn=1;
                
                    for j=1:length(electrodesSpec) %loop over electrodes
                        
                        %Average value
                        output_nbColomn=output_nbColomn+1;
                        output{file,output_nbColomn}=mean(data(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,electrodesSpec(j)));
                        
                        %headers
                        outputHeader{1,output_nbColomn}=strcat('TimeAvg_',header.Channels(electrodesSpec(j),:));
                        
                        %compute of the sum of selected electrodes
                        if j==1
                            sumElectrodes=data(:,electrodesSpec(j));
                        else
                            sumElectrodes=sumElectrodes+data(:,electrodesSpec(j));
                        end
                    end
                    
                    %Look at electrod mean
                    if app.AverageCheckBox.Value
                        avgElectrode=sumElectrodes./length(electrodesSpec);
                        
                        output_nbColomn=output_nbColomn+1;
                        output{file,output_nbColomn}=mean(avgElectrode(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='TimeAvg_ElectodesAvg'; %header amp                       
                    end
                    
                    %Look at the GFP
                    if app.GFPCheckBox.Value
                        GFP=compute_gfp(data);
                        
                        output_nbColomn=output_nbColomn+1;
                        output{file,output_nbColomn}=mean(GFP(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='TimeAvg_GFP'; %header amp                        
                    end
                    
                    %Look at the GMD
                    if app.GMDCheckBox.Value
                        GMD=compute_gmd(data);
                        
                        output_nbColomn=output_nbColomn+1;
                        output{file,output_nbColomn}=mean(GMD(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                    
                        %headers
                        outputHeader{1,output_nbColomn}='Dis'; %header amp                        
                    end
                    
                    %Save the output file                    
                    if strcmp(app.OutputformatDropDown.Value,'.xlsx')
                        fileNameOutput=fullfile(app.SaveFolder.Value{1},['AverageTimeInterval_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.xlsx']);                    
                        writecell([outputHeader;output],fileNameOutput);
                    else
                        fileNameOutput=fullfile(app.SaveFolder.Value{1},['AverageTimeInterval_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt']);                    
                        writecell([outputHeader;output],fileNameOutput,'Delimiter','tab');
                    end

                %% Timecourse extraction
                else
                    output_nbColomn=1;

                    if strcmp(app.SelectOutputTime.Value,'2D files') 
                        %% save 2D files
                        output=cell(nbTF,nbValue+1); %reset output
                        output(:,1)=num2cell([app.IntervalOnset_tf.Value:1:app.IntervalOffset_tf.Value]);

                        for j=1:length(electrodesSpec) %loop over electrodes
                            
                            %Time course extraction per electrodes
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn)=num2cell(data(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,electrodesSpec(j)));
                            
                            %headers
                            if isfield(header, 'Channels')
                                outputHeader{1,output_nbColomn}=strcat(header.Channels(electrodesSpec(j),:));
                            elseif ~isempty(app.SessionParameters.Electrodes.SettingTable)
                                outputHeader{1,output_nbColomn}=strcat(app.SessionParameters.Electrodes.SettingTable.labels(electrodesSpec(j)));
                            else
                                outputHeader{1,output_nbColomn}=strcat('e',num2str(electrodesSpec(j)));
                            end
                            
                            %compute of the sum of selected electrodes
                            if j==1
                                sumElectrodes=data(:,electrodesSpec(j));
                            else
                                sumElectrodes=sumElectrodes+data(:,electrodesSpec(j));
                            end
                        end
                        
                        %Look at electrod mean
                        if app.AverageCheckBox.Value
                            avgElectrode=sumElectrodes./length(electrodesSpec);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn)=num2cell(avgElectrode(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                        
                            %headers
                            outputHeader{1,output_nbColomn}='ElectodesAvg'; %header amp                       
                        end
                        
                        %Look at the GFP
                        if app.GFPCheckBox.Value
                            GFP=compute_gfp(data);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn)=num2cell(GFP(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                        
                            %headers
                            outputHeader{1,output_nbColomn}='GFP'; %header amp                        
                        end
                        
                        %Look at the GMD
                        if app.GMDCheckBox.Value
                            GMD=compute_gmd(data);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn)=num2cell(GMD(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1));
                        
                            %headers
                            outputHeader{1,output_nbColomn}='Dis'; %header amp                        
                        end
                        
                        %Save the output file                    
                        if strcmp(app.OutputformatDropDown.Value,'.xlsx')
                            fileNameOutput=fullfile(app.SaveFolder.Value{1},[FileName,'_Timecourse_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.xlsx']);                    
                            writecell([outputHeader;output],fileNameOutput);
                        elseif strcmp(app.OutputformatDropDown.Value,'.txt')
                            fileNameOutput=fullfile(app.SaveFolder.Value{1},[FileName,'_Timecourse_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt']);                    
                            writecell([outputHeader;output],fileNameOutput,'Delimiter','tab');
                        else % .ep
                            fileNameOutput=fullfile(app.SaveFolder.Value{1},[FileName,'_Timecourse_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.ep']);
                            save_eph(fileNameOutput,output(:,2:end));
                        end
                        tempsavefileNameOutput(file)=fileNameOutput;

                    else 
                        %% save 3D mat
                        output_nbColomn=0;
                        for j=1:length(electrodesSpec) %loop over electrodes
                            
                            %Time course extraction per electrodes
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn,file)=data(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,electrodesSpec(j));
                            
                            %compute of the sum of selected electrodes
                            if j==1
                                sumElectrodes=data(:,electrodesSpec(j));
                            else
                                sumElectrodes=sumElectrodes+data(:,electrodesSpec(j));
                            end
                        end
                        
                        %Look at electrod mean
                        if app.AverageCheckBox.Value
                            avgElectrode=sumElectrodes./length(electrodesSpec);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn,file)=avgElectrode(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1);                  
                        end
                        
                        %Look at the GFP
                        if app.GFPCheckBox.Value
                            GFP=compute_gfp(data);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn, file)=GFP(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1);                       
                        end
                        
                        %Look at the GMD
                        if app.GMDCheckBox.Value
                            GMD=compute_gmd(data);
                            
                            output_nbColomn=output_nbColomn+1;
                            output(:,output_nbColomn,file)=GMD(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,1);                      
                        end                       

                    end


                end    


            end

            %Save the output file in case of timecourse 3D
            if strcmp(app.SelectOutputTime.Value,'3D file')
                fileNameOutput=fullfile(app.SaveFolder.Value{1},['3D_Timecourse_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.mat']);
                save(fileNameOutput, "output");
            end
            
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            
            if strcmp(app.ProcessingChoice.Value,'Peaks detection')
                %peak detection
                if strcmp(app.SelectOutputTime.Value,'ms')
                   PifFileID = fopen(fullfile(app.SaveFolder.Value{1},['PIF_PeakDetection_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_ms.Value)),'-',num2str(round(app.IntervalOffset_ms.Value)),'ms_',app.OptionalSuffix.Value,'.txt']),'w');
                else
                   PifFileID = fopen(fullfile(app.SaveFolder.Value{1},['PIF_PeakDetection_',app.PeakDropDown.Value,'_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt']),'w'); 
                end

                fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal PeakDetection Info File',...
                '======================================================================='); % header
            elseif strcmp(app.ProcessingChoice.Value,'Average time interval')
                %Average Time Interval
                 PifFileID = fopen(fullfile(app.SaveFolder.Value{1},['PIF_AverageTimeInterval_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt']),'w');
                 
                 fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Average Time Interval Info File',...
                '======================================================================='); % header
            else
                %Timecourse extraction
                 PifFileID = fopen(fullfile(app.SaveFolder.Value{1},['PIF_Timecourse_',num2str(round(app.IntervalOnset_tf.Value)),'-',num2str(round(app.IntervalOffset_tf.Value)),'tf_',app.OptionalSuffix.Value,'.txt']),'w');
                 
                 fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Timecourse export Info File',...
                '======================================================================='); % header
            end
           
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});         
            
            % Processing parameters
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','PROCESSING PARAMETERS');
            fprintf(PifFileID,'%s\t%s\r\n','Processing performed:',app.ProcessingChoice.Value);
            fprintf(PifFileID,'%s\t%d\r\n','Sampling Rate (in Hz):', app.SamplingRate.Value);
            fprintf(PifFileID,'%s\t%d%s\t%d%s\r\n','Interval onset:',app.IntervalOnset_ms.Value,' ms,',app.IntervalOnset_tf.Value,' tf');
            fprintf(PifFileID,'%s\t%d%s\t%d%s\r\n','Interval offset:',app.IntervalOffset_ms.Value,' ms,',app.IntervalOffset_tf.Value,' tf');
            fprintf(PifFileID,'%s\t%s\r\n','Electrodes processed indices:',app.ElectrodesEditField.Value);
            
            if strcmp(app.ProcessingChoice.Value,'Peaks detection')
                %peak detection         
                if strcmp(app.PeakDropDown.Value,'Positive')
                    fprintf(PifFileID,'%s\t%s\r\n','Type of peaks detected:','Positive (maxima in the interval)');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Type of peaks detected:','Negative (minima in the interval)');
                end
                if app.AverageCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s):','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s):','NO');
                end
                if app.GFPCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','GFP studied (look only at maxima):','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','GFP studied (look only at maxima):','NO');
                end
                if app.GMDCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','Dis studied (look only at maxima):','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Dis studied (look only at maxima):','NO');
                end
            else
                %AverageTimeInterval and Timecourse extraction
                if app.AverageCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s):','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s):','NO');
                end
                if app.GFPCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','GFP:','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','GFP:','NO');
                end
                if app.GMDCheckBox.Value
                    fprintf(PifFileID,'%s\t%s\r\n','Dis:','YES');
                else
                    fprintf(PifFileID,'%s\t%s\r\n','Dis:','NO');
                end
            end
            
            %input files
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name)');
            for file2 = 1:nbFiles
                temp=fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file2),'..'),app.DataTable.File(file2));
                fprintf(PifFileID,'%s\r\n',temp{1});
            end
            
            %output files
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name)');
            
            if strcmp(app.ProcessingChoice.Value,'Peaks detection')
                fprintf(PifFileID,'%s\t%s\r\n','Output file (Peak amplitude and position):',fileNameOutput);
            elseif strcmp(app.ProcessingChoice.Value,'Average time interval')
                fprintf(PifFileID,'%s\t%s\r\n','Output file:',fileNameOutput);
            else %timecourse extraction
                if strcmp(app.SelectOutputTime.Value,'2D files')
                    fprintf(PifFileID,'%s\r\n','Timecourse for each input file was record in 2D files (Time X Electrodes)');
                    for file3 = 1:nbFiles
                        fprintf(PifFileID,'%s\r\n',tempsavefileNameOutput(file3));
                    end

                else %for 3D mat file
                    fprintf(PifFileID,'%s\r\n','Timecourse of all input files was record in 3D files (Time X Electrodes X Input file):');
                    fprintf(PifFileID,'%s\r\n',fileNameOutput);
                end

            end            
            
            if and(strcmp(app.CheckfileDropDown.Value,'Yes'),strcmp(app.ProcessingChoice.Value,'Peaks detection'))
                fprintf(PifFileID,'%s\t%s\r\n','Check file (suggestion of position to visually check the detection when value is different from 0 [1=no peak detected, 2=more than one peak in the interval]):',fileNameCheck);
            end

            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])

            
            %Close the PIF file 
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fclose(PifFileID);

            delete (h);
            
            if strcmp(app.ProcessingChoice.Value,'Peaks detection')
                rz = msgbox('Peak detection done.');
            elseif strcmp(app.ProcessingChoice.Value,'Average time interval')
                rz = msgbox('Average Time Interval done.');
            else
                rz = msgbox('Timecourse export done.');
            end
            
            
        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.PeaksorTraceexportButton.Enable = 'on';

            % Update parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.PeaksorTraceexportButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpal_PeaksTraceExport
        function EEGpal_PeaksTraceExportCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.PeaksorTraceexportButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: IntervalOnset_ms
        function IntervalOnset_msValueChanged(app, event)
            app.IntervalOnset_tf.Value     = ms2tf(app,app.IntervalOnset_ms.Value,app.SamplingRate.Value);
            app.IntervalOnset_ms.Value     = tf2ms(app,app.IntervalOnset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
        end

        % Value changed function: IntervalOnset_tf
        function IntervalOnset_tfValueChanged(app, event)
            app.IntervalOnset_ms.Value     = tf2ms(app,app.IntervalOnset_tf.Value,app.SamplingRate.Value);
        end

        % Value changed function: IntervalOffset_ms
        function IntervalOffset_msValueChanged(app, event)
            app.IntervalOffset_tf.Value    = ms2tf(app,app.IntervalOffset_ms.Value,app.SamplingRate.Value);
            app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision            
        end

        % Value changed function: IntervalOffset_tf
        function IntervalOffset_tfValueChanged(app, event)
            app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value,app.SamplingRate.Value);            
        end

        % Value changed function: EndOfFile
        function EndOfFileValueChanged(app, event)
            if app.EndOfFile.Value
                app.IntervalOffset_tf.Enable   = 'off';
                app.IntervalOffset_ms.Enable   = 'off';
                try
                    app.IntervalOffset_tf.Value    = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                    app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                catch % if no data selected
                    app.IntervalOffset_tf.Value    = 0;
                    app.IntervalOffset_ms.Value    = 0;
                end
            else
                app.IntervalOffset_tf.Enable       = 'on';
                app.IntervalOffset_ms.Enable       = 'on';
            end        
        end

        % Value changed function: ElectrodesEditField
        function ElectrodesEditFieldValueChanged(app, event)
            if ~isempty(app.ElectrodesEditField.Value)
                app.AverageCheckBox.Enable  = 'on';
            else
                app.AverageCheckBox.Enable  = 'off';
                app.AverageCheckBox.Value  = 0;
            end
        end

        % Value changed function: ProcessingChoice
        function ProcessingChoiceValueChanged(app, event)
            if strcmp(app.ProcessingChoice.Value,'Average time interval')
                app.OutputformatDropDown.Visible=1;
                app.OutputformatDropDown.Items={'.xlsx', '.txt'};
                app.OutputformatDropDown.Value='.xlsx';
                app.OutputformatDropDownLabel.Visible=1;
                app.SelectOutputTime.Visible=0;
                app.InstructionOutputTime.Visible=0;
                app.CheckfileDropDown.Visible=0;
                app.InstructionCheck.Visible=0;
                app.InstructionOutputTime_2.Visible=0;

                app.PeakDropDown.Visible=0;
                app.InstructionPeak.Visible=0;
                app.InstructPeak1.Visible=0;
                app.InstructElements1_2.Visible=0;
                app.InstructWarning.Visible=0;


            elseif strcmp(app.ProcessingChoice.Value,'Peaks detection')
                app.OutputformatDropDown.Visible=1;
                app.OutputformatDropDown.Items={'.xlsx', '.txt'};
                app.OutputformatDropDown.Value='.xlsx';
                app.OutputformatDropDownLabel.Visible=1;
                app.SelectOutputTime.Visible=1;
                app.SelectOutputTime.Items=[{'ms','TF'}];
                app.SelectOutputTime.Value='ms';                
                app.InstructionOutputTime.Visible=1;
                app.InstructionOutputTime.Text='Peak position need to by saved in ms or TF ?';
                app.CheckfileDropDown.Visible=1;
                app.InstructionCheck.Visible=1;
                app.InstructionOutputTime_2.Visible=1;

                app.PeakDropDown.Visible=1;
                app.InstructionPeak.Visible=1;
                app.InstructPeak1.Visible=1;
                app.InstructElements1_2.Visible=1;
                app. InstructWarning.Visible=1;

            
            else %timecourse extraction
                app.OutputformatDropDown.Visible=1;
                app.OutputformatDropDown.Items={'.xlsx', '.txt','.ep'};
                app.OutputformatDropDown.Value='.xlsx';
                app.OutputformatDropDownLabel.Visible=1;                
                app.SelectOutputTime.Visible=1;
                app.SelectOutputTime.Items=[{'2D files','3D file'}];
                app.SelectOutputTime.Value='2D files';
                app.InstructionOutputTime.Visible=1;
                app.InstructionOutputTime.Text='Save in 2D text files (timeXelectrodes) or one 3D mat file ?';
                app.CheckfileDropDown.Visible=0;
                app.InstructionCheck.Visible=0;
                app.InstructionOutputTime_2.Visible=0;

                app.PeakDropDown.Visible=0;
                app.InstructionPeak.Visible=0;
                app.InstructPeak1.Visible=0;
                app.InstructElements1_2.Visible=0;
                app. InstructWarning.Visible=0;

            end
            
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_PeaksTraceExport_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_PeaksTraceExport_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_PeaksTraceExport_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Value changed function: SelectOutputTime
        function SelectOutputTimeValueChanged(app, event)
            if strcmp(app.SelectOutputTime.Value,'3D file');
                app.OutputformatDropDown.Visible=0;
                app.OutputformatDropDownLabel.Visible=0;
            else
                app.OutputformatDropDown.Visible=1;
                app.OutputformatDropDownLabel.Visible=1;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpal_PeaksTraceExport = uifigure('Visible', 'off');
            app.EEGpal_PeaksTraceExport.Name = 'EEGpal_PeaksTraceExport';
            app.EEGpal_PeaksTraceExport.CloseRequestFcn = createCallbackFcn(app, @EEGpal_PeaksTraceExportCloseRequest, true);
            app.PeakDetectionorTraceExportLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.PeakDetectionorTraceExportLabel.Text = ' Peak Detection or Trace Export';
            app.OUTPUTOPTIONSLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.InstructionFolderLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructionFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.EEGpal_PeaksTraceExport, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.EEGpal_PeaksTraceExport);
            app.SaveFolder.Editable = 'off';
            app.APPLYLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.EEGpal_PeaksTraceExport, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.SaveinmemoryButton = uibutton(app.EEGpal_PeaksTraceExport, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window without applying the processing and save the parameters.'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.EEGpal_PeaksTraceExport, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.InstructModule = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructModule.Text = 'This module permit to generate different ERP exports wihch can be used to perform trace analysis in your statistic software (R, SPSS, ...). It allow to export the time course, the average or detect peak value for the specified time interval.';
            app.InstructElements1 = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructElements1.Text = 'Specify the channel number separate by space (e.g 1 2 64). Put * to select all channels ';
            app.Epoch_RelativeInterval1 = uilabel(app.EEGpal_PeaksTraceExport);
            app.Epoch_RelativeInterval1.Text = 'Relative to file start';
            app.Epoch_RelativeInterval1_2 = uilabel(app.EEGpal_PeaksTraceExport);
            app.Epoch_RelativeInterval1_2.Text = 'Time interval';
            app.msLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.msLabel.Text = '[ms]';
            app.tfLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.tfLabel.Text = '[tf]';
            app.IntervalOnset_tf = uieditfield(app.EEGpal_PeaksTraceExport, 'numeric');
            app.IntervalOnset_tf.Limits = [0 Inf];
            app.IntervalOnset_tf.ValueDisplayFormat = '%.0f';
            app.IntervalOnset_tf.ValueChangedFcn = createCallbackFcn(app, @IntervalOnset_tfValueChanged, true);
            app.IntervalOnset_tf.Tooltip = {''};
            app.IntervalonsetLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.IntervalonsetLabel.Text = 'Interval onset:';
            app.IntervalOnset_ms = uieditfield(app.EEGpal_PeaksTraceExport, 'numeric');
            app.IntervalOnset_ms.Limits = [0 Inf];
            app.IntervalOnset_ms.ValueDisplayFormat = '%.1f';
            app.IntervalOnset_ms.ValueChangedFcn = createCallbackFcn(app, @IntervalOnset_msValueChanged, true);
            app.IntervalOnset_ms.Tooltip = {''};
            app.IntervalOffset_tf = uieditfield(app.EEGpal_PeaksTraceExport, 'numeric');
            app.IntervalOffset_tf.Limits = [0 Inf];
            app.IntervalOffset_tf.ValueDisplayFormat = '%.0f';
            app.IntervalOffset_tf.ValueChangedFcn = createCallbackFcn(app, @IntervalOffset_tfValueChanged, true);
            app.IntervalOffset_tf.Tooltip = {''};
            app.EndOfFile = uibutton(app.EEGpal_PeaksTraceExport, 'state');
            app.EndOfFile.ValueChangedFcn = createCallbackFcn(app, @EndOfFileValueChanged, true);
            app.EndOfFile.Tooltip = {'End of File'; 'When selected, ''Epoch offset'', ''Epoch duration'', and ''Number of sub-epochs'' values based on the first file of the list in the main window.,When pressing run, these values will be adapted to the file being processed.'};
            app.EndOfFile.Text = 'EoF';
            app.IntervaloffsetLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.IntervaloffsetLabel.Text = 'Interval offset:';
            app.IntervalOffset_ms = uieditfield(app.EEGpal_PeaksTraceExport, 'numeric');
            app.IntervalOffset_ms.Limits = [0 Inf];
            app.IntervalOffset_ms.ValueDisplayFormat = '%.1f';
            app.IntervalOffset_ms.ValueChangedFcn = createCallbackFcn(app, @IntervalOffset_msValueChanged, true);
            app.IntervalOffset_ms.Tooltip = {''};
            app.ElectrodesEditFieldLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.ElectrodesEditFieldLabel.Text = 'Electrodes';
            app.ElectrodesEditField = uieditfield(app.EEGpal_PeaksTraceExport, 'text');
            app.ElectrodesEditField.ValueChangedFcn = createCallbackFcn(app, @ElectrodesEditFieldValueChanged, true);
            app.ElectrodesEditField.Value = '*';
            app.Epoch_RelativeInterval1_3 = uilabel(app.EEGpal_PeaksTraceExport);
            app.Epoch_RelativeInterval1_3.Text = 'Elements to process';
            app.AverageCheckBox = uicheckbox(app.EEGpal_PeaksTraceExport);
            app.AverageCheckBox.Enable = 'off';
            app.AverageCheckBox.Text = 'Average selected electrode(s)';
            app.GFPCheckBox = uicheckbox(app.EEGpal_PeaksTraceExport);
            app.GFPCheckBox.Text = 'GFP';
            app.GMDCheckBox = uicheckbox(app.EEGpal_PeaksTraceExport);
            app.GMDCheckBox.Text = 'Dis';
            app.SamplingRateinHzEditFieldLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.SamplingRateinHzEditFieldLabel.Text = 'Sampling Rate (in Hz)';
            app.SamplingRate = uieditfield(app.EEGpal_PeaksTraceExport, 'numeric');
            app.Instruct_timeinter1 = uilabel(app.EEGpal_PeaksTraceExport);
            app.Instruct_timeinter1.Text = 'Time must be specified in the Cartool scale (first time fame is tf=0).';
            app.PeakDropDown = uidropdown(app.EEGpal_PeaksTraceExport);
            app.PeakDropDown.Items = {'Positive', 'Negative'};
            app.PeakDropDown.Value = 'Positive';
            app.InstructPeak1 = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructPeak1.Text = 'The script searches for the max value if it is positive or the min value if it is negative.';
            app.InstructWarning = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructWarning.Text = {'WARNING for peak detection: Use at your own risk. Peak analysis is not reliable because it depends on the reference (except for GFP and Dis). Automatic peak detection is also challenging because of the high variability of individual EEG signals. '; 'This script uses the Matlab function ''findpeaks''. If serval peaks are detected, only the global maxima/minima are recorded in the main output file. If no peaks are detected, no value is recorded. A visual check of the peaks in these cases is preferable (in Cartool) using the Check file.'};
            app.SelectOutputTime = uidropdown(app.EEGpal_PeaksTraceExport);
            app.SelectOutputTime.Items = {'ms', 'TF'};
            app.SelectOutputTime.ValueChangedFcn = createCallbackFcn(app, @SelectOutputTimeValueChanged, true);
            app.SelectOutputTime.Value = 'ms';
            app.InstructionOutputTime = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructionOutputTime.Text = 'Peak position need to by saved in ms or TF ?';
            app.CheckfileDropDown = uidropdown(app.EEGpal_PeaksTraceExport);
            app.CheckfileDropDown.Items = {'Yes', 'No'};
            app.CheckfileDropDown.Value = 'Yes';
            app.InstructionOutputTime_2 = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructionOutputTime_2.Text = 'Save another .xlsx informing if the findpeaks script is unsure of the result. You could check the position manually when values are different from 0 (1: no peak detected, 2: several peaks detected).';
            app.WhatprocessingshouldIdoDropDownLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.WhatprocessingshouldIdoDropDownLabel.Text = 'What processing should I do ?';
            app.ProcessingChoice = uidropdown(app.EEGpal_PeaksTraceExport);
            app.ProcessingChoice.Items = {'Peaks detection', 'Timecourse extraction', 'Average time interval'};
            app.ProcessingChoice.ValueChangedFcn = createCallbackFcn(app, @ProcessingChoiceValueChanged, true);
            app.ProcessingChoice.Value = 'Peaks detection';
            app.OptionalsuffixinoutputfilenameEditFieldLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.OptionalsuffixinoutputfilenameEditFieldLabel.Text = 'Optional suffix in output file name';
            app.OptionalSuffix = uieditfield(app.EEGpal_PeaksTraceExport, 'text');
            app.OptionalSuffix.Tooltip = {'If empty, might overwright existing files'};
            app.InstructElements1_2 = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructElements1_2.Visible = 'off';
            app.InstructElements1_2.Text = 'Only maxima';
            app.InstructionCheck = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructionCheck.Text = 'Do you want to save also the Check file ?';
            app.InstructionPeak = uilabel(app.EEGpal_PeaksTraceExport);
            app.InstructionPeak.Text = 'Peak';
            app.HelpButton = uibutton(app.EEGpal_PeaksTraceExport, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.OutputformatDropDownLabel = uilabel(app.EEGpal_PeaksTraceExport);
            app.OutputformatDropDownLabel.Text = 'Output format';
            app.OutputformatDropDown = uidropdown(app.EEGpal_PeaksTraceExport);
            app.OutputformatDropDown.Items = {'.xlsx', '.txt'};
            app.OutputformatDropDown.Value = '.xlsx';
            % Show the figure after all components are created
            app.EEGpal_PeaksTraceExport.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_PeaksTraceExport(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpal_PeaksTraceExport)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpal_PeaksTraceExport)
        end
    end
end