classdef EEGpalCS_mod_Rereference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_Rereference         matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Reref1Tab                    matlab.ui.container.Tab
        Label1                       matlab.ui.control.Label
        HelpButton_1                 matlab.ui.control.Button
        REREFERENCELabel_1           matlab.ui.control.Label
        ReferenceSelection_1         matlab.ui.control.ListBox
        ReferenceSelectionLabel_1    matlab.ui.control.Label
        ClusterDropDown_1            matlab.ui.control.DropDown
        ClusterDropDownLabel_1       matlab.ui.control.Label
        OUTPUTOPTIONSLabel_1         matlab.ui.control.Label
        FilesExtension_1             matlab.ui.control.DropDown
        FilesExtensionLabel_1        matlab.ui.control.Label
        SelectFolderButton_1         matlab.ui.control.Button
        SelectUpperFolderLabel_1     matlab.ui.control.Label
        SaveFolder_1                 matlab.ui.control.TextArea
        SaveFolderLabel_1            matlab.ui.control.Label
        SuffixString_1               matlab.ui.control.EditField
        SuffixStringLabel_1          matlab.ui.control.Label
        APPLYLabel_1                 matlab.ui.control.Label
        RunButton_1                  matlab.ui.control.Button
        SaveinmemoryButton_1         matlab.ui.control.Button
        CancelButton_1               matlab.ui.control.Button
        Reref2Tab                    matlab.ui.container.Tab
        Label2                       matlab.ui.control.Label
        HelpButton_2                 matlab.ui.control.Button
        REREFERENCELabel_2           matlab.ui.control.Label
        ReferenceSelection_2         matlab.ui.control.ListBox
        ReferenceSelectionLabel_2    matlab.ui.control.Label
        ClusterDropDown_2            matlab.ui.control.DropDown
        ClusterDropDownLabel_2       matlab.ui.control.Label
        OUTPUTOPTIONSLabel_2         matlab.ui.control.Label
        FilesExtension_2             matlab.ui.control.DropDown
        FilesExtensionLabel_2        matlab.ui.control.Label
        SelectFolderButton_2         matlab.ui.control.Button
        SelectUpperFolderLabel_2     matlab.ui.control.Label
        SaveFolder_2                 matlab.ui.control.TextArea
        SaveFolderLabel_2            matlab.ui.control.Label
        SuffixString_2               matlab.ui.control.EditField
        SuffixStringLabel_2          matlab.ui.control.Label
        APPLYLabel_2                 matlab.ui.control.Label
        RunButton_2                  matlab.ui.control.Button
        SaveinmemoryButton_2         matlab.ui.control.Button
        CancelButton_2               matlab.ui.control.Button
        HelpButton                   matlab.ui.control.Button
        CancelButton                 matlab.ui.control.Button
        SaveinmemoryButton           matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        APPLYLabel                   matlab.ui.control.Label
        SuffixString                 matlab.ui.control.EditField
        SuffixstringLabel            matlab.ui.control.Label
        SaveFolder                   matlab.ui.control.TextArea
        SavingfolderLabel            matlab.ui.control.Label
        SelectFolderButton           matlab.ui.control.Button
        SelectUpperFolderLabel       matlab.ui.control.Label
        FilesExtension               matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel  matlab.ui.control.Label
        OUTPUTOPTIONSLabel           matlab.ui.control.Label
        ClusterDropDown              matlab.ui.control.DropDown
        ScalpelectrodesclusterDropDownLabel  matlab.ui.control.Label
        ReferenceSelection           matlab.ui.control.ListBox
        NewreferenceListBoxLabel     matlab.ui.control.Label
        REREFERENCELabel             matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object        
        DataTable        
        SettingTable
        batchHasCrashed 
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function applySessionParam(app)
            
            % OUTPUT OPTION
            app.FilesExtension_1.Value        = app.SessionParameters.Rereference.FilesExtention_1;
            app.SaveFolder_1.Value            = app.SessionParameters.Rereference.SaveFolder_1;
            app.SuffixString_1.Value          = app.SessionParameters.Rereference.SuffixString_1;
            app.FilesExtension_2.Value        = app.SessionParameters.Rereference.FilesExtention_2;
            app.SaveFolder_2.Value            = app.SessionParameters.Rereference.SaveFolder_2;
            app.SuffixString_2.Value          = app.SessionParameters.Rereference.SuffixString_2;
            
            
            % REREFERENCE
            
            % Reference selection
            app.ReferenceSelection_1.Items    = app.SessionParameters.Rereference.ReferenceItems_1;
            app.ReferenceSelection_1.Value    = app.SessionParameters.Rereference.ReferenceValue_1;
            app.ReferenceSelection_2.Items    = app.SessionParameters.Rereference.ReferenceItems_2;
            app.ReferenceSelection_2.Value    = app.SessionParameters.Rereference.ReferenceValue_2;
            
            % Electrodes cluster
            app.ClusterDropDown_1.Items       = app.SessionParameters.Rereference.ClusterItems_1;
            app.ClusterDropDown_1.Value       = app.SessionParameters.Rereference.ClusterValue_1;
            app.ClusterDropDown_2.Items       = app.SessionParameters.Rereference.ClusterItems_2;
            app.ClusterDropDown_2.Value       = app.SessionParameters.Rereference.ClusterValue_2;
        end
        
        function SessionParameters   = updateSessionParam(app,SessionParameters)
            
            % OUTPUT OPTION
            SessionParameters.Rereference.FilesExtention_1    = app.FilesExtension_1.Value;
            SessionParameters.Rereference.SaveFolder_1        = app.SaveFolder_1.Value;
            SessionParameters.Rereference.SuffixString_1      = app.SuffixString_1.Value;
            SessionParameters.Rereference.FilesExtention_2    = app.FilesExtension_2.Value;
            SessionParameters.Rereference.SaveFolder_2        = app.SaveFolder_2.Value;
            SessionParameters.Rereference.SuffixString_2      = app.SuffixString_2.Value;
            
            
            % REREFERENCE
            
            % Reference selection
            SessionParameters.Rereference.ReferenceItems_1    = app.ReferenceSelection_1.Items;
            SessionParameters.Rereference.ReferenceValue_1    = app.ReferenceSelection_1.Value;
            SessionParameters.Rereference.ReferenceItems_2    = app.ReferenceSelection_2.Items;
            SessionParameters.Rereference.ReferenceValue_2    = app.ReferenceSelection_2.Value;
            
            
            % Electrodes cluster
            SessionParameters.Rereference.ClusterItems_1      = app.ClusterDropDown_1.Items;
            SessionParameters.Rereference.ClusterValue_1      = app.ClusterDropDown_1.Value;
            SessionParameters.Rereference.ClusterItems_2      = app.ClusterDropDown_2.Items;
            SessionParameters.Rereference.ClusterValue_2      = app.ClusterDropDown_2.Value;
        end
        
        function openHelp(app)
             if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_ReReferencing_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_ReReferencing_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_ReReferencing_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end
        
        function saveMemoryButton(app)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Interpolation parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            %batch
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                app.SessionParameters.BatchProcessing.BatchExecution = 0; % If the participant was able to click on this button, be sure to break the batch
            end

            % Update parameters          
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            
            % Delete the dialog box
            delete(app)
        end
        
        function  cancelButton(app)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values          
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            try
                app.SettingTable            = app.SessionParameters.Electrodes.SettingTable;
            catch
            end          
            
            %Preset Cartool/eeglab parameters
            if strcmp(app.EEGpalCS_Main.PresetParametersSwitch.Value,'eeglab')
                app.FilesExtension_1.Value = '.set';
            end
            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
                try
                    ActiveElectrodes                = app.SettingTable.labels([app.SettingTable.include{:}]); % Selected electrodes only
                    app.ReferenceSelection_1.Items    = [{'Average'};ActiveElectrodes];
                    app.ReferenceSelection_2.Items    = [{'Average'};ActiveElectrodes];
                    
                    ActiveClusters                  = app.SettingTable.cluster([app.SettingTable.include{:}]); % Clusters of selected electrodes only
                    app.ClusterDropDown_1.Items       = [{'all'};unique(ActiveClusters,'stable')];
                    app.ClusterDropDown_2.Items       = [{'all'};unique(ActiveClusters,'stable')];
                catch
                    app.ReferenceSelection_1.Items = [{'Average'}; cellstr(string(1:app.DataTable.nChan(1)))'];
                    app.ClusterDropDown_1.Items       = {'all'};
                    app.ReferenceSelection_2.Items = [{'Average'}; cellstr(string(1:app.DataTable.nChan(1)))'];
                    app.ClusterDropDown_2.Items       = {'all'};
                end
                app.ReferenceSelection_1.Value        = 'Average';
                app.ClusterDropDown_1.Value           = 'all';
                app.ReferenceSelection_2.Value        = 'Average';
                app.ClusterDropDown_2.Value           = 'all';
            end
            % Save folder
            if isempty(app.SaveFolder_1.Value{:})
                try
                    app.SaveFolder_1.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end

            %Execute the batch System
            app.batchHasCrashed = false;
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                RunButtonPushed(app);
            end
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_Rereference); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %% CHECK
            
            if app.SessionParameters.BatchProcessing.BatchExecution == 0 %normal mode
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                        'Please make sure to select the output folder where to put the processed files.'),...
                        'No output folder selected','warn');
                    return
                end
                
                if length(app.ReferenceSelection.Value) > 1 && logical(sum(strcmp(app.ReferenceSelection.Value,'Average')))
                    msgbox(sprintf('%s\n\n%s','Average reference selected with another channel!', ...
                        'I don''t think it makes sense. I will not let you do that!'),...
                        'Average reference selected with another channel','warn');
                    return
                end
                
                if logical(sum(strcmp(app.ReferenceSelection.Value,'Average'))) &&...
                        strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
                    AvgWarn = questdlg(sprintf('%s\n\n%s',...
                        'Average reference selected while you have more than one cluster in your electrode setting',...
                        'Are you sure that''s what you want to do?'),'Average reference warning','OK','Cancel','Cancel');
                    if strcmpi(AvgWarn,'Cancel')
                        return
                    end
                end
                
                if strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
                    ClustWarn = questdlg(sprintf('%s\n\n%s',...
                        'You have selected all clusters while you have more than one cluster in your electrode setting',...
                        'Are you sure that''s what you want to do?'),'Clusters warning','OK','Cancel','Cancel');
                    if strcmpi(ClustWarn,'Cancel')
                        return
                    end
                end
                
                Run = questdlg(sprintf('%s\n%s\n\n%s%s\n%s%d\n\n%s\n\n%s',...
                    'Greetings Earthling!',...
                    'Me about to re-reference the data. Ready for the ride?',...
                    'Cluster on which re-referencing will be applied: ',app.ClusterDropDown.Value,...
                    'Number of files: ',height(app.DataTable),...
                    'A long list may take time to proceed (though not that much, let''s be honnest). In order to reduce time, you can select subsets of files in the main window.',...
                    'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
                
                if strcmpi(Run,'Cancel')
                    return
                end
            else %Batch mode
                if isempty(app.SaveFolder.Value{:})
                    msgbox(sprintf('%s\n\n%s\n%s','No output folder selected.', ...
                    'The batch process has been interrupted because there is no output folder specified in the Re-reference module',...
                    'Please specify a output folder and press on run. To continue the batch after that, press the "RUN BATCH" button again from the main window!'),...
                    'No output folder specified','warn');
                    app.batchHasCrashed = true;
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert the batch status in the main EEGpal windows.
                    return
                end

                if length(app.ReferenceSelection.Value) > 1 && logical(sum(strcmp(app.ReferenceSelection.Value,'Average')))                    
                    msgbox(sprintf('%s\n\n%s\n\n%s','Average reference selected with another channel!', ...
                        'I don''t think it makes sense. I will not let you do that!',...
                        'Please specify a output folder and press on run. To continue the batch after that, press the "RUN BATCH" button again from the main window!'),...
                        'Average reference selected with another channel','warn');
                    app.batchHasCrashed = true;
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert the batch status in the main EEGpal windows.
                    return
                end
            end
            
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Rereference_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Re-reference Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Session parameters file:',app.EEGpalCS_Main.SessionParametersFilePath.Value);
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','This processing was performed in Batch mode.');                
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','No');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','REFERENCE');
            if length(app.ReferenceSelection.Value) > 1
                fprintf(PifFileID,'%s\r\n','New reference:');
                fprintf(PifFileID,'\t\t%s\r\n',app.ReferenceSelection.Value{:});
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','New reference:',app.ReferenceSelection.Value{:});
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            fprintf(PifFileID,'%s\t\t\t%s\r\n','Electrodes cluster:',app.ClusterDropDown.Value);
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
                Included = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                fprintf(PifFileID,'%s\r\n','Channels left in file:');
                fprintf(PifFileID,'\t\t%s\r\n',Included{:});
            catch
                fprintf(PifFileID,'%s\r\n','No electrodes setting file.');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN RE-REFERNCE
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run Re-reference...')
            StartTime = datetime('now');
            tStart = tic;
            
            h = waitbar(0,'Re-referencing.....');
            nbFile= height(app.DataTable);
            
            % Re-reference file by file
            for file = 1:nbFile
                waitbar(file/nbFile,h, strcat('Re-referencing ',num2str(file),'/',num2str(nbFile)));
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    continue
                end
                disp('File loaded!')
                
                
                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    % FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    
                    % Channels check (if electrode setting defined)
                    try
                        Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        if isequal(char(FileChannels),char(Channels))
                            ChannelsCheck(file,1)   = "OK";
                        else
                            ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No channels in file header";
                    end
                catch
                    ChannelsCheck(file,1)   = "No electrode setting defined";
                end
                
                                
                % Remove channels from non-scalp clusters
                if ~strcmpi(app.ClusterDropDown.Value,'all') && length(app.ClusterDropDown.Items) > 2
%                     NewRefData = data(:,strcmpi(allElectrodes.cluster,app.ClusterDropDown.Value));
                    ScalpElectrodes = app.SettingTable.labels(strcmp(app.SettingTable.cluster,app.ClusterDropDown.Value)); % Selected electrodes only
                else
%                     NewRefData = data;
                    ScalpElectrodes = app.SettingTable.labels; % Selected scalp electrodes only
                end
                
                % Re-reference
                disp(['Re-referencing file ', num2str(file), ' out of ', num2str(height(app.DataTable))])
                if logical(sum(strcmp(app.ReferenceSelection.Value,'Average')))
                    ref = 'avg';
                else
                    ref = find(contains(app.SettingTable.labels([app.SettingTable.include{:}]),app.ReferenceSelection.Value));
                end
                if ~logical(sum(strcmp(app.ReferenceSelection.Value,'Average'))) && ...
                        ~logical(sum(contains(app.ReferenceSelection.Value,ScalpElectrodes))) % The new reference is not within the scalp cluster
                    aux = 'aux'; % indicate that the reference is an auxiliary electrode (e.g., earlobes)
                else
                    aux = [];
                end
                NewRefData = ref_change(data,ref,aux);
                disp('Re-referencing completed')
                
                
                % Replace unselected channels from ChanCompTable (unprocessed)
                if strcmpi(aux,'aux')
                    AuxIndices = ~strcmpi(app.SettingTable.cluster,app.ClusterDropDown.Value);
                    for i = 1:length(AuxIndices)
                        if AuxIndices(i) == 1
                            NewRefData = [data(:,1), NewRefData];
                        elseif AuxIndices(i) > size(NewRefData,2)
                            NewRefData = [NewRefData, data(:,AuxIndices(i))];
                        else
                            NewRefData = [NewRefData(:,1:AuxIndices(i)-1), data(:,AuxIndices(i)), NewRefData(:,AuxIndices(i):end)];
                        end
                    end
                end
                
                
                % Save processed file
                if ~isempty(app.SuffixString.Value)
                    suffix = ['_', app.SuffixString.Value];
                end
                
                SubPath = app.DataTable.Path{file}(3:end);
                NewPath = [app.SaveFolder.Value{:} SubPath];
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end
                
                savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension.Value]);
                disp(['Saving ',savefilename])
                if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph')
                    save_eph(savefilename,NewRefData,header.SamplingRate,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.sef')
                    try
                        %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                        Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                    catch
                        Channels = '';
                    end
                    save_sef(savefilename,NewRefData,header.SamplingRate,Channels,events,header.firstindex)
                elseif strcmp(app.FilesExtension.Value,'.set')
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,2);
                        %Channels.labels     = char(ElectrodeSetting.labels);

                        % Recompute Theta and Radius to match EEGlab orientation
                        [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                        Channels.theta      = -rad2deg(az);
                        Channels.radius     = 0.5 - rad2deg(elev)/180;
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.y;
                        Channels.Y          = -ElectrodeSetting.x;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                    catch
                        Channels = '';
                    end
                    save_eeglab(savefilename,NewRefData,header.SamplingRate,events,header.firstindex,Channels)
                end
                
                % Update Table
                ProcessedDataTable.(1)(file)        = {[FileName, suffix, app.FilesExtension.Value]};
                ProcessedDataTable.(3)(file)        = size(NewRefData,2);
                %ProcessedDataTable.(4)(file)        = app.SamplingRate;
                ProcessedDataTable.(5)(file)        = size(NewRefData,1);
                
                
                % File completed
                tMid = toc(tStart);
                disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                disp('...............................................................')
            end
            delete(h);
            
            % UPDATE DATA TABLE IN MAIN WINDOW
            
            % Data table in the Main Window
            app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
            app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
            app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
            app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
            disp('Data table in the Main Window updated with the processed files')
            cd(app.SaveFolder.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(NewRefData,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Channel names check  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';ChannelsCheck';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Re-reference processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            %Close
            if app.SessionParameters.BatchProcessing.BatchExecution == 0
                rz = msgbox('Rereferencing done.');
            else %batch mode module successly completed                
                if app.batchHasCrashed
                    app.SessionParameters.BatchProcessing.BatchExecution = 0; %break the batch
                end
                
                app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=1; %process complited
                app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert only Batch variable to the main window
                CancelButtonPushed(app); %to close the module without saving Session Parameters
            end
        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Interpolation parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            %batch
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                app.SessionParameters.BatchProcessing.BatchExecution = 0; % If the participant was able to click on this button, be sure to break the batch
            end

            % Update parameters          
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.RereferenceButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpalCS_Rereference
        function EEGpalCS_RereferenceCloseRequest(app, event)
            cancelButton(app);
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_ReReferencing_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_ReReferencing_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_ReReferencing_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Button pushed function: SelectFolderButton_1
        function SelectFolderButton_1Pushed(app, event)
             savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_Rereference); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder_1.Value = savefolder;
            cd(app.SaveFolder_1.Value{:})
        end

        % Button pushed function: SelectFolderButton_2
        function SelectFolderButton_2Pushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpalCS_Rereference); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder_2.Value = savefolder;
            cd(app.SaveFolder_2.Value{:})
        end

        % Button pushed function: RunButton_1
        function RunButton_1Pushed(app, event)
             %% CHECK
            
            if app.SessionParameters.BatchProcessing.BatchExecution == 0 %normal mode
                if isempty(app.SaveFolder_1.Value{:})
                    msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                        'Please make sure to select the output folder where to put the processed files.'),...
                        'No output folder selected','warn');
                    return
                end
                
                if length(app.ReferenceSelection_1.Value) > 1 && logical(sum(strcmp(app.ReferenceSelection_1.Value,'Average')))
                    msgbox(sprintf('%s\n\n%s','Average reference selected with another channel!', ...
                        'I don''t think it makes sense. I will not let you do that!'),...
                        'Average reference selected with another channel','warn');
                    return
                end
                
                if logical(sum(strcmp(app.ReferenceSelection_1.Value,'Average'))) &&...
                        strcmpi(app.ClusterDropDown_1.Value,'all') && length(app.ClusterDropDown_1.Items) > 2
                    AvgWarn = questdlg(sprintf('%s\n\n%s',...
                        'Average reference selected while you have more than one cluster in your electrode setting',...
                        'Are you sure that''s what you want to do?'),'Average reference warning','OK','Cancel','Cancel');
                    if strcmpi(AvgWarn,'Cancel')
                        return
                    end
                end
                
                if strcmpi(app.ClusterDropDown_1.Value,'all') && length(app.ClusterDropDown_1.Items) > 2
                    ClustWarn = questdlg(sprintf('%s\n\n%s',...
                        'You have selected all clusters while you have more than one cluster in your electrode setting',...
                        'Are you sure that''s what you want to do?'),'Clusters warning','OK','Cancel','Cancel');
                    if strcmpi(ClustWarn,'Cancel')
                        return
                    end
                end
                
                Run = questdlg(sprintf('%s\n%s\n\n%s%s\n%s%d\n\n%s\n\n%s',...
                    'Greetings Earthling!',...
                    'Me about to re-reference the data. Ready for the ride?',...
                    'Cluster on which re-referencing will be applied: ',app.ClusterDropDown_1.Value,...
                    'Number of files: ',height(app.DataTable),...
                    'A long list may take time to proceed (though not that much, let''s be honnest). In order to reduce time, you can select subsets of files in the main window.',...
                    'Do you wish to proceed?'),'Run checked options','OK','Cancel','Cancel');
                
                if strcmpi(Run,'Cancel')
                    return
                end
            else %Batch mode
                if isempty(app.SaveFolder_1.Value{:})
                    msgbox(sprintf('%s\n\n%s\n%s','No output folder selected.', ...
                    'The batch process has been interrupted because there is no output folder specified in the Re-reference module',...
                    'Please specify a output folder and press on run. To continue the batch after that, press the "RUN BATCH" button again from the main window!'),...
                    'No output folder specified','warn');
                    app.batchHasCrashed = true;
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert the batch status in the main EEGpal windows.
                    return
                end

                if length(app.ReferenceSelection_1.Value) > 1 && logical(sum(strcmp(app.ReferenceSelection_1.Value,'Average')))                    
                    msgbox(sprintf('%s\n\n%s\n\n%s','Average reference selected with another channel!', ...
                        'I don''t think it makes sense. I will not let you do that!',...
                        'Please specify a output folder and press on run. To continue the batch after that, press the "RUN BATCH" button again from the main window!'),...
                        'Average reference selected with another channel','warn');
                    app.batchHasCrashed = true;
                    app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=2; %save the mistake
                    app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert the batch status in the main EEGpal windows.
                    return
                end
            end
            
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder_1.Value{:},['PIF_Rereference_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Re-reference Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder_1.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.SuffixString_1.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension_1.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Session parameters file:',app.EEGpalCS_Main.SessionParametersFilePath.Value);
            if app.SessionParameters.BatchProcessing.BatchExecution == 1
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','This processing was performed in Batch mode.');                
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','Batch Processing:','No');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','REFERENCE');
            if length(app.ReferenceSelection_1.Value) > 1
                fprintf(PifFileID,'%s\r\n','New reference:');
                fprintf(PifFileID,'\t\t%s\r\n',app.ReferenceSelection_1.Value{:});
            else
                fprintf(PifFileID,'%s\t\t%s\r\n','New reference:',app.ReferenceSelection_1.Value{:});
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','ELECTRODE SETTING');
            fprintf(PifFileID,'%s\t\t\t%s\r\n','Electrodes cluster:',app.ClusterDropDown_1.Value);
            try
                fprintf(PifFileID,'%s\t%s\r\n','Electrodes setting file:',app.SessionParameters.Electrodes.SettingFile{:});
                RemovedChannels = app.SessionParameters.Electrodes.SettingTable.labels(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                RemovedCluster = app.SessionParameters.Electrodes.SettingTable.cluster(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                Removed = [RemovedChannels';RemovedCluster'];
                fprintf(PifFileID,'%s\r\n','Channels removed from file (Channel  /  Cluster):');
                if isempty(Removed)
                    fprintf(PifFileID,'\t\t%s\r\n','No channels removed');
                else
                    fprintf(PifFileID,'\t\t%s\t\t%s\r\n',Removed{:});
                end
                Included = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                fprintf(PifFileID,'%s\r\n','Channels left in file:');
                fprintf(PifFileID,'\t\t%s\r\n',Included{:});
            catch
                fprintf(PifFileID,'%s\r\n','No electrodes setting file.');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            
            %% RUN RE-REFERNCE
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run Re-reference...')
            StartTime = datetime('now');
            tStart = tic;
            
            h = waitbar(0,'Re-referencing.....');
            nbFile= height(app.DataTable);
            
            % Re-reference file by file
            for file = 1:nbFile
                waitbar(file/nbFile,h, strcat('Re-referencing ',num2str(file),'/',num2str(nbFile)));
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    continue
                end
                disp('File loaded!')
                
                
                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data            = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    % FileChannels    = FileChannels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                    %app.SessionParameters.Electrodes.SettingTable = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                    
                    % Channels check (if electrode setting defined)
                    try
                        Channels = app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]);
                        if isequal(char(FileChannels),char(Channels))
                            ChannelsCheck(file,1)   = "OK";
                        else
                            ChannelsCheck(file,1)   = "Selected channels don't match selected channel names in file header";
                        end
                    catch
                        ChannelsCheck(file,1)   = "No channels in file header";
                    end
                catch
                    ChannelsCheck(file,1)   = "No electrode setting defined";
                end
                
                                
                % Remove channels from non-scalp clusters
                if ~strcmpi(app.ClusterDropDown_1.Value,'all') && length(app.ClusterDropDown_1.Items) > 2
                    ScalpElectrodes = app.SettingTable.labels(strcmp(app.SettingTable.cluster,app.ClusterDropDown_1.Value)); % Selected electrodes only
                else
                    ScalpElectrodes = app.SettingTable.labels; % Selected scalp electrodes only
                end
                
                % Re-reference
                disp(['Re-referencing file ', num2str(file), ' out of ', num2str(height(app.DataTable))])
                if logical(sum(strcmp(app.ReferenceSelection_1.Value,'Average')))
                    ref = 'avg';
                else
                    ref = find(contains(app.SettingTable.labels([app.SettingTable.include{:}]),app.ReferenceSelection_1.Value));
                end
                if ~logical(sum(strcmp(app.ReferenceSelection_1.Value,'Average'))) && ...
                        ~logical(sum(contains(app.ReferenceSelection_1.Value,ScalpElectrodes))) % The new reference is not within the scalp cluster
                    aux = 'aux'; % indicate that the reference is an auxiliary electrode (e.g., earlobes)
                else
                    aux = [];
                end
                NewRefData = ref_change(data,ref,aux);
                disp('Re-referencing completed')
                
                
                % Replace unselected channels from ChanCompTable (unprocessed)
                if strcmpi(aux,'aux')
                    AuxIndices = ~strcmpi(app.SettingTable.cluster,app.ClusterDropDown_1.Value);
                    for i = 1:length(AuxIndices)
                        if AuxIndices(i) == 1
                            NewRefData = [data(:,1), NewRefData];
                        elseif AuxIndices(i) > size(NewRefData,2)
                            NewRefData = [NewRefData, data(:,AuxIndices(i))];
                        else
                            NewRefData = [NewRefData(:,1:AuxIndices(i)-1), data(:,AuxIndices(i)), NewRefData(:,AuxIndices(i):end)];
                        end
                    end
                end
                
                
                % Save processed file
                if ~isempty(app.SuffixString_1.Value)
                    suffix = ['_', app.SuffixString_1.Value];
                end
                
                SubPath = app.DataTable.Path{file}(3:end);
                NewPath = [app.SaveFolder_1.Value{:} SubPath];
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end
                
                savefilename = fullfile(NewPath,[FileName, suffix, app.FilesExtension_1.Value]);
                disp(['Saving ',savefilename])
                if strcmp(app.FilesExtension_1.Value,'.ep') || strcmp(app.FilesExtension_1.Value,'.eph')
                    save_eph(savefilename,NewRefData,header.SamplingRate,events,header.firstindex)
                elseif strcmp(app.FilesExtension_1.Value,'.sef')
                    try
                        %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                        Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                    catch
                        Channels = '';
                    end
                    save_sef(savefilename,NewRefData,header.SamplingRate,Channels,events,header.firstindex)
                elseif strcmp(app.FilesExtension_1.Value,'.set')
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                        Channels            = ElectrodeSetting(:,2);
                        %Channels.labels     = char(ElectrodeSetting.labels);

                        % Recompute Theta and Radius to match EEGlab orientation
                        [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                        Channels.theta      = -rad2deg(az);
                        Channels.radius     = 0.5 - rad2deg(elev)/180;
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.y;
                        Channels.Y          = -ElectrodeSetting.x;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                    catch
                        Channels = '';
                    end
                    save_eeglab(savefilename,NewRefData,header.SamplingRate,events,header.firstindex,Channels)
                end
                
                % Update Table
                ProcessedDataTable.(1)(file)        = {[FileName, suffix, app.FilesExtension_1.Value]};
                ProcessedDataTable.(3)(file)        = size(NewRefData,2);
                %ProcessedDataTable.(4)(file)        = app.SamplingRate;
                ProcessedDataTable.(5)(file)        = size(NewRefData,1);
                
                
                % File completed
                tMid = toc(tStart);
                disp(['Completed file ', num2str(file), ' out of ', num2str(height(app.DataTable)), ' at ', datestr(datetime('now'),'HH:MM:SS')])
                disp(['Duration until now: ', datestr(seconds(tMid),'HH:MM:SS')])
                disp('...............................................................')
            end
            delete(h);
            
            % UPDATE DATA TABLE IN MAIN WINDOW
            
            % Data table in the Main Window
            app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
            app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
            app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
            app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder_1.Value;
            disp('Data table in the Main Window updated with the processed files')
            cd(app.SaveFolder_1.Value{:})
            
            % Electrodes setting
            if ~isempty(app.SessionParameters.Electrodes.SettingTable) &&...
                    size(NewRefData,2) < size(app.SessionParameters.Electrodes.SettingTable,1) % some channels have been removed
                app.SessionParameters.Electrodes.SettingTable(~[app.SessionParameters.Electrodes.SettingTable.include{:}],:) = [];
                disp('/!\ WARNING: Some electrodes have been removed from the electrode setting to match the processed data')
                disp('Make sure to save your parameters /!\')
            end
            
            
            %% END
            
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])
            
            
            % PIF
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name  /  Sampling rate  /  N. channels  /  Channel names check  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';ChannelsCheck';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%s\t\t%s\t\t%s\t\t%s\r\n',Files{:});
            
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('Re-reference processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            %Close
            if app.SessionParameters.BatchProcessing.BatchExecution == 0
                rz = msgbox('Rereferencing done.');
            else %batch mode module successly completed                
                if app.batchHasCrashed
                    app.SessionParameters.BatchProcessing.BatchExecution = 0; %break the batch
                end
                
                app.SessionParameters.BatchProcessing.Processes{app.SessionParameters.BatchProcessing.nbCurrentProcess,3}=1; %process complited
                app.EEGpalCS_Main.SessionParameters.BatchProcessing = app.SessionParameters.BatchProcessing; %transfert only Batch variable to the main window
                CancelButtonPushed(app); %to close the module without saving Session Parameters
            end
        end

        % Button pushed function: RunButton_2
        function RunButton_2Pushed(app, event)
            
        end

        % Button pushed function: SaveinmemoryButton_1
        function SaveinmemoryButton_1Pushed(app, event)
            saveMemoryButton(app);
        end

        % Button pushed function: SaveinmemoryButton_2
        function SaveinmemoryButton_2Pushed(app, event)
            saveMemoryButton(app);
        end

        % Button pushed function: CancelButton_1
        function CancelButton_1Pushed(app, event)
            cancelButton(app);
        end

        % Button pushed function: CancelButton_2
        function CancelButton_2Pushed(app, event)
            cancelButton(app);
        end

        % Button pushed function: HelpButton_1
        function HelpButton_1Pushed(app, event)
            openHelp(app);
        end

        % Button pushed function: HelpButton_2
        function HelpButton_2Pushed(app, event)
            openHelp(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_Rereference = uifigure('Visible', 'off');
            app.EEGpalCS_Rereference.Name = 'EEGpal_Re-reference';
            app.EEGpalCS_Rereference.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_RereferenceCloseRequest, true);
            app.REREFERENCELabel = uilabel(app.EEGpalCS_Rereference);
            app.REREFERENCELabel.Text = 'RE-REFERENCE';
            app.NewreferenceListBoxLabel = uilabel(app.EEGpalCS_Rereference);
            app.NewreferenceListBoxLabel.Text = {'New reference:'; ''; 'Ctrl+click to select more'; 'than one channel. The'; 'reference will be the'; 'average of the selected '; 'channels.'; 'The average reference '; 'shouldn''t be selected'; 'with other channels.'};
            app.ReferenceSelection = uilistbox(app.EEGpalCS_Rereference);
            app.ReferenceSelection.Multiselect = 'on';
            app.ReferenceSelection.Value = {'Item 1'};
            app.ScalpelectrodesclusterDropDownLabel = uilabel(app.EEGpalCS_Rereference);
            app.ScalpelectrodesclusterDropDownLabel.Text = 'Scalp electrodes cluster:';
            app.ClusterDropDown = uidropdown(app.EEGpalCS_Rereference);
            app.OUTPUTOPTIONSLabel = uilabel(app.EEGpalCS_Rereference);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.EEGpalCS_Rereference);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.EEGpalCS_Rereference);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.EEGpalCS_Rereference);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.EEGpalCS_Rereference);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.EEGpalCS_Rereference);
            app.SaveFolder.Editable = 'off';
            app.SuffixstringLabel = uilabel(app.EEGpalCS_Rereference);
            app.SuffixstringLabel.Text = 'Suffix string';
            app.SuffixString = uieditfield(app.EEGpalCS_Rereference, 'text');
            app.SuffixString.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString.Value = 'rereferenced';
            app.APPLYLabel = uilabel(app.EEGpalCS_Rereference);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.SaveinmemoryButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window without applying the processing and save the parameters.'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.HelpButton = uibutton(app.EEGpalCS_Rereference, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.TabGroup = uitabgroup(app.EEGpalCS_Rereference);
            app.Reref1Tab = uitab(app.TabGroup);
            app.Reref1Tab.Title = 'Reref1';
            app.CancelButton_1 = uibutton(app.Reref1Tab, 'push');
            app.CancelButton_1.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_1Pushed, true);
            app.CancelButton_1.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton_1.Text = 'Cancel';
            app.SaveinmemoryButton_1 = uibutton(app.Reref1Tab, 'push');
            app.SaveinmemoryButton_1.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButton_1Pushed, true);
            app.SaveinmemoryButton_1.Tooltip = {'Return to the main window without applying the processing and save the parameters.'};
            app.SaveinmemoryButton_1.Text = 'Save in memory';
            app.RunButton_1 = uibutton(app.Reref1Tab, 'push');
            app.RunButton_1.ButtonPushedFcn = createCallbackFcn(app, @RunButton_1Pushed, true);
            app.RunButton_1.Tooltip = {'Run the selected processes'};
            app.RunButton_1.Text = 'Run';
            app.APPLYLabel_1 = uilabel(app.Reref1Tab);
            app.APPLYLabel_1.Text = 'APPLY';
            app.SuffixStringLabel_1 = uilabel(app.Reref1Tab);
            app.SuffixStringLabel_1.Text = 'Suffix string';
            app.SuffixString_1 = uieditfield(app.Reref1Tab, 'text');
            app.SuffixString_1.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString_1.Value = 'rereferenced1';
            app.SaveFolderLabel_1 = uilabel(app.Reref1Tab);
            app.SaveFolderLabel_1.Text = 'Saving folder:';
            app.SaveFolder_1 = uitextarea(app.Reref1Tab);
            app.SaveFolder_1.Editable = 'off';
            app.SelectUpperFolderLabel_1 = uilabel(app.Reref1Tab);
            app.SelectUpperFolderLabel_1.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton_1 = uibutton(app.Reref1Tab, 'push');
            app.SelectFolderButton_1.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_1Pushed, true);
            app.SelectFolderButton_1.Text = 'Select folder';
            app.FilesExtensionLabel_1 = uilabel(app.Reref1Tab);
            app.FilesExtensionLabel_1.Text = 'Extension of output files:';
            app.FilesExtension_1 = uidropdown(app.Reref1Tab);
            app.FilesExtension_1.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension_1.Value = '.sef';
            app.OUTPUTOPTIONSLabel_1 = uilabel(app.Reref1Tab);
            app.OUTPUTOPTIONSLabel_1.Text = 'OUTPUT OPTIONS';
            app.ClusterDropDownLabel_1 = uilabel(app.Reref1Tab);
            app.ClusterDropDownLabel_1.Text = 'Scalp electrodes cluster:';
            app.ClusterDropDown_1 = uidropdown(app.Reref1Tab);
            app.ReferenceSelectionLabel_1 = uilabel(app.Reref1Tab);
            app.ReferenceSelectionLabel_1.Text = {'New reference:'; ''; 'Ctrl+click to select more'; 'than one channel. The'; 'reference will be the'; 'average of the selected '; 'channels.'; 'The average reference '; 'shouldn''t be selected'; 'with other channels.'};
            app.ReferenceSelection_1 = uilistbox(app.Reref1Tab);
            app.ReferenceSelection_1.Multiselect = 'on';
            app.ReferenceSelection_1.Value = {'Item 1'};
            app.REREFERENCELabel_1 = uilabel(app.Reref1Tab);
            app.REREFERENCELabel_1.Text = 'RE-REFERENCE';
            app.HelpButton_1 = uibutton(app.Reref1Tab, 'push');
            app.HelpButton_1.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_1Pushed, true);
            app.HelpButton_1.Text = '?';
            app.Label1 = uilabel(app.Reref1Tab);
            app.Label1.Text = '1';
            app.Reref2Tab = uitab(app.TabGroup);
            app.Reref2Tab.Title = 'Reref2';
            app.CancelButton_2 = uibutton(app.Reref2Tab, 'push');
            app.CancelButton_2.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_2Pushed, true);
            app.CancelButton_2.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton_2.Text = 'Cancel';
            app.SaveinmemoryButton_2 = uibutton(app.Reref2Tab, 'push');
            app.SaveinmemoryButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButton_2Pushed, true);
            app.SaveinmemoryButton_2.Tooltip = {'Return to the main window without applying the processing and save the parameters.'};
            app.SaveinmemoryButton_2.Text = 'Save in memory';
            app.RunButton_2 = uibutton(app.Reref2Tab, 'push');
            app.RunButton_2.ButtonPushedFcn = createCallbackFcn(app, @RunButton_2Pushed, true);
            app.RunButton_2.Tooltip = {'Run the selected processes'};
            app.RunButton_2.Text = 'Run';
            app.APPLYLabel_2 = uilabel(app.Reref2Tab);
            app.APPLYLabel_2.Text = 'APPLY';
            app.SuffixStringLabel_2 = uilabel(app.Reref2Tab);
            app.SuffixStringLabel_2.Text = 'Suffix string';
            app.SuffixString_2 = uieditfield(app.Reref2Tab, 'text');
            app.SuffixString_2.Tooltip = {'If empty, might overwright existing files'};
            app.SuffixString_2.Value = 'rereferenced2';
            app.SaveFolderLabel_2 = uilabel(app.Reref2Tab);
            app.SaveFolderLabel_2.Text = 'Saving folder:';
            app.SaveFolder_2 = uitextarea(app.Reref2Tab);
            app.SaveFolder_2.Editable = 'off';
            app.SelectUpperFolderLabel_2 = uilabel(app.Reref2Tab);
            app.SelectUpperFolderLabel_2.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton_2 = uibutton(app.Reref2Tab, 'push');
            app.SelectFolderButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButton_2Pushed, true);
            app.SelectFolderButton_2.Text = 'Select folder';
            app.FilesExtensionLabel_2 = uilabel(app.Reref2Tab);
            app.FilesExtensionLabel_2.Text = 'Extension of output files:';
            app.FilesExtension_2 = uidropdown(app.Reref2Tab);
            app.FilesExtension_2.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension_2.Value = '.sef';
            app.OUTPUTOPTIONSLabel_2 = uilabel(app.Reref2Tab);
            app.OUTPUTOPTIONSLabel_2.Text = 'OUTPUT OPTIONS';
            app.ClusterDropDownLabel_2 = uilabel(app.Reref2Tab);
            app.ClusterDropDownLabel_2.Text = 'Scalp electrodes cluster:';
            app.ClusterDropDown_2 = uidropdown(app.Reref2Tab);
            app.ReferenceSelectionLabel_2 = uilabel(app.Reref2Tab);
            app.ReferenceSelectionLabel_2.Text = {'New reference:'; ''; 'Ctrl+click to select more'; 'than one channel. The'; 'reference will be the'; 'average of the selected '; 'channels.'; 'The average reference '; 'shouldn''t be selected'; 'with other channels.'};
            app.ReferenceSelection_2 = uilistbox(app.Reref2Tab);
            app.ReferenceSelection_2.Multiselect = 'on';
            app.ReferenceSelection_2.Value = {'Item 1'};
            app.REREFERENCELabel_2 = uilabel(app.Reref2Tab);
            app.REREFERENCELabel_2.Text = 'RE-REFERENCE';
            app.HelpButton_2 = uibutton(app.Reref2Tab, 'push');
            app.HelpButton_2.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_2Pushed, true);
            app.HelpButton_2.Text = '?';
            app.Label2 = uilabel(app.Reref2Tab);
            app.Label2.Text = '2';
            % Show the figure after all components are created
            app.EEGpalCS_Rereference.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Rereference(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_Rereference)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_Rereference)
        end
    end
end