classdef EEGpalCS_mod_Statistics < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpal_Statistics               matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        AveragetimeCheckBox             matlab.ui.control.CheckBox
        InstructElements1               matlab.ui.control.Label
        HelpButton                      matlab.ui.control.Button
        SamplingRate                    matlab.ui.control.NumericEditField
        SamplingRateinHzEditFieldLabel  matlab.ui.control.Label
        ChannelsEditField               matlab.ui.control.EditField
        ChannelsEditFieldLabel          matlab.ui.control.Label
        IntervalOffset_ms               matlab.ui.control.NumericEditField
        IntervaloffsetLabel             matlab.ui.control.Label
        IntervalOnset_ms                matlab.ui.control.NumericEditField
        IntervalonsetLabel              matlab.ui.control.Label
        Instruct_timeinter1             matlab.ui.control.Label
        GMDCheckBox                     matlab.ui.control.CheckBox
        GFPCheckBox                     matlab.ui.control.CheckBox
        AverageCheckBox                 matlab.ui.control.CheckBox
        Epoch_RelativeInterval1_3       matlab.ui.control.Label
        EndOfFile                       matlab.ui.control.StateButton
        IntervalOffset_tf               matlab.ui.control.NumericEditField
        IntervalOnset_tf                matlab.ui.control.NumericEditField
        tfLabel                         matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        Epoch_RelativeInterval1_2       matlab.ui.control.Label
        Epoch_RelativeInterval1         matlab.ui.control.Label
        InstructModule                  matlab.ui.control.Label
        StatisticsmoduleLabel           matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        NumberofrandompermutationsLabel  matlab.ui.control.Label
        NumberofrendompermutationEditField  matlab.ui.control.NumericEditField
        NonParametrictestCheckBox       matlab.ui.control.CheckBox
        StatThresTitle                  matlab.ui.control.Label
        WarningCluster                  matlab.ui.control.Label
        MinimalclusterdurationTFLabel   matlab.ui.control.Label
        FalseDiscoveryrate_q_EditField  matlab.ui.control.NumericEditField
        FalseDiscoveryrate_q_Label      matlab.ui.control.Label
        PvalueClusterEditField          matlab.ui.control.NumericEditField
        PvalueClusterLabel              matlab.ui.control.Label
        MinClusterSize                  matlab.ui.control.NumericEditField
        MinimalclustersizenbofchannelsLabel  matlab.ui.control.Label
        MinClusterDuration              matlab.ui.control.NumericEditField
        MultiplecomparisoncorrectionDropDown  matlab.ui.control.DropDown
        MultiplecomparisoncorrectionDropDownLabel  matlab.ui.control.Label
        PvalueEditField                 matlab.ui.control.NumericEditField
        PvaluethresholdEditFieldLabel   matlab.ui.control.Label
        StatistictoperformDropDown      matlab.ui.control.DropDown
        StatistictoperformLabel         matlab.ui.control.Label
        designDescription               matlab.ui.control.Label
        DesignTitle                     matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        OutputformatDropDown            matlab.ui.control.DropDown
        OutputformatDropDownLabel       matlab.ui.control.Label
        OutputPvalueDropDown            matlab.ui.control.DropDown
        OutputPvalueDropDownLabel       matlab.ui.control.Label
        OptionalSuffix                  matlab.ui.control.EditField
        OptionalsuffixinoutputfilenameEditFieldLabel  matlab.ui.control.Label
        SaveFolder                      matlab.ui.control.TextArea
        SavingfolderLabel               matlab.ui.control.Label
        CancelButton                    matlab.ui.control.Button
        SaveinmemoryButton              matlab.ui.control.Button
        RunButton                       matlab.ui.control.Button
        APPLYLabel                      matlab.ui.control.Label
        SelectFolderButton              matlab.ui.control.Button
        InstructionFolderLabel          matlab.ui.control.Label
        OUTPUTOPTIONSLabel              matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        SettingTable
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function tf = ms2tf(~,ms,sr)
            tf = round((ms / 1000) * sr, 0); % round() because time-frames are counted as integer numbers
                                             % => requires to recompute tf2ms for inputs, to be more precise
        end
       
        
        function ms = tf2ms(~,tf,sr)
            ms = (tf / sr) * 1000;
        end
        
        
        function results = SaveOutputFile(app, data2d, fileName, filePath)
            if strcmp(app.OutputformatDropDown.Value,'.ep') || strcmp(app.OutputformatDropDown.Value,'.eph')
                    save_eph(fullfile(filePath,fileName),data2d,app.SamplingRate.Value);
                elseif strcmp(app.OutputformatDropDown.Value,'.sef')
                   try
                        if  strcmp(app.ChannelsEditField.Value,'*')
                            Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([1:1:size(data2d,2)]));
                        else
                            Channels = char(app.SessionParameters.Electrodes.SettingTable.labels(str2num(app.ChannelsEditField.Value)));
                        end
                        
                        if app.AverageCheckBox.Value
                            Channels(end+1,:)='Avg';
                        end
                        if app.GFPCheckBox.Value
                            Channels(end+1,:)='GFP';
                        end
                        if app.GMDCheckBox.Value
                            Channels(end+1,:)='Dis';
                        end
                    catch
                        Channels = '';
                    end
                    save_sef(fullfile(filePath,fileName),data2d,app.SamplingRate.Value,Channels)

                elseif strcmp(app.OutputformatDropDown.Value,'.set')
                    try
                        ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable(str2num(app.ChannelsEditField.Value),:);
                        Channels            = ElectrodeSetting(:,[6 7 2]);
                        Channels.labels     = char(Channels.labels);
                        Channels.sph_theta  = -Channels.theta;
                        Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                        Channels.X          = ElectrodeSetting.x;
                        Channels.Y          = ElectrodeSetting.y;
                        Channels.Z          = ElectrodeSetting.z;
%                         Channels.ref        = char(repmat({''},height(Channels),1));
%                         Channels.sph_radius = repmat({[]},height(Channels),1);
%                         Channels.types      = char(repmat({''},height(Channels),1));
%                         Channels.urchan     = repmat({[]},height(Channels),1);
                        Channels            = table2struct(Channels);
                        t=size(Channels,1)
                        if app.AverageCheckBox.Value
                            t=t+1;
                            Channels(t).labels='Avg';
                        end
                        if app.GFPCheckBox.Value
                            t=t+1;
                            Channels(t).labels='GFP';
                        end
                        if app.GMDCheckBox.Value
                            t=t+1;
                            Channels(t).labels='Dis';
                        end
                    catch
                        Channels = '';
                    end
                    save_eeglab(fullfile(filePath,fileName),data2d,app.SamplingRate.Value)
                elseif strcmp(app.OutputformatDropDown.Value,'.ris')
                    save_ris(fullfile(filePath,fileName),data2d,app.SamplingRate.Value)
                elseif strcmp(app.OutputformatDropDown.Value,'.csv')
                    try
                        Channels = char(app.SessionParameters.Electrodes.SettingTable.labels(str2num(app.ChannelsEditField.Value)));
                        if app.AverageCheckBox.Value
                            Channels(end+1,:)='Avg';
                        end
                        if app.GFPCheckBox.Value
                            Channels(end+1,:)='GFP';
                        end
                        if app.GMDCheckBox.Value
                            Channels(end+1,:)='Dis';
                        end
                        header=cellstr(Channels)';
                    catch
                        header=num2cell([1:1:size(data2d,2)]);
                    end
                    writecell([header;num2cell(data2d)],fullfile(filePath,fileName));
                end
            
        end
        
        
        function applySessionParam(app)
            % TIMING OPTION            
            app.IntervalOnset_tf.Value                       = app.SessionParameters.Statistics.IntervalOnset_TF;
            IntervalOnset_tfValueChanged(app);
            app.IntervalOffset_tf.Value                      = app.SessionParameters.Statistics.IntervalOffset_TF;
            IntervalOffset_tfValueChanged(app);
            app.AveragetimeCheckBox.Value                    = app.SessionParameters.Statistics.AveragetimeCheckBox;
            AveragetimeCheckBoxValueChanged(app);
            
            % CHANNELS
            app.ChannelsEditField.Value                      = app.SessionParameters.Statistics.ChannelsEditField;
            ChannelsEditFieldValueChanged(app);
            app.AverageCheckBox.Value                        = app.SessionParameters.Statistics.AverageCheckBox;
            app.GFPCheckBox.Value                            = app.SessionParameters.Statistics.GFPCheckBox;
            app.GMDCheckBox.Value                            = app.SessionParameters.Statistics.GMDCheckBox;

            % DESIGN            
            app.PvalueEditField.Value                        = app.SessionParameters.Statistics.PvalueEditField;
            app.NonParametrictestCheckBox.Value              = app.SessionParameters.Statistics.NonParametrictestCheckBox;
            NonParametrictestCheckBoxValueChanged(app);
            app.NumberofrendompermutationEditField.Value     = app.SessionParameters.Statistics.NumberofrendompermutationEditField;
            app.MultiplecomparisoncorrectionDropDown.Value   = app.SessionParameters.Statistics.MultiplecomparisoncorrectionDropDown;
            MultiplecomparisoncorrectionDropDownValueChanged(app);
            app.FalseDiscoveryrate_q_EditField.Value         = app.SessionParameters.Statistics.FalseDiscoveryrate_q_EditField;
            app.MinClusterSize.Value                         = app.SessionParameters.Statistics.MinClusterSize;
            app.MinClusterDuration.Value                     = app.SessionParameters.Statistics.MinClusterDuration;
            app.PvalueClusterEditField.Value                 = app.SessionParameters.Statistics.PvalueClusterEditField;

            % OUTPUT OPTION
            app.OutputformatDropDown.Value                   = app.SessionParameters.Statistics.OutputformatDropDown;
            OutputformatDropDownValueChanged(app);
            app.OutputPvalueDropDown.Value                   = app.SessionParameters.Statistics.OutputPvalueDropDown;
            app.SaveFolder.Value                             = app.SessionParameters.Statistics.SaveFolder;
            app.OptionalSuffix.Value                         = app.SessionParameters.Statistics.OptionalSuffix;
            
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            % TIMING OPTION            
            SessionParameters.Statistics.IntervalOnset_TF                       = app.IntervalOnset_tf.Value;
            SessionParameters.Statistics.IntervalOffset_TF                      = app.IntervalOffset_tf.Value;
            SessionParameters.Statistics.AveragetimeCheckBox                    = app.AveragetimeCheckBox.Value;
            
            % CHANNELS
            SessionParameters.Statistics.ChannelsEditField                      = app.ChannelsEditField.Value;
            SessionParameters.Statistics.AverageCheckBox                        = app.AverageCheckBox.Value;
            SessionParameters.Statistics.GFPCheckBox                            = app.GFPCheckBox.Value;
            SessionParameters.Statistics.GMDCheckBox                            = app.GMDCheckBox.Value;

            % DESIGN
            SessionParameters.Statistics.StatistictoperformDropDown             = app.StatistictoperformDropDown.Value;
            SessionParameters.Statistics.PvalueEditField                        = app.PvalueEditField.Value;
            SessionParameters.Statistics.NonParametrictestCheckBox              = app.NonParametrictestCheckBox.Value;
            SessionParameters.Statistics.NumberofrendompermutationEditField     = app.NumberofrendompermutationEditField.Value;
            SessionParameters.Statistics.MultiplecomparisoncorrectionDropDown   = app.MultiplecomparisoncorrectionDropDown.Value;
            SessionParameters.Statistics.FalseDiscoveryrate_q_EditField         = app.FalseDiscoveryrate_q_EditField.Value;
            SessionParameters.Statistics.MinClusterSize                         = app.MinClusterSize.Value;
            SessionParameters.Statistics.MinClusterDuration                     = app.MinClusterDuration.Value;
            SessionParameters.Statistics.PvalueClusterEditField                 = app.PvalueClusterEditField.Value;

            % OUTPUT OPTION
            SessionParameters.Statistics.OutputformatDropDown                   = app.OutputformatDropDown.Value;
            SessionParameters.Statistics.OutputPvalueDropDown                   = app.OutputPvalueDropDown.Value;
            SessionParameters.Statistics.SaveFolder                             = app.SaveFolder.Value;
            SessionParameters.Statistics.OptionalSuffix                         = app.OptionalSuffix.Value;

        end
        
        function ft_data = converttoFieldtripStruct(app, currentData, labels)
            [nSubj,nTime,nElec] = size(currentData);
            samplingRate= app.DataTable.SamplingRate(1);
            ft_data = [];
            ft_data.individual = permute(currentData,[1 3 2]); % permutation [Sujet Temps Electrodes]-> [Sujet Electrodes Temps]
            ft_data.dimord = 'subj_chan_time';            
            ft_data.time = (app.IntervalOnset_tf.Value:app.IntervalOffset_tf.Value)/samplingRate; %time must be in second
            ft_data.label = labels;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            %Sampling Rate
            app.SamplingRate.Value          = app.DataTable.SamplingRate(1);

            %Preset Cartool/eeglab parameters
            if strcmp(app.EEGpalCS_Main.PresetParametersSwitch.Value,'eeglab')
                app.OutputformatDropDown.Value = '.set';
            end
            
            % Apply Session Parameters
            try
                applySessionParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end
            
            
            %Check the file format and apply the specific GUI
            [~,~,FileExtension] = fileparts(app.DataTable.File(1));
            if strcmp(FileExtension, '.ris')
                 app.GFPCheckBox.Enable="off";
                 app.GMDCheckBox.Enable="off";
                 app.GFPCheckBox.Value=0;
                 app.GMDCheckBox.Value=0;
                 app.OutputformatDropDown.Value='.ris';
            end

            %Reminder of the design
            nbFactors = size(app.SessionParameters.Design.FactTable, 1);
            designRemainder="Design with "+num2str(nbFactors)+ " Factor(s):";
            for i=1:nbFactors
                nbLevels=max(str2double(app.SessionParameters.Design.CondFactTable(:,i+1)));
                temp=app.SessionParameters.Design.FactTable{i, 'Between'};
                if temp{1}
                    factorType="Between";
                else
                    factorType="Within";
                end
                designRemainder=[designRemainder, ...
                    "Factor " + num2str(i) + ": " + app.SessionParameters.Design.FactTable{i,1} + " (Type: " + factorType + ", nb of level: " + num2str(nbLevels) + ")."];
            end
            app.designDescription.Text=designRemainder;

            %Check if you could perform a T-test
            if nbFactors == 1
                if nbLevels == 2
                    if temp{i}
                        app.StatistictoperformDropDown.Items = {'ANOVA between', 'Two Samples T-Test'};
                        app.NonParametrictestCheckBox.Visible = "on";
                        app.NumberofrendompermutationEditField.Visible = "on";
                        app.NumberofrandompermutationsLabel.Visible = "on";
                        StatistictoperformDropDownValueChanged(app);
                    else
                        app.StatistictoperformDropDown.Items = {'ANOVA repeated measure', 'Paired T-Test'};
                        app.NonParametrictestCheckBox.Visible = "on";
                        app.NumberofrendompermutationEditField.Visible = "on";
                        app.NumberofrandompermutationsLabel.Visible = "on";
                        StatistictoperformDropDownValueChanged(app);
                    end
                elseif nbLevels == 1
                    app.StatistictoperformDropDown.Items = {'One sample T-Test'};
                    app.StatistictoperformDropDown.Value = 'One sample T-Test';
                    app.MultiplecomparisoncorrectionDropDown.Items = {'None' 'FDR'};
                end
            else
                %text of the stat for the other case
                NumBetween=sum(cell2mat(app.SessionParameters.Design.FactTable.Between(:))); %compute the number of between factor
                NumWithin=nbFactors-NumBetween; %compute the number of within factor
                if and(NumBetween>0,NumWithin==0)
                    app.StatistictoperformDropDown.Items = {'ANOVA between'};
                elseif and(NumBetween==0,NumWithin>0)
                    app.StatistictoperformDropDown.Items = {'ANOVA repeated measure'};
                else
                    app.StatistictoperformDropDown.Items = {'ANOVA mixed'};
                end 
                StatistictoperformDropDownValueChanged(app);
            end         
            
            %Case when the Statistics_Toolbox is not available. Allow only
            %the non-parametric test of Fieldtrip
            if  ~license('test', 'Statistics_Toolbox')
                temp=app.SessionParameters.Design.FactTable{1, 'Between'};
                if temp{1}
                    app.StatistictoperformDropDown.Items = {'Two Samples T-Test'};
                else
                    app.StatistictoperformDropDown.Items = {'Paired T-Test'}
                end                   
                app.NonParametrictestCheckBox.Visible = "on";
                app.NonParametrictestCheckBox.Value = 1;
                app.NonParametrictestCheckBox.Enable = "off";
                app.NumberofrendompermutationEditField.Visible = "on";
                app.NumberofrandompermutationsLabel.Visible = "on";
                NonParametrictestCheckBoxValueChanged(app);                
            end


        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpal_Statistics); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
                       
            
            %% CHECK          
       
            % check if an output path has been set
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','error');
                return
            end
            
            % Check if sampling rate is correctely specified
            if app.SamplingRate.Value == 1 || app.SamplingRate.Value == 0 
                 msgbox(sprintf('%s\n\n%s','Missing Sampling rate!', ...
                        'The sampling rate is not specified. Please specify it before to continue'),...
                        'Missing Sampling rate','error');
                 return     
            end            

            % check if offset value bigger than onset
            if app.IntervalOffset_tf.Value<app.IntervalOnset_tf.Value
                 msgbox(sprintf('%s\n\n%s','Incorrect Offset time!', ...
                        'The specified offset time is smaller than the onset time. Please select a bigger or equal value.'),...
                        'Incorrect offset','error');
                        return 
            end

            % check if at leat one channel has been specified
            if isempty(app.ChannelsEditField.Value) && ~app.GFPCheckBox.Value && ~app.GMDCheckBox.Value
                 msgbox(sprintf('%s\n\n%s','Incorrect channels specified!', ...
                        'There is no channel spacified. You should specify at least one channel to test.'),...
                        'Incorrect channel selection','error');
                        return 
            end         

            % check that the time interval has at least 2TF if Dis is activated
            if app.GMDCheckBox.Value && (app.IntervalOffset_tf.Value-app.IntervalOnset_tf.Value+1)<2
                 msgbox(sprintf('%s\n\n%s','Incompatible time interval with the Dis!', ...
                        'Your time interval lasts only one TF which is incompatible with the computation of the Dis. It required at least two TF. Please desactivate the GMD or change the time interval.'),...
                        'Incompatible time interval with the Dis','error');
                        return 
            end

            % check if the minimal duration <= nb of TF 
            if app.IntervalOffset_tf.Value-app.IntervalOnset_tf.Value < app.MinClusterDuration.Value
                 msgbox(sprintf('%s\n\n%s','Incorrect value for the minimal duration !', ...
                        'You have specified a higher minimum duration than the time interval to be tested. Please correct this and restart.'),...
                        'Incorrect value for the minimal duration','error');
                 return 
            end
            
            %check that there is a coordinate file before start a spatio-temporal clustering analysis
            if isempty(app.SessionParameters.Electrodes.SettingTable) & strcmp(app.MultiplecomparisoncorrectionDropDown.Value, 'Cluster analysis')
                msgbox(sprintf('%s\n\n%s','No electrode coordinates', ...
                        'The coordinates for the electrodes are not specified. Please go to the Electrode Setting of the main window and specify coordinates for electrodes before to run a cluster analysis.' ),...
                        'No electrode coordinates','error');
                        return 
            end

            %check if SPM is available in Matlab 
            if strcmp(app.MultiplecomparisoncorrectionDropDown.Value, 'Cluster analysis') & isempty(which('spm'))
                msgbox(sprintf('%s\n\n%s','SPM not available', ...
                        'In order to perform the cluster analysis, SPM toolbox is needed. You can download it at the following adress: https://www.fil.ion.ucl.ac.uk/spm/software/#current. Please download it and add it to your Matlab using the commande addpath or the button set path.' ),...
                        'SPM not available','error');
                        return             
            end

            % if strcmp(app.StatistictoperformDropDown.Value, 'ANOVA')
            %     Run = questdlg(sprintf('%s\n%s\n\n%s\n\n%s',...
            %     'Go one!',...
            %     'I am goinig to perform and an ANOVA analysis in the specified interval and electrodes.',...
            %     'I will record an file at the specified location',...
            %     'Do you wish to proceed? '),'Run checked options','OK','Cancel','Cancel');           
            % end
            % 
            % if strcmpi(Run,'Cancel')
            %     return
            % end
            
           
            %% PROCESSING INFORMATION SHEET
            
            currentVersionModule = dir([mfilename('fullpath') '.mlapp']); % Information about current file


            %% RUN MODULE
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ProcessedDataTable(ProcessedDataTable.Condition == "", :) = []; %suppress line which are not associate with a condition
            
            %check the number of specified electrodes
            electrodesSpec=[];
            if ~isempty(app.ChannelsEditField.Value)
                electrodesSpec=str2num(app.ChannelsEditField.Value);
            end
            
            %check if specified all electrodes
            if strcmp(app.ChannelsEditField.Value,'*')
                electrodesSpec=[1:1:ProcessedDataTable.nChan(1)];
            end

            %% Preparation
            %number of files
            nbFiles=size(ProcessedDataTable,1); 
            
            %compute the number of element which will generated/studied
            NumChan=length(electrodesSpec);
            NumSupChan = 0; %count the number of additional channel (average, GFP, GMD)
            electrodesSpecExtended=electrodesSpec;
            IndiceChan=ProcessedDataTable.nChan(1);
            if app.AverageCheckBox.Value
                NumChan=NumChan+1;
                NumSupChan=NumSupChan+1;
                IndiceChan=IndiceChan+1;
                electrodesSpecExtended=[electrodesSpecExtended IndiceChan];
            end
            if app.GFPCheckBox.Value
                NumChan=NumChan+1;
                NumSupChan=NumSupChan+1;
                IndiceChan=IndiceChan+1;
                electrodesSpecExtended=[electrodesSpecExtended IndiceChan];
            end
            if app.GMDCheckBox.Value
                NumChan=NumChan+1;
                NumSupChan=NumSupChan+1;
                IndiceChan=IndiceChan+1;
                electrodesSpecExtended=[electrodesSpecExtended IndiceChan];
            end
                        
            % check if the minimal cluster size <= nb of EEG channels 
            if NumChan-NumSupChan < app.MinClusterSize.Value
                 msgbox(sprintf('%s\n\n%s','Incorrect value for the minimal cluster size !', ...
                        'You have specified a higher minimum cluster size than the number of channels to be processed (excluding Average, GFP and Dis). Please correct this and restart.'),...
                        'Incorrect value for the minimal cluster size','error');
                 return 
            end
            
            % compute the number of subjects for analysis
            SUBJ = unique(ProcessedDataTable.Subject);
            NumSubj = length(SUBJ);
           

            %compute the number of TF to study
            if ~app.AveragetimeCheckBox.Value
                NumTF=app.IntervalOffset_tf.Value - app.IntervalOnset_tf.Value+1; %update the time computed
            else
                NumTF=1;
            end

            %compute the number of condition
            COND = app.SessionParameters.Design.CondFactTable(:,1);
            NumCond=length(COND);
                         
            % Prepare matrix for the data per condition
            DataMatrix = zeros(NumSubj,NumTF,NumChan,NumCond);
            
            %Last quality check for paired T-test
            if strcmp(app.StatistictoperformDropDown.Value, "Paired T-Test")
                if NumSubj ~= nbFiles/2
                     msgbox(sprintf('%s\n\n%s','Problem with subject number and number of file associate with a condition!', ...
                        'There is a different number of subject that (nb of file)/2. You must have two file per participant to pair them.'),...
                        'Inadequat number of participants and files!','error');
                        return
                end
            end
                        
            %save output file names
            tempsavefileNameOutput=strings(1,1);
            countOutputFiles=0;
            
            StartTime = datetime('now');
            tStart = tic;
            h = waitbar(0,'Loading files.....');
            countForWaitbar=0;   
            
            %% Loading of the input files                     
            for subj = 1:NumSubj
                for cond = 1:NumCond
                    for file = 1:nbFiles
                        if contains(ProcessedDataTable.File(file),SUBJ(subj,1)) && contains(ProcessedDataTable.File(file),COND(cond,1))
                            countForWaitbar=countForWaitbar+1;  
                            waitbar(countForWaitbar/nbFiles,h, strcat('Loading Files',num2str(countForWaitbar),'/',num2str(nbFiles)));
                
                            % Load file                            
                            openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(ProcessedDataTable.Path(file),'..'),ProcessedDataTable.File(file))); % full path and name of the file
                           
                            [~,FileName,FileExtension] = fileparts(openfilename);
                            if strcmpi(FileExtension,'.bdf')
                                [header,data,events] = open_bdf(openfilename);
                                events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                                FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                            elseif strcmpi(FileExtension,'.set')
                                [header,data,events] = open_eeglab(openfilename);
                            elseif strcmpi(FileExtension,'.sef')
                                [header,data,events] = open_sef(openfilename);
                                FileChannels = header.Channels;
                            elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                                [header,data,events] = open_eph(openfilename);
                            elseif strcmpi(FileExtension,'.ris')
                                [header,data] = open_ris(openfilename);                         
                            else
                                msgbox(sprintf('%s\n\n%s','Invalid file format!', ...
                                    'The processing can not continue with the current file selection. The file format is porbabely not supported.'),...
                                    'File format not supported','error');
                                return
                            end

                            %extract the specified time period
                            data=data(app.IntervalOnset_tf.Value+1:app.IntervalOffset_tf.Value+1,:);                            

                            %compute average time if need it
                            if app.AveragetimeCheckBox.Value
                                data=mean(data,1);
                            end

                            %compute additional channels
                            %Look at electrod mean
                            if app.AverageCheckBox.Value
                                avgElectrode=mean(data(:,electrodesSpec),2);
                                data(:,end+1)=avgElectrode; %add the average all electrode                               
                            end
                            %Look at the GFP
                            if app.GFPCheckBox.Value
                                GFP=compute_gfp(data(:,1:ProcessedDataTable.nChan(1)));
                                data(:,end+1)=GFP; %add the GFP
                            end
                            %Look at the GMD/Dis
                            if app.GMDCheckBox.Value
                                GMD=compute_gmd(data(:,1:ProcessedDataTable.nChan(1)));
                                data(:,end+1)=GMD; %add the Dis 
                            end

                            %extract the specified channels
                            data=data(:,electrodesSpecExtended);                            

                            %Store in the DataMatrix for all data together in 4D matrix (SubjectNumber x NbTF x channels x condition)
                            DataMatrix(subj,:,:,cond) = data;
                        end
                    end
                end
            end
            delete (h); % close importation progression bar

            
            if length(strfind(app.StatistictoperformDropDown.Value,"ANOVA"))==1
                %% ANOVAs

                %%Model specifications
                
                %compute number of factor, condition as well as between and within facor
                NumFactor=length(cell2mat(app.SessionParameters.Design.FactTable.Between(:)));
                NumBetween=sum(cell2mat(app.SessionParameters.Design.FactTable.Between(:))); %compute the number of between factor
                NumWithin=NumFactor-NumBetween; %compute the number of within factor
                
                % Prepare ConditionTable and Data Matrix
                if NumBetween == 0 %No Between factor
                    betweenModel = '1'; % if no between-subjects factor, put constant term (usually implicit)
                    
                    % Prepare ConditionTable to be a table with categorical variables
                    ConditionTable = array2table(categorical(app.SessionParameters.Design.CondFactTable(:,2:end)), 'VariableNames', app.SessionParameters.Design.FactTable.Factors);
                    for fact = 1:NumFactor
                        Levels = unique(ConditionTable{:,fact},'stable');
                        for level = 1:length(Levels)
                            ConditionTable(ConditionTable{:,fact}==Levels(level,1),fact) = {categorical(level)};
                        end
                        ConditionTable.(fact) = removecats(ConditionTable.(fact));
                    end
                    
                    % Wilkinson notation to study all the main effects and interactions
                    Model = strjoin(app.SessionParameters.Design.FactTable.Factors,'*'); % will generate X1*X2 which is equivalent to X1, X2, X1:X2

                    % Safety check that COND elements don't start by a number (make the commande fitrm crash if it is the case)
                    for cond = 1:NumCond
                        if ~isempty(str2num(COND{cond}(1)))
                            COND(cond)=strcat('_',COND(cond));
                        end
                    end

                else %Presence of a between factor                  
                    %find which factor is within and between
                    factorWithin=find(~cell2mat(app.SessionParameters.Design.FactTable.Between(:)));
                    factorBetween=find(cell2mat(app.SessionParameters.Design.FactTable.Between(:)));
                    
                    %regroup levels for the within
                    Factorlevel=strings(NumCond,1);
                    if NumWithin ~= 0 
                        for indiceCond=1:NumCond
                            for indiceFact=2:NumFactor+1
                                Factorlevel(indiceCond)=strcat(Factorlevel(indiceCond), app.SessionParameters.Design.CondFactTable{indiceCond,indiceFact});
                            end
                        end
                        Factorlevel=char(Factorlevel);
                        Factorlevel=Factorlevel(:,factorWithin);
                        [~,~,concatenateVector]=unique(string(Factorlevel));
                    else
                        concatenateVector=ones(NumCond,1);
                    end

                    %concatenate within effetcs                    
                    newNumCond=length(unique(concatenateVector));
                    newDataMatrix=[];       

                    for indiceCondNEW=1:newNumCond
                        idx = find(concatenateVector == indiceCondNEW);
                        newDataMatrix(:,:,:,indiceCondNEW) = sum(DataMatrix(:,:,:,idx),4);                        
                    end
                    
                    %compute BetweenFactor indices
                    BetweenFactors=zeros(NumSubj,length(factorBetween));                    
                    for subjIndice=1:NumSubj
                        idx = find(strcmp(ProcessedDataTable.Subject, SUBJ(subjIndice)), 1, 'first');
                        for factorIndice=1:length(factorBetween)
                            temp=char(ProcessedDataTable.FactorLevel{idx});
                            BetweenFactors(subjIndice,factorIndice)=str2num(temp(factorBetween(factorIndice)));                           
                        end
                    end
                    %Note: The factor are stored in the same order as File
                    %load order (and organization of the DataMatrix) which correspond to the order of the variable SUBJ.  
                    %It is not necessary the same order than
                    %ProcessedDataTable. However, this version is CORRECT.

                    %generation of the newCOND vector et ConditionTable 
                    newCOND="";
                    factorWithinName=cell(NumWithin,1);
                    if NumWithin > 0
                        indice=0;                        
                        for q=1:length(factorWithin)
                            for u=1:max(str2double(app.SessionParameters.Design.CondFactTable(:, q+1)))
                               indice=indice+1;
                               newCOND(indice)=strcat(app.SessionParameters.Design.FactTable.Factors(factorWithin(q)),'_',num2str(u));
                            end
                            factorWithinName{q}=app.SessionParameters.Design.FactTable.Factors{factorWithin(q)};
                        end

                        %Generation of Condition Table               
                        ConditionTable = table('Size',[size(newDataMatrix,4), NumWithin],'VariableTypes', repmat({'categorical'}, 1, NumWithin), 'VariableNames', factorWithinName);
                        withinIndex=unique(concatenateVector);
                        for u=1:NumWithin                            
                            temp="";
                            for q=1:length(withinIndex)
                                idx = find(concatenateVector == withinIndex(q), 1, 'first');
                                temp(q)=app.SessionParameters.Design.CondFactTable(idx,factorWithin(u)+1);
                            end
                            ConditionTable{:,u}=categorical(temp)';
                        end

                    else
                        newCOND="values";
                    end
                                        
                    % Generate Model with Wilkinson notation only for within factor
                    if NumWithin > 0
                        Model = strjoin(app.SessionParameters.Design.FactTable.Factors(factorWithin(:)),'*'); % will generate X1*X2 which is equivalent to X1, X2, X1:X2
                    end
                    
                    %generate the betweenModel variable
                    betweenModel=app.SessionParameters.Design.FactTable.Factors(factorBetween(1));
                    for u=2:length(factorBetween)
                        betweenModel=strcat(betweenModel,'*',app.SessionParameters.Design.FactTable.Factors(factorBetween(u)));
                    end

                    %generate the BetweenFACT which contains between factor names
                    BetweenFACT=""; %to contain between factor name
                    for factorIndice=1:length(factorBetween)
                        BetweenFACT(factorIndice)=app.SessionParameters.Design.FactTable.Factors(factorBetween(factorIndice));
                    end
                end

                
                % Number of Main and Interaction effects
                NumEff = NumFactor;
                for n = NumFactor-1:-1:1
                    NumEff = NumEff + (n * (n + 1)) / 2;
                end
    
                % Initialisation of results matrix
                DatamatrixDimension=size(DataMatrix);
                Pvals = zeros(DatamatrixDimension(2),DatamatrixDimension(3),NumEff);
                PvalsCorrected = zeros(DatamatrixDimension(2),DatamatrixDimension(3),NumEff);
                Fvals = zeros(DatamatrixDimension(2),DatamatrixDimension(3),NumEff);
    
                %%generation of the ModelSpecs variable                
                if NumBetween == 0 %within model
                    ModelSpecs = sprintf('%s-%s~%s', COND{1},COND{NumCond},betweenModel);
                elseif NumWithin == 0 %between model                  
                    ModelSpecs = sprintf('%s~%s', newCOND{1},betweenModel{1});
                else %mix model
                    ModelSpecs = sprintf('%s-%s~%s', newCOND{1},newCOND{end},betweenModel{1});
                end
    
                %%ANOVAsConditionTable
                tANOVAs = tic; % Start time (for progression bar)
                bar_tf = waitbar(0,'Computing ANOVAs','Name','Computing ANOVAs'); % Progression bar
    
                for tf = 1:NumTF
                    for channel = 1:NumChan
                        waitbar(((tf-1)*NumChan+channel)/(NumTF*NumChan),bar_tf,strcat('Computing ANOVAs, remaining time:',{' '},...
                            num2str(floor((((toc(tANOVAs)/((tf-1)*NumChan+channel))*(NumTF*NumChan))-toc(tANOVAs))/86400)),' day(s)',... % 86400 is the number of seconds in a day
                            datestr(seconds(((toc(tANOVAs)/((tf-1)*NumChan+channel))*(NumTF*NumChan))-toc(tANOVAs)),' HH:MM:SS'))); % = elapsed time / steps completed * Tot number of steps - elapsed time
                                                                
                        if  NumBetween == 0
                            DataTable = array2table(squeeze(DataMatrix(:,tf,channel,:)),'VariableNames',COND);
                        else
                            betweenTable = array2table(BetweenFactors,'VariableNames',BetweenFACT);
                            withinTable = array2table(squeeze(newDataMatrix(:,tf,channel,:)),'VariableNames',newCOND);
                            DataTable=[betweenTable,withinTable]; %concatenation of between and within table
                        end
                        
                        % Fit repeated measures model                    
                        if NumBetween == 0 %within model
                            rm = fitrm(DataTable,ModelSpecs,'WithinDesign',ConditionTable);
                            rmANOVA = ranova(rm,'WithinModel',Model);
                        elseif NumWithin == 0 %between model
                            if NumBetween == 1
                                [~,tbl]=anovan(table2array(withinTable),table2array(betweenTable),'varnames',BetweenFACT, 'display', 'off');
                            else
                                [~,tbl]=anovan(table2array(withinTable),table2array(betweenTable),'model','interaction','varnames',BetweenFACT, 'display', 'off');
                            end

                        else %mix model                            
                            rm = fitrm(DataTable,ModelSpecs,'WithinDesign',ConditionTable);
                            rmANOVA = ranova(rm,'WithinModel',Model);
                            
                        end    

                        % Extract p-values and F-values
                        countEffect=0;
                        vecIndEffect=[];
                        if NumWithin ~=0                            
                            for rmInd=1:size(rmANOVA,1)
                                if ~strcmp(rmANOVA.Properties.RowNames{rmInd},'(Intercept)') && ~contains(rmANOVA.Properties.RowNames{rmInd},'Error')
                                    countEffect=countEffect+1;
                                    Pvals(tf,channel,countEffect) = rmANOVA.pValue(rmInd);
                                    Fvals(tf,channel,countEffect) = rmANOVA.F(rmInd);
                                    vecIndEffect(countEffect)=rmInd;
                                end
                            end
                        else %case only between
                            for aInd=2:size(tbl,1)
                                if ~strcmp(tbl{aInd,1},'Error') && ~strcmp(tbl{aInd,1},'Total')
                                    countEffect=countEffect+1;
                                    Pvals(tf,channel,countEffect) = tbl{aInd,7};
                                    Fvals(tf,channel,countEffect) = tbl{aInd,6};
                                    vecIndEffect(countEffect)=aInd;
                                end
                            end
                        end
    
                    end
                end
                close(bar_tf)

            elseif length(strfind(app.StatistictoperformDropDown.Value,"T-Test"))==1 & ~app.NonParametrictestCheckBox.Value %classical T-test using Statistical toolbox
                %% T-tests               
                
                % Initialisation of results matrix
                NumEff=1;
                DatamatrixDimension=size(DataMatrix);
                Pvals = zeros(DatamatrixDimension(2),DatamatrixDimension(3),NumEff);
                PvalsCorrected = zeros(DatamatrixDimension(2),DatamatrixDimension(3),NumEff);
                Tvals = zeros(DatamatrixDimension(2),DatamatrixDimension(3),NumEff);                
              

                %%Perform the statistical test
                tTest = tic; % Start time (for progression bar)
                bar_tf = waitbar(0,'Computing T-test','Name','Computing T-test'); % Progression bar
    
                for tf = 1:NumTF
                    for channel = 1:NumChan
                        waitbar(((tf-1)*NumChan+channel)/(NumTF*NumChan),bar_tf,strcat('Computing T-test, remaining time:',{' '},...
                            num2str(floor((((toc(tTest)/((tf-1)*NumChan+channel))*(NumTF*NumChan))-toc(tTest))/86400)),' day(s)',... % 86400 is the number of seconds in a day
                            datestr(seconds(((toc(tTest)/((tf-1)*NumChan+channel))*(NumTF*NumChan))-toc(tTest)),' HH:MM:SS'))); % = elapsed time / steps completed * Tot number of steps - elapsed time
                        
                        %CORRECT THE CALL OF DATAmatrix (SubjectNumber x NbTF x channels x condition)!!! 

                        if strcmp(app.StatistictoperformDropDown.Value,"Paired T-Test")
                            [~,Pvals(tf,channel,1),~,stats] = ttest(squeeze(DataMatrix(:,tf,channel,1)),squeeze(DataMatrix(:,tf,channel,2)));
                            Tvals(tf,channel,1)=stats.tstat;

                        elseif strcmp(app.StatistictoperformDropDown.Value,"One sample T-Test")
                            [~,Pvals(tf,channel,1),~,stats] = ttest(squeeze(DataMatrix(:,tf,channel,1)));
                            Tvals(tf,channel,1)=stats.tstat;
                        else %Two sample T-test
                            [~,Pvals(tf,channel,1),~,stats] = ttest2(squeeze(DataMatrix(:,tf,channel,1)),squeeze(DataMatrix(:,tf,channel,2)));
                            Tvals(tf,channel,1)=stats.tstat;
                        end
                    end
                end
                close(bar_tf)
            
            elseif length(strfind(app.StatistictoperformDropDown.Value,"T-Test"))==1 & app.NonParametrictestCheckBox.Value % non parametric T-test using Fieldtrip
                               
                %Extract the label of the electrodes
                labels=cellstr(app.SessionParameters.Electrodes.SettingTable.labels(electrodesSpec));

                %Conversion into FieldTrip structure
                ft_data_cond1 = converttoFieldtripStruct(app, DataMatrix(:,:,:,1), labels);                
                ft_data_cond2 = converttoFieldtripStruct(app, DataMatrix(:,:,:,2), labels);

                if strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'Cluster analysis') %perform a cluster analysis               
                    
                    %Preparation of the spatial configuration (determine which electrodes are neighbours)
                    X=app.SessionParameters.Electrodes.SettingTable.x(electrodesSpec);
                    Y=app.SessionParameters.Electrodes.SettingTable.y(electrodesSpec);
                    Z=app.SessionParameters.Electrodes.SettingTable.z(electrodesSpec);
    
                    elec = struct(); %creation of the FieldTrip structure
                    elec.label = labels; % labels
                    elec.elecpos = [X Y Z]; %electrode coorddinates
                    elec.chanpos = [X Y Z]; %channel coordinates
                    cfg = [];
                    cfg.method = 'triangulation';
                    cfg.elec = elec;
                    
                    neighbours = ft_prepare_neighbours(cfg); %note: the result wild me identical if the corrdinate are in mm (as xyz file) or noramlized (as in locs file)

                    %setup the Cluster-based permutation test
                    cfg = [];                    
                    cfg.correctm = 'cluster';                    
                    cfg.clusterstatistic = 'maxsum';
                    cfg.clusteralpha  = app.PvalueEditField.Value; %Seuil de formation des clusters : seuil appliqué aux statistiques voxel/sample par voxel avant le clustering
                    cfg.alpha = app.PvalueClusterEditField.Value; %Seuil de significativité au niveau du cluster : seuil utilisé pour déterminer si le cluster obtenu est significatif après permutation
                    cfg.clustertail      = 0;
                    cfg.neighbours = neighbours;

                else %non-parametric without cluster analysis                     
                    cfg = [];
                    cfg.correctm = 'none';
                    cfg.alpha = app.PvalueEditField.Value;
                end

                %configuration of the non corrected test
                cfg.method = 'montecarlo';                
                cfg.tail = 0;
                cfg.numrandomization = app.NumberofrendompermutationEditField.Value;

                if strcmp (app.StatistictoperformDropDown.Value,'Paired T-Test')                    
                    bar_tf = waitbar(1,'Computing pared T-test with montecarlo permutations','Name','Computing T-test'); % Progression bar
                    design = zeros(2,2*NumSubj);
                    design(1,:) = [1:NumSubj 1:NumSubj]; % participant indices
                    design(2,:) = [ones(1,NumSubj) 2*ones(1,NumSubj)]; % condition indices
                    
                    cfg.statistic = 'ft_statfun_depsamplesT';                      
                    cfg.design = design;
                    cfg.uvar = 1; % subject
                    cfg.ivar = 2; % condition

                    % Run the Analysis 
                    stat = ft_timelockstatistics(cfg,ft_data_cond1,ft_data_cond2);

                    close(bar_tf)

                elseif strcmp (app.StatistictoperformDropDown.Value,'Two Samples T-Test')
                    bar_tf = waitbar(1,'Computing Two sample T-test with montecarlo permutations','Name','Computing T-test'); % Progression bar
                    n1 = size(ft_data_cond1.individual,1);
                    n2 = size(ft_data_cond2.individual,1);

                    design = [ones(1,n1),2*ones(1,n2)]; %condition indices

                    cfg.statistic = 'indepsamplesT';                      
                    cfg.design = design;
                    cfg.ivar = 1; % condition

                     % Run the Analysis  
                    stat = ft_timelockstatistics(cfg,ft_data_cond1,ft_data_cond2);

                    close(bar_tf)           
                end 


                % Manage the output in the case of an non-parametric statistic
                if strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'Cluster analysis')
                    %determine the number of positive and negative cluster
                    PositiveClusters=find([stat.posclusters.prob]<cfg.alpha); %je confirme que c'est bien cfg.alpha qui est utilisé pour déterminé si la stat du cluster est significative ou pas (et pas cfg.clusteralpha). 
                    NbPositiveClusters=length(PositiveClusters);
                    NegativeClusters=find([stat.negclusters.prob]<cfg.alpha);
                    NbNegativeClusters=length(NegativeClusters);

                    %creation of P-value results
                    PositiveClustersPvals=zeros(NumTF,NumChan,NbPositiveClusters);
                    NegativeClustersPvals=zeros(NumTF,NumChan,NbNegativeClusters);
                    AllClustersPvals=zeros(NumTF,NumChan);

                    %creation of the statistics
                    PositiveClustersClusterstat=zeros(NumTF,NumChan,NbPositiveClusters);
                    NegativeClustersClusterstat=zeros(NumTF,NumChan,NbNegativeClusters);
                    AllClustersClusterstat=zeros(NumTF,NumChan);

                    %fill with values (p-value cluster and cluster stat)
                    for pos=1:NbPositiveClusters
                        tempMatrixOfSignificativity=double(stat.posclusterslabelmat'== PositiveClusters(pos));
                        PositiveClustersPvals(:,:,pos)=tempMatrixOfSignificativity.*stat.posclusters(PositiveClusters(pos)).prob;
                        PositiveClustersClusterstat(:,:,pos)=tempMatrixOfSignificativity.*stat.posclusters(PositiveClusters(pos)).clusterstat;
                        AllClustersPvals=AllClustersPvals+PositiveClustersPvals(:,:,pos);
                        AllClustersClusterstat=AllClustersClusterstat+PositiveClustersClusterstat(:,:,pos);
                    end
                    for neg=1:NbNegativeClusters
                        tempMatrixOfSignificativity=double(stat.negclusterslabelmat'== NegativeClusters(neg));
                        NegativeClustersPvals(:,:,neg)=tempMatrixOfSignificativity.*stat.negclusters(NegativeClusters(neg)).prob;
                        NegativeClustersClusterstat(:,:,neg)=tempMatrixOfSignificativity.*stat.negclusters(NegativeClusters(neg)).clusterstat;
                        AllClustersPvals=AllClustersPvals+NegativeClustersPvals(:,:,neg);
                        AllClustersClusterstat=AllClustersClusterstat+NegativeClustersClusterstat(:,:,neg);
                    end
                    
                    
                    %test
                    % figure;
                    % imagesc(stat.time,1:length(stat.label),AllClustersClusterstat(:,:)');
                    % xlabel('Temps (s)');
                    % ylabel('Electrode');
                    % title('Clusters significatifs');
                    % colorbar;


                else %non-parametric_test without cluster                    
                    % Initialisation of results matrix
                    NumEff=1;
                    DatamatrixDimension=size(DataMatrix);
                    Pvals = zeros(DatamatrixDimension(2),DatamatrixDimension(3),NumEff);
                    PvalsCorrected = zeros(DatamatrixDimension(2),DatamatrixDimension(3),NumEff);
                    Tvals = zeros(DatamatrixDimension(2),DatamatrixDimension(3),NumEff); 
                    
                    Pvals = stat.prob';
                    Tvals = stat.stat';    
                end
                               
            end


            %% Additional thresholding and 1-p computation
            % FDR correction
            if strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'FDR')                
                for effect = 1:NumEff
                    h=zeros(size(Pvals(:,:,effect)));
                    NumEEGChan=NumChan-NumSupChan;
                    
                    %correction for the EEG channels according number of electrodes (space)
                    for TFp=1:size(Pvals,1) %apply the FDR correction separetly for each TF like in Cartool (instead of correcting through all tests which is too conservative)
                        [h(TFp,1:NumEEGChan),~,~,~]=fdr_bh(Pvals(TFp,1:NumEEGChan,effect), app.FalseDiscoveryrate_q_EditField.Value, 'dep', 'no');
                    end

                    %correction for supplementary channels (avg, GFP, GMD) according number of TF (time)                    
                    for supChan=1:NumSupChan
                        [h(:,NumEEGChan+supChan),~,~,~]=fdr_bh(Pvals(:,NumEEGChan+supChan,effect), app.FalseDiscoveryrate_q_EditField.Value, 'dep', 'no');
                    end
                    
                    %retrieve significant P-values
                    for x=1:NumTF
                        for y=1:NumChan
                            if h(x,y)>0
                                if strcmp(app.OutputPvalueDropDown.Value,'1-p')
                                    PvalsCorrected(x,y,effect)=1-Pvals(x,y,effect); % 1-p
                                else
                                    PvalsCorrected(x,y,effect)=Pvals(x,y,effect); % p
                                end
                            end
                        end
                    end                    
                end
                
                %cleaning NaN
                PvalsCorrected(isnan(PvalsCorrected)) = 0;

            % None correction
            elseif strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'None')
                for effect = 1:NumEff
                    for x=1:NumTF
                        for y=1:NumChan
                            if Pvals(x,y,effect)<app.PvalueEditField.Value
                                if strcmp(app.OutputPvalueDropDown.Value,'1-p')
                                    PvalsCorrected(x,y,effect)=1-Pvals(x,y, effect);
                                else
                                    PvalsCorrected(x,y,effect)=Pvals(x,y, effect);
                                end
                            end
                        end
                    end
                end

                %cleaning NaN
                PvalsCorrected(isnan(PvalsCorrected)) = 0;            
            
            elseif strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'Cluster analysis')
                if strcmp(app.OutputPvalueDropDown.Value,'1-p')
                    %Positive clusters
                    for pos=1:NbPositiveClusters
                        for x=1:NumTF
                            for y=1:NumChan
                                if PositiveClustersPvals(x,y,pos)~=0
                                    PositiveClustersPvals(x,y,pos)=1-PositiveClustersPvals(x,y,pos);
                                end
                            end
                        end
                    end
                    %Negative clusters
                    for neg=1:NbNegativeClusters
                        for x=1:NumTF
                            for y=1:NumChan
                                if NegativeClustersPvals(x,y,neg)~=0
                                    NegativeClustersPvals(x,y,neg)=1-NegativeClustersPvals(x,y,neg);
                                end
                            end
                        end
                    end
                    %All clusters
                    for x=1:NumTF
                        for y=1:NumChan
                            if AllClustersPvals(x,y)~=0
                                AllClustersPvals(x,y)=1-AllClustersPvals(x,y);
                            end
                        end
                    end
                end

                %cleaning NaN
                PositiveClustersPvals(isnan(PositiveClustersPvals)) = 0;
                NegativeClustersPvals(isnan(NegativeClustersPvals)) = 0;
                AllClustersPvals(isnan(AllClustersPvals)) = 0;
            end
            
            % minimal cluster size correction
            if app.MinClusterSize.Value>1 %execute only if a threshold is specified          
                for effect = 1:NumEff
                     for x=1:NumTF
                        consecutiveCount = 0; %to count consecutive values
                        for y=1:length(electrodesSpec) % number of electrode without GFP, DISS, mean
                            if PvalsCorrected(x,y,effect) ~= 0
                                consecutiveCount = consecutiveCount + 1; % Augmente le compteur
                            else
                                % Remet à zéro le compteur si une valeur non significative est rencontrée
                                if consecutiveCount < app.MinClusterSize.Value
                                    % Remplace les valeurs précédentes par 0
                                    PvalsCorrected(x, y-consecutiveCount:y-1, effect) = 0;
                                end
                                consecutiveCount = 0; % Réinitialise le compteur
                            end
                        end
    
                        % Vérifie le dernier segment si la colonne se termine sur une séquence significative
                        if consecutiveCount < app.MinClusterSize.Value
                            PvalsCorrected(x, y-consecutiveCount:y-1, effect) = 0;
                        end
                    end

                end

            end
            
            % minimal Duration
            if app.MinClusterDuration.Value>1 %execute only if a threshold is specified          
                for effect = 1:NumEff
                    for y=1:NumChan % number of electrode including GFP, DISS, mean 
                        consecutiveCount = 0; %to count consecutive values
                        for x=1:NumTF
                            if PvalsCorrected(x,y,effect) ~= 0
                                consecutiveCount = consecutiveCount + 1; % Augmente le compteur
                            else
                                % Remet à zéro le compteur si une valeur non significative est rencontrée
                                if consecutiveCount < app.MinClusterDuration.Value
                                    % Remplace les valeurs précédentes par 0
                                    PvalsCorrected(x-consecutiveCount:x-1, y, effect) = 0;
                                end
                                consecutiveCount = 0; % Réinitialise le compteur
                            end
                        end
    
                        % Vérifie le dernier segment si la ligne se termine sur une séquence significative
                        if consecutiveCount < app.MinClusterDuration.Value
                            PvalsCorrected(x-consecutiveCount+1:x, y, effect) = 0;
                        end
                    end

                end

            end

            %% Save the output            
            
            % Suffix
            if ~isempty(app.OptionalSuffix.Value)
                suffix = ['_', app.OptionalSuffix.Value];
            else
                suffix = ''; % = '';
            end

            %output path
            if length(strfind(app.StatistictoperformDropDown.Value,"ANOVA"))==1
                SubPath = 'ANOVA';
                NewPath = fullfile(app.SaveFolder.Value{:}, SubPath);

                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end    

                %Which correction applied
                if strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'None')
                    CorrectionName='';
                elseif strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'FDR')
                    CorrectionName='_FDRcorrected';                
                end
                
                for effect = 1:NumEff                    
                    if NumWithin~=0
                        Name = rmANOVA.Properties.RowNames{vecIndEffect(effect)};
                    else %case only between
                        Name = tbl{vecIndEffect(effect),1};
                    end
                    EffectNames=strrep(Name,'(Intercept):',''); %remove intercepte
                    if contains (EffectNames,':')
                        EffectNames=strcat('Interaction_',strrep(EffectNames,':','x'));
                    else
                        EffectNames=strcat('MainEffect_',EffectNames);
                    end                      
                  
                    %file save
                    savefilenameF = strcat('Fvalues_',EffectNames, suffix,'.f', app.OutputformatDropDown.Value);
                    if strcmp(app.OutputPvalueDropDown.Value,'1-p')
                        savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.1-p', app.OutputformatDropDown.Value);
                    else
                        savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.p', app.OutputformatDropDown.Value);
                    end

                    SaveOutputFile(app,Fvals(:,:,effect),savefilenameF,NewPath); % save F-value
                    SaveOutputFile(app,PvalsCorrected(:,:,effect),savefilenameP,NewPath); % save P-value

                    %Save outputfile name for PIF
                    countOutputFiles=countOutputFiles+1;
                    tempsavefileNameOutput(countOutputFiles)=savefilenameF;
                    countOutputFiles=countOutputFiles+1;
                    tempsavefileNameOutput(countOutputFiles)=savefilenameP;
                end
            elseif length(strfind(app.StatistictoperformDropDown.Value,"T-Test"))==1 & ~app.NonParametrictestCheckBox.Value %classical T-test using Statistical toolbox
                SubPath = 'Ttest';
                NewPath = fullfile(app.SaveFolder.Value{:}, SubPath);
                
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end                 
                
                if strcmp(app.StatistictoperformDropDown.Value,"Paired T-Test")
                     EffectNames='pairedT';
                elseif strcmp(app.StatistictoperformDropDown.Value,"One sample T-Test")
                     EffectNames='oneT';
                else
                     EffectNames='unpairedT';
                end

                %Which correction applied
                if strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'None')
                    CorrectionName='';
                elseif strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'FDR')
                    CorrectionName='_FDRcorrected';                
                end
  

                savefilenameT = strcat('Tvalues_',EffectNames, suffix,'.t', app.OutputformatDropDown.Value);
                if strcmp(app.OutputPvalueDropDown.Value,'1-p')
                    savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.1-p', app.OutputformatDropDown.Value);
                else
                     savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.p', app.OutputformatDropDown.Value);
                end

                SaveOutputFile(app,Tvals(:,:),savefilenameT,NewPath); % save F-value
                SaveOutputFile(app,PvalsCorrected(:,:),savefilenameP,NewPath); % save P-value

                %Save outputfile name for PIF
                countOutputFiles=countOutputFiles+1;
                tempsavefileNameOutput(countOutputFiles)=savefilenameT;
                countOutputFiles=countOutputFiles+1;
                tempsavefileNameOutput(countOutputFiles)=savefilenameP;

            elseif length(strfind(app.StatistictoperformDropDown.Value,"T-Test"))==1 & app.NonParametrictestCheckBox.Value % non parametric T-test using Fieldtrip
                SubPath = 'Ttest';
                NewPath = fullfile(app.SaveFolder.Value{:}, SubPath);
                
                % Creating the folder
                if ~exist(NewPath, 'dir')
                    mkdir(NewPath);
                end                 
                
                if strcmp(app.StatistictoperformDropDown.Value,"Paired T-Test")
                     EffectNames='non-Parametric_pairedT';                 
                else
                     EffectNames='non-Parametric_unpairedT';
                end

                if strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'Cluster analysis')
                    %Save all positive clusters
                    for pos=1:NbPositiveClusters
                        CorrectionName=strcat('_positiveCLUSTER',num2str(pos));
                        savefilenameT = strcat('ClusterStat_',EffectNames, CorrectionName, suffix,'.maxsum', app.OutputformatDropDown.Value);
                        if strcmp(app.OutputPvalueDropDown.Value,'1-p')
                           savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.1-p', app.OutputformatDropDown.Value);
                        else
                           savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.p', app.OutputformatDropDown.Value);
                        end
                        SaveOutputFile(app,PositiveClustersClusterstat(:,:,pos),savefilenameT,NewPath); % save Clusterstat value
                        SaveOutputFile(app,PositiveClustersPvals(:,:,pos),savefilenameP,NewPath); % save P-value

                        %Save outputfile name for PIF
                        countOutputFiles=countOutputFiles+1;
                        tempsavefileNameOutput(countOutputFiles)=savefilenameT;
                        countOutputFiles=countOutputFiles+1;
                        tempsavefileNameOutput(countOutputFiles)=savefilenameP;
                    end
                    
                    %Save all negative clusters
                    for neg=1:NbNegativeClusters
                        CorrectionName=strcat('_negativeCLUSTER',num2str(neg));
                        savefilenameT = strcat('ClusterStat_',EffectNames, CorrectionName, suffix,'.maxsum', app.OutputformatDropDown.Value);
                        if strcmp(app.OutputPvalueDropDown.Value,'1-p')
                           savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.1-p', app.OutputformatDropDown.Value);
                        else
                           savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.p', app.OutputformatDropDown.Value);
                        end
                        SaveOutputFile(app,NegativeClustersClusterstat(:,:,neg),savefilenameT,NewPath); % save Clusterstat value
                        SaveOutputFile(app,NegativeClustersPvals(:,:,neg),savefilenameP,NewPath); % save P-value

                        %Save outputfile name for PIF
                        countOutputFiles=countOutputFiles+1;
                        tempsavefileNameOutput(countOutputFiles)=savefilenameT;
                        countOutputFiles=countOutputFiles+1;
                        tempsavefileNameOutput(countOutputFiles)=savefilenameP;
                    end

                    %Save all clusters
                    CorrectionName='_allCLUSTERS';
                    savefilenameT = strcat('ClusterStat_',EffectNames, CorrectionName, suffix,'.maxsum', app.OutputformatDropDown.Value);
                    if strcmp(app.OutputPvalueDropDown.Value,'1-p')
                       savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.1-p', app.OutputformatDropDown.Value);
                    else
                       savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.p', app.OutputformatDropDown.Value);
                    end
                    SaveOutputFile(app,AllClustersClusterstat,savefilenameT,NewPath); % save Clusterstat value
                    SaveOutputFile(app,AllClustersPvals,savefilenameP,NewPath); % save P-value

                    %Save outputfile name for PIF
                    countOutputFiles=countOutputFiles+1;
                    tempsavefileNameOutput(countOutputFiles)=savefilenameT;
                    countOutputFiles=countOutputFiles+1;
                    tempsavefileNameOutput(countOutputFiles)=savefilenameP;

                else %other case of non-parametric stat
                    
                    %Which correction applied
                    if strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'None')
                        CorrectionName='';
                    elseif strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'FDR')
                        CorrectionName='_FDRcorrected';                
                    end

                    savefilenameT = strcat('Tvalues_',EffectNames, suffix,'.t', app.OutputformatDropDown.Value);
                    if strcmp(app.OutputPvalueDropDown.Value,'1-p')
                       savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.1-p', app.OutputformatDropDown.Value);
                    else
                        savefilenameP = strcat('Pvalues_',EffectNames, CorrectionName, suffix,'.p', app.OutputformatDropDown.Value);
                    end
    
                    SaveOutputFile(app,Tvals(:,:),savefilenameT,NewPath); % save F-value
                    SaveOutputFile(app,PvalsCorrected(:,:),savefilenameP,NewPath); % save P-value

                    %Save outputfile name for PIF
                    countOutputFiles=countOutputFiles+1;
                    tempsavefileNameOutput(countOutputFiles)=savefilenameT;
                    countOutputFiles=countOutputFiles+1;
                    tempsavefileNameOutput(countOutputFiles)=savefilenameP;
                end              

            end

            
            %% PROCESSING INFORMATION SHEET START
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_Statistics_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal Statistics Analysis Processing Info File',...
                '======================================================================='); % header
           
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
            fprintf(PifFileID,'%s\t\t%s\r\n','Output suffix string:',app.OptionalSuffix.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.OutputformatDropDown.Value);
            fprintf(PifFileID,'%s\t\t%s\r\n','Output P-values:',app.OutputPvalueDropDown.Value);
                
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','TIME INTERVAL TO PROCESS');
            fprintf(PifFileID,'%s\t%d\r\n','Sampling Rate (in Hz):', app.SamplingRate.Value);
            fprintf(PifFileID,'%s\t%d%s\t%d%s\r\n','Interval onset:',app.IntervalOnset_ms.Value,' ms,',app.IntervalOnset_tf.Value,' tf');
            fprintf(PifFileID,'%s\t%d%s\t%d%s\r\n','Interval offset:',app.IntervalOffset_ms.Value,' ms,',app.IntervalOffset_tf.Value,' tf');
            if app.AveragetimeCheckBox.Value
                fprintf(PifFileID,'%s\t%s\r\n','Time interval averaged:','Yes');
            else
                fprintf(PifFileID,'%s\t%s\r\n','Time interval averaged:','No');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','CHANNELS TO PROCESS');       
            fprintf(PifFileID,'%s\t%s\r\n','Electrodes processed indices:',app.ChannelsEditField.Value);
            if app.AverageCheckBox.Value
                fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s) processed:','YES');
            else
                fprintf(PifFileID,'%s\t%s\r\n','Average of specified electrode(s) processed:','NO');
            end
            if app.GFPCheckBox.Value
                fprintf(PifFileID,'%s\t%s\r\n','GFP processed:','YES');
            else
                fprintf(PifFileID,'%s\t%s\r\n','GFP processed:','NO');
            end
            if app.GMDCheckBox.Value
                fprintf(PifFileID,'%s\t%s\r\n','Dis processed:','YES');
            else
                fprintf(PifFileID,'%s\t%s\r\n','Dis processed:','NO');
            end
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DESIGN');
            fprintf(PifFileID,'%s\r\n',app.designDescription.Text{:});

            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','STATISTICS AND THRESHOLD');
            fprintf(PifFileID,'%s\t%s\r\n','Statistical Analysis performed:', app.StatistictoperformDropDown.Value);
            if app.NonParametrictestCheckBox.Value
                 fprintf(PifFileID,'%s\t%s%d%s\r\n','Non-parametric test:','YES with ',app.NumberofrendompermutationEditField.Value,' permutations');
            else
                fprintf(PifFileID,'%s\t%s\r\n','Non-parametric test:','NO');
            end
            fprintf(PifFileID,'%s\t%f\r\n','P-value Threshold:', app.PvalueEditField.Value);
            if strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'Cluster analysis')
                 fprintf(PifFileID,'%s\t%f\r\n','P-value Threshold for clusters:', app.PvalueClusterEditField.Value);
            end
            fprintf(PifFileID,'%s\t%s\r\n','Multiple comparison correction:', app.MultiplecomparisoncorrectionDropDown.Value);
            if strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'FDR')
                fprintf(PifFileID,'%s\t%f\r\n','False Discovery rate aloowed (q): ', app.FalseDiscoveryrate_q_EditField.Value);
            end
            fprintf(PifFileID,'%s\t%d\r\n','Minimal cluster size (nb of channels) threshold:', app.MinClusterSize.Value);
            fprintf(PifFileID,'%s\t%d\r\n','Minimal duration threshold (in TF):', app.MinClusterDuration.Value);
                        
            %input files
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name)');
            for file2 = 1:nbFiles
                temp=fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(ProcessedDataTable.Path(file2),'..'),ProcessedDataTable.File(file2));
                fprintf(PifFileID,'%s\r\n',temp{1});
            end
            
            %output files
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name)');           
            for file3 = 1:size(tempsavefileNameOutput,2)
                fprintf(PifFileID,'%s\r\n',fullfile(app.SaveFolder.Value,tempsavefileNameOutput(file3)));
            end

            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])

            
            %Close the PIF file 
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fclose(PifFileID);            
            
            rz = msgbox('Statistical analysis finish!');            
            
        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.StatisticsButton.Enable = 'on';
            
            % Update parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.StatisticsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpal_Statistics
        function EEGpal_StatisticsCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.StatisticsButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: IntervalOnset_ms
        function IntervalOnset_msValueChanged(app, event)
            app.IntervalOnset_tf.Value     = ms2tf(app,app.IntervalOnset_ms.Value,app.SamplingRate.Value);
            app.IntervalOnset_ms.Value     = tf2ms(app,app.IntervalOnset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision
        end

        % Value changed function: IntervalOnset_tf
        function IntervalOnset_tfValueChanged(app, event)
            app.IntervalOnset_ms.Value     = tf2ms(app,app.IntervalOnset_tf.Value,app.SamplingRate.Value);
        end

        % Value changed function: IntervalOffset_ms
        function IntervalOffset_msValueChanged(app, event)
            app.IntervalOffset_tf.Value    = ms2tf(app,app.IntervalOffset_ms.Value,app.SamplingRate.Value);
            app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value,app.SamplingRate.Value); % recompute from tf for precision            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEGpal_Statistics.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {507, 507, 507};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {507, 507};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {391, '1x', 280};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end

        % Value changed function: IntervalOffset_tf
        function IntervalOffset_tfValueChanged(app, event)
            app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value,app.SamplingRate.Value);            
        end

        % Value changed function: EndOfFile
        function EndOfFileValueChanged(app, event)
            if app.EndOfFile.Value
                app.IntervalOffset_tf.Enable   = 'off';
                app.IntervalOffset_ms.Enable   = 'off';
                try
                    app.IntervalOffset_tf.Value    = app.DataTable.(5)(1) - 1; % -1 because TF0 is the event
                    app.IntervalOffset_ms.Value    = tf2ms(app,app.IntervalOffset_tf.Value+1,app.SamplingRate.Value); % +1 because the onset TF is included
                catch % if no data selected
                    app.IntervalOffset_tf.Value    = 0;
                    app.IntervalOffset_ms.Value    = 0;
                end
            else
                app.IntervalOffset_tf.Enable       = 'on';
                app.IntervalOffset_ms.Enable       = 'on';
            end        
        end

        % Value changed function: ChannelsEditField
        function ChannelsEditFieldValueChanged(app, event)
            if ~isempty(app.ChannelsEditField.Value)
                app.AverageCheckBox.Enable  = 'on';
            else
                app.AverageCheckBox.Enable  = 'off';
                app.AverageCheckBox.Value  = 0;
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_Statistics_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Statistics_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_Statistics_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Value changed function: AveragetimeCheckBox
        function AveragetimeCheckBoxValueChanged(app, event)
            if app.AveragetimeCheckBox.Value
                app.GMDCheckBox.Enable = "off";
                app.GMDCheckBox.Value = 0;
                app.MinClusterDuration.Value=1;
                app.MinClusterDuration.Enable = "off";
            else
                app.GMDCheckBox.Enable = "on";
                app.MinClusterDuration.Enable = "on";
            end
        end

        % Value changed function: MultiplecomparisoncorrectionDropDown
        function MultiplecomparisoncorrectionDropDownValueChanged(app, event)
            value = app.MultiplecomparisoncorrectionDropDown.Value;
            if strcmp(app.MultiplecomparisoncorrectionDropDown.Value, 'FDR')
                app.FalseDiscoveryrate_q_EditField.Enable="on";
                app.FalseDiscoveryrate_q_EditField.Visible="on"; 
                app.FalseDiscoveryrate_q_Label.Visible="on";
                app.AverageCheckBox.Enable="on";
                app.GFPCheckBox.Enable = "on";
                app.GMDCheckBox.Enable = "on";
                app.MinClusterSize.Enable = "on";
                app.MinClusterDuration.Enable = "on";
                app.PvalueClusterEditField.Visible = "off";
                app.PvalueClusterLabel.Visible = "off";
            elseif strcmp(app.MultiplecomparisoncorrectionDropDown.Value, 'Cluster analysis')
                app.FalseDiscoveryrate_q_EditField.Enable="off";
                app.FalseDiscoveryrate_q_EditField.Visible="off";
                app.FalseDiscoveryrate_q_Label.Visible="off";
                app.AverageCheckBox.Value=0; 
                app.GFPCheckBox.Value=0;
                app.GMDCheckBox.Value=0;
                app.MinClusterSize.Value=1;
                app.MinClusterDuration.Value=1;
                app.AverageCheckBox.Enable="off";
                app.GFPCheckBox.Enable = "off";
                app.GMDCheckBox.Enable = "off";
                app.MinClusterSize.Enable = "off";
                app.MinClusterDuration.Enable = "off";
                app.PvalueClusterEditField.Visible = "on";
                app.PvalueClusterLabel.Visible = "on";
            else
                app.FalseDiscoveryrate_q_EditField.Enable="off";
                app.FalseDiscoveryrate_q_EditField.Visible="off";
                app.FalseDiscoveryrate_q_Label.Visible="off";
                app.AverageCheckBox.Enable="on";
                app.GFPCheckBox.Enable = "on";
                app.GMDCheckBox.Enable = "on";
                app.MinClusterSize.Enable = "on";
                app.MinClusterDuration.Enable = "on";
                app.PvalueClusterEditField.Visible = "off";
                app.PvalueClusterLabel.Visible = "off";
            end
        end

        % Value changed function: OutputformatDropDown
        function OutputformatDropDownValueChanged(app, event)
            value = app.OutputformatDropDown.Value;
            if strcmp(app.OutputformatDropDown.Value,'.ris')
                app.AverageCheckBox.Enable = "off";
                app.AverageCheckBox.Value = 0;
                app.GFPCheckBox.Enable = "off";
                app.GFPCheckBox.Value = 0;
                app.GMDCheckBox.Enable = "off";
                app.GMDCheckBox.Value = 0;                
            else
                if ~strcmp(app.MultiplecomparisoncorrectionDropDown.Value,'Cluster analysis')
                    app.AverageCheckBox.Enable = "on";
                    app.GFPCheckBox.Enable = "on";
                    if ~app.AveragetimeCheckBox.Value              
                        app.GMDCheckBox.Enable = "on";
                    end
                end
            end
        end

        % Value changed function: NonParametrictestCheckBox
        function NonParametrictestCheckBoxValueChanged(app, event)
            if app.NonParametrictestCheckBox.Value
                app.NumberofrendompermutationEditField.Enable="on";
                app.NumberofrandompermutationsLabel.Enable="on";
                app.MultiplecomparisoncorrectionDropDown.Items = {'None','FDR','Cluster analysis'};
                app.MultiplecomparisoncorrectionDropDown.Value = 'Cluster analysis';
                MultiplecomparisoncorrectionDropDownValueChanged(app);
            else
                app.NumberofrendompermutationEditField.Enable="off";
                app.NumberofrandompermutationsLabel.Enable="off";
                app.MultiplecomparisoncorrectionDropDown.Items = {'None','FDR'};
                app.MultiplecomparisoncorrectionDropDown.Value = 'None';
                MultiplecomparisoncorrectionDropDownValueChanged(app);
            end
        end

        % Value changed function: StatistictoperformDropDown
        function StatistictoperformDropDownValueChanged(app, event)
            if strcmp(app.StatistictoperformDropDown.Value,'Two Samples T-Test') || strcmp(app.StatistictoperformDropDown.Value,'Paired T-Test') || strcmp(app.StatistictoperformDropDown.Value,'One sample T-Test')
                app.NonParametrictestCheckBox.Enable = "on";                
            else
                app.NonParametrictestCheckBox.Value = 0 ;
                app.NonParametrictestCheckBox.Enable = "off";
                NonParametrictestCheckBoxValueChanged(app);                
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpal_Statistics = uifigure('Visible', 'off');
            app.EEGpal_Statistics.Name = 'EEGpal_Statistics';
            app.EEGpal_Statistics.CloseRequestFcn = createCallbackFcn(app, @EEGpal_StatisticsCloseRequest, true);
            app.EEGpal_Statistics.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEGpal_Statistics);
            app.LeftPanel = uipanel(app.GridLayout);
            app.StatisticsmoduleLabel = uilabel(app.LeftPanel);
            app.StatisticsmoduleLabel.Text = 'Statistics module';
            app.InstructModule = uilabel(app.LeftPanel);
            app.InstructModule.Text = 'This module to performs statistics over trace (amplitude), frequency or inverse solution ';
            app.Epoch_RelativeInterval1 = uilabel(app.LeftPanel);
            app.Epoch_RelativeInterval1.Text = 'Relative to file start';
            app.Epoch_RelativeInterval1_2 = uilabel(app.LeftPanel);
            app.Epoch_RelativeInterval1_2.Text = 'Time interval';
            app.msLabel = uilabel(app.LeftPanel);
            app.msLabel.Text = '[ms]';
            app.tfLabel = uilabel(app.LeftPanel);
            app.tfLabel.Text = '[tf]';
            app.IntervalOnset_tf = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalOnset_tf.Limits = [0 Inf];
            app.IntervalOnset_tf.ValueDisplayFormat = '%.0f';
            app.IntervalOnset_tf.ValueChangedFcn = createCallbackFcn(app, @IntervalOnset_tfValueChanged, true);
            app.IntervalOnset_tf.Tooltip = {''};
            app.IntervalOffset_tf = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalOffset_tf.Limits = [0 Inf];
            app.IntervalOffset_tf.ValueDisplayFormat = '%.0f';
            app.IntervalOffset_tf.ValueChangedFcn = createCallbackFcn(app, @IntervalOffset_tfValueChanged, true);
            app.IntervalOffset_tf.Tooltip = {''};
            app.EndOfFile = uibutton(app.LeftPanel, 'state');
            app.EndOfFile.ValueChangedFcn = createCallbackFcn(app, @EndOfFileValueChanged, true);
            app.EndOfFile.Tooltip = {'End of File'; 'When selected, ''Epoch offset'', ''Epoch duration'', and ''Number of sub-epochs'' values based on the first file of the list in the main window.,When pressing run, these values will be adapted to the file being processed.'};
            app.EndOfFile.Text = 'EoF';
            app.Epoch_RelativeInterval1_3 = uilabel(app.LeftPanel);
            app.Epoch_RelativeInterval1_3.Text = 'Channels to process';
            app.AverageCheckBox = uicheckbox(app.LeftPanel);
            app.AverageCheckBox.Text = 'Average selected channels';
            app.GFPCheckBox = uicheckbox(app.LeftPanel);
            app.GFPCheckBox.Text = 'GFP';
            app.GMDCheckBox = uicheckbox(app.LeftPanel);
            app.GMDCheckBox.Text = 'Dis';
            app.Instruct_timeinter1 = uilabel(app.LeftPanel);
            app.Instruct_timeinter1.Text = 'Time must be specified in the Cartool scale (first time fame is tf=0).';
            app.IntervalonsetLabel = uilabel(app.LeftPanel);
            app.IntervalonsetLabel.Text = 'Interval onset:';
            app.IntervalOnset_ms = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalOnset_ms.Limits = [0 Inf];
            app.IntervalOnset_ms.ValueDisplayFormat = '%.1f';
            app.IntervalOnset_ms.ValueChangedFcn = createCallbackFcn(app, @IntervalOnset_msValueChanged, true);
            app.IntervalOnset_ms.Tooltip = {''};
            app.IntervaloffsetLabel = uilabel(app.LeftPanel);
            app.IntervaloffsetLabel.Text = 'Interval offset:';
            app.IntervalOffset_ms = uieditfield(app.LeftPanel, 'numeric');
            app.IntervalOffset_ms.Limits = [0 Inf];
            app.IntervalOffset_ms.ValueDisplayFormat = '%.1f';
            app.IntervalOffset_ms.ValueChangedFcn = createCallbackFcn(app, @IntervalOffset_msValueChanged, true);
            app.IntervalOffset_ms.Tooltip = {''};
            app.ChannelsEditFieldLabel = uilabel(app.LeftPanel);
            app.ChannelsEditFieldLabel.Text = 'Channels';
            app.ChannelsEditField = uieditfield(app.LeftPanel, 'text');
            app.ChannelsEditField.ValueChangedFcn = createCallbackFcn(app, @ChannelsEditFieldValueChanged, true);
            app.ChannelsEditField.Value = '*';
            app.SamplingRateinHzEditFieldLabel = uilabel(app.LeftPanel);
            app.SamplingRateinHzEditFieldLabel.Text = 'Sampling Rate (in Hz)';
            app.SamplingRate = uieditfield(app.LeftPanel, 'numeric');
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.InstructElements1 = uilabel(app.LeftPanel);
            app.InstructElements1.Text = 'Specify the channel number separate by space (e.g 1 2 64). Put * to select all channels ';
            app.AveragetimeCheckBox = uicheckbox(app.LeftPanel);
            app.AveragetimeCheckBox.ValueChangedFcn = createCallbackFcn(app, @AveragetimeCheckBoxValueChanged, true);
            app.AveragetimeCheckBox.Text = 'Average time';
            app.CenterPanel = uipanel(app.GridLayout);
            app.DesignTitle = uilabel(app.CenterPanel);
            app.DesignTitle.Text = 'Design';
            app.designDescription = uilabel(app.CenterPanel);
            app.designDescription.Text = 'Description of the Design';
            app.StatistictoperformLabel = uilabel(app.CenterPanel);
            app.StatistictoperformLabel.Text = 'Statistic to perform: ';
            app.StatistictoperformDropDown = uidropdown(app.CenterPanel);
            app.StatistictoperformDropDown.Items = {'ANOVA'};
            app.StatistictoperformDropDown.ValueChangedFcn = createCallbackFcn(app, @StatistictoperformDropDownValueChanged, true);
            app.StatistictoperformDropDown.Value = 'ANOVA';
            app.PvaluethresholdEditFieldLabel = uilabel(app.CenterPanel);
            app.PvaluethresholdEditFieldLabel.Text = 'P-value threshold';
            app.PvalueEditField = uieditfield(app.CenterPanel, 'numeric');
            app.PvalueEditField.Limits = [0 Inf];
            app.PvalueEditField.Value = 0.05;
            app.MultiplecomparisoncorrectionDropDownLabel = uilabel(app.CenterPanel);
            app.MultiplecomparisoncorrectionDropDownLabel.Text = 'Multiple comparison correction';
            app.MultiplecomparisoncorrectionDropDown = uidropdown(app.CenterPanel);
            app.MultiplecomparisoncorrectionDropDown.Items = {'None', 'FDR'};
            app.MultiplecomparisoncorrectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MultiplecomparisoncorrectionDropDownValueChanged, true);
            app.MultiplecomparisoncorrectionDropDown.Value = 'None';
            app.MinClusterDuration = uieditfield(app.CenterPanel, 'numeric');
            app.MinClusterDuration.Limits = [1 Inf];
            app.MinClusterDuration.RoundFractionalValues = 'on';
            app.MinClusterDuration.Value = 1;
            app.MinimalclustersizenbofchannelsLabel = uilabel(app.CenterPanel);
            app.MinimalclustersizenbofchannelsLabel.Text = 'Minimal cluster size (nb of channels) *';
            app.MinClusterSize = uieditfield(app.CenterPanel, 'numeric');
            app.MinClusterSize.Limits = [1 Inf];
            app.MinClusterSize.RoundFractionalValues = 'on';
            app.MinClusterSize.Value = 1;
            app.PvalueClusterLabel = uilabel(app.CenterPanel);
            app.PvalueClusterLabel.Visible = 'off';
            app.PvalueClusterLabel.Text = 'P-value threshold for clusters';
            app.PvalueClusterEditField = uieditfield(app.CenterPanel, 'numeric');
            app.PvalueClusterEditField.Limits = [0 Inf];
            app.PvalueClusterEditField.Visible = 'off';
            app.PvalueClusterEditField.Value = 0.05;
            app.FalseDiscoveryrate_q_Label = uilabel(app.CenterPanel);
            app.FalseDiscoveryrate_q_Label.Visible = 'off';
            app.FalseDiscoveryrate_q_Label.Text = 'False Discovery rate aloowed (q)';
            app.FalseDiscoveryrate_q_EditField = uieditfield(app.CenterPanel, 'numeric');
            app.FalseDiscoveryrate_q_EditField.Limits = [0 Inf];
            app.FalseDiscoveryrate_q_EditField.Visible = 'off';
            app.FalseDiscoveryrate_q_EditField.Value = 0.05;
            app.MinimalclusterdurationTFLabel = uilabel(app.CenterPanel);
            app.MinimalclusterdurationTFLabel.Text = 'Minimal duration (TF)';
            app.WarningCluster = uilabel(app.CenterPanel);
            app.WarningCluster.Text = {'* Warning: 1. This parameter doesn''t take into account the spatial localisation, but only the consecutive channels).'; '2. It excludes Average, GFP, Dis channels'};
            app.StatThresTitle = uilabel(app.CenterPanel);
            app.StatThresTitle.Text = 'Statistics and Threshold';
            app.NonParametrictestCheckBox = uicheckbox(app.CenterPanel);
            app.NonParametrictestCheckBox.ValueChangedFcn = createCallbackFcn(app, @NonParametrictestCheckBoxValueChanged, true);
            app.NonParametrictestCheckBox.Enable = 'off';
            app.NonParametrictestCheckBox.Visible = 'off';
            app.NonParametrictestCheckBox.Text = 'Non-Parametric test';
            app.NumberofrendompermutationEditField = uieditfield(app.CenterPanel, 'numeric');
            app.NumberofrendompermutationEditField.Limits = [0 Inf];
            app.NumberofrendompermutationEditField.RoundFractionalValues = 'on';
            app.NumberofrendompermutationEditField.Enable = 'off';
            app.NumberofrendompermutationEditField.Visible = 'off';
            app.NumberofrendompermutationEditField.Value = 5000;
            app.NumberofrandompermutationsLabel = uilabel(app.CenterPanel);
            app.NumberofrandompermutationsLabel.Enable = 'off';
            app.NumberofrandompermutationsLabel.Visible = 'off';
            app.NumberofrandompermutationsLabel.Text = 'Number of random permutations';
            app.RightPanel = uipanel(app.GridLayout);
            app.OUTPUTOPTIONSLabel = uilabel(app.RightPanel);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.InstructionFolderLabel = uilabel(app.RightPanel);
            app.InstructionFolderLabel.Text = {'Select folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.RightPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.APPLYLabel = uilabel(app.RightPanel);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.RightPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.SaveinmemoryButton = uibutton(app.RightPanel, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window without applying the processing and save the parameters.'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.RightPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.SavingfolderLabel = uilabel(app.RightPanel);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.RightPanel);
            app.SaveFolder.Editable = 'off';
            app.OptionalsuffixinoutputfilenameEditFieldLabel = uilabel(app.RightPanel);
            app.OptionalsuffixinoutputfilenameEditFieldLabel.Text = 'Optional suffix in output file name';
            app.OptionalSuffix = uieditfield(app.RightPanel, 'text');
            app.OptionalSuffix.Tooltip = {'If empty, might overwright existing files'};
            app.OutputPvalueDropDownLabel = uilabel(app.RightPanel);
            app.OutputPvalueDropDownLabel.Text = 'Output P-value';
            app.OutputPvalueDropDown = uidropdown(app.RightPanel);
            app.OutputPvalueDropDown.Items = {'1-p', 'p'};
            app.OutputPvalueDropDown.Value = '1-p';
            app.OutputformatDropDownLabel = uilabel(app.RightPanel);
            app.OutputformatDropDownLabel.Text = 'Output format';
            app.OutputformatDropDown = uidropdown(app.RightPanel);
            app.OutputformatDropDown.Items = {'.ep', '.eph', '.sef', '.set', '.ris', '.csv'};
            app.OutputformatDropDown.ValueChangedFcn = createCallbackFcn(app, @OutputformatDropDownValueChanged, true);
            app.OutputformatDropDown.Value = '.sef';
            % Show the figure after all components are created
            app.EEGpal_Statistics.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Statistics(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpal_Statistics)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpal_Statistics)
        end
    end
end