classdef EEGpalCS_mod_BatchProcessing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpal_Batch            matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        CancelButton            matlab.ui.control.Button
        SaveinmemoryButton      matlab.ui.control.Button
        RunButton               matlab.ui.control.Button
        APPLYLabel              matlab.ui.control.Label
        ModulesAvailabelLabel   matlab.ui.control.Label
        ProcessesparameterssavedinthisfileLabel  matlab.ui.control.Label
        HelpButton              matlab.ui.control.Button
        ProcessingAnalysisparametersfileEditField  matlab.ui.control.EditField
        ProcessingAnalysisparametersfileEditFieldLabel  matlab.ui.control.Label
        SaveFolder              matlab.ui.control.TextArea
        SavingfolderLabel       matlab.ui.control.Label
        SelectFolderButton      matlab.ui.control.Button
        SelectUpperFolderLabel  matlab.ui.control.Label
        BATCHProcessingLabel    matlab.ui.control.Label
        RightPanel              matlab.ui.container.Panel
        ResetButton             matlab.ui.control.Button
        Option9DropDown         matlab.ui.control.DropDown
        Option8DropDown         matlab.ui.control.DropDown
        Option7DropDown         matlab.ui.control.DropDown
        Option6DropDown         matlab.ui.control.DropDown
        Option5DropDown         matlab.ui.control.DropDown
        Option4DropDown         matlab.ui.control.DropDown
        Option3DropDown         matlab.ui.control.DropDown
        Option2DropDown         matlab.ui.control.DropDown
        Option1DropDown         matlab.ui.control.DropDown
        Process9DropDown        matlab.ui.control.DropDown
        Label_10                matlab.ui.control.Label
        Process8DropDown        matlab.ui.control.DropDown
        Label_9                 matlab.ui.control.Label
        Process7DropDown        matlab.ui.control.DropDown
        Label_8                 matlab.ui.control.Label
        Process6DropDown        matlab.ui.control.DropDown
        Label_7                 matlab.ui.control.Label
        Process5DropDown        matlab.ui.control.DropDown
        Label_6                 matlab.ui.control.Label
        Process4DropDown        matlab.ui.control.DropDown
        Label_5                 matlab.ui.control.Label
        Process3DropDown        matlab.ui.control.DropDown
        Label_4                 matlab.ui.control.Label
        Process2DropDown        matlab.ui.control.DropDown
        Label_3                 matlab.ui.control.Label
        Process1DropDown        matlab.ui.control.DropDown
        Label_2                 matlab.ui.control.Label
        OptionLabel             matlab.ui.control.Label
        SucessionLabel          matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        SettingTable
        ModulesAvailable % Module available for processing
        ModulesAvailableStartup % Module available save
        ProcessDropDownList  % Will contain the option of all Process DropDown List
        ProcessDropDownListLabel  % Will contain the label of all Process DropDown List
        OptionsDropDownList  % Will contain the option of all Options DropDown List
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function applySessionParam(app)
            % TABLE OF FILECUT
            app.CutTable.Data                      = app.SessionParameters.FileCut.CutTable;

            % SAVE OPTION
            app.concatenate.Value                   = app.SessionParameters.FileCut.concatenate;
            app.concatenateSuffix1.Value            = app.SessionParameters.FileCut.concatenateSuffix1;
            app.concatenateSuffix2.Value            = app.SessionParameters.FileCut.concatenateSuffix2;
            app.concatenateSuffix3.Value            = app.SessionParameters.FileCut.concatenateSuffix3;
            app.FilesExtension.Value                = app.SessionParameters.FileCut.FilesExtension;            
            app.SaveFolder.Value                    = app.SessionParameters.FileCut.SaveFolder;
            app.SaveinthesamefolderButton.Value     = app.SessionParameters.FileCut.SaveinthesamefolderButton;
            app.SaveinseparatefoldersButton.Value   = app.SessionParameters.FileCut.SaveinseparatefoldersButton;
            
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            % TABLE OF FILECUT
            SessionParameters.FileCut.CutTable                      = app.CutTable.Data;

            % SAVE OPTION
            SessionParameters.FileCut.concatenate                   = app.concatenate.Value;
            SessionParameters.FileCut.concatenateSuffix1            = app.concatenateSuffix1.Value;
            SessionParameters.FileCut.concatenateSuffix2            = app.concatenateSuffix2.Value;
            SessionParameters.FileCut.concatenateSuffix3            = app.concatenateSuffix3.Value;
            SessionParameters.FileCut.FilesExtension                = app.FilesExtension.Value;
            SessionParameters.FileCut.SaveFolder                    = app.SaveFolder.Value;
            SessionParameters.FileCut.SaveinthesamefolderButton     = app.SaveinthesamefolderButton.Value;
            SessionParameters.FileCut.SaveinseparatefoldersButton   = app.SaveinseparatefoldersButton.Value;
            
        end
        
        function lookForNextAvalaibleProcess(app,numProcess)
            
            
            %reset all the ProcessDropDown and OptionDropDown which are below (to avoid bugs)
            if numProcess ~= 9
                for i=numProcess:9
                    currentDropDownList = app.ProcessDropDownList{i}; %retrive the current DownDown to edit
                    currentDropDownList.Visible = "off";
                    currentDropDownList.Enable = "off";
                    switch currentDropDownList.Value
                        case 'Bridge Detection'
                            app.ModulesAvailable(1)=1;
                        case 'Filtering'
                            app.ModulesAvailable(2)=1;
                        case 'ICA'
                            app.ModulesAvailable(3)=1;                          
                        case 'Interpolation'
                            app.ModulesAvailable(4)=1;                           
                        case 'Re-reference'
                            app.ModulesAvailable(5)=1;
                        case 'Epoching'
                            app.ModulesAvailable(6)=1; 
                    end
                    currentDropDownList.Items={''};
                    currentDropDownList = app.ProcessDropDownListLabel{i}; %retrive the Label
                    currentDropDownList.Visible = "off";
                    currentDropDownList.Enable = "off";
                    currentDropDownList = app.OptionsDropDownList{i}; %retrive option
                    currentDropDownList.Visible = "off";
                end
            end


            %Determine the next possible processings
            currentDropDownList = app.ProcessDropDownList{numProcess}; %retrive the current DownDown to edit            
            currentDropDownListLabel = app.ProcessDropDownListLabel{numProcess};
            availableProcess{1}='';
            indice=1;

            for k=1:size(app.ModulesAvailable,1)              
                if app.ModulesAvailable(k) == 1
                    indice=indice+1;
                    switch k
                        case 1
                            availableProcess{indice}='Bridge Detection';
                        case 2
                            availableProcess{indice}='Filtering';
                        case 3
                            availableProcess{indice}='ICA';                            
                        case 4
                            availableProcess{indice}='Interpolation';
                        case 5
                            availableProcess{indice}='Re-reference';
                        case 6
                            availableProcess{indice}='Epoching';
                    end
                end
            end            

            % set the next available processings
            currentDropDownList.Items = availableProcess;
            currentDropDownList.Value = ''; %Reset

            %make it visible
            currentDropDownList.Visible = "on";
            currentDropDownList.Enable = "on";
            currentDropDownListLabel.Visible = "on";
            currentDropDownListLabel.Enable = "on";
            currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
            currentOptionsDropDownList.Visible="off";          
            
        end
        
        function applyProcessChoice(app, numProcess, Process)
            switch Process
                case 'Bridge Detection'
                    app.ModulesAvailable(1)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
                case 'Filtering'
                    app.ModulesAvailable(2)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
                case 'ICA'
                    app.ModulesAvailable(3)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Items = {'Manual Bad Components Inspection','Automatic Bad Components Rejection'};
                    currentOptionsDropDownList.Value = 'Manual Bad Components Inspection';
                    currentOptionsDropDownList.Visible = "on";
                case 'Interpolation'
                    app.ModulesAvailable(4)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};                    
                    currentOptionsDropDownList.Items = {'Manual Bad Electrodes Inspection','Automatic Bad Electrodes Rejection'};                   
                    currentOptionsDropDownList.Visible = "on";
                case 'Re-reference'
                    app.ModulesAvailable(5)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
                case 'Epoching'
                    app.ModulesAvailable(6)=0;
                    currentOptionsDropDownList = app.OptionsDropDownList{numProcess};
                    currentOptionsDropDownList.Visible = "off";
            end

        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
            ParameterFilePath               = app.EEGpalCS_Main.SessionParametersFilePath.Value;
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;     
           
            %create of the Layout of the app to enter the parameters
            app.ProcessingAnalysisparametersfileEditField.Value = ParameterFilePath;
            
            % Determine the available modules for batch
            app.ModulesAvailable=zeros(6,1); % Initialisation
            TextModules="";

            app.ModulesAvailable(1)=1; %eBridge
            TextModules="Bridge Detection";
            if isfield (app.SessionParameters,'Filtering')
                app.ModulesAvailable(2)=1; %Filtering+
                TextModules=[TextModules,"Filtering+"];
            end
            if isfield (app.SessionParameters,'ICA')
                app.ModulesAvailable(3)=1; %ICA
                TextModules=[TextModules,"ICA"];
            end
            if isfield (app.SessionParameters,'Interpolation')
                app.ModulesAvailable(4)=1; %Interpolation
                TextModules=[TextModules,"Interpolation"];
            end
            if isfield (app.SessionParameters,'Rereference')
                app.ModulesAvailable(5)=1; %Rereference
                TextModules=[TextModules,"Re-reference"];
            end
            if isfield (app.SessionParameters,'Epoching')
                app.ModulesAvailable(6)=1; %Epoching
                TextModules=[TextModules,"Epoching"];
            end
            
            app.ModulesAvailabelLabel.Text=TextModules; % Display the available modules in the left column
            app.ModulesAvailableStartup=app.ModulesAvailable;

            % Create a superclass to hold all the Process dropdown 
            app.ProcessDropDownList = {
                app.Process1DropDown;
                app.Process2DropDown;
                app.Process3DropDown;
                app.Process4DropDown;
                app.Process5DropDown;
                app.Process6DropDown;
                app.Process7DropDown;
                app.Process8DropDown;
                app.Process9DropDown
                };

            % Create a superclass to hold all the Process dropdown Label
            app.ProcessDropDownListLabel = {
                app.Label_2;
                app.Label_3;
                app.Label_4;
                app.Label_5;
                app.Label_6;
                app.Label_7;
                app.Label_8;
                app.Label_9;
                app.Label_10
                };

            % Create a superclass to hold all the Option dropdown 
            app.OptionsDropDownList = {
                app.Option1DropDown;
                app.Option2DropDown;
                app.Option3DropDown;
                app.Option4DropDown;
                app.Option5DropDown;
                app.Option6DropDown;
                app.Option7DropDown;
                app.Option8DropDown;
                app.Option9DropDown
                };
                     
            % put the intialisation of the first here
            lookForNextAvalaibleProcess(app,1);


            % Apply Session parameters 
            try
                applySessionParam(app)
            catch
            end


        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpal_Batch); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %length table
            [CutTableLength,~]=size(app.CutTable.Data); %length of table
            workingIndices =[]; %to save the indice where a start trigger has been specified
            
            %% CHECK
            
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            indice=0;
            for i=1:CutTableLength
                if app.CutTable.Data.Start_Trigger(i)~=0 %case to process
                    indice=indice+1;
                    workingIndices(indice)=i;
                    
                    if app.CutTable.Data.End_Value(i)==0  %check if no End Point
                        msgbox(sprintf('%s\n\n%s','Missing End Point!', ...
                            'You have forget to specify a End_Point in the table. Please make sure to have specify an End_Point to every line where you specified a Start_Trigger.'),...
                        'Missing End Point','warn');
                        return
                    end
                    
                    if and (~app.CutTable.Data.End_Trigger(i),~app.CutTable.Data.End_Duration(i))
                        msgbox(sprintf('%s\n\n%s','Missing End Point Type!', ...
                        'You have forget to specify if the End_Point is a Trigger or a Duration to the Start_trigger . Please make sure to have tick either the option "End_Option_Trigger" or "End_Option_Duration" to every line where you specified a Start_Trigger.'),...
                        'Missing End Point Type','warn');
                        return
                    end
                    
                end
            end   
            
            if indice==0
                msgbox(sprintf('%s','Sorry but you did not provide me any start point insctuction'),...
                'Missing Start Trigger','warn');
                return
            end
            
            Run = questdlg(sprintf('%s\n%s\n\n%s%d\n\n%s',...
                'Hello!',...
                'Me about to split the eeg files into subfiles the data. Ready for the ride?',...
                'Number of subfiles which will be generated per files: ',length(workingIndices),...
                'Do you wish to proceed? (Warning: after computation the results will be not transfer to the main window of EEGpal. You will need to manually import the files you want for next steps of processing)'),'Run checked options','OK','Cancel','Cancel');
            
            if strcmpi(Run,'Cancel')
                return
            end
            
            concatenateArgument=[];
            if app.concatenate.Value
                %check if user didn't respect the instruction
                concatenateArgument=unique(app.CutTable.Data.Concatenate(:)); concatenateArgument(concatenateArgument==0)=[];
                problem=false;
                for i=1:length(concatenateArgument)
                    if concatenateArgument(i)~=1 & concatenateArgument(i)~=2 & concatenateArgument(i)~=3
                         problem=true;
                         if problem
                            msgbox(sprintf('%s','Sorry but you used another value that 1,2 or 3 in the Concatenate Colum. I can concatenate only three files per run. Please correct and press Run again!'),...
                            'Wrong concatenation argument','warn');
                            return
                         end
                    end
                end                 
            end
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_FileCut_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal File Cut Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
          
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            
            %cutting instruction 
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','CUTTING INSTRUCTIONS (Start Trigger  /  End Option(Trigger or Duration)  /  End Point  /  File suffix  /  Concatenate index)');
            for i=1:indice
                fprintf(PifFileID,'%d\t\t', app.CutTable.Data.Start_Trigger(workingIndices(i)));
                if app.CutTable.Data.End_Trigger(workingIndices(i))
                    fprintf(PifFileID,'%s\t\t','Trigger');
                else
                    fprintf(PifFileID,'%s\t\t','Duration');
                end
                fprintf(PifFileID,'%d\t\t', app.CutTable.Data.End_Value(workingIndices(i)));
                
                if ~ismissing(app.CutTable.Data.Suffix_String(workingIndices(i)))
                    fprintf(PifFileID,'%s\t\t',app.CutTable.Data.Suffix_String(workingIndices(i)));
                else
                    fprintf(PifFileID,'%s\t\t','missing');
                end
                if ~ismissing(app.CutTable.Data.Concatenate(workingIndices(i)))
                    fprintf(PifFileID,'%d\r\n',app.CutTable.Data.Concatenate(workingIndices(i)));
                else
                    fprintf(PifFileID,'\r\n');
                end
            end
            
            %input files
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            %output files
            if app.SaveinseparatefoldersButton.Value
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: File saved in separate sub-folder');
            else
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: All files saved in the target directory');
            end
            if app.concatenate.Value
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: Concatenate signal according Concatenate column');
            else
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: No concatenation of signal');
            end
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name)');
            %the outputfile file be contiousely write in the PIF file
            %during processing

            
            %% RUN CUT File
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run File Cut...')
            StartTime = datetime('now');
            tStart = tic;
            h = waitbar(0,'Processing.....');
            
            for file = 1:height(app.DataTable)  % Load file by file
                waitbar(file/height(app.DataTable),h, strcat('Processing File ',num2str(file),'/',num2str(height(app.DataTable))));
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
%                     events = [events.latency(:)-1, events.latency(:)-1, events{:,2}]; % Convert to Cartool MRK format (onset, offset, code)
%                     if ismember('duration', events.Properties.VariableNames)
%                         events(:,2) = events.latency(:)-1 + events.duration(:);
%                     end
%                     FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    %continue
                end
                disp('File loaded!')

                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                catch
                end
                
                %check if event are cell
                if iscell(events)
                    events=cell2mat(events);
                end
                
                %correct the event indice from Cartool (0) to Matlab (1)
                if header.firstindex == 0
                    events(:,1)=events(:,1)+1;
                    events(:,2)=events(:,2)+1;
                end
                
                %intitiate the concatenation vectors
                if length(find(concatenateArgument(:)==1))==1 & app.concatenate.Value
                    EEGconcatenated1=[]; eventsConcatenated1=[];indiceConcatenate1=0;
                end
                if length(find(concatenateArgument(:)==2))==1  & app.concatenate.Value
                    EEGconcatenated2=[]; eventsConcatenated2=[];indiceConcatenate2=0;
                end
                if length(find(concatenateArgument(:)==3))==1  & app.concatenate.Value
                    EEGconcatenated3=[]; eventsConcatenated3=[]; indiceConcatenate3=0;
                end
                
                %run the File Cut
                for j=1:length(workingIndices) %loop over the segments
                    
                    %set the start position for the current segment
                    posTriggerStart=find(events(:,3)==app.CutTable.Data.Start_Trigger(workingIndices(j)));
                    
                    if length(posTriggerStart)>1 %problem of several occurence of the same trigger
                        disp('The start trigger has several occurence')
                        NewDataConcatenate=[]; %need to concatenate files
                        eventsConcatenated0=[];
                    end
                    startTFPosition=events(posTriggerStart,1);
                    
                    %set the end position for the current segment
                    if isnan(app.CutTable.Data.End_Value(workingIndices(j)))
                        endTFPosition=size(data,1); %case where you go until the end of the file. 
                    elseif app.CutTable.Data.End_Trigger(workingIndices(j)) %case where the end is specified by a trigger                   
                        posTriggerEnd=find(events(:,3)==app.CutTable.Data.End_Value(workingIndices(j)));
                        if length(posTriggerEnd) ~= length(posTriggerStart) %problem of several occurence of the same trigger
                            msgbox(sprintf('%s\n\n%s\n\n%s','The number of occurences of the specified End_Point trigger is different that the number of occurences of Start_Trigger for the file:', FileName, 'This case is not manage by this module. Please fix manually your marker file or use a fix duration instead'),...
                            'Problem of inequal number of Start Trigger and End Point Trigger','error');
                           disp('Processing Abort!')
                           delete (h);
                           return
                        end
                        endTFPosition=events(posTriggerEnd,1)-1;  
                      
                    else %case where the end if specified by a fix duration in second
                        durationComputeNbTF=round(app.CutTable.Data.End_Value(j)/(1/app.DataTable.SamplingRate(file)));
                        endTFPosition=startTFPosition+durationComputeNbTF; %this line code work also if the startTFPosition dimension is bigger than 1 (serveral occurence of Start_Trigger)     
                    end
                        
                    
                    for k=1:length(posTriggerStart) %manage if serveral occurence of Start_Trigger    
                          
                        if endTFPosition(k)>size(data,1) %check if the endPoint is outside of the eegfile
                            msgbox(sprintf('%s\n\n%s\n\n%s','The specified End_Point duration is outside of the EEG file (duration value too big) for the file:', FileName, 'Please check the entered values.'),...
                                'End Point outside of the eeg file','error'); 
                            disp('Processing Abort!')
                            delete (h);
                            return
                        end
                    
                    
                        %preparation of the cut file
                        
                        MRK=zeros(1,3); %creation MRK factice (nécessaire pour appeler la fonction save_sef
                        
                        NewData = [];
                        NewData = [NewData; data(startTFPosition(k):endTFPosition(k),:)];
                        MRK(1,:)= [1,1,app.CutTable.Data.Start_Trigger(workingIndices(j))];
                        
                        %look if there is any other trigger/event if the period of interest
                        tempPos=1;
                        for z=1:size(events,1)
                            if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
                                tempPos=tempPos+1;
                                MRK(tempPos,1)=events(z,1)-startTFPosition(k)+1; %need to correct for +1 because of correction header.first index at recording
                                MRK(tempPos,2)=events(z,1)-startTFPosition(k)+1;
                                MRK(tempPos,3)=events(z,3);
                            end
                        end
                        
                        % Concatenation between segments
                        if app.CutTable.Data.Concatenate(j)==1 & app.concatenate.Value %concatenate1
                            indiceConcatenate1=indiceConcatenate1+1;
                            eventsConcatenated1(indiceConcatenate1,:)=[size(EEGconcatenated1,1)+1, size(EEGconcatenated1,1)+1, app.CutTable.Data.Start_Trigger(workingIndices(j))];                           
                            
                            %look if there is any other trigger/event if the period of interest
                            for z=1:size(events,1)
                                if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
                                    indiceConcatenate1=indiceConcatenate1+1;
                                    eventsConcatenated1(indiceConcatenate1,1)=events(z,1)-startTFPosition(k)+size(EEGconcatenated1,1)+1;
                                    eventsConcatenated1(indiceConcatenate1,2)=events(z,1)-startTFPosition(k)+size(EEGconcatenated1,1)+1;
                                    eventsConcatenated1(indiceConcatenate1,3)=events(z,3);
                                end
                            end
                            
                            EEGconcatenated1=vertcat(EEGconcatenated1,NewData);
                        end
                        if app.CutTable.Data.Concatenate(j)==2 & app.concatenate.Value %concatenate2
                            indiceConcatenate2=indiceConcatenate2+1;
                            eventsConcatenated2(indiceConcatenate2,:)=[size(EEGconcatenated2,1)+1, size(EEGconcatenated2,1)+1, app.CutTable.Data.Start_Trigger(workingIndices(j))];
                            
                            %look if there is any other trigger/event if the period of interest
                            for z=1:size(events,1)
                                if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
                                    indiceConcatenate2=indiceConcatenate2+1;
                                    eventsConcatenated2(indiceConcatenate2,1)=events(z,1)-startTFPosition(k)+size(EEGconcatenated2,1)+1;
                                    eventsConcatenated2(indiceConcatenate2,2)=events(z,1)-startTFPosition(k)+size(EEGconcatenated2,1)+1;
                                    eventsConcatenated2(indiceConcatenate2,3)=events(z,3);
                                end
                            end
                            
                            EEGconcatenated2=vertcat(EEGconcatenated2,NewData);
                        end
                        if app.CutTable.Data.Concatenate(j)==3 & app.concatenate.Value %concatenate3
                            indiceConcatenate3=indiceConcatenate3+1;
                            eventsConcatenated3(indiceConcatenate3,:)=[size(EEGconcatenated3,1)+1, size(EEGconcatenated3,1)+1, app.CutTable.Data.Start_Trigger(workingIndices(j))];
                            
                            %look if there is any other trigger/event if the period of interest
                            for z=1:size(events,1)
                                if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
                                    indiceConcatenate3=indiceConcatenate3+1;
                                    eventsConcatenated3(indiceConcatenate3,1)=events(z,1)-startTFPosition(k)+size(EEGconcatenated3,1)+1;
                                    eventsConcatenated3(indiceConcatenate3,2)=events(z,1)-startTFPosition(k)+size(EEGconcatenated3,1)+1;
                                    eventsConcatenated3(indiceConcatenate3,3)=events(z,3);
                                end
                            end
                            
                            EEGconcatenated3=vertcat(EEGconcatenated3,NewData);
                        end


                        % Save processed file
                        
                        %suffix filename
                        if ~ismissing(app.CutTable.Data.Suffix_String(workingIndices(j))) %case the user specified a suffix string
                            suffix = app.CutTable.Data.Suffix_String(workingIndices(j));
                        else %case the user did not specify a suffix
                            suffix = strcat("Tigger",num2str(app.CutTable.Data.Start_Trigger(workingIndices(j))));
                        end
                        
                        %Generate the outputfilename
                        if length(posTriggerStart)>1
                            outputfilename=strcat(app.DataTable.File{file}(1:end-4),'_',suffix,'_',num2str(k), app.FilesExtension.Value);
                        else
                            outputfilename=strcat(app.DataTable.File{file}(1:end-4),'_',suffix,app.FilesExtension.Value);
                        end
                        
                        %complete path of recording
                        if app.SaveinseparatefoldersButton.Value %case of subdirectory
                            SubPath=fullfile(app.SaveFolder.Value{1},suffix);
                            if ~exist(SubPath, 'dir') %create folder
                                mkdir(SubPath);
                            end
                            savefilename=fullfile(SubPath,outputfilename);
                        else %case save in one folder 
                            savefilename=fullfile(app.SaveFolder.Value{1},outputfilename);
                        end
       
                        %saving
                        disp(['Saving ',savefilename])
                        if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph') %manage the output format
                            save_eph(savefilename,NewData,header.SamplingRate,MRK,1) %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.sef')
                            try
                                %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                                Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                            catch
                                Channels = '';
                            end
                            save_sef(savefilename,NewData,header.SamplingRate,Channels,MRK,1) %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.set')
                            savefilename = convertStringsToChars(savefilename); %need to be char for save_eeglab
                            try
                                ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                                Channels            = ElectrodeSetting(:,2);
                                %Channels.labels     = char(ElectrodeSetting.labels);
    
                                % Recompute Theta and Radius to match EEGlab orientation
                                [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                                Channels.theta      = -rad2deg(az);
                                Channels.radius     = 0.5 - rad2deg(elev)/180;
                                Channels.sph_theta  = -Channels.theta;
                                Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                                Channels.X          = ElectrodeSetting.y;
                                Channels.Y          = -ElectrodeSetting.x;
                                Channels.Z          = ElectrodeSetting.z;
        %                         Channels.ref        = char(repmat({''},height(Channels),1));
        %                         Channels.sph_radius = repmat({[]},height(Channels),1);
        %                         Channels.types      = char(repmat({''},height(Channels),1));
        %                         Channels.urchan     = repmat({[]},height(Channels),1);
                                Channels            = table2struct(Channels);
                                save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1,Channels) %event start at 1
                            catch
                                save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1) %event start at 1
                            end
                            
%                             try
%                                 ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
%                                 Channels            = ElectrodeSetting(:,[6 7 2]);
%                                 Channels.labels     = char(Channels.labels);
%                                 Channels.sph_theta  = -Channels.theta;
%                                 Channels.sph_phi    = (0.5 - Channels.radius) * 180;
%                                 Channels.X          = ElectrodeSetting.x;
%                                 Channels.Y          = ElectrodeSetting.y;
%                                 Channels.Z          = ElectrodeSetting.z;
%         %                         Channels.ref        = char(repmat({''},height(Channels),1));
%         %                         Channels.sph_radius = repmat({[]},height(Channels),1);
%         %                         Channels.types      = char(repmat({''},height(Channels),1));
%         %                         Channels.urchan     = repmat({[]},height(Channels),1);
%                                 Channels            = table2struct(Channels);
%                             catch
%                                 Channels = '';
%                             end
%                             savefilename = convertStringsToChars(savefilename); %need to be char for save_eeglab
%                             
%                             save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1,Channels) %event start at 1
                        end                  
                        
                        %update the PIF (section output file)
                        fprintf(PifFileID,'%s\r\n',outputfilename);
                    end
                end
                
                %saving concatenate file
                if app.concatenate.Value 
                    for t=1:length(concatenateArgument) %loop according the number of concatenate file to record
                        if concatenateArgument(t)==1
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',app.concatenateSuffix1.Value{1}, app.FilesExtension.Value);
                            NewDataConcatenate=EEGconcatenated1;                            
                            MRK = eventsConcatenated1;
                        elseif concatenateArgument(t)==2
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',app.concatenateSuffix2.Value{1}, app.FilesExtension.Value);
                            NewDataConcatenate=EEGconcatenated2;                            
                            MRK = eventsConcatenated2;
                        elseif concatenateArgument(t)==3
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',app.concatenateSuffix3.Value{1}, app.FilesExtension.Value);
                            NewDataConcatenate=EEGconcatenated3;                            
                            MRK = eventsConcatenated3;
                        end
                      
                        %filling the complete path
                        savefilenameConcatenate=fullfile(app.SaveFolder.Value{1},outputfilenameConcatenate);
                       
                        %log file
                        fprintf(PifFileID,'%s\r\n',outputfilenameConcatenate);
                                    
                        
                        if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph') %manage the output format
                            save_eph(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1) %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.sef')
                            try
                                Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                            catch
                                Channels = '';
                            end
                            save_sef(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,Channels,MRK,1); %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.set')
                            savefilenameConcatenate = convertStringsToChars(savefilenameConcatenate); %nned to be char for save_eeglab
                            try
                                ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                                Channels            = ElectrodeSetting(:,2);
                                %Channels.labels     = char(ElectrodeSetting.labels);
    
                                % Recompute Theta and Radius to match EEGlab orientation
                                [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                                Channels.theta      = -rad2deg(az);
                                Channels.radius     = 0.5 - rad2deg(elev)/180;
                                Channels.sph_theta  = -Channels.theta;
                                Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                                Channels.X          = ElectrodeSetting.y;
                                Channels.Y          = -ElectrodeSetting.x;
                                Channels.Z          = ElectrodeSetting.z;
        %                         Channels.ref        = char(repmat({''},height(Channels),1));
        %                         Channels.sph_radius = repmat({[]},height(Channels),1);
        %                         Channels.types      = char(repmat({''},height(Channels),1));
        %                         Channels.urchan     = repmat({[]},height(Channels),1);
                                Channels            = table2struct(Channels);
                                save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1,Channels) %event start at 1
                            catch
                                save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1) %event start at 1
                            end
%                             try
%                                 ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
%                                 Channels            = ElectrodeSetting(:,[6 7 2]);
%                                 Channels.labels     = char(Channels.labels);
%                                 Channels.sph_theta  = -Channels.theta;
%                                 Channels.sph_phi    = (0.5 - Channels.radius) * 180;
%                                 Channels.X          = ElectrodeSetting.x;
%                                 Channels.Y          = ElectrodeSetting.y;
%                                 Channels.Z          = ElectrodeSetting.z;
%         %                         Channels.ref        = char(repmat({''},height(Channels),1));
%         %                         Channels.sph_radius = repmat({[]},height(Channels),1);
%         %                         Channels.types      = char(repmat({''},height(Channels),1));
%         %                         Channels.urchan     = repmat({[]},height(Channels),1);
%                                 Channels            = table2struct(Channels);
%                             catch
%                                 Channels = '';
%                             end
%                             savefilenameConcatenate = convertStringsToChars(savefilenameConcatenate); %nned to be char for save_eeglab
%                             save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1,Channels) %event start at 1
                        end
                   end
                end
                    
            end
            
            
%             % UPDATE DATA TABLE IN MAIN WINDOW
%             
%             % Data table in the Main Window
%             app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
%             app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
%             app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
%             app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
%             disp('Data table in the Main Window updated with the processed files')
%             cd(app.SaveFolder.Value{:})
%                      
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])

            
            %Close the PIF file 
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('File cut processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            delete (h);
            rz = msgbox('File cut processing done.');
        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.BatchButton.Enable = 'on';

            % Update parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.BatchButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpal_Batch
        function EEGpal_BatchCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.BatchButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_FileCut_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_FileCut_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_FileCut_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end

        % Value changed function: Process1DropDown
        function Process1DropDownValueChanged(app, event)
            value = app.Process1DropDown.Value;

            applyProcessChoice(app,1,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,2);


        end

        % Value changed function: Process2DropDown
        function Process2DropDownValueChanged(app, event)
            value = app.Process2DropDown.Value;
            
            applyProcessChoice(app,2,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,3);
        end

        % Value changed function: Process3DropDown
        function Process3DropDownValueChanged(app, event)
            value = app.Process3DropDown.Value;            
            
            applyProcessChoice(app,3,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,4);
        end

        % Value changed function: Process4DropDown
        function Process4DropDownValueChanged(app, event)
            value = app.Process4DropDown.Value;
            
            applyProcessChoice(app,4,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,5);
        end

        % Value changed function: Process5DropDown
        function Process5DropDownValueChanged(app, event)
            value = app.Process5DropDown.Value;
            
            applyProcessChoice(app,5,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,6);
        end

        % Value changed function: Process6DropDown
        function Process6DropDownValueChanged(app, event)
            value = app.Process6DropDown.Value;
            
            applyProcessChoice(app,6,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,7);
        end

        % Value changed function: Process7DropDown
        function Process7DropDownValueChanged(app, event)
            value = app.Process7DropDown.Value;
            
            applyProcessChoice(app,7,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,8);
        end

        % Value changed function: Process8DropDown
        function Process8DropDownValueChanged(app, event)
            value = app.Process8DropDown.Value;
            
            applyProcessChoice(app,8,value);

            % activate the next available process
            lookForNextAvalaibleProcess(app,9);
        end

        % Value changed function: Process9DropDown
        function Process9DropDownValueChanged(app, event)
            value = app.Process9DropDown.Value;
            
            applyProcessChoice(app,9,value);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.ModulesAvailable=app.ModulesAvailableStartup;
           
            for i=1:9 %reset layout
                currentDropDownList = app.ProcessDropDownList{i}; %retrive the current DownDown to edit
                currentDropDownList.Visible = "off";
                currentDropDownList.Enable = "off";                
                currentDropDownList.Items={''};
                currentDropDownList = app.ProcessDropDownListLabel{i}; %retrive the Label
                currentDropDownList.Visible = "off";
                currentDropDownList = app.OptionsDropDownList{i}; %retrive option
                currentDropDownList.Visible = "off";
            end
            
            % intialisation of the first 
            lookForNextAvalaibleProcess(app,1);
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.EEGpal_Batch.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {646, 646};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {287, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpal_Batch = uifigure('Visible', 'off');
            app.EEGpal_Batch.Name = 'EEGpal_BatchProcessing';
            app.EEGpal_Batch.CloseRequestFcn = createCallbackFcn(app, @EEGpal_BatchCloseRequest, true);
            app.EEGpal_Batch.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.EEGpal_Batch);
            app.LeftPanel = uipanel(app.GridLayout);
            app.BATCHProcessingLabel = uilabel(app.LeftPanel);
            app.BATCHProcessingLabel.Text = 'BATCH Processing';
            app.SelectUpperFolderLabel = uilabel(app.LeftPanel);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.LeftPanel, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.LeftPanel);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.LeftPanel);
            app.SaveFolder.Editable = 'off';
            app.ProcessingAnalysisparametersfileEditFieldLabel = uilabel(app.LeftPanel);
            app.ProcessingAnalysisparametersfileEditFieldLabel.Text = 'Processing/Analysis parameters file';
            app.ProcessingAnalysisparametersfileEditField = uieditfield(app.LeftPanel, 'text');
            app.ProcessingAnalysisparametersfileEditField.Editable = 'off';
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.ProcessesparameterssavedinthisfileLabel = uilabel(app.LeftPanel);
            app.ProcessesparameterssavedinthisfileLabel.Text = 'Process(es) parameters saved in this file';
            app.ModulesAvailabelLabel = uilabel(app.LeftPanel);
            app.APPLYLabel = uilabel(app.LeftPanel);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.LeftPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.SaveinmemoryButton = uibutton(app.LeftPanel, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.LeftPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.RightPanel = uipanel(app.GridLayout);
            app.SucessionLabel = uilabel(app.RightPanel);
            app.SucessionLabel.Text = 'Sucession of process(es)';
            app.OptionLabel = uilabel(app.RightPanel);
            app.OptionLabel.Text = 'Options';
            app.Label_2 = uilabel(app.RightPanel);
            app.Label_2.Text = '1';
            app.Process1DropDown = uidropdown(app.RightPanel);
            app.Process1DropDown.ValueChangedFcn = createCallbackFcn(app, @Process1DropDownValueChanged, true);
            app.Label_3 = uilabel(app.RightPanel);
            app.Label_3.Enable = 'off';
            app.Label_3.Visible = 'off';
            app.Label_3.Text = '2';
            app.Process2DropDown = uidropdown(app.RightPanel);
            app.Process2DropDown.ValueChangedFcn = createCallbackFcn(app, @Process2DropDownValueChanged, true);
            app.Process2DropDown.Enable = 'off';
            app.Process2DropDown.Visible = 'off';
            app.Label_4 = uilabel(app.RightPanel);
            app.Label_4.Enable = 'off';
            app.Label_4.Visible = 'off';
            app.Label_4.Text = '3';
            app.Process3DropDown = uidropdown(app.RightPanel);
            app.Process3DropDown.ValueChangedFcn = createCallbackFcn(app, @Process3DropDownValueChanged, true);
            app.Process3DropDown.Enable = 'off';
            app.Process3DropDown.Visible = 'off';
            app.Label_5 = uilabel(app.RightPanel);
            app.Label_5.Enable = 'off';
            app.Label_5.Visible = 'off';
            app.Label_5.Text = '4';
            app.Process4DropDown = uidropdown(app.RightPanel);
            app.Process4DropDown.ValueChangedFcn = createCallbackFcn(app, @Process4DropDownValueChanged, true);
            app.Process4DropDown.Enable = 'off';
            app.Process4DropDown.Visible = 'off';
            app.Label_6 = uilabel(app.RightPanel);
            app.Label_6.Enable = 'off';
            app.Label_6.Visible = 'off';
            app.Label_6.Text = '5';
            app.Process5DropDown = uidropdown(app.RightPanel);
            app.Process5DropDown.ValueChangedFcn = createCallbackFcn(app, @Process5DropDownValueChanged, true);
            app.Process5DropDown.Enable = 'off';
            app.Process5DropDown.Visible = 'off';
            app.Label_7 = uilabel(app.RightPanel);
            app.Label_7.Enable = 'off';
            app.Label_7.Visible = 'off';
            app.Label_7.Text = '6';
            app.Process6DropDown = uidropdown(app.RightPanel);
            app.Process6DropDown.ValueChangedFcn = createCallbackFcn(app, @Process6DropDownValueChanged, true);
            app.Process6DropDown.Enable = 'off';
            app.Process6DropDown.Visible = 'off';
            app.Label_8 = uilabel(app.RightPanel);
            app.Label_8.Enable = 'off';
            app.Label_8.Visible = 'off';
            app.Label_8.Text = '7';
            app.Process7DropDown = uidropdown(app.RightPanel);
            app.Process7DropDown.ValueChangedFcn = createCallbackFcn(app, @Process7DropDownValueChanged, true);
            app.Process7DropDown.Enable = 'off';
            app.Process7DropDown.Visible = 'off';
            app.Label_9 = uilabel(app.RightPanel);
            app.Label_9.Enable = 'off';
            app.Label_9.Visible = 'off';
            app.Label_9.Text = '8';
            app.Process8DropDown = uidropdown(app.RightPanel);
            app.Process8DropDown.ValueChangedFcn = createCallbackFcn(app, @Process8DropDownValueChanged, true);
            app.Process8DropDown.Enable = 'off';
            app.Process8DropDown.Visible = 'off';
            app.Label_10 = uilabel(app.RightPanel);
            app.Label_10.Enable = 'off';
            app.Label_10.Visible = 'off';
            app.Label_10.Text = '9';
            app.Process9DropDown = uidropdown(app.RightPanel);
            app.Process9DropDown.ValueChangedFcn = createCallbackFcn(app, @Process9DropDownValueChanged, true);
            app.Process9DropDown.Enable = 'off';
            app.Process9DropDown.Visible = 'off';
            app.Option1DropDown = uidropdown(app.RightPanel);
            app.Option1DropDown.Visible = 'off';
            app.Option2DropDown = uidropdown(app.RightPanel);
            app.Option2DropDown.Visible = 'off';
            app.Option3DropDown = uidropdown(app.RightPanel);
            app.Option3DropDown.Visible = 'off';
            app.Option4DropDown = uidropdown(app.RightPanel);
            app.Option4DropDown.Visible = 'off';
            app.Option5DropDown = uidropdown(app.RightPanel);
            app.Option5DropDown.Visible = 'off';
            app.Option6DropDown = uidropdown(app.RightPanel);
            app.Option6DropDown.Visible = 'off';
            app.Option7DropDown = uidropdown(app.RightPanel);
            app.Option7DropDown.Visible = 'off';
            app.Option8DropDown = uidropdown(app.RightPanel);
            app.Option8DropDown.Visible = 'off';
            app.Option9DropDown = uidropdown(app.RightPanel);
            app.Option9DropDown.Visible = 'off';
            app.ResetButton = uibutton(app.RightPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            % Show the figure after all components are created
            app.EEGpal_Batch.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_BatchProcessing(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpal_Batch)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpal_Batch)
        end
    end
end