classdef EEGpalCS_mod_Electrodes < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpalCS_Electrodes          matlab.ui.Figure
        CheckBoxAll                  matlab.ui.control.CheckBox
        CoordinateSystem             matlab.ui.control.Label
        SettingTable                 matlab.ui.control.Table
        CancelButton                 matlab.ui.control.Button
        DoneButton                   matlab.ui.control.Button
        ParameterFilePath            matlab.ui.control.EditField
        ParameterfileEditFieldLabel  matlab.ui.control.Label
        SaveAsParamButton            matlab.ui.control.Button
        SaveParamButton              matlab.ui.control.Button
        OpenButton                   matlab.ui.control.Button
        InputParametersFile          matlab.ui.control.Label
        SaveAsSettingButton          matlab.ui.control.Button
        SaveelectrodesettingLabel    matlab.ui.control.Label
        AddElectrodesInstruction     matlab.ui.control.Label
        AddButton                    matlab.ui.control.Button
        NumElecAdd                   matlab.ui.control.NumericEditField
        ManuallyaddelectrodesLabel   matlab.ui.control.Label
        NumElecData                  matlab.ui.control.EditField
        NumberofelectrodesindataEditFieldLabel  matlab.ui.control.Label
        DatasettingsLabel            matlab.ui.control.Label
        NumClust                     matlab.ui.control.NumericEditField
        NumberofclustersinfileLabel  matlab.ui.control.Label
        NumElecFile                  matlab.ui.control.NumericEditField
        NumberofelectrodesinfileEditFieldLabel  matlab.ui.control.Label
        SettingAddress               matlab.ui.control.TextArea
        FileLabel                    matlab.ui.control.Label
        ElectrodesInstrution         matlab.ui.control.Label
        UploadSettingFileButton      matlab.ui.control.Button
        UploadSettingsLabel          matlab.ui.control.Label
        ELECTRODESETTINGLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        NumChanData % Number of channels in files
    end
    
    properties (Access = public)
        Study_Parameters
        SessionParameters
    end
    
    methods (Access = private)
        
        function applyStudyParam(app)
            app.SettingAddress.Value    = app.Study_Parameters.Electrodes.SettingFile;
            app.SettingTable.Data       = app.Study_Parameters.Electrodes.SettingTable;
        end
        
        function Study_Parameters = updateStudyParam(app,Study_Parameters)
            Study_Parameters.Electrodes.SettingTable       = app.SettingTable.Data;
            Study_Parameters.Electrodes.SettingFile        = app.SettingAddress.Value;
        end
        
        function applySessionParam(app)
            app.SettingAddress.Value    = app.SessionParameters.Electrodes.SettingFile;
            app.SettingTable.Data       = app.SessionParameters.Electrodes.SettingTable;
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            SessionParameters.Electrodes.SettingTable      = app.SettingTable.Data;
            SessionParameters.Electrodes.SettingFile       = app.SettingAddress.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main = EEGpalCS_Main;
            
            
            %% Update UI with input values
            DataTable = app.EEGpalCS_Main.DataFilesTable.Data;
            if ~isempty(DataTable)
                app.NumChanData = unique(DataTable.nChan)';
                app.NumElecData.Value = num2str(app.NumChanData);
            end
            app.ParameterFilePath.Value     = app.EEGpalCS_Main.ParameterFilePath.Value;
            app.Study_Parameters            = app.EEGpalCS_Main.Study_Parameters;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;
            
            % Saved study parameters
            try
                applyStudyParam(app)
            catch
            end
            
            % Session parameters override saved parameters
            try
                applySessionParam(app)
            catch
            end
        end

        % Button pushed function: UploadSettingFileButton
        function UploadSettingFileButtonPushed(app, event)
            % Open file            
            if ispc
                defaultLocation=strcat(app.EEGpalCS_Main.AppPath.value, '\Resources');
            else
                defaultLocation=strcat(app.EEGpalCS_Main.AppPath.value, '/Resources');
            end

            [ElectrodeFile,ElectrodePath] = uigetfile('*.els;*.xyz;*.loc;*.locs','Select electrode setting file',defaultLocation);
            drawnow; figure(app.EEGpalCS_Electrodes); % Restaure the app module
            if isequal(ElectrodeFile,0) || isequal(ElectrodePath,0)
               return; % User pressed cancel
            end
            
            app.SettingTable.Data = []; % Remove pre-exising data
            
            openfilename = fullfile(ElectrodePath,ElectrodeFile);
            [~,~,FileExtension] = fileparts(openfilename);
            if strcmpi(FileExtension,'.els')
                [~,nElec,ELSclusters] = open_els(openfilename);
                app.NumClust.Value = size(ELSclusters,2);
                % Table
                pointer = 1;
                for clust = 1:length(ELSclusters)
                    NumT = array2table((pointer:pointer+ELSclusters(clust).numelectrodes-1)','VariableNames',{'nr'});
                    T = struct2table(ELSclusters(clust).electrodes);
                    T = [NumT T];
                    [az,elev,~]             = cart2sph(T.x,T.y,T.z);
                    T.theta                 = round(-rad2deg(az));
                    T.radius                = 0.5 - rad2deg(elev)/180;
                    T.cluster               = repmat({ELSclusters(clust).name},height(T),1);
                    T.source                = repmat({'Setting File'},height(T),1);
                    T.include               = repmat({true},height(T),1);
                    pointer                 = pointer + ELSclusters(clust).numelectrodes;
                    app.SettingTable.Data   = [app.SettingTable.Data;T];
                end
            elseif strcmpi(FileExtension,'.xyz')
                [ElectrodesTable,nElec] = open_xyz(openfilename);
                app.NumClust.Value = 1;
                % Table
                app.SettingTable.Data           = ElectrodesTable(:,[1 5 2 3 4]);
                [az,elev,~]                     = cart2sph(ElectrodesTable.x,ElectrodesTable.y,ElectrodesTable.z);
                app.SettingTable.Data.theta    = -rad2deg(az);
                app.SettingTable.Data.radius    = 0.5 - rad2deg(elev)/180;
                app.SettingTable.Data.cluster   = repmat({'XYZfile'},height(app.SettingTable.Data),1);
                app.SettingTable.Data.source    = repmat({'Setting File'},height(app.SettingTable.Data),1);
                app.SettingTable.Data.include   = repmat({true},height(app.SettingTable.Data),1);
            elseif strcmpi(FileExtension,'.loc') || strcmpi(FileExtension,'.locs')
                [ElectrodesTable,nElec] = open_loc(openfilename);
                app.NumClust.Value = 1;
                app.SettingTable.Data           = ElectrodesTable(:,[1 4]);
                sph_theta                       = -ElectrodesTable.theta;
                sph_phi                         = (0.5 - ElectrodesTable.radius) * 180;
                [X,Y,Z]                         = sph2cart(deg2rad(sph_theta),deg2rad(sph_phi),1);
                app.SettingTable.Data.x         = -Y; % Nasion: in Cartool = Y+, in EEGlab = X+;
                app.SettingTable.Data.y         = X; % Nasion: in Cartool = Y+, in EEGlab = X+;
                app.SettingTable.Data.z         = Z;
                app.SettingTable.Data.theta     = ElectrodesTable.theta;
                app.SettingTable.Data.radius    = ElectrodesTable.radius;
                app.SettingTable.Data.cluster   = repmat({'LOCfile'},height(app.SettingTable.Data),1);
                app.SettingTable.Data.source    = repmat({'Setting File'},height(app.SettingTable.Data),1);
                app.SettingTable.Data.include   = repmat({true},height(app.SettingTable.Data),1);
            end
            
            
            % Display information
            app.SettingAddress.Value = openfilename;
            app.NumElecFile.Value = nElec;
            
            if max(app.NumChanData) > nElec
                app.NumElecAdd.Value = max(app.NumChanData) - nElec;
            end
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            if app.NumElecAdd.Value < 1
                msgbox(sprintf('%s\n\n%s','Number of electrodes to add set to 0.', ...
                    'Please enter a positive integer number in the appropriate field.'),...
                    'No electrodes to add','warn');
            else
                VariableTypes = {'double','char','double','double','double','double','double','char','char','logical'};
                T = table('Size',[app.NumElecAdd.Value length(app.SettingTable.ColumnName)],...
                    'VariableTypes',VariableTypes,'VariableNames',app.SettingTable.ColumnName);
                T(:,3:7) = {1};
                FirstIndex = size(app.SettingTable.Data,1)+1;
                T.nr = (FirstIndex:FirstIndex+app.NumElecAdd.Value-1)';
                T.labels = cellstr(strcat('e',num2str(T.nr)));
                T.cluster = cellstr(repmat('Extra',height(T),1));
                T.source = cellstr(repmat('Manual',height(T),1));
                T.include = repmat({false},height(T),1);
                
                app.SettingTable.Data = [app.SettingTable.Data;T];
                
                if max(app.NumChanData) > height(app.SettingTable.Data)
                    app.NumElecAdd.Value = max(app.NumChanData) - height(app.SettingTable.Data);
                else
                    app.NumElecAdd.Value = 0;
                end
            end
        end

        % Button pushed function: SaveAsSettingButton
        function SaveAsSettingButtonPushed(app, event)
            % Get saving file name and path
            [File,Path] = uiputfile({'*.els';'*.locs'},'Save electrodes setting file','ElectrodeSetting_EEGpalCS.els');
            drawnow; figure(app.EEGpalCS_Electrodes); % Restaure the app module
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            savefilename = fullfile(Path,File);
            SaveTable = app.SettingTable.Data;
            SaveTable(~[app.SettingTable.Data.include{:}]',:) = [];
            
            
            % SAVE FILE
            
            % Cartool format
            if strcmp(savefilename(end-3:end),'.els')
                electrodestable = SaveTable(:,[3 4 5 2 8]);
                save_els(savefilename,electrodestable)
            
            % EEGlab format
            elseif strcmp(savefilename(end-3:end),'.loc') || strcmp(savefilename(end-4:end),'.locs')
                electrodestable         = SaveTable(:,[1 6 7 2]);
                % Recompute Theta and Radius to match EEGlab orientation
                [az,elev,~]             = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                electrodestable.theta   = -rad2deg(az);
                electrodestable.radius  = 0.5 - rad2deg(elev)/180;
                save_loc(savefilename,electrodestable)
            
            % Matlab Cartesian XYZ - not used to avoid mixing-up with obsolete (but still used) Cartool XYZ
%             elseif strcmp(savefilename(end-3:end),'.xyz')
%                 electrodestable = SaveTable(:,[1 3 4 5 2]);
%                 save_xyz(savefilename,electrodestable)
            end
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            [File,Path] = uigetfile('*.mat','Select parameter file','StudyParameters_EEGpalCS.mat');
            drawnow; figure(app.EEGpalCS_Electrodes); % Restaure the app module
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            load(app.ParameterFilePath.Value);
            
            try
                applyStudyParam(app);
            catch
                msgbox(sprintf('%s','No parameters saved for this module.'),...
                    'No parameters','warn');
            end
        end

        % Button pushed function: SaveParamButton
        function SaveParamButtonPushed(app, event)
            % Save parameter file
            if ~isempty (app.ParameterFilePath.Value)
                app.Study_Parameters = load(app.ParameterFilePath.Value);
                app.Study_Parameters = updateStudyParam(app,app.Study_Parameters.Parameters);
                Parameters = app.Study_Parameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            else
                [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
                drawnow; figure(app.EEGpalCS_Electrodes); % Restaure the app module
                if isequal(File,0) || isequal(Path,0)
                   return; % User pressed cancel
                end
                app.ParameterFilePath.Value = fullfile(Path,File);
                app.SessionParameters = updateSessionParam(app,app.SessionParameters);
                Parameters = app.SessionParameters;
                save(app.ParameterFilePath.Value,'-struct','Parameters');
            end
        end

        % Button pushed function: SaveAsParamButton
        function SaveAsParamButtonPushed(app, event)
            app.SessionParameters = updateSessionParam(app,app.SessionParameters);
            Parameters = app.SessionParameters;
            
            % Save parameter file
            [File,Path] = uiputfile('*.mat','Save parameter file',[app.EEGpalCS_Main.StudyName.Value '_StudyParameters_EEGpalCS.mat']);
            drawnow; figure(app.EEGpalCS_Electrodes); % Restaure the app module
            if isequal(File,0) || isequal(Path,0)
               return; % User pressed cancel
            end
            app.ParameterFilePath.Value = fullfile(Path,File);
            save(app.ParameterFilePath.Value,'Parameters');
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.ElectrodeSettingButton.Enable = 'on';
            
            % Electrode setting parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            
            % Update parameters
            app.EEGpalCS_Main.Study_Parameters  = app.Study_Parameters;
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.ElectrodeSettingButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpalCS_Electrodes
        function EEGpalCS_ElectrodesCloseRequest(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.ElectrodeSettingButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Value changed function: CheckBoxAll
        function CheckBoxAllValueChanged(app, event)
            value = app.CheckBoxAll.Value;
            nbElec=size(app.SettingTable.Data,1);
            if nbElec~=0
                if value
                    for i=1:nbElec
                        app.SettingTable.Data.include{i}=true;
                    end
                else
                    for i=1:nbElec
                        app.SettingTable.Data.include{i}=false;
                    end
                end   
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpalCS_Electrodes = uifigure('Visible', 'off');
            app.EEGpalCS_Electrodes.Name = 'EEGpalCS Electrode Setting';
            app.EEGpalCS_Electrodes.CloseRequestFcn = createCallbackFcn(app, @EEGpalCS_ElectrodesCloseRequest, true);
            app.ELECTRODESETTINGLabel = uilabel(app.EEGpalCS_Electrodes);
            app.ELECTRODESETTINGLabel.Text = 'ELECTRODE SETTING';
            app.UploadSettingsLabel = uilabel(app.EEGpalCS_Electrodes);
            app.UploadSettingsLabel.Text = 'Upload settings';
            app.UploadSettingFileButton = uibutton(app.EEGpalCS_Electrodes, 'push');
            app.UploadSettingFileButton.ButtonPushedFcn = createCallbackFcn(app, @UploadSettingFileButtonPushed, true);
            app.UploadSettingFileButton.Text = {'Electrode '; 'Setting file'};
            app.ElectrodesInstrution = uilabel(app.EEGpalCS_Electrodes);
            app.ElectrodesInstrution.Text = {'Cartool coordinate system (Y-axis towards nasion).'; 'Unselected channels will be removed from the processed data. This cannot be undone. The input data will not be '; 'modified. Specific modules have option to ignore channels without removing them.'};
            app.FileLabel = uilabel(app.EEGpalCS_Electrodes);
            app.FileLabel.Text = 'File:';
            app.SettingAddress = uitextarea(app.EEGpalCS_Electrodes);
            app.SettingAddress.Editable = 'off';
            app.NumberofelectrodesinfileEditFieldLabel = uilabel(app.EEGpalCS_Electrodes);
            app.NumberofelectrodesinfileEditFieldLabel.Text = 'Number of electrodes in file:';
            app.NumElecFile = uieditfield(app.EEGpalCS_Electrodes, 'numeric');
            app.NumElecFile.Editable = 'off';
            app.NumberofclustersinfileLabel = uilabel(app.EEGpalCS_Electrodes);
            app.NumberofclustersinfileLabel.Text = 'Number of clusters in file:';
            app.NumClust = uieditfield(app.EEGpalCS_Electrodes, 'numeric');
            app.NumClust.Editable = 'off';
            app.DatasettingsLabel = uilabel(app.EEGpalCS_Electrodes);
            app.DatasettingsLabel.Text = 'Data settings';
            app.NumberofelectrodesindataEditFieldLabel = uilabel(app.EEGpalCS_Electrodes);
            app.NumberofelectrodesindataEditFieldLabel.Text = 'Number of electrodes in data:';
            app.NumElecData = uieditfield(app.EEGpalCS_Electrodes, 'text');
            app.NumElecData.Editable = 'off';
            app.NumElecData.Tooltip = {'If more than one number, the number of electrodes is inconsistent between files.'};
            app.NumElecData.Value = 'undefined';
            app.ManuallyaddelectrodesLabel = uilabel(app.EEGpalCS_Electrodes);
            app.ManuallyaddelectrodesLabel.Text = 'Manually add           electrodes:';
            app.NumElecAdd = uieditfield(app.EEGpalCS_Electrodes, 'numeric');
            app.AddButton = uibutton(app.EEGpalCS_Electrodes, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.AddElectrodesInstruction = uilabel(app.EEGpalCS_Electrodes);
            app.AddElectrodesInstruction.Text = {'If you add electrodes manually, check in '; 'the table and modify accordingly.'; 'This is advanced stuff, so do it only if you'; 'know what you''re doing...'; '... or if you want to play around. It always '; 'good to play around to understand how '; 'things work, and then brag about how you'; 'know what you''re doing!'};
            app.SaveelectrodesettingLabel = uilabel(app.EEGpalCS_Electrodes);
            app.SaveelectrodesettingLabel.Text = 'Save electrode setting:';
            app.SaveAsSettingButton = uibutton(app.EEGpalCS_Electrodes, 'push');
            app.SaveAsSettingButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsSettingButtonPushed, true);
            app.SaveAsSettingButton.Tooltip = {'Only selected electrodes will be saved'};
            app.SaveAsSettingButton.Text = 'Save as';
            app.InputParametersFile = uilabel(app.EEGpalCS_Electrodes);
            app.InputParametersFile.Text = 'Parameters file';
            app.OpenButton = uibutton(app.EEGpalCS_Electrodes, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.SaveParamButton = uibutton(app.EEGpalCS_Electrodes, 'push');
            app.SaveParamButton.ButtonPushedFcn = createCallbackFcn(app, @SaveParamButtonPushed, true);
            app.SaveParamButton.Tooltip = {'Update an existing file.'; 'Will erase preexisting Electrodes options.'};
            app.SaveParamButton.Text = 'Save';
            app.SaveAsParamButton = uibutton(app.EEGpalCS_Electrodes, 'push');
            app.SaveAsParamButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsParamButtonPushed, true);
            app.SaveAsParamButton.Tooltip = {'Create a new file.'; 'Will erase data if file already exists.'};
            app.SaveAsParamButton.Text = 'Save as';
            app.ParameterfileEditFieldLabel = uilabel(app.EEGpalCS_Electrodes);
            app.ParameterfileEditFieldLabel.Text = 'Parameter file:';
            app.ParameterFilePath = uieditfield(app.EEGpalCS_Electrodes, 'text');
            app.ParameterFilePath.Editable = 'off';
            app.DoneButton = uibutton(app.EEGpalCS_Electrodes, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Tooltip = {'Return to the main window'};
            app.DoneButton.Text = 'Done';
            app.CancelButton = uibutton(app.EEGpalCS_Electrodes, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.SettingTable = uitable(app.EEGpalCS_Electrodes);
            app.SettingTable.ColumnName = {'nr'; 'labels'; 'x'; 'y'; 'z'; 'theta'; 'radius'; 'cluster'; 'source'; 'include'};
            app.SettingTable.RowName = {};
            app.SettingTable.ColumnSortable = [true true false false false false false true true true];
            app.SettingTable.ColumnEditable = [false true true true true true true true false true];
            app.CoordinateSystem = uilabel(app.EEGpalCS_Electrodes);
            app.CoordinateSystem.Text = {'Cartool coordinate system '; '(Y-axis towards nasion)'};
            app.CheckBoxAll = uicheckbox(app.EEGpalCS_Electrodes);
            app.CheckBoxAll.ValueChangedFcn = createCallbackFcn(app, @CheckBoxAllValueChanged, true);
            app.CheckBoxAll.Text = 'all';
            app.CheckBoxAll.Value = true;
            % Show the figure after all components are created
            app.EEGpalCS_Electrodes.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_Electrodes(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpalCS_Electrodes)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpalCS_Electrodes)
        end
    end
end