classdef EEGpalCS_mod_FileCut < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EEGpal_FileCut               matlab.ui.Figure
        HelpButton                   matlab.ui.control.Button
        LoadtableButton              matlab.ui.control.Button
        SavetableasButton            matlab.ui.control.Button
        concatenateSuffix3           matlab.ui.control.TextArea
        Suffix_Concatenate_3Label    matlab.ui.control.Label
        concatenateSuffix2           matlab.ui.control.TextArea
        Suffix_Concatenate_2Label    matlab.ui.control.Label
        concatenateSuffix1           matlab.ui.control.TextArea
        Suffix_Concatenate_1Label    matlab.ui.control.Label
        concatenate                  matlab.ui.control.CheckBox
        SelectUpperFolderLabel_2     matlab.ui.control.Label
        Instruct2                    matlab.ui.control.Label
        Instruct1                    matlab.ui.control.Label
        CutTable                     matlab.ui.control.Table
        ButtonGroup                  matlab.ui.container.ButtonGroup
        SaveinthesamefolderButton    matlab.ui.control.RadioButton
        SaveinseparatefoldersButton  matlab.ui.control.RadioButton
        CancelButton                 matlab.ui.control.Button
        SaveinmemoryButton           matlab.ui.control.Button
        RunButton                    matlab.ui.control.Button
        APPLYLabel                   matlab.ui.control.Label
        SaveFolder                   matlab.ui.control.TextArea
        SavingfolderLabel            matlab.ui.control.Label
        SelectFolderButton           matlab.ui.control.Button
        SelectUpperFolderLabel       matlab.ui.control.Label
        FilesExtension               matlab.ui.control.DropDown
        ExtensionofoutputfilesLabel  matlab.ui.control.Label
        OUTPUTOPTIONSLabel           matlab.ui.control.Label
        FileCutLabel                 matlab.ui.control.Label
    end

    
    properties (Access = private)
        EEGpalCS_Main   % Main app object
        
        DataTable
        
        SettingTable
    end
    
    properties (Access = public)
        SessionParameters
    end
    
    methods (Access = private)


        function applySessionParam(app)
            % TABLE OF FILECUT
            app.CutTable.Data                      = app.SessionParameters.FileCut.CutTable;

            % SAVE OPTION
            app.concatenate.Value                   = app.SessionParameters.FileCut.concatenate;
            app.concatenateSuffix1.Value            = app.SessionParameters.FileCut.concatenateSuffix1;
            app.concatenateSuffix2.Value            = app.SessionParameters.FileCut.concatenateSuffix2;
            app.concatenateSuffix3.Value            = app.SessionParameters.FileCut.concatenateSuffix3;
            app.FilesExtension.Value                = app.SessionParameters.FileCut.FilesExtension;            
            app.SaveFolder.Value                    = app.SessionParameters.FileCut.SaveFolder;
            app.SaveinthesamefolderButton.Value     = app.SessionParameters.FileCut.SaveinthesamefolderButton;
            app.SaveinseparatefoldersButton.Value   = app.SessionParameters.FileCut.SaveinseparatefoldersButton;
            
        end
        
        function SessionParameters = updateSessionParam(app,SessionParameters)
            % TABLE OF FILECUT
            SessionParameters.FileCut.CutTable                      = app.CutTable.Data;

            % SAVE OPTION
            SessionParameters.FileCut.concatenate                   = app.concatenate.Value;
            SessionParameters.FileCut.concatenateSuffix1            = app.concatenateSuffix1.Value;
            SessionParameters.FileCut.concatenateSuffix2            = app.concatenateSuffix2.Value;
            SessionParameters.FileCut.concatenateSuffix3            = app.concatenateSuffix3.Value;
            SessionParameters.FileCut.FilesExtension                = app.FilesExtension.Value;
            SessionParameters.FileCut.SaveFolder                    = app.SaveFolder.Value;
            SessionParameters.FileCut.SaveinthesamefolderButton     = app.SaveinthesamefolderButton.Value;
            SessionParameters.FileCut.SaveinseparatefoldersButton   = app.SaveinseparatefoldersButton.Value;
            
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, EEGpalCS_Main)
            % Store EEGpalCS_Main app object
            app.EEGpalCS_Main   = EEGpalCS_Main;
            app.DataTable       = app.EEGpalCS_Main.DataFilesTable.Data;
            
            
            % Update UI with input values
            ParameterFilePath               = app.EEGpalCS_Main.ParameterFilePath.Value;
            app.DataTable                   = app.EEGpalCS_Main.DataFilesTable.Data;
            app.SessionParameters           = app.EEGpalCS_Main.SessionParameters;     
           
            %create of the app.CutTable to enter the parameters
            labels      = string({'Start_Trigger','End_Trigger','End_Duration','End_Value','Suffix_String','Concatenate'});
            app.CutTable.Data = table('Size',[15 length(labels)],...
                'VariableTypes',["single", "logical", "logical","single","string","single"],'VariableNames',labels);

            %Preset Cartool/eeglab parameters
            if strcmp(app.EEGpalCS_Main.PresetParametersSwitch.Value,'eeglab')
                app.FilesExtension.Value = '.set';
            end 
            
            % Apply Session parameters 
            try
                applySessionParam(app)
            catch
            end
            % Save folder
            if isempty(app.SaveFolder.Value{:})
                try
                    app.SaveFolder.Value = app.SessionParameters.Files.SaveFolder;
                catch
                end
            end
            
            %restaure visual view
            concatenateValueChanged(app);
            
            
        end

        % Button pushed function: SelectFolderButton
        function SelectFolderButtonPushed(app, event)
            savefolder = uigetdir('title',...
                'Select uppermost folder where to save the files');
            drawnow; figure(app.EEGpal_FileCut); % Restaure the app module
            if isequal(savefolder,0)
               return; % User pressed cancel
            end
            app.SaveFolder.Value = savefolder;
            cd(app.SaveFolder.Value{:})
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            %length table
            [CutTableLength,~]=size(app.CutTable.Data); %length of table
            workingIndices =[]; %to save the indice where a start trigger has been specified
            
            %% CHECK
            
            if isempty(app.SaveFolder.Value{:})
                msgbox(sprintf('%s\n\n%s','No output folder selected!', ...
                    'Please make sure to select the output folder where to put the processed files.'),...
                    'No output folder selected','warn');
                return
            end
            
            indice=0;
            for i=1:CutTableLength
                if app.CutTable.Data.Start_Trigger(i)~=0 %case to process
                    indice=indice+1;
                    workingIndices(indice)=i;
                    
                    if app.CutTable.Data.End_Value(i)==0  %check if no End Point
                        msgbox(sprintf('%s\n\n%s','Missing End Point!', ...
                            'You have forget to specify a End_Point in the table. Please make sure to have specify an End_Point to every line where you specified a Start_Trigger.'),...
                        'Missing End Point','warn');
                        return
                    end
                    
                    if and (~app.CutTable.Data.End_Trigger(i),~app.CutTable.Data.End_Duration(i))
                        msgbox(sprintf('%s\n\n%s','Missing End Point Type!', ...
                        'You have forget to specify if the End_Point is a Trigger or a Duration to the Start_trigger . Please make sure to have tick either the option "End_Option_Trigger" or "End_Option_Duration" to every line where you specified a Start_Trigger.'),...
                        'Missing End Point Type','warn');
                        return
                    end
                    
                end
            end   
            
            if indice==0
                msgbox(sprintf('%s','Sorry but you did not provide me any start point insctuction'),...
                'Missing Start Trigger','warn');
                return
            end
            
            Run = questdlg(sprintf('%s\n%s\n\n%s%d\n\n%s',...
                'Hello!',...
                'Me about to split the eeg files into subfiles the data. Ready for the ride?',...
                'Number of subfiles which will be generated per files: ',length(workingIndices),...
                'Do you wish to proceed? (Warning: after computation the results will be not transfer to the main window of EEGpal. You will need to manually import the files you want for next steps of processing)'),'Run checked options','OK','Cancel','Cancel');
            
            if strcmpi(Run,'Cancel')
                return
            end
            
            concatenateArgument=[];
            if app.concatenate.Value
                %check if user didn't respect the instruction
                concatenateArgument=unique(app.CutTable.Data.Concatenate(:)); concatenateArgument(concatenateArgument==0)=[];
                problem=false;
                for i=1:length(concatenateArgument)
                    if concatenateArgument(i)~=1 & concatenateArgument(i)~=2 & concatenateArgument(i)~=3
                         problem=true;
                         if problem
                            msgbox(sprintf('%s','Sorry but you used another value that 1,2 or 3 in the Concatenate Colum. I can concatenate only three files per run. Please correct and press Run again!'),...
                            'Wrong concatenation argument','warn');
                            return
                         end
                    end
                end                 
            end
            
            %% PROCESSING INFORMATION SHEET
            
            currentfile = dir([mfilename('fullpath') '.mlapp']); % Information about current file
            
            % Create a .txt file for PIF (Processing Info File)
            PifFileID = fopen(fullfile(app.SaveFolder.Value{:},['PIF_FileCut_' datestr(now,'dd-mm-yy_HHMMSS') '.txt']),'w');
            fprintf(PifFileID,'%s\r\n%s\r\n\r\n','EEGpal File Cut Processing Info File',...
                '======================================================================='); % header
            
            fprintf(PifFileID,'%s\r\n\r\n',char(strcat('Date:',{' '},datestr(datetime))));
            fprintf(PifFileID,'%s\t\t%s\r\n','App name:',currentfile.name);
            fprintf(PifFileID,'%s\t\t%s\r\n','App folder:',currentfile.folder);
%             fprintf(PifFileID,'%s%s\r\n','Script last official update: ',update(11:end));
            fprintf(PifFileID,'%s\t%s\r\n','App last save:',currentfile.date);
            fprintf(PifFileID,'%s\t%f\r\n','Current version of EEGpal:',app.EEGpalCS_Main.Versions.EEGpal);
            
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','DATA');
            fprintf(PifFileID,'%s\t\t%s\r\n','Input data root folder:',app.EEGpalCS_Main.RootFolder.Value{:});
            fprintf(PifFileID,'%s\t%s\r\n','Output data root folder:',app.SaveFolder.Value{:});
          
            fprintf(PifFileID,'%s\t\t%s\r\n','Output files extension:',app.FilesExtension.Value);
            
            %cutting instruction 
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','CUTTING INSTRUCTIONS (Start Trigger  /  End Option(Trigger or Duration)  /  End Point  /  File suffix  /  Concatenate index)');
            for i=1:indice
                fprintf(PifFileID,'%d\t\t', app.CutTable.Data.Start_Trigger(workingIndices(i)));
                if app.CutTable.Data.End_Trigger(workingIndices(i))
                    fprintf(PifFileID,'%s\t\t','Trigger');
                else
                    fprintf(PifFileID,'%s\t\t','Duration');
                end
                fprintf(PifFileID,'%d\t\t', app.CutTable.Data.End_Value(workingIndices(i)));
                
                if ~ismissing(app.CutTable.Data.Suffix_String(workingIndices(i)))
                    fprintf(PifFileID,'%s\t\t',app.CutTable.Data.Suffix_String(workingIndices(i)));
                else
                    fprintf(PifFileID,'%s\t\t','missing');
                end
                if ~ismissing(app.CutTable.Data.Concatenate(workingIndices(i)))
                    fprintf(PifFileID,'%d\r\n',app.CutTable.Data.Concatenate(workingIndices(i)));
                else
                    fprintf(PifFileID,'\r\n');
                end
            end
            
            %input files
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','INPUT FILES (File name  /  Sampling rate  /  N. channels  /  Folder)');
            Files = [app.DataTable.File';num2cell(app.DataTable.(4))';num2cell(app.DataTable.(3))';app.DataTable.Path'];
            fprintf(PifFileID,'%s\t\t%d\t\t%d\t\t%s\r\n',Files{:});
            
            %output files
            if app.SaveinseparatefoldersButton.Value
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: File saved in separate sub-folder');
            else
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: All files saved in the target directory');
            end
            if app.concatenate.Value
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: Concatenate signal according Concatenate column');
            else
                fprintf(PifFileID,'\r\n\r\n%s','SAVING OPTION: No concatenation of signal');
            end
            fprintf(PifFileID,'\r\n\r\n%s\r\n\r\n','OUTPUT FILES (File name)');
            %the outputfile file be contiousely write in the PIF file
            %during processing

            
            %% RUN CUT File
            
            ProcessedDataTable = app.DataTable; % For updating the Main Window
            ChannelsCheck = strings(height(app.DataTable),1); % For checking the names of the channels in the file and in the electrode setting
            
            disp('Run File Cut...')
            StartTime = datetime('now');
            tStart = tic;
            h = waitbar(0,'Processing.....');
            
            for file = 1:height(app.DataTable)  % Load file by file
                waitbar(file/height(app.DataTable),h, strcat('Processing File ',num2str(file),'/',num2str(height(app.DataTable))));
                % Load file
                disp(['Loading ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                disp(['Root folder: ', app.EEGpalCS_Main.RootFolder.Value{:}])
                openfilename = cell2mat(fullfile(app.EEGpalCS_Main.RootFolder.Value,erase(app.DataTable.Path(file),'..'),app.DataTable.File(file))); % full path and name of the file
                [~,FileName,FileExtension] = fileparts(openfilename);
                if strcmpi(FileExtension,'.bdf')
                    [header,data,events] = open_bdf(openfilename);
                    events = [events(:,1), events(:,1), events(:,2)]; % Convert to Cartool MRK format (onset, offset, code)
                    FileChannels = header.Channel.Labels(1:end-1); % Remove 'Status' channel
                elseif strcmpi(FileExtension,'.set')
                    [header,data,events] = open_eeglab(openfilename);
%                     events = [events.latency(:)-1, events.latency(:)-1, events{:,2}]; % Convert to Cartool MRK format (onset, offset, code)
%                     if ismember('duration', events.Properties.VariableNames)
%                         events(:,2) = events.latency(:)-1 + events.duration(:);
%                     end
%                     FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.sef')
                    [header,data,events] = open_sef(openfilename);
                    FileChannels = header.Channels;
                elseif strcmpi(FileExtension,'.ep') || strcmpi(FileExtension,'.eph')
                    [header,data,events] = open_eph(openfilename);
                else
                    if strcmpi(FileExtension,'.ris') || strcmpi(FileExtension,'.freq')
                        disp('These tools are not intended for .freq or .ris files.')
                    else
                        disp('File extension not recognized.')
                    end
                    disp(['File: ', cell2mat(fullfile(app.DataTable.Path(file),app.DataTable.File(file)))])
                    disp(['Root folder: ,' app.EEGpalCS_Main.RootFolder.Value{:}])
                    disp('File skipped.')
                    %continue
                end
                disp('File loaded!')

                % Remove unselected channels from the EEGpalCS Electrode Setting window
                try
                    data = data(:,[app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                catch
                end
                
                %check if event are cell
                if iscell(events)
                    events=cell2mat(events);
                end
                
                %correct the event indice from Cartool (0) to Matlab (1)
                if header.firstindex == 0
                    events(:,1)=events(:,1)+1;
                    events(:,2)=events(:,2)+1;
                end
                
                %intitiate the concatenation vectors
                if length(find(concatenateArgument(:)==1))==1 & app.concatenate.Value
                    EEGconcatenated1=[]; eventsConcatenated1=[];indiceConcatenate1=0;
                end
                if length(find(concatenateArgument(:)==2))==1  & app.concatenate.Value
                    EEGconcatenated2=[]; eventsConcatenated2=[];indiceConcatenate2=0;
                end
                if length(find(concatenateArgument(:)==3))==1  & app.concatenate.Value
                    EEGconcatenated3=[]; eventsConcatenated3=[]; indiceConcatenate3=0;
                end
                
                %run the File Cut
                for j=1:length(workingIndices) %loop over the segments
                    
                    %set the start position for the current segment
                    posTriggerStart=find(events(:,3)==app.CutTable.Data.Start_Trigger(workingIndices(j)));
                    
                    if length(posTriggerStart)>1 %problem of several occurence of the same trigger
                        disp('The start trigger has several occurence')
                        NewDataConcatenate=[]; %need to concatenate files
                        eventsConcatenated0=[];
                    end
                    startTFPosition=events(posTriggerStart,1);
                    
                    %set the end position for the current segment
                    if isnan(app.CutTable.Data.End_Value(workingIndices(j)))
                        endTFPosition=size(data,1); %case where you go until the end of the file. 
                    elseif app.CutTable.Data.End_Trigger(workingIndices(j)) %case where the end is specified by a trigger                   
                        posTriggerEnd=find(events(:,3)==app.CutTable.Data.End_Value(workingIndices(j)));
                        if length(posTriggerEnd) ~= length(posTriggerStart) %problem of several occurence of the same trigger
                            msgbox(sprintf('%s\n\n%s\n\n%s','The number of occurences of the specified End_Point trigger is different that the number of occurences of Start_Trigger for the file:', FileName, 'This case is not manage by this module. Please fix manually your marker file or use a fix duration instead'),...
                            'Problem of inequal number of Start Trigger and End Point Trigger','error');
                           disp('Processing Abort!')
                           delete (h);
                           return
                        end
                        endTFPosition=events(posTriggerEnd,1)-1;  
                      
                    else %case where the end if specified by a fix duration in second
                        durationComputeNbTF=round(app.CutTable.Data.End_Value(j)/(1/app.DataTable.SamplingRate(file)));
                        endTFPosition=startTFPosition+durationComputeNbTF; %this line code work also if the startTFPosition dimension is bigger than 1 (serveral occurence of Start_Trigger)     
                    end
                        
                    
                    for k=1:length(posTriggerStart) %manage if serveral occurence of Start_Trigger    
                          
                        if endTFPosition(k)>size(data,1) %check if the endPoint is outside of the eegfile
                            msgbox(sprintf('%s\n\n%s\n\n%s','The specified End_Point duration is outside of the EEG file (duration value too big) for the file:', FileName, 'Please check the entered values.'),...
                                'End Point outside of the eeg file','error'); 
                            disp('Processing Abort!')
                            delete (h);
                            return
                        end
                    
                    
                        %preparation of the cut file
                        
                        MRK=zeros(1,3); %creation MRK factice (nécessaire pour appeler la fonction save_sef
                        
                        NewData = [];
                        NewData = [NewData; data(startTFPosition(k):endTFPosition(k),:)];
                        MRK(1,:)= [1,1,app.CutTable.Data.Start_Trigger(workingIndices(j))];
                        
                        %look if there is any other trigger/event if the period of interest
                        tempPos=1;
                        for z=1:size(events,1)
                            if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
                                tempPos=tempPos+1;
                                MRK(tempPos,1)=events(z,1)-startTFPosition(k)+1; %need to correct for +1 because of correction header.first index at recording
                                MRK(tempPos,2)=events(z,1)-startTFPosition(k)+1;
                                MRK(tempPos,3)=events(z,3);
                            end
                        end
                        
                        % Concatenation between segments
                        if app.CutTable.Data.Concatenate(j)==1 & app.concatenate.Value %concatenate1
                            indiceConcatenate1=indiceConcatenate1+1;
                            eventsConcatenated1(indiceConcatenate1,:)=[size(EEGconcatenated1,1)+1, size(EEGconcatenated1,1)+1, app.CutTable.Data.Start_Trigger(workingIndices(j))];                           
                            
                            %look if there is any other trigger/event if the period of interest
                            for z=1:size(events,1)
                                if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
                                    indiceConcatenate1=indiceConcatenate1+1;
                                    eventsConcatenated1(indiceConcatenate1,1)=events(z,1)-startTFPosition(k)+size(EEGconcatenated1,1)+1;
                                    eventsConcatenated1(indiceConcatenate1,2)=events(z,1)-startTFPosition(k)+size(EEGconcatenated1,1)+1;
                                    eventsConcatenated1(indiceConcatenate1,3)=events(z,3);
                                end
                            end
                            
                            EEGconcatenated1=vertcat(EEGconcatenated1,NewData);
                        end
                        if app.CutTable.Data.Concatenate(j)==2 & app.concatenate.Value %concatenate2
                            indiceConcatenate2=indiceConcatenate2+1;
                            eventsConcatenated2(indiceConcatenate2,:)=[size(EEGconcatenated2,1)+1, size(EEGconcatenated2,1)+1, app.CutTable.Data.Start_Trigger(workingIndices(j))];
                            
                            %look if there is any other trigger/event if the period of interest
                            for z=1:size(events,1)
                                if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
                                    indiceConcatenate2=indiceConcatenate2+1;
                                    eventsConcatenated2(indiceConcatenate2,1)=events(z,1)-startTFPosition(k)+size(EEGconcatenated2,1)+1;
                                    eventsConcatenated2(indiceConcatenate2,2)=events(z,1)-startTFPosition(k)+size(EEGconcatenated2,1)+1;
                                    eventsConcatenated2(indiceConcatenate2,3)=events(z,3);
                                end
                            end
                            
                            EEGconcatenated2=vertcat(EEGconcatenated2,NewData);
                        end
                        if app.CutTable.Data.Concatenate(j)==3 & app.concatenate.Value %concatenate3
                            indiceConcatenate3=indiceConcatenate3+1;
                            eventsConcatenated3(indiceConcatenate3,:)=[size(EEGconcatenated3,1)+1, size(EEGconcatenated3,1)+1, app.CutTable.Data.Start_Trigger(workingIndices(j))];
                            
                            %look if there is any other trigger/event if the period of interest
                            for z=1:size(events,1)
                                if and(events(z,1)>startTFPosition(k),events(z,1)<endTFPosition(k))
                                    indiceConcatenate3=indiceConcatenate3+1;
                                    eventsConcatenated3(indiceConcatenate3,1)=events(z,1)-startTFPosition(k)+size(EEGconcatenated3,1)+1;
                                    eventsConcatenated3(indiceConcatenate3,2)=events(z,1)-startTFPosition(k)+size(EEGconcatenated3,1)+1;
                                    eventsConcatenated3(indiceConcatenate3,3)=events(z,3);
                                end
                            end
                            
                            EEGconcatenated3=vertcat(EEGconcatenated3,NewData);
                        end


                        % Save processed file
                        
                        %suffix filename
                        if ~ismissing(app.CutTable.Data.Suffix_String(workingIndices(j))) %case the user specified a suffix string
                            suffix = app.CutTable.Data.Suffix_String(workingIndices(j));
                        else %case the user did not specify a suffix
                            suffix = strcat("Tigger",num2str(app.CutTable.Data.Start_Trigger(workingIndices(j))));
                        end
                        
                        %Generate the outputfilename
                        if length(posTriggerStart)>1
                            outputfilename=strcat(app.DataTable.File{file}(1:end-4),'_',suffix,'_',num2str(k), app.FilesExtension.Value);
                        else
                            outputfilename=strcat(app.DataTable.File{file}(1:end-4),'_',suffix,app.FilesExtension.Value);
                        end
                        
                        %complete path of recording
                        if app.SaveinseparatefoldersButton.Value %case of subdirectory
                            SubPath=fullfile(app.SaveFolder.Value{1},suffix);
                            if ~exist(SubPath, 'dir') %create folder
                                mkdir(SubPath);
                            end
                            savefilename=fullfile(SubPath,outputfilename);
                        else %case save in one folder 
                            savefilename=fullfile(app.SaveFolder.Value{1},outputfilename);
                        end
       
                        %saving
                        disp(['Saving ',savefilename])
                        if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph') %manage the output format
                            save_eph(savefilename,NewData,header.SamplingRate,MRK,1) %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.sef')
                            try
                                %Channels = char(app.SessionParameters.Electrodes.SettingTable.labels);
                                Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                            catch
                                Channels = '';
                            end
                            save_sef(savefilename,NewData,header.SamplingRate,Channels,MRK,1) %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.set')
                            savefilename = convertStringsToChars(savefilename); %need to be char for save_eeglab
                            try
                                ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                                Channels            = ElectrodeSetting(:,2);
                                %Channels.labels     = char(ElectrodeSetting.labels);
    
                                % Recompute Theta and Radius to match EEGlab orientation
                                [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                                Channels.theta      = -rad2deg(az);
                                Channels.radius     = 0.5 - rad2deg(elev)/180;
                                Channels.sph_theta  = -Channels.theta;
                                Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                                Channels.X          = ElectrodeSetting.y;
                                Channels.Y          = -ElectrodeSetting.x;
                                Channels.Z          = ElectrodeSetting.z;
        %                         Channels.ref        = char(repmat({''},height(Channels),1));
        %                         Channels.sph_radius = repmat({[]},height(Channels),1);
        %                         Channels.types      = char(repmat({''},height(Channels),1));
        %                         Channels.urchan     = repmat({[]},height(Channels),1);
                                Channels            = table2struct(Channels);
                                save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1,Channels) %event start at 1
                            catch
                                save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1) %event start at 1
                            end
                            
%                             try
%                                 ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
%                                 Channels            = ElectrodeSetting(:,[6 7 2]);
%                                 Channels.labels     = char(Channels.labels);
%                                 Channels.sph_theta  = -Channels.theta;
%                                 Channels.sph_phi    = (0.5 - Channels.radius) * 180;
%                                 Channels.X          = ElectrodeSetting.x;
%                                 Channels.Y          = ElectrodeSetting.y;
%                                 Channels.Z          = ElectrodeSetting.z;
%         %                         Channels.ref        = char(repmat({''},height(Channels),1));
%         %                         Channels.sph_radius = repmat({[]},height(Channels),1);
%         %                         Channels.types      = char(repmat({''},height(Channels),1));
%         %                         Channels.urchan     = repmat({[]},height(Channels),1);
%                                 Channels            = table2struct(Channels);
%                             catch
%                                 Channels = '';
%                             end
%                             savefilename = convertStringsToChars(savefilename); %need to be char for save_eeglab
%                             
%                             save_eeglab(savefilename,NewData,header.SamplingRate,MRK,1,Channels) %event start at 1
                        end                  
                        
                        %update the PIF (section output file)
                        fprintf(PifFileID,'%s\r\n',outputfilename);
                    end
                end
                
                %saving concatenate file
                if app.concatenate.Value 
                    for t=1:length(concatenateArgument) %loop according the number of concatenate file to record
                        if concatenateArgument(t)==1
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',app.concatenateSuffix1.Value{1}, app.FilesExtension.Value);
                            NewDataConcatenate=EEGconcatenated1;                            
                            MRK = eventsConcatenated1;
                        elseif concatenateArgument(t)==2
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',app.concatenateSuffix2.Value{1}, app.FilesExtension.Value);
                            NewDataConcatenate=EEGconcatenated2;                            
                            MRK = eventsConcatenated2;
                        elseif concatenateArgument(t)==3
                            outputfilenameConcatenate=strcat(app.DataTable.File{file}(1:end-4),'_',app.concatenateSuffix3.Value{1}, app.FilesExtension.Value);
                            NewDataConcatenate=EEGconcatenated3;                            
                            MRK = eventsConcatenated3;
                        end
                      
                        %filling the complete path
                        savefilenameConcatenate=fullfile(app.SaveFolder.Value{1},outputfilenameConcatenate);
                       
                        %log file
                        fprintf(PifFileID,'%s\r\n',outputfilenameConcatenate);
                                    
                        
                        if strcmp(app.FilesExtension.Value,'.ep') || strcmp(app.FilesExtension.Value,'.eph') %manage the output format
                            save_eph(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1) %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.sef')
                            try
                                Channels = char(app.SessionParameters.Electrodes.SettingTable.labels([app.SessionParameters.Electrodes.SettingTable.include{:}]));
                            catch
                                Channels = '';
                            end
                            save_sef(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,Channels,MRK,1); %event start at 1
                        elseif strcmp(app.FilesExtension.Value,'.set')
                            savefilenameConcatenate = convertStringsToChars(savefilenameConcatenate); %nned to be char for save_eeglab
                            try
                                ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
                                Channels            = ElectrodeSetting(:,2);
                                %Channels.labels     = char(ElectrodeSetting.labels);
    
                                % Recompute Theta and Radius to match EEGlab orientation
                                [az,elev,~]         = cart2sph(ElectrodeSetting.y,-ElectrodeSetting.x,ElectrodeSetting.z); % Nasion: in Cartool = Y+, in EEGlab = X+; 
                                Channels.theta      = -rad2deg(az);
                                Channels.radius     = 0.5 - rad2deg(elev)/180;
                                Channels.sph_theta  = -Channels.theta;
                                Channels.sph_phi    = (0.5 - Channels.radius) * 180;
                                Channels.X          = ElectrodeSetting.y;
                                Channels.Y          = -ElectrodeSetting.x;
                                Channels.Z          = ElectrodeSetting.z;
        %                         Channels.ref        = char(repmat({''},height(Channels),1));
        %                         Channels.sph_radius = repmat({[]},height(Channels),1);
        %                         Channels.types      = char(repmat({''},height(Channels),1));
        %                         Channels.urchan     = repmat({[]},height(Channels),1);
                                Channels            = table2struct(Channels);
                                save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1,Channels) %event start at 1
                            catch
                                save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1) %event start at 1
                            end
%                             try
%                                 ElectrodeSetting    = app.SessionParameters.Electrodes.SettingTable([app.SessionParameters.Electrodes.SettingTable.include{:}],:);
%                                 Channels            = ElectrodeSetting(:,[6 7 2]);
%                                 Channels.labels     = char(Channels.labels);
%                                 Channels.sph_theta  = -Channels.theta;
%                                 Channels.sph_phi    = (0.5 - Channels.radius) * 180;
%                                 Channels.X          = ElectrodeSetting.x;
%                                 Channels.Y          = ElectrodeSetting.y;
%                                 Channels.Z          = ElectrodeSetting.z;
%         %                         Channels.ref        = char(repmat({''},height(Channels),1));
%         %                         Channels.sph_radius = repmat({[]},height(Channels),1);
%         %                         Channels.types      = char(repmat({''},height(Channels),1));
%         %                         Channels.urchan     = repmat({[]},height(Channels),1);
%                                 Channels            = table2struct(Channels);
%                             catch
%                                 Channels = '';
%                             end
%                             savefilenameConcatenate = convertStringsToChars(savefilenameConcatenate); %nned to be char for save_eeglab
%                             save_eeglab(savefilenameConcatenate,NewDataConcatenate,header.SamplingRate,MRK,1,Channels) %event start at 1
                        end
                   end
                end
                    
            end
            
            
%             % UPDATE DATA TABLE IN MAIN WINDOW
%             
%             % Data table in the Main Window
%             app.EEGpalCS_Main.DataFilesTable.Data   = ProcessedDataTable;
%             app.EEGpalCS_Main.FilesTable            = ProcessedDataTable;
%             app.DataTable                           = ProcessedDataTable; % So to be able to re run on the processed files
%             app.EEGpalCS_Main.RootFolder.Value      = app.SaveFolder.Value;
%             disp('Data table in the Main Window updated with the processed files')
%             cd(app.SaveFolder.Value{:})
%                      
            disp('Finished :)')
            EndTime = datetime('now');
            tEnd = toc(tStart);
            disp(['Start Time: ' datestr(StartTime)])
            disp(['End Time: ' datestr(EndTime)])
            disp(['Total duration: ',datestr(seconds(tEnd),'HH:MM:SS')])

            
            %Close the PIF file 
            fprintf(PifFileID,'\r\n\r\n\r\n%s\r\n\r\n','PROCESSING COMPLETION');
            fprintf(PifFileID,'%s',char(strcat('File cut processing completed on:',{' '},datestr(EndTime))));
            fclose(PifFileID);

            delete (h);
            rz = msgbox('File cut processing done.');
        end

        % Button pushed function: SaveinmemoryButton
        function SaveinmemoryButtonPushed(app, event)
            % Enable the Plot Options button in main app
            app.EEGpalCS_Main.FileCutButton.Enable = 'on';

            % Update parameters
            app.SessionParameters   = updateSessionParam(app,app.SessionParameters);
            app.EEGpalCS_Main.SessionParameters = app.SessionParameters;
            
            % Delete the dialog box
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FileCutButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Close request function: EEGpal_FileCut
        function EEGpal_FileCutCloseRequest(app, event)
            % Enable the Electrode Setting button in main app
            app.EEGpalCS_Main.FileCutButton.Enable = 'on';
            
            % Delete the dialog box
            delete(app)
        end

        % Cell edit callback: CutTable
        function CutTableEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            if(indices(2)==2)
                if app.CutTable.Data.End_Duration(indices(1))
                    app.CutTable.Data.End_Duration(indices(1))=false;
                end
            elseif (indices(2)==3)
                if app.CutTable.Data.End_Trigger(indices(1))
                    app.CutTable.Data.End_Trigger(indices(1))=false;
                end                
            end
            
        end

        % Value changed function: concatenate
        function concatenateValueChanged(app, event)
            value = app.concatenate.Value;
            if value
                app.concatenateSuffix1.Enable=true;
                app.concatenateSuffix2.Enable=true;
                app.concatenateSuffix3.Enable=true;
            else
                app.concatenateSuffix1.Enable=false;
                app.concatenateSuffix2.Enable=false;
                app.concatenateSuffix3.Enable=false; 
            end
        end

        % Button pushed function: SavetableasButton
        function SavetableasButtonPushed(app, event)
            [FileData,PathData] = uiputfile({'*.csv';'*.xlsx'},'Save File cut table',[app.EEGpalCS_Main.StudyName.Value '_FileCutTable']);
            drawnow; figure(app.EEGpal_FileCut); % Restaure the app module
            if FileData==0 %user press on cancel
                return
            end
            writetable(app.CutTable.Data,fullfile(PathData,FileData));
        end

        % Button pushed function: LoadtableButton
        function LoadtableButtonPushed(app, event)
            [FileData,PathData] = uigetfile({'*.csv';'*.xlsx'},'Select the File cut table to load');
            drawnow; figure(app.EEGpal_FileCut); % Restaure the app module
            if FileData==0 %user press on cancel
                return
            end
            FileName            = fullfile(PathData,FileData);
            uploadTable         = readtable(FileName);

            if or(size(uploadTable,1)~=15,size(uploadTable,2)~=6)
                 msgbox(sprintf('%s\n\n%s\n%s','Table in the file don''t match.', ...
                        'The file you selected do not contain a proper table with a dimention 15x6',...
                        'Please check!'),...
                        'Invalid file','warn');
                  return
            end
            
            labels      = string({'Start_Trigger','End_Trigger','End_Duration','End_Value','Suffix_String','Concatenate'});
            transfertTable = table('Size',[15 length(labels)],...
            'VariableTypes',["single", "logical", "logical","single","string","single"],'VariableNames',labels);
             
            %convert variable type
            for i=1:15
                 transfertTable{i,1}=uploadTable{i,1}; %Start_Trigger
                 transfertTable{i,2}=logical(uploadTable{i,2}); %End_Trigger
                 transfertTable{i,3}=logical(uploadTable{i,3}); %End_DUration
                 transfertTable{i,4}=uploadTable{i,4}; %End_Value
                 if ~ismissing(uploadTable{i,5})
                    transfertTable{i,5}=string(uploadTable{i,5}{1}); %Suffix_String
                 end
                 transfertTable{i,6}=uploadTable{i,6}; %Concatenate
            end
             
            app.CutTable.Data=transfertTable;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            if ispc
                % For Windows
                filePath = strcat(app.EEGpalCS_Main.AppPath.value, '\Manuels\Manuel_FileCut_module.pdf');
                winopen(filePath);
            elseif ismac
                % For macOS
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_FileCut_module.pdf');
                system(['open ', filePath]);
            else
                % Pour Linux ou autres systèmes
                filePath = strcat(app.AppPath.value, '/Manuels/Manuel_FileCut_module.pdf');
                system(['xdg-open ',filePath,' &']);                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EEGpal_FileCut = uifigure('Visible', 'off');
            app.EEGpal_FileCut.Name = 'EEGpal_FileCut';
            app.EEGpal_FileCut.CloseRequestFcn = createCallbackFcn(app, @EEGpal_FileCutCloseRequest, true);
            app.FileCutLabel = uilabel(app.EEGpal_FileCut);
            app.FileCutLabel.Text = 'File Cut';
            app.OUTPUTOPTIONSLabel = uilabel(app.EEGpal_FileCut);
            app.OUTPUTOPTIONSLabel.Text = 'OUTPUT OPTIONS';
            app.ExtensionofoutputfilesLabel = uilabel(app.EEGpal_FileCut);
            app.ExtensionofoutputfilesLabel.Text = 'Extension of output files:';
            app.FilesExtension = uidropdown(app.EEGpal_FileCut);
            app.FilesExtension.Items = {'.ep', '.eph', '.sef', '.set'};
            app.FilesExtension.Value = '.sef';
            app.SelectUpperFolderLabel = uilabel(app.EEGpal_FileCut);
            app.SelectUpperFolderLabel.Text = {'Select uppermost folder '; 'where to save the files:'};
            app.SelectFolderButton = uibutton(app.EEGpal_FileCut, 'push');
            app.SelectFolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFolderButtonPushed, true);
            app.SelectFolderButton.Text = 'Select folder';
            app.SavingfolderLabel = uilabel(app.EEGpal_FileCut);
            app.SavingfolderLabel.Text = 'Saving folder:';
            app.SaveFolder = uitextarea(app.EEGpal_FileCut);
            app.SaveFolder.Editable = 'off';
            app.APPLYLabel = uilabel(app.EEGpal_FileCut);
            app.APPLYLabel.Text = 'APPLY';
            app.RunButton = uibutton(app.EEGpal_FileCut, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Tooltip = {'Run the selected processes'};
            app.RunButton.Text = 'Run';
            app.SaveinmemoryButton = uibutton(app.EEGpal_FileCut, 'push');
            app.SaveinmemoryButton.ButtonPushedFcn = createCallbackFcn(app, @SaveinmemoryButtonPushed, true);
            app.SaveinmemoryButton.Tooltip = {'Return to the main window without applying the processing and save the parameters.'};
            app.SaveinmemoryButton.Text = 'Save in memory';
            app.CancelButton = uibutton(app.EEGpal_FileCut, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Tooltip = {'Return to the main window without memorising the parameters.'};
            app.CancelButton.Text = 'Cancel';
            app.ButtonGroup = uibuttongroup(app.EEGpal_FileCut);
            app.SaveinseparatefoldersButton = uiradiobutton(app.ButtonGroup);
            app.SaveinseparatefoldersButton.Text = 'Save in separate folders';
            app.SaveinseparatefoldersButton.Value = true;
            app.SaveinthesamefolderButton = uiradiobutton(app.ButtonGroup);
            app.SaveinthesamefolderButton.Text = 'Save in the same folder';
            app.CutTable = uitable(app.EEGpal_FileCut);
            app.CutTable.ColumnName = {'Start_Trigger'; 'End_Option_Trigger'; 'End_Option_Duration'; 'End_Point(tigger number or s)'; 'Suffix_String'; 'Concatenate'};
            app.CutTable.RowName = {};
            app.CutTable.ColumnEditable = true;
            app.CutTable.CellEditCallback = createCallbackFcn(app, @CutTableEdit, true);
            app.Instruct1 = uilabel(app.EEGpal_FileCut);
            app.Instruct1.Text = 'Module to cut EEG files into several subfiles. Please fill the following table to instruct the module about the borders (instructions below the table):';
            app.Instruct2 = uilabel(app.EEGpal_FileCut);
            app.Instruct2.Text = {'Start_trigger : Enter the value of the trigger which will mark the beginning of the subfile (can have several occurence)'; 'End_Option_trigger: Select if the End_Point is a trigger'; 'End_option_Duration: Select if the End_Point is fix duration (in s) after to the Strat_Trigger'; 'End_point: either trigger number, either duration in s, either NaN until the end of the file'; 'Suffix_String: Custom suffix identifier in the output file name'; 'Concatenate: Put integer between 1 to 3 (max) to concatenate segments (for example all segments with the value 1 will be concatenate) - work only if option ''Concatenate'' is activated on the left'};
            app.SelectUpperFolderLabel_2 = uilabel(app.EEGpal_FileCut);
            app.SelectUpperFolderLabel_2.Text = 'Saving option:';
            app.concatenate = uicheckbox(app.EEGpal_FileCut);
            app.concatenate.ValueChangedFcn = createCallbackFcn(app, @concatenateValueChanged, true);
            app.concatenate.Text = {'Concatenate different segments according the ''Concatenate'' Clomn (max 3 output files).'; 'It also perimt to concatenate segments when the same ''Start_Trigger'' is present in the inpout file. '};
            app.Suffix_Concatenate_1Label = uilabel(app.EEGpal_FileCut);
            app.Suffix_Concatenate_1Label.Text = 'Suffix_Concatenate_1';
            app.concatenateSuffix1 = uitextarea(app.EEGpal_FileCut);
            app.concatenateSuffix1.Enable = 'off';
            app.concatenateSuffix1.Value = {'CONCATENATE1'};
            app.Suffix_Concatenate_2Label = uilabel(app.EEGpal_FileCut);
            app.Suffix_Concatenate_2Label.Text = 'Suffix_Concatenate_2';
            app.concatenateSuffix2 = uitextarea(app.EEGpal_FileCut);
            app.concatenateSuffix2.Enable = 'off';
            app.concatenateSuffix2.Value = {'CONCATENATE2'};
            app.Suffix_Concatenate_3Label = uilabel(app.EEGpal_FileCut);
            app.Suffix_Concatenate_3Label.Text = 'Suffix_Concatenate_3';
            app.concatenateSuffix3 = uitextarea(app.EEGpal_FileCut);
            app.concatenateSuffix3.Enable = 'off';
            app.concatenateSuffix3.Value = {'CONCATENATE3'};
            app.SavetableasButton = uibutton(app.EEGpal_FileCut, 'push');
            app.SavetableasButton.ButtonPushedFcn = createCallbackFcn(app, @SavetableasButtonPushed, true);
            app.SavetableasButton.Text = 'Save table as...';
            app.LoadtableButton = uibutton(app.EEGpal_FileCut, 'push');
            app.LoadtableButton.ButtonPushedFcn = createCallbackFcn(app, @LoadtableButtonPushed, true);
            app.LoadtableButton.Text = 'Load table ...';
            app.HelpButton = uibutton(app.EEGpal_FileCut, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            % Show the figure after all components are created
            app.EEGpal_FileCut.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = EEGpalCS_mod_FileCut(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EEGpal_FileCut)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EEGpal_FileCut)
        end
    end
end